classdef AllenAtlasROICreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.CheckBoxTree
        GridLayout3              matlab.ui.container.GridLayout
        RememberselectionButton  matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        OkButton                 matlab.ui.control.Button
        SearchResultsDropDown    matlab.ui.control.DropDown
        SearchField              matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % =====================================================================
        % Atlas source data
        % =====================================================================
        AtlasFile char = ''
        BregmaCoordsXY double = []
        LambdaCoordsXY double = []
        area_bwlabel double = []
        area_longNames cell = {}
        area_parentNames cell = {}
        area_polyshapes
        area_tags cell = {}
        bound_mask logical = []
        cluster_bwlabel double = []
        cluster_longNames cell = {}
        cluster_polyshapes
        cluster_tags cell = {}
        pixels_per_mm double = []

        % =====================================================================
        % Normalized atlas tables
        % =====================================================================
        AreaTable table = table()
        ModuleTable table = table()
        SearchTable table = table()

        % =====================================================================
        % Runtime lookup maps
        % =====================================================================
        TreeNodeByAreaBaseKey
        TreeNodeByModuleBaseKey
        AreaKeysByModuleBaseKey
        ModuleKeysByModuleBaseKey
        AreaHandleByAreaKey
        ModuleHandleByModuleKey

        % =====================================================================
        % Selection state
        % =====================================================================
        SelectedAreaKeys string = strings(0, 1)       % side-specific area tags, e.g. "VISp_L"
        SelectedModuleKeys string = strings(0, 1)     % side-specific merged-module tags, e.g. "VIS_L"
        MergedModuleBaseKeys string = strings(0, 1)   % side-collapsed module tags, e.g. "VIS"
        CheckedTreeNodeKeys string = strings(0, 1)

        % =====================================================================
        % Map runtime graphics
        % =====================================================================
        AreaPatchHandles = gobjects(0)
        ModulePatchHandles = gobjects(0)
        BoundaryHandle = gobjects(1)

        % =====================================================================
        % Parent app and OK output
        % =====================================================================
        MainApp = []
        Result struct = struct()
        Confirmed logical = false

        % =====================================================================
        % UI runtime flags/resources
        % =====================================================================
        IsUpdatingTree logical = false
        IsUpdatingSearch logical = false
        StatusTitleTimer timer = timer.empty
    end


    methods (Access = private)
        function initializeAtlasRuntimeMaps(app)
            %INITIALIZEATLASRUNTIMEMAPS Initialize selector lookup maps and state.

            app.TreeNodeByAreaBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.TreeNodeByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.AreaKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.AreaHandleByAreaKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleHandleByModuleKey = containers.Map('KeyType', 'char', 'ValueType', 'any');

            app.SelectedAreaKeys = strings(0, 1);
            app.SelectedModuleKeys = strings(0, 1);
            app.MergedModuleBaseKeys = strings(0, 1);
            app.CheckedTreeNodeKeys = strings(0, 1);

            app.Result = struct();
            app.Confirmed = false;

        end


        function loadAtlasInfo(app)
            %LOADATLASINFO Load and validate Allen atlas top-view info.
            %
            %   The production app always loads:
            %       <app folder>/CCF_2022_mouse_topView_data.mat
            %
            %   Optional atlas-file/struct injection was removed because the atlas source
            %   is now part of the app contract.

            app.AtlasFile = app.getDefaultAtlasInfoFilePath();

            if ~isfile(app.AtlasFile)
                error('AllenAtlasROICreator:AtlasFileNotFound', ...
                    'Atlas info file not found: "%s".', app.AtlasFile);
            end

            S = load(app.AtlasFile, '-mat');

            requiredVars = { ...
                'BregmaCoordsXY', ...
                'LambdaCoordsXY', ...
                'area_bwlabel', ...
                'area_longNames', ...
                'area_parentNames', ...
                'area_polyshapes', ...
                'area_tags', ...
                'bound_mask', ...
                'cluster_bwlabel', ...
                'cluster_longNames', ...
                'cluster_polyshapes', ...
                'cluster_tags', ...
                'pixels_per_mm'};

            for iVar = 1:numel(requiredVars)
                if ~isfield(S, requiredVars{iVar})
                    error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                        'Atlas info is missing required variable "%s".', requiredVars{iVar});
                end
            end

            app.BregmaCoordsXY = double(S.BregmaCoordsXY);
            app.LambdaCoordsXY = double(S.LambdaCoordsXY);
            app.area_bwlabel = double(S.area_bwlabel);
            app.area_longNames = S.area_longNames;
            app.area_parentNames = S.area_parentNames;
            app.area_polyshapes = S.area_polyshapes;
            app.area_tags = S.area_tags;
            app.bound_mask = logical(S.bound_mask);
            app.cluster_bwlabel = double(S.cluster_bwlabel);
            app.cluster_longNames = S.cluster_longNames;
            app.cluster_polyshapes = S.cluster_polyshapes;
            app.cluster_tags = S.cluster_tags;
            app.pixels_per_mm = double(S.pixels_per_mm);

            app.validateAtlasInfo();

        end


        function atlasFile = getDefaultAtlasInfoFilePath(app) %#ok<MANU>
            %GETDEFAULTATLASINFOFILEPATH Return atlas file path next to this app.

            appFolder = fileparts(mfilename('fullpath'));
            atlasFile = fullfile(appFolder, 'CCF_2022_mouse_topView_data.mat');

        end


        function validateAtlasInfo(app)
            %VALIDATEATLASINFO Validate dimensions/classes needed by selector.

            nAreas = numel(app.area_tags);

            if numel(app.area_longNames) ~= nAreas || ...
                    numel(app.area_parentNames) ~= nAreas || ...
                    numel(app.area_polyshapes) ~= nAreas
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'Area fields must have matching lengths.');
            end

            nModules = numel(app.cluster_tags);

            if numel(app.cluster_longNames) ~= nModules || ...
                    numel(app.cluster_polyshapes) ~= nModules
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'Cluster/module fields must have matching lengths.');
            end

            if ~isequal(size(app.area_bwlabel), size(app.bound_mask)) || ...
                    ~isequal(size(app.cluster_bwlabel), size(app.bound_mask))
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'area_bwlabel, cluster_bwlabel, and bound_mask must have matching sizes.');
            end

            if numel(app.BregmaCoordsXY) ~= 2 || numel(app.LambdaCoordsXY) ~= 2
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'BregmaCoordsXY and LambdaCoordsXY must be 1-by-2 coordinates.');
            end

            if isempty(app.pixels_per_mm) || ~isscalar(app.pixels_per_mm) || ...
                    ~isfinite(app.pixels_per_mm) || app.pixels_per_mm <= 0
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'pixels_per_mm must be a positive finite scalar.');
            end

        end


        % =====================================================================
        % Atlas table construction
        % =====================================================================

        function buildAtlasLookupTables(app)
            %BUILDATLASLOOKUPTABLES Normalize side-specific atlas metadata.
            %
            %   Internal tables remain side-specific. Tree/search labels collapse
            %   left/right names and tags so one UI node can represent both sides.

            nAreas = numel(app.area_tags);
            nModules = numel(app.cluster_tags);

            try
                areaColors = distinguishable_colors(nAreas, {'b', 'w'});
            catch
                areaColors = lines(max(nAreas, 1));
                areaColors = areaColors(1:nAreas, :);
            end

            areaIdx = (1:nAreas).';
            areaTagFull = strings(nAreas, 1);
            areaTagBase = strings(nAreas, 1);
            areaSide = strings(nAreas, 1);
            areaLongNameFull = strings(nAreas, 1);
            areaLongNameBase = strings(nAreas, 1);
            areaParentFull = strings(nAreas, 1);
            areaParentBase = strings(nAreas, 1);
            areaModuleBaseKey = strings(nAreas, 1);
            areaColor = cell(nAreas, 1);

            for iArea = 1:nAreas
                tagFull = string(app.area_tags{iArea});
                longNameFull = string(app.area_longNames{iArea});
                parentFull = string(app.area_parentNames{iArea});

                [tagBase, sideFromTag] = app.stripAtlasSideFromTag(tagFull);
                [longNameBase, sideFromName] = app.stripAtlasSideFromName(longNameFull);
                [parentBase, ~] = app.stripAtlasSideFromName(parentFull);

                side = sideFromTag;
                if side == ""
                    side = sideFromName;
                end

                areaTagFull(iArea) = tagFull;
                areaTagBase(iArea) = tagBase;
                areaSide(iArea) = side;
                areaLongNameFull(iArea) = longNameFull;
                areaLongNameBase(iArea) = longNameBase;
                areaParentFull(iArea) = parentFull;
                areaParentBase(iArea) = parentBase;
                areaModuleBaseKey(iArea) = matlab.lang.makeValidName(char(parentBase));
                areaColor{iArea} = areaColors(iArea, :);
            end

            moduleIdx = (1:nModules).';
            moduleTagFull = strings(nModules, 1);
            moduleTagBase = strings(nModules, 1);
            moduleSide = strings(nModules, 1);
            moduleLongNameFull = strings(nModules, 1);
            moduleLongNameBase = strings(nModules, 1);
            moduleBaseKey = strings(nModules, 1);
            moduleColor = cell(nModules, 1);

            for iModule = 1:nModules
                tagFull = string(app.cluster_tags{iModule});
                longNameFull = string(app.cluster_longNames{iModule});

                [tagBase, sideFromTag] = app.stripAtlasSideFromTag(tagFull);
                [longNameBase, sideFromName] = app.stripAtlasSideFromName(longNameFull);

                side = sideFromTag;
                if side == ""
                    side = sideFromName;
                end

                if tagBase == ""
                    thisModuleBaseKey = string(matlab.lang.makeValidName(char(longNameBase)));
                else
                    thisModuleBaseKey = tagBase;
                end

                moduleTagFull(iModule) = tagFull;
                moduleTagBase(iModule) = tagBase;
                moduleSide(iModule) = side;
                moduleLongNameFull(iModule) = longNameFull;
                moduleLongNameBase(iModule) = longNameBase;
                moduleBaseKey(iModule) = thisModuleBaseKey;
            end

            nameToModuleKey = containers.Map('KeyType', 'char', 'ValueType', 'char');

            for iModule = 1:nModules
                nameKey = char(moduleLongNameBase(iModule));

                if ~isKey(nameToModuleKey, nameKey)
                    nameToModuleKey(nameKey) = char(moduleBaseKey(iModule));
                end
            end

            for iArea = 1:nAreas
                parentName = char(areaParentBase(iArea));

                if isKey(nameToModuleKey, parentName)
                    areaModuleBaseKey(iArea) = string(nameToModuleKey(parentName));
                else
                    areaModuleBaseKey(iArea) = string(matlab.lang.makeValidName(parentName));
                end
            end

            for iModule = 1:nModules
                idx = find(areaModuleBaseKey == moduleBaseKey(iModule) & ...
                    areaSide == moduleSide(iModule), 1, 'first');

                if isempty(idx)
                    idx = find(areaModuleBaseKey == moduleBaseKey(iModule), 1, 'first');
                end

                if isempty(idx)
                    moduleColor{iModule} = [0.85 0.85 0.85];
                else
                    moduleColor{iModule} = areaColor{idx};
                end
            end

            app.AreaTable = table(areaIdx, areaTagFull, areaTagBase, areaSide, ...
                areaLongNameFull, areaLongNameBase, areaParentFull, areaParentBase, ...
                areaModuleBaseKey, areaColor, ...
                'VariableNames', {'AreaIdx', 'TagFull', 'TagBase', 'Side', ...
                'LongNameFull', 'LongNameBase', 'ParentFull', 'ParentBase', ...
                'ModuleBaseKey', 'Color'});

            app.ModuleTable = table(moduleIdx, moduleTagFull, moduleTagBase, moduleSide, ...
                moduleLongNameFull, moduleLongNameBase, moduleBaseKey, moduleColor, ...
                'VariableNames', {'ModuleIdx', 'TagFull', 'TagBase', 'Side', ...
                'LongNameFull', 'LongNameBase', 'ModuleBaseKey', 'Color'});

            app.buildAtlasSearchTable();
            app.buildModuleAreaLookupMaps();

        end


        function buildModuleAreaLookupMaps(app)
            %BUILDMODULEAREALOOKUPMAPS Cache module-to-area/module key lists.

            app.AreaKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');

            moduleKeys = unique(app.AreaTable.ModuleBaseKey, 'stable');

            for iKey = 1:numel(moduleKeys)
                key = char(moduleKeys(iKey));

                idxArea = app.AreaTable.ModuleBaseKey == moduleKeys(iKey);
                app.AreaKeysByModuleBaseKey(key) = cellstr(app.AreaTable.TagFull(idxArea));

                idxModule = app.ModuleTable.ModuleBaseKey == moduleKeys(iKey);
                app.ModuleKeysByModuleBaseKey(key) = cellstr(app.ModuleTable.TagFull(idxModule));
            end

        end


        function buildAtlasSearchTable(app)
            %BUILDATLASSEARCHTABLE Build side-collapsed search index.

            itemKey = strings(0, 1);
            itemType = strings(0, 1);
            displayText = strings(0, 1);
            searchText = strings(0, 1);

            moduleKeys = unique(app.ModuleTable.ModuleBaseKey, 'stable');

            for iModule = 1:numel(moduleKeys)
                idx = find(app.ModuleTable.ModuleBaseKey == moduleKeys(iModule), 1, 'first');

                if isempty(idx)
                    continue
                end

                name = app.ModuleTable.LongNameBase(idx);
                tag = app.ModuleTable.ModuleBaseKey(idx);

                itemKey(end+1, 1) = "module|" + tag; %#ok<AGROW>
                itemType(end+1, 1) = "module"; %#ok<AGROW>
                displayText(end+1, 1) = sprintf('%s (%s)', name, tag); %#ok<AGROW>
                searchText(end+1, 1) = lower(displayText(end)); %#ok<AGROW>
            end

            areaBases = unique(app.AreaTable.TagBase, 'stable');

            for iAreaBase = 1:numel(areaBases)
                idx = find(app.AreaTable.TagBase == areaBases(iAreaBase), 1, 'first');

                if isempty(idx)
                    continue
                end

                name = app.AreaTable.LongNameBase(idx);
                tag = app.AreaTable.TagBase(idx);

                itemKey(end+1, 1) = "area|" + tag; %#ok<AGROW>
                itemType(end+1, 1) = "area"; %#ok<AGROW>
                displayText(end+1, 1) = sprintf('%s (%s)', name, tag); %#ok<AGROW>
                searchText(end+1, 1) = lower(displayText(end)); %#ok<AGROW>
            end

            app.SearchTable = table(itemKey, itemType, displayText, searchText, ...
                'VariableNames', {'ItemKey', 'ItemType', 'DisplayText', 'SearchText'});

        end


        % =====================================================================
        % Tree creation and tree state
        % =====================================================================

        function populateAtlasTree(app)
            %POPULATEATLANTREE Populate bilateral module/area checkbox tree.

            delete(app.Tree.Children);

            app.TreeNodeByAreaBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.TreeNodeByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');

            rootNode = uitreenode(app.Tree);
            rootNode.Text = 'Neocortex';
            rootNode.NodeData = struct( ...
                'type', 'root', ...
                'nodeKey', "root|neocortex");

            moduleKeys = unique(app.AreaTable.ModuleBaseKey, 'stable');

            for iModule = 1:numel(moduleKeys)
                moduleKey = moduleKeys(iModule);
                idxModuleTable = find(app.ModuleTable.ModuleBaseKey == moduleKey, 1, 'first');

                if isempty(idxModuleTable)
                    idxArea = find(app.AreaTable.ModuleBaseKey == moduleKey, 1, 'first');
                    moduleName = app.AreaTable.ParentBase(idxArea);
                    moduleTag = moduleKey;
                else
                    moduleName = app.ModuleTable.LongNameBase(idxModuleTable);
                    moduleTag = app.ModuleTable.ModuleBaseKey(idxModuleTable);
                end

                moduleNode = uitreenode(rootNode);
                moduleNode.Text = app.formatAtlasTreeNodeText(moduleName, moduleTag, false);
                moduleNode.NodeData = struct( ...
                    'type', 'module', ...
                    'nodeKey', "module|" + moduleKey, ...
                    'moduleBaseKey', moduleKey);

                app.TreeNodeByModuleBaseKey(char(moduleKey)) = moduleNode;
                app.attachModuleContextMenu(moduleNode, moduleKey);

                areaIdx = find(app.AreaTable.ModuleBaseKey == moduleKey);
                areaBases = unique(app.AreaTable.TagBase(areaIdx), 'stable');

                for iAreaBase = 1:numel(areaBases)
                    areaBase = areaBases(iAreaBase);
                    idxAreaTable = find(app.AreaTable.TagBase == areaBase & ...
                        app.AreaTable.ModuleBaseKey == moduleKey, 1, 'first');

                    if isempty(idxAreaTable)
                        continue
                    end

                    areaName = app.AreaTable.LongNameBase(idxAreaTable);
                    areaTag = app.AreaTable.TagBase(idxAreaTable);
                    sideRows = app.AreaTable.TagBase == areaBase & ...
                        app.AreaTable.ModuleBaseKey == moduleKey;
                    areaKeys = app.AreaTable.TagFull(sideRows);

                    areaNode = uitreenode(moduleNode);
                    areaNode.Text = app.formatAtlasTreeNodeText(areaName, areaTag, false);
                    areaNode.NodeData = struct( ...
                        'type', 'area', ...
                        'nodeKey', "area|" + areaBase, ...
                        'areaBaseKey', areaBase, ...
                        'moduleBaseKey', moduleKey, ...
                        'areaKeys', areaKeys(:).');

                    app.TreeNodeByAreaBaseKey(char(areaBase)) = areaNode;
                end
            end

            app.expandAllAtlasTreeNodes();
            app.updateTreeChecksFromSelection();

        end


        function expandAllAtlasTreeNodes(app)
            %EXPANDALLATLANTREENODES Expand all tree nodes by default.

            if isempty(app.Tree.Children)
                return
            end

            iExpandNodeRecursive(app.Tree.Children(1));

            function iExpandNodeRecursive(node)
                try
                    expand(node);
                catch
                end

                for iChild = 1:numel(node.Children)
                    iExpandNodeRecursive(node.Children(iChild));
                end
            end

        end


        function onAtlasTreeCheckedNodesChanged(app)
            %ONATLASTREECHECKEDNODESCHANGED Apply checkbox tree selection changes.

            if app.IsUpdatingTree
                return
            end

            newKeys = app.getCheckedNodeKeys();
            oldKeys = app.CheckedTreeNodeKeys(:);

            addedKeys = setdiff(newKeys, oldKeys, 'stable');
            removedKeys = setdiff(oldKeys, newKeys, 'stable');

            for iKey = 1:numel(addedKeys)
                app.applyAtlasTreeNodeCheckChange(addedKeys(iKey), true);
            end

            for iKey = 1:numel(removedKeys)
                app.applyAtlasTreeNodeCheckChange(removedKeys(iKey), false);
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

        end


        function onAtlasTreeSelectionChanged(app)
            %ONATLASTREESELECTIONCHANGED Collapse merged module nodes if needed.

            app.collapseMergedModuleNodes();

        end


        function applyAtlasTreeNodeCheckChange(app, nodeKey, bChecked)
            %APPLYATLANTREENODECHECKCHANGE Apply user checkbox change.

            parts = split(string(nodeKey), "|");

            if numel(parts) ~= 2
                return
            end

            switch parts(1)
                case "root"
                    app.selectAllAtlasTreeItems(bChecked);

                case "module"
                    app.selectModuleBaseFromTreeOrSearch(parts(2), bChecked);

                case "area"
                    moduleKey = app.getModuleKeyForAreaBase(parts(2));

                    if moduleKey ~= "" && app.isModuleMerged(moduleKey)
                        return
                    end

                    app.selectAreaBaseFromTreeOrSearch(parts(2), bChecked);
            end

        end


        function updateTreeChecksFromSelection(app)
            %UPDATETREECHECKSFROMSELECTION Reflect current selection in checkboxes.
            %
            %   CheckBoxTree.CheckedNodes is picky in some MATLAB releases. In particular,
            %   assigning matlab.ui.container.TreeNode.empty can fail even when it represents
            %   an empty TreeNode array. This function therefore collects checked nodes in a
            %   cell array first and assigns [] when no valid checked tree nodes exist.

            if isempty(app.Tree.Children)
                return
            end

            app.IsUpdatingTree = true;
            cleanupObj = onCleanup(@() app.setTreeUpdatingFalse());

            checkedNodeList = {};

            % ---------------------------------------------------------------------
            % Area nodes: checked when at least one side-specific area is selected.
            % ---------------------------------------------------------------------
            areaBaseKeys = keys(app.TreeNodeByAreaBaseKey);

            for iKey = 1:numel(areaBaseKeys)
                areaBaseKey = string(areaBaseKeys{iKey});
                node = app.TreeNodeByAreaBaseKey(areaBaseKeys{iKey});

                if isempty(node) || ~isvalid(node)
                    continue
                end

                data = node.NodeData;

                if isfield(data, 'moduleBaseKey') && app.isModuleMerged(data.moduleBaseKey)
                    continue
                end

                areaKeys = string(data.areaKeys(:));

                if any(ismember(areaKeys, app.SelectedAreaKeys))
                    checkedNodeList{end+1} = node; %#ok<AGROW>
                end
            end

            % ---------------------------------------------------------------------
            % Module nodes:
            %   merged   -> checked if at least one side-specific module is selected
            %   unmerged -> checked if all child side-specific areas are selected
            % ---------------------------------------------------------------------
            moduleBaseKeys = keys(app.TreeNodeByModuleBaseKey);

            for iKey = 1:numel(moduleBaseKeys)
                moduleBaseKey = string(moduleBaseKeys{iKey});
                node = app.TreeNodeByModuleBaseKey(moduleBaseKeys{iKey});

                if isempty(node) || ~isvalid(node)
                    continue
                end

                if app.isModuleMerged(moduleBaseKey)
                    moduleKeys = app.getModuleSideKeys(moduleBaseKey);

                    if any(ismember(moduleKeys, app.SelectedModuleKeys))
                        checkedNodeList{end+1} = node; %#ok<AGROW>
                    end
                else
                    areaKeys = app.getAreaKeysForModule(moduleBaseKey);

                    if ~isempty(areaKeys) && all(ismember(areaKeys, app.SelectedAreaKeys))
                        checkedNodeList{end+1} = node; %#ok<AGROW>
                    end
                end
            end

            % ---------------------------------------------------------------------
            % Root node: checked only if all module nodes are checked.
            % ---------------------------------------------------------------------
            rootNode = app.Tree.Children(1);
            moduleNodes = rootNode.Children;

            if ~isempty(moduleNodes) && ~isempty(checkedNodeList)
                checkedNodesTmp = [checkedNodeList{:}];

                if all(ismember(moduleNodes, checkedNodesTmp))
                    checkedNodeList{end+1} = rootNode; %#ok<AGROW>
                end
            end

            % ---------------------------------------------------------------------
            % Assign CheckedNodes robustly.
            % ---------------------------------------------------------------------
            if isempty(checkedNodeList)
                checkedNodes = [];
            else
                checkedNodes = [checkedNodeList{:}];

                % Keep only live nodes that still belong to this tree.
                validNodeMask = false(size(checkedNodes));

                for iNode = 1:numel(checkedNodes)
                    validNodeMask(iNode) = app.isTreeNodeInTree(checkedNodes(iNode));
                end

                checkedNodes = checkedNodes(validNodeMask);

                if isempty(checkedNodes)
                    checkedNodes = [];
                end
            end

            app.Tree.CheckedNodes = checkedNodes;
            app.CheckedTreeNodeKeys = app.getNodeKeysFromNodes(checkedNodes);

            clear cleanupObj
            app.IsUpdatingTree = false;

            app.collapseMergedModuleNodes();

        end


        function tf = isTreeNodeInTree(app, node)
            %ISTREENODEINTREE True when node is a live descendant of app.Tree.

            tf = false;

            if isempty(node) || ~isvalid(node)
                return
            end

            try
                currentNode = node;

                while ~isempty(currentNode) && isvalid(currentNode)
                    parentObj = currentNode.Parent;

                    if isequal(parentObj, app.Tree)
                        tf = true;
                        return
                    end

                    if isa(parentObj, 'matlab.ui.container.TreeNode')
                        currentNode = parentObj;
                    else
                        return
                    end
                end
            catch
                tf = false;
            end

        end


        function setTreeUpdatingFalse(app)
            %SETTREEUPDATINGFALSE Cleanup helper for onCleanup.

            app.IsUpdatingTree = false;

        end


        function checkedKeys = getCheckedNodeKeys(app)
            %GETCHECKEDNODEKEYS Return checked tree node keys.

            try
                checkedNodes = app.Tree.CheckedNodes;
            catch
                checkedNodes = matlab.ui.container.TreeNode.empty;
            end

            checkedKeys = app.getNodeKeysFromNodes(checkedNodes);

        end


        function nodeKeys = getNodeKeysFromNodes(app, nodeArray) %#ok<INUSL>
            %GETNODEKEYSFROMNODES Extract NodeData.nodeKey strings.

            nodeKeys = strings(0, 1);

            for iNode = 1:numel(nodeArray)
                data = nodeArray(iNode).NodeData;

                if isstruct(data) && isfield(data, 'nodeKey')
                    nodeKeys(end+1, 1) = string(data.nodeKey); %#ok<AGROW>
                end
            end

            nodeKeys = unique(nodeKeys, 'stable');

        end


        function collapseMergedModuleNodes(app)
            %COLLAPSEMERGEDMODULENODES Force merged module nodes to stay collapsed.

            for iKey = 1:numel(app.MergedModuleBaseKeys)
                app.collapseModuleNode(app.MergedModuleBaseKeys(iKey));
            end

        end


        function collapseModuleNode(app, moduleBaseKey)
            %COLLAPSEMODULENODE Collapse one module node if possible.

            moduleBaseKey = string(moduleBaseKey);

            if ~isKey(app.TreeNodeByModuleBaseKey, char(moduleBaseKey))
                return
            end

            try
                collapse(app.TreeNodeByModuleBaseKey(char(moduleBaseKey)));
            catch
            end

        end


        function expandModuleNode(app, moduleBaseKey)
            %EXPANDMODULENODE Expand one module node if possible and not merged.

            moduleBaseKey = string(moduleBaseKey);

            if app.isModuleMerged(moduleBaseKey)
                app.collapseModuleNode(moduleBaseKey);
                return
            end

            if ~isKey(app.TreeNodeByModuleBaseKey, char(moduleBaseKey))
                return
            end

            try
                expand(app.TreeNodeByModuleBaseKey(char(moduleBaseKey)));
            catch
            end

        end


        function expandParentModuleForArea(app, areaBaseKey)
            %EXPANDPARENTMODULEFORAREA Expand area's parent module.

            moduleKey = app.getModuleKeyForAreaBase(areaBaseKey);

            if moduleKey ~= ""
                app.expandModuleNode(moduleKey);
            end

        end


        % =====================================================================
        % Map drawing and map interaction
        % =====================================================================

        function createAtlasMapLayers(app)
            %CREATEATLASMAPLAYERS Draw module, area, and boundary layers.
            %
            %   Bregma/Lambda markers are intentionally not drawn in this selector.
            %   They are drawn during atlas fitting in the main DataViewer app.

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            app.AreaHandleByAreaKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleHandleByModuleKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.AreaPatchHandles = gobjects(0);
            app.ModulePatchHandles = gobjects(0);

            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Box = 'on';
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.DataAspectRatio = [1 1 1];

            for iModule = 1:height(app.ModuleTable)
                moduleKey = app.ModuleTable.TagFull(iModule);
                pgon = app.cluster_polyshapes(app.ModuleTable.ModuleIdx(iModule));

                h = plot(app.UIAxes, pgon);
                h.FaceColor = [0.85 0.85 0.85];
                h.FaceAlpha = 0.12;
                h.EdgeColor = 'none';
                h.LineWidth = 0.5;
                h.HitTest = 'on';
                h.PickableParts = 'visible';
                h.ButtonDownFcn = @(src, evt) app.onAtlasModulePatchClicked(moduleKey);

                app.ModulePatchHandles(end+1) = h; %#ok<AGROW>
                app.ModuleHandleByModuleKey(char(moduleKey)) = h;
            end

            for iArea = 1:height(app.AreaTable)
                areaKey = app.AreaTable.TagFull(iArea);
                pgon = app.area_polyshapes(app.AreaTable.AreaIdx(iArea));

                h = plot(app.UIAxes, pgon);
                h.FaceColor = [0.85 0.85 0.85];
                h.FaceAlpha = 0.20;
                h.EdgeColor = 'none';
                h.LineWidth = 0.5;
                h.HitTest = 'on';
                h.PickableParts = 'visible';
                h.ButtonDownFcn = @(src, evt) app.onAtlasAreaPatchClicked(areaKey);

                app.AreaPatchHandles(end+1) = h; %#ok<AGROW>
                app.AreaHandleByAreaKey(char(areaKey)) = h;
            end

            [Ny, Nx] = size(app.bound_mask);
            blackRGB = zeros(Ny, Nx, 3, 'single');

            app.BoundaryHandle = image(app.UIAxes, ...
                'CData', blackRGB, ...
                'AlphaData', 0.95 .* double(app.bound_mask), ...
                'XData', [1 Nx], ...
                'YData', [1 Ny], ...
                'HitTest', 'off', ...
                'PickableParts', 'none');

            axis(app.UIAxes, 'image');
            app.UIAxes.YDir = 'reverse';
            title(app.UIAxes, 'Interactive Map');

            hold(app.UIAxes, 'off');
            app.stackAtlasTopLayer();

        end


        function updateAtlasMapDisplay(app)
            %UPDATEATLASMAPDISPLAY Refresh map colors/visibility from state.

            gray = [0.85 0.85 0.85];

            for iModule = 1:height(app.ModuleTable)
                moduleKey = app.ModuleTable.TagFull(iModule);
                moduleBaseKey = app.ModuleTable.ModuleBaseKey(iModule);

                if ~isKey(app.ModuleHandleByModuleKey, char(moduleKey))
                    continue
                end

                h = app.ModuleHandleByModuleKey(char(moduleKey));

                if isempty(h) || ~all(isvalid(h))
                    continue
                end

                bMerged = app.isModuleMerged(moduleBaseKey);
                bSelected = app.isKeySelected(moduleKey, app.SelectedModuleKeys);

                h.Visible = 'on';
                h.HitTest = 'on';
                h.PickableParts = 'visible';

                if bMerged && bSelected
                    h.FaceColor = app.ModuleTable.Color{iModule};
                    h.FaceAlpha = 0.65;
                    h.EdgeColor = [0 0 0];
                    h.LineWidth = 1.0;
                elseif bMerged
                    h.FaceColor = gray;
                    h.FaceAlpha = 0.20;
                    h.EdgeColor = 'none';
                    h.LineWidth = 0.5;
                else
                    h.FaceColor = gray;
                    h.FaceAlpha = 0.10;
                    h.EdgeColor = 'none';
                    h.LineWidth = 0.5;
                end
            end

            for iArea = 1:height(app.AreaTable)
                areaKey = app.AreaTable.TagFull(iArea);
                moduleBaseKey = app.AreaTable.ModuleBaseKey(iArea);

                if ~isKey(app.AreaHandleByAreaKey, char(areaKey))
                    continue
                end

                h = app.AreaHandleByAreaKey(char(areaKey));

                if isempty(h) || ~all(isvalid(h))
                    continue
                end

                if app.isModuleMerged(moduleBaseKey)
                    h.Visible = 'off';
                    h.HitTest = 'off';
                    h.PickableParts = 'none';
                    continue
                end

                bSelected = app.isKeySelected(areaKey, app.SelectedAreaKeys);

                h.Visible = 'on';
                h.HitTest = 'on';
                h.PickableParts = 'visible';

                if bSelected
                    h.FaceColor = app.AreaTable.Color{iArea};
                    h.FaceAlpha = 0.70;
                    h.EdgeColor = [0 0 0];
                    h.LineWidth = 0.8;
                else
                    h.FaceColor = gray;
                    h.FaceAlpha = 0.20;
                    h.EdgeColor = 'none';
                    h.LineWidth = 0.5;
                end
            end

            app.stackAtlasTopLayer();
            drawnow limitrate

        end


        function stackAtlasTopLayer(app)
            %STACKATLASTOPLAYER Keep boundary mask above polygons.

            try
                if ~isempty(app.BoundaryHandle) && isvalid(app.BoundaryHandle)
                    uistack(app.BoundaryHandle, 'top');
                end
            catch
            end

        end


        function onAtlasAreaPatchClicked(app, areaKey)
            %ONATLASAREAPATCHCLICKED Toggle one side-specific area from map.

            areaKey = string(areaKey);
            row = find(app.AreaTable.TagFull == areaKey, 1, 'first');

            if isempty(row)
                return
            end

            moduleBaseKey = app.AreaTable.ModuleBaseKey(row);

            if app.isModuleMerged(moduleBaseKey)
                return
            end

            if app.isKeySelected(areaKey, app.SelectedAreaKeys)
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKey, 'stable');
            else
                app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKey], 'stable');
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

        end


        function onAtlasModulePatchClicked(app, moduleKey)
            %ONATLASMODULEPATCHCLICKED Toggle one side-specific module if merged.

            moduleKey = string(moduleKey);
            row = find(app.ModuleTable.TagFull == moduleKey, 1, 'first');

            if isempty(row)
                return
            end

            moduleBaseKey = app.ModuleTable.ModuleBaseKey(row);

            if ~app.isModuleMerged(moduleBaseKey)
                return
            end

            if app.isKeySelected(moduleKey, app.SelectedModuleKeys)
                app.SelectedModuleKeys = setdiff(app.SelectedModuleKeys(:), moduleKey, 'stable');
            else
                app.SelectedModuleKeys = unique([app.SelectedModuleKeys(:); moduleKey], 'stable');
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

        end


        % =====================================================================
        % Search/dropdown
        % =====================================================================

        function updateAtlasSearchResults(app, queryText)
            %UPDATEATLASEARCHRESULTS Update dropdown matches using contains search.
            %
            %   A placeholder first item prevents the first real search result from
            %   being preselected, so selecting it triggers ValueChangedFcn normally.

            if app.IsUpdatingSearch
                return
            end

            queryText = strtrim(lower(char(string(queryText))));

            if isempty(queryText)
                app.resetSearchResultsDropdown();
                app.hideSearchResultsDropdown();
                return
            end

            idx = contains(app.SearchTable.SearchText, queryText);

            if ~any(idx)
                app.SearchResultsDropDown.Items = {'No matches'};
                app.SearchResultsDropDown.ItemsData = {'__none__'};
                app.SearchResultsDropDown.Value = '__none__';
                app.showSearchResultsDropdown();
                return
            end

            matches = app.SearchTable(idx, :);
            matches = matches(1:min(20, height(matches)), :);

            app.IsUpdatingSearch = true;
            cleanupObj = onCleanup(@() app.setSearchUpdatingFalse());

            app.SearchResultsDropDown.Items = [{'Select search result...'}; cellstr(matches.DisplayText)];
            app.SearchResultsDropDown.ItemsData = [{'__select__'}; cellstr(matches.ItemKey)];
            app.SearchResultsDropDown.Value = '__select__';

            clear cleanupObj
            app.IsUpdatingSearch = false;

            app.showSearchResultsDropdown();

        end


        function selectAtlasItemFromSearchKey(app, itemKey)
            %SELECTATLASITEMFROMSEARCHKEY Select area/module chosen in dropdown.

            if app.IsUpdatingSearch
                return
            end

            itemKey = string(itemKey);

            if itemKey == "" || itemKey == "__select__" || itemKey == "__none__"
                return
            end

            parts = split(itemKey, "|");

            if numel(parts) ~= 2
                return
            end

            switch parts(1)
                case "area"
                    app.selectAreaBaseFromTreeOrSearch(parts(2), true);
                    app.expandParentModuleForArea(parts(2));

                case "module"
                    app.selectModuleBaseFromTreeOrSearch(parts(2), true);
                    app.expandModuleNode(parts(2));
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

            app.IsUpdatingSearch = true;
            cleanupObj = onCleanup(@() app.setSearchUpdatingFalse());

            app.SearchField.Value = '';
            app.resetSearchResultsDropdown();

            clear cleanupObj
            app.IsUpdatingSearch = false;

            app.hideSearchResultsDropdown();

        end


        function resetSearchResultsDropdown(app)
            %RESETSEARCHRESULTSDROPDOWN Reset dropdown to hidden placeholder state.

            app.SearchResultsDropDown.Items = {'Select search result...'};
            app.SearchResultsDropDown.ItemsData = {'__select__'};
            app.SearchResultsDropDown.Value = '__select__';

        end


        function hideSearchResultsDropdown(app)
            %HIDESEARCHRESULTSDROPDOWN Hide dropdown and collapse its grid row.

            try
                app.SearchResultsDropDown.Visible = 'off';
            catch
            end

            try
                rowHeight = app.GridLayout2.RowHeight;
                rowHeight{2} = 0;
                rowHeight{3} = 325;
                app.GridLayout2.RowHeight = rowHeight;
            catch
            end

        end


        function showSearchResultsDropdown(app)
            %SHOWSEARCHRESULTSDROPDOWN Show dropdown and restore its grid row.

            try
                app.SearchResultsDropDown.Visible = 'on';
            catch
            end

            try
                rowHeight = app.GridLayout2.RowHeight;
                rowHeight{2} = 30;
                rowHeight{3} = 295;
                app.GridLayout2.RowHeight = rowHeight;
            catch
            end

        end


        function setSearchUpdatingFalse(app)
            %SETSEARCHUPDATINGFALSE Cleanup helper for onCleanup.

            app.IsUpdatingSearch = false;

        end


        % =====================================================================
        % Selection state and merge/unmerge
        % =====================================================================

        function selectAllAtlasTreeItems(app, bSelected)
            %SELECTALLATLANTREEITEMS Select/unselect all tree items.

            if ~bSelected
                app.SelectedAreaKeys = strings(0, 1);
                app.SelectedModuleKeys = strings(0, 1);
                return
            end

            areaKeys = strings(0, 1);
            moduleKeys = strings(0, 1);
            moduleBaseKeys = unique(app.AreaTable.ModuleBaseKey, 'stable');

            for iModule = 1:numel(moduleBaseKeys)
                if app.isModuleMerged(moduleBaseKeys(iModule))
                    moduleKeys = [moduleKeys; app.getModuleSideKeys(moduleBaseKeys(iModule))]; %#ok<AGROW>
                else
                    areaKeys = [areaKeys; app.getAreaKeysForModule(moduleBaseKeys(iModule))]; %#ok<AGROW>
                end
            end

            app.SelectedAreaKeys = unique(areaKeys, 'stable');
            app.SelectedModuleKeys = unique(moduleKeys, 'stable');

        end


        function selectAreaBaseFromTreeOrSearch(app, areaBaseKey, bSelected)
            %SELECTAREABASEFROMTREEORSEARCH Select/unselect both sides of one area.

            areaKeys = app.AreaTable.TagFull(app.AreaTable.TagBase == string(areaBaseKey));

            if bSelected
                app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKeys(:)], 'stable');
            else
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');
            end

        end


        function selectModuleBaseFromTreeOrSearch(app, moduleBaseKey, bSelected)
            %SELECTMODULEBASEFROMTREEORSEARCH Select module or its child areas.
            %
            %   If the module is merged, the side-specific module/cluster keys are
            %   selected. If it is unmerged, selecting the module selects all child areas.

            moduleBaseKey = string(moduleBaseKey);

            if app.isModuleMerged(moduleBaseKey)
                moduleKeys = app.getModuleSideKeys(moduleBaseKey);

                if bSelected
                    app.SelectedModuleKeys = unique([app.SelectedModuleKeys(:); moduleKeys(:)], 'stable');
                else
                    app.SelectedModuleKeys = setdiff(app.SelectedModuleKeys(:), moduleKeys(:), 'stable');
                end

                return
            end

            areaKeys = app.getAreaKeysForModule(moduleBaseKey);

            if bSelected
                app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKeys(:)], 'stable');
            else
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');
            end

        end


        function attachModuleContextMenu(app, moduleNode, moduleBaseKey)
            %ATTACHMODULECONTEXTMENU Add merge/unmerge context menu to module node.
            %
            %   Node context menus are MATLAB-version dependent, so this is intentionally
            %   defensive. Failure here should not prevent basic atlas selection.

            try
                cm = uicontextmenu(app.UIFigure);

                mergeMenu = uimenu(cm);
                mergeMenu.Text = 'Merge module';
                mergeMenu.MenuSelectedFcn = @(src, evt) app.mergeAtlasModule(moduleBaseKey);

                unmergeMenu = uimenu(cm);
                unmergeMenu.Text = 'Unmerge module';
                unmergeMenu.MenuSelectedFcn = @(src, evt) app.unmergeAtlasModule(moduleBaseKey);

                moduleNode.ContextMenu = cm;
            catch
            end

        end


        function mergeAtlasModule(app, moduleBaseKey)
            %MERGEATLASMODULE Merge a side-collapsed module.

            moduleBaseKey = string(moduleBaseKey);
            app.MergedModuleBaseKeys = unique([app.MergedModuleBaseKeys(:); moduleBaseKey], 'stable');

            areaKeys = app.getAreaKeysForModule(moduleBaseKey);
            bAnyChildSelected = any(ismember(areaKeys, app.SelectedAreaKeys));

            app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');

            if bAnyChildSelected
                moduleKeys = app.getModuleSideKeys(moduleBaseKey);
                app.SelectedModuleKeys = unique([app.SelectedModuleKeys(:); moduleKeys(:)], 'stable');
            end

            app.updateModuleNodeMergedText(moduleBaseKey);
            app.collapseModuleNode(moduleBaseKey);
            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

        end


        function unmergeAtlasModule(app, moduleBaseKey)
            %UNMERGEATLASMODULE Unmerge module and select all child areas.

            moduleBaseKey = string(moduleBaseKey);

            app.MergedModuleBaseKeys = setdiff(app.MergedModuleBaseKeys(:), moduleBaseKey, 'stable');

            moduleKeys = app.getModuleSideKeys(moduleBaseKey);
            app.SelectedModuleKeys = setdiff(app.SelectedModuleKeys(:), moduleKeys(:), 'stable');

            areaKeys = app.getAreaKeysForModule(moduleBaseKey);
            app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKeys(:)], 'stable');

            app.updateModuleNodeMergedText(moduleBaseKey);
            app.expandModuleNode(moduleBaseKey);
            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

        end


        function updateModuleNodeMergedText(app, moduleBaseKey)
            %UPDATEMODULENODEMERGEDTEXT Append/remove " - merged" in tree text.

            moduleBaseKey = string(moduleBaseKey);

            if ~isKey(app.TreeNodeByModuleBaseKey, char(moduleBaseKey))
                return
            end

            node = app.TreeNodeByModuleBaseKey(char(moduleBaseKey));
            idx = find(app.ModuleTable.ModuleBaseKey == moduleBaseKey, 1, 'first');

            if isempty(idx)
                moduleName = moduleBaseKey;
                moduleTag = moduleBaseKey;
            else
                moduleName = app.ModuleTable.LongNameBase(idx);
                moduleTag = app.ModuleTable.ModuleBaseKey(idx);
            end

            node.Text = app.formatAtlasTreeNodeText(moduleName, moduleTag, app.isModuleMerged(moduleBaseKey));

        end


        function moduleKey = getModuleKeyForAreaBase(app, areaBaseKey)
            %GETMODULEKEYFORAREABASE Return module base key for area base key.

            idx = find(app.AreaTable.TagBase == string(areaBaseKey), 1, 'first');

            if isempty(idx)
                moduleKey = "";
            else
                moduleKey = app.AreaTable.ModuleBaseKey(idx);
            end

        end


        function areaKeys = getAreaKeysForModule(app, moduleBaseKey)
            %GETAREAKEYSFORMODULE Return side-specific area tags for module.

            moduleBaseKey = string(moduleBaseKey);

            if isKey(app.AreaKeysByModuleBaseKey, char(moduleBaseKey))
                areaKeys = string(app.AreaKeysByModuleBaseKey(char(moduleBaseKey)));
            else
                areaKeys = strings(0, 1);
            end

            areaKeys = areaKeys(:);

        end


        function moduleKeys = getModuleSideKeys(app, moduleBaseKey)
            %GETMODULESIDEKEYS Return side-specific module tags.

            moduleBaseKey = string(moduleBaseKey);

            if isKey(app.ModuleKeysByModuleBaseKey, char(moduleBaseKey))
                moduleKeys = string(app.ModuleKeysByModuleBaseKey(char(moduleBaseKey)));
            else
                moduleKeys = strings(0, 1);
            end

            moduleKeys = moduleKeys(:);

        end


        function tf = isModuleMerged(app, moduleBaseKey)
            %ISMODULEMERGED True if side-collapsed module is merged.

            tf = any(app.MergedModuleBaseKeys == string(moduleBaseKey));

        end


        function tf = isKeySelected(app, key, keyList) %#ok<INUSL>
            %ISKEYSELECTED True if key exists in string keyList.

            tf = any(string(keyList(:)) == string(key));

        end


        % =====================================================================
        % OK result handoff
        % =====================================================================

        function result = buildAtlasSelectionResult(app)
            %BUILDATLASSELECTIONRESULT Build selected-region output for DataViewer.

            result = struct();
            result.confirmed = true;
            result.createdBy = 'AllenAtlasROICreator';
            result.createdOn = datetime('now');

            result.atlasFile = app.AtlasFile;
            result.BregmaCoordsXY = double(app.BregmaCoordsXY(:).');
            result.LambdaCoordsXY = double(app.LambdaCoordsXY(:).');
            result.pixels_per_mm = double(app.pixels_per_mm(1));
            result.atlasImageSizeYX = double(size(app.bound_mask));
            result.boundaryVerticesXY = app.getAtlasBoundaryVerticesFromMask();

            selectedRegions = app.buildSelectedAtlasRegionStructArray();

            result.selectedRegions = selectedRegions;
            result.selectedAreaTags = cellstr(app.SelectedAreaKeys(:));
            result.selectedModuleTags = cellstr(app.SelectedModuleKeys(:));
            result.mergedModuleTags = cellstr(app.MergedModuleBaseKeys(:));

        end


        function selectedRegions = buildSelectedAtlasRegionStructArray(app)
            %BUILDSELECTEDATLASREGIONSTRUCTARRAY Return selected atlas area/module records.

            selectedRegions = app.makeEmptyAtlasOutputRegionStruct();

            areaKeys = string(app.SelectedAreaKeys(:));

            for iKey = 1:numel(areaKeys)
                idx = find(app.AreaTable.TagFull == areaKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                pgon = app.area_polyshapes(app.AreaTable.AreaIdx(idx));
                selectedRegions(end+1) = app.makeAtlasOutputRegionRecord( ... %#ok<AGROW>
                    'area', ...
                    app.AreaTable.TagFull(idx), ...
                    app.AreaTable.TagBase(idx), ...
                    app.AreaTable.Side(idx), ...
                    app.AreaTable.LongNameBase(idx), ...
                    app.AreaTable.LongNameFull(idx), ...
                    app.AreaTable.ModuleBaseKey(idx), ...
                    app.AreaTable.ParentBase(idx), ...
                    app.AreaTable.Color{idx}, ...
                    pgon);
            end

            moduleKeys = string(app.SelectedModuleKeys(:));

            for iKey = 1:numel(moduleKeys)
                idx = find(app.ModuleTable.TagFull == moduleKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                pgon = app.cluster_polyshapes(app.ModuleTable.ModuleIdx(idx));
                selectedRegions(end+1) = app.makeAtlasOutputRegionRecord( ... %#ok<AGROW>
                    'module', ...
                    app.ModuleTable.TagFull(idx), ...
                    app.ModuleTable.ModuleBaseKey(idx), ...
                    app.ModuleTable.Side(idx), ...
                    app.ModuleTable.LongNameBase(idx), ...
                    app.ModuleTable.LongNameFull(idx), ...
                    app.ModuleTable.ModuleBaseKey(idx), ...
                    app.ModuleTable.LongNameBase(idx), ...
                    app.ModuleTable.Color{idx}, ...
                    pgon);
            end

            if ~isempty(selectedRegions) && isempty(selectedRegions(1).tag)
                selectedRegions(1) = [];
            end

        end


        function rec = makeAtlasOutputRegionRecord(app, typeText, tag, baseTag, sideText, ...
                nameText, fullNameText, parentTag, parentName, colorRGB, pgon)
            %MAKEATLASOUTPUTREGIONRECORD Build one selected atlas output region.

            rec = app.makeEmptyAtlasOutputRegionStruct();
            rec.type = char(string(typeText));
            rec.tag = char(string(tag));
            rec.baseTag = char(string(baseTag));
            rec.side = char(string(sideText));
            rec.name = char(string(nameText));
            rec.fullName = char(string(fullNameText));
            rec.parentTag = char(string(parentTag));
            rec.parentName = char(string(parentName));
            rec.color = min(max(double(colorRGB(:).'), 0), 1);
            rec.polyshape = pgon;
            rec.verticesXY = app.getVerticesFromAtlasPolyshape(pgon);

        end


        function rec = makeEmptyAtlasOutputRegionStruct(app) %#ok<MANU>
            %MAKEEMPTYATLASOUTPUTREGIONSTRUCT Return empty selected-region template.

            rec = struct( ...
                'type', '', ...
                'tag', '', ...
                'baseTag', '', ...
                'side', '', ...
                'name', '', ...
                'fullName', '', ...
                'parentTag', '', ...
                'parentName', '', ...
                'color', [0.5 0.5 0.5], ...
                'polyshape', polyshape(), ...
                'verticesXY', zeros(0, 2));

        end


        function verticesXY = getVerticesFromAtlasPolyshape(app, pgon) %#ok<INUSL>
            %GETVERTICESFROMATLASPOLYSHAPE Extract vertices from atlas polyshape.

            verticesXY = double(pgon.Vertices);

            if isempty(verticesXY) || size(verticesXY, 2) ~= 2
                verticesXY = zeros(0, 2);
            end

        end


        function boundaryVerticesXY = getAtlasBoundaryVerticesFromMask(app)
            %GETATLASBOUNDARYVERTICESFROMMASK Convert bound_mask to boundary polylines.
            %
            %   Output is a cell array of N-by-2 [X Y] vertex arrays.

            boundaryVerticesXY = {};

            if isempty(app.bound_mask) || ~any(app.bound_mask(:))
                return
            end

            try
                B = bwboundaries(app.bound_mask, 8, 'noholes');
            catch
                B = bwboundaries(app.bound_mask);
            end

            for iB = 1:numel(B)
                rc = double(B{iB});

                if size(rc, 1) < 2
                    continue
                end

                boundaryVerticesXY{end+1} = [rc(:, 2), rc(:, 1)]; %#ok<AGROW>
            end

        end


        % =====================================================================
        % Remembered selection JSON
        % =====================================================================

        function applyRememberedAtlasSelectionFromJSON(app)
            %APPLYREMEMBEREDATLASSELECTIONFROMJSON Restore remembered atlas selection.
            %
            %   Missing files and stale tags are ignored. This keeps startup robust if
            %   users move/delete the JSON file or update atlas data.

            [~, jsonFile] = app.getAtlasSelectionJSONPath();

            if ~isfile(jsonFile)
                return
            end

            try
                savedSelection = jsondecode(fileread(jsonFile));
            catch ME
                warning('AllenAtlasROICreator:RememberedSelectionReadFailed', ...
                    'Could not read remembered atlas selection: %s', ME.message);
                return
            end

            [selectedAreaTags, selectedModuleTags, mergedModuleTags] = ...
                app.parseRememberedAtlasSelection(savedSelection);

            selectedAreaTags = app.keepValidRememberedKeys(selectedAreaTags, app.AreaTable.TagFull);
            selectedModuleTags = app.keepValidRememberedKeys(selectedModuleTags, app.ModuleTable.TagFull);
            mergedModuleTags = app.keepValidRememberedKeys(mergedModuleTags, app.ModuleTable.ModuleBaseKey);

            app.MergedModuleBaseKeys = unique(mergedModuleTags(:), 'stable');
            app.SelectedAreaKeys = unique(selectedAreaTags(:), 'stable');
            app.SelectedModuleKeys = unique(selectedModuleTags(:), 'stable');

            % A merged module is represented by the module layer. Remove selected child
            % area keys from merged modules to avoid hidden selections.
            for iModule = 1:numel(app.MergedModuleBaseKeys)
                areaKeys = app.getAreaKeysForModule(app.MergedModuleBaseKeys(iModule));
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');
            end

            for iModule = 1:numel(app.MergedModuleBaseKeys)
                app.updateModuleNodeMergedText(app.MergedModuleBaseKeys(iModule));
                app.collapseModuleNode(app.MergedModuleBaseKeys(iModule));
            end

            app.showStatusInTitle('remembered selection restored');

        end


        function [selectedAreaTags, selectedModuleTags, mergedModuleTags] = parseRememberedAtlasSelection(app, savedSelection)
            %PARSEREMEMBEREDATLASSELECTION Extract tag arrays from remembered JSON.

            selectedAreaTags = strings(0, 1);
            selectedModuleTags = strings(0, 1);
            mergedModuleTags = strings(0, 1);

            if ~isstruct(savedSelection) || ~isscalar(savedSelection)
                return
            end

            selectedAreaTags = app.readStringVectorField(savedSelection, 'selectedAreaTags');
            selectedModuleTags = app.readStringVectorField(savedSelection, 'selectedModuleTags');
            mergedModuleTags = app.readStringVectorField(savedSelection, 'mergedModuleTags');

            if isempty(selectedAreaTags) && isfield(savedSelection, 'selectedAreas')
                selectedAreaTags = app.readTagFieldFromRecordArray(savedSelection.selectedAreas, 'tag');
            end

            if isempty(selectedModuleTags) && isfield(savedSelection, 'selectedModules')
                selectedModuleTags = app.readTagFieldFromRecordArray(savedSelection.selectedModules, 'tag');
            end

            if isempty(mergedModuleTags) && isfield(savedSelection, 'mergedModules')
                mergedModuleTags = app.readTagFieldFromRecordArray(savedSelection.mergedModules, 'baseTag');
            end

            selectedAreaTags = unique(selectedAreaTags(:), 'stable');
            selectedModuleTags = unique(selectedModuleTags(:), 'stable');
            mergedModuleTags = unique(mergedModuleTags(:), 'stable');

        end


        function rememberAtlasSelectionToJSON(app)
            %REMEMBERATLASSELECTIONTOJSON Save selected atlas regions to JSON.

            [outFolder, outFile] = app.getAtlasSelectionJSONPath();

            if ~isfolder(outFolder)
                mkdir(outFolder);
            end

            selectionStruct = app.buildAtlasSelectionJSONStruct();

            try
                jsonText = jsonencode(selectionStruct, 'PrettyPrint', true);
            catch
                jsonText = jsonencode(selectionStruct);
            end

            fid = fopen(outFile, 'w');

            if fid < 0
                error('AllenAtlasROICreator:JSONWriteFailed', ...
                    'Could not open file for writing: "%s".', outFile);
            end

            cleaner = onCleanup(@() fclose(fid));
            fwrite(fid, jsonText, 'char');
            clear cleaner

            app.showStatusInTitle(sprintf('selection saved to %s', outFile));

        end


        function selectionStruct = buildAtlasSelectionJSONStruct(app)
            %BUILDATLASSELECTIONJSONSTRUCT Build serializable atlas selection struct.

            selectionStruct = struct();
            selectionStruct.schemaVersion = 1;
            selectionStruct.createdBy = 'AllenAtlasROICreator';
            selectionStruct.savedOn = char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));
            selectionStruct.atlasFile = app.AtlasFile;

            selectionStruct.selectedAreas = app.buildSelectedAreaJSONRecords();
            selectionStruct.selectedModules = app.buildSelectedModuleJSONRecords();
            selectionStruct.mergedModules = app.buildMergedModuleJSONRecords();

            selectionStruct.selectedAreaTags = cellstr(app.SelectedAreaKeys(:));
            selectionStruct.selectedModuleTags = cellstr(app.SelectedModuleKeys(:));
            selectionStruct.mergedModuleTags = cellstr(app.MergedModuleBaseKeys(:));

        end


        function records = buildSelectedAreaJSONRecords(app)
            %BUILDSELECTEDAREAJSONRECORDS Build JSON records for selected areas.

            records = app.makeEmptyAtlasRegionJSONRecords();
            selectedKeys = string(app.SelectedAreaKeys(:));

            for iKey = 1:numel(selectedKeys)
                idx = find(app.AreaTable.TagFull == selectedKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                moduleIdx = find(app.ModuleTable.ModuleBaseKey == app.AreaTable.ModuleBaseKey(idx) & ...
                    app.ModuleTable.Side == app.AreaTable.Side(idx), 1, 'first');

                if isempty(moduleIdx)
                    moduleTag = app.AreaTable.ModuleBaseKey(idx);
                    moduleName = app.AreaTable.ParentBase(idx);
                else
                    moduleTag = app.ModuleTable.TagFull(moduleIdx);
                    moduleName = app.ModuleTable.LongNameBase(moduleIdx);
                end

                records(end+1) = app.makeAtlasRegionJSONRecord( ... %#ok<AGROW>
                    'area', ...
                    app.AreaTable.TagFull(idx), ...
                    app.AreaTable.TagBase(idx), ...
                    app.AreaTable.Side(idx), ...
                    app.AreaTable.LongNameBase(idx), ...
                    app.AreaTable.LongNameFull(idx), ...
                    moduleTag, ...
                    app.AreaTable.ModuleBaseKey(idx), ...
                    moduleName);
            end

        end


        function records = buildSelectedModuleJSONRecords(app)
            %BUILDSELECTEDMODULEJSONRECORDS Build JSON records for selected modules.

            records = app.makeEmptyAtlasRegionJSONRecords();
            selectedKeys = string(app.SelectedModuleKeys(:));

            for iKey = 1:numel(selectedKeys)
                idx = find(app.ModuleTable.TagFull == selectedKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                records(end+1) = app.makeAtlasRegionJSONRecord( ... %#ok<AGROW>
                    'module', ...
                    app.ModuleTable.TagFull(idx), ...
                    app.ModuleTable.ModuleBaseKey(idx), ...
                    app.ModuleTable.Side(idx), ...
                    app.ModuleTable.LongNameBase(idx), ...
                    app.ModuleTable.LongNameFull(idx), ...
                    app.ModuleTable.TagFull(idx), ...
                    app.ModuleTable.ModuleBaseKey(idx), ...
                    app.ModuleTable.LongNameBase(idx));
            end

        end


        function rec = makeAtlasRegionJSONRecord(app, typeText, tag, baseTag, sideText, ...
                nameText, fullNameText, moduleTag, moduleBaseTag, moduleName) %#ok<INUSL>
            %MAKEATLASREGIONJSONRECORD Build one area/module JSON record.

            rec = struct();
            rec.type = char(string(typeText));
            rec.tag = char(string(tag));
            rec.baseTag = char(string(baseTag));
            rec.side = char(string(sideText));
            rec.name = char(string(nameText));
            rec.fullName = char(string(fullNameText));
            rec.moduleTag = char(string(moduleTag));
            rec.moduleBaseTag = char(string(moduleBaseTag));
            rec.moduleName = char(string(moduleName));

        end


        function records = makeEmptyAtlasRegionJSONRecords(app) %#ok<MANU>
            %MAKEEMPTYATLASREGIONJSONRECORDS Return empty region record array.

            records = struct( ...
                'type', {}, ...
                'tag', {}, ...
                'baseTag', {}, ...
                'side', {}, ...
                'name', {}, ...
                'fullName', {}, ...
                'moduleTag', {}, ...
                'moduleBaseTag', {}, ...
                'moduleName', {});

        end


        function records = buildMergedModuleJSONRecords(app)
            %BUILDMERGEDMODULEJSONRECORDS Build JSON records for merged modules.

            records = struct( ...
                'type', {}, ...
                'baseTag', {}, ...
                'name', {}, ...
                'leftTag', {}, ...
                'rightTag', {});

            mergedKeys = string(app.MergedModuleBaseKeys(:));

            for iKey = 1:numel(mergedKeys)
                idx = find(app.ModuleTable.ModuleBaseKey == mergedKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                rec = struct();
                rec.type = 'mergedModule';
                rec.baseTag = char(app.ModuleTable.ModuleBaseKey(idx));
                rec.name = char(app.ModuleTable.LongNameBase(idx));
                rec.leftTag = '';
                rec.rightTag = '';

                idxLeft = find(app.ModuleTable.ModuleBaseKey == mergedKeys(iKey) & ...
                    app.ModuleTable.Side == "L", 1, 'first');
                idxRight = find(app.ModuleTable.ModuleBaseKey == mergedKeys(iKey) & ...
                    app.ModuleTable.Side == "R", 1, 'first');

                if ~isempty(idxLeft)
                    rec.leftTag = char(app.ModuleTable.TagFull(idxLeft));
                end

                if ~isempty(idxRight)
                    rec.rightTag = char(app.ModuleTable.TagFull(idxRight));
                end

                records(end+1) = rec; %#ok<AGROW>
            end

        end


        function values = readStringVectorField(app, S, fieldName) %#ok<INUSL>
            %READSTRINGVECTORFIELD Safely read a JSON-decoded string/cell/char field.

            values = strings(0, 1);

            if ~isstruct(S) || ~isfield(S, fieldName)
                return
            end

            raw = S.(fieldName);

            if isempty(raw)
                return
            end

            try
                values = string(raw);
                values = values(:);
                values = values(strlength(values) > 0);
            catch
                values = strings(0, 1);
            end

        end


        function values = readTagFieldFromRecordArray(app, records, fieldName) %#ok<INUSL>
            %READTAGFIELDFROMRECORDARRAY Read a tag-like field from JSON records.

            values = strings(0, 1);

            if isempty(records) || ~isstruct(records)
                return
            end

            for iRec = 1:numel(records)
                if ~isfield(records(iRec), fieldName)
                    continue
                end

                thisValue = string(records(iRec).(fieldName));

                if strlength(thisValue) > 0
                    values(end+1, 1) = thisValue; %#ok<AGROW>
                end
            end

            values = unique(values, 'stable');

        end


        function validKeys = keepValidRememberedKeys(app, candidateKeys, validKeysReference) %#ok<INUSL>
            %KEEPVALIDREMEMBEREDKEYS Keep only remembered keys present in current atlas.

            candidateKeys = string(candidateKeys(:));
            candidateKeys = candidateKeys(strlength(candidateKeys) > 0);
            validKeysReference = string(validKeysReference(:));

            validKeys = candidateKeys(ismember(candidateKeys, validKeysReference));
            validKeys = unique(validKeys(:), 'stable');

        end


        function [outFolder, outFile] = getAtlasSelectionJSONPath(app)
            %GETATLASSELECTIONJSONPATH Return default JSON save path.

            homeFolder = app.getUserHomeFolder();
            outFolder = fullfile(homeFolder, 'Umit');
            outFile = fullfile(outFolder, 'AllenAtlasROISelection.json');

        end


        function homeFolder = getUserHomeFolder(app) %#ok<INUSL>
            %GETUSERHOMEFOLDER Return cross-platform user home folder.

            homeFolder = '';

            try
                homeFolder = char(java.lang.System.getProperty('user.home'));
            catch
                homeFolder = '';
            end

            if isempty(homeFolder)
                homeFolder = getenv('USERPROFILE');
            end

            if isempty(homeFolder)
                homeFolder = getenv('HOME');
            end

            if isempty(homeFolder)
                homeFolder = pwd;
            end

        end


        % =====================================================================
        % Formatting and atlas string normalization
        % =====================================================================

        function txt = formatAtlasTreeNodeText(app, nameText, tagText, bMerged) %#ok<INUSL>
            %FORMATATLANTREENODETEXT Format module/area node text.

            nameText = strtrim(char(string(nameText)));
            tagText = strtrim(char(string(tagText)));

            if isempty(tagText)
                txt = nameText;
            else
                txt = sprintf('%s (%s)', nameText, tagText);
            end

            if bMerged
                txt = sprintf('%s - merged', txt);
            end

        end


        function [baseName, side] = stripAtlasSideFromName(app, fullName) %#ok<INUSL>
            %STRIPATLASSIDEFROMNAME Remove Left/Right prefix from atlas names.

            txt = strtrim(char(string(fullName)));
            side = "";

            if startsWith(txt, 'Left ', 'IgnoreCase', true)
                side = "L";
                txt = regexprep(txt, '^[Ll]eft\s+', '');

            elseif startsWith(txt, 'Right ', 'IgnoreCase', true)
                side = "R";
                txt = regexprep(txt, '^[Rr]ight\s+', '');
            end

            baseName = string(strtrim(txt));

        end


        function [baseTag, side] = stripAtlasSideFromTag(app, fullTag) %#ok<INUSL>
            %STRIPATLASSIDEFROMTAG Remove _L/_R suffix from atlas tags.

            tag = strtrim(char(string(fullTag)));
            side = "";

            if endsWith(tag, '_L')
                side = "L";
                tag = extractBefore(string(tag), strlength(tag) - 1);
            elseif endsWith(tag, '_R')
                side = "R";
                tag = extractBefore(string(tag), strlength(tag) - 1);
            else
                tag = string(tag);
            end

            baseTag = string(tag);

        end


        % =====================================================================
        % Temporary title status
        % =====================================================================

        function showStatusInTitle(app, msg)
            %SHOWSTATUSINTITLE Show temporary status text in app title for 5 seconds.
            %
            %   The timer is deleted from StopFcn, not TimerFcn, to avoid deleting a
            %   running timer object.

            baseTitle = 'Allen Atlas ROI Creator';
            app.cleanupStatusTitleTimer();

            try
                app.UIFigure.Name = sprintf('%s - %s', baseTitle, char(string(msg)));
            catch
                return
            end

            t = timer( ...
                'ExecutionMode', 'singleShot', ...
                'StartDelay', 5, ...
                'TimerFcn', @(src, evt) app.restoreStatusTitleTimerFcn(baseTitle), ...
                'StopFcn', @(src, evt) app.deleteStatusTitleTimerFcn(src));

            app.StatusTitleTimer = t;
            start(t);

        end


        function restoreStatusTitleTimerFcn(app, baseTitle)
            %RESTORESTATUSTITLETIMERFCN Restore the app title after temporary status.

            try
                if isvalid(app) && isvalid(app.UIFigure)
                    app.UIFigure.Name = baseTitle;
                end
            catch
            end

        end


        function deleteStatusTitleTimerFcn(app, timerObj)
            %DELETESTATUSTITLETIMERFCN Delete stopped status-title timer.

            try
                if isvalid(timerObj)
                    delete(timerObj);
                end
            catch
            end

            try
                if isvalid(app)
                    app.StatusTitleTimer = timer.empty;
                end
            catch
            end

        end


        function cleanupStatusTitleTimer(app)
            %CLEANUPSTATUSTITLETIMER Stop/delete any existing title-status timer.

            try
                if isempty(app.StatusTitleTimer) || ~isvalid(app.StatusTitleTimer)
                    app.StatusTitleTimer = timer.empty;
                    return
                end

                t = app.StatusTitleTimer;
                app.StatusTitleTimer = timer.empty;

                if strcmpi(t.Running, 'on')
                    stop(t);   % StopFcn deletes the stopped timer.
                else
                    delete(t);
                end
            catch
                app.StatusTitleTimer = timer.empty;
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            %STARTUPFCN Load atlas data and initialize selector GUI.
            %
            %   startupFcn(app) starts the selector in standalone/development mode.
            %   startupFcn(app, MainApp) stores the parent DataViewer app handle and
            %   centers this child app inside the caller when possible.
            %
            %   The atlas file is loaded from the same folder as this app:
            %       CCF_2022_mouse_topView_data.mat
            %
            %   If a remembered selection JSON exists under <home>/Umit, selection and
            %   merge state are restored after the tree and map are created.

            app.UIFigure.Visible = 'off';
            app.MainApp = [];

            if nargin >= 2 && ~isempty(MainApp)
                app.MainApp = MainApp;
            end

            app.initializeAtlasRuntimeMaps();
            app.hideSearchResultsDropdown();

            app.loadAtlasInfo();
            app.buildAtlasLookupTables();
            app.populateAtlasTree();
            app.createAtlasMapLayers();

            app.applyRememberedAtlasSelectionFromJSON();
            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

            app.SearchResultsDropDown.Items = {'Select search result...'};
            app.SearchResultsDropDown.ItemsData = {'__select__'};
            app.SearchResultsDropDown.Value = '__select__';
            app.hideSearchResultsDropdown();

            try
                if ~isempty(app.MainApp) && isvalid(app.MainApp) && exist('placeAppInsideCaller', 'file') == 2
                    placeAppInsideCaller(app.MainApp, app, 'center');
                end
            catch
            end

            app.UIFigure.Visible = 'on';
            initializeUmitTheme(app.UIFigure);
        end

        % Value changing function: SearchField
        function SearchFieldValueChanging(app, event)
            app.updateAtlasSearchResults(event.Value);

        end

        % Value changed function: SearchField
        function SearchFieldValueChanged(app, event)
            app.updateAtlasSearchResults(app.SearchField.Value);

        end

        % Value changed function: SearchResultsDropDown
        function SearchResultsDropDownValueChanged(app, event)
            app.selectAtlasItemFromSearchKey(app.SearchResultsDropDown.Value);


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %OKBUTTONPUSHED Return selected atlas regions to the parent DataViewer app.

            result = app.buildAtlasSelectionResult();

            if isempty(result.selectedRegions)
                app.showStatusInTitle('no atlas regions selected');
                return
            end

            app.Result = result;
            app.Confirmed = true;

            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                try
                    app.MainApp.startAllenAtlasROIPlacement(result);
                    app.UIFigure.Visible = 'off';
                catch ME
                    app.showStatusInTitle(sprintf('handoff failed: %s', ME.message));
                    rethrow(ME)
                end
            else
                % Standalone/test mode: keep result in app.Result and close modal wait if used.
                try
                    uiresume(app.UIFigure);
                catch ME
                    rethrow(ME)
                end
                app.showStatusInTitle('selection ready');
            end

        end

        % Callback function: CancelButton, UIFigure
        function CancelButtonPushed(app, event)
            app.cleanupStatusTitleTimer();
            delete(app);

        end

        % Button pushed function: RememberselectionButton
        function RememberselectionButtonPushed(app, event)
            %REMEMBERSELECTIONBUTTONPUSHED Save current atlas ROI selection to JSON.

            app.rememberAtlasSelectionToJSON();

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.onAtlasTreeCheckedNodesChanged();


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.onAtlasTreeSelectionChanged();


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Allen Atlas ROI Creator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Interactive Map')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SearchField = uieditfield(app.GridLayout2, 'text');
            app.SearchField.ValueChangedFcn = createCallbackFcn(app, @SearchFieldValueChanged, true);
            app.SearchField.ValueChangingFcn = createCallbackFcn(app, @SearchFieldValueChanging, true);
            app.SearchField.Placeholder = 'Search for area...';
            app.SearchResultsDropDown = uidropdown(app.GridLayout2);
            app.SearchResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchResultsDropDownValueChanged, true);
            app.SearchResultsDropDown.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.OkButton = uibutton(app.GridLayout3, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RememberselectionButton = uibutton(app.GridLayout3, 'push');
            app.RememberselectionButton.ButtonPushedFcn = createCallbackFcn(app, @RememberselectionButtonPushed, true);
            app.RememberselectionButton.Text = 'Remember selection';
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AllenAtlasROICreator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end