classdef DataViewer_Coreg2Cams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        GrifLayoutLeft                  matlab.ui.container.GridLayout
        CoregistrationSourcePanel       matlab.ui.container.Panel
        GridCoregistrationSource        matlab.ui.container.GridLayout
        LoadCoregistrationDataButton    matlab.ui.control.Button
        CoregistrationFilesPanel        matlab.ui.container.Panel
        GridGeometricPanel              matlab.ui.container.GridLayout
        ManageCoregistrationFilesButton  matlab.ui.control.Button
        CoregistrationFileInfoTextArea  matlab.ui.control.TextArea
        ManualfinetuningPanel           matlab.ui.container.Panel
        GridManualFineTuning            matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        ApplyStepSpinner                matlab.ui.control.Spinner
        ApplyStepSpinnerLabel           matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        TransformationDropDown          matlab.ui.control.DropDown
        TransformationDropDownLabel     matlab.ui.control.Label
        ViewmodeButtonGroup             matlab.ui.container.ButtonGroup
        FalseColorOverlayButton         matlab.ui.control.RadioButton
        AlternateViewButton             matlab.ui.control.RadioButton
        SaveCoregistrationButton        matlab.ui.control.Button
        CoregistrationparametersPanel   matlab.ui.container.Panel
        GridCoregistrationPanel         matlab.ui.container.GridLayout
        FlipHorizontallyCheckBox        matlab.ui.control.CheckBox
        UseenhancedcontrastCheckBox     matlab.ui.control.CheckBox
        RunAutomaticcoregistrationButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Parent app/context.
        MainApp = []
        appMode char = 'CoregistrationUtility'

        % Centralized rig context. UMITRigStore is this app's sole persistence layer
        % for camera coregistration -- a default rig is resolved/auto-created at
        % startup (see ensureRigContext) if the caller didn't already provide one, so
        % HasRigContext should normally always be true once startupFcn completes.
        % cameraCoregistration is a rig-owned resource, completely independent of any
        % project; ProjectStore itself is not required to resolve/save it, but is
        % kept for possible future use and parity with the launch context DataViewer
        % already resolves.
        ProjectStore = []
        RigUUID char = ''
        RigID char = ''
        SubjectUUID char = ''
        SubjectID char = ''
        SessionUUID char = ''
        SessionID char = ''
        HasRigContext logical = false
        RigStoreHandle = [] % Cached UMITRigStore handle; see openRigStore.

        % Coregistration source images. These must represent unregistered camera
        % images. CurrentMovingImage is transformed into CurrentRegisteredMovingImage.
        CurrentFixedImage = []
        CurrentMovingImage = []
        CurrentRegisteredMovingImage = []
        EnhancedFixedImage = []
        EnhancedMovingImage = []
        HasCoregistrationSource logical = false
        CoregistrationSourceFolder char = ''
        CoregistrationSourceDescription char = ''

        % Active coregistration, resolved from UMITRigStore (see loadActiveCoregistration).
        ActiveTform = []
        ActiveTformInfo = struct()

        % Candidate coregistration currently previewed in the app.
        CurrentTform = []
        CurrentTformInfo = struct()
        CandidateSource char = ''
        HasUnsavedCoregistration logical = false

        % Manual adjustment state. These are applied on top of BaseTform.
        BaseTform = []
        ManualDx double = 0
        ManualDy double = 0
        ManualRotationDeg double = 0
        ManualScale double = 1

        % Alternate-view timer state.
        AlternateViewTimer = timer.empty
        AlternateViewImageHandle = []
        AlternateViewIndex double = 1
    end


    properties (Access = public)
        DataFolder                % Optional context folder from the parent app.
    end


    methods (Access = {?DataViewer})
        function rigStore = openRigStore(app)
            %OPENRIGSTORE Return this app's cached rig store handle, opening (and
            %caching) it on first use. Returns [] if there is no rig context or the
            %rig can't be opened. Cached so frequent UI refreshes don't repeatedly
            %pay for UMITRigStore.open's directory scan; each accessor call on the
            %returned handle still reads current on-disk registry data.

            if ~app.HasRigContext
                app.RigStoreHandle = [];
                rigStore = [];
                return
            end

            if ~isempty(app.RigStoreHandle) && isvalid(app.RigStoreHandle)
                rigStore = app.RigStoreHandle;
                return
            end

            rigStore = [];
            if ~isempty(app.RigUUID)
                try
                    rigStore = UMITRigStore.open(app.RigUUID);
                catch
                    rigStore = [];
                end
            end
            if isempty(rigStore) && ~isempty(app.RigID)
                try
                    rigStore = UMITRigStore.openByRigID(app.RigID);
                catch
                    rigStore = [];
                end
            end

            app.RigStoreHandle = rigStore;
        end

        function [ok, message] = ensureRigContext(app)
            %ENSURERIGCONTEXT Resolve the library rig this calibration belongs to.
            %
            %   Priority: the rig bound in the DataViewer folder's rig ledger;
            %   otherwise a rig passed by the caller (RigUUID); otherwise the
            %   user picks a library rig, with the Active rig pre-selected
            %   (see resolveCoregistrationRig). Never falls back to the
            %   machine's default rig and never creates one. Called at startup,
            %   and defensively again before save in case the rig became
            %   unavailable mid-session. MESSAGE explains a failure.

            message = '';
            ok = app.HasRigContext && ~isempty(app.openRigStore());
            if ok
                return
            end

            app.HasRigContext = false;
            app.RigStoreHandle = [];
            try
                target = resolveCoregistrationRig(app.DataFolder, 'RigUUID', app.RigUUID);
                rigUUID = target.rigUUID;
                if target.needsSelection
                    rigUUID = promptSelectLibraryRig(app.UIFigure, ...
                        'Title', 'Choose the rig to calibrate', ...
                        'Message', target.message);
                end
                if isempty(rigUUID)
                    message = ['No rig was selected. A camera coregistration is ' ...
                        'saved into a library rig.'];
                    return
                end
                rigStore = UMITRigStore.open(rigUUID);
                RigInfo = rigStore.getRigInfo();
                app.RigUUID = RigInfo.uuid;
                app.RigID = RigInfo.rigID;
                app.RigStoreHandle = rigStore;
                app.HasRigContext = true;
                ok = true;
            catch ME
                message = ME.message;
            end
        end


        function resourceUUID = saveTform(app, tform, tformInfo)
            %SAVETFORM Save a dual-camera coregistration transform as the rig's new
            %active managed cameraCoregistration resource.
            %
            %   resourceUUID = saveTform(app, tform, tformInfo) writes tform/tformInfo
            %   to a temporary .mat file, imports it into UMITRigStore via
            %   addCameraCoregistration, and activates it. UMITRigStore is the sole
            %   persistence layer for this -- there is no local flat-file fallback --
            %   so this errors (rather than warning and continuing) if a rig can't be
            %   resolved or the store write fails. The previously active resource, if
            %   any, is automatically demoted to 'available' (not deleted) by
            %   setActiveCameraCoregistration, and remains browsable/restorable from
            %   Coregistration History.

            errID = 'DataViewerCoreg2Cams:SaveFailed';

            [hasRig, rigMessage] = app.ensureRigContext();
            if ~hasRig
                error(errID, ...
                    'No rig is available to save this camera coregistration into: %s', ...
                    rigMessage);
            end

            rigStore = app.openRigStore();
            if isempty(rigStore)
                error(errID, 'Could not open the rig store to save this coregistration.');
            end

            tempFile = [tempname() '.mat'];
            save(tempFile, 'tform', 'tformInfo');

            try
                resourceInfo = struct( ...
                    'displayName', sprintf('Camera coregistration %s', ...
                    char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm'))), ...
                    'description', sprintf('Saved via DataViewer_Coreg2Cams from %s', ...
                    app.CoregistrationSourceDescription));

                resourceUUID = rigStore.addCameraCoregistration(tempFile, resourceInfo);
                rigStore.setActiveCameraCoregistration(resourceUUID);
            catch ME
                if isfile(tempFile)
                    delete(tempFile);
                end
                rethrow(ME)
            end

            if isfile(tempFile)
                delete(tempFile);
            end

            app.ActiveTform = tform;
            app.ActiveTformInfo = tformInfo;
        end
    end

    methods (Access = private)
        function loadCameraImages(app)
            %LOADCAMERAIMAGES Load unregistered coregistration-source camera images.
            %
            %   The coregistration source folder must contain AcqInfos.mat and the
            %   per-channel .dat files generated in the workflow-owned temporary
            %   SaveFolder. Raw-data classification is handled before this method.

            if isempty(app.CoregistrationSourceFolder) || ~isfolder(app.CoregistrationSourceFolder)
                error('DataViewerCoreg2Cams:MissingCoregistrationSource', ...
                    'Select a valid coregistration-source folder first.');
            end


            datFiles = dir(fullfile(app.CoregistrationSourceFolder, '*.dat'));
            coregFiles = {datFiles.name};
            % Get AcqInfo:
            S = load(fullfile(app.CoregistrationSourceFolder,'AcqInfos.mat'));
            AcqInfoStream = S.AcqInfoStream;
            illumFields = fieldnames(AcqInfoStream);
            illumFields = illumFields(startsWith(illumFields, 'Illumination'));

            if isempty(illumFields)
                error('DataViewerCoreg2Cams:NoIlluminations', ...
                    'No Illumination fields were found in AcqInfoStream.');
            end

            cam1List = {};
            cam2List = {};

            for iField = 1:numel(illumFields)
                thisInfo = AcqInfoStream.(illumFields{iField});

                if ~isfield(thisInfo, 'CamIdx') || ~isfield(thisInfo, 'Color')
                    continue
                end

                idx = thisInfo.CamIdx;
                chan = lower(char(string(thisInfo.Color)));
                % Manage Fluo Channel Name
                if contains(chan, 'fluo')
                    tok= regexp(chan, '(\d+)\s*nm\b*', 'tokens');
                    if ~isempty(tok)
                        wavTag = tok{:}{:};
                        chan = ['fluo_' wavTag];
                    else
                        chan = 'fluo';
                    end
                end
                % Manage Yellow Channel name
                if contains(chan, 'amber')
                    chan = 'yellow';
                end

                datName = [chan '.dat'];
                if idx == 1
                    cam1List{end+1} = datName; %#ok<AGROW>
                elseif idx == 2
                    cam2List{end+1} = datName; %#ok<AGROW>
                end
            end

            if isempty(cam1List) || isempty(cam2List)
                error('DataViewerCoreg2Cams:MissingCameraChannels', ...
                    'Could not identify at least one .dat channel for each camera.');
            end
            assert(all(ismember([cam1List, cam2List],coregFiles)), ...
                'There is a mismatch between the existing .dat files and the channel names extracted from AcqInfos.mat file.');

            app.CurrentFixedImage = app.buildCompositeCameraImage(cam1List);
            app.CurrentMovingImage = app.buildCompositeCameraImage(cam2List);

            if app.FlipHorizontallyCheckBox.Value
                app.CurrentMovingImage = fliplr(app.CurrentMovingImage);
            end

            app.CurrentFixedImage = app.normalizeImage(app.CurrentFixedImage);
            app.CurrentMovingImage = app.normalizeImage(app.CurrentMovingImage);

            app.EnhancedFixedImage = adapthisteq(app.CurrentFixedImage);
            app.EnhancedMovingImage = adapthisteq(app.CurrentMovingImage);

            app.HasCoregistrationSource = true;
        end

        function state = getCalibrationWorkflowState(app)
            %GETCALIBRATIONWORKFLOWSTATE Return transient calibration state.

            state = struct( ...
                'IsInProgress', false, ...
                'IsRecalibration', false, ...
                'PreviousActiveUUID', '', ...
                'TemporarySaveFolder', '', ...
                'RawFolder', '');

            userData = app.UIFigure.UserData;
            if isstruct(userData) && isfield(userData, 'CalibrationWorkflow')
                savedState = userData.CalibrationWorkflow;
                names = fieldnames(state);
                for iName = 1:numel(names)
                    if isfield(savedState, names{iName})
                        state.(names{iName}) = savedState.(names{iName});
                    end
                end
            end
        end

        function setCalibrationWorkflowState(app, state)
            %SETCALIBRATIONWORKFLOWSTATE Save transient calibration state.

            userData = app.UIFigure.UserData;
            if ~isstruct(userData)
                userData = struct();
            end
            userData.CalibrationWorkflow = state;
            app.UIFigure.UserData = userData;
        end

        function clearCalibrationWorkflowState(app)
            %CLEARCALIBRATIONWORKFLOWSTATE Remove transient calibration state.

            userData = app.UIFigure.UserData;
            if isstruct(userData) && isfield(userData, 'CalibrationWorkflow')
                userData = rmfield(userData, 'CalibrationWorkflow');
                app.UIFigure.UserData = userData;
            end
        end

        function deactivatePreviousCoregistration(app, temporarySaveFolder)
            %DEACTIVATEPREVIOUSCOREGISTRATION Clear the active transform before
            %classification so the temporary dataset remains unregistered.

            state = app.getCalibrationWorkflowState();
            state.TemporarySaveFolder = char(string(temporarySaveFolder));
            app.setCalibrationWorkflowState(state);

            if state.IsRecalibration
                rigStore = app.openRigStore();
                if isempty(rigStore)
                    error('DataViewerCoreg2Cams:NoRig', ...
                        'The rig store became unavailable during recalibration.');
                end
                rigStore.clearActiveCameraCoregistration();
            end
        end

        function completed = prepareCalibrationSource(app, isRecalibration)
            %PREPARECALIBRATIONSOURCE Select and classify one raw acquisition.

            completed = false;
            startFolder = app.DataFolder;
            if isempty(startFolder) || ~isfolder(startFolder)
                startFolder = pwd;
            end

            rawFolder = uigetdir(startFolder, ...
                'Select raw dual-camera calibration acquisition');
            if isequal(rawFolder, 0)
                return
            end

            state = app.getCalibrationWorkflowState();
            state.IsInProgress = true;
            state.IsRecalibration = logical(isRecalibration);
            state.RawFolder = rawFolder;

            if isRecalibration
                rigStore = app.openRigStore();
                activeResource = rigStore.getActiveCameraCoregistration();
                if isempty(activeResource)
                    error('DataViewerCoreg2Cams:MissingActiveCoregistration', ...
                        'No active camera coregistration is available to recalibrate.');
                end
                state.PreviousActiveUUID = activeResource.uuid;
            end
            app.setCalibrationWorkflowState(state);

            temporarySaveFolder = prepareDualCameraCalibrationData(rawFolder, ...
                'BeforeClassificationFcn', ...
                @(folder) app.deactivatePreviousCoregistration(folder));

            state = app.getCalibrationWorkflowState();
            state.TemporarySaveFolder = char(temporarySaveFolder);
            app.setCalibrationWorkflowState(state);

            app.stopAlternateViewTimer();
            app.CoregistrationSourceFolder = char(temporarySaveFolder);
            app.CoregistrationSourceDescription = rawFolder;
            app.CurrentFixedImage = [];
            app.CurrentMovingImage = [];
            app.CurrentRegisteredMovingImage = [];
            app.EnhancedFixedImage = [];
            app.EnhancedMovingImage = [];
            app.HasCoregistrationSource = false;

            app.loadCameraImages();
            completed = true;
        end

        function finishCalibrationWorkflow(app, succeeded)
            %FINISHCALIBRATIONWORKFLOW Clean temporary data and restore on failure.

            state = app.getCalibrationWorkflowState();
            if ~succeeded && state.IsRecalibration && ...
                    ~isempty(state.PreviousActiveUUID)
                try
                    rigStore = app.openRigStore();
                    previous = rigStore.getResource(state.PreviousActiveUUID);
                    if strcmp(previous.status, 'archived')
                        rigStore.restoreResource(previous.uuid);
                    end
                    rigStore.resolveResourcePath(previous.uuid);
                    rigStore.setActiveCameraCoregistration(previous.uuid);
                catch ME
                    warning('DataViewerCoreg2Cams:RestorePreviousFailed', ...
                        'Could not restore the previous active coregistration: %s', ...
                        ME.message);
                end
            end

            if ~isempty(state.TemporarySaveFolder) && ...
                    isfolder(state.TemporarySaveFolder)
                rmdir(state.TemporarySaveFolder, 's');
            end
            app.clearCalibrationWorkflowState();
        end

        function abortCalibrationWorkflow(app)
            %ABORTCALIBRATIONWORKFLOW Restore state after cancel or failure.

            app.finishCalibrationWorkflow(false);
            app.HasCoregistrationSource = false;
            app.CoregistrationSourceFolder = '';
            app.CoregistrationSourceDescription = '';

            try
                app.loadActiveCoregistration();
            catch
                app.ActiveTform = [];
                app.ActiveTformInfo = struct();
            end
        end

        function configureInitialState(app)
            %CONFIGUREINITIALSTATE Initialize UI state before file/source loading.

            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowStyle = 'modal';

            app.CoregistrationFileInfoTextArea.Editable = 'off';
            app.CoregistrationFileInfoTextArea.Value = {'No coregistration information loaded.'};

            app.StepsizeEditField.Limits = [eps Inf];
            app.StepsizeEditField.Value = 1;
            app.updateStepSizeLabel();

            app.ApplyStepSpinner.Limits = [-1 1];
            app.ApplyStepSpinner.Step = 1;
            app.ApplyStepSpinner.Value = 0;

            app.RunAutomaticcoregistrationButton.Enable = 'off';
            app.SaveCoregistrationButton.Enable = 'off';
            app.ManageCoregistrationFilesButton.Enable = 'off';
            app.LoadCoregistrationDataButton.Text = 'Start Initial Calibration...';
            app.setManualControlsEnabled(false);

            cla(app.UIAxes);
            title(app.UIAxes, 'Load coregistration data to start');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            axis(app.UIAxes, 'image');
            colormap(app.UIAxes, gray);

            app.setLocalStatus('Ready. Load coregistration data to preview or create a coregistration.');
        end

        function severity = setLocalStatus(app, msg, varargin)
            %SETLOCALSTATUS Update status label text.
            %
            %   severity = setLocalStatus(app, msg)
            %   severity = setLocalStatus(app, msg, 'Severity', severity)
            %
            %   Routes Dual Camera Coregistration footer messages through the shared
            %   GUI status utility. This wrapper keeps callbacks independent from the
            %   concrete status-label name and from light/dark color rules.

            if isempty(app.StatusLabel) || ~isvalid(app.StatusLabel)
                severity = '';
                return
            end

            background = app.getStatusBackgroundPolarity();

            severity = setStatusMessage(app.StatusLabel, msg, ...
                'Background', background, ...
                varargin{:});

        end

        function background = getStatusBackgroundPolarity(app)
            %GETSTATUSBACKGROUNDPOLARITY Return UI background polarity for status colors.
            %
            %   The Dual Camera Coregistration utility currently uses the default
            %   light App Designer appearance. This helper keeps the status-message
            %   API ready for a future manual light/dark mode implementation. When a
            %   dedicated theme property is added to the app, update this method only.

            background = 'light';

            if isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end

            try
                if isprop(app.UIFigure, 'Color') && ~isempty(app.UIFigure.Color)
                    figColor = double(app.UIFigure.Color(:).');

                    if numel(figColor) >= 3 && all(isfinite(figColor(1:3)))
                        luminanceValue = 0.2126 * figColor(1) + ...
                            0.7152 * figColor(2) + ...
                            0.0722 * figColor(3);

                        if luminanceValue < 0.5
                            background = 'dark';
                        end
                    end
                end
            catch
                background = 'light';
            end

        end

        function setManualControlsEnabled(app, tf)
            %SETMANUALCONTROLSENABLED Enable or disable manual fine-tuning controls.

            state = matlab.lang.OnOffSwitchState(tf);
            app.TransformationDropDown.Enable = state;
            app.StepsizeEditField.Enable = state;
            app.ApplyStepSpinner.Enable = state;
            app.ResetButton.Enable = state;
        end

        function refreshButtonStates(app)
            %REFRESHBUTTONSTATES Enable controls from current camera coregistration state.

            hasSourceImages = app.HasCoregistrationSource && ...
                ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentMovingImage);
            hasWorkingSource = hasSourceImages && isfolder(app.CoregistrationSourceFolder);
            hasCandidate = ~isempty(app.CurrentTform);
            hasHistory = app.hasCoregistrationHistory();
            hasActive = app.hasActiveCoregistration();
            workflowState = app.getCalibrationWorkflowState();

            if hasActive
                app.LoadCoregistrationDataButton.Text = 'Recalibrate...';
            else
                app.LoadCoregistrationDataButton.Text = 'Start Initial Calibration...';
            end
            app.LoadCoregistrationDataButton.Enable = ...
                matlab.lang.OnOffSwitchState(~workflowState.IsInProgress);
            app.RunAutomaticcoregistrationButton.Enable = matlab.lang.OnOffSwitchState(hasWorkingSource);
            app.ManageCoregistrationFilesButton.Enable = ...
                matlab.lang.OnOffSwitchState(hasHistory && ~workflowState.IsInProgress);
            app.SaveCoregistrationButton.Enable = matlab.lang.OnOffSwitchState(~isempty(app.CurrentTform) && app.HasUnsavedCoregistration);
            app.setManualControlsEnabled(hasSourceImages && hasCandidate);
        end

        function refreshCoregistrationFileInfo(app)
            %REFRESHCOREGISTRATIONFILEINFO Update coregistration/source status text area.

            lines = strings(0, 1);

            if app.hasActiveCoregistration()
                rigStore = app.openRigStore();
                activeResource = rigStore.getActiveCameraCoregistration();
                lines(end+1) = "Active coregistration: " + string(activeResource.displayName);
                lines(end+1) = "Created: " + string(activeResource.createdOn);
            else
                lines(end+1) = "Active coregistration: not found";
            end

            lines(end+1) = "";

            if app.HasCoregistrationSource
                lines(end+1) = "Coregistration source: loaded";
                lines(end+1) = "Raw source folder: " + string(app.CoregistrationSourceDescription);
            else
                lines(end+1) = "Coregistration source: not loaded";
            end

            lines(end+1) = "";
            if isempty(app.CandidateSource)
                lines(end+1) = "Preview candidate: none";
            else
                lines(end+1) = "Preview candidate: " + string(app.CandidateSource);
            end

            hasPreviewImages = ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentMovingImage);
            lines(end+1) = "Preview available: " + string(hasPreviewImages);

            if app.HasUnsavedCoregistration
                lines(end+1) = "Unsaved candidate: yes";
            else
                lines(end+1) = "Unsaved candidate: no";
            end

            if ~isempty(app.CurrentTform)
                lines(end+1) = sprintf('Manual adjustment: dx=%.3g px, dy=%.3g px, rot=%.3g deg, scale=%.6g', ...
                    app.ManualDx, app.ManualDy, app.ManualRotationDeg, app.ManualScale);
            end

            app.CoregistrationFileInfoTextArea.Value = cellstr(lines);
        end

        function refreshAllState(app)
            %REFRESHALLSTATE Refresh all status, buttons, and preview.


            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
        end

        function tf = hasCoregistrationHistory(app)
            %HASCOREGISTRATIONHISTORY Return true when the rig has any saved
            %cameraCoregistration resources to manage.

            rigStore = app.openRigStore();
            tf = ~isempty(rigStore) && ...
                ~isempty(rigStore.listResources('Type', 'cameraCoregistration'));
        end

        function openCoregistrationFileManager(app)
            %OPENCOREGISTRATIONFILEMANAGER Manage this rig's calibration files.

            rigStore = app.openRigStore();
            if isempty(rigStore)
                uialert(app.UIFigure, ...
                    'No rig is available to manage coregistration files.', ...
                    'No rig available', 'Icon', 'warning');
                return
            end

            resources = rigStore.listResources('Type', 'cameraCoregistration');
            if isempty(resources)
                uialert(app.UIFigure, ...
                    'This rig has no saved camera coregistration files yet.', ...
                    'No coregistration files', 'Icon', 'info');
                return
            end

            managerFigure = uifigure( ...
                'Name', 'Manage Coregistration Files', ...
                'Position', [100 100 720 440], ...
                'Resize', 'off', ...
                'WindowStyle', 'modal');
            managerFigure.CloseRequestFcn = @(~, ~) closeManager();

            managerGrid = uigridlayout(managerFigure, [3 1]);
            managerGrid.RowHeight = {'1x', 120, 42};
            managerGrid.Padding = [10 10 10 10];
            managerGrid.RowSpacing = 8;

            resourceList = uilistbox(managerGrid, ...
                'Multiselect', 'off', ...
                'ValueChangedFcn', @(~, ~) updateSelection());
            resourceList.Layout.Row = 1;

            detailsArea = uitextarea(managerGrid, ...
                'Editable', 'off', ...
                'BackgroundColor', [0.96 0.96 0.96]);
            detailsArea.Layout.Row = 2;

            actionGrid = uigridlayout(managerGrid, [1 6]);
            actionGrid.Layout.Row = 3;
            actionGrid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x', 80};
            actionGrid.Padding = [0 0 0 0];

            reviewButton = uibutton(actionGrid, 'push', ...
                'Text', 'Review', ...
                'ButtonPushedFcn', @(~, ~) reviewSelected());
            activateButton = uibutton(actionGrid, 'push', ...
                'Text', 'Make Active', ...
                'ButtonPushedFcn', @(~, ~) activateSelected());
            archiveButton = uibutton(actionGrid, 'push', ...
                'Text', 'Archive', ...
                'ButtonPushedFcn', @(~, ~) archiveSelected());
            restoreButton = uibutton(actionGrid, 'push', ...
                'Text', 'Restore', ...
                'ButtonPushedFcn', @(~, ~) restoreSelected());
            purgeButton = uibutton(actionGrid, 'push', ...
                'Text', 'Purge', ...
                'ButtonPushedFcn', @(~, ~) purgeSelected());
            uibutton(actionGrid, 'push', ...
                'Text', 'Close', ...
                'ButtonPushedFcn', @(~, ~) closeManager());

            activateButton.Tooltip = ...
                'Make an available calibration the active calibration.';
            archiveButton.Tooltip = ...
                'Archive an available calibration. Activate another file first to archive the active one.';
            restoreButton.Tooltip = ...
                'Restore an archived calibration to available status.';
            purgeButton.Tooltip = ...
                'Permanently delete an archived calibration.';

            refreshManager('');
            initializeUmitTheme(managerFigure);
            movegui(managerFigure, 'center');
            uiwait(managerFigure);

            if isvalid(managerFigure)
                delete(managerFigure);
            end
            app.refreshAllState();

            function refreshManager(preferredUUID)
                resources = rigStore.listResources( ...
                    'Type', 'cameraCoregistration');
                if isempty(resources)
                    resourceList.Items = {'No calibration files'};
                    resourceList.ItemsData = {''};
                    resourceList.Value = '';
                    detailsArea.Value = {'No calibration files are registered.'};
                    setActionStates([]);
                    return
                end

                [~, sortIndex] = sort([resources.createdOn], 'descend');
                resources = resources(sortIndex);
                listItems = cell(numel(resources), 1);
                resourceUUIDs = cell(numel(resources), 1);
                for iResource = 1:numel(resources)
                    listItems{iResource} = sprintf('%s  [%s]  %s', ...
                        resources(iResource).displayName, ...
                        upper(resources(iResource).status), ...
                        char(resources(iResource).createdOn));
                    resourceUUIDs{iResource} = resources(iResource).uuid;
                end

                resourceList.Items = listItems;
                resourceList.ItemsData = resourceUUIDs;
                if ~isempty(preferredUUID) && ...
                        any(strcmp(resourceUUIDs, preferredUUID))
                    resourceList.Value = preferredUUID;
                else
                    resourceList.Value = resourceUUIDs{1};
                end
                updateSelection();
            end

            function record = selectedRecord()
                record = [];
                if isempty(resources) || isempty(resourceList.Value)
                    return
                end
                index = find(strcmp({resources.uuid}, ...
                    char(string(resourceList.Value))), 1, 'first');
                if ~isempty(index)
                    record = resources(index);
                end
            end

            function updateSelection()
                record = selectedRecord();
                if isempty(record)
                    detailsArea.Value = {'No calibration file selected.'};
                    setActionStates([]);
                    return
                end

                description = record.description;
                if isempty(description)
                    description = '(none)';
                end
                detailsArea.Value = { ...
                    ['Name: ' record.displayName], ...
                    ['Status: ' upper(record.status)], ...
                    ['Created: ' char(record.createdOn)], ...
                    ['UUID: ' record.uuid], ...
                    ['Description: ' description]};
                setActionStates(record);
            end

            function setActionStates(record)
                hasRecord = ~isempty(record);
                reviewButton.Enable = matlab.lang.OnOffSwitchState(hasRecord);
                activateButton.Enable = matlab.lang.OnOffSwitchState( ...
                    hasRecord && strcmp(record.status, 'available'));
                archiveButton.Enable = matlab.lang.OnOffSwitchState( ...
                    hasRecord && strcmp(record.status, 'available'));
                restoreButton.Enable = matlab.lang.OnOffSwitchState( ...
                    hasRecord && strcmp(record.status, 'archived'));
                purgeButton.Enable = matlab.lang.OnOffSwitchState( ...
                    hasRecord && strcmp(record.status, 'archived'));
            end

            function reviewSelected()
                record = selectedRecord();
                if isempty(record)
                    return
                end

                try
                    resourcePath = rigStore.resolveResourcePath(record.uuid);
                    loaded = load(resourcePath, 'tform', 'tformInfo');
                    if ~isfield(loaded, 'tform')
                        error('DataViewerCoreg2Cams:InvalidTformFile', ...
                            'The selected file does not contain variable "tform".');
                    end
                    if isfield(loaded, 'tformInfo')
                        tformInfo = loaded.tformInfo;
                    else
                        tformInfo = struct();
                    end

                    tformInfo.CandidateSource = 'Coregistration file manager';
                    tformInfo.SourceResourceUUID = record.uuid;
                    tformInfo.LoadedFromHistoryOn = datetime('now');
                    hasPreview = app.restorePreviewImagesFromTformInfo(tformInfo);
                    app.setCandidateTransform(loaded.tform, tformInfo, ...
                        ['History: ' record.displayName], true, true);
                    if hasPreview
                        app.setLocalStatus(['Calibration loaded for review. ' ...
                            'Review or fine tune it, then Save Coregistration to create a new active resource.']);
                    else
                        app.setLocalStatus(['Calibration loaded, but it has no stored preview images.']);
                    end
                    closeManager();
                catch ME
                    showManagerError('Review failed', ME);
                end
            end

            function activateSelected()
                record = selectedRecord();
                if isempty(record) || ~strcmp(record.status, 'available')
                    return
                end
                answer = uiconfirm(managerFigure, ...
                    sprintf('Make "%s" the active camera coregistration?', ...
                    record.displayName), ...
                    'Make calibration active?', ...
                    'Options', {'Make Active', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'question');
                if strcmp(answer, 'Cancel')
                    return
                end
                try
                    rigStore.setActiveCameraCoregistration(record.uuid);
                    app.loadActiveCoregistration();
                    app.refreshAllState();
                    app.setLocalStatus(sprintf('Made "%s" the active coregistration.', ...
                        record.displayName));
                    refreshManager(record.uuid);
                catch ME
                    showManagerError('Activation failed', ME);
                end
            end

            function archiveSelected()
                record = selectedRecord();
                if isempty(record) || ~strcmp(record.status, 'available')
                    return
                end
                answer = uiconfirm(managerFigure, ...
                    sprintf(['Archive "%s"?' newline newline ...
                    'It will remain registered and can be restored later.'], ...
                    record.displayName), ...
                    'Archive calibration?', ...
                    'Options', {'Archive', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');
                if strcmp(answer, 'Cancel')
                    return
                end
                try
                    rigStore.archiveResource(record.uuid);
                    app.refreshAllState();
                    app.setLocalStatus(sprintf('Archived "%s".', record.displayName));
                    refreshManager(record.uuid);
                catch ME
                    showManagerError('Archive failed', ME);
                end
            end

            function restoreSelected()
                record = selectedRecord();
                if isempty(record) || ~strcmp(record.status, 'archived')
                    return
                end
                answer = uiconfirm(managerFigure, ...
                    sprintf(['Restore "%s" to available status?' newline newline ...
                    'It will not become active automatically.'], record.displayName), ...
                    'Restore calibration?', ...
                    'Options', {'Restore', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'question');
                if strcmp(answer, 'Cancel')
                    return
                end
                try
                    rigStore.restoreResource(record.uuid);
                    app.refreshAllState();
                    app.setLocalStatus(sprintf('Restored "%s" as available.', ...
                        record.displayName));
                    refreshManager(record.uuid);
                catch ME
                    showManagerError('Restore failed', ME);
                end
            end

            function purgeSelected()
                record = selectedRecord();
                if isempty(record) || ~strcmp(record.status, 'archived')
                    return
                end
                answer = uiconfirm(managerFigure, ...
                    sprintf(['Permanently delete "%s"?' newline newline ...
                    'This removes the managed file and cannot be undone.'], ...
                    record.displayName), ...
                    'Permanently purge calibration?', ...
                    'Options', {'Purge Permanently', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');
                if strcmp(answer, 'Cancel')
                    return
                end
                try
                    purgedName = record.displayName;
                    rigStore.purgeResource(record.uuid);
                    app.refreshAllState();
                    app.setLocalStatus(sprintf('Purged "%s" permanently.', purgedName));
                    refreshManager('');
                catch ME
                    showManagerError('Purge failed', ME);
                end
            end

            function showManagerError(titleText, exception)
                app.setLocalStatus(sprintf('%s: %s', titleText, exception.message));
                uialert(managerFigure, ...
                    getReport(exception, 'basic', 'hyperlinks', 'off'), ...
                    titleText, 'Icon', 'error');
            end

            function closeManager()
                if isvalid(managerFigure)
                    uiresume(managerFigure);
                    delete(managerFigure);
                end
            end
        end

        function tf = restorePreviewImagesFromTformInfo(app, tformInfo)
            %RESTOREPREVIEWIMAGESFROMTFORMINFO Populate preview images from a loaded
            %tformInfo's stored OriginalImages, so an existing coregistration can be
            %reviewed/manually edited without reloading raw coregistration data.
            %
            %   Returns true when tformInfo.OriginalImages was present and used.
            %   OriginalImages is set by genTform2Cams (the fixed/moving pair that
            %   actually produced the stored tform) and is reused verbatim here -- it
            %   is NOT re-flipped against FlipHorizontallyCheckBox, since doing so
            %   could double-flip relative to how the tform was originally computed.

            tf = false;

            if ~isstruct(tformInfo) || ~isfield(tformInfo, 'OriginalImages')
                return
            end

            images = tformInfo.OriginalImages;
            if ~iscell(images) || numel(images) < 2 || isempty(images{1}) || isempty(images{2})
                return
            end

            app.CurrentFixedImage = images{1};
            app.CurrentMovingImage = images{2};
            app.EnhancedFixedImage = adapthisteq(app.CurrentFixedImage);
            app.EnhancedMovingImage = adapthisteq(app.CurrentMovingImage);
            app.HasCoregistrationSource = true;

            tf = true;
        end

        function loadActiveCoregistration(app)
            %LOADACTIVECOREGISTRATION Load the rig's active camera coregistration, if
            %any, into active/candidate state -- including its stored preview images,
            %when available, so it can be reviewed/edited without a fresh data load.

            app.ActiveTform = [];
            app.ActiveTformInfo = struct();

            rigStore = app.openRigStore();
            if isempty(rigStore)
                return
            end

            resource = rigStore.getActiveCameraCoregistration();
            if isempty(resource)
                return
            end

            activePath = rigStore.resolveResourcePath(resource.uuid);
            S = load(activePath, 'tform', 'tformInfo');
            if ~isfield(S, 'tform')
                error('DataViewerCoreg2Cams:InvalidTformFile', ...
                    'The active coregistration file does not contain variable "tform".');
            end

            app.ActiveTform = S.tform;
            if isfield(S, 'tformInfo')
                app.ActiveTformInfo = S.tformInfo;
            else
                app.ActiveTformInfo = struct();
            end

            app.restorePreviewImagesFromTformInfo(app.ActiveTformInfo);

            app.setCandidateTransform(app.ActiveTform, app.ActiveTformInfo, ...
                'Active coregistration', false, true);
        end

        function setCandidateTransform(app, tform, tformInfo, sourceLabel, isUnsaved, resetManual)
            %SETCANDIDATETRANSFORM Set current preview transform and metadata.

            if nargin < 6
                resetManual = true;
            end

            app.BaseTform = tform;
            app.CurrentTform = tform;
            app.CurrentTformInfo = tformInfo;
            app.CandidateSource = char(string(sourceLabel));
            app.HasUnsavedCoregistration = logical(isUnsaved);

            if resetManual
                app.ManualDx = 0;
                app.ManualDy = 0;
                app.ManualRotationDeg = 0;
                app.ManualScale = 1;
            end

            app.updateRegisteredMovingImage();
            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
        end

        function updateRegisteredMovingImage(app)
            %UPDATEREGISTEREDMOVINGIMAGE Apply current transform to moving image.

            app.CurrentRegisteredMovingImage = [];

            if isempty(app.CurrentTform) || isempty(app.CurrentMovingImage) || isempty(app.CurrentFixedImage)
                return
            end

            outRef = imref2d(size(app.CurrentFixedImage));
            app.CurrentRegisteredMovingImage = imwarp(app.CurrentMovingImage, app.CurrentTform, ...
                'OutputView', outRef, 'FillValues', 0);
            app.CurrentRegisteredMovingImage = app.normalizeImage(app.CurrentRegisteredMovingImage);
        end

        function img = getDisplayFixedImage(app)
            %GETDISPLAYFIXEDIMAGE Return fixed image with selected contrast mode.

            if app.UseenhancedcontrastCheckBox.Value && ~isempty(app.EnhancedFixedImage)
                img = app.EnhancedFixedImage;
            else
                img = app.CurrentFixedImage;
            end
        end

        function img = getDisplayMovingImage(app)
            %GETDISPLAYMOVINGIMAGE Return registered/unregistered moving image for display.

            if ~isempty(app.CurrentRegisteredMovingImage)
                img = app.CurrentRegisteredMovingImage;
            else
                img = app.CurrentMovingImage;
            end

            if app.UseenhancedcontrastCheckBox.Value
                img = adapthisteq(app.normalizeImage(img));
            else
                img = app.normalizeImage(img);
            end
        end

        function refreshPreview(app)
            %REFRESHPREVIEW Draw the current preview mode.

            app.stopAlternateViewTimer();
            cla(app.UIAxes);

            if ~app.HasCoregistrationSource || isempty(app.CurrentFixedImage) || isempty(app.CurrentMovingImage)
                title(app.UIAxes, 'Load coregistration data to preview coregistration');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                axis(app.UIAxes, 'image');
                return
            end

            fixedImg = app.getDisplayFixedImage();
            movingImg = app.getDisplayMovingImage();

            if startsWith(app.ViewmodeButtonGroup.SelectedObject.Text, 'False', 'IgnoreCase', true)
                imshowpair(fixedImg, movingImg, 'falsecolor', 'Scaling', 'joint', 'Parent', app.UIAxes);
                title(app.UIAxes, app.getPreviewTitle('False-color overlay'), 'Interpreter', 'none');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                axis(app.UIAxes, 'image');
            else
                app.startAlternateView(fixedImg, movingImg);
            end
        end

        function titleText = getPreviewTitle(app, modeText)
            %GETPREVIEWTITLE Compose preview title.

            if isempty(app.CandidateSource)
                candidateText = 'unregistered source';
            else
                candidateText = app.CandidateSource;
            end

            titleText = sprintf('%s | %s', modeText, candidateText);
        end

        function startAlternateView(app, fixedImg, movingImg)
            %STARTALTERNATEVIEW Start 1 Hz flicker comparison on one axes.

            app.stopAlternateViewTimer();
            app.AlternateViewIndex = 1;

            app.AlternateViewImageHandle = imagesc(app.UIAxes, fixedImg, [0 1]);
            colormap(app.UIAxes, gray);
            axis(app.UIAxes, 'image');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            title(app.UIAxes, app.getPreviewTitle('Alternate view: Camera 1'), 'Interpreter', 'none');

            app.AlternateViewTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'BusyMode', 'drop', ...
                'TimerFcn', @(~, ~) app.onAlternateViewTimerTick(fixedImg, movingImg));

            start(app.AlternateViewTimer);
        end

        function onAlternateViewTimerTick(app, fixedImg, movingImg)
            %ONALTERNATEVIEWTIMERTICK Alternate fixed and moving images in UIAxes.

            if isempty(app) || ~isvalid(app) || isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end

            if isempty(app.AlternateViewImageHandle) || ~isvalid(app.AlternateViewImageHandle)
                return
            end

            if app.AlternateViewIndex == 1
                app.AlternateViewImageHandle.CData = movingImg;
                app.AlternateViewIndex = 2;
                title(app.UIAxes, app.getPreviewTitle('Alternate view: Camera 2'), 'Interpreter', 'none');
            else
                app.AlternateViewImageHandle.CData = fixedImg;
                app.AlternateViewIndex = 1;
                title(app.UIAxes, app.getPreviewTitle('Alternate view: Camera 1'), 'Interpreter', 'none');
            end

            drawnow limitrate
        end

        function stopAlternateViewTimer(app)
            %STOPALTERNATEVIEWTIMER Stop and delete alternate-view timer.

            if ~isempty(app.AlternateViewTimer)
                try
                    if isvalid(app.AlternateViewTimer)
                        stop(app.AlternateViewTimer);
                        delete(app.AlternateViewTimer);
                    end
                catch
                end
            end

            app.AlternateViewTimer = timer.empty;
            app.AlternateViewImageHandle = [];
        end

        function updateStepSizeLabel(app)
            %UPDATESTEPSIZELABEL Update step-size label unit from selected transform.

            switch app.TransformationDropDown.Value
                case {'Horizontal (transl.)', 'Vertical (transl.)'}
                    app.StepsizeEditFieldLabel.Text = 'Step size (px)';
                    if app.StepsizeEditField.Value <= 0
                        app.StepsizeEditField.Value = 1;
                    end
                case 'Rotation'
                    app.StepsizeEditFieldLabel.Text = 'Step size (deg)';
                    if app.StepsizeEditField.Value <= 0
                        app.StepsizeEditField.Value = 0.1;
                    end
                case 'Scaling'
                    app.StepsizeEditFieldLabel.Text = 'Step size (%)';
                    if app.StepsizeEditField.Value <= 0
                        app.StepsizeEditField.Value = 1;
                    end
            end
        end

        function applyManualStep(app, signedStep)
            %APPLYMANUALSTEP Apply one manual nudge to the candidate transform.

            if isempty(app.BaseTform) || isempty(app.CurrentTform)
                return
            end

            stepSize = double(app.StepsizeEditField.Value);
            if ~isfinite(stepSize) || stepSize <= 0
                error('DataViewerCoreg2Cams:InvalidStepSize', ...
                    'Step size must be a finite positive value.');
            end

            delta = signedStep * stepSize;

            switch app.TransformationDropDown.Value
                case 'Horizontal (transl.)'
                    app.ManualDx = app.ManualDx + delta;
                case 'Vertical (transl.)'
                    app.ManualDy = app.ManualDy + delta;
                case 'Rotation'
                    app.ManualRotationDeg = app.ManualRotationDeg + delta;
                case 'Scaling'
                    app.ManualScale = app.ManualScale * (1 + delta / 100);
                    app.ManualScale = max(app.ManualScale, eps);
            end

            app.recomposeCurrentTransform();
            app.HasUnsavedCoregistration = true;
            app.CandidateSource = app.appendManualCandidateLabel(app.CandidateSource);

            app.updateRegisteredMovingImage();
            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
            app.setLocalStatus('Manual adjustment applied. Save Coregistration to make it active.');
        end

        function label = appendManualCandidateLabel(app, labelIn) %#ok<INUSL>
            %APPENDMANUALCANDIDATELABEL Mark source label as manually adjusted.

            label = char(string(labelIn));
            if isempty(label)
                label = 'Manual adjustment';
            elseif ~contains(label, '+ manual adjustment')
                label = [label '+ manual adjustment'];
            end
        end

        function recomposeCurrentTransform(app)
            %RECOMPOSECURRENTTRANSFORM Compose base transform plus manual adjustment.

            if isempty(app.BaseTform)
                return
            end

            fixedSize = size(app.CurrentFixedImage);
            cx = (fixedSize(2) + 1) / 2;
            cy = (fixedSize(1) + 1) / 2;

            TtoOrigin = [1 0 0; 0 1 0; -cx -cy 1];
            TfromOrigin = [1 0 0; 0 1 0; cx cy 1];

            theta = deg2rad(app.ManualRotationDeg);
            R = [cos(theta) sin(theta) 0; -sin(theta) cos(theta) 0; 0 0 1];
            S = [app.ManualScale 0 0; 0 app.ManualScale 0; 0 0 1];
            Tr = [1 0 0; 0 1 0; app.ManualDx app.ManualDy 1];

            manualT = TtoOrigin * S * R * TfromOrigin * Tr;
            app.CurrentTform = affine2d(app.BaseTform.T * manualT);

            app.CurrentTformInfo = app.updateTformInfoForManualAdjustment(app.CurrentTformInfo);
        end

        function info = updateTformInfoForManualAdjustment(app, info)
            %UPDATETFORMINFOFORMANUALADJUSTMENT Add manual-adjustment metadata.

            if isempty(info) || ~isstruct(info)
                info = struct();
            end

            info.ManualAdjustment = struct( ...
                'dx_px', app.ManualDx, ...
                'dy_px', app.ManualDy, ...
                'rotation_deg', app.ManualRotationDeg, ...
                'scale', app.ManualScale, ...
                'updatedOn', datetime('now'));

            info.CandidateSource = app.CandidateSource;
        end

        function img = buildCompositeCameraImage(app, datFileList)
            %BUILDCOMPOSITECAMERAIMAGE Create one normalized mean image from channels.

            if isempty(datFileList)
                error('DataViewerCoreg2Cams:EmptyDatList', ...
                    'No .dat files were provided for camera image construction.');
            end

            firstPath = fullfile(app.CoregistrationSourceFolder, datFileList{1});
            if ~isfile(firstPath)
                error('DataViewerCoreg2Cams:MissingDatFile', ...
                    'Missing required channel file: %s', firstPath);
            end

            datMap = mapDat(firstPath);
            img = zeros([numel(datFileList), size(datMap.Data.data, 1), size(datMap.Data.data, 2)], 'single');
            clear datMap

            for iFile = 1:numel(datFileList)
                datPath = fullfile(app.CoregistrationSourceFolder, datFileList{iFile});
                if ~isfile(datPath)
                    error('DataViewerCoreg2Cams:MissingDatFile', ...
                        'Missing required channel file: %s', datPath);
                end

                datMap = mapDat(datPath);
                nFrames = size(datMap.Data.data, 3);

                if nFrames > 100
                    frameIdx = round(linspace(1, nFrames, 100));
                    dat = mean(datMap.Data.data(:, :, frameIdx), 3);
                else
                    dat = mean(datMap.Data.data, 3);
                end

                if mean(dat(:), 'omitnan') > 1e3
                    dat = dat ./ mean(dat(:), 'omitnan');
                else
                    dat = zeros(size(dat), 'single');
                end

                img(iFile, :, :) = single(dat);
                clear datMap
            end

            img = squeeze(sum(img, 1));
        end

        function img = normalizeImage(app, img) %#ok<INUSL>
            %NORMALIZEIMAGE Normalize image to [0 1] safely.

            img = single(img);
            imgMin = min(img(:), [], 'omitnan');
            imgMax = max(img(:), [], 'omitnan');

            if ~isfinite(imgMin) || ~isfinite(imgMax) || imgMax <= imgMin
                img = zeros(size(img), 'single');
            else
                img = (img - imgMin) ./ (imgMax - imgMin);
            end
        end

        function ensureCurrentTformInfoHasRegisteredImages(app)
            %ENSURECURRENTTFORMINFOHASREGISTEREDIMAGES Store preview images in tformInfo.

            if isempty(app.CurrentTformInfo) || ~isstruct(app.CurrentTformInfo)
                app.CurrentTformInfo = struct();
            end

            if ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentRegisteredMovingImage)
                app.CurrentTformInfo.RegisteredImages = { ...
                    app.CurrentFixedImage, ...
                    app.CurrentRegisteredMovingImage};
            end

            if ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentMovingImage)
                % Keep the true unregistered pair explicit (not just carried over by
                % accident from genTform2Cams's output) so a future review/edit
                % session -- from any load path -- can always restore a preview
                % without needing a fresh data import.
                app.CurrentTformInfo.OriginalImages = { ...
                    app.CurrentFixedImage, ...
                    app.CurrentMovingImage};
            end

            app.CurrentTformInfo.SourceDataFolder = app.CoregistrationSourceFolder;
            app.CurrentTformInfo.CandidateSource = app.CandidateSource;
            app.CurrentTformInfo.SavedOn = datetime('now');
        end

        function tf = hasCandidateTransform(app)
            %HASCANDIDATETRANSFORM Return true when a candidate transform exists.

            tf = ~isempty(app.CurrentTform);
        end

        function tf = hasActiveCoregistration(app)
            %HASACTIVECOREGISTRATION Return true when the rig has an active camera
            %coregistration resource (file existence is already checked inside
            %UMITRigStore's iGetActiveResource).

            rigStore = app.openRigStore();
            tf = ~isempty(rigStore) && ~isempty(rigStore.getActiveCameraCoregistration());
        end

        function updateCameraCoregistrationDataParams(app, tform, resourceUUID, ...
                sourceFilePath, sourceTimestamp)
            %UPDATECAMERACOREGISTRATIONDATAPARAMS Record the applied camera coregistration in
            %the coregistration-source folder's DataParams.mat.
            %
            %   Written unconditionally after a successful camera coregistration save
            %   to UMITRigStore. This local DataParams.mat record is a convenience
            %   mirror, not the authoritative store -- a failure here must never undo
            %   or block the rig-store save that already happened.


            try
                folder = app.CoregistrationSourceFolder;

                if isempty(folder) || ~isfolder(folder)
                    return
                end

                if ~isfile(fullfile(folder, 'DataParams.mat'))
                    createDataParams(folder);
                end

                DataParams = loadDataParams(folder);
                cc = DataParams.cameraCoregistration;

                cc.isCoregistered = true;
                cc.isReviewed = true;
                cc.tform = tform;
                cc.resourceUUID = char(string(resourceUUID));
                cc.rigID = app.RigID;
                cc.transformType = 'similarity';
                cc.method = 'DataViewer_Coreg2Cams';
                cc.sourceFile = char(string(sourceFilePath));
                cc.sourceFileTimestamp = char(string(sourceTimestamp));
                cc.createdOn = char(datetime('now'));
                cc.source = folder;
                cc.appliedOn = char(datetime('now'));
                cc.appliedBy = 'DataViewer_Coreg2Cams';
                cc.confirmationMode = 'user-confirmed-save-coregistration';
                cc.notes = 'Active dual-camera coregistration saved via DataViewer_Coreg2Cams.';

                T = app.getTformMatrix(tform);
                A = T(1:2, 1:2);
                cc.qcMetrics.translationXY_px = [T(3,1), T(3,2)];
                cc.qcMetrics.rotationDeg = atan2d(A(1,2), A(1,1));
                cc.qcMetrics.scaleXY = [hypot(A(1,1), A(1,2)), hypot(A(2,1), A(2,2))];
                cc.qcMetrics.determinant = det(A);

                DataParams.cameraCoregistration = cc;
                DataParams.lastModified = datetime('now');

                validateDataParams(DataParams);
                save(fullfile(folder, 'DataParams.mat'), 'DataParams', '-mat');

            catch ME
                warning('DataViewerCoreg2Cams:DataParamsRecordFailed', ...
                    ['Coregistration was saved successfully, but the local DataParams.mat ' ...
                    'record could not be updated: %s'], ME.message);
            end
        end

        function T = getTformMatrix(app, tform) %#ok<INUSL>
            %GETTFORMMATRIX Return 3 x 3 transform matrix from a tform object.

            if isnumeric(tform)
                T = tform;
                return
            end

            if isprop(tform, 'T')
                T = tform.T;
                return
            end

            if isprop(tform, 'A')
                T = tform.A;
                return
            end

            error('DataViewerCoreg2Cams:MissingTransformMatrix', ...
                'Could not extract transform matrix from class "%s".', class(tform));
        end

        function cleanupCoregistrationApp(app)
            %CLEANUPCOREGISTRATIONAPP Stop timers and release transient UI resources.

            try
                app.stopAlternateViewTimer();
            catch
            end

            try
                app.finishCalibrationWorkflow(false);
            catch ME
                warning('DataViewerCoreg2Cams:CleanupFailed', ...
                    'Could not fully clean up the calibration workflow: %s', ME.message);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, dataFolder, appMode, varargin)
            %STARTUPFCN Initialize the dual-camera coregistration utility.
            %
            %   DataViewer_Coreg2Cams(parentApp, dataFolder)
            %   DataViewer_Coreg2Cams(parentApp, dataFolder, appMode)
            %   DataViewer_Coreg2Cams(parentApp, dataFolder, appMode, ...
            %       'ProjectStore', store, 'RigUUID', rigUUID, 'RigID', rigID, ...
            %       'SubjectUUID', subjectUUID, 'SubjectID', subjectID, ...
            %       'SessionUUID', sessionUUID, 'SessionID', sessionID)
            %
            %   The dataFolder argument is treated as context only. Camera coregistration
            %   source data must be explicitly loaded by the user because processed
            %   .dat files may already have been coregistered during import.

            %
            %   The rig/project context name-value pairs are all optional. This
            %   tool must keep working standalone/unbound, since dual-camera
            %   coregistration is a legacy, project-independent workflow.

            if nargin < 2
                parentApp = [];
            end

            if nargin < 3 || isempty(dataFolder)
                dataFolder = '';
            end

            if nargin < 4 || isempty(appMode)
                appMode = 'CoregistrationUtility';
            end

            app.MainApp = parentApp;
            app.DataFolder = char(string(dataFolder));
            app.appMode = char(string(appMode));

            p = inputParser;
            p.FunctionName = 'DataViewer_Coreg2Cams.startupFcn';

            addParameter(p, 'ProjectStore', [], ...
                @(x) isempty(x) || isa(x, 'UMITProjectStore'));
            addParameter(p, 'RigUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'RigID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SubjectUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SubjectID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SessionUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SessionID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));

            parse(p, varargin{:});
            R = p.Results;

            app.ProjectStore = R.ProjectStore;
            app.RigUUID = char(string(R.RigUUID));
            app.RigID = char(string(R.RigID));
            app.SubjectUUID = char(string(R.SubjectUUID));
            app.SubjectID = char(string(R.SubjectID));
            app.SessionUUID = char(string(R.SessionUUID));
            app.SessionID = char(string(R.SessionID));

            % The target rig is resolved by ensureRigContext: the folder's rig
            % ledger binding first, then the RigUUID passed here, then a user
            % choice. Never the machine's default rig.
            app.HasRigContext = false;

            app.configureInitialState();

            % A failure is reported once the figure is visible (below):
            % uialert refuses a hidden figure.
            [hasRig, rigMessage] = app.ensureRigContext();

            app.loadActiveCoregistration();

            app.refreshCoregistrationFileInfo();

            app.refreshButtonStates();
            app.refreshPreview();

            if exist('placeAppInsideCaller', 'file') == 2 && ~isempty(parentApp)
                try
                    placeAppInsideCaller(parentApp, app.UIFigure, 'center');
                catch
                    movegui(app.UIFigure, 'center');
                end
            else
                movegui(app.UIFigure, 'center');
            end

            app.UIFigure.Visible = 'on';
            initializeUmitTheme(app.UIFigure);

            if ~hasRig
                % Nothing can be saved without a rig: close the tool once the
                % user has read why.
                uialert(app.UIFigure, ...
                    sprintf(['No rig is available for camera coregistration: %s' ...
                    newline newline 'Camera coregistration is saved into a library ' ...
                    'rig. This tool closes now; fix the issue and reopen it.'], rigMessage), ...
                    'No rig available', ...
                    'Icon', 'error', ...
                    'CloseFcn', @(~, ~) app.UIFigureCloseRequest([]));
            end
            %             if strcmpi(AppMode, 'CoregistrationMode')
            %                 app.UseenhancedcontrastCheckBoxValueChanged('');
            %             end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            %UIFIGURECLOSEREQUEST Close app after stopping preview timers.

            if app.HasUnsavedCoregistration
                answer = uiconfirm(app.UIFigure, ...
                    'There is an unsaved coregistration candidate. Close without saving?', ...
                    'Unsaved coregistration', ...
                    'Options', {'Close without saving', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');

                if strcmpi(answer, 'Cancel')
                    return
                end
            end

            app.cleanupCoregistrationApp();
            delete(app)

        end

        % Button pushed function: RunAutomaticcoregistrationButton
        function RunAutomaticcoregistrationButtonPushed(app, event)
            %RUNAUTOMATICCOREGISTRATIONBUTTONPUSHED Generate automatic candidate tform.

            if ~app.HasCoregistrationSource
                uialert(app.UIFigure, ...
                    'Load unregistered coregistration data before running automatic coregistration.', ...
                    'No coregistration source', ...
                    'Icon', 'warning');
                return
            end

            try
                w = uiprogressdlg(app.UIFigure, ...
                    'Message', 'Calculating geometric transformation...', ...
                    'Title', 'Please wait', ...
                    'Indeterminate', 'on');
                cleanupObj = onCleanup(@() delete(w));

                [tform, tformInfo, warnmsg] = genTform2Cams(app.CoregistrationSourceFolder, ...
                    app.UseenhancedcontrastCheckBox.Value, ...
                    app.FlipHorizontallyCheckBox.Value);

                if ~isempty(warnmsg)
                    app.abortCalibrationWorkflow();
                    uialert(app.UIFigure, warnmsg, ...
                        'Failed to generate geometric transformation file', ...
                        'Icon', 'warning');
                    app.setLocalStatus('Automatic coregistration failed.');
                    return
                end

                tformInfo.CandidateSource = 'Automatic coregistration';
                tformInfo.SourceDataFolder = app.CoregistrationSourceFolder;
                workflowState = app.getCalibrationWorkflowState();
                tformInfo.RawSourceFolder = workflowState.RawFolder;
                tformInfo.GeneratedOn = datetime('now');

                app.setCandidateTransform(tform, tformInfo, ...
                    'Automatic coregistration', true, true);

                app.setLocalStatus('Automatic coregistration completed. Review and save the coregistration if acceptable.');

            catch ME
                app.abortCalibrationWorkflow();
                app.setLocalStatus(sprintf('Automatic coregistration failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Automatic coregistration failed', ...
                    'Icon', 'error');
            end

        end

        % Selection changed function: ViewmodeButtonGroup
        function ViewmodeButtonGroupSelectionChanged(app, event)
            %VIEWMODEBUTTONGROUPSELECTIONCHANGED Refresh preview mode.

            app.refreshPreview();

        end

        % Value changed function: UseenhancedcontrastCheckBox
        function UseenhancedcontrastCheckBoxValueChanged(app, event)
            %USEENHANCEDCONTRASTCHECKBOXVALUECHANGED Refresh preview contrast mode.

            app.refreshPreview();

        end

        % Value changed function: FlipHorizontallyCheckBox
        function FlipHorizontallyCheckBoxValueChanged(app, event)
            %FLIPHORIZONTALLYCHECKBOXVALUECHANGED Flip moving source image before coregistration.


            if app.HasCoregistrationSource && ~isempty(app.CurrentMovingImage)
                app.CurrentMovingImage = fliplr(app.CurrentMovingImage);
                app.EnhancedMovingImage = adapthisteq(app.normalizeImage(app.CurrentMovingImage));
                app.updateRegisteredMovingImage();
                app.refreshPreview();
                app.setLocalStatus('Moving camera image flipped horizontally. Re-run automatic coregistration if needed.');
            end

        end

        % Value changed function: TransformationDropDown
        function TransformationDropDownValueChanged(app, event)
            %TRANSFORMATIONDROPDOWNVALUECHANGED Update manual-step units.

            app.updateStepSizeLabel();

        end

        % Value changed function: StepsizeEditField
        function StepsizeEditFieldValueChanged(app, event)
            %STEPSIZEEDITFIELDVALUECHANGED Validate manual adjustment step size.

            if ~isfinite(app.StepsizeEditField.Value) || app.StepsizeEditField.Value <= 0
                app.StepsizeEditField.Value = 1;
                uialert(app.UIFigure, ...
                    'Step size must be a finite positive value.', ...
                    'Invalid step size', ...
                    'Icon', 'warning');
            end

        end

        % Value changed function: ApplyStepSpinner
        function ApplyStepSpinnerValueChanged(app, event)
            %APPLYSTEPSPINNERVALUECHANGED Apply relative manual transform step.

            try
                if isempty(app.CurrentTform)
                    app.ApplyStepSpinner.Value = 0;
                    return
                end

                signedStep = event.Value - event.PreviousValue;
                if signedStep == 0
                    return
                end

                app.applyManualStep(signedStep);

            catch ME
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Manual adjustment failed', ...
                    'Icon', 'error');
            end

            app.ApplyStepSpinner.Value = 0;

        end

        % Button pushed function: SaveCoregistrationButton
        function SaveCoregistrationButtonPushed(app, event)
            %SAVECOREGISTRATIONBUTTONPUSHED Save current candidate as the rig's new
            %active camera coregistration resource.

            if isempty(app.CurrentTform)
                uialert(app.UIFigure, ...
                    'No candidate coregistration is available to save.', ...
                    'No coregistration candidate', ...
                    'Icon', 'warning');
                return
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Save the currently previewed coregistration as the active coregistration?' newline newline ...
                'If an active coregistration exists, it will remain saved and browsable ' ...
                'from Coregistration History -- it just won''t be active anymore.'], ...
                'Save active coregistration?', ...
                'Options', {'Save Coregistration', 'Cancel'}, ...
                'DefaultOption', 'Save Coregistration', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'question');

            if strcmpi(answer, 'Cancel')
                workflowState = app.getCalibrationWorkflowState();
                if workflowState.IsInProgress
                    app.abortCalibrationWorkflow();
                    app.refreshAllState();
                    if workflowState.IsRecalibration
                        app.setLocalStatus(['Recalibration cancelled; the previous ' ...
                            'active calibration was restored.']);
                    else
                        app.setLocalStatus('Initial calibration cancelled.');
                    end
                end
                return
            end

            try
                app.ensureCurrentTformInfoHasRegisteredImages();
                tform = app.CurrentTform;
                tformInfo = app.CurrentTformInfo;

                resourceUUID = app.saveTform(tform, tformInfo);

                % From here on, the camera coregistration is already saved and active
                % in the rig store. Everything below is best-effort local
                % provenance and must never undo or block the save that already
                % happened.
                sourceFilePath = '';
                try
                    rigStore = app.openRigStore();
                    if ~isempty(rigStore)
                        sourceFilePath = rigStore.resolveResourcePath(resourceUUID);
                    end
                catch
                    sourceFilePath = '';
                end

                app.updateCameraCoregistrationDataParams( ...
                    tform, resourceUUID, sourceFilePath, char(datetime('now')));

                app.finishCalibrationWorkflow(true);
                app.HasUnsavedCoregistration = false;
                app.setCandidateTransform(app.ActiveTform, app.ActiveTformInfo, ...
                    'Active coregistration', false, true);

                app.refreshAllState();
                app.setLocalStatus(sprintf(['Coregistration saved and activated for rig "%s". ' ...
                    'Folders imported earlier keep the transform they were imported with; ' ...
                    're-import their raw data to apply this one.'], app.RigID));

            catch ME
                app.abortCalibrationWorkflow();
                app.setLocalStatus(sprintf('Save coregistration failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Save coregistration failed', ...
                    'Icon', 'error');
            end


        end

        % Button pushed function: ManageCoregistrationFilesButton
        function ManageCoregistrationFilesButtonPushed(app, event)
            %MANAGECOREGISTRATIONFILESBUTTONPUSHED Open the calibration manager.

            app.openCoregistrationFileManager();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %RESETBUTTONPUSHED Reset manual fine-tuning adjustment.

            if isempty(app.BaseTform)
                return
            end

            app.ManualDx = 0;
            app.ManualDy = 0;
            app.ManualRotationDeg = 0;
            app.ManualScale = 1;
            app.CurrentTform = app.BaseTform;
            app.CurrentTformInfo = app.updateTformInfoForManualAdjustment(app.CurrentTformInfo);
            app.updateRegisteredMovingImage();
            app.HasUnsavedCoregistration = true;

            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
            app.setLocalStatus('Manual adjustment reset. Save Coregistration to keep the reset candidate.');

        end

        % Button pushed function: LoadCoregistrationDataButton
        function LoadCoregistrationDataButtonPushed(app, event)
            %LOADCOREGISTRATIONDATABUTTONPUSHED Start initial calibration or
            %safe recalibration from a raw dual-camera acquisition folder.

            isRecalibration = app.hasActiveCoregistration();
            if isRecalibration
                answer = uiconfirm(app.UIFigure, ...
                    ['The current calibration will be preserved and temporarily ' ...
                    'deactivated while the new calibration is created.' newline newline ...
                    'If recalibration is cancelled or fails, the current calibration ' ...
                    'will be restored automatically. The new calibration becomes ' ...
                    'active only after it is successfully saved and validated.'], ...
                    'Recalibrate dual cameras?', ...
                    'Options', {'Recalibrate', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');

                if strcmpi(answer, 'Cancel')
                    return
                end
            end

            try
                w = uiprogressdlg(app.UIFigure, ...
                    'Title', 'Preparing calibration data', ...
                    'Message', 'Validating and classifying the raw acquisition...', ...
                    'Indeterminate', 'on');
                cleanupObj = onCleanup(@() delete(w));

                completed = app.prepareCalibrationSource(isRecalibration);
                if ~completed
                    return
                end

                app.CurrentTform = [];
                app.CurrentTformInfo = struct();
                app.BaseTform = [];
                app.CandidateSource = '';
                app.HasUnsavedCoregistration = false;

                app.refreshCoregistrationFileInfo();
                app.refreshButtonStates();
                app.refreshPreview();
                app.setLocalStatus(['Raw dual-camera data prepared in a temporary folder. ' ...
                    'Run automatic coregistration to create a candidate.']);

            catch ME
                app.abortCalibrationWorkflow();
                app.refreshCoregistrationFileInfo();
                app.refreshButtonStates();
                app.refreshPreview();
                app.setLocalStatus(sprintf('Calibration preparation failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Calibration preparation failed', ...
                    'Icon', 'error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dual Camera coregistration';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GrifLayoutLeft = uigridlayout(app.GridLayout);
            app.RunAutomaticcoregistrationButton = uibutton(app.GrifLayoutLeft, 'push');
            app.RunAutomaticcoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutomaticcoregistrationButtonPushed, true);
            app.RunAutomaticcoregistrationButton.Text = 'Run Automatic coregistration';
            app.CoregistrationparametersPanel = uipanel(app.GrifLayoutLeft);
            app.CoregistrationparametersPanel.Title = 'Coregistration parameters';
            app.GridCoregistrationPanel = uigridlayout(app.CoregistrationparametersPanel);
            app.UseenhancedcontrastCheckBox = uicheckbox(app.GridCoregistrationPanel);
            app.UseenhancedcontrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseenhancedcontrastCheckBoxValueChanged, true);
            app.UseenhancedcontrastCheckBox.Tooltip = {'Check this box to enhance the image contrast to improve coregistration'};
            app.UseenhancedcontrastCheckBox.Text = 'Use enhanced contrast';
            app.UseenhancedcontrastCheckBox.Value = true;
            app.FlipHorizontallyCheckBox = uicheckbox(app.GridCoregistrationPanel);
            app.FlipHorizontallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipHorizontallyCheckBoxValueChanged, true);
            app.FlipHorizontallyCheckBox.Tooltip = {'Some Imaging systems will have a mirrored image in camera #2. Check this box if this is the case.'};
            app.FlipHorizontallyCheckBox.Text = 'Flip Horizontally';
            app.SaveCoregistrationButton = uibutton(app.GrifLayoutLeft, 'push');
            app.SaveCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCoregistrationButtonPushed, true);
            app.SaveCoregistrationButton.Text = 'Save Coregistration';
            app.ViewmodeButtonGroup = uibuttongroup(app.GrifLayoutLeft);
            app.ViewmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewmodeButtonGroupSelectionChanged, true);
            app.ViewmodeButtonGroup.Title = 'View mode';
            app.AlternateViewButton = uiradiobutton(app.ViewmodeButtonGroup);
            app.AlternateViewButton.Text = 'Alternate View';
            app.AlternateViewButton.Value = true;
            app.FalseColorOverlayButton = uiradiobutton(app.ViewmodeButtonGroup);
            app.FalseColorOverlayButton.Text = 'False Color Overlay';
            app.ManualfinetuningPanel = uipanel(app.GrifLayoutLeft);
            app.ManualfinetuningPanel.Title = 'Manual fine tuning';
            app.GridManualFineTuning = uigridlayout(app.ManualfinetuningPanel);
            app.TransformationDropDownLabel = uilabel(app.GridManualFineTuning);
            app.TransformationDropDownLabel.Text = 'Transformation';
            app.TransformationDropDown = uidropdown(app.GridManualFineTuning);
            app.TransformationDropDown.Items = {'Horizontal (transl.)', 'Vertical (transl.)', 'Rotation', 'Scaling'};
            app.TransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformationDropDownValueChanged, true);
            app.TransformationDropDown.Value = 'Horizontal (transl.)';
            app.StepsizeEditFieldLabel = uilabel(app.GridManualFineTuning);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.GridManualFineTuning, 'numeric');
            app.StepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeEditFieldValueChanged, true);
            app.ApplyStepSpinnerLabel = uilabel(app.GridManualFineTuning);
            app.ApplyStepSpinnerLabel.Text = 'Apply Step';
            app.ApplyStepSpinner = uispinner(app.GridManualFineTuning);
            app.ApplyStepSpinner.ValueChangedFcn = createCallbackFcn(app, @ApplyStepSpinnerValueChanged, true);
            app.ResetButton = uibutton(app.GridManualFineTuning, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CoregistrationFilesPanel = uipanel(app.GrifLayoutLeft);
            app.CoregistrationFilesPanel.Title = 'Coregistration Files';
            app.GridGeometricPanel = uigridlayout(app.CoregistrationFilesPanel);
            app.CoregistrationFileInfoTextArea = uitextarea(app.GridGeometricPanel);
            app.ManageCoregistrationFilesButton = uibutton(app.GridGeometricPanel, 'push');
            app.ManageCoregistrationFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ManageCoregistrationFilesButtonPushed, true);
            app.ManageCoregistrationFilesButton.Tooltip = {'Review, activate, archive, restore, or purge this rig''s coregistration files.'};
            app.ManageCoregistrationFilesButton.Text = 'Manage Coregistration Files';
            app.CoregistrationSourcePanel = uipanel(app.GrifLayoutLeft);
            app.CoregistrationSourcePanel.Title = 'Coregistration Source';
            app.GridCoregistrationSource = uigridlayout(app.CoregistrationSourcePanel);
            app.LoadCoregistrationDataButton = uibutton(app.GridCoregistrationSource, 'push');
            app.LoadCoregistrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoregistrationDataButtonPushed, true);
            app.LoadCoregistrationDataButton.Text = 'Load Coregistration Data....';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_Coreg2Cams(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end