classdef ImageReferenceManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        StatusLabel                   matlab.ui.control.Label
        GridLayoutBottom              matlab.ui.container.GridLayout
        CreatefromcurrentframeButton  matlab.ui.control.Button
        CreatefromfileButton          matlab.ui.control.Button
        ArchiveButton                 matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        UseSelectedButton             matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SelectedImageReferencePanel   matlab.ui.container.Panel
        GridSelectedImageReferencePanel  matlab.ui.container.GridLayout
        RestoreButton                 matlab.ui.control.Button
        EditButton                    matlab.ui.control.Button
        MetaDataTextArea              matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ParentApp = []
        AppMode char = 'manage'  % 'manage' or 'select'

        % Required centralized project context.
        ProjectStore = []
        ProjectInfo = struct()
        SubjectInfo = struct()
        SubjectID char = ''
        SessionID char = ''

        ReferenceTable table = table()
        SelectedRow double = []
        SelectedResource = struct()
        SelectedResourceUUID char = ''
        SelectedResourceStatus char = ''
        SelectedReferenceFileRel char = ''
        SelectedReferenceFileAbs char = ''
        SelectedImageReference = []

        CurrentFrameImage = []
        CurrentFrameSourceFile char = ''
        CurrentFrameSourceFolder char = ''
        CurrentFrameIndex double = NaN
        CurrentSourceType char = ''

        IsEditingReference logical = false
        ReferenceEditOriginalImage = []
        ReferenceEditCandidateImage = []
        ReferenceEditBoxHandle = gobjects(1)
        ReferenceEditListeners cell = {}
        ReferenceEditTform = []
        ReferenceEditPreviewHandle = matlab.graphics.primitive.Image.empty
        ReferencePreviewHandle = matlab.graphics.primitive.Image.empty
    end

    properties (Access = public)
        OutputReferenceUUID char = ''
        OutputReferenceChecksum char = ''
        OutputReferenceFileRel char = ''
        OutputReferenceFileAbs char = ''
        OutputImageReference = []
        WasSelectionConfirmed logical = false
    end

    methods (Access = private)

        function configureInitialState(app)
            %CONFIGUREINITIALSTATE Initialize static GUI state.

            app.StatusLabel.Text = 'Ready.';
            app.MetaDataTextArea.Editable = 'off';
            app.MetaDataTextArea.Value = {'Select an Image Reference from the table.'};

            cla(app.UIAxes);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            title(app.UIAxes, 'No Image Reference selected', 'Interpreter', 'none');

            switch lower(app.AppMode)
                case 'standalone'
                    app.MetaDataTextArea.Value = { ...
                        'No project: create an Image Reference from the current frame', ...
                        'or from a file, then export it as a reference file.', ...
                        '', ...
                        'A reference file can be selected in the Image Alignment Tool', ...
                        '(Reference File) or imported into a subject library later.'};
                    app.StatusLabel.Text = ['No project: create an Image Reference and ' ...
                        'export it as a file.'];
                case 'select'
                    app.StatusLabel.Text = sprintf( ...
                        'Select an Image Reference for subject %s.', app.SubjectID);
                otherwise
                    app.StatusLabel.Text = sprintf( ...
                        'Manage Image References for subject %s.', app.SubjectID);
            end
        end

        function refreshReferenceTable(app)
            %REFRESHREFERENCETABLE Reload project-managed Image References.

            selectedUUID = app.SelectedResourceUUID;

            try
                if strcmpi(app.AppMode, 'manage')
                    statusFilter = {'active', 'available', 'archived'};
                else
                    statusFilter = {'active', 'available'};
                end

                resources = app.ProjectStore.listSubjectResources( ...
                    app.SubjectID, ...
                    'Type', 'imageReference', ...
                    'Status', statusFilter, ...
                    'VerifyFiles', true);

                T = app.buildReferenceTable(resources);
                app.ReferenceTable = T;

                visibleColumns = { ...
                    'Status', ...
                    'CreatedOn', ...
                    'ReferenceName', ...
                    'ImageSizeYX', ...
                    'SourceType', ...
                    'FileName'};

                app.UITable.Data = T(:, visibleColumns);
                app.UITable.ColumnName = { ...
                    'Status', ...
                    'Created on', ...
                    'Reference name', ...
                    'Image size [Y x X]', ...
                    'Source type', ...
                    'Managed file'};
                app.UITable.RowName = {};

                app.setStatusMessage(sprintf( ...
                    'Loaded %d Image Reference resource(s) for %s.', ...
                    height(T), app.SubjectID));

            catch ME
                app.ReferenceTable = table();
                app.UITable.Data = table();
                app.clearSelectedReference();
                app.setStatusMessage(sprintf( ...
                    'Failed to list Image References: %s', ME.message));
                return
            end

            app.clearSelectedReference();

            if ~isempty(selectedUUID)
                app.selectReferenceByUUID(selectedUUID);
            end
        end

        function selectReferenceRow(app, rowIdx)
            %SELECTREFERENCEROW Load and preview one managed Image Reference.

            if isempty(app.ReferenceTable) || height(app.ReferenceTable) == 0
                app.clearSelectedReference();
                return
            end

            if rowIdx < 1 || rowIdx > height(app.ReferenceTable)
                app.clearSelectedReference();
                return
            end

            T = app.ReferenceTable;

            if ~T.IsValid(rowIdx)
                app.clearSelectedReference();
                app.setStatusMessage(sprintf( ...
                    'Selected Image Reference is invalid: %s', ...
                    T.ValidationMessage(rowIdx)));
                return
            end

            resourceUUID = char(T.ResourceUUID(rowIdx));
            resource = app.ProjectStore.getResource(resourceUUID);

            if ~strcmp(resource.ownerType, 'subject') || ...
                    ~strcmp(resource.ownerUUID, app.SubjectInfo.uuid)
                error('ImageReferenceManager:ResourceOwnerMismatch', ...
                    ['Selected Image Reference does not belong to subject "%s".'], ...
                    app.SubjectID);
            end

            ImageReference = app.loadManagedImageReference(resource.absolutePath);
            ImageReference = app.augmentImageReferenceForOutput( ...
                ImageReference, resource);

            app.SelectedRow = rowIdx;
            app.SelectedResource = resource;
            app.SelectedResourceUUID = resource.uuid;
            app.SelectedResourceStatus = resource.status;
            app.SelectedReferenceFileRel = resource.relativePath;
            app.SelectedReferenceFileAbs = resource.absolutePath;
            app.SelectedImageReference = ImageReference;

            app.previewSelectedReference();
            app.updateMetadataTextArea();
            app.refreshButtonState();

            app.setStatusMessage(sprintf( ...
                'Selected %s Image Reference: %s', ...
                resource.status, resource.displayName));
        end

        function selectReferenceByUUID(app, resourceUUID)
            %SELECTREFERENCEBYUUID Select a table row by immutable resource UUID.

            if isempty(app.ReferenceTable) || height(app.ReferenceTable) == 0
                return
            end

            resourceUUID = string(resourceUUID);
            idx = find(strcmp(app.ReferenceTable.ResourceUUID, resourceUUID), ...
                1, 'first');

            if isempty(idx)
                return
            end

            try
                app.UITable.Selection = idx;
            catch
            end

            app.selectReferenceRow(idx);
        end

        function clearSelectedReference(app)
            %CLEARSELECTEDREFERENCE Clear selected resource state and preview.

            if app.IsEditingReference
                app.cancelReferenceEdit();
            end

            app.SelectedRow = [];
            app.SelectedResource = struct();
            app.SelectedResourceUUID = '';
            app.SelectedResourceStatus = '';
            app.SelectedReferenceFileRel = '';
            app.SelectedReferenceFileAbs = '';
            app.SelectedImageReference = [];

            app.clearPreviewAxes('No Image Reference selected');
            app.MetaDataTextArea.Value = ...
                {'Select an Image Reference from the table.'};

            app.refreshButtonState();
        end

        function selectReferenceByRelativePath(app, relativePath)
            %SELECTREFERENCEBYRELATIVEPATH Select a row by ImageReference relative path.

            if isempty(app.ReferenceTable) || height(app.ReferenceTable) == 0
                return
            end

            relativePath = string(relativePath);
            relPaths = string(app.ReferenceTable.RelativePath);

            idx = find(strcmp(relPaths, relativePath), 1, 'first');

            if isempty(idx)
                return
            end

            try
                app.UITable.Selection = idx;
            catch
            end

            app.selectReferenceRow(idx);
        end

        function refreshButtonState(app)
            %REFRESHBUTTONSTATE Update controls from mode, status, and write access.

            hasCurrentFrame = ~isempty(app.CurrentFrameImage);
            hasSelection = ~isempty(app.SelectedResourceUUID) && ...
                ~isempty(app.SelectedImageReference);
            isArchived = hasSelection && ...
                strcmp(app.SelectedResourceStatus, 'archived');
            isActive = hasSelection && ...
                strcmp(app.SelectedResourceStatus, 'active');
            canWrite = ~isempty(app.ProjectStore) && ...
                ~app.ProjectStore.IsReadOnly;

            if app.IsEditingReference
                app.CreatefromcurrentframeButton.Enable = 'off';
                app.CreatefromfileButton.Enable = 'off';
                app.UseSelectedButton.Enable = 'off';
                app.ArchiveButton.Enable = 'off';
                app.CloseButton.Enable = 'off';

                app.EditButton.Enable = 'on';
                app.EditButton.Text = 'Apply';

                app.RestoreButton.Enable = 'on';
                app.RestoreButton.Tooltip = { ...
                    'Restore the original image and cancel the current edit'};
                return
            end

            app.EditButton.Text = 'Edit';
            app.CloseButton.Enable = 'on';

            % The icon-only Restore button is reserved for edit cancellation.
            app.RestoreButton.Enable = 'off';
            app.RestoreButton.Tooltip = { ...
                'Restore the original image and cancel the current edit'};

            if strcmpi(app.AppMode, 'standalone')
                app.CreatefromcurrentframeButton.Enable = app.onOff(hasCurrentFrame);
                app.CreatefromfileButton.Enable = 'on';

                app.UseSelectedButton.Text = 'Export...';
                app.UseSelectedButton.Tooltip = { ...
                    'Save the Image Reference as a standalone reference file'};
                app.UseSelectedButton.Enable = ...
                    app.onOff(~isempty(app.SelectedImageReference));

                app.ArchiveButton.Text = 'Archive';
                app.ArchiveButton.Tooltip = { ...
                    'Available for references in a subject library'};
                app.ArchiveButton.Enable = 'off';
                app.EditButton.Enable = 'off';

            elseif strcmpi(app.AppMode, 'manage')
                app.CreatefromcurrentframeButton.Enable = ...
                    app.onOff(canWrite && hasCurrentFrame);
                app.CreatefromfileButton.Enable = app.onOff(canWrite);

                app.UseSelectedButton.Text = 'Set Active';
                app.UseSelectedButton.Tooltip = { ...
                    'Set the selected Image Reference as the subject default'};
                app.UseSelectedButton.Enable = app.onOff( ...
                    canWrite && hasSelection && ~isArchived && ~isActive);

                if isArchived
                    app.ArchiveButton.Text = 'Restore';
                    app.ArchiveButton.Tooltip = { ...
                        'Restore the selected archived Image Reference'};
                else
                    app.ArchiveButton.Text = 'Archive';
                    app.ArchiveButton.Tooltip = { ...
                        'Archive the selected Image Reference'};
                end

                app.ArchiveButton.Enable = ...
                    app.onOff(canWrite && hasSelection);
                app.EditButton.Enable = ...
                    app.onOff(canWrite && hasSelection && ~isArchived);

            else
                app.CreatefromcurrentframeButton.Enable = 'off';
                app.CreatefromfileButton.Enable = 'off';

                app.UseSelectedButton.Text = 'Use Selected';
                app.UseSelectedButton.Tooltip = { ...
                    'Return the selected Image Reference to the caller'};
                app.UseSelectedButton.Enable = ...
                    app.onOff(hasSelection && ~isArchived);

                app.ArchiveButton.Text = 'Archive';
                app.ArchiveButton.Tooltip = { ...
                    'Archive or restore the selected Image Reference'};
                app.ArchiveButton.Enable = 'off';

                app.EditButton.Enable = 'off';
            end
        end

        function setStatusMessage(app, msg)
            %SETSTATUSMESSAGE Display status text.

            app.StatusLabel.Text = char(string(msg));
            drawnow limitrate;
        end

        function previewSelectedReference(app)
            %PREVIEWSELECTEDREFERENCE Show selected ImageReference image.

            if isempty(app.SelectedImageReference) || ...
                    ~isfield(app.SelectedImageReference, 'image')
                app.clearPreviewAxes('No Image Reference selected');
                return
            end

            img = single(app.SelectedImageReference.image);

            titleText = app.SelectedResource.displayName;
            if isempty(titleText)
                titleText = app.SelectedImageReference.name;
            end

            app.showReferencePreviewImage(img, titleText);
        end

        function updateMetadataTextArea(app)
            %UPDATEMETADATATEXTAREA Show payload and managed-resource metadata.

            if strcmpi(app.AppMode, 'standalone')
                app.updateStandaloneMetadataText();
                return
            end

            if isempty(app.SelectedImageReference) || ...
                    isempty(app.SelectedResourceUUID)
                app.MetaDataTextArea.Value = {'No Image Reference selected.'};
                return
            end

            R = app.SelectedImageReference;
            resource = app.SelectedResource;

            lines = strings(0, 1);

            lines(end+1) = "Name: " + string(resource.displayName);
            lines(end+1) = "Status: " + string(resource.status);
            lines(end+1) = "Resource UUID: " + string(resource.uuid);
            lines(end+1) = "Checksum: " + string(resource.checksum);
            lines(end+1) = "";
            lines(end+1) = "Project: " + ...
                string(app.getReferenceField(R, 'projectName', ...
                app.ProjectInfo.projectName));
            lines(end+1) = "Subject ID: " + ...
                string(app.getReferenceField(R, 'subjectID', app.SubjectID));
            lines(end+1) = "Subject UUID: " + ...
                string(app.getReferenceField(R, 'subjectUUID', ...
                app.SubjectInfo.uuid));
            lines(end+1) = "Source session ID: " + ...
                string(app.getReferenceField(R, 'sessionID', ''));
            lines(end+1) = "Source session UUID: " + ...
                string(app.getReferenceField(R, 'sessionUUID', ''));
            lines(end+1) = "";
            lines(end+1) = "Created: " + ...
                string(app.getReferenceField(R, 'createdOn', resource.createdOn));
            lines(end+1) = "Last modified: " + ...
                string(app.getReferenceField(R, 'lastModified', ...
                resource.modifiedOn));

            imageSizeYX = app.getReferenceField(R, 'imageSizeYX', ...
                size(R.image));
            lines(end+1) = "Image size: " + ...
                sprintf('%d x %d', imageSizeYX(1), imageSizeYX(2));
            lines(end+1) = "Axis convention: " + ...
                string(app.getReferenceField(R, ...
                'axisConvention', 'imageXY_topLeft'));
            lines(end+1) = "";
            lines(end+1) = "Description:";
            lines(end+1) = string(resource.description);
            lines(end+1) = "";
            lines(end+1) = "Source folder: " + ...
                string(app.getReferenceField(R, 'sourceFolder', ''));
            lines(end+1) = "Source file: " + ...
                string(app.getReferenceField(R, 'sourceFile', ''));
            lines(end+1) = "Source frame: " + ...
                app.valueToText(app.getReferenceField(R, 'sourceFrame', []));
            lines(end+1) = "Source type: " + ...
                string(app.getReferenceField(R, 'sourceType', ''));
            lines(end+1) = "Created by: " + ...
                string(app.getReferenceField(R, 'createdBy', ''));
            lines(end+1) = "Manually transformed: " + ...
                string(app.getReferenceField(R, ...
                'wasManuallyTransformed', false));

            manualAppliedOn = app.getReferenceField( ...
                R, 'manualTransformAppliedOn', NaT);
            if isdatetime(manualAppliedOn) && ...
                    isscalar(manualAppliedOn) && ~isnat(manualAppliedOn)
                lines(end+1) = ...
                    "Manual transform applied on: " + string(manualAppliedOn);
            end

            manualNote = string(app.getReferenceField( ...
                R, 'manualTransformNote', ''));
            if strlength(manualNote) > 0
                lines(end+1) = "Manual transform note: " + manualNote;
            end

            parentUUID = string(app.getReferenceField( ...
                R, 'parentResourceUUID', ''));
            if strlength(parentUUID) > 0
                lines(end+1) = "Parent resource UUID: " + parentUUID;
            end

            lines(end+1) = "";
            lines(end+1) = "Processing notes:";
            lines(end+1) = string(app.getReferenceField( ...
                R, 'processingNotes', ''));
            lines(end+1) = "";
            lines(end+1) = "Managed relative path:";
            lines(end+1) = string(resource.relativePath);
            lines(end+1) = "Managed absolute path:";
            lines(end+1) = string(resource.absolutePath);

            app.MetaDataTextArea.Value = cellstr(lines);
        end

        function metadata = promptCreateReferenceMetadata(app)
            %PROMPTCREATEREFERENCEMETADATA Prompt for resource-level metadata.
            %
            % Project and subject identity are supplied by UMITProjectStore and are not
            % editable in this dialog.

            metadata = [];

            dlg = uifigure( ...
                'Name', 'Create Image Reference', ...
                'WindowStyle', 'modal', ...
                'Visible', 'off', ...
                'Position', [100 100 520 340]);

            grid = uigridlayout(dlg);
            grid.ColumnWidth = {130, '1x'};
            grid.RowHeight = {30, 35, 90, 90, 40};
            grid.Padding = [12 12 12 12];

            contextLabel = uilabel(grid);
            if strcmpi(app.AppMode, 'standalone')
                contextLabel.Text = 'No project: the reference is exported as a file.';
            else
                contextLabel.Text = sprintf('Project: %s    Subject: %s', ...
                    app.ProjectInfo.projectName, app.SubjectID);
            end
            contextLabel.FontWeight = 'bold';
            contextLabel.Layout.Row = 1;
            contextLabel.Layout.Column = [1 2];

            nameField = app.addDialogEditField( ...
                grid, 2, 'Reference name', 'ImageReference');

            descriptionLabel = uilabel(grid);
            descriptionLabel.Text = 'Description';
            descriptionLabel.Layout.Row = 3;
            descriptionLabel.Layout.Column = 1;

            descriptionArea = uitextarea(grid);
            descriptionArea.Layout.Row = 3;
            descriptionArea.Layout.Column = 2;
            descriptionArea.Tooltip = {'Optional resource description'};

            notesLabel = uilabel(grid);
            notesLabel.Text = 'Processing notes';
            notesLabel.Layout.Row = 4;
            notesLabel.Layout.Column = 1;

            notesArea = uitextarea(grid);
            notesArea.Layout.Row = 4;
            notesArea.Layout.Column = 2;
            notesArea.Tooltip = ...
                {'Optional preprocessing or creation notes'};

            buttonGrid = uigridlayout(grid);
            buttonGrid.ColumnWidth = {'1x', 90, 90};
            buttonGrid.RowHeight = {'1x'};
            buttonGrid.Padding = [0 0 0 0];
            buttonGrid.Layout.Row = 5;
            buttonGrid.Layout.Column = [1 2];

            createButton = uibutton(buttonGrid, 'push');
            createButton.Text = 'Create';
            createButton.Layout.Row = 1;
            createButton.Layout.Column = 2;

            cancelButton = uibutton(buttonGrid, 'push');
            cancelButton.Text = 'Cancel';
            cancelButton.Layout.Row = 1;
            cancelButton.Layout.Column = 3;

            dlg.UserData = [];

            createButton.ButtonPushedFcn = @(~, ~) onCreate();
            cancelButton.ButtonPushedFcn = @(~, ~) onCancel();
            dlg.CloseRequestFcn = @(~, ~) onCancel();

            placeAppInsideCaller(app, dlg, 'center');
            dlg.Visible = 'on';
            uiwait(dlg);

            if isvalid(dlg)
                metadata = dlg.UserData;
                delete(dlg);
            end

            function onCreate()
                referenceName = strtrim(string(nameField.Value));

                if referenceName == ""
                    uialert(dlg, ...
                        'Reference name is required.', ...
                        'Missing required field', ...
                        'Icon', 'warning');
                    return
                end

                out = struct();
                out.referenceName = char(referenceName);
                out.description = char(strjoin( ...
                    string(descriptionArea.Value), newline));
                out.processingNotes = char(strjoin( ...
                    string(notesArea.Value), newline));

                dlg.UserData = out;
                uiresume(dlg);
            end

            function onCancel()
                dlg.UserData = [];
                uiresume(dlg);
            end
        end

        function editField = addDialogEditField(app, parentGrid, rowIdx, labelText, defaultText) %#ok<INUSL>
            %ADDDIALOGEDITFIELD Add labeled edit field to modal dialog.

            label = uilabel(parentGrid);
            label.Text = labelText;
            label.Layout.Row = rowIdx;
            label.Layout.Column = 1;

            editField = uieditfield(parentGrid, 'text');
            editField.Layout.Row = rowIdx;
            editField.Layout.Column = 2;
            editField.Value = char(string(defaultText));
        end

        function tryReadCurrentFrameContextFromParent(app)
            %TRYREADCURRENTFRAMECONTEXTFROMPARENT Best-effort DataViewer context read.
            %
            % This keeps the manager usable before the main DataViewer callback is
            % finalized. Explicit startup name-value inputs take precedence.

            if isempty(app.ParentApp)
                return
            end

            parentApp = app.ParentApp;

            if isempty(app.CurrentFrameImage)
                try
                    if ismethod(parentApp, 'getCurrentFrameImage')
                        app.CurrentFrameImage = parentApp.getCurrentFrameImage();
                    end
                catch
                end
            end

            if isempty(app.CurrentFrameSourceFile)
                try
                    if isprop(parentApp, 'CurrentFile')
                        app.CurrentFrameSourceFile = parentApp.CurrentFile;
                    end
                catch
                end
            end

            if isempty(app.CurrentFrameSourceFolder) && ~isempty(app.CurrentFrameSourceFile)
                try
                    app.CurrentFrameSourceFolder = fileparts(app.CurrentFrameSourceFile);
                catch
                end
            end

            if isnan(app.CurrentFrameIndex)
                try
                    if isprop(parentApp, 'CurrentFrame')
                        app.CurrentFrameIndex = double(parentApp.CurrentFrame);
                    elseif isprop(parentApp, 'FrameIndex')
                        app.CurrentFrameIndex = double(parentApp.FrameIndex);
                    end
                catch
                end
            end

            if isempty(app.CurrentSourceType)
                try
                    if ismethod(parentApp, 'getSourceType')
                        app.CurrentSourceType = parentApp.getSourceType();
                    end
                catch
                end
            end
        end

        function txt = valueToText(app, value) %#ok<INUSL>
            %VALUETOTEXT Convert arbitrary scalar/display value to text.

            if isempty(value)
                txt = "";
            elseif isnumeric(value) && isscalar(value)
                if isnan(value)
                    txt = "";
                else
                    txt = string(value);
                end
            elseif isstring(value) && isscalar(value)
                txt = value;
            elseif ischar(value)
                txt = string(value);
            else
                txt = string(evalc('disp(value)'));
                txt = strtrim(txt);
            end
        end

        function state = onOff(app, tf) %#ok<INUSL>
            %ONOFF Convert logical to App Designer Enable string.

            if tf
                state = 'on';
            else
                state = 'off';
            end
        end

        function startReferenceEdit(app)
            %STARTREFERENCEEDIT Start interactive reference-image curation.

            if isempty(app.SelectedImageReference) || ~isfield(app.SelectedImageReference, 'image')
                app.setStatusMessage('Select a valid Image Reference before editing.');
                return
            end

            app.ReferenceEditOriginalImage = single(app.SelectedImageReference.image);
            app.ReferenceEditCandidateImage = app.ReferenceEditOriginalImage;
            app.ReferenceEditTform = affine2d(eye(3));
            app.IsEditingReference = true;

            app.deleteReferenceEditRuntime();

            % Create the false-color preview once. During editing, only CData is
            % updated so user zoom/pan is not reset by repeated imshowpair calls.
            app.ReferenceEditPreviewHandle = imagesc(app.UIAxes, ...
                app.buildReferenceEditFalseColorFrame( ...
                app.ReferenceEditOriginalImage, ...
                app.ReferenceEditCandidateImage));

            axis(app.UIAxes, 'image');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            title(app.UIAxes, 'Original vs edited reference', 'Interpreter', 'none');

            imgSize = size(app.ReferenceEditOriginalImage);
            rectPos = [1 1 imgSize(2) imgSize(1)];

            % Start slightly zoomed out so the rectangle edges/rotation handle are
            % easy to grab. The user can then zoom/pan normally.
            padFrac = 0.35;
            app.UIAxes.XLim = [0.5 - padFrac * imgSize(2), imgSize(2) + 0.5 + padFrac * imgSize(2)];
            app.UIAxes.YLim = [0.5 - padFrac * imgSize(1), imgSize(1) + 0.5 + padFrac * imgSize(1)];

            hold(app.UIAxes, 'on');

            app.ReferenceEditBoxHandle = drawrectangle(app.UIAxes, ...
                'Position', rectPos, ...
                'Rotatable', true, ...
                'DrawingArea', 'unlimited', ...
                'Color', [1 1 1], ...
                'FaceAlpha', 0, ...
                'LineWidth', 1.5, ...
                'InteractionsAllowed', 'all');

            app.ReferenceEditListeners = {};
            app.ReferenceEditListeners{end+1} = addlistener(app.ReferenceEditBoxHandle, ...
                'MovingROI', @(src, evt) app.onReferenceEditBoxMoving(src, evt));
            app.ReferenceEditListeners{end+1} = addlistener(app.ReferenceEditBoxHandle, ...
                'ROIMoved', @(src, evt) app.onReferenceEditBoxMoved(src, evt));

            app.updateReferenceEditCandidate();
            app.refreshButtonState();

            app.setStatusMessage('Editing Image Reference. Translate/rotate the box, then click Apply. Click Restore to cancel.');
        end

        function onReferenceEditBoxMoving(app, src, evt) %#ok<INUSD>
            %ONREFERENCEEDITBOXMOVING Update candidate while edit box moves.

            app.lockReferenceEditBoxSize();
            app.updateReferenceEditCandidate();
        end

        function onReferenceEditBoxMoved(app, src, evt) %#ok<INUSD>
            %ONREFERENCEEDITBOXMOVED Update candidate after edit box moves.

            app.lockReferenceEditBoxSize();
            app.updateReferenceEditCandidate();
        end

        function lockReferenceEditBoxSize(app)
            %LOCKREFERENCEEDITBOXSIZE Prevent scaling during reference editing.
            %
            %   The user may translate and rotate the reference image, but scaling is
            %   intentionally disabled. If the rectangle is resized interactively,
            %   this method restores its original image-sized extent around the
            %   current rectangle center.

            if ~app.isUsableReferenceEditBox() || isempty(app.ReferenceEditOriginalImage)
                return
            end

            imgSize = size(app.ReferenceEditOriginalImage);
            lockedSize = [imgSize(2), imgSize(1)]; % [width height]

            pos = double(app.ReferenceEditBoxHandle.Position(:).');

            if numel(pos) < 4 || any(~isfinite(pos))
                return
            end

            centerXY = [pos(1) + pos(3)/2, pos(2) + pos(4)/2];

            app.ReferenceEditBoxHandle.Position = [ ...
                centerXY(1) - lockedSize(1)/2, ...
                centerXY(2) - lockedSize(2)/2, ...
                lockedSize(1), ...
                lockedSize(2)];
        end

        function updateReferenceEditCandidate(app)
            %UPDATEREFERENCEEDITCANDIDATE Build candidate image from current edit box.

            if ~app.IsEditingReference || isempty(app.ReferenceEditOriginalImage) || ...
                    ~app.isUsableReferenceEditBox()
                return
            end

            img = app.ReferenceEditOriginalImage;
            imgSize = size(img);

            tform = app.getReferenceEditTform();
            Rout = imref2d(imgSize);

            candidate = imwarp(img, tform, ...
                'OutputView', Rout, ...
                'InterpolationMethod', 'linear', ...
                'FillValues', 0);

            candidate(~isfinite(candidate)) = 0;

            app.ReferenceEditCandidateImage = single(candidate);
            app.ReferenceEditTform = tform;

            rgbPreview = app.buildReferenceEditFalseColorFrame( ...
                app.ReferenceEditOriginalImage, ...
                app.ReferenceEditCandidateImage);

            if isempty(app.ReferenceEditPreviewHandle) || ~isvalid(app.ReferenceEditPreviewHandle)
                app.ReferenceEditPreviewHandle = imagesc(app.UIAxes, rgbPreview);
                axis(app.UIAxes, 'image');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                title(app.UIAxes, 'Original vs edited reference', 'Interpreter', 'none');
            else
                app.ReferenceEditPreviewHandle.CData = rgbPreview;
            end

            try
                uistack(app.ReferenceEditPreviewHandle, 'bottom');
                uistack(app.ReferenceEditBoxHandle, 'top');
            catch
            end
        end

        function rgb = buildReferenceEditFalseColorFrame(app, fixedImg, movingImg) %#ok<INUSL>
            %BUILDREFERENCEEDITFALSECOLORFRAME Create RGB false-color overlay.
            %
            %   Red   = original reference image.
            %   Green = edited candidate image.
            %   Blue  = average of both channels.

            fixedImg = single(fixedImg);
            movingImg = single(movingImg);

            fixedImg(~isfinite(fixedImg)) = 0;
            movingImg(~isfinite(movingImg)) = 0;

            fixedImg = iNormalize01(fixedImg);
            movingImg = iNormalize01(movingImg);

            rgb = zeros([size(fixedImg), 3], 'single');
            rgb(:, :, 1) = fixedImg;
            rgb(:, :, 2) = movingImg;
            rgb(:, :, 3) = 0.5 .* (fixedImg + movingImg);

            function imgOut = iNormalize01(imgIn)
                finiteVals = imgIn(isfinite(imgIn));

                if isempty(finiteVals)
                    imgOut = zeros(size(imgIn), 'single');
                    return
                end

                lo = min(finiteVals(:));
                hi = max(finiteVals(:));

                if hi <= lo
                    imgOut = zeros(size(imgIn), 'single');
                    return
                end

                imgOut = (imgIn - lo) ./ (hi - lo);
                imgOut = min(max(imgOut, 0), 1);
            end
        end

        function tform = getReferenceEditTform(app)
            %GETREFERENCEEDITTFORM Return affine2d from current edit-box state.
            %
            %   The transform is from original reference-image space to edited
            %   reference-image space. It is stored only as provenance.

            img = app.ReferenceEditOriginalImage;
            imgSize = size(img);

            pos = double(app.ReferenceEditBoxHandle.Position(:).');
            angleDeg = app.getReferenceEditBoxRotation();

            originalCenter = [(imgSize(2) + 1) / 2, (imgSize(1) + 1) / 2];
            newCenter = [pos(1) + pos(3) / 2, pos(2) + pos(4) / 2];

            dx = newCenter(1) - originalCenter(1);
            dy = newCenter(2) - originalCenter(2);

            angleRad = -deg2rad(angleDeg);

            Tcenter1 = [1 0 0; 0 1 0; -originalCenter(1) -originalCenter(2) 1];
            R = [cos(angleRad) sin(angleRad) 0; -sin(angleRad) cos(angleRad) 0; 0 0 1];
            Tcenter2 = [1 0 0; 0 1 0; originalCenter(1) originalCenter(2) 1];
            Ttrans = [1 0 0; 0 1 0; dx dy 1];

            tform = affine2d(Tcenter1 * R * Tcenter2 * Ttrans);
        end

        function angleDeg = getReferenceEditBoxRotation(app)
            %GETREFERENCEEDITBOXROTATION Return rectangle rotation angle.

            angleDeg = 0;

            if ~app.isUsableReferenceEditBox()
                return
            end

            try
                if isprop(app.ReferenceEditBoxHandle, 'RotationAngle')
                    angleDeg = double(app.ReferenceEditBoxHandle.RotationAngle);
                end
            catch
                angleDeg = 0;
            end

            if ~isscalar(angleDeg) || ~isfinite(angleDeg)
                angleDeg = 0;
            end
        end

        function applyReferenceEdit(app)
            %APPLYREFERENCEEDIT Save an edited image as a new managed resource version.
            %
            % Managed resources are immutable. Editing therefore creates a new
            % ImageReference resource and preserves the selected resource as history.

            if ~app.IsEditingReference || isempty(app.ReferenceEditCandidateImage)
                app.setStatusMessage('No active Image Reference edit to apply.');
                return
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Create a new Image Reference version from the edited image?' ...
                newline newline ...
                'The selected managed file will not be overwritten. The new resource ' ...
                'will retain the selected resource UUID as provenance.'], ...
                'Create edited Image Reference version', ...
                'Options', {'Create Version', 'Cancel'}, ...
                'DefaultOption', 'Create Version', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'warning');

            if ~strcmpi(answer, 'Create Version')
                return
            end

            try
                notes = [ ...
                    'Reference image manually translated/rotated in ' ...
                    'ImageReferenceManager.'];

                R = app.SelectedImageReference;

                metadata = struct();
                metadata.referenceName = app.SelectedResource.displayName;
                metadata.description = app.SelectedResource.description;
                metadata.processingNotes = char(app.combineProcessingNotes( ...
                    app.getReferenceField(R, 'processingNotes', ''), notes));

                sourceInfo = struct();
                sourceInfo.sourceFolder = ...
                    app.getReferenceField(R, 'sourceFolder', '');
                sourceInfo.sourceFile = ...
                    app.getReferenceField(R, 'sourceFile', '');
                sourceInfo.sourceFrame = ...
                    app.getReferenceField(R, 'sourceFrame', []);
                sourceInfo.sourceType = ...
                    app.getReferenceField(R, 'sourceType', '');
                sourceInfo.sessionUUID = ...
                    app.getReferenceField(R, 'sessionUUID', '');
                sourceInfo.sessionID = ...
                    app.getReferenceField(R, 'sessionID', '');

                newUUID = app.createManagedImageReference( ...
                    app.ReferenceEditCandidateImage, ...
                    metadata, ...
                    sourceInfo, ...
                    'ParentResourceUUID', app.SelectedResourceUUID, ...
                    'WasManuallyTransformed', true, ...
                    'ManualTransformTform', app.ReferenceEditTform, ...
                    'ManualTransformNote', notes);

                % Preserve active-state semantics: editing an active reference creates
                % a new active version; editing an available reference creates another
                % available version.
                if strcmp(app.SelectedResourceStatus, 'active')
                    app.ProjectStore.setActiveImageReference( ...
                        app.SubjectID, newUUID);
                end

                app.deleteReferenceEditRuntime();
                app.IsEditingReference = false;
                app.ReferenceEditOriginalImage = [];
                app.ReferenceEditCandidateImage = [];
                app.ReferenceEditTform = [];

                app.refreshReferenceTable();
                app.selectReferenceByUUID(newUUID);
                app.setStatusMessage( ...
                    'Edited Image Reference version created.');

            catch ME
                app.setStatusMessage(sprintf( ...
                    'Failed to create edited Image Reference: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Create edited version failed', ...
                    'Icon', 'error');
            end
        end

        function cancelReferenceEdit(app)
            %CANCELREFERENCEEDIT Cancel current edit and restore saved preview.

            app.deleteReferenceEditRuntime();

            app.IsEditingReference = false;
            app.ReferenceEditOriginalImage = [];
            app.ReferenceEditCandidateImage = [];
            app.ReferenceEditTform = [];

            app.previewSelectedReference();
            app.updateMetadataTextArea();
            app.refreshButtonState();

            app.setStatusMessage('Image Reference edit cancelled.');
        end

        function deleteReferenceEditRuntime(app)
            %DELETEREFERENCEEDITRUNTIME Delete temporary edit handles/listeners.

            if ~isempty(app.ReferenceEditListeners)
                for iListener = 1:numel(app.ReferenceEditListeners)
                    try
                        if ~isempty(app.ReferenceEditListeners{iListener}) && ...
                                isvalid(app.ReferenceEditListeners{iListener})
                            delete(app.ReferenceEditListeners{iListener});
                        end
                    catch
                    end
                end
            end

            app.ReferenceEditListeners = {};

            try
                if app.isUsableReferenceEditBox()
                    delete(app.ReferenceEditBoxHandle);
                end
            catch
            end

            app.ReferenceEditBoxHandle = gobjects(1);

            try
                if ~isempty(app.ReferenceEditPreviewHandle) && isvalid(app.ReferenceEditPreviewHandle)
                    delete(app.ReferenceEditPreviewHandle);
                end
            catch
            end

            app.ReferenceEditPreviewHandle = matlab.graphics.primitive.Image.empty;
            hold(app.UIAxes, 'off');
            app.UIAxes.NextPlot = 'replacechildren';
        end

        function tf = isUsableReferenceEditBox(app)
            %ISUSABLEREFERENCEEDITBOX True when edit box handle is valid.

            tf = ~isempty(app.ReferenceEditBoxHandle) && ...
                all(isgraphics(app.ReferenceEditBoxHandle)) && ...
                all(isvalid(app.ReferenceEditBoxHandle));
        end

        function previewCandidateReferenceImage(app, referenceImage, sourceInfo)
            %PREVIEWCANDIDATEREFERENCEIMAGE Preview external image before save.

            imagesc(app.UIAxes, referenceImage);
            axis(app.UIAxes, 'image');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, gray);

            title(app.UIAxes, 'Candidate Image Reference', 'Interpreter', 'none');

            lines = strings(0, 1);

            lines(end+1) = "Candidate Image Reference";
            lines(end+1) = "";
            lines(end+1) = "Source type: " + string(sourceInfo.sourceType);
            lines(end+1) = "Source file:";
            lines(end+1) = string(sourceInfo.sourceFile);

            if isfield(sourceInfo, 'selectedEntry') && ...
                    strlength(string(sourceInfo.selectedEntry)) > 0
                lines(end+1) = "Selected UMT entry: " + string(sourceInfo.selectedEntry);
            end

            if isfield(sourceInfo, 'selectedVariable') && ...
                    strlength(string(sourceInfo.selectedVariable)) > 0
                lines(end+1) = "Selected MAT variable: " + string(sourceInfo.selectedVariable);
            end

            lines(end+1) = "";
            lines(end+1) = "Image size: " + sprintf('%d x %d', ...
                sourceInfo.imageSizeYX(1), sourceInfo.imageSizeYX(2));

            if isfield(sourceInfo, 'nSourceFrames') && ~isempty(sourceInfo.nSourceFrames)
                lines(end+1) = "Source frames/pages: " + string(sourceInfo.nSourceFrames);
            end

            if isfield(sourceInfo, 'usedFrameIndex') && ~isempty(sourceInfo.usedFrameIndex)
                lines(end+1) = "Frames/pages averaged: " + string(numel(sourceInfo.usedFrameIndex));
            end

            lines(end+1) = "";
            lines(end+1) = "Processing notes:";
            lines(end+1) = string(sourceInfo.processingNotes);

            app.MetaDataTextArea.Value = cellstr(lines);
        end

        function notes = combineProcessingNotes(app, sourceNotes, userNotes) %#ok<INUSL>
            %COMBINEPROCESSINGNOTES Combine source and user processing notes.

            sourceNotes = strtrim(string(sourceNotes));
            userNotes = strtrim(string(userNotes));

            if sourceNotes == "" && userNotes == ""
                notes = "";
            elseif sourceNotes == ""
                notes = userNotes;
            elseif userNotes == ""
                notes = sourceNotes;
            else
                notes = sourceNotes + newline + userNotes;
            end
        end

        function showReferencePreviewImage(app, img, titleText)
            %SHOWREFERENCEPREVIEWIMAGE Display selected reference image cleanly.
            %
            %   This resets edit-mode axes state before showing the selected
            %   grayscale reference image. It also updates image geometry so changing
            %   from one image size to another does not leave stale axes limits or
            %   old image overlays.

            if isempty(img)
                app.clearPreviewAxes('No Image Reference selected');
                return
            end

            img = single(img);
            img(~isfinite(img)) = 0;

            imgSize = size(img);
            nY = imgSize(1);
            nX = imgSize(2);

            % Remove edit-only graphics before normal preview.
            app.deleteReferenceEditRuntime();

            % Reset axes state that may have been changed during editing.
            hold(app.UIAxes, 'off');
            app.UIAxes.NextPlot = 'replacechildren';
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLimMode = 'auto';

            % Reuse a normal preview image handle when possible. If another image
            % handle exists from a different preview mode, recreate cleanly.
            if isempty(app.ReferencePreviewHandle) || ...
                    ~isvalid(app.ReferencePreviewHandle) || ...
                    ~isequal(size(app.ReferencePreviewHandle.CData), size(img))

                cla(app.UIAxes);

                app.ReferencePreviewHandle = imagesc(app.UIAxes, ...
                    [1 nX], [1 nY], img);

            else
                app.ReferencePreviewHandle.CData = img;
                app.ReferencePreviewHandle.XData = [1 nX];
                app.ReferencePreviewHandle.YData = [1 nY];
            end

            axis(app.UIAxes, 'image');
            app.UIAxes.XLim = [0.5, nX + 0.5];
            app.UIAxes.YLim = [0.5, nY + 0.5];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, gray);

            title(app.UIAxes, titleText, 'Interpreter', 'none');
        end

        function clearPreviewAxes(app, titleText)
            %CLEARPREVIEWAXES Clear preview axes and reset image handles.

            app.deleteReferenceEditRuntime();

            if ~isempty(app.ReferencePreviewHandle)
                try
                    if isvalid(app.ReferencePreviewHandle)
                        delete(app.ReferencePreviewHandle);
                    end
                catch
                end
            end

            app.ReferencePreviewHandle = matlab.graphics.primitive.Image.empty;

            cla(app.UIAxes);
            hold(app.UIAxes, 'off');

            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.CLimMode = 'auto';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];

            title(app.UIAxes, titleText, 'Interpreter', 'none');
        end

        function T = buildReferenceTable(app, resources)
            %BUILDREFERENCETABLE Build UI rows from managed resource records.

            nResources = numel(resources);

            ResourceUUID = strings(nResources, 1);
            Status = strings(nResources, 1);
            StatusOrder = zeros(nResources, 1);
            CreatedOn = NaT(nResources, 1);
            ReferenceName = strings(nResources, 1);
            Description = strings(nResources, 1);
            ImageSizeYX = strings(nResources, 1);
            SourceType = strings(nResources, 1);
            SourceFile = strings(nResources, 1);
            FileName = strings(nResources, 1);
            RelativePath = strings(nResources, 1);
            AbsolutePath = strings(nResources, 1);
            Checksum = strings(nResources, 1);
            IsValid = false(nResources, 1);
            ValidationMessage = strings(nResources, 1);

            for iResource = 1:nResources
                resource = resources(iResource);

                ResourceUUID(iResource) = string(resource.uuid);
                Status(iResource) = string(resource.status);
                CreatedOn(iResource) = resource.createdOn;
                ReferenceName(iResource) = string(resource.displayName);
                Description(iResource) = string(resource.description);
                FileName(iResource) = string(resource.fileName);
                RelativePath(iResource) = string(resource.relativePath);
                AbsolutePath(iResource) = string(resource.absolutePath);
                Checksum(iResource) = string(resource.checksum);

                switch resource.status
                    case 'active'
                        StatusOrder(iResource) = 1;
                    case 'available'
                        StatusOrder(iResource) = 2;
                    case 'archived'
                        StatusOrder(iResource) = 3;
                    otherwise
                        StatusOrder(iResource) = 4;
                end

                try
                    R = app.loadManagedImageReference(resource.absolutePath);
                    imageSizeYX = R.imageSizeYX;
                    ImageSizeYX(iResource) = sprintf( ...
                        '%d x %d', imageSizeYX(1), imageSizeYX(2));
                    SourceType(iResource) = string(R.sourceType);
                    SourceFile(iResource) = string(R.sourceFile);
                    IsValid(iResource) = true;
                catch ME
                    ImageSizeYX(iResource) = "Invalid";
                    ValidationMessage(iResource) = string(ME.message);
                end
            end

            T = table( ...
                ResourceUUID, ...
                Status, ...
                StatusOrder, ...
                CreatedOn, ...
                ReferenceName, ...
                Description, ...
                ImageSizeYX, ...
                SourceType, ...
                SourceFile, ...
                FileName, ...
                RelativePath, ...
                AbsolutePath, ...
                Checksum, ...
                IsValid, ...
                ValidationMessage);

            if ~isempty(T)
                T = sortrows(T, ...
                    {'StatusOrder', 'CreatedOn'}, ...
                    {'ascend', 'descend'});
            end
        end

        function ImageReference = loadManagedImageReference(~, filePath)
            %LOADMANAGEDIMAGEREFERENCE Load and validate one managed payload.

            if ~isfile(filePath)
                error('ImageReferenceManager:MissingReferenceFile', ...
                    'Managed Image Reference file does not exist: %s', filePath);
            end

            loaded = load(filePath, 'ImageReference', '-mat');

            if ~isfield(loaded, 'ImageReference')
                error('ImageReferenceManager:MissingReferenceVariable', ...
                    'Managed file does not contain variable "ImageReference".');
            end

            ImageReference = loaded.ImageReference;
            validateImageReferenceStruct(ImageReference);
        end

        function ImageReference = augmentImageReferenceForOutput(~, ImageReference, resource)
            %AUGMENTIMAGEREFERENCEFOROUTPUT Add runtime resource information.
            %
            % These fields are not saved inside the managed payload.

            ImageReference.resourceUUID = resource.uuid;
            ImageReference.resourceStatus = resource.status;
            ImageReference.resourceChecksum = resource.checksum;
            ImageReference.fileName = resource.fileName;
            ImageReference.relativePath = resource.relativePath;
            ImageReference.absolutePath = resource.absolutePath;
        end

        function resourceUUID = createManagedImageReference( ...
                app, referenceImage, metadata, sourceInfo, varargin)
            %CREATEMANAGEDIMAGEREFERENCE Build, stage, and import one reference.
            %
            % The temporary file is outside the centralized project. UMITProjectStore
            % chooses the final filename and destination.

            if ~isstruct(metadata) || ~isscalar(metadata)
                error('ImageReferenceManager:InvalidMetadata', ...
                    '"metadata" must be a scalar struct.');
            end
            if ~isstruct(sourceInfo) || ~isscalar(sourceInfo)
                error('ImageReferenceManager:InvalidSourceInfo', ...
                    '"sourceInfo" must be a scalar struct.');
            end

            p = inputParser;
            p.FunctionName = ...
                'ImageReferenceManager.createManagedImageReference';
            addParameter(p, 'ParentResourceUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'WasManuallyTransformed', false, ...
                @(x) islogical(x) && isscalar(x));
            addParameter(p, 'ManualTransformTform', []);
            addParameter(p, 'ManualTransformNote', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            parse(p, varargin{:});

            sourceFolder = app.getReferenceField( ...
                sourceInfo, 'sourceFolder', '');
            sourceFile = app.getReferenceField( ...
                sourceInfo, 'sourceFile', '');
            sourceFrame = app.getReferenceField( ...
                sourceInfo, 'sourceFrame', []);
            sourceType = app.getReferenceField( ...
                sourceInfo, 'sourceType', '');

            sessionUUID = app.getReferenceField( ...
                sourceInfo, 'sessionUUID', '');
            sessionID = app.getReferenceField( ...
                sourceInfo, 'sessionID', '');

            if isempty(sessionUUID) && isempty(sessionID)
                [sessionUUID, sessionID] = ...
                    app.resolveSourceSessionContext(sourceFolder);
            end

            ImageReference = genImageReferenceStruct( ...
                referenceImage, ...
                'Name', metadata.referenceName, ...
                'ProjectUUID', app.ProjectInfo.projectUUID, ...
                'ProjectName', app.ProjectInfo.projectName, ...
                'SubjectUUID', app.SubjectInfo.uuid, ...
                'SubjectID', app.SubjectID, ...
                'SessionUUID', sessionUUID, ...
                'SessionID', sessionID, ...
                'Description', metadata.description, ...
                'SourceFolder', sourceFolder, ...
                'SourceFile', sourceFile, ...
                'SourceFrame', sourceFrame, ...
                'SourceType', sourceType, ...
                'ProcessingNotes', metadata.processingNotes, ...
                'CreatedBy', 'ImageReferenceManager', ...
                'ParentResourceUUID', p.Results.ParentResourceUUID, ...
                'WasManuallyTransformed', p.Results.WasManuallyTransformed, ...
                'ManualTransformTform', p.Results.ManualTransformTform, ...
                'ManualTransformNote', p.Results.ManualTransformNote);

            temporaryFile = [tempname, '.mat'];
            cleanupFile = onCleanup(@() iDeleteTemporaryFile(temporaryFile));

            save(temporaryFile, 'ImageReference', '-mat');

            resourceInfo = struct();
            resourceInfo.displayName = metadata.referenceName;
            resourceInfo.description = metadata.description;

            resourceUUID = app.ProjectStore.addImageReference( ...
                app.SubjectID, temporaryFile, resourceInfo);

            clear cleanupFile

            function iDeleteTemporaryFile(filePath)
                if isfile(filePath)
                    delete(filePath);
                end
            end
        end

        function [sessionUUID, sessionID] = ...
                resolveSourceSessionContext(app, sourceFolder)
            %RESOLVESOURCESESSIONCONTEXT Resolve optional source-session provenance.

            sessionUUID = '';
            sessionID = '';

            if ~isempty(app.SessionID)
                SessionInfo = app.ProjectStore.getSessionInfo( ...
                    app.SubjectID, app.SessionID);
                sessionUUID = SessionInfo.uuid;
                sessionID = SessionInfo.sessionID;
                return
            end

            if isempty(sourceFolder)
                return
            end

            context = app.ProjectStore.findSessionByDataFolder(sourceFolder);

            if isempty(context)
                return
            end

            if ~strcmp(context.subjectUUID, app.SubjectInfo.uuid)
                error('ImageReferenceManager:SourceSubjectMismatch', ...
                    ['The source folder is registered to subject "%s", but this ' ...
                    'manager is scoped to subject "%s".'], ...
                    context.subjectID, app.SubjectID);
            end

            sessionUUID = context.sessionUUID;
            sessionID = context.sessionID;
        end

        function replacementUUID = promptArchiveReplacementUUID(app)
            %PROMPTARCHIVEREPLACEMENTUUID Select replacement for an active resource.

            replacementUUID = '';

            resources = app.ProjectStore.listSubjectResources( ...
                app.SubjectID, ...
                'Type', 'imageReference', ...
                'Status', {'active', 'available'}, ...
                'VerifyFiles', true);

            keep = ~strcmp({resources.uuid}, app.SelectedResourceUUID);
            resources = resources(keep);

            if isempty(resources)
                uialert(app.UIFigure, ...
                    ['The active Image Reference cannot be archived because no other ' ...
                    'available Image Reference can replace it.'], ...
                    'Replacement required', ...
                    'Icon', 'warning');
                return
            end

            labels = strings(numel(resources), 1);
            for iResource = 1:numel(resources)
                labels(iResource) = sprintf('[%s] %s — %s', ...
                    upper(resources(iResource).status), ...
                    resources(iResource).displayName, ...
                    char(string(resources(iResource).createdOn)));
            end

            [selectedIndex, confirmed] = listdlg( ...
                'PromptString', ...
                'Select the Image Reference that will become active:', ...
                'SelectionMode', 'single', ...
                'ListString', cellstr(labels), ...
                'Name', 'Select replacement Image Reference', ...
                'ListSize', [520 260]);

            if ~confirmed || isempty(selectedIndex)
                return
            end

            replacementUUID = resources(selectedIndex).uuid;
        end

        function value = getReferenceField(~, S, fieldName, defaultValue)
            %GETREFERENCEFIELD Return a structure field or a supplied default.

            if isstruct(S) && isscalar(S) && isfield(S, fieldName)
                value = S.(fieldName);
            else
                value = defaultValue;
            end
        end

        function useStandaloneReference(app, referenceImage, metadata, sourceInfo)
            %USESTANDALONEREFERENCE Build a reference without a project (in memory).
            %
            %   The ImageReference has no project, subject or session identity
            %   (version 2 allows that). It is shown and can be exported; nothing
            %   is written until Export.

            sourceFrame = app.getReferenceField(sourceInfo, 'sourceFrame', []);
            ImageReference = genImageReferenceStruct( ...
                referenceImage, ...
                'Name', metadata.referenceName, ...
                'Description', metadata.description, ...
                'SourceFolder', app.getReferenceField(sourceInfo, 'sourceFolder', ''), ...
                'SourceFile', app.getReferenceField(sourceInfo, 'sourceFile', ''), ...
                'SourceFrame', sourceFrame, ...
                'SourceType', app.getReferenceField(sourceInfo, 'sourceType', ''), ...
                'ProcessingNotes', metadata.processingNotes, ...
                'CreatedBy', 'ImageReferenceManager');

            app.SelectedImageReference = ImageReference;
            app.SelectedResource = struct('displayName', ImageReference.name, ...
                'description', ImageReference.description);
            app.previewSelectedReference();
            app.updateMetadataTextArea();
            app.refreshButtonState();
            app.setStatusMessage(sprintf( ...
                'Image Reference "%s" created (%s). Use Export... to save it as a file.', ...
                ImageReference.name, UMITLedgerCore.shortHash(ImageReference.contentHash)));
        end

        function updateStandaloneMetadataText(app)
            %UPDATESTANDALONEMETADATATEXT Metadata of an in-memory (no project) reference.

            R = app.SelectedImageReference;
            if isempty(R)
                app.MetaDataTextArea.Value = {'No Image Reference created yet.'};
                return
            end
            lines = strings(0, 1);
            lines(end+1) = "Name: " + string(R.name);
            lines(end+1) = "Content hash: " + string(R.contentHash);
            lines(end+1) = "Project: none (exported as a standalone reference file)";
            lines(end+1) = "";
            lines(end+1) = "Image size: " + sprintf('%d x %d', size(R.image, 1), size(R.image, 2));
            lines(end+1) = "Axis convention: " + string(R.axisConvention);
            lines(end+1) = "";
            lines(end+1) = "Description:";
            lines(end+1) = string(R.description);
            lines(end+1) = "";
            lines(end+1) = "Source folder: " + string(app.getReferenceField(R, 'sourceFolder', ''));
            lines(end+1) = "Source file: " + string(app.getReferenceField(R, 'sourceFile', ''));
            lines(end+1) = "Source frame: " + ...
                app.valueToText(app.getReferenceField(R, 'sourceFrame', []));
            lines(end+1) = "Source type: " + string(app.getReferenceField(R, 'sourceType', ''));
            lines(end+1) = "";
            lines(end+1) = "Processing notes:";
            lines(end+1) = string(app.getReferenceField(R, 'processingNotes', ''));
            app.MetaDataTextArea.Value = cellstr(lines);
        end

        function exportCurrentReference(app)
            %EXPORTCURRENTREFERENCE Save the shown reference as a standalone file.
            %
            %   Standalone mode: the in-memory reference. Project modes: the
            %   selected library reference, exported as stored (its project and
            %   subject identity are kept). The file can be selected as a
            %   'Reference File' in the Image Alignment Tool, with or without a
            %   project, or imported into a subject library.

            try
                if strcmpi(app.AppMode, 'standalone')
                    ImageReference = app.SelectedImageReference;
                else
                    if isempty(app.SelectedReferenceFileAbs)
                        app.setStatusMessage('Select an Image Reference to export.');
                        return
                    end
                    ImageReference = app.loadManagedImageReference( ...
                        app.SelectedReferenceFileAbs);
                end
                if isempty(ImageReference)
                    app.setStatusMessage('Create an Image Reference before exporting.');
                    return
                end

                defaultName = matlab.lang.makeValidName(char(string(ImageReference.name)));
                startFolder = pwd;
                if ~isempty(app.CurrentFrameSourceFolder) && isfolder(app.CurrentFrameSourceFolder)
                    startFolder = app.CurrentFrameSourceFolder;
                end
                [fileName, folderName] = uiputfile({'*.mat', 'Image Reference (*.mat)'}, ...
                    'Export Image Reference', fullfile(startFolder, [defaultName '.mat']));
                if isequal(fileName, 0)
                    app.setStatusMessage('Export cancelled.');
                    return
                end

                filePath = exportImageReferenceFile(ImageReference, fullfile(folderName, fileName));
                app.setStatusMessage(sprintf('Image Reference exported: %s', filePath));
                uialert(app.UIFigure, sprintf('Image Reference exported:\n%s', filePath), ...
                    'Image Reference exported', 'Icon', 'success');
            catch ME
                app.setStatusMessage(sprintf('Export failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Export failed', 'Icon', 'error');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, appMode, varargin)
            %STARTUPFCN Initialize the Image Reference Manager.
            %
            %   ImageReferenceManager(parentApp, appMode, ...
            %       'ProjectStore', store, ...
            %       'SubjectID', subjectID)
            %   ImageReferenceManager(parentApp, 'standalone', ...)
            %
            %   appMode:
            %       'manage'     - the subject's library: create, archive,
            %                      set active, export (right-click on the table)
            %       'select'     - return one library reference to the caller
            %       'standalone' - no project (project redesign gate P5): create a
            %                      reference from the current frame or a file and
            %                      export it as a standalone reference file
            %
            %   ProjectStore and SubjectID are required in 'manage' and 'select'.
            %
            %   Optional:
            %       'SessionID'         - Source-session provenance.
            %       'currentFrameImage' - Current 2-D DataViewer image.
            %       'sourceFile'
            %       'sourceFolder'
            %       'sourceFrame'
            %       'sourceType'

            app.UIFigure.Visible = 'off';

            if nargin < 2
                parentApp = [];
            end
            if nargin < 3 || isempty(appMode)
                appMode = 'manage';
            end

            app.ParentApp = parentApp;

            appMode = lower(char(string(appMode)));
            if ~ismember(appMode, {'manage', 'select', 'standalone'})
                error('ImageReferenceManager:InvalidMode', ...
                    'appMode must be "manage", "select" or "standalone".');
            end
            app.AppMode = appMode;
            isStandalone = strcmp(appMode, 'standalone');

            p = inputParser;
            p.FunctionName = 'ImageReferenceManager.startupFcn';

            addParameter(p, 'ProjectStore', [], ...
                @(x) isempty(x) || isa(x, 'UMITProjectStore'));
            addParameter(p, 'SubjectID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SessionID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'currentFrameImage', [], ...
                @(x) isempty(x) || isnumeric(x) || islogical(x));
            addParameter(p, 'sourceFile', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'sourceFolder', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'sourceFrame', NaN, ...
                @(x) isempty(x) || (isnumeric(x) && isscalar(x)));
            addParameter(p, 'sourceType', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));

            parse(p, varargin{:});
            R = p.Results;

            if ~isStandalone
                if isempty(R.ProjectStore)
                    error('ImageReferenceManager:MissingProjectStore', ...
                        'A UMITProjectStore object must be provided.');
                end
                if strlength(strtrim(string(R.SubjectID))) == 0
                    error('ImageReferenceManager:MissingSubjectID', ...
                        'SubjectID must be provided.');
                end

                app.ProjectStore = R.ProjectStore;
                app.ProjectInfo = app.ProjectStore.getProjectInfo();
                app.SubjectInfo = app.ProjectStore.getSubjectInfo(R.SubjectID);
                app.SubjectID = app.SubjectInfo.subjectID;
                app.SessionID = char(string(R.SessionID));

                if ~isempty(app.SessionID)
                    SessionInfo = app.ProjectStore.getSessionInfo( ...
                        app.SubjectID, app.SessionID);
                    app.SessionID = SessionInfo.sessionID;
                end
            end

            app.CurrentFrameImage = R.currentFrameImage;
            app.CurrentFrameSourceFile = char(string(R.sourceFile));
            app.CurrentFrameSourceFolder = char(string(R.sourceFolder));
            app.CurrentSourceType = char(string(R.sourceType));

            if isempty(R.sourceFrame)
                app.CurrentFrameIndex = NaN;
            else
                app.CurrentFrameIndex = double(R.sourceFrame);
            end

            app.tryReadCurrentFrameContextFromParent();

            if isStandalone
                app.UIFigure.Name = 'Image Reference Manager — no project';
            else
                app.UIFigure.Name = sprintf( ...
                    'Image Reference Manager — %s', app.SubjectID);
            end
            app.UIFigure.CloseRequestFcn = ...
                createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowStyle = 'modal';

            app.UITable.CellSelectionCallback = ...
                createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;

            app.configureInitialState();
            if isStandalone
                app.UITable.Data = table();
                app.UITable.Enable = 'off';
            else
                app.refreshReferenceTable();
                exportMenu = uicontextmenu(app.UIFigure);
                uimenu(exportMenu, 'Text', 'Export as File...', ...
                    'MenuSelectedFcn', @(~, ~) app.exportCurrentReference());
                app.UITable.ContextMenu = exportMenu;
            end
            app.refreshButtonState();

            if ~isStandalone && app.ProjectStore.IsReadOnly
                app.setStatusMessage( ...
                    ['Project validation failed. Image References are available ' ...
                    'read-only until the project is repaired.']);
            end

            if ~isempty(app.ParentApp)
                placeAppInsideCaller(app.ParentApp, app.UIFigure, 'center');
            end

            app.UIFigure.Visible = 'on';
            initializeUmitTheme(app.UIFigure);
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)

            %UIFIGURECLOSEREQUEST Close the manager without confirming selection.

            app.WasSelectionConfirmed = false;

            if strcmpi(app.AppMode, 'select')
                app.UIFigure.Visible = 'off';
                uiresume(app.UIFigure);
            else
                delete(app);
            end

        end

        % Button pushed function: CreatefromcurrentframeButton
        function CreatefromcurrentframeButtonPushed(app, event)
            %CREATEFROMCURRENTFRAMEBUTTONPUSHED Create a managed reference.

            if isempty(app.CurrentFrameImage)
                uialert(app.UIFigure, ...
                    ['No current frame image is available. Open this manager from ' ...
                    'DataViewer with loaded image data.'], ...
                    'No current frame', ...
                    'Icon', 'warning');
                return
            end

            metadata = app.promptCreateReferenceMetadata();

            if isempty(metadata)
                app.setStatusMessage('Image Reference creation cancelled.');
                return
            end

            try
                sourceFrame = [];
                if isfinite(app.CurrentFrameIndex) && app.CurrentFrameIndex >= 1
                    sourceFrame = app.CurrentFrameIndex;
                end

                sourceInfo = struct();
                sourceInfo.sourceFolder = app.CurrentFrameSourceFolder;
                sourceInfo.sourceFile = app.CurrentFrameSourceFile;
                sourceInfo.sourceFrame = sourceFrame;
                sourceInfo.sourceType = app.CurrentSourceType;

                if strcmpi(app.AppMode, 'standalone')
                    app.useStandaloneReference(app.CurrentFrameImage, metadata, sourceInfo);
                    return
                end

                resourceUUID = app.createManagedImageReference( ...
                    app.CurrentFrameImage, metadata, sourceInfo);
                resource = app.ProjectStore.getResource(resourceUUID);

                app.refreshReferenceTable();
                app.selectReferenceByUUID(resourceUUID);

                app.setStatusMessage(sprintf( ...
                    'Image Reference created: %s', resource.relativePath));

                uialert(app.UIFigure, ...
                    sprintf('Image Reference created:\n%s', ...
                    resource.absolutePath), ...
                    'Image Reference created', ...
                    'Icon', 'success');

            catch ME
                app.setStatusMessage(sprintf( ...
                    'Failed to create Image Reference: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Image Reference creation failed', ...
                    'Icon', 'error');
            end

        end

        % Button pushed function: UseSelectedButton
        function UseSelectedButtonPushed(app, event)
            %USESELECTEDBUTTONPUSHED Set active resource, return selection, or export.

            if strcmpi(app.AppMode, 'standalone')
                app.exportCurrentReference();
                return
            end

            if isempty(app.SelectedResourceUUID) || ...
                    isempty(app.SelectedImageReference)
                app.setStatusMessage('No valid Image Reference selected.');
                return
            end

            if strcmpi(app.AppMode, 'manage')
                if strcmp(app.SelectedResourceStatus, 'archived')
                    app.setStatusMessage( ...
                        'Restore the archived Image Reference before activating it.');
                    return
                end

                try
                    resourceUUID = app.SelectedResourceUUID;
                    app.ProjectStore.setActiveImageReference( ...
                        app.SubjectID, resourceUUID);

                    app.refreshReferenceTable();
                    app.selectReferenceByUUID(resourceUUID);
                    app.setStatusMessage( ...
                        'Selected Image Reference is now active.');
                catch ME
                    app.setStatusMessage(sprintf( ...
                        'Failed to activate Image Reference: %s', ME.message));
                    uialert(app.UIFigure, ...
                        getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                        'Set active failed', ...
                        'Icon', 'error');
                end
                return
            end

            app.OutputReferenceUUID = app.SelectedResource.uuid;
            app.OutputReferenceChecksum = app.SelectedResource.checksum;
            app.OutputReferenceFileRel = app.SelectedResource.relativePath;
            app.OutputReferenceFileAbs = app.SelectedResource.absolutePath;
            app.OutputImageReference = app.SelectedImageReference;
            app.WasSelectionConfirmed = true;

            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);

        end

        % Button pushed function: ArchiveButton
        function ArchiveButtonPushed(app, event)
            %ARCHIVEBUTTONPUSHED Archive or restore the selected managed resource.

            if ~strcmpi(app.AppMode, 'manage')
                app.setStatusMessage( ...
                    'Archive and Restore are available only in manager mode.');
                return
            end

            if isempty(app.SelectedResourceUUID)
                app.setStatusMessage('No valid Image Reference selected.');
                return
            end

            resourceUUID = app.SelectedResourceUUID;

            % Archived resources are restored as available. No confirmation is needed
            % because the operation is non-destructive and can be reversed by archiving
            % the resource again.
            if strcmp(app.SelectedResourceStatus, 'archived')
                try
                    app.ProjectStore.restoreResource(resourceUUID);

                    app.refreshReferenceTable();
                    app.selectReferenceByUUID(resourceUUID);

                    app.setStatusMessage( ...
                        'Image Reference restored as available.');

                catch ME
                    app.setStatusMessage(sprintf( ...
                        'Failed to restore Image Reference: %s', ME.message));

                    uialert(app.UIFigure, ...
                        getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                        'Restore failed', ...
                        'Icon', 'error');
                end

                return
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Archive the selected Image Reference?' newline newline ...
                'The resource remains registered and can be restored later.'], ...
                'Archive Image Reference', ...
                'Options', {'Archive', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'warning');

            if ~strcmpi(answer, 'Archive')
                return
            end

            try
                if strcmp(app.SelectedResourceStatus, 'active')
                    replacementUUID = app.promptArchiveReplacementUUID();

                    if isempty(replacementUUID)
                        app.setStatusMessage( ...
                            'Image Reference archive cancelled.');
                        return
                    end

                    app.ProjectStore.archiveResource( ...
                        resourceUUID, ...
                        'ReplacementUUID', replacementUUID);
                else
                    app.ProjectStore.archiveResource(resourceUUID);
                end

                app.refreshReferenceTable();
                app.selectReferenceByUUID(resourceUUID);

                app.setStatusMessage('Image Reference archived.');

            catch ME
                app.setStatusMessage(sprintf( ...
                    'Failed to archive Image Reference: %s', ME.message));

                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Archive failed', ...
                    'Icon', 'error');
            end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %UITABLECELLSELECTION Preview selected ImageReference row.

            if isempty(event.Indices)
                app.clearSelectedReference();
                return
            end

            rowIdx = event.Indices(1);

            try
                app.selectReferenceRow(rowIdx);
            catch ME
                app.clearSelectedReference();
                app.setStatusMessage(sprintf('Failed to load selected reference: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Image Reference load failed', ...
                    'Icon', 'warning');
            end


        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            %EDITBUTTONPUSHED Start or apply ImageReference edit mode.

            if isempty(app.SelectedImageReference)
                app.setStatusMessage('Select an Image Reference before editing.');
                return
            end

            if app.IsEditingReference
                app.applyReferenceEdit();
            else
                app.startReferenceEdit();
            end

        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            %RESTOREBUTTONPUSHED Cancel the current Image Reference edit.

            if ~app.IsEditingReference
                app.setStatusMessage( ...
                    'No active Image Reference edit to restore.');
                return
            end

            app.cancelReferenceEdit();

        end

        % Button pushed function: CreatefromfileButton
        function CreatefromfileButtonPushed(app, event)
            %CREATEFROMFILEBUTTONPUSHED Create a managed reference from a file.

            if ~ismember(lower(app.AppMode), {'manage', 'standalone'})
                app.setStatusMessage( ...
                    'Create from file is available only in manager mode.');
                return
            end

            [fileName, folderName] = uigetfile( ...
                { ...
                '*.tif;*.tiff;*.dat;*.umt;*.mat', ...
                'Supported reference sources (*.tif, *.tiff, *.dat, *.umt, *.mat)'; ...
                '*.tif;*.tiff', ...
                'TIFF image or stack (*.tif, *.tiff)'; ...
                '*.dat', ...
                'DAT image time series (*.dat)'; ...
                '*.umt', ...
                'UMT image data (*.umt)'; ...
                '*.mat', ...
                'MAT-file 2D numeric image (*.mat)'}, ...
                'Select image reference source');

            if isequal(fileName, 0)
                app.setStatusMessage('Create from file cancelled.');
                return
            end

            sourceFile = fullfile(folderName, fileName);

            try
                app.setStatusMessage('Reading image reference source...');
                drawnow limitrate

                [referenceImage, sourceInfo] = readImageReferenceSource( ...
                    sourceFile, 'maxFramesToAverage', 100);

                app.previewCandidateReferenceImage(referenceImage, sourceInfo);

                metadata = app.promptCreateReferenceMetadata();

                if isempty(metadata)
                    app.setStatusMessage('Image Reference creation cancelled.');
                    app.previewSelectedReference();
                    return
                end

                sourceInfo.processingNotes = app.combineProcessingNotes( ...
                    sourceInfo.processingNotes, metadata.processingNotes);
                metadata.processingNotes = sourceInfo.processingNotes;

                if strcmpi(app.AppMode, 'standalone')
                    app.useStandaloneReference(referenceImage, metadata, sourceInfo);
                    return
                end

                resourceUUID = app.createManagedImageReference( ...
                    referenceImage, metadata, sourceInfo);
                resource = app.ProjectStore.getResource(resourceUUID);

                app.refreshReferenceTable();
                app.selectReferenceByUUID(resourceUUID);

                app.setStatusMessage(sprintf( ...
                    'Image Reference created from file: %s', ...
                    resource.relativePath));

                uialert(app.UIFigure, ...
                    sprintf('Image Reference created:\n%s', ...
                    resource.absolutePath), ...
                    'Image Reference created', ...
                    'Icon', 'success');

            catch ME
                if strcmp(ME.identifier, ...
                        'readImageReferenceSource:SelectionCancelled')
                    app.setStatusMessage('Create from file cancelled.');
                    app.previewSelectedReference();
                    return
                end

                app.setStatusMessage(sprintf( ...
                    'Failed to create Image Reference from file: %s', ...
                    ME.message));

                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Create from file failed', ...
                    'Icon', 'error');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Reference Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectedImageReferencePanel = uipanel(app.GridLayout);
            app.SelectedImageReferencePanel.Title = 'Selected Image Reference';
            app.GridSelectedImageReferencePanel = uigridlayout(app.SelectedImageReferencePanel);
            app.UIAxes = uiaxes(app.GridSelectedImageReferencePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MetaDataTextArea = uitextarea(app.GridSelectedImageReferencePanel);
            app.MetaDataTextArea.Editable = 'off';
            app.EditButton = uibutton(app.GridSelectedImageReferencePanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RestoreButton = uibutton(app.GridSelectedImageReferencePanel, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Icon = 'icon_restore_state_Black.png';
            app.RestoreButton.Tooltip = {'Restore the original image and cancel the current edit'};
            app.RestoreButton.Text = '';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayoutBottom = uigridlayout(app.GridLayout);
            app.UseSelectedButton = uibutton(app.GridLayoutBottom, 'push');
            app.UseSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @UseSelectedButtonPushed, true);
            app.UseSelectedButton.Tooltip = {'Set active or return the selected Image Reference'};
            app.UseSelectedButton.Text = 'Use Selected';
            app.CloseButton = uibutton(app.GridLayoutBottom, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.ArchiveButton = uibutton(app.GridLayoutBottom, 'push');
            app.ArchiveButton.ButtonPushedFcn = createCallbackFcn(app, @ArchiveButtonPushed, true);
            app.ArchiveButton.Tooltip = {'Archive or restore the selected Image Reference'};
            app.ArchiveButton.Text = 'Archive';
            app.CreatefromfileButton = uibutton(app.GridLayoutBottom, 'push');
            app.CreatefromfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefromfileButtonPushed, true);
            app.CreatefromfileButton.Text = {'Create from'; 'file'};
            app.CreatefromcurrentframeButton = uibutton(app.GridLayoutBottom, 'push');
            app.CreatefromcurrentframeButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefromcurrentframeButtonPushed, true);
            app.CreatefromcurrentframeButton.Text = {'Create from current '; 'frame'};
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageReferenceManager(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end