classdef DataViewer_Coreg2Cams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        RunCoregistrationButton        matlab.ui.control.Button
        CoregistrationparametersPanel  matlab.ui.container.Panel
        UseenhancedcontrastCheckBox    matlab.ui.control.CheckBox
        FlipHorizontallyCheckBox       matlab.ui.control.CheckBox
        ViewmodeButtonGroup            matlab.ui.container.ButtonGroup
        MontageButton                  matlab.ui.control.RadioButton
        FalseColoroverlayButton        matlab.ui.control.RadioButton
        GeometrictransformationfilePanel  matlab.ui.container.Panel
        DiscardButton                  matlab.ui.control.Button
        RecoverfrombackupButton        matlab.ui.control.Button
        ShowinfolderButton             matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % Handle to DataViewer
        appMode % App mode: 'CoregistrationMode' or 'ResultsViewMode'.
        camImages % Sample images from both cameras.
    end
    
    properties (Access = public)
        filename = 'coreg2cam_tform' % Default name of the .mat file containing the tform and registered images.
        tformFile % Structure with info of all tform files from the Labeo folder:  organized in descending order of creation datetime.
        DataFolder % Folder containing the .dat files to be used to calculate the geometric transformation (Tform).
        LabeoFolder % " .../Documents/LabeoTech/Config/umIT/tformFiles"
    end
    
    methods (Access = {?DataViewer})
        
        function lookForTformFile(app)
            % This function looks for any tform files saved in
            % .../Documents/LabeoTech/Config/umIT/tformFiles/... folder.
            % If the folder doesn't exist, it creates one.
            % Output:
            %   tFormFile (struct): "dir" struct containing the info from all
            %   tform files found in the LabeoTech folder.
            
            % Find folder:
            if ispc
                % For windows:
                root = getenv('USERPROFILE');
            else
                % For unix:
                root = getenv('HOME');
            end
            % If the folder doesn't exist, create one:
            app.LabeoFolder = fullfile(root,'Documents', 'LabeoTech', 'Config','umIT','tformFiles');
            if ~isfolder(app.LabeoFolder)
                mkdir(app.LabeoFolder);
            end
            % Get the list of tform files:
            app.tformFile = dir(fullfile(app.LabeoFolder, [app.filename '.mat']));
        end
        
        function saveTform(app, tform, tformInfo)
            % saveTform - Save transformation data and registered images.
            %
            %   This function saves the provided transformation 'tform' and the
            %   'tformInfo' structure in the LabeoTech directory, creating a backup of
            %   the previous transformation file if it exists.
            %
            %   Inputs:
            %   - tform (affine2d): The transformation data to be saved.
            %   - tformInfo (struct): Extra parameters used to create the
            %       tform. 
            %
            
            
            % Create a backup filename based on the current filename and timestamp.
            bkpName = [app.filename '_' datestr(now(),'yyyymmddHHMMSS') '_bkp.mat'];
            
            if ~isempty(app.tformFile)
                % If a previous transformation file exists, create a backup of it.
                copyfile(fullfile(app.tformFile.folder, app.tformFile.name), fullfile(app.tformFile.folder, bkpName));
                % Overwrite the existing transformation file with new data.
                save(fullfile(app.tformFile.folder, app.tformFile.name), 'tform', 'tformInfo');
            else
                % If no previous transformation file exists, create a new one and
                % make a backup.
                save(fullfile(app.LabeoFolder, [app.filename '.mat']), 'tform', 'tformInfo');
                copyfile(fullfile(app.LabeoFolder, [app.filename '.mat']), fullfile(app.LabeoFolder, bkpName));
            end
        end
        
        function switchAppMode(app)
            % Switches the application between Coregistration Mode and Results View Mode.
            %
            % This function toggles the application mode between Coregistration Mode and Results View Mode.
            % In Coregistration Mode, it adjusts the UI to display coregistration-related information.
            % In Results View Mode, it adjusts the UI to display registered camera images and related controls.
            %
            
            app.loadCameraImages;  % Load composite images from each camera.
            app.ViewmodeButtonGroup.Visible = 'on';
            if strcmpi(app.appMode, 'CoregistrationMode')
                % In Coregistration Mode:
                
                % Hide ResultsViewMode panel:
                app.GridLayout2.RowHeight{4} = 0;
                %
                app.UseenhancedcontrastCheckBoxValueChanged('');
            else
                % In Results View Mode:
                
                % Hide CoregistrationMode panel and Execution button:
                app.GridLayout2.RowHeight([2,3]) = {0,0};                               
                % Check if there are backup files:
                bkpFiles = dir(fullfile(app.LabeoFolder, [app.filename '_*_bkp.mat']));
                if ~isempty(bkpFiles)
                    app.RecoverfrombackupButton.Enable = 'on';
                else
                    app.RecoverfrombackupButton.Enable = 'off';
                end                
                % Check if there is a tformFile:
                if isempty(app.tformFile)
                    set(app.DiscardButton,'Enable','off','Text','No File!')
                    app.ShowinfolderButton.Enable = 'off';
                else
                    set(app.DiscardButton,'Enable','on','Text','Discard')
                    app.ShowinfolderButton.Enable = 'on';
                end
                app.ViewmodeButtonGroupSelectionChanged('');
            end
            set(app.UIAxes, 'XTick',[],'YTick',[],'DataAspectRatio',[1 1 1], 'XLim',[1 size(app.camImages{1},2)], 'YLim',[1 size(app.camImages{1},1)], 'Colormap',gray)
            set(app.UIAxes2, 'XTick',[],'YTick',[],'DataAspectRatio',[1 1 1], 'XLim',[1 size(app.camImages{2},2)], 'YLim',[1 size(app.camImages{2},1)], 'Colormap',gray)            
        end
    end
    
    methods (Access = private)
        
        function loadCameraImages(app)
            % Create sample images from each camera used by coregistration algorithm.
            
            if ~isempty(app.camImages)
                return
            end
            
            % Load acquisition information from AcqInfos.mat file
            load(fullfile(app.DataFolder, 'AcqInfos.mat')); %#ok
            
            % Determine the number of illuminations
            NbIllum = sum(cellfun(@(X) contains(X, 'Illumination'), fieldnames(AcqInfoStream)));
            
            % Initialize lists for Camera 1 and Camera 2 files
            Cam1List = {};
            Cam2List = {};
            
            % Iterate through illuminations and organize files by camera and channel
            for ind = 1:NbIllum
                idx = AcqInfoStream.("Illumination" + int2str(ind)).CamIdx;
                chan = lower(AcqInfoStream.("Illumination" + int2str(ind)).Color);
                
                % Handle special cases for channel names
                if contains(chan, 'fluo')
                    chan = ['fluo_' chan(9:11)];
                end
                if contains(chan, 'amber')
                    chan = 'yellow';
                end
                
                % Add file to the appropriate camera list
                if idx == 1
                    Cam1List{end+1} = [chan '.dat']; %#ok
                else
                    Cam2List{end+1} = [chan '.dat']; %#ok
                end
            end
            clear NbIllum ind idx chan
            
            % Get data size from the first channel
            datMap = mapDatFile(fullfile(app.DataFolder, Cam1List{1}));
            cam1Img = zeros([length(Cam1List), size(datMap.Data.data, 1), size(datMap.Data.data, 2)], 'single');
            cam2Img = zeros([length(Cam2List), size(datMap.Data.data, 1), size(datMap.Data.data, 2)], 'single');
            clear datMap
            
            % Create a combined image from Camera 1
            for ii = 1:length(Cam1List)
                datMap = mapDatFile(fullfile(app.DataFolder, Cam1List{ii}));
                if size(datMap.Data.data,3) > 100
                    % Limit the data to 100 frames to speed up
                    % calculations:
                    frIndx = round(linspace(1,size(datMap.Data.data,3),100));
                    dat = mean(datMap.Data.data(:,:,frIndx),3);
                else
                    dat = mean(datMap.Data.data, 3);
                end
                
                % Normalize data if necessary
                if mean(dat(:)) > 1e3
                    dat = dat ./ mean(dat(:));
                else
                    dat = zeros(size(dat));
                end
                cam1Img(ii, :, :) = dat;
            end
            cam1Img = squeeze(sum(cam1Img, 1));
            clear datMap
            
            % Create a combined image from Camera 2
            for ii = 1:length(Cam2List)
                datMap = mapDatFile(fullfile(app.DataFolder, Cam2List{ii}));
                if size(datMap.Data.data,3) > 100
                    % Limit the data to 100 frames to speed up
                    % calculations:
                    frIndx = round(linspace(1,size(datMap.Data.data,3),100));
                    dat = mean(datMap.Data.data(:,:,frIndx),3);
                else
                    dat = mean(datMap.Data.data, 3);
                end
                
                % Normalize data if necessary
                if mean(dat(:)) > 1e3
                    dat = dat ./ mean(dat(:));
                else
                    dat = zeros(size(dat));
                end
                cam2Img(ii, :, :) = dat;
            end
            clear datMap
            cam2Img = squeeze(sum(cam2Img, 1));
            
            % Normalize images
            cam1Img = (cam1Img - min(cam1Img(:))) / (max(cam1Img(:)) - min(cam1Img(:)));
            cam2Img = (cam2Img - min(cam2Img(:))) / (max(cam2Img(:)) - min(cam2Img(:)));
            
            % Enhance image contrast if enabled
            if app.UseenhancedcontrastCheckBox.Value
                cam1Img = adapthisteq(cam1Img);
                cam2Img = adapthisteq(cam2Img);
            end
            
            % Store the combined camera images in a cell array
            app.camImages = {cam1Img, cam2Img, adapthisteq(cam1Img), adapthisteq(cam2Img)};
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, AppMode)
            %
            app.UIFigure.Visible = 'off';
            app.DataFolder = MainApp.dummyProt.SaveDir;
            app.lookForTformFile;
            if strcmpi(AppMode, 'CoregistrationMode') && ~isempty(app.tformFile)
                MainApp.tformFile = ''; % The tform file already exists. Dataviewer will then skip the coregistration.
                app.appMode = ''; % To supress uiconfirm in CloseReq callback;
                close(app.UIFigure);
                return
            end
            app.appMode = AppMode;
            app.MainApp = MainApp;
            app.UIFigure.Visible = 'on';
            app.switchAppMode;
%             app.ViewmodeButtonGroupSelectionChanged('');
%             if strcmpi(AppMode, 'CoregistrationMode')
%                 app.UseenhancedcontrastCheckBoxValueChanged('');
%             end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmpi(app.appMode, 'CoregistrationMode')
                answer = uiconfirm(app.UIFigure, 'Abort creation of Geometric transformation file?','Abort process?',"Options",{'Yes','No'},'DefaultOption',2,'Icon',"question");
                if strcmpi(answer,'no')
                    return
                end
            end
            delete(app)
        end

        % Button pushed function: ShowinfolderButton
        function ShowinfolderButtonPushed(app, event)
            % Opens OS File managers in folder containing the tform files
            % used to coregister the data.
            
            if ispc
                winopen(app.tformFile.folder);
            elseif ismac
                system(['open "' app.tformFile.folder '"']);
            elseif isunix
                system(['xdg-open "' app.tformFile.folder '"']);
            end
            
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            % Creates a new tform file using the .dat files in the RawDir
            % from the currently opened file in DataViewer.
            % If there is already an existing file, the file will be moved
            % as backup.
            answer = 'Yes';
            
            if ~isempty(app.tformFile)
                answer = uiconfirm(app.UIFigure, 'Remove current Tform file? A copy of this file will be stored as backup.', 'Delete Tform file?',"Options",{'Yes','No'},'DefaultOption',2);
            end
            
            if ~strcmpi(answer,'yes')
                return
            end
            
            dat = load(fullfile(app.tformFile.folder, app.tformFile.name));
            % Save tform file:
            app.saveTform(dat.tform, dat.tformInfo);
            delete(fullfile(app.tformFile.folder, app.tformFile.name));
            % Update tfom file info:
            app.lookForTformFile;
            % Update GUI and images:
            app.switchAppMode;
            %            
            uialert(app.UIFigure, 'Tform file erased! A new file will be created at the next data import operation.', 'Tform file discarded','Icon','info',"CloseFcn",'uiresume(gcbf)')            
            uiwait(app.UIFigure);            
        end

        % Button pushed function: RecoverfrombackupButton
        function RecoverfrombackupButtonPushed(app, event)
            % User picks one of the backup files to overwrite the current
            % tform file.
            
            % Confirm:
            if isempty(app.tformFile)
                msg = 'Create Tform file from a backup?';
            else
                msg = 'Replace the current Tform file with a backup?';
            end
                    
            answer = uiconfirm(app.UIFigure, msg, 'Reset Tform file?', 'Options',{'Yes','No'}, 'DefaultOption',2);
            if ~strcmpi(answer,'yes')                                
                return
            end
            
            % Choose backup file:
            bkpFile = uigetfile([app.filename '_*_bkp.mat'],'Select backup tform file',fullfile(app.LabeoFolder,[app.filename '_bkp.mat']));
            if bkpFile == 0               
                return
            end
            % Load backup data:
            try
                bkp = load(fullfile(app.LabeoFolder, bkpFile));
            catch
                uialert(app.UIFigure, ['Backup file "' bkpFile '" not found in folder "' app.LabeoFolder '"'],...
                    'Failed to load backup file','CloseFcn','uiresume(gcbf)','Icon',"error","Modal",true);
                uiwait(app.UIFigure);                 
                return
            end
            % Show a preview of the registration from the backup file:
            prevAns = false;
            f = uifigure('Name', 'Backup File registration preview (close fig. to continue)','CloseRequestFcn',@closeFig);
            ax =axes('Parent',f);
            imshowpair(bkp.tformInfo.RegisteredImages{2}, bkp.tformInfo.RegisteredImages{1},'falsecolor','Parent',ax);
            % Format backup file name to show backup date:
            str = regexpi(bkpFile,'coreg2cam_tform_(\d*)_bkp.mat','tokens');str = str{:}{:};
            str = ['Backup from ' datestr(datenum(str,'yyyymmddHHMMSS'), 'mmm dd yyyy (HH:MM:ss)')];
            title(ax,str);
            waitfor(f);
            if ~prevAns                 
                return
            end
            % Overwrite current tform file:
            save(fullfile(app.LabeoFolder,[app.filename '.mat']),'-struct','bkp');           
            %
            figure(app.UIFigure);
            uialert(app.UIFigure, 'Reset successfull! The TForm file will be used in the next data import operation.','Done', 'Icon','info', 'CloseFcn','uiresume(gcbf)');
            uiwait(app.UIFigure);

            %% Local callbacks
            function closeFig(src,~)
                % Preview figure close request callback
                Ans = uiconfirm(src, 'Use this backup file?', 'Confirm file selection', 'Options',{'Yes','No'}, 'DefaultOption',2);
                if strcmpi(Ans,'Yes')
                    prevAns = true;
                end
                delete(src);
            end
            
        end

        % Button pushed function: RunCoregistrationButton
        function RunCoregistrationButtonPushed(app, event)
            % This callback function is triggered when the user clicks the "Run Coregistration" button
            % in the application. It performs the coregistration process between two images, calculates
            % the geometric transformation, and handles any warnings or errors.
            %
            %
            % Description:
            %   1. Displays a progress dialog while calculating the geometric transformation.
            %   2. Calls the 'genTform2Cams' function to generate the transformation.
            %   3. Handles warnings and displays an alert if the transformation generation fails.
            %   4. Calls the 'saveTform' function to save the transformation and registered images.
            %
            % Example:
            %   RunCoregistrationButtonPushed(app, event);
            %
            % See also:
            %   genTform2Cams, saveTform
            
            % Display a progress dialog while calculating the transformation.
            w = uiprogressdlg(app.UIFigure, 'Message', 'Calculating geometric transformation...', 'Title', 'Please wait', 'Indeterminate', 'on');
            
            % Call the 'genTform2Cams' function to generate the transformation.
            [tform, tformInfo, warnmsg] = genTform2Cams(app.DataFolder, app.UseenhancedcontrastCheckBox.Value,app.FlipHorizontallyCheckBox.Value);
            
            % Update the progress dialog and close it.
            w.Message = 'Finished with calculation';
            pause(0.8);
            delete(w);
            
            % Handle warnings and display an alert if the transformation generation fails.
            if ~isempty(warnmsg)
                uialert(app.UIFigure, warnmsg, 'Failed to generate geometric transformation file!', 'Icon', 'warning', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(app.UIFigure);
                close(app.UIFigure);
                return;
            end
            
            % Save the geometric transformation.
            app.saveTform(tform, tformInfo);
            % Give to DataViewer the path to the TForm file:
            app.lookForTformFile;
            app.MainApp.tformFile = fullfile(app.tformFile.folder,app.tformFile.name);           
            % Close app
            delete(app);
        end

        % Selection changed function: ViewmodeButtonGroup
        function ViewmodeButtonGroupSelectionChanged(app, event)
            w = uiprogressdlg(app.UIFigure, 'Message','','Title','Please wait','Indeterminate',"on");
            if strcmpi(app.appMode, 'CoregistrationMode')
                suffix = 'Preview';
            else
                suffix = 'Registered';
            end
            if startsWith(app.ViewmodeButtonGroup.SelectedObject.Text, 'Falsecolor','IgnoreCase',true)
                % For FalseColor overlay view mode:
                % Hide Axes2
                app.GridLayout.ColumnWidth{3} = 0;      
                app.UIAxes2.Visible = 'off';
                % Show images in Axes 1:
                imshowpair(app.camImages{1},app.camImages{2},'falsecolor','Scaling','joint','Parent',app.UIAxes);drawnow;
                % Custom title:
                str= sprintf('\\color[rgb]{%.2f,%.2f,%.2f}Camera #1\\color{black} and \\color[rgb]{%.2f,%.2f,%.2f}Camera #2 \\color{black}%s', [0 0.7 0], [1 0 1],suffix);
                title(app.UIAxes, str, 'Interpreter','tex');
            else
                app.GridLayout.ColumnWidth{3}= '1x';
                app.UIAxes2.Visible = 'on';
                app.UIFigure.Position(3) = 960;
                % Show combined images from each camera:
                imagesc(app.UIAxes, app.camImages{1}); % Camera #1
                imagesc(app.UIAxes2, app.camImages{2}); % Camera #2                                       
                %
                title(app.UIAxes, ['Camera #1 ' suffix]);
                title(app.UIAxes2, ['Camera #2 ' suffix]);
                drawnow;
            end
            set(app.UIAxes, 'DataAspectRatio',[1 1 1],'Colormap',gray);
            set(app.UIAxes2, 'DataAspectRatio',[1 1 1],'Colormap',gray);
            %
            close(w);
        end

        % Value changed function: UseenhancedcontrastCheckBox
        function UseenhancedcontrastCheckBoxValueChanged(app, event)
            % Toggles the images' preview between enhanced contrast and
            % original data            
            app.camImages = app.camImages([3 4 1 2]);
            app.ViewmodeButtonGroupSelectionChanged('');
        end

        % Value changed function: FlipHorizontallyCheckBox
        function FlipHorizontallyCheckBoxValueChanged(app, event)
           
            % Flip image preview of camera #2
            app.camImages{2} = fliplr(app.camImages{2});
            app.camImages{4} = fliplr(app.camImages{4});
            app.ViewmodeButtonGroupSelectionChanged('');            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dual Camera coregistration';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.RunCoregistrationButton = uibutton(app.GridLayout2, 'push');
            app.RunCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCoregistrationButtonPushed, true);
            app.RunCoregistrationButton.Text = 'Run Coregistration';
            app.CoregistrationparametersPanel = uipanel(app.GridLayout2);
            app.CoregistrationparametersPanel.Title = 'Coregistration parameters';
            app.UseenhancedcontrastCheckBox = uicheckbox(app.CoregistrationparametersPanel);
            app.UseenhancedcontrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseenhancedcontrastCheckBoxValueChanged, true);
            app.UseenhancedcontrastCheckBox.Tooltip = {'Check this box to enhance the image contrast to improve coregistration'};
            app.UseenhancedcontrastCheckBox.Text = 'Use enhanced contrast';
            app.UseenhancedcontrastCheckBox.Value = true;
            app.FlipHorizontallyCheckBox = uicheckbox(app.CoregistrationparametersPanel);
            app.FlipHorizontallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipHorizontallyCheckBoxValueChanged, true);
            app.FlipHorizontallyCheckBox.Tooltip = {'Some Imaging systems will have a mirrored image in camera #2. Check this box if this is the case.'};
            app.FlipHorizontallyCheckBox.Text = 'Flip Horizontally';
            app.ViewmodeButtonGroup = uibuttongroup(app.GridLayout2);
            app.ViewmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewmodeButtonGroupSelectionChanged, true);
            app.ViewmodeButtonGroup.Title = 'View mode';
            app.MontageButton = uiradiobutton(app.ViewmodeButtonGroup);
            app.MontageButton.Text = 'Montage';
            app.MontageButton.Value = true;
            app.FalseColoroverlayButton = uiradiobutton(app.ViewmodeButtonGroup);
            app.FalseColoroverlayButton.Text = 'FalseColor overlay';
            app.GeometrictransformationfilePanel = uipanel(app.GridLayout2);
            app.GeometrictransformationfilePanel.Title = 'Geometric transformation file';
            app.DiscardButton = uibutton(app.GeometrictransformationfilePanel, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Tooltip = {'Creates a backup and erases the current Geometric transformation file.'};
            app.DiscardButton.Text = 'Discard';
            app.RecoverfrombackupButton = uibutton(app.GeometrictransformationfilePanel, 'push');
            app.RecoverfrombackupButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverfrombackupButtonPushed, true);
            app.RecoverfrombackupButton.Tooltip = {'Replace the curent Tform file with a backup Tform file.'};
            app.RecoverfrombackupButton.Text = {'Recover '; 'from backup'};
            app.ShowinfolderButton = uibutton(app.GeometrictransformationfilePanel, 'push');
            app.ShowinfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinfolderButtonPushed, true);
            app.ShowinfolderButton.Tooltip = {'Show Tform files location.'};
            app.ShowinfolderButton.Text = 'Show in folder';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_Coreg2Cams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end