classdef DataViewer_ROIopts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIfunctionoptionsUIFigure    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        AggregationfuncButtonGroup    matlab.ui.container.ButtonGroup
        maxButton                     matlab.ui.control.RadioButton
        minButton                     matlab.ui.control.RadioButton
        medianButton                  matlab.ui.control.RadioButton
        modeButton                    matlab.ui.control.RadioButton
        standarddevButton             matlab.ui.control.RadioButton
        sumButton                     matlab.ui.control.RadioButton
        noneButton                    matlab.ui.control.RadioButton
        meanButton                    matlab.ui.control.RadioButton
        CorrelationmatrixparamsPanel  matlab.ui.container.Panel
        AlgorithmButtonGroup          matlab.ui.container.ButtonGroup
        centroidvscentroidButton      matlab.ui.control.RadioButton
        centroidvsAggfuncButton       matlab.ui.control.RadioButton
        averagevsaverageButton        matlab.ui.control.RadioButton
        ZFishertransformCheckBox      matlab.ui.control.CheckBox
        GenSPCMapsCheckBox            matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Handle to DataViewer app.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, src)
            app.ROIfunctionoptionsUIFigure.Visible = 'off';
            movegui(app.ROIfunctionoptionsUIFigure, 'center');
            app.MainApp = MainApp;            
            if startsWith(src, 'extract', 'IgnoreCase',true)
                app.GridLayout.ColumnWidth{2} = 0;
                app.ROIfunctionoptionsUIFigure.Position(3) = 263;
            else
                app.noneButton.Visible = 'off';
                app.modeButton.Visible = 'off';
                app.sumButton.Visible = 'off';
                app.standarddevButton.Visible = 'off';
                app.meanButton.Value = true;
                arrayfun(@(x) set(x, 'Enable', 'off'), app.AggregationfuncButtonGroup.Children);
            end
            app.ROIfunctionoptionsUIFigure.Visible = 'on';            
        end

        % Selection changed function: AlgorithmButtonGroup
        function AlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmButtonGroup.SelectedObject;
%             disp('Button selected!')
            if contains(selectedButton.Text, 'agg','IgnoreCase', true)
                arrayfun(@(x) set(x, 'Enable', 'on'), app.AggregationfuncButtonGroup.Children);
            else
                arrayfun(@(x) set(x, 'Enable', 'off'), app.AggregationfuncButtonGroup.Children);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Saves selected parameters to MainApp's property and close the
            % app.
            nameMap = containers.Map({'mean','median','min','max','standard dev.','mode','sum','none'},...
                {'mean','median','min','max','std','mode','sum','none'});
            app.MainApp.roi_opts_params.aggfcn = nameMap(app.AggregationfuncButtonGroup.SelectedObject.Text);
            if app.GridLayout.ColumnWidth{2} ~=0
                switch app.AlgorithmButtonGroup.SelectedObject.Text
                    case 'centroid vs centroid'
                        app.MainApp.roi_opts_params.corrAlgo = 'centroid_vs_centroid';
                    case 'centroid vs Agg. func.'
                        app.MainApp.roi_opts_params.corrAlgo = 'centroid_vs_agg';
                    otherwise
                        app.MainApp.roi_opts_params.corrAlgo = 'avg_vs_avg';
                end
            end
            app.MainApp.roi_opts_params.b_applyZFisher = app.ZFishertransformCheckBox.Value;
            app.MainApp.roi_opts_params.b_genSPCMaps = app.GenSPCMapsCheckBox.Value;
            delete(app);
        end

        % Close request function: ROIfunctionoptionsUIFigure
        function CloseApp(app, event)
            app.MainApp.roi_opts_params = [];            
            disp('Operation cancelled by User');
            delete(app)                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIfunctionoptionsUIFigure = uifigure('Visible', 'off');
            app.ROIfunctionoptionsUIFigure.Name = 'ROI function options';
            app.ROIfunctionoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.GridLayout = uigridlayout(app.ROIfunctionoptionsUIFigure);
            app.AggregationfuncButtonGroup = uibuttongroup(app.GridLayout);
            app.AggregationfuncButtonGroup.Title = 'Aggregation func.';
            app.maxButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.maxButton.Text = 'max';
            app.minButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.minButton.Text = 'min';
            app.medianButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.medianButton.Text = 'median';
            app.modeButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.modeButton.Text = 'mode';
            app.standarddevButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.standarddevButton.Text = 'standard dev.';
            app.sumButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.sumButton.Text = 'sum';
            app.noneButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.noneButton.Text = 'none';
            app.meanButton = uiradiobutton(app.AggregationfuncButtonGroup);
            app.meanButton.Text = 'mean';
            app.meanButton.Value = true;
            app.CorrelationmatrixparamsPanel = uipanel(app.GridLayout);
            app.CorrelationmatrixparamsPanel.Title = 'Correlation matrix params';
            app.AlgorithmButtonGroup = uibuttongroup(app.CorrelationmatrixparamsPanel);
            app.AlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmButtonGroupSelectionChanged, true);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.centroidvscentroidButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.centroidvscentroidButton.Text = 'centroid vs centroid';
            app.centroidvscentroidButton.Value = true;
            app.centroidvsAggfuncButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.centroidvsAggfuncButton.Text = 'centroid vs Agg. func.';
            app.averagevsaverageButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.averagevsaverageButton.Text = 'average vs average';
            app.ZFishertransformCheckBox = uicheckbox(app.CorrelationmatrixparamsPanel);
            app.ZFishertransformCheckBox.Tooltip = {'Apply Z-Fisher transformation to Pearson''s rho values.'};
            app.ZFishertransformCheckBox.Text = 'Z-Fisher transform';
            app.GenSPCMapsCheckBox = uicheckbox(app.CorrelationmatrixparamsPanel);
            app.GenSPCMapsCheckBox.Text = 'Gen. SPCMaps';
            app.Panel = uipanel(app.GridLayout);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.ROIfunctionoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_ROIopts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIfunctionoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIfunctionoptionsUIFigure)
        end
    end
end