classdef DataViewer_TIFimportMetaData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        OkButton                   matlab.ui.control.Button
        ResetParamsButton          matlab.ui.control.Button
        GridLayout3                matlab.ui.container.GridLayout
        FileToImportLabel          matlab.ui.control.EditField
        SelectButton               matlab.ui.control.Button
        FrameRateHzLabel           matlab.ui.control.EditField
        FrameRateHzField           matlab.ui.control.NumericEditField
        ExposureTimeLabel          matlab.ui.control.EditField
        ExposureTimeField          matlab.ui.control.NumericEditField
        IlluminationColorLabel     matlab.ui.control.EditField
        IlluminationColorDropDown  matlab.ui.control.DropDown
        AddNewParamsLabel          matlab.ui.control.EditField
        AddNewParamsButton         matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        GridLayout4                matlab.ui.container.GridLayout
        AddnewfileButton           matlab.ui.control.Button
        RemoveFileButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        tifStruct_original = struct('filename', '', 'FrameRateHz',0,'ExposureMsec',0,'IlluminationColor','Red');% Structure with minimal metada from TIFs.\
        tifInfo cell
        RawFolder = pwd ;%Raw Folder where the .TIFs are located.
        NewComponents = struct()
        MainApp % Handle to DataViewer
    end
    
    methods (Access = private)
        % Custom Callbacks for new Params
        function newParamLabelValueChanged(~,src, ~)
            % Get the user-entered text from the triggered edit field
            rawText = src.Value;
            
            % Convert text to a valid MATLAB variable name
            validVarName = matlab.lang.makeValidName(rawText);
            
            % Update the edit field with the corrected variable name
            src.Value = validVarName;
        end
        function textOutput = textifyParams(app)
            % Initialize an empty string to store the formatted output
            textOutput = "";
            
            % Get all field names from app.tifInfo
            fieldNames = fieldnames(app.tifInfo{end});
            
            % Loop through each field and format its value
            for i = 1:numel(fieldNames)
                fieldName = fieldNames{i};
                fieldValue = app.tifInfo{end}.(fieldName);
                
                % Convert numeric values to text
                if isnumeric(fieldValue)
                    fieldValue = num2str(fieldValue);
                end
                
                % Append formatted field to the output string
                
                textOutput = sprintf('%s%s : %s\n', textOutput, fieldName, fieldValue);
            end
        end
        function appendParams(app)
            
            if isempty(app.tifInfo)
                app.tifInfo{1} = app.tifStruct_original;
            else
                if strcmp(app.tifInfo{end}.filename, app.SelectButton.Text)
                    return;
                end
                app.tifInfo{end+1} = app.tifStruct_original;
            end
            
            app.tifInfo{end}.filename = app.SelectButton.Text;
            app.tifInfo{end}.FrameRateHz = app.FrameRateHzField.Value;
            app.tifInfo{end}.ExposureMsec = app.ExposureTimeField.Value;
            app.tifInfo{end}.IlluminationColor = app.IlluminationColorDropDown.Value;
            
            % Loop through variable parameters and append them to app.tifInfo
            fnComp = fieldnames(app.NewComponents);
            for ii = 1:length(fnComp)
                propName = fnComp{ii};
                
                % Check for dynamically created parameter labels
                if startsWith(propName, "newParamLabel")
                    paramLabel = app.NewComponents.(propName).Value;
                    
                    % Find the corresponding parameter field
                    paramFieldName = strrep(propName, "newParamLabel", "newParamField");
                    idx = startsWith(fnComp, paramFieldName);
                    paramValue = app.NewComponents.(fnComp{idx}).Value;
                    
                    % Skip iteration if paramValue is empty
                    if isempty(paramValue)                        
                        continue;
                    end
                    
                    % Convert numeric values to numbers
                    if isnumeric(paramValue) || ~isempty(str2num(paramValue))
                        paramValue = str2num(paramValue);
                    end
                    
                    % Append to tifStruct
                    app.tifInfo{end}.(paramLabel) = paramValue;
                end
            end
        end
        
        
        function generateJSONfile(app)
            % Generates the info.json file necessary for import of TIFs.
            out.Tiffiles = app.tifInfo;
            textOut = jsonencode(out);
            fid = fopen(fullfile(app.RawFolder,'info.json'),'w');
            fprintf(fid,'%s',textOut);
            fclose(fid);
            fprintf('File info.json created in folder "%s"\n',app.RawFolder);    
            % Update RawFolder in DataViewer
            app.MainApp.dummyProt.MainDir{1} = app.RawFolder;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Open file selection dialog for .TIF files
            [file, folder] = uigetfile(fullfile(app.RawFolder, '*.TIF'), 'Select a TIF File');
            
            % Check if the user selected a file
            if isequal(file, 0)
                figure(app.UIFigure);
                return;
            end


            % Check if an existing file is already set in app.tifInfo
            if ~isempty(app.tifInfo) && ~isempty(app.tifInfo{end}.filename)
                existingFilenames = cellfun(@(x) x.filename,app.tifInfo,'UniformOutput',false);
                if ismember(file,existingFilenames)
                    figure(app.UIFigure);
                    uialert(app.UIFigure,'This file is aready set! To edit, remove it from the table and select again.','File already set',"Icon",'warning','CloseFcn','uiresume(gcbf)')
                    uiwait(app.UIFigure);
                    return
                end
                
                % Define regular expression for extracting the prefix
                exp = '^(.*?)([-_]\d+)?\.tif$';
                
                % Extract the prefix of the selected file
                newPrefix = regexp(file, exp, 'tokens', 'once', 'ignorecase');
                newPrefix = newPrefix{1};
                % Extract all filenames from app.tifInfo
                existingPrefixes = cellfun(@(s) regexp(s.filename, exp, 'tokens', 'once', 'ignorecase'), app.tifInfo, 'UniformOutput', false);
                
                % Extract the first match from each result
                existingPrefixes = cellfun(@(x) x{1}, existingPrefixes, 'UniformOutput', false);
                
                % Compare prefixes and raise a warning if they match
                if any(strcmp(existingPrefixes, newPrefix))
                    figure(app.UIFigure);
                    selection = uiconfirm(app.UIFigure, ...
                        sprintf('The selected file may be part of a multi-file sequence. If so, do not select it. The import function will automatically import multi-file sequences.\nDo you want to continue?'), ...
                        'Warning', 'Options', {'Ignore & Continue', 'Cancel'}, ...
                        'DefaultOption', 'Cancel', 'Icon', 'warning');
                    
                    if strcmp(selection, 'Cancel')
                        return; % Exit without updating
                    end
                end
            end
            
            % Store the selected folder in app.RawFolder
            app.RawFolder = folder;
            
            % Modify button properties
            app.SelectButton.Text = file;
            app.SelectButton.Enable = 'off';
            app.SelectButton.BackgroundColor = [0.8, 1, 0.8];
            %
            app.OkButton.Enable = 'on';
            app.AddnewfileButton.Enable = 'off';
            figure(app.UIFigure);
        end

        % Value changed function: IlluminationColorDropDown
        function IlluminationColorDropDownValueChanged(app, event)
            % Get selected value from the dropdown menu
            selectedValue = app.IlluminationColorDropDown.Value;
            
            % Check if the user selected "Other"
            if strcmpi(selectedValue, "other")
                % Hide main figure during input dialog execution
                app.UIFigure.Visible = 'off';
                
                % Open input dialog for user to enter a new string
                newColor = inputdlg('Enter a new illumination color:', 'Custom Input', [1 50]);
                
                % Make the main figure visible again
                app.UIFigure.Visible = 'on';
                
                % Check if user canceled the input dialog
                if isempty(newColor)
                    return; % Exit callback without changes
                end
                
                % Clean up the user input by removing extra whitespaces
                newColor = strtrim(newColor{1});
                
                % Add new color to dropdown items if it's not empty
                if ~isempty(newColor)
                    app.IlluminationColorDropDown.Items = [app.IlluminationColorDropDown.Items(1:end-1), newColor, app.IlluminationColorDropDown.Items(end)];
                    app.IlluminationColorDropDown.Value = newColor; % Set newly added value as selected
                end
            end
            
        end

        % Button pushed function: AddNewParamsButton
        function AddNewParamsButtonPushed(app, event)
            % Get current row count of the GridLayout
            numRows = size(app.GridLayout3.RowHeight, 2);
            
            % Append a new row at the bottom
            app.GridLayout3.RowHeight = [app.GridLayout3.RowHeight, 40];
            
            % Move AddNewParamsLabel and AddNewParamsButton to the new last row
            app.AddNewParamsLabel.Layout.Row = numRows + 1;
            app.AddNewParamsButton.Layout.Row = numRows + 1;
            
            % Determine unique variable names for the new parameter fields
            fnNewComp = fieldnames(app.NewComponents);
            paramCount = sum(contains(fnNewComp, "newParamLabel_"));
            paramLabelName = sprintf("newParamLabel_%d", paramCount + 1);
            paramFieldName = sprintf("newParamField_%d", paramCount + 1);
            
            % Create new text edit field for parameter label
            app.NewComponents.(paramLabelName) = uieditfield(app.GridLayout3, 'text', 'ValueChangedFcn',@app.newParamLabelValueChanged);
            app.NewComponents.(paramLabelName).Layout.Row = numRows;
            app.NewComponents.(paramLabelName).Layout.Column = 1;
            
            % Create new text edit field for parameter value
            app.NewComponents.(paramFieldName) = uieditfield(app.GridLayout3, 'text');
            app.NewComponents.(paramFieldName).Layout.Row = numRows;
            app.NewComponents.(paramFieldName).Layout.Column = 2;
            
            % Update app.UIFigure height to accommodate the new row
            app.UIFigure.Position(4) = app.UIFigure.Position(4) + 40 + app.GridLayout3.ColumnSpacing;
        end

        % Button pushed function: ResetParamsButton
        function ResetParamsButtonPushed(app, event)
            % Ask user to confirm reset operation
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to reset settings?', 'Confirm Reset', ...
                'Options', {'Yes', 'Cancel'}, 'DefaultOption', 'Cancel', 'Icon', 'warning');
            
            % Cancel operation if user chooses not to reset
            if strcmp(selection, 'Cancel')
                return;
            end
            
            % Identify and delete all new parameter objects
            deletedRows = 0; % Counter for removed rows
            fnNewComp = fieldnames(app.NewComponents);
            if ~isempty(fnNewComp)
                deletedRows = length(fieldnames(app.NewComponents))/2;
                cellfun(@(x) delete(app.NewComponents.(x)), fnNewComp);
                app.NewComponents = struct();
            end
            
            % Calculate the total height reduction
            rowHeightReduction = deletedRows * (40 + app.GridLayout3.ColumnSpacing);
            
            % Reset the layout row count and reposition AddNewParamsLabel and Button
            app.AddNewParamsLabel.Layout.Row = 5;
            app.AddNewParamsButton.Layout.Row = 5;
            app.GridLayout3.RowHeight = repmat({40}, 1, 5);
            % Adjust the figure height
            app.UIFigure.Position(4) = app.UIFigure.Position(4) - rowHeightReduction;
            
            % Reset specified field values
            app.FrameRateHzField.Value = 10;
            app.ExposureTimeField.Value = 1;
            app.IlluminationColorDropDown.Value = "Red";
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Change background color of OK button to light green
            
            if strcmpi(app.SelectButton.Enable, 'on');return;end
            
            % Check current text of the button
            if strcmp(app.OkButton.Text, "Ok")
                % Check for duplicate illumination colors:
                existingColors = cellfun(@(x) x.IlluminationColor, app.tifInfo, 'UniformOutput',false);
                if ismember(app.IlluminationColorDropDown.Value,  existingColors)
                    uialert(app.UIFigure, ['Cannot add this file! There is already an Illumination Color set as ' app.IlluminationColorDropDown.Value '!'],'Failed to add file',"Icon","error",'Modal',true);
                    return
                end
                                
                % Get all field names from the structure
                fnComp = fieldnames(app.NewComponents);
                fnComp = fnComp(startsWith(fnComp, 'newParamField_'));
                emptyFields = sum(cellfun(@(x) any(isempty(app.NewComponents.(x).Value)),fnComp));
                                
                % If there are empty fields, raise a warning
                if emptyFields
                    warningMsg = sprintf('There are emtpy parameteres. They will be ignored. Continue?');
                    answer = uiconfirm(app.UIFigure, warningMsg, 'Warning', 'Options',{'Ignore Empty Fields and Continue','Cancel'},'DefaultOption','Cancel');
                    if strcmpi(answer,'cancel')
                        return
                    end                    
                end
                
                
                % Change button text to "Save and Close"
                app.OkButton.Text = "Save and Close";
                
                % Hide the GridLayout
                app.GridLayout3.Visible = 'off';
                % Update the tifInfo cell array:
                app.appendParams()
                % Call function to process parameters
                Params = app.textifyParams();
                app.ResetParamsButton.Enable = 'off';
                % Append filename and Params to the table
                newRow = {false, app.tifInfo{end}.filename, Params};
                app.UITable.Data = [app.UITable.Data; newRow];
                app.UITable.ColumnFormat = {'logical', 'char', 'char'};
                % Enable removing
                app.RemoveFileButton.Enable = 'on';
                % Enable Add new file
                app.AddnewfileButton.Enable = 'on';
                app.OkButton.BackgroundColor = [0.8, 1, 0.8];
            elseif strcmp(app.OkButton.Text, "Save and Close")
                if isempty(app.tifInfo)
                    answer = uiconfirm(app.UIFigure, 'There are no files set! TIF import will be cancelled', 'No TIF files selected','Options',{'Go back','Cancel data Import'},'DefaultOption',1,"Icon",'warning');
                    if strcmpi(answer,'go back')
                        return
                    end
                end
                % Create info.json file
                app.generateJSONfile;
                % Close the app
                delete(app.UIFigure);
            end
            
        end

        % Button pushed function: AddnewfileButton
        function AddnewfileButtonPushed(app, event)
            
            if strcmpi(app.SelectButton.Enable,'on');return;end
            % Set the background color and text of the OK button
            app.OkButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.OkButton.Text = "Ok";
            app.OkButton.Enable = 'off';
            % Make the GridLayout visible
            app.GridLayout3.Visible = 'on';
            
            % Reset SelectButton properties
            app.SelectButton.Text = "Select";
            app.SelectButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.SelectButton.Enable = 'on';
            %
            app.AddnewfileButton.Enable = 'off';
            app.ResetParamsButton.Enable = 'on';
        end

        % Button pushed function: RemoveFileButton
        function RemoveFileButtonPushed(app, event)
            
            % Get the table data
            tableData = app.UITable.Data;
            if isempty(tableData);return;end
            % Find selected rows (column 1 is TRUE)
            selectedRows = [tableData{:,1}];
            
            % Check if any rows are selected
            if ~any(selectedRows)
                uialert(app.UIFigure, 'You need to select at least one element from the table.', 'Warning');
                return;
            end
            
            % Retrieve filenames from selected rows
            selectedFilenames = tableData(selectedRows, 2);
            
            % Ask user for confirmation before removing files
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to remove the selected files?', 'Confirm Removal', ...
                'Options', {'Yes', 'Cancel'}, 'DefaultOption', 'Cancel', 'Icon', 'warning');
            
            % Cancel operation if user selects "Cancel"
            if strcmp(selection, 'Cancel')
                return;
            end
            
            % Find indices of matching filenames in app.tifInfo
            indicesToRemove = cellfun(@(s) ismember(s.filename, selectedFilenames), app.tifInfo);
            
            % Remove the matching entries from app.tifInfo
            app.tifInfo(indicesToRemove) = [];
            
            % Remove selected rows from UITable
            app.UITable.Data(selectedRows, :) = [];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            answer = uiconfirm(app.UIFigure, 'Are you sure to cancel import of TIFs?','Cancel import','Options',{'Yes','No, go back'},'DefaultOption',2);
            if ~strcmpi(answer,'yes')
                return;          
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OkButton = uibutton(app.GridLayout2, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Tooltip = {'Save parameters to a .JSON file'};
            app.OkButton.Text = 'Ok';
            app.ResetParamsButton = uibutton(app.GridLayout2, 'push');
            app.ResetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParamsButtonPushed, true);
            app.ResetParamsButton.Tooltip = {'Reset Parameters to default.'};
            app.ResetParamsButton.Text = {'Reset Params'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.FileToImportLabel = uieditfield(app.GridLayout3, 'text');
            app.FileToImportLabel.Editable = 'off';
            app.FileToImportLabel.Value = 'File to import';
            app.SelectButton = uibutton(app.GridLayout3, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Tooltip = {'Click to select a .TIF'};
            app.SelectButton.Text = 'Select';
            app.FrameRateHzLabel = uieditfield(app.GridLayout3, 'text');
            app.FrameRateHzLabel.Editable = 'off';
            app.FrameRateHzLabel.Tooltip = {'Acquisition sample rate in Hz'};
            app.FrameRateHzLabel.Value = 'Frame Rate (Hz)';
            app.FrameRateHzField = uieditfield(app.GridLayout3, 'numeric');
            app.FrameRateHzField.Limits = [2.22044604925031e-16 Inf];
            app.FrameRateHzField.Tooltip = {'Frame Rate in Hz.Positive number'};
            app.FrameRateHzField.Value = 10;
            app.ExposureTimeLabel = uieditfield(app.GridLayout3, 'text');
            app.ExposureTimeLabel.Editable = 'off';
            app.ExposureTimeLabel.Tooltip = {'Exposure time (in milliseconds)'};
            app.ExposureTimeLabel.Value = 'Exposure time (ms)';
            app.ExposureTimeField = uieditfield(app.GridLayout3, 'numeric');
            app.ExposureTimeField.Limits = [2.22044604925031e-16 Inf];
            app.ExposureTimeField.Tooltip = {'Exposure time (in milliseconds). Positive number.'};
            app.ExposureTimeField.Value = 1;
            app.IlluminationColorLabel = uieditfield(app.GridLayout3, 'text');
            app.IlluminationColorLabel.Editable = 'off';
            app.IlluminationColorLabel.Value = 'Illumination Color';
            app.IlluminationColorDropDown = uidropdown(app.GridLayout3);
            app.IlluminationColorDropDown.Items = {'Red', ' Green', ' Fluo', ' Fluo_475', ' Fluo_567', 'Speckle', 'Other'};
            app.IlluminationColorDropDown.ValueChangedFcn = createCallbackFcn(app, @IlluminationColorDropDownValueChanged, true);
            app.IlluminationColorDropDown.Value = 'Red';
            app.AddNewParamsLabel = uieditfield(app.GridLayout3, 'text');
            app.AddNewParamsLabel.Editable = 'off';
            app.AddNewParamsLabel.Enable = 'off';
            app.AddNewParamsLabel.Value = 'Add New Parameter';
            app.AddNewParamsButton = uibutton(app.GridLayout3, 'push');
            app.AddNewParamsButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewParamsButtonPushed, true);
            app.AddNewParamsButton.Icon = 'icon_plusBW.png';
            app.AddNewParamsButton.Tooltip = {'Add a new parameter'};
            app.AddNewParamsButton.Text = '';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {''; 'Files Set'; 'Params'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false];
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.AddnewfileButton = uibutton(app.GridLayout4, 'push');
            app.AddnewfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewfileButtonPushed, true);
            app.AddnewfileButton.Tooltip = {'Set another TIF.'};
            app.AddnewfileButton.Text = 'Add new file';
            app.RemoveFileButton = uibutton(app.GridLayout4, 'push');
            app.RemoveFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFileButtonPushed, true);
            app.RemoveFileButton.Enable = 'off';
            app.RemoveFileButton.Tooltip = {'Remove t'};
            app.RemoveFileButton.Text = {'Remove File'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_TIFimportMetaData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end