classdef DataViewer_evntManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        EventdetectionparametersPanel   matlab.ui.container.Panel
        mininterstimsSpinnerLabel       matlab.ui.control.Label
        mininterstimsSpinner            matlab.ui.control.Spinner
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        TriggertypeDropDownLabel        matlab.ui.control.Label
        TriggertypeDropDown             matlab.ui.control.DropDown
        LowPassfilterEditFieldLabel     matlab.ui.control.Label
        LowPassfilterEditField          matlab.ui.control.NumericEditField
        ConditionlistPanel              matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        CondFileBtn                     matlab.ui.control.Button
        FileLabel                       matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        DetectButton                    matlab.ui.control.Button
        SaveeventsButton                matlab.ui.control.Button
        SelectchannelsPanel             matlab.ui.container.Panel
        ChanGridLayout                  matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        SelectcolumnsPanel              matlab.ui.container.Panel
        UpdatelistButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ColGridLayout                   matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        EventinfoPanel                  matlab.ui.container.Panel
        NumberofconditionsLabel         matlab.ui.control.Label
        NumberofconditionsEditField     matlab.ui.control.NumericEditField
        DetectionthresholdEditFieldLabel  matlab.ui.control.Label
        DetectionthresholdEditField     matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        AvgtriallengthEditField_2Label  matlab.ui.control.Label
        AvgtriallengthEditField         matlab.ui.control.EditField
        AvgintertriallengthEditFieldLabel  matlab.ui.control.Label
        AvgintertriallengthEditField    matlab.ui.control.EditField
        RecordinglengthEditFieldLabel   matlab.ui.control.Label
        RecordinglengthEditField        matlab.ui.control.NumericEditField
        EventsdetectedEditFieldLabel    matlab.ui.control.Label
        EventsdetectedEditField         matlab.ui.control.NumericEditField
        SettrialintervalPanel           matlab.ui.container.Panel
        preeventtimeEditFieldLabel      matlab.ui.control.Label
        preeventtimeEditField           matlab.ui.control.NumericEditField
        posteventtimeEditFieldLabel     matlab.ui.control.Label
        posteventtimeEditField          matlab.ui.control.NumericEditField
        sLabel_4                        matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        OkButton                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        SaveFolder % Folder where to save the "events.mat" file
        RawFolder % Folder containing the ai.bin files
        evObj % EventsManager class instance.
        fH  % plot figure handle array.
        previousParams % Values of the main properties from the EventsManager object at startup. This will be used to ask the user to save changes to the events.mat file or not.
    end
    
    methods (Access = private)
        
        function showStats(app)
            % Updates fields of "Trigger info" panel.
            
            if isempty(app.evObj.timestamps)
                % Disable stats panel if trigger detection failed
                app.RecordinglengthEditField.Value = 0;
                app.DetectionthresholdEditField.Value = 0;
                app.EventsdetectedEditField.Value = 0;
                app.NumberofconditionsEditField.Value = 0;
                app.AvgtriallengthEditField.Value = '0 (0/0)';
                app.AvgintertriallengthEditField.Value = '0 (0/0)';
                arrayfun(@(x) set(x, 'Enable','off'), app.EventinfoPanel.Children);
                app.SettrialintervalPanel.Visible = 'off';
                return
            end
            
            app.RecordinglengthEditField.Value = double(size(app.evObj.AnalogIN,1)/app.evObj.sr);
            if isnumeric(app.evObj.trigThr)
                app.DetectionthresholdEditField.Value = double(app.evObj.trigThr);
            end
            app.EventsdetectedEditField.Value = double(sum(app.evObj.state));
            app.NumberofconditionsEditField.Value = length(app.evObj.eventNameList);
            
            deltaT = [diff(app.evObj.timestamps); nan];
            trialLen = mean(deltaT(app.evObj.state == 1), 'omitnan');
            app.AvgtriallengthEditField.Value = sprintf('%0.2f (%0.2f/%0.2f)',trialLen,min(deltaT(app.evObj.state == 1),[],'all'), max(deltaT(app.evObj.state == 1),[],'all'));
            
            interTrialLen = mean(deltaT(app.evObj.state == 0), 'omitnan');
            if isnan(interTrialLen)
                app.AvgintertriallengthEditField.Value = '0 (0/0)';
            else
                app.AvgintertriallengthEditField.Value = sprintf('%0.2f (%0.2f/%0.2f)',interTrialLen,min(deltaT(app.evObj.state == 0),[],'all'), max(deltaT(app.evObj.state == 0),[],'all'));
            end
            % Re-enable elements:
            arrayfun(@(x) set(x, 'Enable','on'), app.EventinfoPanel.Children);
            % Update trial interval values:
            app.preeventtimeEditField.Value = double(round(app.evObj.trialInterval(1),3));
            app.posteventtimeEditField.Value = double(round(app.evObj.trialInterval(2),3));
            app.SettrialintervalPanel.Visible = 'on';
        end
        
        function showPlots(app)
            % Displays the plots of the selected channels.
            
            % Delete existing plots
            if ~isempty(app.fH) & isvalid(app.fH)%#ok
                close(app.fH);
            end
            
            if isempty(app.evObj.timestamps)
                % PLot All channels when a trigger detection fails:
                app.fH = app.evObj.plot({app.ChanGridLayout.Children.Text});
            else
                % Create plots for the selected channels:
                app.fH = app.evObj.plot(app.evObj.trigChanName);
            end
            % Put plot figures next to the main app.
            cascadeArr = 10*(0:length(app.fH)-1);
            for ii = 1:length(app.fH)
                app.fH(ii).Position(1) = sum([app.UIFigure.Position([1 3]),cascadeArr(ii),5]);
                app.fH(ii).Position(2) = app.UIFigure.OuterPosition(4) + app.UIFigure.OuterPosition(2) - app.fH(ii).Position(4)-50 - cascadeArr(ii);
            end
            
        end
        
        function updateAIchannelList(app)
            % Updates the list of channels in the "Select Channels" panel.
            % Camera triggers excluded.
            
            if isempty(app.evObj) || ~isvalid(app.evObj)
                return
            end
            % Populate Panel with list of available AnalogIN channels:
            chanList = setdiff(app.evObj.AIChanList, {'CameraTrig', 'CameraTrig2'}, 'stable') ; % Hide Camera triggers from User.
            app.ChanGridLayout.RowHeight = repmat({25},size(chanList));
            for ii = 1:length(chanList)
                c = uicheckbox('Parent',app.ChanGridLayout, 'Text', chanList{ii},...
                    'Value', any(strcmpi(chanList{ii}, app.evObj.trigChanName)), 'ValueChangedFcn', @app.lockCheckBox);
                c.Layout.Row = ii;
            end
            if ~any([app.ChanGridLayout.Children.Value])
                app.ChanGridLayout.Children(1).Value = true; % Select first channel on the list.
            end
            % Show panel
            app.GridLayout.ColumnWidth{2} = 150;
            app.UIFigure.Position(3) = app.UIFigure.Position(3) + 150;
            
        end
        function lockCheckBox(~,src,~)
            % This callback avoids the unchecking of the last checked box.
            idxState = arrayfun(@(x) x.Value, src.Parent.Children);
            if ~any(idxState)
                src.Value = 1;
            end
        end
        
        function readConditionList(app, evFile, colNames)
            % Reads the list of conditions in the Selected condition file.
            % Returs TRUE, if the conditions were read and pass all
            % validations from the EventsManager class (e.g., equal number
            % of conditions and timestamps).
            if strcmpi(app.evObj.EventFileParseMethod, 'none')
                app.showPlots;
                app.showStats;
                return
            end
            w = uiprogressdlg(app.UIFigure, 'Message','Updating Condition IDs...', 'Indeterminate','on');
            try
                b_OK = app.evObj.readConditionFile(evFile,'CSVcols', colNames);
                if b_OK
                    [~,file,ext] = fileparts(app.evObj.EventFileName);
                    set(app.CondFileBtn, 'Text', [file ext],'Tooltip',app.evObj.EventFileName);
                    app.showPlots;
                    app.showStats;
                    delete(w)
                else
                    delete(w)
                    uialert(app.UIFigure,lastwarn,'Failed to update condition list',"Modal",true,'Icon',"warning", 'CloseFcn','uiresume(gcbf)');
                    uiwait(app.UIFigure);
                    app.TypeDropDown.Value = 'none';
                    app.TypeDropDownValueChanged;
                end
            catch ME
                delete(w)
                uialert(app.UIFigure,getReport(ME, 'basic', 'hyperlinks','off'),'Failed to update condition list',"Modal",true,'Icon',"error", 'CloseFcn','uiresume(gcbf)');
                uiwait(app.UIFigure);
                % reset Condition list panel elements:
                app.TypeDropDown.Value = 'none';
                app.TypeDropDownValueChanged;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            %
            app.UIFigure.Visible = 'off';
            % Hide the two columns in the middle:
            app.GridLayout.ColumnWidth([2,3]) = {0,0};
            app.UIFigure.Position(3) = app.UIFigure.Position(3) - 320; % Resize figure;
            movegui(app.UIFigure,'northwest');
            %
            app.SaveFolder = MainApp.dummyProt.SaveDir;
            app.RawFolder = MainApp.dummyProt.MainDir{1};
            % Get handle from EventsManager object:
            app.evObj = MainApp.eventObj;                       
            % Check if there is an "events.mat" file already in the Raw
            % Folder and load it:
            if exist(fullfile(app.SaveFolder, 'events.mat'),'file')
                app.evObj.loadEvents(app.SaveFolder);
                % Update raw folder:
                app.RawFolder = app.evObj.RawFolder;
            end
            % Check for existence of analogIN data in raw directory:
            aiFiles = dir(fullfile(app.RawFolder,'ai_*.bin'));
            if isempty(aiFiles)
                % Ask for User input:
                folder = uigetdir(app.RawFolder, 'Select folder with analog binary files (e.g., ai_0000x.bin)');
                if folder == 0
                    disp('Operation cancelled by User')
                    app.UIFigure.Visible = 'on';
                    close(app)
                end
                % Recheck:
                aiFiles = dir(fullfile(folder,'ai_*.bin'));
                if isempty(aiFiles)
                    uialert(app.UIFigure, ['Analog binary files not found in folder "' folder '"'],'Failed to find analog data', 'Modal',true, 'Icon',"error")
                    return
                end
                % Update main directory:
                MainApp.dummyProt.MainDir = folder;
                app.RawFolder = folder;
                app.evObj.RawFolder = folder;
            end


            % Read AnalogIN:
            app.evObj.setAnalogIN;
            % Update list of available channels:
            app.updateAIchannelList;
            app.UIFigure.Visible = 'on';
            % If there are no AnalogIN data in the raw folder, raise error
            % message:
            if isempty(app.evObj.AnalogIN)
                uialert(app.UIFigure, ['Failed to find analog signals in folder "' app.RawFolder '". This app will close.'], 'Error!',"CloseFcn",@(src,evnt) app.UIFigureCloseRequest)
            else
                % If internal stims were already detected, update channel list
                % and plot triggers.
                app.mininterstimsSpinner.Value = double(app.evObj.minInterStim); % Update minInterstim time from triggers already detected.
                if ~isempty(app.evObj.eventID)
                    app.showStats;
                    app.showPlots;
                    app.SaveeventsButton.Enable = 'on';
                    app.TypeDropDown.Enable = 'on';
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %
            
            % Ask User to save the detected triggers to a "event.mat" file.
            % If the file already exists in the SaveFolder, ask to
            % overwrite it.
            try
                origEvInfo = load(fullfile(app.evObj.SaveFolder,'events.mat'));
                thisEvInfo = struct('eventID',app.evObj.eventID,'timestamps',app.evObj.timestamps,'state',...
                    app.evObj.state,'eventNameList',{app.evObj.eventNameList},'trialInterval',app.evObj.trialInterval,...
                    'selectedEvents',app.evObj.selectedEvents,'RawFolder',app.evObj.RawFolder);
                fn = fieldnames(origEvInfo);
                b_evChanged = any(cellfun(@(x) ~isequaln(origEvInfo.(x),thisEvInfo.(x)),fn)); 
                msg = {'Changes in event information detected!', 'Overwrite current "events.mat" file?'};
            catch 
                b_evChanged = true;                
                msg = 'Save current event info to the "events.mat" file?';
            end
                                                                          
            if b_evChanged
                answer = uiconfirm(app.UIFigure,msg,'Save events.mat file','Options',{'Yes','No'},'DefaultOption',1,"CancelOption",2);
                if strcmpi(answer,'yes')
                    app.evObj.saveEvents(app.evObj.SaveFolder);
                    app.evObj.loadEvents(app.evObj.SaveFolder); % Update event info.
                else
                    % Reset the EventsManager object and discard the detected triggers.
                    app.evObj = EventsManager(app.evObj.RawFolder,app.evObj.SaveFolder); % Set EventsManager object
                end
            end
                                  
            %% Close app
            if ~isempty(app.fH) & isvalid(app.fH) %#ok
                close(app.fH);
            end
            delete(app)
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            % Detects triggers in the selected channels and plots the results.
            % If no triggers are detected, all channels are plotted for
            % visual inspection.
            
            % Get trigger channel name:
            trigNames = {app.ChanGridLayout.Children.Text};
            app.evObj.trigChanName = trigNames([app.ChanGridLayout.Children.Value]);
            % Update threshold level:
            new_thr = str2double(app.ThresholdEditField.Value);
            if isnan(new_thr)
                app.evObj.trigThr = 'auto';
            else
                app.evObj.trigThr = new_thr;
            end
            % Update min intertrial length:
            app.evObj.minInterStim = app.mininterstimsSpinner.Value;
            % Update trigger type:
            app.evObj.trigType = app.TriggertypeDropDown.Value;
            % Detect triggers:
            app.evObj.getTriggers('',false,'FilterFreq',app.LowPassfilterEditField.Value);
            % If no triggers were detected, show warning message;
            if isempty(app.evObj.timestamps)
                uialert(app.UIFigure,'Failed to detect triggers in selected channels! All channels will be plotted for visual inspection.','Trigger detection failed!', 'Modal',true,'Icon',"warning", "CloseFcn",'uiresume(gcbf)');
                uiwait(app.UIFigure);
                app.SaveeventsButton.Enable = 'off';
            end
            % Update stats and plots
            if isempty(app.ColGridLayout.Children)
                colNames = {''};
            else
                colNames = {app.ColGridLayout.Children.Text};
                colNames = colNames([app.ColGridLayout.Children.Value]);
            end
            app.readConditionList(app.CondFileBtn.Tooltip, colNames);
            app.SaveeventsButton.Enable = 'on';
            app.TypeDropDown.Enable = 'on';
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            app.evObj.EventFileParseMethod = app.TypeDropDown.Value;
            if strcmpi(app.TypeDropDown.Value, 'none')
                app.GridLayout.ColumnWidth{3} = 0;
                delete(app.ColGridLayout.Children)
                % Re-detect data and update plots:
                app.DetectButtonPushed;
                set(app.CondFileBtn, 'Enable','off', 'Text', 'Choose file...');
                return
            end
            if strcmpi(app.TypeDropDown.Value, 'vpixx')
                app.GridLayout.ColumnWidth{3} = 0;
                delete(app.ColGridLayout.Children)
            end
            set(app.CondFileBtn, 'Enable','on');
            app.disableSaveBtn;
        end

        % Button pushed function: UpdatelistButton
        function UpdatelistButtonPushed(app, event)
            % Updates condition names from selection of columns from .CSV
            % file.
            colNames = {app.ColGridLayout.Children.Text};
            app.readConditionList(app.evObj.EventFileName, colNames([app.ColGridLayout.Children.Value]));
        end

        % Button pushed function: CondFileBtn
        function CondFileBtnPushed(app, event)
            % Condition File selection.
            
            if strcmpi(app.TypeDropDown.Value, 'vpixx')
                myFilter = {'*.vpixx','Vpixx file';'*.txt', 'Vpixx file (.txt)'};
            else
                myFilter = {'*.csv', '.CSV'};
            end
            [file,path] = uigetfile(myFilter,'Select Condition list file.',fullfile(app.evObj.RawFolder,['file.' lower(app.TypeDropDown.Value)]));
            if file == 0
                disp('Operation Cancelled by User')
                return
            end
            evFile = fullfile(path,file);
            colName = {''};
            if endsWith(file, '.csv',"IgnoreCase",true)
                opts = detectImportOptions(evFile);
                if opts.DataLines(1) > 1
                    % Populate "Select columns" panel:
                    app.ColGridLayout.RowHeight = repmat({25},size(opts.VariableNames));
                    %
                    delete(app.ColGridLayout.Children);
                    for ii = 1:length(opts.VariableNames)
                        c = uicheckbox('Parent',app.ColGridLayout, 'Text', opts.VariableNames{ii},...
                            'Value', false, 'ValueChangedFcn', @app.lockCheckBox);
                        c.Layout.Row = ii;
                        if ii == 1
                            c.Value = true;
                        end
                    end
                    colName = {app.ColGridLayout.Children.Text};
                    colName = colName([app.ColGridLayout.Children.Value]);
                    % Show "Select columns" panel
                    app.GridLayout.ColumnWidth{3} = 150;
                    app.GridLayout.ColumnWidth{3} = '1x'; pause(.2);drawnow()
                    if app.Panel.Position(3) < 150
                        app.UIFigure.Position(3) = app.UIFigure.Position(3) + 150;
                    end
                else
                    % Hide "Select columns" panel
                    app.GridLayout.ColumnWidth{3} = 0;
                end
            end
            app.CondFileBtn.Text = file;
            app.CondFileBtn.Tooltip = fullfile(path,file);
            
            % Read Condition list:
            app.readConditionList(evFile,colName);
            %
            app.disableSaveBtn;
        end

        % Button pushed function: SaveeventsButton
        function SaveeventsButtonPushed(app, event)
            % Create "events.mat" file in the SaveFolder
            if isempty(app.evObj.timestamps)
                return
            end
            app.evObj.saveEvents(app.SaveFolder);
            uialert(app.UIFigure, ['File "events.mat" created in folder "' app.SaveFolder '"'],'Events file created',"Icon","info","Modal",true);
            app.evObj.loadEvents(app.SaveFolder); % Update info inside EventsManager object
        end

        % Value changed function: ThresholdEditField, 
        % TriggertypeDropDown, mininterstimsSpinner
        function disableSaveBtn(app, event)
            app.SaveeventsButton.Enable = 'off';
        end

        % Callback function
        function setTrialInterval_callback(app, event)

            if isequal(event.Source, app.posteventtimeEditField) && event.Source.Value == 0
                app.evObj.setTrialInterval(app.preeventtimeEditField.Value);
            elseif isequal(event.Source, app.preeventtimeEditField) && event.Source.Value == 0
                app.evObj.setTrialInterval;
            else
                app.evObj.setTrialInterval(app.preeventtimeEditField.Value, app.posteventtimeEditField.Value);
            end
            % Update field values:
            app.preeventtimeEditField.Value = round(double(app.evObj.trialInterval(1)),3);
            app.posteventtimeEditField.Value = round(double(app.evObj.trialInterval(2)),3);
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Apply new trial intervals to the events object and update
            % fields.
            
            % Alert user for the risks of updating the pre and post-time
            % after normalizing data by baseline:
            answer = uiconfirm(app.UIFigure, {'Important:',...
                'Changes in pre and/or post-event onset time periods may impact all data already split by events (e.g. data normalized by baseline) in the folder:',...
                ['"' app.SaveFolder '"'],...
                'Proceed?'},'Confirm changes in pre/post event onset time periods',"CancelOption",'Cancel',"DefaultOption",'Cancel','Options',{'Confirm','Cancel'},"Icon","warning");
            if strcmpi(answer,'cancel'); return;end
            
            if app.preeventtimeEditField.Value ~= 0  && app.posteventtimeEditField.Value == 0
                app.evObj.setTrialInterval(app.preeventtimeEditField.Value);
            elseif app.preeventtimeEditField.Value == 0  && app.posteventtimeEditField.Value == 0
                app.evObj.setTrialInterval;
            else
                app.evObj.setTrialInterval(app.preeventtimeEditField.Value, app.posteventtimeEditField.Value);
            end
            % Update field values:
            app.preeventtimeEditField.Value = round(double(app.evObj.trialInterval(1)),3);
            app.posteventtimeEditField.Value = round(double(app.evObj.trialInterval(2)),3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Events Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EventdetectionparametersPanel = uipanel(app.GridLayout2);
            app.EventdetectionparametersPanel.Tooltip = {''};
            app.EventdetectionparametersPanel.Title = 'Event detection parameters';
            app.mininterstimsSpinnerLabel = uilabel(app.EventdetectionparametersPanel);
            app.mininterstimsSpinnerLabel.Text = 'min. interstim (s)';
            app.mininterstimsSpinner = uispinner(app.EventdetectionparametersPanel);
            app.mininterstimsSpinner.Step = 0.2;
            app.mininterstimsSpinner.Limits = [0 Inf];
            app.mininterstimsSpinner.ValueChangedFcn = createCallbackFcn(app, @disableSaveBtn, true);
            app.mininterstimsSpinner.Tooltip = {'Minimal time between stimuli in seconds (used to identify burst stimuli).'};
            app.mininterstimsSpinner.Value = 2;
            app.ThresholdEditFieldLabel = uilabel(app.EventdetectionparametersPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.EventdetectionparametersPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @disableSaveBtn, true);
            app.ThresholdEditField.Tooltip = {'Trigger detection threshold ("auto" or a numeric value in Volts).'};
            app.ThresholdEditField.Value = 'auto';
            app.TriggertypeDropDownLabel = uilabel(app.EventdetectionparametersPanel);
            app.TriggertypeDropDownLabel.Text = 'Trigger type';
            app.TriggertypeDropDown = uidropdown(app.EventdetectionparametersPanel);
            app.TriggertypeDropDown.Items = {'EdgeSet', 'EdgeToggle'};
            app.TriggertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @disableSaveBtn, true);
            app.TriggertypeDropDown.Tooltip = {'Type of trigger. EdgeSet: rising and falling edges of a pulse mark the start and end of an event. '; 'EdgeToggle: two consecutive pulses mark the start and end of an event.'};
            app.TriggertypeDropDown.Value = 'EdgeSet';
            app.LowPassfilterEditFieldLabel = uilabel(app.EventdetectionparametersPanel);
            app.LowPassfilterEditFieldLabel.Text = 'Low-Pass filter';
            app.LowPassfilterEditField = uieditfield(app.EventdetectionparametersPanel, 'numeric');
            app.LowPassfilterEditField.Limits = [0 Inf];
            app.LowPassfilterEditField.Tooltip = {'Type a cut-off frequency value (Hz) to apply a low-pass filter to the selected channel(s).'};
            app.ConditionlistPanel = uipanel(app.GridLayout2);
            app.ConditionlistPanel.Title = 'Condition list';
            app.TypeDropDownLabel = uilabel(app.ConditionlistPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ConditionlistPanel);
            app.TypeDropDown.Items = {'none', 'CSV', 'Vpixx'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Enable = 'off';
            app.TypeDropDown.Value = 'none';
            app.CondFileBtn = uibutton(app.ConditionlistPanel, 'push');
            app.CondFileBtn.ButtonPushedFcn = createCallbackFcn(app, @CondFileBtnPushed, true);
            app.CondFileBtn.Enable = 'off';
            app.CondFileBtn.Text = 'Choose file...';
            app.FileLabel = uilabel(app.ConditionlistPanel);
            app.FileLabel.Text = 'File:';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.DetectButton = uibutton(app.GridLayout3, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Tooltip = {'Detect and plot events.'};
            app.DetectButton.Text = 'Detect';
            app.SaveeventsButton = uibutton(app.GridLayout3, 'push');
            app.SaveeventsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveeventsButtonPushed, true);
            app.SaveeventsButton.Enable = 'off';
            app.SaveeventsButton.Tooltip = {'Save info to "events.mat" file.'};
            app.SaveeventsButton.Text = 'Save events';
            app.SelectchannelsPanel = uipanel(app.GridLayout);
            app.SelectchannelsPanel.Tooltip = {'Select one or more channels containing the triggers. If two or more channels are chosen'; ' each channel will be considered as a condition'; ' unless a condition file is set.'};
            app.SelectchannelsPanel.Title = 'Select channel(s) ';
            app.ChanGridLayout = uigridlayout(app.SelectchannelsPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.SelectcolumnsPanel = uipanel(app.GridLayout4);
            app.SelectcolumnsPanel.Title = 'Select column(s)';
            app.UpdatelistButton = uibutton(app.SelectcolumnsPanel, 'push');
            app.UpdatelistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatelistButtonPushed, true);
            app.UpdatelistButton.Text = 'Update list';
            app.Panel = uipanel(app.GridLayout4);
            app.Panel.Tooltip = {'Select one or more columns from the .CSV file. For multiple selection'; ' each row will be concatenated to a single condition.'};
            app.ColGridLayout = uigridlayout(app.Panel);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.EventinfoPanel = uipanel(app.GridLayout5);
            app.EventinfoPanel.Title = 'Event info';
            app.NumberofconditionsLabel = uilabel(app.EventinfoPanel);
            app.NumberofconditionsLabel.Enable = 'off';
            app.NumberofconditionsLabel.Text = 'Number of conditions:';
            app.NumberofconditionsEditField = uieditfield(app.EventinfoPanel, 'numeric');
            app.NumberofconditionsEditField.Limits = [0 Inf];
            app.NumberofconditionsEditField.Editable = 'off';
            app.NumberofconditionsEditField.Enable = 'off';
            app.DetectionthresholdEditFieldLabel = uilabel(app.EventinfoPanel);
            app.DetectionthresholdEditFieldLabel.Enable = 'off';
            app.DetectionthresholdEditFieldLabel.Text = 'Detection threshold:';
            app.DetectionthresholdEditField = uieditfield(app.EventinfoPanel, 'numeric');
            app.DetectionthresholdEditField.Limits = [0 Inf];
            app.DetectionthresholdEditField.ValueDisplayFormat = '%0.2f';
            app.DetectionthresholdEditField.Editable = 'off';
            app.DetectionthresholdEditField.Enable = 'off';
            app.sLabel = uilabel(app.EventinfoPanel);
            app.sLabel.Enable = 'off';
            app.sLabel.Text = 's';
            app.VLabel = uilabel(app.EventinfoPanel);
            app.VLabel.Enable = 'off';
            app.VLabel.Text = 'V';
            app.sLabel_2 = uilabel(app.EventinfoPanel);
            app.sLabel_2.Enable = 'off';
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.EventinfoPanel);
            app.sLabel_3.Enable = 'off';
            app.sLabel_3.Text = 's';
            app.AvgtriallengthEditField_2Label = uilabel(app.EventinfoPanel);
            app.AvgtriallengthEditField_2Label.Enable = 'off';
            app.AvgtriallengthEditField_2Label.Text = 'Avg. trial length :';
            app.AvgtriallengthEditField = uieditfield(app.EventinfoPanel, 'text');
            app.AvgtriallengthEditField.Enable = 'off';
            app.AvgtriallengthEditField.Tooltip = {'Average trial length (min/max)'};
            app.AvgtriallengthEditField.Value = '0 (0/0)';
            app.AvgintertriallengthEditFieldLabel = uilabel(app.EventinfoPanel);
            app.AvgintertriallengthEditFieldLabel.Enable = 'off';
            app.AvgintertriallengthEditFieldLabel.Text = 'Avg. inter-trial length :';
            app.AvgintertriallengthEditField = uieditfield(app.EventinfoPanel, 'text');
            app.AvgintertriallengthEditField.Enable = 'off';
            app.AvgintertriallengthEditField.Tooltip = {'Average inter-trial length (min/max)'};
            app.AvgintertriallengthEditField.Value = '0 (0/0)';
            app.RecordinglengthEditFieldLabel = uilabel(app.EventinfoPanel);
            app.RecordinglengthEditFieldLabel.Enable = 'off';
            app.RecordinglengthEditFieldLabel.Text = 'Recording length:';
            app.RecordinglengthEditField = uieditfield(app.EventinfoPanel, 'numeric');
            app.RecordinglengthEditField.Limits = [0 Inf];
            app.RecordinglengthEditField.ValueDisplayFormat = '%0.2f';
            app.RecordinglengthEditField.Editable = 'off';
            app.RecordinglengthEditField.Enable = 'off';
            app.EventsdetectedEditFieldLabel = uilabel(app.EventinfoPanel);
            app.EventsdetectedEditFieldLabel.Enable = 'off';
            app.EventsdetectedEditFieldLabel.Text = 'Events detected:';
            app.EventsdetectedEditField = uieditfield(app.EventinfoPanel, 'numeric');
            app.EventsdetectedEditField.Limits = [0 Inf];
            app.EventsdetectedEditField.Editable = 'off';
            app.EventsdetectedEditField.Enable = 'off';
            app.SettrialintervalPanel = uipanel(app.GridLayout5);
            app.SettrialintervalPanel.Title = 'Set trial interval';
            app.preeventtimeEditFieldLabel = uilabel(app.SettrialintervalPanel);
            app.preeventtimeEditFieldLabel.Text = 'pre-event time';
            app.preeventtimeEditField = uieditfield(app.SettrialintervalPanel, 'numeric');
            app.preeventtimeEditField.Limits = [0 Inf];
            app.preeventtimeEditField.ValueDisplayFormat = '%11.2g';
            app.posteventtimeEditFieldLabel = uilabel(app.SettrialintervalPanel);
            app.posteventtimeEditFieldLabel.Text = 'post-event time';
            app.posteventtimeEditField = uieditfield(app.SettrialintervalPanel, 'numeric');
            app.posteventtimeEditField.Limits = [0 Inf];
            app.posteventtimeEditField.ValueDisplayFormat = '%11.2g';
            app.posteventtimeEditField.Tooltip = {'Type "0" to automatically set the post-event time'};
            app.sLabel_4 = uilabel(app.SettrialintervalPanel);
            app.sLabel_4.Text = 's';
            app.sLabel_5 = uilabel(app.SettrialintervalPanel);
            app.sLabel_5.Text = 's';
            app.OkButton = uibutton(app.SettrialintervalPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_evntManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end