classdef DataViewer_evntWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OptionsMenu              matlab.ui.container.Menu
        DeletecurrentMenu        matlab.ui.container.Menu
        ConditionMenu            matlab.ui.container.Menu
        RepetitionMenu           matlab.ui.container.Menu
        RestoreallMenu           matlab.ui.container.Menu
        EventsoptionsPanel       matlab.ui.container.Panel
        ConditionDropDownLabel   matlab.ui.control.Label
        ConditionDropDown        matlab.ui.control.DropDown
        RepetitionDropDownLabel  matlab.ui.control.Label
        RepetitionDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MainApp % Handle to Main App
        repInfo % structure containing the repetitions indices in ID_list for each condition.
    end
    
    properties (Access = public)
        condList % cell array of initial condition names
        ID_list % array of indices of condList.
    end
    
    methods (Access = private)
        function genCondList(app)
            % This function creates the items from the DropDown menu of
            % Conditions
            % transform list to cell array of char
            app.ConditionDropDown.Items = cellfun(@(x) num2str(x), app.MainApp.eventName_list, 'UniformOutput',0);
            % Link to index in the original condition list:
            condArr = cellfun(@(x) num2str(x), app.condList, 'UniformOutput',0);
            [~, locB] = ismember(app.ConditionDropDown.Items,condArr);
            app.ConditionDropDown.ItemsData = locB;
            app.MainApp.currInfo.cond = app.ConditionDropDown.Value;
        end
        
        function genRepList(app)
            % This function creates the List of repetitions for the selected condition
            % from the ConditionDropDown menu
            app.RepetitionDropDown.Items = app.repInfo(app.ConditionDropDown.Value).repItems;
            app.RepetitionDropDown.ItemsData = app.repInfo(app.ConditionDropDown.Value).repIdx;
            app.MainApp.currInfo.rep = app.RepetitionDropDown.Value;
        end
        
        function createLists(app)
            % Creates condition and repetition lists. These lists will be used
            % to populate dropdown menus in GUI.
            
            app.ID_list = app.MainApp.eventID_list;
            app.condList = app.MainApp.eventName_list;
            % Create repInfo structure
            app.repInfo = struct();
            for i = 1:length(app.condList)
                app.repInfo(i).condName = num2str(app.condList{i});
                app.repInfo(i).condIdx = i;
                app.repInfo(i).repItems = [arrayfun(@(x) num2str(x), 1:sum(app.ID_list == i),...
                    'UniformOutput', 0) '#Average'];
                app.repInfo(i).repIdx = [find(app.ID_list == i); -1];
            end
            
        end
        
    end
    
    methods (Access = public)
        function updateLists(app)
            % Allows DataViewer to update the event lists in this GUI.
            app.createLists;
            app.genCondList;
            app.genRepList;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.UIFigure.Visible = 'off';
            app.MainApp = MainApp;
            % Update UIFigure position:
            app.UIFigure.Position([1 2]) = [app.MainApp.timeFig.Fig.Position(1) app.MainApp.timeFig.Fig.Position(2)-280];
                       
            % Create repetition and condition lists:
            app.createLists;
            % Populate dropdown menus
            app.genCondList;
            app.genRepList;
            % Select #Average as default Item:
            app.RepetitionDropDown.Value = -1;
            % Note: The figure will be turned visible inside the "loadData"
            % method from DataViewer app. This is to avoid showing the
            % events gui before finishing calculating the data.        
            app.UIFigure.Visible = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %%% Do Nothing!!!%%%
            
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
%             app.MainApp.currInfo.cond = app.ConditionDropDown.Value;
            app.genRepList;
            app.MainApp.condChanged;
        end

        % Value changed function: RepetitionDropDown
        function RepetitionDropDownValueChanged(app, event)
            app.MainApp.currInfo.rep = app.RepetitionDropDown.Value;
            app.MainApp.repChanged;
        end

        % Menu selected function: RepetitionMenu
        function RepetitionMenuSelected(app, event)
            % Deletes the current repetition in the Repetition list
            rep_name = app.RepetitionDropDown.Items(app.RepetitionDropDown.ItemsData == app.RepetitionDropDown.Value);
            if app.RepetitionDropDown.Value == -1
                errordlg('Can''t delete "#Average"!', 'Failed to delete repetition')
                return
            end
            
            % Get the repetition info:
            indx_reps = setdiff(app.repInfo(app.ConditionDropDown.Value).repIdx,0);
            % If this is the only repetition, call the Condition Deletion
            % Callback:
            if numel(indx_reps) == 1
                % Confirm deletion:
                answer = questdlg('This is the last Repetition. Confirm deletion of the whole condition?', 'Delete Condition?', 'Yes', 'No', 'No');
                if strcmp(answer, 'Yes')
                    app.ConditionMenuSelected(event);
                end
                return
            else
                answer = questdlg(['Confirm deletion of repetition #' rep_name{:} '?'], 'Delete Repetition?', 'Yes', 'No', 'No');
                if strcmp(answer, 'No') || isempty(answer)
                    return
                end
            end
            % Change repetition value from "eventID_list"  to "0" in main app:
            app.MainApp.eventID_list(app.RepetitionDropDown.Value) = 0; % Dummy value to indicate "deleted" repetitions.
            % Delete repetition from repInfo structure:
            idx = app.RepetitionDropDown.ItemsData == app.RepetitionDropDown.Value;
            app.repInfo(app.ConditionDropDown.Value).repItems(idx) = [];
            app.repInfo(app.ConditionDropDown.Value).repIdx(idx) = [];
            % Update Repetition DropDown menu:
            app.genRepList;
            % Update plots in Main App:
            app.MainApp.repChanged;
        end

        % Menu selected function: ConditionMenu
        function ConditionMenuSelected(app, event)
            % Deletes the current condition in the Condition list
            
            % Retrieve current condition name:
            cond_name = app.ConditionDropDown.Items(app.ConditionDropDown.ItemsData == app.ConditionDropDown.Value);
            
            % Confirm deletion:
            answer = questdlg(['Confirm deletion of the condition below:', cond_name], 'Delete Condition?', 'Yes', 'No', 'No');
            if strcmp(answer, 'No') || isempty(answer)
                return
            end
            if numel(app.ConditionDropDown.Items) == 1
                errordlg('Can''t delete the last condition!', 'Failed to delete condition')
                return
            end
            % Delete condition from "eventName_list" in main app:
            idx = strcmp(cellfun(@(x) num2str(x), app.MainApp.eventName_list,'UniformOutput',false), cond_name);
            if sum(idx) == 0
                disp('Condition not found in eventID_list');
                return
            end
            app.MainApp.eventName_list(idx) = [];
            % Change value of repetitions from eventID_list to "0"
            idx = app.MainApp.eventID_list == app.ConditionDropDown.Value;
            app.MainApp.eventID_list(idx) = 0;
            % Update Condition DropDown menu:
            app.genCondList;
            % Update Repetition DropDown menu:
            app.genRepList;
            % Update plots in Main App:
            app.MainApp.condChanged;
        end

        % Menu selected function: RestoreallMenu
        function RestoreallMenuSelected(app, event)
            % Restores all conditions and repetitions to the original list
            % when the app was instantiated
            
            % User confirmation:
            answer = questdlg('Restore all conditions and repetitions?', 'Restore All?', 'Yes', 'No', 'No');
            if strcmp(answer, 'No') || isempty(answer)
                return
            end
            % Restore everything
            app.MainApp.eventID_list = app.ID_list;
            app.MainApp.eventName_list = app.condList;
            % Recreate repInfo structure
            app.repInfo = struct();
            for i = 1:length(app.condList)
                app.repInfo(i).condName = num2str(app.condList{i});
                app.repInfo(i).condIdx = i;
                app.repInfo(i).repItems = [arrayfun(@(x) num2str(x), 1:sum(app.ID_list == i),...
                    'UniformOutput', 0) '#Average'];
                app.repInfo(i).repIdx = [find(app.ID_list == i); -1];
            end
            % Update Condition DropDown menu
            app.genCondList;
            % Update Repetition DropDown menu:
            app.genRepList;
            % Update plots in Main App:
            app.MainApp.condChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Events';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.DeletecurrentMenu = uimenu(app.OptionsMenu);
            app.DeletecurrentMenu.Text = 'Delete current...';
            app.ConditionMenu = uimenu(app.DeletecurrentMenu);
            app.ConditionMenu.MenuSelectedFcn = createCallbackFcn(app, @ConditionMenuSelected, true);
            app.ConditionMenu.Text = 'Condition';
            app.RepetitionMenu = uimenu(app.DeletecurrentMenu);
            app.RepetitionMenu.MenuSelectedFcn = createCallbackFcn(app, @RepetitionMenuSelected, true);
            app.RepetitionMenu.Text = 'Repetition';
            app.RestoreallMenu = uimenu(app.OptionsMenu);
            app.RestoreallMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreallMenuSelected, true);
            app.RestoreallMenu.Text = 'Restore all';
            app.EventsoptionsPanel = uipanel(app.UIFigure);
            app.EventsoptionsPanel.Title = 'Events options';
            app.ConditionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.EventsoptionsPanel);
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.RepetitionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.RepetitionDropDownLabel.Text = 'Repetition';
            app.RepetitionDropDown = uidropdown(app.EventsoptionsPanel);
            app.RepetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @RepetitionDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_evntWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end