classdef DataViewer_systemSpectralGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIPanel                 matlab.ui.container.Panel
        ListBox                 matlab.ui.control.ListBox
        AddnewButton            matlab.ui.control.Button
        DeleteButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        FilterSetsandCameraprofilesPanel  matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        CreatenewprofileButton  matlab.ui.control.Button
        DeleteprofileButton     matlab.ui.control.Button
        RestoreButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        sysSpect % Description
        sysSpectPath = which('SysSpect.mat')
        FilterSets % Local copy of FilterSets.mat
        CameraSpect % Local copy of CameraSpect.mat
        plt % plot handle
        plt2 % 2nd plot handle
        leg % Legend handle
        selectedTabRow = 0 % Description
        % Original data to be restored:
        sysSpect_original
        FilterSets_original
        CameraSpect_original
        
    end
    
    methods (Access = private)
        
        
        function saveSpectAndReloadList(app)
            % Overwrite and reload
            data = app.sysSpect;
            save(app.sysSpectPath,'-struct','data');
            % Reload list
            app.sysSpect = load(app.sysSpectPath);
            app.ListBox.Items = fieldnames(app.sysSpect);
            app.ListBox.Value = app.ListBox.Items(end);
            app.ListBoxValueChanged;
        end
        function saveSpect(app,type)
            path = fileparts(app.sysSpectPath);
            
            if strcmpi(type,'Camera')
                % Overwrite 'CameraSpect.mat'
                data = app.CameraSpect;
                save(fullfile(path, 'CameraSpect.mat'),'-struct','data');
            else
                data = app.FilterSets;
                save(fullfile(path, 'FilterSets.mat'),'-struct','data');
            end
            % Reload table
            app.loadTableItems();
        end
        function loadTableItems(app)
            %
            % Load table for camera
            tabCam = table(fieldnames(app.CameraSpect),...
                repmat({'Camera'},length(fieldnames(app.CameraSpect)),1),...
                repmat({'none'},length(fieldnames(app.CameraSpect)),1),...
                cellfun(@(x) app.CameraSpect.(x), fieldnames(app.CameraSpect),'UniformOutput',false));
            % Load table for FilterSets
            % Remove "none" filter set from list:            
            fN = fieldnames(app.FilterSets);
            fN = fN(~strcmp(fN,'none'));            
            tabFilt = table(fN,...
                repmat({'Filter'},length(fN),1),...
                cellfun(@(x) app.FilterSets.(x).Excitation, fN,'UniformOutput',false),...
                cellfun(@(x) app.FilterSets.(x).Emission, fN,'UniformOutput',false));
            tabFilt.Properties.VariableNames{1} = 'Var1';
            % Update table:
            app.UITable.Data = vertcat(tabCam,tabFilt);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIFigure, 'Visible','off');
            try
                app.sysSpect = load('SysSpect.mat');
                path = fileparts(app.sysSpectPath);
                app.FilterSets  = load(fullfile(path,'FilterSets.mat'));
                
                app.CameraSpect = load(fullfile(path,'CameraSpect.mat'));
                % Copy data:
                app.sysSpect_original = app.sysSpect;
                app.CameraSpect_original = app.CameraSpect;
                app.FilterSets_original = app.FilterSets;
            catch ME
                error('Unable to find system spectral information. Be sure that all subfolders from Umit are in Matlab''s path.')                
            end
            movegui(app.UIFigure,'center');
            % Populate list box with system's spectral information.
            app.ListBox.Items = fieldnames(app.sysSpect);
            disableDefaultInteractivity(app.UIAxes)
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.Title.Interpreter = 'none';
            % Set plot
            hold(app.UIAxes,'on')
            app.plt = plot(app.UIAxes,400:700,ones(301,1),'r');
            app.plt2 = plot(app.UIAxes,400:700,ones(301,1),'b','Visible',"off");
            hold(app.UIAxes,'off')
            app.leg = legend([app.plt,app.plt2],{'A','B'},'Location',"eastoutside",'Interpreter','none');
            set(app.leg,'Visible','off');
            app.ListBoxValueChanged;
            % Set table
            app.loadTableItems;
            set(app.UIFigure, 'Visible','on')
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            app.plt.YData = app.sysSpect.(app.ListBox.Value);
            app.UIAxes.Title.String = app.ListBox.Value;
            set(app.plt,'Visible','on');
            set(app.plt2, 'Visible','off');
            set(app.leg,'Visible','off');
            
        end

        % Button pushed function: AddnewButton
        function AddnewButtonPushed(app, event)
            [file,path] = uigetfile('*.txt', 'Select text file containing the new filter spectrum');
            try
                newSpectrum = importSpectralProfileFromTXT(fullfile(path,file));
            catch ME
                uialert(app.UIFigure, {['Operation aborted! Failed to read file "' file '"'], ME.message},'Failed to import Spetral profile!','Icon','error');
                return
            end
            [~,name,~] = fileparts(file);
            % Create new profile
            prompt = {'Set New spectrum name'};
            dlgtitle = 'Set name';
            fieldsize = [1 40];
            definput = {name};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            if isempty(answer) || isempty(answer{:})
                return
            end
            spectrumName = matlab.lang.makeValidName(answer{:});
            % Append new spectrum to file:
            app.sysSpect.(spectrumName) = newSpectrum;
            app.saveSpectAndReloadList;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            answer = uiconfirm(app.UIFigure, ['Are you sure to delete spectrum ' app.ListBox.Value '?'],'Delete Spectrum',"DefaultOption",'Cancel');
            if strcmpi(answer,'cancel');return;end
            app.sysSpect = rmfield(app.sysSpect,app.ListBox.Value);
            % Overwrite and reload
            app.saveSpectAndReloadList;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedTabRow = 0;
            if numel(indices)>2;return;end
            app.selectedTabRow = indices(1);
            % Selection:
            type = app.UITable.Data.Var2{app.selectedTabRow};
            excitationProfileName = app.UITable.Data.Var3{app.selectedTabRow};
            emissionProfileName = app.UITable.Data.Var4{app.selectedTabRow};
            
            % Plot profiles
            if strcmpi(type,'filter')
                % Plot excitation Profile
                if ~strcmpi(excitationProfileName,'none')
                    app.plt.YData = app.sysSpect.(excitationProfileName);
                    set(app.plt,'Visible','on');
                else
                    set(app.plt,'Visible','off');
                end
                % Plot emission Profile
                if ~strcmpi(emissionProfileName,'none')
                    app.plt2.YData = app.sysSpect.(emissionProfileName);
                    set(app.plt2,'Visible','on')
                    % Set Legend and hide title:
                    set(app.leg,'String',{excitationProfileName,emissionProfileName},...
                        'Visible','on');
                else
                    set(app.plt2, 'Visible','off');
                end
            else
                if strcmpi(emissionProfileName,'none');return;end
                set(app.leg,'Visible','off')
                set(app.plt,'Visible','off');
                % Plot Camera filter profile
                app.plt2.YData = app.sysSpect.(app.UITable.Data.Var4{app.selectedTabRow});
                set(app.plt2,'Visible','on');
                set(app.plt,'Visible','off');
                
            end
            
            % Set Legend and title:
            set(app.leg,'String',{excitationProfileName,emissionProfileName},...
                'Visible','on');
            app.UIAxes.Title.String = app.UITable.Data.Var1{app.selectedTabRow};
        end

        % Button pushed function: CreatenewprofileButton
        function CreatenewprofileButtonPushed(app, event)
            profileName  = '';
            selectedValue1 = '';
            selectedValue2 = '';
            prompt = 'Select Profile type and options:';
            options = {'Camera', 'FilterSet', 'Cancel'};
            
            % Use uiconfirm to display the dialog
            selection = uiconfirm(app.UIFigure, prompt, 'Profile Type Selection', ...
                'Options', options, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel');
            
            % Handle the user's selection
            switch selection
                case 'Camera'
                    createDropdownWindow('Filter', app.ListBox.Items);
                    % Create new Camera Profile:
                    if any(strcmp(profileName, fieldnames(app.CameraSpect)))
                        answer2 = uiconfirm(app.UIFigure,['There is already a camera Spectrum named ' profileName '. Overwrite?'],...
                            'Overwrite existing Camera spectrum profile','Options',{'Yes','Cancel'},'DefaultOption','Cancel');
                        if strcmpi(answer2,'cancel');return;end
                    end
                    % Add new camera spectrum to the list
                    app.CameraSpect.(profileName) = selectedValue1;
                    % Save
                    app.saveSpect('camera');
                case 'FilterSet'
                    createDropdownWindow('Excitation', app.ListBox.Items, 'Emission',app.ListBox.Items);
                    % Create new filter set:
                    if any(strcmp(profileName, fieldnames(app.FilterSets)))
                        answer2 = uiconfirm(app.UIFigure,['There is already a Filter Set named ' profileName '. Overwrite?'],...
                            'Overwrite existing Camera spectrum profile','Options',{'Yes','Cancel'},'DefaultOption','Cancel');
                        if strcmpi(answer2,'cancel');return;end
                    end
                    % Add new Filter Set
                    app.FilterSets.(profileName).Excitation = selectedValue1;
                    app.FilterSets.(profileName).Emission   = selectedValue2;
                    % Save
                    app.saveSpect('filterset');
                case 'Cancel'
                    return
                    % Handle cancellation if needed
            end
            
            function createDropdownWindow(label1, options1, label2, options2)
                % Create a new figure for the dropdown window
                dropdownFig = uifigure('Position', [400, 400, 300, 250], 'Name', 'Set new Camera or Filter Set');
                
                % Create a text edit field for the profile name
                profileNameField = uieditfield(dropdownFig, 'text', ...
                    'Position', [20, 200, 260, 30], ...
                    'Value','NewProfile');
                
                % Create the first dropdown menu
                lbl1 = uilabel(dropdownFig, 'Text', label1, 'Position', [20, 150, 100, 22]);
                dd1 = uidropdown(dropdownFig, 'Items', options1, ...
                    'Position', [120, 150, 150, 22]);
                dd2 = [];
                % If the second label and options are provided, create the second dropdown
                if nargin > 3
                    lbl2 = uilabel(dropdownFig, 'Text', label2, 'Position', [20, 100, 100, 22]);
                    dd2 = uidropdown(dropdownFig, 'Items', options2, ...
                        'Position', [120, 100, 150, 22]);
                end
                % Create the OK and Cancel buttons
                okBtn = uibutton(dropdownFig, 'Text', 'OK', ...
                    'Position', [50, 30, 80, 30], ...
                    'ButtonPushedFcn', @(btn,event) okCallback(dd1, dd2, profileNameField));
                cancelBtn = uibutton(dropdownFig, 'Text', 'Cancel', ...
                    'Position', [170, 30, 80, 30], ...
                    'ButtonPushedFcn', @(btn,event) cancelCallback());
                
                % Wait for the dropdown window to close
                uiwait(dropdownFig);
                
                % Callback for OK button
                function okCallback(dd1, dd2, profileNameField)
                    profileName = matlab.lang.makeValidName(profileNameField.Value); % Get the profile name
                    selectedValue1 = dd1.Value; % Get the selected value from the first dropdown
                    if ~isempty(dd2)% Check if the second dropdown exists
                        selectedValue2 = dd2.Value; % Get the selected value from the second dropdown
                    end
                    uiresume(dropdownFig); % Resume execution after closing the dropdown window
                    close(dropdownFig); % Close the dropdown window
                end
                
                % Callback for Cancel button
                function cancelCallback()
                    disp('Operation cancelled.');
                    uiresume(dropdownFig); % Resume execution after closing the dropdown window
                    close(dropdownFig); % Close the dropdown window
                end
            end
            
            
        end

        % Button pushed function: DeleteprofileButton
        function DeleteprofileButtonPushed(app, event)
            % Get selected item from table:            
            if ~app.selectedTabRow;return;end
            answer = uiconfirm(app.UIFigure,['Delete profile named ' app.UITable.Data.Var1{app.selectedTabRow} '?'],...
                'Delete existing Camera/Filter profile','Options',{'Yes','Cancel'},'DefaultOption','Cancel');
            if strcmpi(answer,'cancel');return;end
            
            if strcmpi(app.UITable.Data.Var2{app.selectedTabRow},'Camera')
                % Delete from CameraSpect
                app.CameraSpect = rmfield(app.CameraSpect,app.UITable.Data.Var1{app.selectedTabRow});
            else
                % Delete from FilterSet
                app.FilterSets= rmfield(app.FilterSets,app.UITable.Data.Var1{app.selectedTabRow});
            end
            % Save and reload table:
            app.saveSpect(app.UITable.Data.Var2{app.selectedTabRow});
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            answer = uiconfirm(app.UIFigure, 'Are you sure to reset all spetra and profiles?','Reset all','Options',{'Yes','Cancel'},'DefaultOption',2);
            if strcmpi(answer,'cancel');return;end
            % Reset to original
            app.sysSpect = app.sysSpect_original;
            app.CameraSpect = app.CameraSpect_original;
            app.FilterSets = app.FilterSets_original;
            % Save and reload lists and tables 
            app.saveSpectAndReloadList;
            app.saveSpect('Camera')
            app.saveSpect('FilterSet');
            app.loadTableItems();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'System Spectral Properties';
            app.UIPanel = uipanel(app.UIFigure);
            app.UIPanel.Title = 'Available system spectra';
            app.ListBox = uilistbox(app.UIPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.AddnewButton = uibutton(app.UIPanel, 'push');
            app.AddnewButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewButtonPushed, true);
            app.AddnewButton.Icon = 'icon_plusBW.png';
            app.AddnewButton.Tooltip = {'Add a new filter/camera  spectral profile from a TXT file.'};
            app.AddnewButton.Text = 'Add new';
            app.DeleteButton = uibutton(app.UIPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = 'icon_deleteBW.png';
            app.DeleteButton.Tooltip = {'Delete selected spectrum from list.'};
            app.DeleteButton.Text = 'Delete';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'nm')
            ylabel(app.UIAxes, 'T')
            app.UIAxes.XLim = [400 700];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [400 550 700];
            app.UIAxes.XTickLabel = {'400'; '550'; '700'};
            app.UIAxes.YTick = [0 1];
            app.UIAxes.YTickLabel = {'0'; '1'};
            app.FilterSetsandCameraprofilesPanel = uipanel(app.UIFigure);
            app.FilterSetsandCameraprofilesPanel.Title = 'Filter Sets and Camera profiles';
            app.UITable = uitable(app.FilterSetsandCameraprofilesPanel);
            app.UITable.ColumnName = {'Name'; 'Type'; 'Excitation Filter'; 'Emission Filter'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'Current values for filter sets and cameras'};
            app.CreatenewprofileButton = uibutton(app.FilterSetsandCameraprofilesPanel, 'push');
            app.CreatenewprofileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenewprofileButtonPushed, true);
            app.CreatenewprofileButton.Icon = 'icon_plusBW.png';
            app.CreatenewprofileButton.Tooltip = {'Create new camera/Filter set profile.'};
            app.CreatenewprofileButton.Text = 'Create new profile';
            app.DeleteprofileButton = uibutton(app.FilterSetsandCameraprofilesPanel, 'push');
            app.DeleteprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButtonPushed, true);
            app.DeleteprofileButton.Icon = 'icon_deleteBW.png';
            app.DeleteprofileButton.Tooltip = {'Delete selected Camera/FilterSet profile'};
            app.DeleteprofileButton.Text = 'Delete profile';
            app.RestoreButton = uibutton(app.UIFigure, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Icon = 'icon_back_arrow.png';
            app.RestoreButton.Tooltip = {'Resets all lists and profiles.'};
            app.RestoreButton.Text = 'Restore';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_systemSpectralGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end