classdef ROIMask_creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIMaskCreatorUIFigure    matlab.ui.Figure
        CreateROIsButton          matlab.ui.control.Button
        ControlsPanel             matlab.ui.container.Panel
        NormCheckBx               matlab.ui.control.CheckBox
        ThresholdlevelLabel       matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        ThrLim                    matlab.ui.control.NumericEditField
        ExclROIsSpinnerLabel      matlab.ui.control.Label
        PixelLim                  matlab.ui.control.Spinner
        SelDirGroup               matlab.ui.container.ButtonGroup
        abovethrButton            matlab.ui.control.RadioButton
        belowthrButton            matlab.ui.control.RadioButton
        RemovesmallholesCheckBox  matlab.ui.control.CheckBox
        SmoothbordersCheckBox     matlab.ui.control.CheckBox
        AutosetButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        maskArr % Array of masks containing individual ROIs based on contiguous pixels.
        image % input image after replacemente of NaNs and Infs.
        orig_image % same as image in startup. Used to toggle betwee Normalized and not normalized.
        overlayHandle % handle for the mask overlay over the image axis.
        imgHandle % handle to image.
        maskHandle % handle to binary mask image.
        textHandle % handle of text annotation over image.
        MainApp % Handle to ROImanager app.
        lkPropsObj % Handle to linkprops object created in startupFcn
        myCmap % stores the cool colormap.
        imageMask % Mask from original image created in ROImanager. This will be used to exclude the analysis outside the Mask.
    end
    
    methods (Access = private)
        
        function DoNothing(~, ~, ~)
            % EMPTY CALLBACK %
            
        end
        
        function updateMask(app, thr, b_applyMorphOp)
            % This function updates the mask using the threshold in "thr".
            % inputs:
            % thr (float): threshold to be used to create masks:
            % b_applyMorphOp (bool): if True, applies the morphological
            % operations based on the checkbox values in GUI
            
            if ishandle(app.textHandle)
                delete(app.textHandle)
            end
            
            if app.abovethrButton.Value
                BW = app.image > thr;
            else
                BW = app.image < thr;
            end
            
            app.maskArr = bwlabel(bwareaopen(BW & app.imageMask, app.PixelLim.Value,8));
            if ~b_applyMorphOp | ~(app.RemovesmallholesCheckBox.Value | app.SmoothbordersCheckBox.Value)                
                set(app.overlayHandle, 'CData', app.maskArr, 'AlphaData', .5* single(app.maskArr~=0));
                set(app.maskHandle, 'CData', app.maskArr, 'AlphaData', app.maskArr~=0);
                app.maskHandle.Parent.Title.String = 'Binary Mask';
                % Update colormap
                app.myCmap = [0 0 0; distinguishable_colors(numel(unique(app.maskArr(:)))-1, {'w','k'})];
                set(app.overlayHandle.Parent, 'Colormap',app.myCmap); % Update colormap on overlay axis
                set(app.maskHandle.Parent, 'Colormap',app.myCmap); % Update colormap on mask axis
                return
            end
                        
            newArr = zeros(size(app.maskArr));
            for i = 1:max(app.maskArr(:))
                if app.RemovesmallholesCheckBox.Value & ~app.SmoothbordersCheckBox.Value
                    newArr = newArr | bwmorph(app.maskArr == i, 'close', Inf);
                elseif app.SmoothbordersCheckBox.Value & ~app.RemovesmallholesCheckBox.Value
                    newArr = newArr |  bwmorph(app.maskArr == i, 'open', Inf);
                elseif app.SmoothbordersCheckBox.Value & app.RemovesmallholesCheckBox.Value
                   newArr = newArr | bwmorph(bwmorph(app.maskArr == i, 'close', Inf),'open',Inf); 
                end
            end
            app.maskArr = bwlabel(bwareaopen(newArr, app.PixelLim.Value, 8)); 
            % Update colormap
            app.myCmap = [0 0 0; distinguishable_colors(numel(unique(app.maskArr(:)))-1, {'w','k'})];
            set(app.overlayHandle.Parent, 'Colormap',app.myCmap); % Update colormap on overlay axis
            set(app.maskHandle.Parent, 'Colormap',app.myCmap); % Update colormap on mask axis
            set(app.overlayHandle, 'CData', app.maskArr, 'AlphaData', .5* single(app.maskArr~=0));
            set(app.maskHandle, 'CData', app.maskArr);
            app.maskHandle.Parent.Title.String = 'Binary Mask';
        end
        
        function overlayLabels(app)
            % This function shows the ROIs indices over the Binary mask
            % image.
            msk_indx = unique(app.maskArr(:));
            msk_indx(msk_indx == 0) = [];
            centroids = zeros(length(msk_indx),2);
            for i = 1:length(msk_indx)
                info = regionprops(app.maskArr == msk_indx(i), 'Centroid');
                centroids(i,:) = info.Centroid;
            end
            % Erase existing ROI labels:
            if ~isempty(app.textHandle)
                delete(app.textHandle)
            end
            % Create new labels:
            app.textHandle = text(app.maskHandle.Parent, centroids(:,1), centroids(:,2),...
                arrayfun(@num2str,msk_indx, 'UniformOutput', false), 'Color', 'w', 'FontWeight', 'bold');
            hold(app.maskHandle.Parent, 'off');
            % Update mask image title:
            app.maskHandle.Parent.Title.String = ['Binary Mask (number of Regions = ' num2str(numel(msk_indx)) ')'];
            %
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, imgData, imgMsk)
            % The input for the app must be a 2-D numerical matrix.
            app.ROIMaskCreatorUIFigure.Visible = 'off';
            if ~ismatrix(imgData)
                uialert(app.ROIMaskCreatorUIFigure, 'Wrong data type. This app accepts only 2D image data', 'Invalid Input', ...
                    "CloseFcn",'uiresume(gcbf)')
                uiwait(app.ROIMaskCreatorUIFigure)
                app.FigureCloseRequest;
            end
            %
            app.MainApp = MainApp;
            app.imageMask = imgMsk;
            % Replace NaNs with zeros:
            imgData(isnan(imgData)) = 0;
            % Replace -Inf and Inf with min and max values of the image
            % data:
            idxInf = isinf(imgData);
            if any(idxInf)
                imgData(idxInf & imgData == min(imgData(:))) = min(imgData(~idxInf(:))); % Replace -Inf with min of imgData.
                imgData(idxInf & imgData == max(imgData(:))) = max(imgData(~idxInf(:))); % Replace -Inf with min of imgData.
            end
            app.image = imgData;
            app.orig_image = imgData;
            % Populate interface component values with image's data:
            app.Slider.Limits = double([min(app.image(imgMsk)) max(app.image(imgMsk))]);
            app.Slider.Value = app.Slider.Limits(1);
            app.ThrLim.Limits = app.Slider.Limits;
            app.ThrLim.Value = app.Slider.Value;
            app.PixelLim.Limits = [0 numel(app.image)];
            % Create Figure and plots:
            f = figure('Name', 'Image Window','NumberTitle','off', 'Position', [0 0 750 500],'Visible','on', "CloseRequestFcn",@app.DoNothing, 'Tag', 'imgFig');
            % Initialize mask
            app.maskArr = zeros(size(app.image),'single');
            
            % Create axes:
            imgAx = subplot(121);
            maskAx = subplot(122);
            bcgAx = axes(); bcgAx.Color = [0 0 0]; bcgAx.Box = 'on';
            overlayAx = axes();
            % Plot image and mask:
            app.imgHandle = imagesc(imgAx, app.image, 'Tag', 'imgData');
            % Plot overlay mask
            app.overlayHandle = imagesc(overlayAx, app.maskArr, 'AlphaData',app.maskArr);
            % Plot Mask
            app.maskHandle = imagesc(maskAx, app.maskArr);
            % set colormaps:
            cmap = colormap('cool');
%             cmap(1,:) = [0 0 0];
            app.myCmap = cmap;
            colormap(maskAx, cmap);
            colormap(overlayAx, cmap);
            colormap(imgAx, 'gray');
%             colormap(bcgAx, 'gray');
            % Add titles:
            imgAx.Title.String = 'Image';
            maskAx.Title.String = 'Binary mask';
            % Link properties:
            app.lkPropsObj = linkprop([imgAx, maskAx, overlayAx, bcgAx], {'XLim', 'YLim', 'YDir', 'DataAspectRatio'});            
            set(bcgAx, 'XTick', [], 'YTick', [], 'Position', maskAx.Position, 'NextPlot', 'replaceChildren', 'DataAspectRatio', [1 1 1]);
            set(overlayAx, 'Position', imgAx.Position, 'NextPlot', 'replaceChildren', 'DataAspectRatio', [1 1 1]);
            axis(imgAx, 'off')
            uistack(bcgAx, 'bottom');            
            axis(maskAx, 'off')
            axis(overlayAx, 'off')
            movegui(f, 'center');
            movegui(app.ROIMaskCreatorUIFigure, 'northwest');
            f.Visible = 'on';
            app.ROIMaskCreatorUIFigure.Visible = 'on';
        end

        % Close request function: ROIMaskCreatorUIFigure
        function FigureCloseRequest(app, event)
            disp('deleting app');
            delete(findall(0, 'Tag', 'imgFig'));
            delete(app);
        end

        % Value changed function: NormCheckBx
        function NormCheckBxValueChanged(app, event)
            % This callback normalizes the image
            currVal = app.Slider.Value;
            if app.NormCheckBx.Value
                app.image = (app.image - min(app.image(app.imageMask)))./(max(app.image(app.imageMask)) - min(app.image(app.imageMask)));
                app.Slider.Limits = [0 1];
                app.Slider.Value = double((currVal - min(app.orig_image(app.imageMask))) ./...
                    (max(app.orig_image(app.imageMask)) - min(app.orig_image(app.imageMask)))); % Normalize current Thr. val.
                
            else
                app.image = app.orig_image;
                app.Slider.Limits = double([min(app.image(app.imageMask)) max(app.image(app.imageMask))]);
                app.Slider.Value = double((currVal * (max(app.image(app.imageMask)) - min(app.image(app.imageMask)))) + min(app.image(app.imageMask)));
            end
            % Update Limits of Edit field:
            app.ThrLim.Limits = app.Slider.Limits;
            app.ThrLim.Value = app.Slider.Value;
            % Update image:
            app.imgHandle.CData = app.image;
        end

        % Value changed function: ThrLim
        function ThrLimValueChanged(app, event)
            % This callback updates the mask threshold level
            app.Slider.Value = app.ThrLim.Value;
            app.updateMask(app.ThrLim.Value, true);
            app.overlayLabels;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % This callback updates the mask threshold level
            app.ThrLim.Value = event.Value;
            app.updateMask(event.Value, false);
        end

        % Button pushed function: CreateROIsButton
        function CreateROIsButtonPushed(app, event)
            % This callback creates the array of ROIs and saves to the
            % "newMasks" property of ROImanager
            if ~any(app.maskArr(:))
                w = warndlg('No ROIs found! Change threshold levels and try again!', 'Failed to find ROIs');
                waitfor(w)
                return
            end
            app.MainApp.newMasks = app.maskArr;
            app.MainApp.newMaskColors = app.myCmap;
            app.FigureCloseRequest;
        end

        % Callback function: PixelLim, RemovesmallholesCheckBox, 
        % SelDirGroup, Slider, SmoothbordersCheckBox
        function UpdateROIs(app, event)
            app.updateMask(app.Slider.Value, true);
            app.overlayLabels;
        end

        % Button pushed function: AutosetButton
        function AutosetButtonPushed(app, event)
            % Sets the threshold to 2 SD of the pixels from the image or
            % inside the logical mask.
            
            disp('setting auto thr.')
            % Calculate the standard deviation of the image pixels:
            STD = std(app.orig_image(app.imageMask),0,"all","omitnan");
            avg = mean(app.orig_image(app.imageMask),"all","omitnan");
            if contains(app.SelDirGroup.SelectedObject.Text, 'above','IgnoreCase',true)
                thr = avg + 2*STD;
            else
                thr = avg - 2*STD;
            end
            
            if thr > max(app.orig_image(app.imageMask),[],"all","omitnan")
                thr = max(app.orig_image(app.imageMask),[],"all","omitnan");
            end
            
            if thr < min(app.orig_image(app.imageMask),[],"all","omitnan")
                thr = min(app.orig_image(app.imageMask),[],"all","omitnan");
            end
            
            if app.NormCheckBx.Value
                thr = (thr - min(app.orig_image(app.imageMask),[],"all","omitnan"))./ ( max(app.orig_image(app.imageMask),[],"all","omitnan") - min(app.orig_image(app.imageMask),[],"all","omitnan") );
            end
            % Set threshold:
            app.ThrLim.Value = double(thr);
            % Update all:
            app.ThrLimValueChanged;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIMaskCreatorUIFigure = uifigure('Visible', 'off');
            app.ROIMaskCreatorUIFigure.Name = 'ROIMask Creator';
            app.ROIMaskCreatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.CreateROIsButton = uibutton(app.ROIMaskCreatorUIFigure, 'push');
            app.CreateROIsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateROIsButtonPushed, true);
            app.CreateROIsButton.Text = 'Create ROIs';
            app.ControlsPanel = uipanel(app.ROIMaskCreatorUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.NormCheckBx = uicheckbox(app.ControlsPanel);
            app.NormCheckBx.ValueChangedFcn = createCallbackFcn(app, @NormCheckBxValueChanged, true);
            app.NormCheckBx.Text = 'Normalize';
            app.ThresholdlevelLabel = uilabel(app.ControlsPanel);
            app.ThresholdlevelLabel.Tooltip = {'Threshold level'};
            app.ThresholdlevelLabel.Text = 'Threshold level';
            app.Slider = uislider(app.ControlsPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateROIs, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ThrLim = uieditfield(app.ControlsPanel, 'numeric');
            app.ThrLim.ValueDisplayFormat = '%.3f';
            app.ThrLim.ValueChangedFcn = createCallbackFcn(app, @ThrLimValueChanged, true);
            app.ExclROIsSpinnerLabel = uilabel(app.ControlsPanel);
            app.ExclROIsSpinnerLabel.Text = 'Excl. ROIs';
            app.PixelLim = uispinner(app.ControlsPanel);
            app.PixelLim.Limits = [1 1000];
            app.PixelLim.ValueChangedFcn = createCallbackFcn(app, @UpdateROIs, true);
            app.PixelLim.Tooltip = {'Removes ROIs with a number of pixels less than N.'};
            app.PixelLim.Value = 1;
            app.SelDirGroup = uibuttongroup(app.ControlsPanel);
            app.SelDirGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateROIs, true);
            app.SelDirGroup.Title = 'Sel. direction';
            app.abovethrButton = uiradiobutton(app.SelDirGroup);
            app.abovethrButton.Text = 'above thr.';
            app.abovethrButton.Value = true;
            app.belowthrButton = uiradiobutton(app.SelDirGroup);
            app.belowthrButton.Text = 'below thr.';
            app.RemovesmallholesCheckBox = uicheckbox(app.ControlsPanel);
            app.RemovesmallholesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROIs, true);
            app.RemovesmallholesCheckBox.Text = 'Remove small holes';
            app.SmoothbordersCheckBox = uicheckbox(app.ControlsPanel);
            app.SmoothbordersCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROIs, true);
            app.SmoothbordersCheckBox.Text = 'Smooth borders';
            app.AutosetButton = uibutton(app.ControlsPanel, 'push');
            app.AutosetButton.ButtonPushedFcn = createCallbackFcn(app, @AutosetButtonPushed, true);
            app.AutosetButton.Tooltip = {'Click here to set the threshold at 2 standard deviations of the image pixel values.'};
            app.AutosetButton.Text = 'Auto set';
            % Show the figure after all components are created
            app.ROIMaskCreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIMask_creator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIMaskCreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIMaskCreatorUIFigure)
        end
    end
end