classdef ROImanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        NewROIfileMenu                matlab.ui.container.Menu
        OpenROImasksfileMenu          matlab.ui.container.Menu
        OpenImageMenu                 matlab.ui.container.Menu
        menuSep1                      matlab.ui.container.Menu
        SavefileMenu                  matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        menuSep2                      matlab.ui.container.Menu
        ImportROIsfromMenu            matlab.ui.container.Menu
        matfileMenu                   matlab.ui.container.Menu
        workspaceMenu                 matlab.ui.container.Menu
        ExporttabletoCSVMenu          matlab.ui.container.Menu
        CreateMenu                    matlab.ui.container.Menu
        PolygonMenu                   matlab.ui.container.Menu
        PointMenu                     matlab.ui.container.Menu
        MouseAllenBrainAtlasMenu      matlab.ui.container.Menu
        AreasMenu                     matlab.ui.container.Menu
        SelectareasMenu               matlab.ui.container.Menu
        OptionsMenu_areas             matlab.ui.container.Menu
        CentroidsMenu                 matlab.ui.container.Menu
        SelectcentroidsMenu           matlab.ui.container.Menu
        OptionsMenu_centroids         matlab.ui.container.Menu
        FromthresholdMenu             matlab.ui.container.Menu
        menuSep4                      matlab.ui.container.Menu
        DeleteallROIsMenu             matlab.ui.container.Menu
        SelectionMenu                 matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ColorMenu                     matlab.ui.container.Menu
        ShapeMenu                     matlab.ui.container.Menu
        ConstrainededitMenu           matlab.ui.container.Menu
        UnconstrainededitMenu         matlab.ui.container.Menu
        DeleteROIMenu                 matlab.ui.container.Menu
        ROImathMenu                   matlab.ui.container.Menu
        splitMenu                     matlab.ui.container.Menu
        mergeMenu                     matlab.ui.container.Menu
        intersectMenu                 matlab.ui.container.Menu
        XORMenu                       matlab.ui.container.Menu
        subtractMenu                  matlab.ui.container.Menu
        ImageMenu                     matlab.ui.container.Menu
        SetoriginMenu                 matlab.ui.container.Menu
        NewOriginMenu                 matlab.ui.container.Menu
        ImportOrigfromfileMenu        matlab.ui.container.Menu
        AlignimagetooriginMenu        matlab.ui.container.Menu
        SetpixelsizeMenu              matlab.ui.container.Menu
        SetcolormapMenu               matlab.ui.container.Menu
        MaskMenu                      matlab.ui.container.Menu
        DrawnewMenu                   matlab.ui.container.Menu
        ImportMskfromfileMenu_2       matlab.ui.container.Menu
        ShowMenu                      matlab.ui.container.Menu
        DeleteMenu                    matlab.ui.container.Menu
        ImagereferencefileMenu        matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        ImportImgRefMenu              matlab.ui.container.Menu
        menuSep3                      matlab.ui.container.Menu
        ResetParametersMenu           matlab.ui.container.Menu
        SaveParamstometadatafileMenu  matlab.ui.container.Menu
        UITable                       matlab.ui.control.Table
        ListofRegionsofInterestLabel  matlab.ui.control.Label
        OverlaymodesButtonGroup       matlab.ui.container.ButtonGroup
        contoursonlyButton            matlab.ui.control.RadioButton
        highlightpixelsButton         matlab.ui.control.RadioButton
        normalButton                  matlab.ui.control.RadioButton
        ImageParamsPanel              matlab.ui.container.Panel
        origxyEditFieldLabel          matlab.ui.control.Label
        origxyEditField               matlab.ui.control.EditField
        pxmmEditFieldLabel            matlab.ui.control.Label
        pxmmEditField                 matlab.ui.control.EditField
        cmapEditFieldLabel            matlab.ui.control.Label
        cmapEditField                 matlab.ui.control.EditField
        logicalmskLabel               matlab.ui.control.Label
        logicalmskEditField           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MainApp % Handle to caller app if in "Add-on" mode. If not, it is empty.
        imageHandle% Handle of image plotted in Ax.
        b_StandAlone % Boolean to indicate one of 2 modes ( 0 = Add-ON ; 1 = StandAlone);
        AllenAtlas % Structure of Top Projection coordinates of cortical areas from Mouse Allen Brain Atlas.
        figHandle % Handle to Image window figure.
        OptsWindow % Handle to imagApps_Opts App
        ROI_file % fullpath of current ROI file.
        axChildren_handle % handles of current Axis children. Used in "lockImage" function to recover their handle visibility.
        Pt_info = struct('currPt',[0 0], 'b_trackPt', false, 'Pt_list', []) % Information for single/multiple point ROIs.
        %% EVENT LISTENERS:
        elPt  % event listener for Point drawing
        elImg  % event listener for Image changing in Add-on mode.
        elPreParams % event listener for changes in image parameters ("image_info" structure).
        elPostParams
        pre_params % copy of "image_info" structure before it is changed.
        figCallbacks = struct('original', {}, 'current', {}) % Structure containing handles of figure callbacks (Used in app.lockAxChild function when app is run as "Add-on" mode.
        % Default structures:
        ROI_list_def  = struct('Name',[],'Color',[],'Type',[],'Shape',[], 'DOC',[], 'Stats',[]) % Structure containing the information of ROIs.
        image_info_def = struct('imageData', [],'refPt', [0,0], 'pxPermm', 0, 'cmap', [],'clims', [], 'reference_frame',[],...
            'logical_mask',[], 'datFile', [])
        % Structure containing :
        % "imageData" = 2-D matrix containing image displayed; "refPtr" = Position (X,Y) for the origin (0,0) point of the
        % image; "pxPermmm" = pixel ratio in pixels/milimeter; "cmap" = colormap of image;
        % "clims" = colormap limits; "reference_frame" = same as imageData, however, no mask is applied.
        % "logical_mask" = logical mask applied during DrawMask callback or
        % stored from ImportMaksFromFile callback.
        % "datFile" = full path of .dat file from which the image was
        % extracted (empty, if the image comes from other sources).
        rootDir % umIT's directory.
    end
    
    properties (Access = public, SetObservable)
        ROI_list % This variable will be set at startup with it's default value (see above).
        Ax % Axis from secondary app (ROImanager_ImageWindow) / StandAlone Mode;
        image_info % This variable will be set at startup with it's default value (see above).
        AllenROIopts = struct('Area', struct('n_shrinkPix', 0), ...
            'Centroid', struct('Radius', 1, 'Unit', 'px'), 'Select', '') % Structure containing all default parameters of Mouse Allen Brain ROIs.
        n_msk % Number of regions of to draw mask.
        mskObj = struct('Shape', [], 'Handle', []) % Structure containing PolyShape representing Mask and its plot handle.
        overlayHandle = {} %  Handle to ROI plots over image.
        newMasks % Array of masks imported using the ROIMask_creator app or from a file/workspace variable.
    end
    
    events
        % These events exist to notify listeners in DataViewer
        
        updateROI
        updateImgParams
        updateMask
    end
    methods (Access = private)
        
        function checkInput(app, input)
            % CHECKINPUT validates if the input to ROImanager is of the
            % following types:
            % 1) empty: Creates a figure with options to the user to load
            % image.
            % 2) Image: Image in 2 formats:
            %      a) 2-D numerical matrix.
            %      b) RGB image. In this case, it will be transformed in a
            %      grayscale image.
            % 3) Axis handle: Valid Axis handle (NOT UIAxes) from another
            % app (Add-on mode).
            
            app.b_StandAlone = true;
            if isempty(input)
                % If no input is provided:
                %                 app.image_info.imageData = app.loadImage;
                % Do nothing!!%
            elseif ischar(input) && endsWith(input, '.mat') && isfile(input) && contains(input, 'ROImasks_')
                % If the input is a valid path for a ROI file:
                app.loadROIfile(input);
                app.ROI_sanity_check;
                app.updateTable;
            elseif ismatrix(input) && isnumeric(input)
                % If the input is a 2D matrix:
                app.image_info.imageData = input;
            elseif isnumeric(input) && ndims(input) == 3 && size(input,3) == 3
                % If the Input is a RGB image:
                app.image_info.imageData = single(rgb2gray(input));
            elseif ischar(input) && endsWith(input, '.dat') && isfile(input)
                % If the input is an existing .dat file created using umIT:
                % Save fullpath of .DAT file to datFile field of
                % image_info:
                [folder,~,~] = fileparts(input);
                if isempty(folder)
                    app.image_info.datFile = fullfile(pwd, input);
                else
                    app.image_info.datFile = input;
                end
                % Map the .dat file:
                [mData, metaData] = mapDatFile(input);%#ok
                % Get dimension names:
                dim_names = metaData.dim_names;
                % update reference point with data's metadata:
                if isprop(metaData, 'refPt')
                    app.image_info.refPt = metaData.refPt;
                end
                % update pixel size with data's metadata:
                if isprop(metaData, 'pxPermm')
                    app.image_info.pxPermm = metaData.pxPermm;
                end
                % Get the first frame of all dimensions and save to image_info.imageData:
                [~, locs] = ismember({'Y','X'}, dim_names);
                ones_str = repmat({'1'},1,length(dim_names));
                for i = 1:length(locs)
                    ones_str{locs(i)} = ':';
                end
                eval(['app.image_info.imageData = squeeze(mData.Data.(metaData.datName)(' strjoin(ones_str,',') '));']);
                % Enable specific buttons:
                app.SaveParamstometadatafileMenu.Enable = 'on';
            elseif isa(input, 'matlab.graphics.axis.Axes') && isa(app.MainApp, 'DataViewer')
                % If the input is an Axis and its only Child is Image:
                app.Ax = input;
                % Find the "image" object among the axis children:
                img = findobj(input, 'Type', 'Image');
                app.image_info.imageData = img.CData;
                app.image_info.datFile = app.MainApp.inputFile;
                app.imageHandle = img;
                app.figHandle = app.Ax.Parent;
                % update reference point with data's metadata:
                if isprop(app.MainApp.dataInfo.MetaMap, 'refPt')
                    app.image_info.refPt = app.MainApp.dataInfo.MetaMap.refPt;
                end
                % update pixel size with data's metadata:
                if isprop(app.MainApp.dataInfo.MetaMap, 'pxPermm')
                    app.image_info.pxPermm = app.MainApp.dataInfo.MetaMap.pxPermm;
                end
                %%%% The line below is disable for now because it is too
                %%%% slow for a high number of ROIs.
                % Create listener to update ROI pixel stats when the original image has changed:
                %                 app.elImg = addlistener(img,'CData','PostSet',@(src, evnt) app.changeImg);
                app.b_StandAlone = false;
                % Get colormap from Axis:
                app.image_info.cmap = colormap(app.Ax);
                % Enable specific buttons:
                app.SaveParamstometadatafileMenu.Enable = 'on';
            else
                % Otherwise thrown an error message and closes the app:
                uialert(app.UIFigure, {'Invalid Input. Input must be one of these types :', ...
                    '2-D numerical matrix', 'RGB image', 'or a valid path to a ROI file'}, 'Failed to open data!',...
                    'Icon', 'error', 'Modal',true, 'CloseFcn', @(a,b) app.UIFigureCloseRequest);
            end
            % Create reference frame from imageData:
            app.image_info.reference_frame = app.image_info.imageData;
            % Create CLims from image:
            app.image_info.clims = [min(app.image_info.imageData(:), [], 'omitnan'),...
                max(app.image_info.imageData(:), [], 'omitnan')];
            app.image_info.clims_def = app.image_info.clims;
            % Create empty logical mask:
            app.image_info.logical_mask = true(size(app.image_info.imageData));
        end
        
        function imageData = loadImage(app)
            % LOADIMAGE create GUIs to open Images from File:
            % The accepted file formats are:
            % 1) .MAT file containing 2-D matrices or RGB images.
            % 2) Any graphics file format accepted by Matlab built-in function "imread".
            % Output:
            % imageData (2D matrix) : image loaded.
            
            imageData = [];
            [filename, path] = uigetfile({'*.dat', 'Binary Files (*.dat)'; '*.mat', 'MAT-files (*.mat)';...
                '*.png; *.jpeg; *.tif', 'Common image Files (*.png; *.jpeg; *.tif)'; '*.*', 'All Files (*.*)'});
            
            if filename == 0
                disp('Operation cancelled by User')
                return
            end
            
            if endsWith(filename, '.mat')
                mD = matfile(fullfile(path, filename));
                varList = setdiff(properties(mD), {'Properties'});
                [idx, tf] = listdlg("ListString", varList, "PromptString", {'Select the image' ;'from the variables below:'}, ...
                    "SelectionMode", 'single');
                if tf
                    imageData = mD.(varList{idx});
                else
                    disp('Operation cancelled by User')
                    return
                end
            elseif endsWith(filename, '.dat')
                % If the input is an existing .dat file created using umIT:
                % Save fullpath of .DAT file to datFile field of
                % image_info:
                app.image_info.datFile = fullfile(path, filename);
                % Map the .dat file:
                [mData, metaData] = mapDatFile(fullfile(path, filename));%#ok
                % Get dimension names:
                dim_names = metaData.dim_names;
                % Get the first frame of all dimensions and save to image_info.imageData:
                [~, locs] = ismember({'Y','X'}, dim_names);
                ones_str = repmat({'1'},1,length(dim_names));
                for i = 1:length(locs)
                    ones_str{locs(i)} = ':';
                end
                eval(['imageData = squeeze(mData.Data.(metaData.datName)(' strjoin(ones_str,',') '));']);
                
            else
                try
                    RGB = imread(fullfile(path, filename));
                    if ndims(RGB) == 3
                        % if the image is a n x m x 3 (RGB) matrix,
                        % transform it to grayscale (n x m).
                        imageData = rgb2gray(RGB);
                    elseif ismatrix(RGB)
                        % if the image is already a n x m matrix:
                        imageData = RGB;
                    else
                        error('Invalid Image file!');
                    end
                    imageData = single(imageData);
                catch ME
                    uialert(app.UIFigure, ME.message, 'Error while loading Image file. The app will close now.', "CloseFcn",@(a,b) app.UIFigureCloseRequest);
                end
            end
            % Save CLim values in image_info structure:
            app.image_info.clims = [min(imageData(:), [], 'omitnan') max(imageData(:), [], 'omitnan')];
        end
        
        function lockAxChild(app, lock_type, b_lock)
            % LOCKAXCHILD changes the "NextPlot" property of "Ax" and the HandleVisibility property of
            % the axis Child(ren) to avoid deleting the plots while
            % overlaying other plots.
            % Inputs:
            % lock_type (str) = {"image", "all"}:
            % "image" : locks the image only.
            % "all"   : locks the image and all ROIs displayed.
            % b_lock (bool) : true = locks the element, false = releases the
            % element.
            
            if b_lock
                tag = "off";
            else
                tag = "on";
            end
            
            % Set axis NextPlot to "replacechildren":
            set(app.Ax, 'NextPlot', 'replacechildren');
            % Set "HandleVisibility" property of image:
            set(app.imageHandle, 'HandleVisibility', tag);
            
            % Keep zero lines if they exist:
            h = findall(app.Ax,'Tag', 'MyZeroLine');
            set(h, 'HandleVisibility', tag);
            % Keep Logical mask
            g = findall(app.Ax,'Tag', 'MskShape');
            set(g, 'HandleVisibility', tag);
            % Set "HandleVisibility" property of all ROIs displayed:
            if strcmp(lock_type, 'all') && iscell(app.overlayHandle)
                cellfun(@(x) set(x, 'HandleVisibility', tag), app.overlayHandle)
            end
        end
        
        function displayImage(app)
            % DISPLAYIMAGE opens a figure with "app.image_info.imageData" as
            % input and saves axis and image handles.
            if isempty(app.Ax)
                app.figHandle = figure('Name', 'ROImanager -- Image window', 'NumberTitle',"off",...
                    'MenuBar',"none", 'ToolBar',"figure",'CloseRequestFcn',@DoNothing);
                % Shift figure to land at the right side of the Main-Window:
                p1 = app.UIFigure.Position;
                app.figHandle.Position = [p1(1)+p1(3)+5 p1(2) p1(3) p1(4)-5];
                app.figHandle.WindowButtonMotionFcn = @app.WinButtonMotionFcn_figH;
                app.figHandle.WindowButtonDownFcn = @app.WinButtonDwnFcn_figH;
                app.figHandle.WindowKeyPressFcn = @app.keyPressClbk_figH;
                app.Ax = gca;
            end
            % Get colormap from Axis:
            app.image_info.cmap = colormap(app.Ax);
            
            % Plot image:
            if app.b_StandAlone
                app.imageHandle = imagesc(app.Ax, app.image_info.imageData);
            end
            colormap(app.Ax, app.image_info.cmap);
            colorbar(app.Ax);
            app.Ax.Interactions = [];
            app.Ax.DataAspectRatio = [1 1 1];
            app.Ax.DataAspectRatioMode = 'manual';
            app.Ax.XLim = [1 size(app.image_info.imageData,2)];
            app.Ax.YLim = [1 size(app.image_info.imageData,1)];
            app.Ax.XLabel.String = 'X (pixels)';
            app.Ax.YLabel.String = 'Y (pixels)';
            
            % Update Axis labels with existing img_info:
            app.shiftAx;
            
            function DoNothing(~,~)
                %%% EMPTY %%%
            end
            
        end
        
        function overlayROI(app)
            % OVERLAYROI plots the ROI over the image. It provides 3 modes:
            % 1) normal: Overlay of polyshapes with black contours and
            % coloured semi-transparent Faces.
            % 2) contour only: polyshapes with coloured contours and transparent faces.
            % 3) highlight pixels: creates an opaque mask over pixels outside
            % ROIs.
            
            if isempty(app.UITable.Data)
                % Delete any ROI from image:
                cellfun(@(x) delete(x), app.overlayHandle)
                app.overlayHandle = {};
                return
            end
            idx_show = app.UITable.Data.b_show;
            plottype = app.OverlaymodesButtonGroup.SelectedObject.Text;
            pShapes = [app.ROI_list.Shape];
            % Create new plot if app.overlayHandle is empty:
            % Replace plot handle in "overlayHanle" property:
            app.overlayHandle{1} = plot(app.Ax,pShapes);
            % Erase overlay;
            if numel(app.overlayHandle)> 1
                app.overlayHandle(2) = [];
            end
            
            % Set Display mode of elements:
            switch plottype
                case 'normal'
                    arrayfun(@(x,y) set(x, 'FaceColor', y.Color, 'EdgeColor', [0 0 0]), app.overlayHandle{1}, app.ROI_list);
                    
                case 'contours only'
                    arrayfun(@(x,y) set(x, 'FaceColor', "none", 'EdgeColor', y.Color, 'LineWidth', 1.5), app.overlayHandle{1}, app.ROI_list);
                otherwise
                    arrayfun(@(x,y) set(x, 'FaceColor', "none", 'EdgeColor', y.Color, 'LineWidth', 1.5), app.overlayHandle{1}, app.ROI_list);
                    % Create opaque mask:
                    msk = arrayfun(@(x) x.Stats.ROI_binary_mask, app.ROI_list(idx_show), 'UniformOutput', false);
                    msk = ~any(cat(3,msk{:}),3);
                    hold(app.Ax, 'on')
                    p2 = imagesc(app.Ax, msk, 'AlphaData', .8.*msk);
                    hold(app.Ax, 'off')
                    app.overlayHandle{2} = p2;
            end
            % Set visibility of elements:
            set(app.overlayHandle{1}(idx_show), 'Visible', true)
            set(app.overlayHandle{1}(~idx_show), 'Visible', false)
            % Notify DataViewer:
            if ~app.b_StandAlone
                notify(app, 'updateROI')
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %            Functions for generating ROIs from Mouse Allen Brain Atlas                %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function loadAtlas(app)
            % LOADATLAS saves the Mouse Allen Brain Atlas structure to
            % "AllenAtlas" property.
            a = load(fullfile(app.rootDir, 'GUI', 'DataViz', 'mouse_ctx_borders.mat'));
            app.AllenAtlas = a.atlas;
        end
        
        function selectAreas(app)
            % SELECTAREAS creates a window with an interactive UITable
            % with the list of the mouse cortical areas.
            % Output:
            % idx_area = index of the selected areas in atlas.areatag;
            % Load areas infos into table:
            tdata = table(app.AllenAtlas.areatag, app.AllenAtlas.area_longNames,...
                app.AllenAtlas.area_ClusterNames, true(length(app.AllenAtlas.areatag),1));
            % Load file with pre-selected areas:
            myPrefAreasFile = fullfile(app.rootDir, 'myAllenAreas.mat');
            if isfile(myPrefAreasFile)
                disp('Found custom Atlas area selection!')
                myprefs = load(myPrefAreasFile);
                tdata.Var4 = myprefs.idx_selected;
            else
                myprefs.idx_selected = tdata.Var4;
            end
            % Create Window and UITable:
            fH = uifigure('Name', 'Select Mouse cortical areas -- Close this window to save selection',...
                'NumberTitle', 'off', 'Position', [1 1 600 500], 'Visible', 'off'); movegui(fH, 'center'); fH.Visible = 'on';
            fH.KeyPressFcn = @(src,evnt) toggleTabSelection(src,evnt);
            fH.CloseRequestFcn = @(src,evnt) SaveAndCloseFig(src,evnt,app);
            tab = uitable(fH, 'Position', [60 10 500 480], 'RowStriping',"on");
            tab.Data = tdata;
            tab.ColumnName = {'Acronym', 'Area Name', 'Modality', 'Selected'};
            tab.Tooltip = 'Check boxes to select areas OR highlight boxes, click outside the table and press "Enter"';
            tab.ColumnSortable = [true true true true];
            tab.ColumnEditable = [false false false true];
            tab.CellSelectionCallback = @(src, evnt) selectElement(src,evnt);
            waitfor(fH);
            return
            %%%%%% Callbacks %%%%%
            function toggleTabSelection(src, evnt)
                % Callback of UIFigure. Changes the State of selected cells in
                % tab if user presses "Enter".
                
                if strcmp(evnt.Key, 'return')
                    indx = src.Children.UserData;
                    if isempty(indx)
                        return
                    end
                    
                    if unique(indx(:,2)) ~= 4
                        return
                    end
                    state = src.Children.Data.Var4(indx(:,1));
                    
                    if all(state) || all(~state)
                        src.Children.Data.Var4(indx(:,1)) = ~state;
                    else
                        src.Children.Data.Var4(indx(:,1)) = true;
                    end
                end
            end
            
            function selectElement(src, evnt)
                % Callback function of tab.
                % It stores the selected column indices in
                % "UserData" property of tab.
                src.UserData = evnt.Indices;
            end
            
            function SaveAndCloseFig(src,~, app)
                % CloseRequestFcn callback of fH.
                % It Saves the indices of the selected cortical areas to a
                % new field in app.AllenAtlas structure called
                % "idx_selected".
                
                idx_selected = src.Children.Data.Var4;
                
                if sum(idx_selected) == 0
                    answer = uiconfirm(src, 'You must choose at least ONE area. Go back to the list?', 'Empty Selection list', 'Icon', 'warning');
                    if strcmp(answer, 'OK')
                        return
                    else
                        disp('Operation cancelled by User.')
                        delete(src);
                    end
                else
                    if ~isequaln(myprefs.idx_selected,idx_selected)
                        answer = uiconfirm(src, 'Remember current selection?', 'Save ROI selection for next time',...
                            'Icon', 'info', 'Options', {'Yes', 'No'});
                        if strcmpi(answer, 'yes')
                            save(myPrefAreasFile, 'idx_selected')
                            disp('ROI selection preferences saved!');
                        end
                    end
                    app.AllenAtlas.idx_selected = idx_selected;
                    disp('Data Saved to Allen Atlas')
                    delete(src)
                end
            end
            %%%%%%%%%%%%%%%%%%%
        end
        
        function [ROI_shapes, ROI_names, ROI_colors] = fitAllenMask(app, varargin)
            % FITALLENMASK allows the user to place the ROI interactively
            % over the image.
            % The mask is first placed over the reference point "refPt". If
            % refPt is not defined, the mask is placed in the middle of the
            % axis.
            % Optional Inputs:
            % b_useCentroids : boolean to use centroids instead of whole
            % ROIs.
            % centroidPx_radius : centroid radius in pixels(default = 2).
            % Outputs:
            % ROI_shapes: array of polyshape objects.
            % ROI_names : cell array of CHAR with the IDs of each ROI.
            % ROI_colors : m x 3 matrix with the color of each area.
            % plotHandle : handle of ROI plot.
            p = inputParser;
            addOptional(p,'b_useCentroids', false, @islogical)
            addOptional(p, 'centroidPx_radius', 2, @(x) isscalar(x) & isnumeric(x))
            parse(p, varargin{:});
            % Initialize optional inputs:
            b_useCentroids = p.Results.b_useCentroids;
            centroidPx_radius = p.Results.centroidPx_radius;
            % Initialize empty outputs:
            %             ROI_shapes =[];
            ROI_names = {};
            %             ROI_colors = [];
            
            % Create Mask "msk" and append "Bregma" polyshape:
            msk = [app.AllenAtlas.polyShapes.polyArr; app.AllenAtlas.polyShapes.Bregma];
            % Create temporary mask (to facilitate calculating Bounding box
            % and centroid...)
            tmp_msk = app.AllenAtlas.polyShapes.flat_polyShape;
            
            % Transform (translation and scaling) "msk" to fit the image:
            
            % First, if there is a pixel ratio, use it to scale "msk".
            % If not, scale it to 90% of the image size.
            if app.image_info.pxPermm == 0
                scale_factor = size(app.image_info.imageData,1)/size(app.AllenAtlas.flatAtlas,1);
            else
                scale_factor = app.image_info.pxPermm / app.AllenAtlas.mmpp;
            end
            [Bx, By] = msk(end).centroid;
            msk_refPt = [Bx, By];
            msk = scale(msk,scale_factor, msk_refPt);
            tmp_msk = scale(tmp_msk,scale_factor, msk_refPt);
            % Then, if there is a reference point, overlay "msk" Bregma over it.
            % If not, translate "msk" to be at the middle of the image.
            
            if all(app.image_info.refPt == 0)
                [x,y] = tmp_msk.centroid;
                refPt = [mean(1:size(app.image_info.imageData,2)) mean(1:size(app.image_info.imageData,1))];
            else
                [x,y] = app.AllenAtlas.polyShapes.Bregma.centroid;
                refPt = app.image_info.refPt;
            end
            translation_dist = refPt - [x,y];
            msk = translate(msk, translation_dist);
            tmp_msk = translate(tmp_msk, translation_dist);
            % In case of displaying only centroids:
            if b_useCentroids
                [cx,cy] = arrayfun(@(x) x.centroid, msk(1:end-1)); % Get centroids of areas, except, Bregma.
                new_msk = createCircles(cx, cy, centroidPx_radius);
                ctr_msk = arrayfun(@(x,y) intersect(x,y), new_msk, msk(1:end-1)); % Limit circles to the size of the area.
                msk = [ctr_msk; msk(end); tmp_msk]; % Concatenate centroid mask, Bregma and areas contours mask.
            end
            
            % Set un-selected areas FaceColor to "none":
            idx = app.AllenAtlas.idx_selected;
            plotHandle = plot(app.Ax,msk);
            for i = 1:length(app.AllenAtlas.areatag)
                if ~(idx(i)) && ~b_useCentroids
                    plotHandle(i).FaceColor = 'none';
                elseif ~(idx(i)) && b_useCentroids
                    plotHandle(i).Visible = 'off';
                else
                    plotHandle(i).FaceAlpha = .45;
                end
            end
            % For centroids, set area contours (tmp_msk) mask FaceColor to "none":
            if b_useCentroids
                plotHandle(end).FaceColor = "none";
            end
            
            [xlim, ylim] = tmp_msk.boundingbox;
            % Fit Mask to image:
            [ROI_shapes, ROI_colors]= app.fitShape_constrained(plotHandle, [xlim(1) ylim(1) (xlim(2) - xlim(1)) (ylim(2)-ylim(1))]);
            if isempty(ROI_shapes)
                disp('Operation cancelled by User')
                return
            end
            % Keep only selected ROIs:
            indx = find(idx);
            ROI_shapes = ROI_shapes(indx);
            ROI_colors = ROI_colors(indx,:);
            % Get ROI Names:
            ROI_names = app.AllenAtlas.areatag(indx);
            
            % Help Function:
            function new_shapes = createCircles(cx,cy,r)
                % createCircles create circular polyshapes at coordinates
                % cx,cy with radius r.
                new_shapes = [];
                theta = 0 : 0.1 : 2*pi;
                for ii = 1:length(cx)
                    new_x = r * cos(theta) + cx(ii);
                    new_y = r * sin(theta) + cy(ii);
                    shape = polyshape(new_x, new_y);
                    new_shapes = [new_shapes; shape];
                end
            end
            
        end
        
        function addROItoList(app,polyShape_List, list_of_names, list_of_colors, list_of_types, varargin)
            % ADDROITOLIST adds new ROIs to the ROI list.
            % ROIs with the same name are overwritten (maybe change
            % this behaviour later...)
            
            % Inputs:
            % polyShape_List (array of polyshape)
            % list_of_names  (cell array of CHAR)
            % list_of_colors (n x 3 numeric matrix encoding RGB values)
            % list_of_types (cell array of CHAR) types include : 'Shape',
            % 'Point', 'Mask'.
            % Optional:
            %       list_of_masks (cell array of matrices): binary masks associated with
            %       the shapes in polyShape_List. This input is provided
            %       when the masks already exist..
            
            if isempty([list_of_names{:}])
                return
            end
            
            if isempty(varargin)
                list_of_masks = cell(1,numel(list_of_names));
            else
                list_of_masks = varargin{:};
            end
            
            if isempty(app.ROI_list(1).Name)
                for i = 1:length(list_of_names)
                    app.ROI_list(i).Name = list_of_names{i};
                    app.ROI_list(i).Color = list_of_colors(i,:);
                    app.ROI_list(i).Type = list_of_types{i};
                    app.ROI_list(i).Shape = polyShape_List(i);
                    app.ROI_list(i).DOC = datetime('today');
                    app.ROI_list(i).Stats = app.getStatsFromShape(polyShape_List(i),app.image_info.imageData, list_of_masks{i});
                end
            else
                a = 0;
                tmp_Struct = struct();
                [idxA, LocB] = ismember(list_of_names, {app.ROI_list.Name});
                for i = 1:length(list_of_names)
                    if idxA(i)
                        app.ROI_list(LocB(i)).Color = list_of_colors(i,:);
                        app.ROI_list(LocB(i)).Type = list_of_types{i};
                        app.ROI_list(LocB(i)).Shape = polyShape_List(i);
                        app.ROI_list(LocB(i)).DOC = datetime('today');
                        app.ROI_list(LocB(i)).Stats = app.getStatsFromShape(polyShape_List(i),app.image_info.imageData, list_of_masks{i});
                    else
                        a = a + 1;
                        tmp_Struct(a).Name = list_of_names{i};
                        tmp_Struct(a).Color = list_of_colors(i,:);
                        tmp_Struct(a).Type = list_of_types{i};
                        tmp_Struct(a).Shape = polyShape_List(i);
                        tmp_Struct(a).DOC = datetime('today');
                        tmp_Struct(a).Stats = app.getStatsFromShape(polyShape_List(i),app.image_info.imageData, list_of_masks{i});
                    end
                end
                if ~isempty(fieldnames(tmp_Struct))
                    app.ROI_list = [app.ROI_list, tmp_Struct];
                    %                     disp(['A total of ' num2str(a) ' new ROI(s) added!' ]);
                end
                %                 disp(['A total of ' num2str(sum(idxA)) ' ROI(s) replaced!']);
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%% General functions for Fitting ROIs to image and Plotting %%%%%%%%%%
        
        function [polyShape, color_list] = fitShape_constrained(app, pHandle, rectSize)
            % FITSHAPE_CONSTRAINED creates an interactible rectangle to fit the polyshape object(s) "pShape"
            % to app.Ax. This function allows the translation,
            % rotation and scaling of the polyshape(s).
            % Input:
            % 1) pHandle : polyshape Plot handle.
            % 2) rectSize : size of rectangle that will enclose pShape
            % ([left bot width height]).
            % Ouput:
            % polyShape: updated "pShape" list after positioning of the
            % mask.
            % color_list = cell array containing m by 3 color array / "none" for each shape.
            polyShape = [];
            color_list = [];
            pxRatio = app.image_info.pxPermm;
            unit = 'mm';
            if pxRatio == 0; pxRatio = 1; unit = 'px'; end
            
            app.Ax.Title.String = {'Fit Shape to image and', 'double-click inside to save'};
            rect = drawrectangle(app.Ax,'Position', rectSize, 'FixedAspectRatio', 0, ...
                'Rotatable', true, 'DrawingArea','unlimited', 'Deletable', true);
            addlistener(rect,'MovingROI',@(src,evt) app.updatePolyShapePos(src,evt,pHandle, pxRatio, unit));
            addlistener(rect,'ROIMoved',@(src,evnt) app.rectMoved(src,evnt));
            addlistener(rect,'ROIClicked',@(src,evt) app.closeRect(src,evt, pHandle));
            addlistener(rect,'DeletingROI',@(src,evt) app.closeRect(src,evt,pHandle));
            waitfor(rect);
            if ~all(ishandle(pHandle))
                disp('Handle empty')
                return
            end
            polyShape = [pHandle.Shape];
            color_list = arrayfun(@(x) x.FaceColor, pHandle, 'UniformOutput', false);
            % Replace the word "none" by black RGB values:
            indx = find(strcmp(color_list, 'none'));
            for i = 1:numel(indx)
                color_list{indx(i)} = [0 0 0];
            end
            color_list = cell2mat(color_list);
            % Empty title:
            app.Ax.Title.String = '';
        end
        
        
        function updateTable(app)
            % UPDATETABLE loads/reloads the UITable in the Main GUI with
            % the ROI info contained in app.ROI_list structure.
            if isempty(app.ROI_list) || isempty(app.ROI_list(1).Name)
                app.ROI_list= app.ROI_list_def;
                % Reset table:
                app.initializeTable;
                return
            end
            
            % Create table with Data:
            % Names:
            Names = {app.ROI_list.Name}';
            % Types:
            Type = {app.ROI_list.Type}';
            % Dates of creation:
            DOC = [app.ROI_list.DOC]';
            % Update ROI stats:
            stats_struct = arrayfun(@(x) app.getStatsFromShape(x, app.image_info.imageData,[]), [app.ROI_list.Shape]);
            app.ROI_list = arrayfun(@(x,y) setfield(x, 'Stats', y), app.ROI_list, stats_struct);
            % Get Centroid coordinates:
            centroids = arrayfun(@(x) x.Stats.ShapeCentroid, app.ROI_list, 'UniformOutput', false);
            centroids = cell2mat(centroids');
            centroids = round(centroids,2);
            % Get euclidean distance from origin:
            EuclDistFromOrig = arrayfun(@(x) round(x.Stats.EuclDistFromOrig,2), app.ROI_list);
            % Get number of pixels inside ROI:
            n_pix_inside = arrayfun(@(x) x.Stats.n_pix_inside, app.ROI_list);
            % Get ROI area:
            Area = arrayfun(@(x) x.Stats.ShapeArea, app.ROI_list);
            % Create centroid coordinate cell array:
            Coords_XY = arrayfun(@(x,y) [num2str(x) ', ' num2str(y)], centroids(:,1), centroids(:,2), 'UniformOutput', false);
            % Get descriptive stats of pixel values inside ROI:
            min_max = arrayfun(@(x) ['(' num2str(x.Stats.min) ', ' num2str(x.Stats.max) ')'], app.ROI_list, 'UniformOutput', false);
            med = arrayfun(@(x) x.Stats.median, app.ROI_list, 'UniformOutput', false);
            mean_pm_std = arrayfun(@(x) [num2str(x.Stats.mean) '+-(' num2str(round(x.Stats.std,3)) ')'], app.ROI_list, 'UniformOutput', false);
            % Get list of ROI colors:
            color_list = vertcat(app.ROI_list.Color);
            % Create table with new items:
            newTable = table(true(length(Names),1), false(length(Names),1),  Names, Type, DOC, Coords_XY, EuclDistFromOrig', n_pix_inside', ...
                Area', min_max', med', mean_pm_std');
            newTable.Properties.VariableNames = app.UITable.Data.Properties.VariableNames;
            % Update Area and coordinates Column names if a pixel ratio is set:
            if app.image_info.pxPermm > 0
                app.UITable.ColumnName{6} = 'Coords_mm(X,Y)';
                app.UITable.ColumnName{7} = 'Dist from orig.(mm)';
                app.UITable.ColumnName{9} = 'Area(mm^2)';
            else
                app.UITable.ColumnName{6} = 'Coords_px(X,Y)';
                app.UITable.ColumnName{7} = 'Dist from orig.(px)';
                app.UITable.ColumnName{9} = 'Area(px^2)';
            end
            
            % Re-create table with up-to-date data:
            app.UITable.Data = newTable;
            pause(.2); % Dont know why, but adding a pause, avoids error with cell background color...
            app.UITable.BackgroundColor = color_list;drawnow
            
        end
        
        function [polyShape, shape_color] = drawShape(app,varargin)
            % DRAWSHAPE either creates or edit (unconstrained) a polyshape.
            % If no input is provided, the function creates a new polygon
            % to draw, if a polyshape plot handle is provided as input, it
            % allows the user to edit the shape.
            % Input:
            % Optional:
            %   1) polyShape : polyshape object of existing ROI.
            %   2) shape_color: color (m x 3) of polyShape.
            % Ouput:
            % polyShape: updated/created  polyshape
            % shape_color = polyshape color.
            
            p = inputParser;
            addOptional(p,'polyShape', []);
            addOptional(p,'shape_color', []);
            parse(p, varargin{:});
            polyShape = p.Results.polyShape;
            shape_color = p.Results.shape_color;
            app.Ax.Title.String = {'Draw/edit shape and ', 'double-click inside it to save'};
            
            if isempty(polyShape)
                color = get(app.Ax,'DefaultAxesColorOrder');
                shape_color = color(randi(size(color,1)),:);
                poly_roi = drawpolygon(app.Ax,'DrawingArea','auto', 'Deletable', true, 'Color', shape_color);
            else
                poly_roi = drawpolygon(app.Ax, 'DrawingArea','auto', 'Deletable', true,...
                    'Position', polyShape.Vertices, 'Color', shape_color);
            end
            addlistener(poly_roi,'ROIClicked',@(src,evt) createPolyShape(src, evt));
            %             addlistener(poly_roi,'DeletingROI',@(src,evt) app.closeRect(src, evt, []));
            %             addlistener(poly_roi,'DrawingFinished',@(src,evt) app.closeRect(src, evt,[]));
            if isempty(poly_roi.Position)
                delete(poly_roi)
            end
            waitfor(poly_roi);
            % Empty title:
            app.Ax.Title.String = '';
            if isempty(polyShape)
                disp('Operation Cancelled by User')
                return
            end
            
            function createPolyShape(src,evnt)
                % Callback. Creates polyshape from polygonROI position.
                click = evnt.SelectionType;
                selFace = evnt.SelectedPart;
                if strcmp(click, 'double') && strcmp(selFace, 'face') && ~strcmp(evnt.EventName, 'DeletingROI')
                    polyShape = polyshape(src.Position);
                    delete(src);
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Custom callbacks %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function changeImg(app, ~, ~)
            % This is a custom callback for an event listener that updates
            % the image "CData" property if the image was changed. This
            % works with the Add-On mode of ROImanager.
            
            % Note: This may be too slow for a high number of ROIs.
            disp('Image was changed!')
            % Update image "data":
            app.image_info.imageData = app.imageHandle.CData;
            % Replace pixels outside mask with NaNs:
            app.image_info.imageData(~app.image_info.logical_mask) = NaN;
            % Re-calculate stats of ROIs:
            if ~isempty(fieldnames(app.ROI_list))
                tic;
                for i = 1:length(app.ROI_list)
                    app.ROI_list(i).Stats = app.getStatsFromShape(app.ROI_list(i).Shape, app.image_info.imageData,[]);
                end
                toc
            end
            app.updateTable;
        end
        
        function rectMoved(~,src,~)
            src.Label = '';
        end
        
        function updatePolyShapePos(~,src,evt,polyPlotHandle, pxRatio,unit)
            % Changes the position of polyshape objects in pShapes to translation,
            % rotation and scaling of an images.roi.Rectangle.
            pShapes= [polyPlotHandle.Shape];
            if size(pShapes) == fliplr(size(polyPlotHandle))
                pShapes = pShapes';
            end
            % Positions of the center of the Rectangle:
            CurrentRectCenter = [(evt.CurrentPosition(1) + (evt.CurrentPosition(3)/2)) ...
                (evt.CurrentPosition(2) + (evt.CurrentPosition(4)/2))];
            PreviousRectCenter = [(evt.PreviousPosition(1) + (evt.PreviousPosition(3)/2))...
                (evt.PreviousPosition(2) + (evt.PreviousPosition(4)/2))];
            % Translation:
            if any(CurrentRectCenter ~= PreviousRectCenter)
                pos_dif = CurrentRectCenter - PreviousRectCenter;
                pShapes = arrayfun(@(x) translate(x, pos_dif(1),pos_dif(2)), pShapes);
            end
            % Scaling:
            if evt.CurrentPosition(3) ~= evt.PreviousPosition(3) || evt.CurrentPosition(4) ~= evt.PreviousPosition(4)
                scale_factor(1) = 1 - (evt.PreviousPosition(3) - evt.CurrentPosition(3)) / ...
                    evt.PreviousPosition(3);
                scale_factor(2) = 1 - (evt.PreviousPosition(4) - evt.CurrentPosition(4)) / ...
                    evt.PreviousPosition(4);
                pShapes = arrayfun(@(x) scale(x, scale_factor,CurrentRectCenter), pShapes);
                % Get rectangle size and display value when scaling changes:
                rect_sz = evt.CurrentPosition([3 4])./pxRatio;
                str = ['est. size: ' num2str(round(rect_sz(1),2)) ' by ' num2str(round(rect_sz(2),2))  unit];
                src.Label = str;
            end
            % Rotation:
            if evt.PreviousRotationAngle ~= evt.CurrentRotationAngle
                ang = evt.PreviousRotationAngle - evt.CurrentRotationAngle;
                pShapes = arrayfun(@(x) rotate(x, ang,CurrentRectCenter), pShapes);
            end
            arrayfun(@(x,y) set(x, 'Shape',y), polyPlotHandle, pShapes);
            
        end
        
        function closeRect(~,src,evnt, pH)
            % Callback for drawrectangle function.
            % It deletes the rectangle object if the User double-clicks
            % inside the rectangle.
            if strcmp(evnt.EventName, 'DeletingROI')
                delete(src)
                delete(pH)
                return
            end
            
            click = evnt.SelectionType;
            selFace = evnt.SelectedPart;
            if strcmp(click, 'double') && strcmp(selFace, 'face') && ~strcmp(evnt.EventName, 'DeletingROI')
                delete(src);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function ROI_sanity_check(app)
            % ROI_SANITY_CHECK verifies if all ROIs of type "Shape" are located
            % inside the "image", that all pixels inside have valid values (not
            % NaNs) and that all ROIs have pixels inside (sometimes too tiny ROIS
            % don't have any pixels inside).
            %
            % ROIs with all pixels out of bounds, NaNs or empty (no pixels) are
            % deleted from the ROI_list.
            % In cases where ROIs are partially out of bounds or with NaNs,
            % the user is provided with a choice to ignore, delete or to try
            % to fix it automatically.
            if isempty(app.ROI_list(1).Name) || isempty(fieldnames(app.ROI_list))
                return
            end
            
            % Test for empty ROIs:
            b_hasNoPix = arrayfun(@(x) ~any(sum(x.Stats.ROI_binary_mask(:))), app.ROI_list);
            
            % Test for ROIs out of image:
            isOutOfPic = arrayfun(@(x) checkROIboundaries(x.Shape,x.Stats.ROI_binary_mask,...
                size(app.image_info.imageData)), app.ROI_list);
            
            % Test for ROIs with NaNs:
            hasNaNs = arrayfun(@(x) checkROIforNaNs(x.Stats.ROI_binary_mask, app.image_info.imageData), app.ROI_list);
            hasNaNs(isOutOfPic == 2) = 0; % Since all ROIs out of pictures have NaNs, remove redundancy.
            
            % Test for ROIs outside the logical mask:
            isOutOfMsk = arrayfun(@(x) checkROIforMask(x.Stats.ROI_binary_mask, app.image_info.logical_mask), app.ROI_list);
            
            % Indices of ROIs without pixels:
            tiny_rois = all([b_hasNoPix; (isOutOfPic == 0); (isOutOfMsk == 0)],1);
            
            % ROIs to be deleted:
            toDelete = [tiny_rois; (isOutOfPic == 2); (hasNaNs == 2 & ~tiny_rois); (isOutOfMsk == 2 & ~tiny_rois)];
            
            % Fixable ROIs:
            toFix = [(isOutOfPic == 1); (hasNaNs == 1); (isOutOfMsk == 1)];
            
            % Get indices of ROIs to be deleted:
            indx_del = find(any(toDelete,1));
            % Get indices of reasons to delete:
            indx_del_reason = zeros(size(indx_del));
            for i = 1:length(indx_del)
                indx_del_reason(i) = find(toDelete(:,indx_del(i)), 1, 'first');
            end
            % Get indices of fixable ROIs:
            indx_2Fix = find(any(toFix,1));
            % Get indices of reasons to fix:
            indx_2Fix_reason = zeros(size(indx_2Fix));
            for i = 1:length(indx_2Fix)
                indx_2Fix_reason(i) = find(toFix(:,indx_2Fix(i)), 1, 'first');
            end
            % Show message to user about ROIs to be deleted:
            if ~isempty(indx_del)
                msg = {'The following ROIs are invalid and will be deleted (Name; reason):'};
                reason = {'ROI has no pixels (too small?)','ROI is outside Image', 'ROI pixels are NaNs', 'ROI pixels are outside Mask'};
                
                for i = 1:length(indx_del)
                    str = {['(' app.ROI_list(indx_del(i)).Name '; ' reason{indx_del_reason(i)} ')']};
                    msg = [msg; str];
                end
                uialert(app.UIFigure, msg, 'Invalid ROIs deleted', 'Icon', "warning", 'Modal',true, ...
                    'CloseFcn','uiresume(gcbf)');
                uiwait(app.UIFigure);
                
            end
            % Fixable ROIs:
            indx_fixErr = []; % Instantiate empty list of indices for invalid ROIs after trying to fix them.
            if ~isempty(indx_2Fix)
                msg = {'The following ROIs are partially invalid  and will be fixed (Name; reason):'};
                reason = {'ROI is partially outside Image', 'ROI has NaNs', 'ROI is partially outside Mask'};
                for i = 1:length(indx_2Fix)
                    str = {['(' app.ROI_list(indx_2Fix(i)).Name '; ' reason{indx_2Fix_reason(i)} ')']};
                    msg = [msg; str];%#ok
                end
                uialert(app.UIFigure, msg, 'ROIs partially invalid', 'Icon', "warning", "modal", true, 'CloseFcn','uiresume(gcbf)');
                uiwait(app.UIFigure);
                for i = 1:numel(indx_2Fix)
                    % Create new shape:
                    % Use mask to create NaNs ouside ROIs:
                    img = app.image_info.imageData;
                    img(~app.image_info.logical_mask) = NaN;
                    % Fix shape:
                    newShape = fixROIshape(app.ROI_list(indx_2Fix(i)).Stats.ROI_binary_mask, img);
                    if isempty(newShape)
                        % For cases where the fix yielded an
                        % empty ROI, delete it:
                        indx_del = [indx_del, indx_2Fix(i)];%#ok
                        continue
                    end
                    % Replace shape in list:
                    app.ROI_list(indx_2Fix(i)).Shape = newShape;
                    % Re-calculate ROI statistics:
                    app.ROI_list(indx_2Fix(i)).Stats = app.getStatsFromShape(newShape, app.image_info.imageData,[]);
                    % Check if resulting binary mask has any
                    % pixels inside:
                    if ~any(app.ROI_list(indx_2Fix(i)).Stats.ROI_binary_mask,'all')
                        % For cases where the fix yielded an
                        % empty binary mask, delete it:
                        indx_fixErr = [indx_fixErr, indx_2Fix(i)];%#ok
                        continue
                    end
                end
            end
            % Inform User for invalid ROIs after fixing:
            if ~isempty(indx_fixErr)
                msg = {'The following ROIs became invalid after the fixing process and will be deleted:'};
                for i = 1:length(indx_fixErr)
                    str = {app.ROI_list(indx_fixErr(i)).Name};
                    msg = [msg; str];%#ok
                end
                uialert(app.UIFigure, msg, 'Failed to fix ROIs', 'Icon', "error", "modal", true, 'CloseFcn','uiresume(gcbf)');
                uiwait(app.UIFigure);
            end
            % Delete invalid ROIs from list:
            indx_del = [indx_del; indx_fixErr];
            app.ROI_list(indx_del) = [];
            % Notify DataViewer:
            %             if ~app.b_StandAlone
            %                 notify(app, 'updateROI')
            %             end
            
            % Local functions:
            function isOut_of_image = checkROIboundaries(shape,ROI_msk, img_sz)
                % CHECKROIBOUNDARIES verifies if the ROI pixels (in ROI_msk) are
                % outside the limits of the image size(img_sz).
                % Output:
                % isOut_of_image:
                %                0 = ROI is completely inside the image.
                %                1 = ROI is partially outside.
                %                2 = ROI is completely outside.
                
                % If shape has zero vertices (too small), consider it
                % inside image:
                if shape.NumRegions == 0
                    isOut_of_image = 0;
                    return
                end
                
                if all(any(shape.Vertices > fliplr(img_sz) | shape.Vertices < 1 ,2)) && sum(ROI_msk(:)) == 0
                    % Means that ROI is completely outside the image.
                    isOut_of_image = 2;
                elseif any(shape.Vertices > fliplr(img_sz) | shape.Vertices < 1, 'all') && sum(ROI_msk(:)) > 0
                    % When the ROI has vertices outside the image AND pixels inside.
                    isOut_of_image = 1;
                else
                    % When the ROI is completely inside the image.
                    isOut_of_image = 0;
                end
            end
            
            function hasNaNs = checkROIforNaNs(ROI_msk, image)
                % CHECKROIFORNANS verifies if the ROI pixels have NaNs.
                % Output:
                % hasNaNs:
                %                0 = No NaNs.
                %                1 = Part of ROI pixels have NaNs.
                %                2 = All ROI pixels are NaNs.
                
                pix_vals = image(ROI_msk);
                if all(isnan(pix_vals))
                    % When all values are NaNs:
                    hasNaNs = 2;
                elseif sum(isnan(pix_vals)) < length(pix_vals) && sum(isnan(pix_vals)) > 0
                    % When part of ROI pixels are NaNs:
                    hasNaNs = 1;
                else
                    % When all ROI pixels are not NaNs:
                    hasNaNs = 0;
                end
                
            end
            
            function isOut = checkROIforMask(ROI_msk, logical_msk)
                % This function verifies if the ROI pixels are
                % outside the logical mask.
                % If the logical mask is not set by user, ROI pixels are
                % considered to be inside the mask.
                % Outputs:
                % isOut:
                %               0 = ROI is completely inside the mask.
                %               1 = ROI is partially outside the mask.
                %               2 = ROI is completely outside the mask.
                
                intrSect = ROI_msk & logical_msk;
                if isequaln(intrSect, ROI_msk)
                    isOut = 0;
                elseif any(intrSect(:))
                    isOut = 1;
                else
                    isOut = 2;
                end
                
            end
            
            function [new_shape, new_mask] = fixROIshape(roi_msk, image)
                % FIXROISHAPE creates a new ROI by subtracting the region out of bounds or with
                % NaNs from the original "shape" ROI mask.
                
                % In case of NaN region, subtract the ROI mask from a
                % NaN mask.
                % If it is a case of Shape outside the image, the roi_msk
                % is already valid. So, no operations on the mask are
                % needed.
                new_shape = [];
                new_mask = [];
                % Turn common polyshape warnings off:
                warning('off', 'all')
                %
                b_hasNaNs = any(isnan(image(roi_msk)));
                if b_hasNaNs
                    % update mask for NaNs case:
                    nan_msk = isnan(image);
                    new_mask = roi_msk & ~nan_msk;
                else
                    % Do nothing for ROIS partially outside image:
                    new_mask = roi_msk;
                end
                if sum(new_mask(:)) == 0
                    % Turn polyshape warnings back on:
                    warning('on', 'all')
                    return
                end
                % Create polyshape from roi_msk:
                new_shape = app.mask2Polyshape(new_mask);
                % Turn polyshape warnings back on:
                warning('on', 'all')
            end
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%  Table functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function initializeTable(app)
            % InitializeTable initializes the UITable settings.
            
            % Set UITable:
            app.UITable.ColumnName = {'Show/Hide','Select','Name', 'Type', 'Date_created','Coords_px(X,Y)', 'Dist. from orig.(px)', 'N_pixels_inside', 'Area(px^2)',...
                '(min,max)', 'median', 'mean(+-STD)'};
            app.UITable.ColumnEditable = [true true true false false false false false false false false false];
            app.UITable.ColumnSortable = [true true true true true false false true true false false false];
            app.UITable.RowStriping = 'on';
            
            % Create empty table:
            Data = table([false],[false], {''}, {''}, {[NaT]}, {''}, {''}, {[]}, {[]}, {''}, {[]}, {''});
            Data.Properties.VariableNames = {'b_show','b_select', 'Name', 'Type', 'DOC', 'XYCoords', 'euclDist', 'n_pix_insde', 'Area', ...
                'min_max', 'median', 'mean_pm_std'};
            app.UITable.Data = Data;
            app.UITable.Data(:,:) = [];
            app.UITable.CellEditCallback = @(src,evnt) ROI_cellEditCallback(src,evnt, app);
            app.UITable.CellSelectionCallback = @(src, evnt) ROI_cellSelection(src,evnt);
            app.UITable.DisplayDataChangedFcn = @(src,evnt) ReColorRows(src,evnt, app);
            % Set UITable callbacks:
            function ReColorRows(src,evnt, app)
                % Update Rows Colors when Table is changed.
                
                if strcmp(evnt.Interaction, 'sort')
                    ROI_names = {app.ROI_list.Name}';
                    [~,locB] = ismember(src.DisplayData.Name, ROI_names);
                    src.BackgroundColor = vertcat(app.ROI_list(locB).Color);
                end
                
            end
            
            function ROI_cellEditCallback(src,evnt,app)
                % CellEditCallback of UITable.
                % It creates specific behaviours to columns "Show/Hide",
                % "Name"
                
                switch evnt.Indices(2)
                    case 1
                        % Response to Show/Hide:
                        %                         app.showHideROI
                        app.lockAxChild("image", true)
                        app.overlayROI
                        app.lockAxChild("image", false)
                    case 3
                        % Response to Name:
                        newName = evnt.NewData;
                        if isempty(strip(newName))
                            uialert(app.UIFigure, 'Invalid ROI Name! Empty strings not accepted.', 'Invalid Name', "Modal",true);
                            src.Data.Name(evnt.Indices(1)) = {evnt.PreviousData};
                            return
                        end
                        indx = evnt.Indices(1);
                        % Check for existing name in table:
                        if any(strcmp(newName, {app.ROI_list.Name}))
                            disp('Name already exists in table. Ignored request.');
                            src.Data.Name(evnt.Indices(1)) = {evnt.PreviousData};
                        end
                        app.ROI_list(indx).Name = newName;
                    otherwise
                        return
                end
                
            end
            
            function ROI_cellSelection(src, evnt)
                % Callback function of tab.
                % It stores the selected column indices in
                % "UserData" property of tab.
                src.UserData = evnt.Indices;
            end
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function stats_struct = getStatsFromShape(app,shape,image, roi_msk)
            % GETSTATSFROMSHAPE calculates descriptive statistics of the
            % shape and the image pixels inside it and saves everything in
            % a structure.
            
            % Initialize empty structure:
            stats_struct = struct('ShapeArea', [], 'ShapeCentroid', [], 'EuclDistFromOrig', [], 'ROI_binary_mask', [], 'n_pix_inside', [],...
                'min', [], 'max', [], 'mean', [], 'median', [], 'std', []);
            % Calculate Shape info:
            stats_struct.ShapeArea = shape.area;
            [cx,cy] = shape.centroid;
            stats_struct.ShapeCentroid= [cx,cy] - app.image_info.refPt;
            stats_struct.EuclDistFromOrig = sqrt(abs(app.image_info.refPt(1) - cx)^2 + abs(app.image_info.refPt(2) - cy)^2);
            % Rescale some stats if a pixel ratio was provided:
            if app.image_info.pxPermm > 0
                stats_struct.ShapeArea = stats_struct.ShapeArea/app.image_info.pxPermm^2;
                stats_struct.ShapeCentroid  = stats_struct.ShapeCentroid./app.image_info.pxPermm;
                stats_struct.EuclDistFromOrig = stats_struct.EuclDistFromOrig/app.image_info.pxPermm;
            end
            % Create binary mask from polyshape:
            if isempty(roi_msk)
                roi_msk = app.polyshape2Mask(shape,size(image));
            end
            % If ROI shrinkage is applied:
            if app.AllenROIopts.Area.n_shrinkPix > 0 && strcmp(app.AllenROIopts.Select, 'Area')
                % Shrink ROI:
                roi_msk= bwmorph(roi_msk, 'shrink', app.AllenROIopts.Area.n_shrinkPix);
            end
            % Save ROI mask to Stats structure:
            stats_struct.ROI_binary_mask = roi_msk;
            
            % Calculate Pixel descriptive statistics:
            vals = image(roi_msk);
            stats_struct.n_pix_inside = length(vals);
            stats_struct.min = min(vals, [], 'all', 'omitnan');
            stats_struct.max = max(vals, [], 'all', 'omitnan');
            stats_struct.mean = mean(vals, 'all', 'omitnan');
            stats_struct.median = median(vals, "all", 'omitnan');
            stats_struct.std = std(vals, 0, "all", 'omitnan');
        end
        
        function shiftAx(app)
            % SHIFTAX changes the axis labels to shift the origin (0,0) to
            % app.images_coordinates.refPt.
            
            refPt = round(app.image_info.refPt);
            %             if app.image_info.pxPermm == 0
            %                 set(app.Ax, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
            %                 set(app.Ax, 'YTickMode', 'auto', 'YTickLabelMode', 'auto')
            %                 app.Ax.XLabel.String = 'X (pixels)';
            %                 app.Ax.YLabel.String = 'Y (pixels)';
            %                 return
            %             end
            
            % Set a number of 10 axis ticks:
            
            % Shift X-Axis:
            xLab = linspace(0,app.Ax.XLim(2),10);
            xLab = unique([-xLab xLab]);
            xLoc = xLab + refPt(1);
            
            % Shift Y-Axis:
            yLab = linspace(0,app.Ax.XLim(2),10);
            yLab = unique([-yLab yLab]);
            yLoc = yLab + refPt(2);
            
            % Prepare data for labels:
            % If there is a pixel Per mm ratio, change axis labels:
            if app.image_info.pxPermm > 0
                xLab = round(xLab./app.image_info.pxPermm, 2);
                yLab = round(yLab./app.image_info.pxPermm, 2);
                app.Ax.XLabel.String = 'X (mm)';
                app.Ax.YLabel.String = 'Y (mm)';
            else
                xLab = round(xLab);
                yLab = round(yLab);
                xLoc = round(xLoc);
                yLoc = round(yLoc);
                app.Ax.XLabel.String = 'X (pixels)';
                app.Ax.YLabel.String = 'Y (pixels)';
            end
            set(app.Ax, 'XTick', xLoc(xLoc>=0), 'XTickLabel', arrayfun(@(x) num2str(x), xLab(xLoc>=0),...
                'UniformOutput', false));
            set(app.Ax, 'YTick', yLoc(yLoc>=0), 'YTickLabel', arrayfun(@(x) num2str(x), yLab(yLoc>=0),...
                'UniformOutput', false));
            
            % Add a line to origin (0,0):
            h = findobj('Tag', 'MyZeroLine');
            if isempty(h)
                l = line(app.Ax, [xLoc(xLab == 0) xLoc(xLab == 0) ; app.Ax.XLim]', [app.Ax.YLim ; yLoc(yLab == 0) yLoc(yLab == 0)]', 'Color', 'k');
                arrayfun(@(x) set(x, 'Tag','MyZeroLine', 'HandleVisibility', 'callback'), l);
            else
                h(1).XData = app.Ax.XLim;
                h(1).YData = [yLoc(yLab == 0) yLoc(yLab == 0)];
                h(2).YData = app.Ax.YLim;
                h(2).XData =  [xLoc(xLab == 0) xLoc(xLab == 0)];
            end
        end
        
        function b_check = checkSingleSelection(app, type)
            % CHECKSINGLESELECTION controls for single selection OR
            % multi-selection of elements in UITable.
            % Input:
            % type (str) : {'single', 'multi'};
            % Output:
            % b_check (bool) : TRUE if selection condition is obeyed.
            
            b_check = true;
            if strcmp(type, 'single') && sum(app.UITable.Data.b_select) > 1
                uialert(app.UIFigure, 'Multi-selection not allowed!', 'Single-selection only', 'Icon',"error", "Modal",true);
                b_check = false;
                return
            elseif sum(app.UITable.Data.b_select) == 0
                uialert(app.UIFigure, 'Select an item from the table!', 'Empty selection', 'Icon',"error", "Modal",true);
                b_check = false;
            end
        end
        
        function in_bounds = getBounds(app)
            
            x_lims = get(app.Ax, 'XLim');
            y_lims = get(app.Ax, 'YLim');
            
            in_bounds = (app.Pt_info.currPt(1) >= x_lims(1)) && ...
                (app.Pt_info.currPt(1) <= x_lims(2)) && ...
                (app.Pt_info.currPt(2) >= y_lims(1)) && ...
                (app.Pt_info.currPt(2) <= y_lims(2));
        end
        
        function [newImag, phi] = rotateImage(app, pT, varargin)
            % ROTATEIMAGE rotates the frame so the line between the reference point and the selected point
            % vertically aligned.
            % This function is used by the callback "AlignImage_to_origin".
            % Inputs:
            % pT [x,y] = point selected by user to be aligned with the reference
            % point.
            % phi (numeric scalar)= angle of rotation (degrees).
            % Output:
            % newImag = rotated image.
            % phi = (calculated/input) rotation angle.
            p = inputParser;
            addRequired(p,'app');
            addRequired(p,'pT', @isnumeric);
            addOptional(p,'phi', [], @(x) isscalar(x) && isnumeric(x) || isempty(x));
            parse(p,app, pT, varargin{:});
            % Inputs:
            app = p.Results.app;
            pT = p.Results.pT;
            phi = p.Results.phi;
            
            if isempty(phi)
                % Calculate angle between reference point and selected point:
                xDif = abs(app.image_info.refPt(1) - pT(1));
                yDif = abs(app.image_info.refPt(2) - pT(2));
                radius = sqrt(xDif^2 + yDif^2);
                theta = atan2([yDif radius], [xDif 0]);
                phi = theta(2) - theta(1);
                phi = rad2deg(phi);
                if pT(1) > app.image_info.refPt(1) && pT(2) < app.image_info.refPt(2)
                    phi = 180 + phi;
                elseif pT(1) < app.image_info.refPt(1) && pT(2) < app.image_info.refPt(2)
                    phi = rad2deg(theta(1) - theta(2)) + 180;
                elseif pT(1) > app.image_info.refPt(1) && pT(2) > app.image_info.refPt(2)
                    phi = rad2deg(theta(1) - theta(2));
                end
            end
            % Rotate image:
            newImag = rotateAround(app.image_info.imageData, app.image_info.refPt(2), app.image_info.refPt(1), phi, 'bicubic');
            
        end
        
        function WinButtonMotionFcn_figH(app, ~, ~)
            % This callback works to draw Point ROIs.
            if ~app.Pt_info.b_trackPt
                return
            end
            coords = get(app.Ax, 'CurrentPoint');
            app.Pt_info.currPt = round(coords(1,1:2));
            crossHair = findall(app.figHandle,'Tag', 'selPixMarker');
            gs = findall(app.figHandle,'Tag', 'Sqr');
            % Check if cursor is over axis and update crosshair position:
            b_in_bounds = app.getBounds;
            if b_in_bounds
                set(crossHair(1), 'XData', app.Ax.XLim, 'YData', [app.Pt_info.currPt(2) app.Pt_info.currPt(2)])
                set(crossHair(2), 'XData', [app.Pt_info.currPt(1) app.Pt_info.currPt(1)], 'YData', app.Ax.YLim);
                set(gs, 'XData', app.Pt_info.currPt(1), 'YData', app.Pt_info.currPt(2));
            end
        end
        
        function WinButtonDwnFcn_figH(app,~,~)
            % WindowButtonDown callback from Image figure (app.figHandle)
            click = get(app.figHandle, 'SelectionType');
            in_bounds = app.getBounds;
            gs = findall(app.figHandle,'Tag', 'Sqr');
            if in_bounds && strcmp(click, 'open') && ~isempty(gs)
                %                 app.Pt_info.b_trackPt = false;
                
                gs.UserData = {app.Pt_info.currPt};
                %                 notify(gs, 'UserData');
                %% CALL METHOD TO SAVE POINT ROI %%%
                
                %                 % Delete handles
                %                 app.Ax.Title.String = '';
                %                 crossHair = findall(app.figHandle,'Tag', 'selPixMarker');
                %                 gs = findall(app.figHandle,'Tag', 'Sqr');
                %                 delete(crossHair)
                %                 delete(gs)
            end
            
        end
        
        function keyPressClbk_figH(app,~,event)
            % KeyPress callback of Image figure (figHandle) to erase
            % crossHair if user presses ESCAPE.
            if strcmp(event.Key, 'escape') && app.Pt_info.b_trackPt
                app.Pt_info.b_trackPt = false;
                app.Ax.Title.String = '';
                app.Pt_info.currPt = [];
                crossHair = findall(app.figHandle,'Tag', 'selPixMarker');
                gs = findall(app.figHandle,'Tag', 'Sqr');
                delete(crossHair)
                delete(gs)
                disp('Operation cancelled by User');
            end
        end
        function copyImageInfo(app,~,~)
            % This custom callback copies the "image_info" structure to the
            % property "pre_params" before it is changed. This method works
            % in tandem with the "dispImgParams" method and the
            % "elPreParams" and "elPostParams" event listener objects.
            app.pre_params = app.image_info;
        end
        function dispImgParams(app,~,~)
            % This method updates the image parameters panel in the the
            % main GUI.
            fn = fieldnames(app.pre_params);
            idx = cellfun(@(x) ~isequaln(app.pre_params.(x), app.image_info.(x)),fn);
            if ~any(idx)
                return
            end
            %
            indx = find(idx);
            for i = 1:numel(indx)
                
                switch fn{indx(i)}
                    case 'refPt'
                        if ~isempty(app.image_info.refPt)
                            app.origxyEditField.Value = [num2str(round(app.image_info.refPt(1))) ', ' num2str(round(app.image_info.refPt(2)))];
                        else
                            app.origxyEditField.Value = 'none';
                        end
                    case 'pxPermm'
                        if ~isempty(app.image_info.pxPermm) && app.image_info.pxPermm ~=0
                            app.pxmmEditField.Value = num2str(app.image_info.pxPermm);
                        else
                            app.pxmmEditField.Value = 'none';
                        end
                    case 'cmap'
                        if isempty(app.image_info.cmap)
                            app.cmapEditField.Value = 'none';
                            continue
                        end
                        % Try to find current colormap name:
                        def_cmaps = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                            'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag', 'white'};
                        b_isDef = false;
                        % create dummy figure and axis:
                        f = figure('Visible', 'off');
                        ax = axes('Parent', f);
                        for j = 1:numel(def_cmaps)
                            if isequaln(app.image_info.cmap, colormap(ax,def_cmaps{j}))
                                b_isDef = true;
                                break
                            end
                        end
                        close(f);
                        if b_isDef
                            app.cmapEditField.Value = def_cmaps{j};
                        else
                            app.cmapEditField.Value = 'custom';
                        end
                    case 'logical_mask'
                        if isempty(app.image_info.logical_mask)
                            app.logicalmskEditField.Value = 'none';
                        elseif all(app.image_info.logical_mask(:))
                            app.logicalmskEditField.Value = 'none';
                        else
                            app.logicalmskEditField.Value = 'Set';
                        end
                        
                    otherwise
                        return
                end
            end
            
            notify(app, 'updateImgParams')
        end
        
        function loadROIfile(app, ROI_filename)
            % This function loads the variables contained in a valid ROI file "ROImasks_xxxx.mat"
            % into the UITable from the main GUI and updates the image
            % window.
            
            mD = load(ROI_filename);
            % Check if .MAT file contains ROI variables:
            if ~all(ismember({'ROI_info', 'img_info'}, fieldnames(mD)))
                uialert(app.UIFigure,'Wrong ROI file. ROI file must contain: "ROI_info" and "img_info" variables', 'Wrong file',...
                    'Icon', "error", "Modal",true);
                return
            end
            
            % Check if there is an image open:
            if isempty(app.image_info.imageData)
                % If there is no image, load image from img_info structure;
                app.image_info.imageData = mD.img_info.imageData;
                app.image_info.clims = mD.img_info.clims;
                app.image_info.clims_def = app.image_info.clims;
                app.image_info.datFile = mD.img_info.datFile;
                
            else
                % If there is already an image open, check if it has the
                % same size as the one in img_info.data:
                if size(mD.img_info.imageData) ~= size(app.image_info.imageData)
                    % if the size is different, throw an error message:
                    uialert(app.UIFigure, {'Cannot open ROI file!' ['Current image size (' num2str(size(app.image_info.imageData,1)) 'x' num2str(size(app.image_info.imageData,2))...
                        ') is different from the one saved in ROI file (' num2str(size(mD.img_info.imageData,1)) 'x' num2str(size(mD.img_info.imageData,2)) ')']},...
                        'Failed to open ROI file', 'Icon', 'error','Modal',true);
                    return
                end
                
                % If the image is different, ask user to keep current image
                % or replace with the one in the ROI file:
                
                if ~isequaln(mD.img_info.imageData, app.image_info.imageData) && app.b_StandAlone
                    answer = uiconfirm(app.UIFigure, 'Replace current image with the one saved in ROI file?', 'Replace current image?',...
                        'Options', {'Yes', 'No'}, 'DefaultOption', 1, 'CancelOption', 2 ,'Icon', "question");
                    if strcmp(answer, 'Yes')
                        app.image_info.imageData = mD.img_info.imageData;
                        app.image_info.clims = mD.img_info.clims;
                    end
                end
                
            end
            % Clear existing plot overlays:
            for i = 1:numel(app.overlayHandle)
                p = app.overlayHandle{i};
                delete(p);
            end
            app.overlayHandle = {};
            % Save ROI filename in app:
            app.ROI_file = ROI_filename;
            % load new data into ROI_list:
            app.ROI_list = mD.ROI_info;
            % load img_info data:
            app.image_info.refPt = mD.img_info.refPt;
            app.image_info.pxPermm = mD.img_info.pxPermm;
            app.image_info.cmap = mD.img_info.cmap;
            app.image_info.logical_mask = mD.img_info.logical_mask;
            % Create emtpy table:
            app.initializeTable;
            % Show image:
            app.displayImage;
            % Update Axes:
            app.shiftAx;
            % Update ROI stats based on data from current image:
            %             ROI_shapes = [app.ROI_list.Shape];
            %             ROI_names = {app.ROI_list.Name};
            %             ROI_colors = cell2mat({app.ROI_list.Color}');
            %             Type_names = {app.ROI_list.Type}';
            % %             app.addROItoList(ROI_shapes, ROI_names, ROI_colors, Type_names);
            % Populate table with ROI data:
            %             app.updateTable;
        end


        function switchFigbehaviour(app, behaviour)
            % This function either disables, replaces or enables some of the
            % existing figure callbacks. This is useful where ROImanager
            % works as "add-on".
            % The figure callbacks are the following:
            %  'WindowButtonDownFcn', 'WindowButtonMotionFcn', 'WindowKeyPressFcn'};
            
            % Input:
            %   behaviour(str):
            %                   "disable": disable figure callbacks and save their handles into a variable.
            %                   "replace": replace some figure callbacks with ROImanager ones (used
            %                               while drawing points only).
            %                   "enable": overwrites current figure callbacks with the ones stored using "disable" flag.
            
            % Figure callbacks:
            clbks_str = {'WindowButtonDownFcn', 'WindowButtonMotionFcn', 'WindowKeyPressFcn'};
            
            % If ROImanager is an add-on:
            switch behaviour
                case 'disable'
                    app.figCallbacks(1).original = cellfun(@(x) get(app.figHandle, x), clbks_str, 'UniformOutput',false);
                    for i = 1:numel(clbks_str)
                        set(app.figHandle, clbks_str{i},  '');
                    end
                    app.figCallbacks.current = {'', '', ''};
                case 'replace'
                    % Replace eventual WindowFcn callbacks with pertinent
                    % callbacks for Point drawing.
                    app.figCallbacks(1).original = cellfun(@(x) get(app.figHandle, x), clbks_str, 'UniformOutput',false);
                    app.figCallbacks(1).current = {@app.WinButtonDwnFcn_figH, @app.WinButtonMotionFcn_figH,  @app.keyPressClbk_figH};
                    for i = 1:numel(clbks_str)
                        set(app.figHandle, clbks_str{i}, app.figCallbacks.current{i});
                    end
                case 'enable'
                    for i = 1:numel(clbks_str)
                        set(app.figHandle, clbks_str{i},  app.figCallbacks.original{i});
                    end
            end
        end
        
        function importROIfromMask(app,varargin)
            % This method uses ROIs stored in app.newMasks property to
            % create new ROIs and add them to the List of regions of
            % interest.
            
            disp('Importing ROIs...')
            indx_masks = unique(app.newMasks(:));
            indx_masks(indx_masks == 0) = [];
            polyShape_List = polyshape.empty(0,length(indx_masks));
            if nargin == 1
                list_of_names = arrayfun(@(x) ['newROI_' num2str(x)], indx_masks, 'UniformOutput', false);
            else
                list_of_names = varargin{1};
            end
            def_colors = get(app.Ax,'DefaultAxesColorOrder');
            list_of_colors = def_colors(randi(size(def_colors,1),length(indx_masks),1),:);
            list_of_types = repmat({'Shape'},1,length(indx_masks));
            list_of_masks = cell(1,numel(list_of_names));
            % Turn common polyshape warnings off:
            warning('off', 'all')
            % Create polyshapes from masks
            disp('Creating ROIs from binary masks...')
            for i = 1:numel(indx_masks)
                list_of_masks{i} = app.newMasks == indx_masks(i);
                % Create polyshape from mask
                polyShape_List(i) = app.mask2Polyshape(list_of_masks{i});
                % Guess type o f shape
                if polyShape_List(i).area == 1
                    list_of_types{i} = 'Point';
                elseif polyShape_List(i).area == 0
                    warning('on', 'all');
                    warning(['Failed to build ROI! The ROI named "' list_of_names{i} '" is too small!']);
                    warning('off', 'all');
                end
            end
            % Turn warnings back on:
            warning('on', 'all')
            % Check for duplicate names before adding the new ROIs to the
            % list:
            
            if ~isempty(app.ROI_list(1).Name)
                [idx] = ismember(list_of_names, {app.ROI_list.Name});
                if any(idx)
                    uialert(app.UIFigure, 'One or more new ROIs have the same name of existing ROIs and were renamed.', 'ROI name conflict found',...
                        "CloseFcn",'uiresume(gcbf)', 'Icon',"warning");
                    uiwait(app.UIFigure);
                    list_of_names(idx) = cellfun(@(x) [x '(2)'], list_of_names(idx), 'UniformOutput',false);
                end
            end
            % Clear the newMasks property:
            app.newMasks = [];
            disp('Adding new masks to ROI list...')
            % Add new ROIs to list:
            app.addROItoList(polyShape_List, list_of_names, list_of_colors, list_of_types, list_of_masks)
            % Update ROIs overlay:
            disp('Drawing new masks...')
            app.lockAxChild("image",true);
            if ~isempty(fieldnames(app.ROI_list))
                app.ROI_sanity_check;
                app.updateTable;
                app.overlayROI;
            end
            app.lockAxChild("image",false);
            disp('Done!')
        end
        
        function b_OK = compareImageSize(app, imgIN)
            % This function checks if the image "imgIN" has the same size
            % than the current frame displayed in the "Image Window".
            b_OK = false;
            
            if ( isempty(imgIN) && ~isempty(app.image_info.imageData) ) || ( isempty(imgIN) && isempty(app.image_info.imageData) )
                return
            end
            
            if ( ~isempty(app.image_info.imageData) && ~isempty(imgIN) && any(size(imgIN) ~= size(app.image_info.imageData)) )
                uialert(app.UIFigure, {'Cannot open image!' ['The size of the new image(' num2str(size(imgIN,1)) 'x' num2str(size(imgIN,2))...
                    ') is different from the one currently open (' num2str(size(app.image_info.imageData,1)) 'x' num2str(size(app.image_info.imageData,2)) ')']},...
                    'Failed to open image', 'Icon', 'error','Modal',true);
                return
            end
            b_OK = true;
        end
        
        function roi_msk = polyshape2Mask(~, shape, imgSz)
            % This function creates a mask from a polyshape with dimensions
            % "imgSz".
            
            % Create mask for regions:
            sRegions = shape.regions;
            %
            roi_msk = false(imgSz);
            % Create mask for regions:
            for i = 1:numel(sRegions)
                list_of_vertices = splitVertices(sRegions(i).Vertices);
                for j = 1:numel(list_of_vertices)
                    if ~sRegions(i).ishole(j)
                        roi_msk = roi_msk | poly2mask(list_of_vertices{j}(:,1),list_of_vertices{j}(:,2), ...
                            imgSz(1), imgSz(2));
                    else
                        roi_msk = roi_msk & ~poly2mask(list_of_vertices{j}(:,1),list_of_vertices{j}(:,2), ...
                            imgSz(1), imgSz(2));
                    end
                end
            end
            
            % Local function
            function out = splitVertices(vertices)
                % This function splits the vertices of a shape using the
                % position of NaNs.
                indxNaN = find(isnan(vertices(:,1)));
                out = cell(1,numel(indxNaN)+1);
                a = 1;
                for k = 1:numel(indxNaN)
                    out{k} = vertices(a:indxNaN(k)-1,:);
                    a = indxNaN(k)+1;
                end
                out{end} = vertices(a:end,:);
                
                assert( ( length(vertices) - length(indxNaN) ) == sum(cellfun(@(x) length(x), out)), 'Failed to split vertices array!')
            end
            
        end
        
        function polyS = mask2Polyshape(~, msk)
            % This function creates a polyshape object from a mask.
            % The mask must have only one region!
            
            %             if numel(regionprops(msk))>1
            %                 error('umIToolbox:ROImanager:mask2PolyShape:InvalidInput', 'Error in ROImanager:mask2PolyShape. The mask must have only one region!');
            %             end
            
            if sum(bwperim(msk),'all') == sum(msk,'all')
                % In case of ROIs made of borders only like a dot or a line:
                polyS = genPolyShapeFromSinglePixel(msk);
            else
                [B, L, n, A] = bwboundaries(msk);
                X = cellfun(@(x) x(:,1), B, 'UniformOutput', false);
                Y = cellfun(@(x) x(:,2), B, 'UniformOutput', false);
                ps_arr = polyshape.empty(0,n);
                for i = 1:n
                    % Create polyshape for each region and subtract holes, if
                    % applicable:
                    ps_arr(i) = polyshape(Y(i), X(i));
                    % find regions and holes:
                    holes = find(A(:,i));
                    if any(holes)
                        ps_holes = polyshape.empty(0,numel(holes));
                        for jj = 1:length(holes)
                            ps_holes(jj) = genPolyShapeFromSinglePixel(L == holes(jj));
                        end
                        ps_arr(i)= subtract(ps_arr(i), union(ps_holes));
                    end
                end
                polyS = union(ps_arr);
            end
            
            % Local function
            
            function ps = genPolyShapeFromSinglePixel(msk)
                % This function creates a polyshape from ROIs containing
                % single pixels or a line where the total number of pixels
                % is equal to the perimeter.
                % THis function builds the ROI pixel by pixel. May be time
                % consuming...
                [x,y] = find(msk);
                arr = polyshape.empty(0,length(x));
                for k = 1:length(x)
                    arr(k) = polyshape([y(k)-.5  y(k)+.5 y(k)+.5 y(k)-.5; ...
                        x(k)-.5 x(k)-.5 x(k)+.5 x(k)+.5]');
                end
                ps = union(arr);
            end
        end


        function [b_OK, roiNames] = plotROImathResults(app, origMsks, origNames, resMsks, defNames)
            % This is a helper function for all ROImath callbacks.
            % This function plots the result of the ROI math, asks for
            % user input to name new ROIs and confirm.
            % Inputs:
            %   origMsks (3D logical array)
            %   origNames (cell array of chars)
            %   resMsks (labeled matrix)
            %   defNames (cell array of chars)
            % Output:
            %   b_OK (bool): TRUE, if the user confirms ROImath operation.
            %   roiNames(cell): array of chars with names of new ROIs.
            
            % Note: The format of "origMsks" as a 3D logical matris instead
            % of a labeled matrix as "resMsks" was chosen to avoid
            % mislabeling areas that due to partially or totally overlapped
            % ROIs.
            
            % Instantiate output variables:
            roiNames = {};
            b_OK = false;
            
            % Guess names of new ROIs:s
            
            % Hide main App:
            app.UIFigure.Visible = 'off';
            f = figure('Name', 'ROI math preview', 'NumberTitle',"off", 'CloseRequestFcn',@DoNothing);
            s1 = subplot(121);
            s2 = subplot(122);
            % Get original ROIs'centroids:
            centroids_orig = zeros([size(origMsks,3),2],'double');
            for i = 1:size(origMsks,3)
                [r,c] = find(bwmorph(origMsks(:,:,i), 'shrink', Inf));
                centroids_orig(i,2)=  mean(r);
                centroids_orig(i,1)=  mean(c);
            end
            % Get new ROIs'centroids:
            centroids_res = zeros([numel(defNames),2],'double');
            indxReg = unique(resMsks(:)); indxReg(indxReg == 0) = [];
            for i = 1:length(indxReg)
                [r,c] = find(bwmorph(resMsks == indxReg(i), 'shrink', Inf));
                centroids_res(i,2)=  mean(r);
                centroids_res(i,1)=  mean(c);
            end
            % Transform original masks to a labeled matrix for better visualization:
            origLab = zeros(size(resMsks));
            for i = 1:size(origMsks,3)
                origLab(origMsks(:,:,i)) = i;
            end
            
            % Plot original masks with names as labels:
            imagesc(s1,origLab, 'AlphaData', origLab>0); hold(s1,'on')
            for ii = 1:size(centroids_orig,1)
                text(s1,centroids_orig(ii,1), centroids_orig(ii,2), origNames{ii},...
                    'HorizontalAlignment',"center", 'Interpreter',"none", 'Color', 'r');
            end
            hold(s1,'off')
            imagesc(s2,resMsks, 'AlphaData',resMsks>0);
            hold(s2,'on');
            for ii = 1:size(centroids_res,1)
                text(s2,centroids_res(ii,1), centroids_res(ii,2), defNames{ii}, ...
                    'HorizontalAlignment', 'center', 'Interpreter', 'none', 'Color', 'r');
            end
            hold(s2, 'off');
            title(s1,'Original ROIs');
            title(s2, 'Result');
            axis(s1,'image')
            axis(s2,'image')
            set(s1, 'XTick', [], 'YTick', [], 'Box', 'on', 'Color', 'k');
            set(s2, 'XTick', [], 'YTick', [], 'Box', 'on', 'Color', 'k');
            % Link axes' X,Y coords:
            linkaxes([s1,s2], 'xy');
            % Ask for user confirmation;
            app.UIFigure.Visible = 'on';
            answer = uiconfirm(app.UIFigure, 'Confirm operation?', 'Confirm');
            if strcmpi(answer, 'cancel')
                disp('Operation cancelled by User')
                delete(f)
                return
            end
            % Get Name of the new ROI:
            
            roiNames = roi_inputdlg(defNames);
            if isempty(roiNames)
                disp('Operation cancelled by User')
                delete(f)
                return
            end
            %             if any(isempty(roiNames))
            %                 err=errordlg('Invalid ROI name. ROI names cannot be empty!', 'Invalid ROI name');
            %                 waitfor(err)
            %                 delete(f)
            %                 return
            %             end
            delete(f)
            b_OK = true;
            
            %Local function
            function DoNothing(~,~)
                %%%EMPTY%%
            end
            
            function out = roi_inputdlg(defStr)
                % This is a function alternative to Matlab's "inputdlg". It
                % creates a list of editFields for user inputs to name
                % ROIs.
                out={};
                % create figure and elements:
                fig = uifigure('Name', 'Type name(s) of new ROIS', 'NumberTitle', 'off', 'Position', [0 0 320 320],...
                    'Resize', "on", 'Visible', "off"); movegui(fig, 'center');
                gl = uigridlayout(fig, [2 1]); gl.RowHeight = {'1x', 50};
                pan = uipanel("Parent",gl); pan.Layout.Row = 1;
                gl1 = uigridlayout(pan,[length(defStr) 2], 'Tag', 'FieldsParent');
                set(gl1, 'ColumnWidth', {55, '1x'}, 'RowHeight', repmat({20},1,length(defStr)), 'RowSpacing', 20, 'Scrollable', 'on');
                gl2 = uigridlayout(gl, [1 2]); gl2.Layout.Row = 2; set(gl2, 'Padding', [20 10 20 10], 'ColumnSpacing',30);
                btn1 = uibutton(gl2, "BackgroundColor",[0 .8 0], 'Text','Save', 'ButtonPushedFcn',@saveBtn_pushed); btn1.Layout.Column = 1;
                btn2 = uibutton(gl2, "BackgroundColor",[.7 .7 .7], 'Text','Reset', 'ButtonPushedFcn',@resetBtn_pushed); btn2.Layout.Column = 2;
                % Populate panel with edifields:
                for jj = 1:length(defStr)
                    lab = uilabel(gl1, 'Text', ['ROI #' num2str(jj) ':']);
                    lab.Layout.Row = jj; lab.Layout.Column = 1;
                    inField = uieditfield(gl1, 'Value', defStr{jj}, 'ValueChangedFcn', @editField_valueChanged, 'UserData', defStr{jj});
                    inField.Layout.Row = jj; inField.Layout.Column = 2;
                end
                fig.Visible = 'on';
                waitfor(fig);
                
                %%%% CALLBACKS for function ROI_INPUTDLG %%%%%
                function saveBtn_pushed(src, ~)
                    % Save ROI names:
                    fieldObjs = findobj(src.Parent.Parent.Parent, 'Type', 'uieditfield');
                    out = arrayfun(@(x) x.Value, fieldObjs, 'UniformOutput', false);
                    delete(src.Parent.Parent.Parent);
                end
                function resetBtn_pushed(src,~)
                    fieldObjs = findobj(src.Parent.Parent.Parent, 'Type', 'uieditfield');
                    for k = 1:length(fieldObjs)
                        fieldObjs(k).Value  = fieldObjs(k).UserData;
                    end
                end
                function editField_valueChanged(src, evnt)
                    % do not allow empty strings
                    if isempty(evnt.Value)
                        dlg = errordlg('Empty string not allowed!');
                        waitfor(dlg);
                        src.Value = evnt.PreviousValue; % reset string;
                    end
                end
            end
        end
    end
    methods (Access = public)
        

        function updateROIinfo(app)
            % This method is called from DataViewer to update ROI stats and
            % Table.
            if isempty(fieldnames(app.ROI_list))
                return
            end
            app.image_info.imageData = app.MainApp.image_info.imageData;
            % Relink image handle from DataViewer.
            app.imageHandle = findall(app.MainApp.spatialFig.Fig, 'Tag', 'MyImg');
            % Update Table:
            app.updateTable;
        end
        
        function rebuildROIinfo(app)
            %This function recreates invalid handles when working as
            %"add-on" to DataViewer app.
            
            fn = intersect(fieldnames(app.image_info), fieldnames(app.MainApp.image_info));
            
            for i = 1:numel(fn)
                app.image_info.(fn{i}) = app.MainApp.image_info.(fn{i});
            end
            app.image_info.reference_frame = app.image_info.imageData;
            % Relink image handle from DataViewer.
            app.imageHandle = findall(app.MainApp.spatialFig.Fig, 'Tag', 'MyImg');
            % Update axes:
            app.shiftAx;
            % Relink mask handle and re-create logical mask:
            if ~isempty(app.MainApp.ROI_data)
                app.mskObj = app.MainApp.ROI_data(1).mask;
                if strcmp(app.ShowMenu.Text, 'Show')
                    app.ShowHide_maskMenu;
                end
            else
                return
            end
            %
            if isempty(app.MainApp.ROI_data.list) || isempty(fieldnames(app.MainApp.ROI_data.list))
                return
            end
            % Re-calculate Pixel info:
            stats_struct = arrayfun(@(x) app.getStatsFromShape(x, app.image_info.imageData,[]), [app.ROI_list.Shape]);
            app.ROI_list = arrayfun(@(x,y) setfield(x, 'Stats', y), app.ROI_list, stats_struct);
            app.updateTable;
            % Recreate ROI overlays
            app.lockAxChild('image', true)
            app.overlayROI; app.lockAxChild('image', false)
            
        end
        
        function resetROItable(app)
            % This function erases all ROIs displayed in the table and their respective information.
            app.image_info.imageData = app.MainApp.image_info.imageData;
            app.image_info.cmap = app.MainApp.image_info.cmap;
            app.image_info.clims = app.MainApp.image_info.clims;
            app.image_info.reference_frame = app.image_info.imageData;
            app.image_info.pxPermm = app.MainApp.image_info.pxPermm;
            % Relink image handle from DataViewer.
            app.imageHandle = findall(app.MainApp.spatialFig.Fig, 'Tag', 'MyImg');
            % Reset table:
            app.initializeTable;
            % Reset ROI_list:
            app.ROI_list = app.ROI_list_def;
        end
        
        function invokeCloseRequestFcn(app)
            % This is a wrapper function so the mainApp properly closes
            % ROImanager
            app.UIFigureCloseRequest;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            % Update umIT path:
            if ~isdeployed
                app.rootDir = mfilename('fullpath');
                app.rootDir = erase(app.rootDir, ['GUI' filesep 'DataViz' filesep 'ROImanager']);
                addpath(app.rootDir)
                umIT_path_cleanup(app.rootDir)
            end
            updateVersionTag(app.UIFigure); 
            % ROImanager works as add-on to other apps of the toolbox as
            % well as an standalone.
            app.UIFigure.Visible = 'off';
            % Set ROI and image-related variables:
            app.ROI_list = app.ROI_list_def;
            app.image_info = app.image_info_def;
            % Set Figure Parameters:
            app.UIFigure.Name = 'ROImanager -- Main Window';
            %             app.UIFigure.Position = denormalize_UIfig_Position([0.0299 0.2303 0.4362 0.631]);
            movegui(app.UIFigure,'northwest'); app.UIFigure.Visible = 'on';
            % Initialize tables:
            app.initializeTable;
            
            % Argument Validation:
            narginchk(1,3);
            if nargin == 3
                app.MainApp = varargin{1};
                input = varargin{2};
            elseif nargin == 2
                input = varargin{1};
                app.MainApp = [];
            else
                input = [];
            end
            
            % Create event listener for image parameters:
            app.elPreParams = addlistener(app, 'image_info', 'PreSet', @app.copyImageInfo);
            app.elPostParams = addlistener(app, 'image_info', 'PostSet', @app.dispImgParams);
            app.checkInput(input);
            if app.b_StandAlone && ~isempty(app.image_info.imageData)
                % If the App mode is StandAlone, open window to display
                % image:
                app.displayImage;
                app.lockAxChild("image", true)
                app.overlayROI;
                app.lockAxChild("image", false);
            elseif ~app.b_StandAlone
                app.NewROIfileMenu.Enable = 'off';
                % Set some axis properties:
                app.Ax.DataAspectRatio = [1 1 1];
                app.Ax.DataAspectRatioMode = 'manual';
                % Disable a couple of options that are redundant with
                % "DataViewer" or will cause errors, if used as an "Add-on"
                app.OpenImageMenu.Enable = 'off';
                app.SetcolormapMenu.Enable = 'off';
                app.AlignimagetooriginMenu.Enable = 'off';
                
                % If the Main app has already ROI information, update the table %
                if ~isempty(app.MainApp.ROI_data.list)
                    app.ROI_list = app.MainApp.ROI_data.list;
                end
                app.rebuildROIinfo;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Check if there is unsaved data open, if so, ask to save:
            ask_question = false;
            if isempty(app.ROI_file) && ~isempty(app.UITable.Data)
                ask_question = true;
            elseif ~isempty(app.ROI_file)
                curr_ROIlist = app.ROI_list;
                mD = matfile(app.ROI_file);
                saved_ROIlist = mD.ROI_info;
                if ~isequaln(curr_ROIlist, saved_ROIlist)
                    ask_question = true;
                end
            end
            
            % Ask question:
            if ask_question
                answer = uiconfirm(app.UIFigure, 'Do you want to save the ROI data before closing?', 'Save data?', "Options",...
                    {'Yes','Close without saving', 'Cancel'}, "DefaultOption",1, "CancelOption",3, "Icon","question");
                switch answer
                    case 'Cancel'
                        return
                    case 'Yes'
                        %                         uiwait(app.UIFigure)
                        app.SaveasROI(''); % Provide empty dummy input, so the callback can work.
                end
            end
            % delete everything:
            if app.b_StandAlone
                delete(app.figHandle)
            else
                % Re-enable button in DataViewer:
                app.MainApp.ROImanagerMenu.Enable = 'on';
            end
            delete(app.OptsWindow);
            delete(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Callback of Main UIFigure. Changes the state of "Show/Hide" OR "Select" selected cells in
            % tab if user presses "Enter" outside table.
            if strcmp(event.Key, 'return')
                indx = app.UITable.UserData;
                if isempty(indx)
                    return
                end
                
                % Change only "Show/Hide" and "Select" columns:
                if unique(indx(:,2)) == 1
                    colName = 'b_show';
                elseif unique(indx(:,2)) == 2
                    colName = 'b_select';
                else
                    return
                end
                
                state = app.UITable.Data.(colName)(indx(:,1));
                if all(state) || all(~state)
                    newState = ~state;
                else
                    newState = true(size(state));
                end
                app.UITable.Data.(colName)(indx(:,1)) = newState;
                % For show/Hide ROIs only:
                if strcmp(colName, 'b_show')
                    app.lockAxChild("image", true)
                    app.overlayROI;
                    app.lockAxChild("image", false)
                end
                
            end
            
        end

        % Menu selected function: SelectareasMenu
        function SelectAllenAreas(app, event)
            app.loadAtlas;
            app.UIFigure.Visible = 'off';app.figHandle.Visible = 'off';
            app.AllenROIopts.Select = 'Area';
            app.selectAreas;
            app.UIFigure.Visible = 'on';app.figHandle.Visible = 'on';
            if ~isfield(app.AllenAtlas, 'idx_selected')
                return
            end
            app.lockAxChild("image", true);
            if ~app.b_StandAlone
                % Temporarily disable figure callbacks if working as
                % "add-on":
                app.switchFigbehaviour('disable')
            end
            [ROI_shapes, ROI_names, ROI_colors] = app.fitAllenMask;
            if isempty(ROI_shapes)
                app.lockAxChild("image", false);
                return
            end
            app.addROItoList(ROI_shapes, ROI_names, ROI_colors, repmat({'Shape'},1,length(ROI_shapes)));
            app.ROI_sanity_check;
            app.updateTable;
            app.overlayROI;
            app.lockAxChild("image", false);
            if ~app.b_StandAlone
                % Enable figure callbacks
                app.switchFigbehaviour('enable')
            end
            % Redraw mask if the mask is displayed:
            if strcmp(app.ShowMenu(1).Text, 'Hide')
                app.ShowMenu(1).Text = 'Show';
                app.ShowHide_maskMenu;
            end
            % Notify DataViewer:
%             if ~app.b_StandAlone
%                 notify(app, 'updateROI')
%             end
%             
        end

        % Menu selected function: SelectcentroidsMenu
        function SelectAllenCentroids(app, event)
            app.loadAtlas;
            app.UIFigure.Visible = 'off';app.figHandle.Visible = 'off';
            app.AllenROIopts.Select = 'Centroid';
            app.selectAreas;
            app.UIFigure.Visible = 'on';app.figHandle.Visible = 'on';
            if ~isfield(app.AllenAtlas, 'idx_selected')
                return
            end
            if strcmp(app.AllenROIopts.Centroid.Unit, 'mm') && app.image_info.pxPermm > 0
                radius = app.image_info.pxPermm * app.AllenROIopts.Centroid.Radius;
            else
                radius = app.AllenROIopts.Centroid.Radius;
            end
            if ~app.b_StandAlone
                % Temporarily disable figure callbacks if working as
                % "add-on":
                app.switchFigbehaviour('disable')
            end
            app.lockAxChild("image", true);
            [ROI_shapes, ROI_names, ROI_colors] = app.fitAllenMask(true, radius);
            if isempty(ROI_shapes)
                app.lockAxChild("image", false);
                return
            end
            app.addROItoList(ROI_shapes, ROI_names, ROI_colors, repmat({'Shape'},1,length(ROI_shapes)));
            app.ROI_sanity_check;
            app.updateTable;
            app.overlayROI;
            app.lockAxChild("image", false);
            if ~app.b_StandAlone
                % Enable figure callbacks
                app.switchFigbehaviour('enable')
            end
            % Redraw mask if the mask is displayed:
            if strcmp(app.ShowMenu(1).Text, 'Hide')
                app.ShowMenu(1).Text = 'Show';
                app.ShowHide_maskMenu;
            end
%             % Notify DataViewer:
%             if ~app.b_StandAlone
%                 notify(app, 'updateROI')
%             end
        end

        % Menu selected function: OptionsMenu_areas
        function Launch_Allen_areas_Settings(app, event)
            % Opens a window with settings for "Area" menu of Mouse Allen Brain atlas.
            app.UIFigure.Visible = 'off';app.figHandle.Visible = 'off';
            app.OptsWindow = imagApps_Opts(app, 'area');
            uiwait(app.OptsWindow.ImageOptsUIFigure);
            app.UIFigure.Visible = 'on';app.figHandle.Visible = 'on';
        end

        % Menu selected function: OptionsMenu_centroids
        function Launch_Allen_centroid_Settings(app, event)
            app.UIFigure.Visible = 'off';app.figHandle.Visible = 'off';
            app.OptsWindow = imagApps_Opts(app, 'centroid');
            uiwait(app.OptsWindow.ImageOptsUIFigure);
            app.UIFigure.Visible = 'on';app.figHandle.Visible = 'on';
        end

        % Menu selected function: SetcolormapMenu
        function Launch_cmap_Setting(app, event)
            %             app.UIFigure.Visible = 'off';app.figHandle.Visible = 'off';
            app.OptsWindow = imagApps_Opts(app, 'cmap');
            uiwait(app.OptsWindow.ImageOptsUIFigure);
            % Update colormap
            colormap(app.Ax, app.image_info.cmap);
            app.Ax.CLim = app.image_info.clims;
            %
            %             app.UIFigure.Visible = 'on';app.figHandle.Visible = 'on';
            
        end

        % Menu selected function: NewOriginMenu
        function DrawNewRefPt(app, event)
            % Callback to draw new reference point.
            app.UIFigure.Visible = 'off';
            % If ROImanager is an add-on:
            if ~app.b_StandAlone
                % Replace eventual WindowFcn callbacks with pertinent
                % callbacks for Point drawing.
                app.switchFigbehaviour('disable');
            end
            app.lockAxChild("image", true);
            app.Ax.Title.String = 'Click to create new origin.';
            if isequal(app.image_info.refPt, [0,0])
                pt = drawpoint(app.Ax, 'Color', [0 1 0], 'Deletable', true, 'SelectedColor', [1 0 0], 'Position', [app.Ax.XLim(2) app.Ax.YLim(2)]./2);
            else
                pt = drawpoint(app.Ax, 'Color', [0 1 0], 'Deletable', true, 'SelectedColor', [1 0 0], 'Position', [app.image_info.refPt(1) app.image_info.refPt(2)]);
            end
            addlistener(pt,'MovingROI',@(src,evt) movingPt(src,evt));
            addlistener(pt,'ROIMoved',@(src,evnt) gluePt(src,evnt));
            addlistener(pt,'ROIClicked',@(src,evt) savePt(src,evt));
            app.lockAxChild("image", false);
            waitfor(pt);
            app.UIFigure.Visible = 'on';
            app.shiftAx;
            app.updateTable;
            if ~app.b_StandAlone
                app.switchFigbehaviour('enable');
            end
            % Redraw mask if the mask is displayed:
            if strcmp(app.ShowMenu(1).Text, 'Hide')
                app.ShowMenu(1).Text = 'Show';
                app.ShowHide_maskMenu;
            end
            %Listener callbacks:
            function movingPt(src,evnt)
                % Shows the X,Y coordinates of point while moving.
                XY_str = [num2str(round(evnt.CurrentPosition(1),2)) '; ' num2str(round(evnt.CurrentPosition(2),2))];
                src.Label = XY_str;
            end
            function gluePt(src,~)
                % Unselect point after moved.
                src.Selected = false;
                src.Label = '';
                app.Ax.Title.String = 'Right-click on point to save';
            end
            function savePt(src,evnt)
                % Callback for drawrectangle function.
                % It deletes the rectangle object if the User double-clicks
                % inside the rectangle.
                click = evnt.SelectionType;
                selFace = evnt.SelectedPart;
                if strcmp(click, 'right') && strcmp(selFace, 'marker')
                    app.image_info.refPt = src.Position;
                    app.Ax.Title.String = '';
                    delete(src);
                end
            end
        end

        % Menu selected function: ImportOrigfromfileMenu
        function ImportRefPt_fromFile(app, event)
            [filename, path] = uigetfile('*.mat', 'Open .MAT file with Reference point (X,Y coordinates)');
            if filename == 0
                disp('Operation Cancelled by User.')
                return
            end
            mD = load(fullfile(path, filename));
            varList = fieldnames(mD);
            [idx, tf] = listdlg("ListString", varList, "PromptString", {'Select reference point' ;'from the variables below:'}, ...
                "SelectionMode", 'single');
            refPt = mD.(varList{idx});
            is_ok = checkRefPt(app,refPt);
            if tf && is_ok == 0
                app.image_info.refPt = refPt;
                app.shiftAx;
                app.updateTable;
                disp('New Reference point set!')
            elseif ~tf
                disp('Operation cancelled by User.')
            elseif tf && is_ok == 1
                uialert(app.UIFigure, 'Selected reference point is outside the current image boundaries.', 'Failed to set Reference point', 'Icon',"error",...
                    'Modal',true);
            else
                uialert(app.UIFigure, 'Reference point variable is invalid. Must be a 1x2 numeric matrix', 'Failed to set Reference point',...
                    'Icon',"error",'Modal',true);
            end
            
            function out = checkRefPt(app,refPt)
                % CHECKREFPT verifies if refPt is a 1x2 numeric matrix with
                % values inside the boundaries of the current image;
                % Output:
                % out (int) = numerical scalar :
                %   0 = refPt is Valid.
                %   1 = refPt is out of bounds.
                %   2 = refPt size/data type is invalid.
                
                if any(size(refPt) ~= [1 2]) || ~isnumeric(refPt)
                    % if refPt size is different from [1,2]:
                    out = 2;
                elseif (refPt(1) < 1 || refPt(1) > size(app.image_info.imageData,1)) || (refPt(2) < 1 || refPt(2) > size(app.image_info.imageData,2))
                    % if refPt is outside the current image:
                    out = 1;
                else
                    out = 0;
                end
            end
        end

        % Selection changed function: OverlaymodesButtonGroup
        function ChangeOverlayMode(app, event)
            idxList = app.UITable.Data.b_show;
            if ~isempty(idxList)
                app.lockAxChild("image", true);
                %                 app.showHideROI;
                app.overlayROI
                app.lockAxChild("image", false);
            end
        end

        % Menu selected function: SetpixelsizeMenu
        function SetImagePixelSize(app, event)
            opts.Resize = 'on';
            opts.WindowStyle = 'modal';
            if app.image_info.pxPermm > 0
                defVal = {num2str(app.image_info.pxPermm)};
            else
                defVal = {'0'};
            end
            answer = inputdlg('pixel ratio (px/mm)', 'Type the image pixel ratio',1, defVal, opts);
            if isempty(answer)
                disp('Operation cancelled by User');
            elseif isnan(str2double(answer)) || str2double(answer)<0
                uialert(app.UIFigure, 'Invalid pixel ratio value. Type a positive number or zero to show axis as pixels.', ...
                    'Invalid input', 'Icon', "error", "Modal",true, "CloseFcn", 'uiresume(gcbf)')
                uiwait(app.UIFigure);
            else
                app.image_info.pxPermm = str2double(answer);
                %Save info to "DataViewer":
                if ~app.b_StandAlone
                    app.MainApp.image_info.pxPermm = app.image_info.pxPermm;
                    
                end
                app.updateTable;
                app.shiftAx;
            end
        end

        % Menu selected function: OpenROImasksfileMenu
        function OpenROIfile(app, event)
            % Confirm Deletion of existing ROIs before creating new empty
            % table:
            if ~isempty(app.ROI_list(1).Name)
                answer = uiconfirm(app.UIFigure, 'Existing ROI table will be erased. Proceed?', 'Erase existing ROIs?', 'Icon', "question");
                if ~strcmp(answer, 'OK')
                    return
                end
            end
            
            % Load ROI file:
            if ~isempty(app.image_info.datFile)
                [filename, path] = uigetfile(fullfile(fileparts(app.image_info.datFile),'ROImasks_*.mat'), 'Select a ROI file');
            else
                [filename, path] = uigetfile('ROImasks_*.mat', 'Select a ROI file');
            end
            if filename == 0
                disp('Operation cancelled by User')
                return
            end
            %
            ROI_filename = fullfile(path, filename);
            app.loadROIfile(ROI_filename);
            % Empty title:
            app.Ax.Title.String = '';
            
            app.lockAxChild("image",true);
            if ~isempty(fieldnames(app.ROI_list))
                app.ROI_sanity_check;
                app.updateTable;
                app.overlayROI;
            end
            app.lockAxChild("image",false);
            % Force show mask:
            app.ShowMenu(1).Text = 'Show';
            app.ShowHide_maskMenu;
            % Notify DataViewer on changes in ROI and Mask:
            if ~app.b_StandAlone
%                 notify(app, 'updateROI')
                notify(app, 'updateMask')
            end
        end

        % Menu selected function: DeleteallROIsMenu, NewROIfileMenu
        function NewROIfile(app, event)
            % This callback erases the ROIs.
            % If the user clicks on "NewMenu", the image params are also
            % deleted.
            if startsWith(event.Source.Text, 'new', 'IgnoreCase',true)
                src = 0;
                msg = 'Existing ROIs and all image parameters will be erased. Proceed?';
            else
                src = 1;
                msg = 'Existing ROIs will be erased. Proceed?';
                if isempty(app.ROI_list(1).Name)
                    return
                end
            end
            
            % Confirm Deletion of existing ROIs before creating new empty
            % table:
            if ~isempty(app.ROI_list(1).Name)
                answer = uiconfirm(app.UIFigure, msg, 'Erase existing ROIs?', 'Icon', "question");
                if ~strcmp(answer, 'OK')
                    return
                end
            end
            % Check if there is an image:
            if isempty(app.image_info.imageData)
                % Call "openImage" callback
                app.OpenImage;
            end
            % If User cancelled the process in "OpenImage" callback
            % execution, return:
            if isempty(app.image_info.imageData)
                return
            end
            % Reset table:
            app.initializeTable;
            % Reset ROI_list:
            app.ROI_list = app.ROI_list_def;
            % Clear existing plot overlays:
            for i = 1:numel(app.overlayHandle)
                p = app.overlayHandle{i};
                delete(p);
            end
            app.overlayHandle = {};
            % Clear saved ROI filename:
            app.ROI_file = '';
            % If the user clicks on "New" menu, erase image params and AllenROI params as
            % well:
            if src == 0
                % Reset Allen ROI settings:
                app.AllenROIopts = struct('Area', struct('n_shrinkPix', 0), ...
                    'Centroid', struct('Radius', 1, 'Unit', 'px'));
                % Reset image spatial info:
                app.image_info.refPt = [0,0];
                app.image_info.pxPermm = [0];
                app.image_info.logical_mask = true(size(app.image_info.imageData));
                app.image_info.imageData = app.image_info.reference_frame; % Reset imageData to reference frame
                % Erase the mask object;
                delete(app.mskObj.Handle);
                app.mskObj.Shape = [];
                app.mskObj.Handle = [];
                
            end
            % Redraw image
            app.displayImage;
            % Show mask;
            if strcmp(app.ShowMenu(1).Text, 'Hide')
                app.ShowMenu(1).Text = 'Show';
                app.ShowHide_maskMenu;
            end
            
            % Notify DataViewer on changes in ROI and Mask:
            if ~app.b_StandAlone
                notify(app, 'updateROI')
                notify(app, 'updateMask')
            end
        end

        % Menu selected function: DeleteROIMenu
        function DeleteROI(app, event)
            idx_selected = app.UITable.Data.b_select;
            if sum(idx_selected) == 0
                return
            end
            nameList = {app.ROI_list(idx_selected).Name};
            msg = cat(2,{'Delete the following ROI(s) from table?'}, nameList);
            answer = uiconfirm(app.UIFigure, msg, 'Confirm deletion', 'Icon',"question");
            if strcmp(answer, 'OK')
                app.ROI_list(idx_selected) = [];
                app.UITable.Data(idx_selected,:) = [];
                app.UITable.BackgroundColor(idx_selected,:) = [];
                % Delete overlay handle:
                if ~isempty(app.overlayHandle)
                    delete(app.overlayHandle{1}(idx_selected));
                    app.overlayHandle{1}(idx_selected) = [];
                end
                app.lockAxChild("image", true);
                app.overlayROI;
                app.lockAxChild("image", false);
            end
            if isempty(app.ROI_list)
                % To avoid deleting the whole structure:
                app.ROI_list = app.ROI_list_def;
            end
%             % Notify DataViewer on changes in ROI:
%             if ~app.b_StandAlone
%                 notify(app, 'updateROI')
%             end
        end

        % Menu selected function: SavefileMenu
        function SavefileROI(app, event)
            if isempty(app.ROI_file)
                app.SaveasROI(event);
                return
            else
                % Unpack data for saving in .mat file:
                ROI_info = app.ROI_list;
                img_info = app.image_info;
                % Save ROI file:
                save(app.ROI_file, 'ROI_info', 'img_info');
                msgbox(['ROI data saved as ' app.ROI_file],"modal");
            end
            
        end

        % Menu selected function: SaveasMenu
        function SaveasROI(app, event)
            % Check if there is ROI data to be saved:
            if isempty(fieldnames(app.ROI_list))
                uialert(app.UIFigure, 'There is no ROI data to save.', 'Empty ROI list', 'Icon', "warning", "Modal",true)
                return
            end
            
            % Create dialog box for input of new file:
            dlg_box = uifigure('Name', 'Type file name', 'Position',[1 1 330 90], 'Visible', 'off',...
                'CloseRequestFcn', @Close_dlg_box); movegui(dlg_box, 'center'); dlg_box.Visible = 'on';
            uilabel('Parent',dlg_box, 'Text', 'ROImasks_','Position', [40 55 70 20], 'FontColor', [.5 .5 .5]);
            uieditfield('Parent', dlg_box, "BackgroundColor", [1 1 1], "Position", [105 55 125 20], 'Value','data');
            uilabel('Parent',dlg_box, 'Text', '.mat','Position', [230 55 50 20], 'FontColor', [.5 .5 .5]);
            uibutton('Parent', dlg_box, "BackgroundColor",[0 .8 0], "Text", 'Save',...
                'Position', [40 10 60 25], 'ButtonPushedFcn',@(src,evnt) SaveROIas(src,evnt,app));
            uiwait(app.UIFigure);
            % Callbacks
            function SaveROIas(src,~,app)
                src.Enable = 'off';
                if ~isa(app.MainApp, 'umIToolbox')
                    saveFolder = uigetdir(pwd, 'Choose a folder to save ROI file');
                    if saveFolder == 0
                        disp('Operation Cancelled by User')
                        delete(src.Parent)
                        return
                    end
                else
                    selObj= app.MainApp.selectedObjs{:};
                    idx = false;
                    % Find Subject object linked to the selected object in the
                    % main app:
                    while ~idx
                        if isa(selObj, 'Subject')
                            idx = true;
                        else
                            selObj = selObj.MyParent;
                        end
                    end
                    % Force save into Subject's folder:
                    saveFolder = selObj.SaveFolder;
                end
                % Unpack data for saving in .mat file:
                ROI_info = app.ROI_list;
                img_info = app.image_info;
                % Save ROI file:
                filename = fullfile(saveFolder, ['ROImasks_' src.Parent.Children(3).Value '.mat']);
                save(filename, 'ROI_info', 'img_info');
                % Save ROI file to app:
                app.ROI_file = filename;
                %
                close(src.Parent);
                uialert(app.UIFigure, ['ROI data saved as: ' filename],'Data Saved', 'Icon', 'info',...
                    "Modal",true, 'CloseFcn','uiresume(gcf)');
            end
            
            function Close_dlg_box(src,~)
                uiresume(app.UIFigure)
                delete(src)
            end
        end

        % Menu selected function: ColorMenu
        function ColorMenuSelected(app, event)
            % Allow only one Item at a time:
            b_ok = app.checkSingleSelection('single');
            if ~b_ok
                return
            end
            % Get item index in table:
            idx = app.UITable.Data.b_select;
            % Get item current color:
            def_c = app.ROI_list(idx).Color;
            % GUI for new color:
            new_c = uisetcolor(def_c);
            % Replace color:
            app.ROI_list(idx).Color = new_c;
            % Show new color on table:
            app.UITable.BackgroundColor(idx,:) = new_c;
            % Show new color on plot:
            app.lockAxChild("image", true);
            app.overlayROI
            app.lockAxChild("image", false);
%             % Notify DataViewer on changes in ROI:
%             if ~app.b_StandAlone
%                 notify(app, 'updateROI')
%             end
        end

        % Menu selected function: OpenImageMenu
        function OpenImage(app, event)
            out = app.loadImage;
            b_OK = app.compareImageSize(out);
            if ~b_OK
                return
            end
            
            %             % Check if new image has the same size as the current image:
            %             if ( ~isempty(app.image_info.imageData) && ~isempty(out) && any(size(out) ~= size(app.image_info.imageData)) )
            %                 uialert(app.UIFigure, {'Cannot open image!' ['The size of the new image(' num2str(size(out,1)) 'x' num2str(size(out,2))...
            %                     ') is different from the one currently open (' num2str(size(app.image_info.imageData,1)) 'x' num2str(size(app.image_info.imageData,2)) ')']},...
            %                     'Failed to open image', 'Icon', 'error','Modal',true);
            %                 return
            %             elseif isempty(out)
            %                 return
            %             end
            
            % Replace imageData with new one:
            app.image_info.imageData = out;
            app.image_info.reference_frame = out;
            % Create logical mask, if it doesn't exist:
            if isempty(app.image_info.logical_mask)
                app.image_info.logical_mask = true(size(out));
            end
            % Show image:
            app.displayImage;
            % Update Axes:
            app.shiftAx;
            if ~isempty(fieldnames(app.ROI_list))
                % Re-plot ROIs:
                app.overlayHandle = {};
                app.lockAxChild("image", true);
                app.overlayROI;
                app.lockAxChild("image", false);
                % Update ROI stats based on data from new image:
                ROI_shapes = [app.ROI_list.Shape];
                ROI_names = {app.ROI_list.Name};
                ROI_colors = cell2mat({app.ROI_list.Color}');
                Type_names = {app.ROI_list.Type}';
                addROItoList(app,ROI_shapes, ROI_names, ROI_colors, Type_names);
                % Update Table:
                app.updateTable;
            end
%             % Notify DataViewer on changes in ROI:
%             if ~app.b_StandAlone
%                 notify(app, 'updateROI')
%             end
            % Put GUI in foreground
            figure(app.UIFigure);
        end

        % Menu selected function: ConstrainededitMenu
        function ConstrainedROIedit(app, event)
            % This callback allows a constrained edit of Shapes. This means,
            % rotation, translation and scaling.
            
            b_ok = app.checkSingleSelection('multi');
            if ~b_ok
                return
            end
            
            app.lockAxChild("image", true);
            % Get item index in table:
            idx = app.UITable.Data.b_select;
            % Exclude Points from editing:
            if any(strcmp({app.ROI_list(idx).Type}, 'Point'))
                uialert(app.UIFigure, 'Cannot edit Points', 'Wrong ROI type!', 'Icon', "error", "Modal",true);
                return
            end
            
            if ~app.b_StandAlone
                % Temporarily disable figure callbacks if working as
                % "add-on":
                app.switchFigbehaviour('disable')
            end
            
            % Get plot Handle:
            allShapesH = app.overlayHandle{1};
            shapeH = allShapesH(idx);
            %             [xlim, ylim] = shapeH.Shape.boundingbox;
            [xlim, ylim] = arrayfun(@(x) x.Shape.boundingbox, shapeH, 'UniformOutput', false);
            xlim = cell2mat(xlim'); ylim = cell2mat(ylim');
            xlim = [min(xlim(:,1)) max(xlim(:,2))];
            ylim = [min(ylim(:,1)) max(ylim(:,2))];
            
            [newShape, ~] = app.fitShape_constrained(shapeH, [xlim(1) ylim(1) (xlim(2) - xlim(1)) (ylim(2)-ylim(1))]);
            disp('Done!')
            app.addROItoList(newShape, {app.ROI_list(idx).Name}, vertcat(app.ROI_list(idx).Color), {app.ROI_list(idx).Type});
            app.ROI_sanity_check;
            app.updateTable;
            app.overlayROI;
            app.lockAxChild("image", false);
            
            if ~app.b_StandAlone
                % Enable original figure callbacks
                app.switchFigbehaviour('enable')
                % Notify DataViewer on changes in ROI:
%                 notify(app, 'updateROI')
            end
        end

        % Menu selected function: UnconstrainededitMenu
        function UnconstrainedROIEdit(app, event)
            % Allow only one Item at a time (for now...):
            b_ok = app.checkSingleSelection('single');
            if ~b_ok
                return
            end
            % Get item index in table:
            idx = app.UITable.Data.b_select;
            % Allow only for ROIs with single regions:
            if app.ROI_list(idx).Shape.NumRegions > 1
                uialert(app.UIFigure, 'Cannot edit Shapes with multiple regions.', 'Wrong ROI shape!', 'Icon', "error", "Modal",true);
                return
            end
            % Exclude Points from editing:
            if strcmp(app.ROI_list(idx).Type, 'Point')
                uialert(app.UIFigure, 'Cannot edit Points.', 'Wrong ROI type!', 'Icon', "error", "Modal",true);
                return
            end
            
            if ~app.b_StandAlone
                % Temporarily disable figure callbacks if working as
                % "add-on":
                app.switchFigbehaviour('disable')
            end
            
            % Get plot Handle:
            allShapesH = app.overlayHandle{1};
            shapeH = allShapesH(idx);
            app.lockAxChild("image", true);
            [newShape, shape_color] = app.drawShape(app.ROI_list(idx).Shape, app.ROI_list(idx).Color);
            if isempty(newShape)
                app.lockAxChild("image", false);
                return
            end
            % Update existing plot:
            shapeH.Shape = newShape;
            shapeH.FaceColor = shape_color;
            % Update everything:
            app.addROItoList(newShape, app.UITable.Data.Name(idx), shape_color, {app.ROI_list(idx).Type});
            app.ROI_sanity_check;
            app.updateTable;
            app.overlayROI;
            app.lockAxChild("image", false);
            if ~app.b_StandAlone
                % Enable original figure callbacks
                app.switchFigbehaviour('enable')
                % Notify DataViewer on changes in ROI:
%                 notify(app, 'updateROI')
            end
        end

        % Menu selected function: DrawnewMenu
        function DrawMask(app, event)
            % Draws a mask over the image.
            
            if ~app.b_StandAlone
                % Temporarily disable figure callbacks if working as
                % "add-on":
                app.switchFigbehaviour('disable')
            end
            % Reset number of mask regions:
            app.n_msk = 1;
            % Open imagApps_Opts with number of ROIs:
            app.OptsWindow = imagApps_Opts(app, 'msk');
            uiwait(app.OptsWindow.ImageOptsUIFigure);
            % create logical mask:
            logicMsk = false([size(app.image_info.imageData), app.n_msk]);
            for i = 1:app.n_msk
                app.Ax.Title.String = ['Draw Region # ' num2str(i)];
                polydraw = drawpolygon(app.Ax, "Color",[.5 0 0], "DrawingArea",[1 1 size(app.image_info.imageData,2) size(app.image_info.imageData,1)]);
                polydraw.UserData = false;
                addlistener(polydraw,'ROIClicked',@(src,evnt) createMsk(src,evnt,app));
                if isempty(polydraw.Position)
                    delete(polydraw)
                    disp('Operation cancelled by User')
                    % Empty title:
                    app.Ax.Title.String = '';
                    if ~app.b_StandAlone
                        % Enable original figure callbacks
                        app.switchFigbehaviour('enable')
                    end
                    return
                end
                waitfor(polydraw);
            end
            app.image_info.logical_mask = any(logicMsk,3); % Add Mask regions.
            % Empty title:
            app.Ax.Title.String = '';
            app.displayImage;
            app.lockAxChild("image",true);
            if ~isempty(app.ROI_list) || ~isempty(fieldnames(app.ROI_list))
                app.ROI_sanity_check;
                app.updateTable;
                app.overlayROI;
            end
            app.lockAxChild("image",false);
            % Delete current mask handle:
            delete(app.mskObj.Handle);
            % Force show mask:
            app.ShowMenu(1).Text = 'Show';
            app.ShowHide_maskMenu;
            
            if ~app.b_StandAlone
                % Enable original figure callbacks
                app.switchFigbehaviour('enable')
                % Notify DataViewer on changes in Mask:
                notify(app, 'updateMask')
%                 notify(app, 'updateROI')
            end
            
            
            % Local function
            
            function createMsk(src,evnt, app)
                % Callback for drawpolygon
                click = evnt.SelectionType;
                selFace = evnt.SelectedPart;
                if strcmp(click, 'double') && strcmp(selFace, 'face') && ~strcmp(evnt.EventName, 'DeletingROI')
                    logicMsk(:,:,i) = createMask(src,app.imageHandle);
                    delete(src);
                end
            end
        end

        % Menu selected function: PointMenu
        function SinglepointMenuSelected(app, event)
            prompt = 'Enter ROI name:';
            dlgtitle = 'New Point ROI';
            dims = [1 35];
            definput = {'new_Pt'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                disp('Operation cancelled by User');
                return
            elseif isempty(strip(answer{:}))
                uialert(app.UIFigure, 'Invalid ROI Name! Empty strings not accepted.', 'Invalid Name', "Modal",true);
                return
            end
            % Create ROI color:
            color = get(app.Ax,'DefaultAxesColorOrder');
            shape_color = color(randi(size(color,1)),:);
            % User chooses how to select pixel:
            selType = questdlg('Choose how to select Pixel', 'Pixel selection method', 'Interactively',...
                'Type coordinates', 'Cancel', 'Interactively');
            switch selType
                case 'Cancel'
                    disp('Operation Cancelled by User')
                    return
                case 'Type coordinates'
                    % Input entry GUI:
                    dlg = uifigure('Name', 'Pixel Coordinates', "Position", [1 1 245 100], 'Visible', "off");
                    uilabel(dlg, 'Text', 'Type Pixel coordinates:', 'Position',[10 75 200 20], 'FontWeight','bold');
                    uilabel(dlg, 'Text',' X:', 'Position',[10 50,20,20]);
                    uieditfield(dlg, 'numeric', 'Position',[35 50 50 20], 'RoundFractionalValues',"on", ...
                        'Limits',[1, size(app.image_info.imageData,2)]);
                    uilabel(dlg, 'Text', 'Y:', 'Position', [100 50 20 20]);
                    uieditfield(dlg, 'numeric', 'Position',[125 50 50 20], 'RoundFractionalValues',"on", ...
                        'Limits',[1, size(app.image_info.imageData,1)]);
                    uibutton(dlg,'Position',[185 10 45 30], 'Text','Ok','BackgroundColor',[0 .7 0], 'ButtonPushedFcn',@goBtnClicked);
                    movegui(dlg, 'center');
                    dlg.Visible = 'on';
                    %
                    waitfor(dlg);
                    if ~exist('plt', 'var')
                        disp('Operation Cancelled by User');
                        return
                    end
                    % Call getclickPt callback directly:
                    getclickPt;
                    
                otherwise
                    app.lockAxChild("all", true);
                    % If ROImanager is an add-on:
                    if ~app.b_StandAlone
                        % Replace eventual WindowFcn callbacks with pertinent
                        % callbacks for Point drawing.
                        app.switchFigbehaviour('replace');
                    end
                    % Create crosshair object:
                    ln = line(app.Ax, [1 1; app.Ax.XLim]', [app.Ax.YLim ; 1 1]', 'Color', [0 1 0],'HandleVisibility', 'callback',...
                        'Tag', 'selPixMarker');
                    plt = plot(app.Ax, 1,1,'ks','Color', shape_color, 'MarkerSize',12, 'HandleVisibility',"callback", "Tag", 'Sqr');
                    app.Ax.Title.String = "Double-click to save Point ROI";
                    % Activate cursor tracking:
                    app.Pt_info.b_trackPt = true;
                    % Track click:
                    app.elPt = addlistener(plt, 'UserData', 'PostSet',@(src,evnt) getclickPt);
                    waitfor(app.elPt)
            end
            
            % Update ROI table and handles:
            app.ROI_sanity_check;
            app.updateTable;
            app.lockAxChild("all", false);
            app.lockAxChild("image", true);
            app.overlayROI;
            app.lockAxChild("image", false);
            
            % Replace original callbacks, if in "Add-on" mode:
            if ~app.b_StandAlone && strcmp(selType, 'Interactively')
                app.switchFigbehaviour('enable');
                % Notify DataViewer on changes in ROI:
%                 notify(app, 'updateROI')
            end
            % Redraw mask if the mask is displayed:
            if strcmp(app.ShowMenu(1).Text, 'Hide')
                app.ShowMenu(1).Text = 'Show';
                app.ShowHide_maskMenu;
            end
            
            % Local functions:
            function goBtnClicked(src,~)
                % CALLBACK to save X,Y coordinates when user chooses to
                % type coordinates.
                
                % create fake plot handle so "getclickPt" can be used:
                plt = struct();
                ln = []; % create fake variables;
                % Save X,Y coords:
                plt.UserData = {[src.Parent.Children(4).Value, src.Parent.Children(2).Value]};
                delete(src.Parent);
            end
            
            function getclickPt(~,~)
                % CALLBACK TO CREATION OF POINT ROI INTERACTIVELY
                
                app.Pt_info.b_trackPt = false;
                app.Pt_info.Pt_list = plt.UserData{:};
                app.Ax.Title.String = '';
                
                % First, create a fake shape with the size of a pixel
                pix_shape = polyshape([app.Pt_info.Pt_list(1)-.5 app.Pt_info.Pt_list(1)-.5 app.Pt_info.Pt_list(1)+.5 app.Pt_info.Pt_list(1)+.5],...
                    [app.Pt_info.Pt_list(2)-.5 app.Pt_info.Pt_list(2)+.5 app.Pt_info.Pt_list(2)+.5 app.Pt_info.Pt_list(2)-.5]);
                app.addROItoList(pix_shape, answer, shape_color, {'Point'});
                
                % delete pointers and event listener:
                if ishandle(plt)
                    delete(plt)
                end
                if ishandle(ln)
                    delete(ln)
                end
                delete(app.elPt)
            end
            
        end

        % Menu selected function: ImportMskfromfileMenu_2
        function ImportMaskFromFile(app, event)
            [filename, path] = uigetfile('*.mat', 'Open .MAT file with Logical mask (2D matrix)');
            if filename == 0
                disp('Operation Cancelled by User.')
                return
            end
            mD = load(fullfile(path, filename));
            varList = fieldnames(mD);
            idxVar = false(size(varList));
            for i = 1:numel(varList)
                idxVar(i) = islogical(mD.(varList{i}));
            end
            if sum(idxVar) == 0
                uialert(app.UIFigure, 'No logical mask found in this file!', 'Variable not found',"Icon","warning");
                return
            end
            varList = varList(idxVar);
            [idx, tf] = listdlg("ListString", varList, "PromptString", {'Select Mask' ;'from the variables below:'}, ...
                "SelectionMode", 'single');
            if ~tf
                disp('Operation cancelled by User.')
                return
            end
            % Load mask:
            msk = mD.(varList{idx});
            % Check if msk is a logical 2D matrix with size equal to the image:
            if size(msk) ~=size(app.image_info.imageData)
                uialert(app.UIFigure, 'Size of mask is different from the size of image', 'Failed to import mask', 'Icon',"error","Modal",true);
                return
            end
            if ~islogical(msk) || ~ismatrix(msk)
                uialert(app.UIFigure, 'Mask must be a 2-D logical array.', 'Failed to import mask', 'Icon',"error","Modal",true);
                return
            end
            
            % Save mask to "image_info" struct:
            app.image_info.logical_mask = msk;
            if app.b_StandAlone
                app.displayImage;
            end
            app.lockAxChild("image", true);
            app.ROI_sanity_check;
            app.updateTable;
            app.overlayROI;
            app.lockAxChild("image", false);
            
            % Delete current mask:
            delete(app.mskObj.Handle)
            % Create new one and show it:
            app.ShowMenu(1).Text = 'Show';
            app.ShowHide_maskMenu;
            % Notify DataViewer on changes in Mask:
            if ~app.b_StandAlone
                notify(app, 'updateMask')
            end
        end

        % Menu selected function: PolygonMenu
        function DrawNewShape(app, event)
            % DRAWNEWSHAPE creates a new polygon shape.
            prompt = 'Enter ROI name:';
            dlgtitle = 'New ROI';
            dims = [1 35];
            definput = {'new_ROI'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                disp('Operation cancelled by User');
                return
            elseif isempty(strip(answer{:}))
                uialert(app.UIFigure, 'Invalid ROI Name! Empty strings not accepted.', 'Invalid Name', "Modal",true);
                if ~app.b_StandAlone
                    % Enable original figure callbacks
                    app.switchFigbehaviour('enable')
                end
                return
            end
            
            if ~app.b_StandAlone
                % Temporarily disable figure callbacks if working as
                % "add-on":
                app.switchFigbehaviour('disable')
            end
            app.lockAxChild("image", true);
            [newShape, shape_color] = app.drawShape;
            if isempty(newShape)
                app.lockAxChild("image", false);
                return
            end
            app.addROItoList(newShape, answer, shape_color, {'Shape'});
            app.ROI_sanity_check;
            app.updateTable;
            app.overlayROI;
            app.lockAxChild("image", false);
            if ~app.b_StandAlone
                % Enable original figure callbacks
                app.switchFigbehaviour('enable')
                % Notify DataViewer on changes in ROI:
%                 notify(app, 'updateROI')
            end
            % Redraw mask if the mask is displayed:
            if strcmp(app.ShowMenu(1).Text, 'Hide')
                app.ShowMenu(1).Text = 'Show';
                app.ShowHide_maskMenu;
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMask(app, event)
            % This callback deletes the current mask
            answer = uiconfirm(app.UIFigure, 'Delete current Mask?', 'Delete mask');
            if strcmpi(answer, 'ok') && ~all(app.image_info.logical_mask(:))
                app.image_info.logical_mask = true(size(app.image_info.logical_mask));
                app.ShowMenu.Text = 'Hide';
                delete(app.mskObj.Handle);
                app.mskObj.Shape = [];
                app.mskObj.Handle = [];
            end
            % Notify DataViewer on changes in Mask:
            if ~app.b_StandAlone
                notify(app, 'updateMask')
            end
        end

        % Menu selected function: ResetParametersMenu
        function ResetParametersMenuSelected(app, event)
            % This callback resets all image parameters.
            answer = uiconfirm(app.UIFigure, {'This action will reset: ', 'origin point, pixel size and mask', 'Proceed?'},...
                'Erase image parameters?', 'Icon', "question");
            if ~strcmp(answer, 'OK')
                return
            end
            app.image_info.refPt = [0,0];
            app.image_info.pxPermm = [];
            app.image_info.logical_mask = true(size(app.image_info.imageData));
            app.ShowMenu.Text = 'Hide';
            app.ShowHide_maskMenu;
            %app.image_info.imageData = app.image_info.reference_frame; % Reset imageData to reference frame
            app.shiftAx;
            app.updateTable;
            %             h = findobj(app.Ax, 'Tag', 'MyZeroLine');
            %             if ~isempty(h)
            %                 h(1).XData = [1,1];
            %                 h(2).YData = [1,1];
            %             end
            %
        end

        % Menu selected function: FromthresholdMenu
        function launchThresholdApp(app, event)
            % This callback launches the Matlab app "imageSegmenter" so the
            % user can interactively create binary mask to generate a new
            % ROI.
            disp('Launching thresholding app...')
            img = app.imageHandle.CData;
            app.figHandle.Visible = 'off';
            curr_masks = app.newMasks;
            a = ROIMask_creator(app,img, app.image_info.logical_mask);
            waitfor(a);
            if isequaln(curr_masks, app.newMasks)
                disp('Operation Cancelled by User')
                app.figHandle.Visible = 'on';
                return
            end
            % Create new ROIs from ROIMask_creator app and add them to the
            % list:
            app.importROIfromMask;
            %
            app.figHandle.Visible = 'on';
%             % Notify DataViewer on changes in ROI:
%             if ~app.b_StandAlone
%                 notify(app, 'updateROI')
%             end
        end

        % Menu selected function: ImportImgRefMenu
        function ImportImageAsRefFrame(app, event)
            % Callback to import image parameters from
            % "ImagingReferenceFrame.mat" file.
            [file, path] = uigetfile('*.mat','Select Imaging Reference Frame file', 'ImagingReferenceFrame.mat');
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            % Load Imaging Reference Frame file:
            imgRefInfo = load(fullfile(path, file));
            % Check if the reference_image has the same size than the
            % current image.
            b_OK = app.compareImageSize(imgRefInfo.reference_frame);
            if ~b_OK
                uialert(app.UIFigure, 'There is a mismatch between the sizes of the current image and the reference frame!', 'Failed to import reference image');
                return
            end
            % Put GUI in foreground, to shift attention of user to the UIALERTs in GUI:
            figure(app.UIFigure);
            
            % Confirm operation
            answer = uiconfirm(app.UIFigure, 'This action will overwrite the current image parameters. Proceed?', 'User Input',...
                'Options', {'Yes', 'Cancel'});
            if strcmpi(answer, 'cancel')
                disp('Operation cancelled by User');
                return
            end
            % Option to load reference_frame as current image:
            if app.b_StandAlone && ~isempty(app.image_info.imageData)
                answer = uiconfirm(app.UIFigure, 'Replace current image with the image reference?', 'User input','Options',{'Yes', 'No'}, 'DefaultOption',2);
                if strcmpi(answer, 'yes')
                    app.image_info.imageData = imgRefInfo.reference_frame;
                end
            elseif app.b_StandAlone && isempty(app.image_info.imageData)
                app.image_info.imageData = imgRefInfo.reference_frame;
            end
            % Show image:            
            app.displayImage;
            % Redraw ROIs:
            app.lockAxChild('image', true);
            app.overlayROI;
            app.lockAxChild('image', false);
            % Overwrite "image_info" fields with the data from
            % "ImagingReferenceFrame" file:
            fn = intersect(fieldnames(app.image_info), fieldnames(imgRefInfo));
            for i = 1:numel(fn)
                app.image_info.(fn{i}) = imgRefInfo.(fn{i});
            end
            % Update axis:
            app.shiftAx;
            % Update colormap:
            app.Ax.Colormap = app.image_info.cmap;
            % Show/Hide mask
            app.ShowHide_maskMenu;
            %
            uialert(app.UIFigure, 'Image parameters updated!', 'Import successful', 'Icon','info');
            %
        end

        % Menu selected function: AlignimagetooriginMenu
        function AlignImage_to_origin(app, event)
            % Callback to user align image using a line between the origin
            % and another reference point (e.g. lambda).
            % The image will be rotated so the line between the two points
            % will become vertical.
            
            % Check if reference point exists:
            if isempty(app.image_info.refPt)
                uialert(app.UIFigure, 'You must select a reference point first!', 'Reference point not set', 'Icon', 'error', 'modal', true);
                return
            end
            
            % Lock image:
            app.lockAxChild("image",true);
            % Draw line to movable point:
            ln = plot(app.Ax, [app.image_info.refPt(1) app.image_info.refPt(1)], [app.image_info.refPt(2) app.image_info.refPt(2)+20],...
                'go-', 'LineWidth', 2, 'MarkerSize', 10, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'g','Tag', 'myLine');
            % Draw selectable point:
            pt = drawpoint(app.Ax, 'Color', [0 1 0], 'Deletable', true, 'SelectedColor', [1 0 0], 'Position', [app.image_info.refPt(1) app.image_info.refPt(2)+20]);
            addlistener(pt,'MovingROI',@(src,evnt) moveLine(src,evnt,ln));
            addlistener(pt,'ROIMoved',@(src,evnt) gluePt(src,evnt, app));
            addlistener(pt,'ROIClicked',@(src,evnt) savePt(src,evnt, app));
            waitfor(pt);
            % Rotate image:
            [rotImag, phi] = app.rotateImage([ln.XData(2) ln.YData(2)]);
            % Delete line:
            delete(ln);
            %             % Unlock image:
            %             app.lockAxChild("image",false);
            % Update image:
            app.imageHandle.CData = rotImag; drawnow;
            % Confirm rotation:
            if ~isempty(fieldnames(app.ROI_list))
                msg = 'Save Rotation? All existing ROIs will also be rotated.';
            else
                msg = 'Save Rotation?';
            end
            answer = uiconfirm(app.UIFigure, msg,'Confirm Image rotation', 'Icon',"question");
            if strcmp(answer,'OK') && isempty(fieldnames(app.ROI_list))
                % Update image data:
                app.image_info.imageData = rotImag;
                % Update reference frame:
                app.image_info.reference_frame = rotateAround(app.image_info.reference_frame, app.image_info.refPt(2), app.image_info.refPt(1), phi, 'bicubic');
            elseif strcmp(answer,'OK') && ~isempty(fieldnames(app.ROI_list))
                % Update image data and rotate ROIs:
                app.image_info.imageData = rotImag;
                % Update reference frame:
                app.image_info.reference_frame = rotateAround(app.image_info.reference_frame, app.image_info.refPt(2), app.image_info.refPt(1), phi, 'bicubic');
                if ~isempty(app.ROI_list.Name)
                    % Rotate existing ROIs:
                    disp('Rotating ROIS...')
                    shape_arr = arrayfun(@(x) x.Shape.rotate(-phi, app.image_info.refPt), app.ROI_list);
                    app.addROItoList(shape_arr, {app.ROI_list.Name}, vertcat(app.ROI_list.Color), {app.ROI_list.Type})
                    app.ROI_sanity_check;
                    app.updateTable;
                    app.overlayROI;
                end
            else
                % Undo image rotation:
                app.imageHandle.CData = app.image_info.imageData; drawnow;
            end
            app.lockAxChild("image", false);
            
            
            % Listener callbacks:
            function moveLine(~,evnt,lineH)
                % Update line position:
                lineH.XData(2) = evnt.CurrentPosition(1);
                lineH.YData(2) = evnt.CurrentPosition(2);
            end
            function gluePt(src,~, app)
                % Unselect point after moved:
                src.Selected = false;
                src.Label = '';
                app.Ax.Title.String = 'Right-click on point to save';
            end
            function savePt(src,evnt, app)
                % Callback for drawrectangle function.
                % It deletes the rectangle object if the User double-clicks
                % inside the rectangle.
                click = evnt.SelectionType;
                selFace = evnt.SelectedPart;
                if strcmp(click, 'right') && strcmp(selFace, 'marker')
                    app.Ax.Title.String = '';
                    delete(src);
                end
            end
            
        end

        % Menu selected function: ExportMenu
        function ExportImageAsRefFrame(app, event)
            % Callback that exports the displayed image as a reference
            % frame for automatic or manual frame alignment.
            
            % Check missing image parameters:
            if app.image_info.pxPermm == 0
                answer = uiconfirm(app.UIFigure, 'Pixel size is not set. Proceed anyway?', 'Missing image info', 'Options', {'Yes', 'Set pixel size', 'Cancel'}, ...
                    "Icon","warning");
                switch answer
                    case 'Set pixel size'
                        app.SetImagePixelSize;
                        return
                    case 'Cancel'
                        return
                end
                
            elseif isempty(app.image_info.refPt)
                answer = uiconfirm(app.UIFigure, 'Reference point is not set. Proceed anyway?',...
                    'Missing image info','Options', {'Yes', 'Set Reference Point', 'Cancel'},...
                    "Icon","warning");
                switch answer
                    case 'Set Reference Point'
                        app.DrawNewRefPt
                        return
                    case 'Cancel'
                        return
                end
            end
            
            % Get frame, refPt, pxPermm, logical_Msk and datSize from
            % app.image_info
            out = app.image_info;
            out = rmfield(out, 'imageData');
            out.datSize = size(out.reference_frame);
            if ~isempty(out.datFile)
                [folder,~,~] = fileparts(out.datFile);
            else
                folder = pwd;
            end
            
            if ~isa(app.MainApp, 'umIToolbox')
                [file, savedir]= uiputfile(fullfile(folder, '*.mat'), 'Select folder to save Reference Frame data',...
                    fullfile(folder, 'ImagingReferenceFrame.mat'));
                if file == 0
                    disp('Operation cancelled by User')
                    return
                end
            else
                selObj= app.MainApp.selectedObjs{:};
                idx = false;
                % Find Subject object linked to the selected object in the
                % main app:
                while ~idx
                    if isa(selObj, 'Subject')
                        idx = true;
                    else
                        selObj = selObj.MyParent;
                    end
                end
                % Force save into Subject's folder:
                savedir = selObj.SaveFolder;
                file = 'ImagingReferenceFrame.mat';
            end
            save(fullfile(savedir, file),'-struct', 'out');
            msgbox(['Imaging reference Frame file saved as ' fullfile(savedir,file)]);
            
        end

        % Menu selected function: ShowMenu
        function ShowHide_maskMenu(app, event)
            % This callback shows/hides the mask axis "mskAx" overlay.
            
            if isempty(app.mskObj) || isempty(app.mskObj.Handle) || ~isvalid(app.mskObj.Handle)
                
                % Temporarily disable warnings:
                warning('off','all')
                % Create polyshape from roi_msk:
                anti_msk = polyshape([.5 .5 size(app.image_info.logical_mask,2)+.5, size(app.image_info.logical_mask,2)+.5],...
                    [.5 size(app.image_info.logical_mask,1)+.5 size(app.image_info.logical_mask,1)+.5 .5]);
                if all(app.image_info.logical_mask,'all')
                    app.mskObj.Shape = anti_msk;
                else
                    app.mskObj.Shape = app.mask2Polyshape(app.image_info.logical_mask);
                end
                % Enable warnings:
                warning('on','all')
                app.lockAxChild('all', true)
                if ~all(app.image_info.logical_mask(:))
                    app.mskObj.Handle = plot(app.Ax,subtract(anti_msk,app.mskObj.Shape), 'Tag', 'MskShape');
                    app.mskObj.Handle.FaceColor = [1 1 1];
                    app.mskObj.Handle.FaceAlpha = .2;
                    app.mskObj.Handle.EdgeColor = 'none';
                else
                    app.mskObj.Handle = [];
                end
                
                app.lockAxChild('all', false);
                % Notify DataViewer on changes in Mask:
                if ~app.b_StandAlone
                    notify(app, 'updateMask')
                end
            end
            
            % Show/Hide mask:
            if ~isempty(app.mskObj.Handle)
                if strcmp(app.ShowMenu(1).Text, 'Show')
                    app.ShowMenu(1).Text = 'Hide';
                    app.mskObj.Handle.Visible = 'on';
                else
                    app.ShowMenu(1).Text = 'Show';
                    app.mskObj.Handle.Visible = 'off';
                end
            end
            
        end

        % Menu selected function: matfileMenu, workspaceMenu
        function importROIsMenuSelected(app, event)
            % This callback imports the ROIs from different sources (.mat
            % file, workspace, .CSV and .ROI).
            % Here, we consider ROIs as 2D or 3D logical masks as well as
            % 2D Label matrices.
            
            % Check if there is an open image
            if isempty(app.image_info.imageData)
                uialert(app.UIFigure,'Open an image before importing ROIs!', 'Warning', 'Icon', 'warning');
                return
            end
            
            % Identify ROI source
            switch event.Source.Text
                case '.mat file'
                    [file,path] = uigetfile('*.mat', 'Select the .MAT file containing the ROIs', 'ROIfile.mat');
                    if file == 0
                        disp('Operation cancelled by User')
                        return
                    end
                    % Open list of variables from the .mat file:
                    mD = matfile(fullfile(path, file));
                    % Show list of valid logical masks:
                    vars = getValidMasks(mD);
                    [indx,tf] = listdlg('PromptString', 'Select a variable', 'SelectionMode', 'single','ListString', vars);
                    if tf == 0
                        disp('Operation cancelled by User')
                        return
                    end
                    new_rois = mD.(vars{indx});
                case 'workspace'
                    vars = getValidMasks('workspace');
                    if isempty(vars)
                        uialert(app.UIFigure, 'ROIs not found in workspace!', 'Failed to import ROIs from workspace.', 'Icon', 'error');
                        return
                    end
                    [indx,tf] = listdlg('PromptString', 'Select a variable', 'SelectionMode', 'single','ListString', vars);
                    if tf == 0
                        disp('Operation cancelled by User')
                        return
                    end
                    new_rois = evalin("base",[vars{indx} ';']);
                case '.CSV'
                    %%%% TODO %%%%
                case '.ROI'
                    %%%% TODO %%%%
            end
            
            % Check if ROI size is the same as the current figure:
            disp('Checking ROI frame size...')
            if ~isequaln(size(app.image_info.imageData), [size(new_rois,1) size(new_rois,2)])
                uialert(app.UIFigure, ['Failed to load ROI. The frame size of the ROI(' num2str(size(new_rois,1)) 'x' size(new_rois,2),...
                    ') is different from the size of the current image(' num2str(size(app.image_info.imageData,1)) 'x' num2str(size(app.image_info.imageData,2))], 'Failed to import ROI');
                return
            end
            % Reset "newMasks" property:
            app.newMasks = zeros(size(app.image_info.imageData),'single');
            % Add new ROIs to table
            if ndims(new_rois) == 3
                % For 3D matrix of logical.
                for i = 1:size(new_rois,3)
                    app.newMasks = app.newMasks + i.*(new_rois(:,:,i)~=0);
                end
            elseif islogical(new_rois) & ismatrix(new_rois)
                % For 2D matrix of logical.
                new_rois = bwlabel(new_rois);
                app.newMasks = bwlabel(new_rois);
            elseif ~islogical(new_rois) & ismatrix(new_rois)
                % For 2D labeled matrix.
                app.newMasks = new_rois;
            else
                if unique(new_rois) == 1
                    uialert(app.UIFigure, 'Failed to import ROI. The variable containing the ROIs must be a logical or a label matrix.',...
                        'Failed to import ROI', "Icon","error");
                    return
                end
            end
            app.importROIfromMask;
            % Local function(s)
            function out = getValidMasks(source)
                % This function checks if "source" contains potentially valid masks.
                % The "source" can be a matfile or the workspace.
                % Valid masks are defined as:
                % 1- 2D logical matrix.
                % 2- 3D logical matrix.
                % 3- 2D Label matrix.
                
                if isa(source, 'matlab.io.MatFile')
                    % List variables from .mat file
                    varsIN = setdiff(properties(source),'Properties');
                    sz = cellfun(@(x) size(source.(x)), varsIN, 'UniformOutput',false);
                    
                elseif strcmp(source, 'workspace')
                    % List variables from the workspace
                    varsIN = evalin('base', 'who');
                    source = struct;
                    for k = 1:numel(varsIN)
                        source.(varsIN{k}) = evalin('base', varsIN{k});
                    end
                end
                idx_bool = false(size(varsIN));
                idx_int = idx_bool;
                idx_sz = idx_bool;
                for k = 1:numel(varsIN)
                    idx_bool(k) = islogical(source.(varsIN{k}));
                    idx_sz(k) = ndims(source.(varsIN{k})) && size(source.(varsIN{k}),1)>1 && size(source.(varsIN{k}),2)>1;
                    if isnumeric(source.(varsIN{k}))
                        idx_int(k) = all(source.(varsIN{k}) == round(source.(varsIN{k})),'all');
                    end
                end
                out = varsIN( (idx_bool | idx_int) & idx_sz);
            end
        end

        % Menu selected function: ExporttabletoCSVMenu
        function ExporttabletoCSVMenuSelected(app, event)
            % This callback exports the current ROI table to a .CSV file
            % with some pertinent image info.
            
            if isempty(app.ROI_list(1).Name)
                uialert(app.UIFigure,'Empty Table!', 'Failed to export table', 'Icon','warning');
                return
            end
            
            %
            disp('Exporting table to CSV file')
            [file,path] = uiputfile('*.csv', 'Export table to .CSV...', 'roiTable.csv');
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            % Create image info table:
            refPt_str = strjoin({num2str(app.image_info.refPt(1), '%0.3f'), num2str(app.image_info.refPt(2), '%0.3f')}, ', ');
            if app.image_info.pxPermm > 0
                pxPermm_str = num2str(app.image_info.pxPermm);
            else
                pxPermm_str = 'none';
            end
            imgDatFile_str = app.image_info.datFile;
            if isempty(imgDatFile_str)
                imgDatFile_str = 'Unknown';
            end
            img_params = [{'Origin (X,Y)'; 'Pixel ratio (px/mm)'; 'image file'}, {refPt_str; pxPermm_str; imgDatFile_str}];
            tab = cell(height(app.UITable.Data)+6, numel(app.UITable.ColumnName)-2);
            tab{1,1} = 'Image parameters:';
            tab(2:4,1:2) = img_params;
            tab{5,1} = 'ROI info:';
            tab(6,:) = app.UITable.ColumnName(2:end-1)';
            tab(7:end,:) = table2cell(app.UITable.Data(:,2:end-1));
            
            % Write table to .CSV file:
            writecell(tab, fullfile(path,file));
            answer = uiconfirm(app.UIFigure, 'ROI table exported to .CSV file!', 'Finished exporting table', "Options", {'OK', 'Show folder'});
            if startsWith(answer, 'show', 'IgnoreCase', true)
                cd(path);
            end
        end

        % Menu selected function: XORMenu, intersectMenu, 
        % mergeMenu, splitMenu, subtractMenu
        function ROIbooleanOps_callback(app, event)
            % This callback performs boolean operations (split, merge, OR, XOR and subtraction)
            % on the selected ROIs.
            
            % Instantiate variables:
            b_select = app.UITable.Data.b_select; % Indices of selected ROIs from UITable.
            roiMath_name = event.Source.Text;% Name of ROI math operation.
            origMsks = arrayfun(@(x) x.Stats.ROI_binary_mask, app.ROI_list(b_select),'UniformOutput',false); % Cell array of binary matrices.
            origROInames = {app.ROI_list(b_select).Name}; % Names of selected ROIs from UITable.
            
            % Check minimal number of ROIs for each operation:
            if strcmp(roiMath_name, 'split') && sum(b_select)~=1
                uialert(app.UIFigure, 'Select at ONE ROI to split!', 'Wrong number of ROIs', 'Icon', "error");
                return
            elseif strcmp(roiMath_name, 'split') && sum(b_select)==1
                % Control for ROIs with multiple regions ("splittable")
                if length(regionprops(origMsks{:})) == 1
                    uialert(app.UIFigure, [ 'The ROI ' origROInames{:} ' has only one region. Select an ROI with multiple regions to split!'],...
                        'Failed to split ROI', 'Icon', "error");
                    return
                end
            elseif ~strcmp(roiMath_name, 'split') && sum(b_select)<2
                uialert(app.UIFigure, 'Select at least 2 ROIs to perform boolean operations!', 'Select 2 or more ROIs!', 'Icon', "error");
                return
            end
            
            % Perform Operations on ROIs
            switch roiMath_name
                case 'split'
                    newMsk = bwlabel(origMsks{:});
                    defROInames = {};
                    regN = unique(newMsk(:)); regN = regN(regN ~= 0);
                    for i = 1:length(regN)
                        defROInames{i} = [origROInames{1}, '_', num2str(i)];
                    end
                case 'merge'
                    newMsk= any(cat(3,origMsks{:}),3);
                    defROInames = {'ROI_merged'};
                case 'subtract'
                    [newMsk, defROInames] = subtractROI(b_select); % Check local functions below!
                    disp('out!')
                case {'intersect', 'XOR'}
                    % Here are some extra variables used in "intersect" and "XOR"
                    % options:
                    % Create array of every possible pairs of masks:
                    indx_pairs = nchoosek(1:length(origMsks),2); % List of all possible pairs of ROIs.
                    out_arr = cell(1,size(indx_pairs,1)); % Cell array for pairs of ROIs.
                    b_hasInteraction = true(size(out_arr)); % boolean array to check if pairs of ROIs interacted or not.
                    for i = 1:size(indx_pairs,1)
                        if strcmpi(roiMath_name, 'intersect')
                            out_arr{i}= origMsks{indx_pairs(i,1)} & origMsks{indx_pairs(i,2)};
                        else
                            out_arr{i}= sum(cat(3,origMsks{indx_pairs(i,1)}, origMsks{indx_pairs(i,2)}),3) == 1;
                        end
                        % Check for interactions between ROIs:
                        if ~any(out_arr{i},'all') || (sum(out_arr{i}, 'all') == sum([origMsks{indx_pairs(i,:)}],'all'))
                            b_hasInteraction(i) = false;
                        end
                    end
                    % Create list of default names:
                    % Here, we choose the name of a Mouse Allen Brain area
                    % as prefix or the first item of the pair in
                    % "indx_pairs" array:
                    disp('creating name list...')
                    resROInames = [origROInames(indx_pairs(b_hasInteraction,1))', origROInames(indx_pairs(b_hasInteraction,2))'];
                    defROInames = {};
                    % Find ROInames from Mouse Brain Atlas:
                    idxNames = endsWith(resROInames, '_R', 'IgnoreCase',true) | endsWith(resROInames, '_L', 'IgnoreCase',true);
                    % Create list of default names:
                    for i = 1:size(idxNames,1)
                        if all(idxNames(i,:)) || ~any(idxNames(i,:))
                            defROInames(i) = resROInames(i,1);
                        else
                            defROInames(i) = resROInames(i,idxNames(i,:));
                        end
                    end
                    % rename duplicate default names:
                    [~, ~, ic] = unique(defROInames, 'stable');
                    indx = unique(ic);
                    for i = 1:length(indx)
                        indxName = find(ic == indx(i));
                        for j = 1:length(indxName)
                            defROInames{indxName(j)} = [defROInames{indxName(j)}, '_', num2str(j)];
                        end
                    end                    %
                    % Create "newMsks" labeled matrix:
                    out_arr = out_arr(b_hasInteraction);
                    for i = 1:numel(out_arr)
                        out_arr{i} = out_arr{i}.*i;
                    end
                    
                    newMsk = sum(cat(3,out_arr{:}),3);
                otherwise
                    % This should never be reached.
                    error('Invalid Boolean Operation!');
            end
            % Check if the resulting ROI exists:
            disp('Checking resulting ROI...')
            if ~any(newMsk(:))
                app.UIFigure.Visible = 'on';
                uialert(app.UIFigure, 'The boolean operation yielded an empty ROI! Try again!', 'Failed to create ROI from boolean op.')
                return
            end
            % Plot results and ask user to confirm:
            
            % Undo default names if resulting label matrix has a different
            % number of ROIs:
            if numel(defROInames) ~= length(unique(newMsk(:)))-1
                defROInames = arrayfun(@(x) ['newROI_' num2str(x)], 1:length(unique(newMsk(:)))-1, 'UniformOutput', false);
            end
            [b_isConf, newROIname] = app.plotROImathResults(cat(3,origMsks{:}),origROInames, newMsk, defROInames);
            if ~b_isConf
                return
            end                       
            
            if isempty(app.ROI_list)
                % To avoid deleting the whole structure:
                app.ROI_list = app.ROI_list_def;
            end
            % Add new ROI
            app.newMasks = newMsk;
            app.importROIfromMask(newROIname)
            
            % Local functions for "subtract" option:
            function [out, outName] = subtractROI(idx_sel)
                % Auxiliary function for the ROIboolOPs callback.
                
                % Create dialog box to select ROIs to subtract. Only
                % two are allowed!
                dlg = uifigure('Name', 'Select the ROIs to subtract', "Position",[0 0 330 110], 'Visible', 'off', 'CloseRequestFcn', @subtractCloseRequest);
                movegui(dlg,'center');
                uidropdown("Parent",dlg,"Position",[10 50 100 25], "Items",{app.ROI_list(idx_sel).Name});
                uilabel('Parent', dlg, "Text", ' minus ', 'Position',[130 50 60 25]);
                uidropdown("Parent",dlg,"Position",[190 50 100 25], "Items",{app.ROI_list(idx_sel).Name});
                uibutton(dlg,'Text','Ok', "ButtonPushedFcn",@btnPushed, 'Position', [275 10 40 30], 'BackgroundColor',[0 1 0]);
                dlg.Visible = 'on';
                waitfor(dlg)
                if isempty(out)
                    disp('Operation cancelled by User')
                    return
                end
                
                function btnPushed(src,~)
                    % Callback for uibutton in "subtract" case GUI.
                    out = [];
                    if isa(src,'matlab.ui.Figure')
                        disp()
                        delete(src)
                        return
                    end
                    idx1 = strcmp(src.Parent.Children(4).Value, {app.ROI_list.Name});
                    idx2 = strcmp(src.Parent.Children(2).Value, {app.ROI_list.Name});
                    if any(idx1) && any(idx2)
                        out = app.ROI_list(idx1).Stats.ROI_binary_mask  - (app.ROI_list(idx1).Stats.ROI_binary_mask & app.ROI_list(idx2).Stats.ROI_binary_mask);
                    end
                    % Remove other ROIs from the list to be deleted
                    idx_sel = (idx1 | idx2)';
                    % Create default ROI name:
                    outName = {[app.ROI_list(idx1).Name, '_subtracted']};
                    % Delete GUI
                    if isa(src,'matlab.ui.control.Button')
                        delete(src.Parent)
                    else
                        delete(src)
                    end
                end
                
                function subtractCloseRequest(src,~)
                    out = [];
                    delete(src)
                end
            end
            
        end

        % Menu selected function: SaveParamstometadatafileMenu
        function SaveParamsToMetaData_callback(app, event)
            % Saves current values of "refPt" and "pxPermm" to data's meta
            % data file
            
            % Names of fields from "image_info" structure to be saved in
            % data's meta data file:
            fn = {'refPt', 'pxPermm'};
            % Update data metaData with image info:
            if isfile(app.image_info.datFile)
                mD = matfile(strrep(app.image_info.datFile, '.dat','.mat'), 'Writable', true);
                disp('Overwriting image parameters in data''s meta data file...')
                for i = 1:length(fn)
                    mD.(fn{i}) = app.image_info.(fn{i});
                end
                uialert(app.UIFigure, ['"Origin" and "Pixel size" values saved to file: ' app.image_info.datFile],...
                    'Save Params to meta data file', 'Icon',"info")
                disp('Done');
            else
                uialert(app.UIFigure, 'Could not find data file!', 'Save Params failed', 'Icon',"error")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ROImanager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewROIfileMenu = uimenu(app.FileMenu);
            app.NewROIfileMenu.MenuSelectedFcn = createCallbackFcn(app, @NewROIfile, true);
            app.NewROIfileMenu.Tooltip = {'Erases curernt ROIs and opens a new image.'; ''};
            app.NewROIfileMenu.Text = 'New';
            app.OpenROImasksfileMenu = uimenu(app.FileMenu);
            app.OpenROImasksfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenROIfile, true);
            app.OpenROImasksfileMenu.Text = 'Open ROImasks file';
            app.OpenImageMenu = uimenu(app.FileMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImage, true);
            app.OpenImageMenu.Text = 'Open Image';
            app.menuSep1 = uimenu(app.FileMenu);
            app.menuSep1.Enable = 'off';
            app.menuSep1.Text = '___________________';
            app.SavefileMenu = uimenu(app.FileMenu);
            app.SavefileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefileROI, true);
            app.SavefileMenu.Text = 'Save file';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasROI, true);
            app.SaveasMenu.Text = 'Save as...';
            app.menuSep2 = uimenu(app.FileMenu);
            app.menuSep2.Enable = 'off';
            app.menuSep2.Text = '___________________';
            app.ImportROIsfromMenu = uimenu(app.FileMenu);
            app.ImportROIsfromMenu.Text = 'Import ROIs from...';
            app.matfileMenu = uimenu(app.ImportROIsfromMenu);
            app.matfileMenu.MenuSelectedFcn = createCallbackFcn(app, @importROIsMenuSelected, true);
            app.matfileMenu.Tooltip = {'imports ROIs stored in a .mat file.'};
            app.matfileMenu.Text = '.mat file';
            app.workspaceMenu = uimenu(app.ImportROIsfromMenu);
            app.workspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @importROIsMenuSelected, true);
            app.workspaceMenu.Tooltip = {'Imports ROIs from MATLAB''s workspace.'};
            app.workspaceMenu.Text = 'workspace';
            app.ExporttabletoCSVMenu = uimenu(app.FileMenu);
            app.ExporttabletoCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttabletoCSVMenuSelected, true);
            app.ExporttabletoCSVMenu.Tooltip = {'Exports the current table to a .CSV file.'};
            app.ExporttabletoCSVMenu.Text = 'Export table to .CSV';
            app.CreateMenu = uimenu(app.UIFigure);
            app.CreateMenu.Text = 'Create';
            app.PolygonMenu = uimenu(app.CreateMenu);
            app.PolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawNewShape, true);
            app.PolygonMenu.Text = 'Polygon';
            app.PointMenu = uimenu(app.CreateMenu);
            app.PointMenu.MenuSelectedFcn = createCallbackFcn(app, @SinglepointMenuSelected, true);
            app.PointMenu.Text = 'Point';
            app.MouseAllenBrainAtlasMenu = uimenu(app.CreateMenu);
            app.MouseAllenBrainAtlasMenu.Text = 'Mouse Allen Brain Atlas';
            app.AreasMenu = uimenu(app.MouseAllenBrainAtlasMenu);
            app.AreasMenu.Text = 'Areas';
            app.SelectareasMenu = uimenu(app.AreasMenu);
            app.SelectareasMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllenAreas, true);
            app.SelectareasMenu.Text = 'Select areas';
            app.OptionsMenu_areas = uimenu(app.AreasMenu);
            app.OptionsMenu_areas.MenuSelectedFcn = createCallbackFcn(app, @Launch_Allen_areas_Settings, true);
            app.OptionsMenu_areas.Text = 'Options...';
            app.CentroidsMenu = uimenu(app.MouseAllenBrainAtlasMenu);
            app.CentroidsMenu.Text = 'Centroids';
            app.SelectcentroidsMenu = uimenu(app.CentroidsMenu);
            app.SelectcentroidsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllenCentroids, true);
            app.SelectcentroidsMenu.Text = 'Select centroids';
            app.OptionsMenu_centroids = uimenu(app.CentroidsMenu);
            app.OptionsMenu_centroids.MenuSelectedFcn = createCallbackFcn(app, @Launch_Allen_centroid_Settings, true);
            app.OptionsMenu_centroids.Text = 'Options...';
            app.FromthresholdMenu = uimenu(app.CreateMenu);
            app.FromthresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @launchThresholdApp, true);
            app.FromthresholdMenu.Text = 'From threshold...';
            app.menuSep4 = uimenu(app.CreateMenu);
            app.menuSep4.Enable = 'off';
            app.menuSep4.Text = '___________________';
            app.DeleteallROIsMenu = uimenu(app.CreateMenu);
            app.DeleteallROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @NewROIfile, true);
            app.DeleteallROIsMenu.Text = 'Delete all ROIs';
            app.SelectionMenu = uimenu(app.UIFigure);
            app.SelectionMenu.Text = 'Selection';
            app.EditMenu = uimenu(app.SelectionMenu);
            app.EditMenu.Text = 'Edit';
            app.ColorMenu = uimenu(app.EditMenu);
            app.ColorMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMenuSelected, true);
            app.ColorMenu.Text = 'Color';
            app.ShapeMenu = uimenu(app.EditMenu);
            app.ShapeMenu.Text = 'Shape';
            app.ConstrainededitMenu = uimenu(app.ShapeMenu);
            app.ConstrainededitMenu.MenuSelectedFcn = createCallbackFcn(app, @ConstrainedROIedit, true);
            app.ConstrainededitMenu.Tooltip = {'Change position while conserving the shape'};
            app.ConstrainededitMenu.Text = 'Constrained edit';
            app.UnconstrainededitMenu = uimenu(app.ShapeMenu);
            app.UnconstrainededitMenu.MenuSelectedFcn = createCallbackFcn(app, @UnconstrainedROIEdit, true);
            app.UnconstrainededitMenu.Tooltip = {'Change position'; ' size and shape'};
            app.UnconstrainededitMenu.Text = 'Unconstrained edit';
            app.DeleteROIMenu = uimenu(app.SelectionMenu);
            app.DeleteROIMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteROI, true);
            app.DeleteROIMenu.Text = 'Delete';
            app.ROImathMenu = uimenu(app.SelectionMenu);
            app.ROImathMenu.Text = 'ROI math';
            app.splitMenu = uimenu(app.ROImathMenu);
            app.splitMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIbooleanOps_callback, true);
            app.splitMenu.Tooltip = {'Splits Multi-region ROI (N =1).'};
            app.splitMenu.Text = 'split';
            app.mergeMenu = uimenu(app.ROImathMenu);
            app.mergeMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIbooleanOps_callback, true);
            app.mergeMenu.Tooltip = {'Adds ROIs together. (N >=2)'};
            app.mergeMenu.Text = 'merge';
            app.intersectMenu = uimenu(app.ROImathMenu);
            app.intersectMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIbooleanOps_callback, true);
            app.intersectMenu.Tooltip = {'Keep the overlapped region between ROIs (N >=2).'};
            app.intersectMenu.Text = 'intersect';
            app.XORMenu = uimenu(app.ROImathMenu);
            app.XORMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIbooleanOps_callback, true);
            app.XORMenu.Tooltip = {'Exclusive OR: keep the regions that do not overlap (N >=2). Better to go 2 at a time.'};
            app.XORMenu.Text = 'XOR';
            app.subtractMenu = uimenu(app.ROImathMenu);
            app.subtractMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIbooleanOps_callback, true);
            app.subtractMenu.Tooltip = {'subtracts one ROI from another (N =2).'};
            app.subtractMenu.Text = 'subtract';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.SetoriginMenu = uimenu(app.ImageMenu);
            app.SetoriginMenu.Tooltip = {'Axes origin (0'; '0)'};
            app.SetoriginMenu.Text = 'Set origin';
            app.NewOriginMenu = uimenu(app.SetoriginMenu);
            app.NewOriginMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawNewRefPt, true);
            app.NewOriginMenu.Text = 'New';
            app.ImportOrigfromfileMenu = uimenu(app.SetoriginMenu);
            app.ImportOrigfromfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportRefPt_fromFile, true);
            app.ImportOrigfromfileMenu.Text = 'Import from file...';
            app.AlignimagetooriginMenu = uimenu(app.SetoriginMenu);
            app.AlignimagetooriginMenu.MenuSelectedFcn = createCallbackFcn(app, @AlignImage_to_origin, true);
            app.AlignimagetooriginMenu.Text = 'Align image to origin';
            app.SetpixelsizeMenu = uimenu(app.ImageMenu);
            app.SetpixelsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @SetImagePixelSize, true);
            app.SetpixelsizeMenu.Tooltip = {'measured in pixels per mm'};
            app.SetpixelsizeMenu.Text = 'Set pixel size';
            app.SetcolormapMenu = uimenu(app.ImageMenu);
            app.SetcolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @Launch_cmap_Setting, true);
            app.SetcolormapMenu.Text = 'Set colormap';
            app.MaskMenu = uimenu(app.ImageMenu);
            app.MaskMenu.Tooltip = {'Image pixels outside the mask will be changed to NaNs. Existing ROIs will be intersected with the mask.'};
            app.MaskMenu.Text = 'Mask';
            app.DrawnewMenu = uimenu(app.MaskMenu);
            app.DrawnewMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawMask, true);
            app.DrawnewMenu.Tooltip = {''};
            app.DrawnewMenu.Text = 'Draw new';
            app.ImportMskfromfileMenu_2 = uimenu(app.MaskMenu);
            app.ImportMskfromfileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ImportMaskFromFile, true);
            app.ImportMskfromfileMenu_2.Tooltip = {'Import logical mask from .MAT file'};
            app.ImportMskfromfileMenu_2.Text = 'Import from file...';
            app.ShowMenu = uimenu(app.MaskMenu);
            app.ShowMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowHide_maskMenu, true);
            app.ShowMenu.Tooltip = {''};
            app.ShowMenu.Text = 'Show';
            app.DeleteMenu = uimenu(app.MaskMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMask, true);
            app.DeleteMenu.Text = 'Delete';
            app.ImagereferencefileMenu = uimenu(app.ImageMenu);
            app.ImagereferencefileMenu.Text = 'Image reference file...';
            app.ExportMenu = uimenu(app.ImagereferencefileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportImageAsRefFrame, true);
            app.ExportMenu.Text = 'Export';
            app.ImportImgRefMenu = uimenu(app.ImagereferencefileMenu);
            app.ImportImgRefMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageAsRefFrame, true);
            app.ImportImgRefMenu.Text = 'Import';
            app.menuSep3 = uimenu(app.ImageMenu);
            app.menuSep3.Enable = 'off';
            app.menuSep3.Text = '___________________';
            app.ResetParametersMenu = uimenu(app.ImageMenu);
            app.ResetParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetParametersMenuSelected, true);
            app.ResetParametersMenu.Text = 'Reset Parameters';
            app.SaveParamstometadatafileMenu = uimenu(app.ImageMenu);
            app.SaveParamstometadatafileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveParamsToMetaData_callback, true);
            app.SaveParamstometadatafileMenu.Tooltip = {'adds/overwrites "origin" and "pixel size"  in data''s meta data file.'};
            app.SaveParamstometadatafileMenu.Enable = 'off';
            app.SaveParamstometadatafileMenu.Text = 'Save Params to meta data file';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ROI table'};
            app.UITable.RowName = {};
            app.ListofRegionsofInterestLabel = uilabel(app.UIFigure);
            app.ListofRegionsofInterestLabel.Text = 'List of Regions of Interest';
            app.OverlaymodesButtonGroup = uibuttongroup(app.UIFigure);
            app.OverlaymodesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangeOverlayMode, true);
            app.OverlaymodesButtonGroup.Title = 'Overlay modes';
            app.contoursonlyButton = uiradiobutton(app.OverlaymodesButtonGroup);
            app.contoursonlyButton.Text = 'contours only';
            app.highlightpixelsButton = uiradiobutton(app.OverlaymodesButtonGroup);
            app.highlightpixelsButton.Text = 'highlight pixels';
            app.normalButton = uiradiobutton(app.OverlaymodesButtonGroup);
            app.normalButton.Text = 'normal';
            app.normalButton.Value = true;
            app.ImageParamsPanel = uipanel(app.UIFigure);
            app.ImageParamsPanel.Title = 'Image Params.';
            app.origxyEditFieldLabel = uilabel(app.ImageParamsPanel);
            app.origxyEditFieldLabel.Text = 'orig.(x,y)';
            app.origxyEditField = uieditfield(app.ImageParamsPanel, 'text');
            app.origxyEditField.Value = '0, 0';
            app.pxmmEditFieldLabel = uilabel(app.ImageParamsPanel);
            app.pxmmEditFieldLabel.Text = 'px/mm';
            app.pxmmEditField = uieditfield(app.ImageParamsPanel, 'text');
            app.pxmmEditField.Value = 'none';
            app.cmapEditFieldLabel = uilabel(app.ImageParamsPanel);
            app.cmapEditFieldLabel.Text = 'cmap';
            app.cmapEditField = uieditfield(app.ImageParamsPanel, 'text');
            app.cmapEditField.Value = 'none';
            app.logicalmskLabel = uilabel(app.ImageParamsPanel);
            app.logicalmskLabel.Text = {'logical'; 'msk'};
            app.logicalmskEditField = uieditfield(app.ImageParamsPanel, 'text');
            app.logicalmskEditField.Value = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImanager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end