classdef ROImanager_ImageWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlotoptionsPanel       matlab.ui.container.Panel
        ClipLabel              matlab.ui.control.Label
        minVal                 matlab.ui.control.NumericEditField
        toLabel                matlab.ui.control.Label
        maxVal                 matlab.ui.control.NumericEditField
        ShowcolorbarCheckBox   matlab.ui.control.CheckBox
        ColormapDropDownLabel  matlab.ui.control.Label
        ColormapDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        %%% EMPTY %%%
        
    end
    
    properties (Access = public)
        customAx % Axis of figure.
        imgHandle % Image Handle from customAx.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image)
            % ROImanager_ImageWindow creates an Axis to display an image.
            % Input:
            % image = 2D Matrix with image from ROImanager_Main App.
            
            % Rename figure and set position:
            app.UIFigure.Name = 'ROImanager -- Image window';
            app.UIFigure.Position = [582 333 706 602];
            % Create Axis and save handle to "customAx" property:
            app.customAx = axes('Parent', app.UIFigure, 'Position',[0.1 0.1 0.75 0.75]);
            % Plot image:
            app.imgHandle = imagesc(app.customAx, image); colormap(app.customAx, app.ColormapDropDown.Value);
            
            % Set axis properties:
            app.customAx.Interactions = [];
            app.customAx.DataAspectRatio = [1 1 1]; 
            app.customAx.DataAspectRatioMode = 'manual';
            app.customAx.XLim = [1 size(image,2)];
            app.customAx.YLim = [1 size(image,1)];
            app.customAx.XLabel.String = 'X (pixels)';
            app.customAx.YLabel.String = 'Y (pixels)';
                        
            % Display min and max values from image:
            app.minVal.Value =  double(min(image(:)));
            app.maxVal.Value = double(max(image(:)));
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            colormap(app.customAx, app.ColormapDropDown.Value);
        end

        % Value changed function: minVal
        function minValValueChanged(app, event)
            app.customAx.CLim(1) = app.minVal.Value;
        end

        % Value changed function: maxVal
        function maxValValueChanged(app, event)
            app.customAx.CLim(2) = app.maxVal.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Empty CloseRequestFnc. The figure will be closed by the
            % CallingApp.
        end

        % Value changed function: ShowcolorbarCheckBox
        function ShowcolorbarCheckBoxValueChanged(app, event)
            value = app.ShowcolorbarCheckBox.Value;
            if value
                colorbar(app.customAx)
            else
                colorbar(app.customAx, 'off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlotoptionsPanel = uipanel(app.UIFigure);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.ClipLabel = uilabel(app.PlotoptionsPanel);
            app.ClipLabel.Text = 'Clip';
            app.minVal = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.minVal.ValueChangedFcn = createCallbackFcn(app, @minValValueChanged, true);
            app.toLabel = uilabel(app.PlotoptionsPanel);
            app.toLabel.Text = 'to';
            app.maxVal = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.maxVal.ValueChangedFcn = createCallbackFcn(app, @maxValValueChanged, true);
            app.ShowcolorbarCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.ShowcolorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcolorbarCheckBoxValueChanged, true);
            app.ShowcolorbarCheckBox.Text = ' Show colorbar';
            app.ColormapDropDownLabel = uilabel(app.PlotoptionsPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.PlotoptionsPanel);
            app.ColormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'parula';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImanager_ImageWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end