classdef ROImanager_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenfileMenu                  matlab.ui.container.Menu
        SavefileMenu                  matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        ImportfromCSVMenu_2           matlab.ui.container.Menu
        ExporttoCSVMenu_2             matlab.ui.container.Menu
        OpenImageMenu_2               matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        CreatenewROIMenu              matlab.ui.container.Menu
        RectangleMenu                 matlab.ui.container.Menu
        PolygonMenu                   matlab.ui.container.Menu
        OvalMenu                      matlab.ui.container.Menu
        PointsMenu                    matlab.ui.container.Menu
        PresetMenu                    matlab.ui.container.Menu
        MouseAllenBrainAtlasMenu      matlab.ui.container.Menu
        LoadcorticalareasMenu         matlab.ui.container.Menu
        LoadcentroidsMenu             matlab.ui.container.Menu
        SelectionMenu_2               matlab.ui.container.Menu
        EditMenu_2                    matlab.ui.container.Menu
        DeleteMenu_2                  matlab.ui.container.Menu
        SetCoordinatesMenu_2          matlab.ui.container.Menu
        ReferencePointMenu_4          matlab.ui.container.Menu
        NewMenu_4                     matlab.ui.container.Menu
        FromfileMenu_3                matlab.ui.container.Menu
        SetpixelsizeMenu_3            matlab.ui.container.Menu
        UITable                       matlab.ui.control.Table
        ListofRegionsofInterestLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageData % Image to plot.
        imageHandle% Handle of image plotted in customAx.
        customAx % Axis from secondary app (ROImanager_ImageWindow) / StandAlone Mode;
        b_StandAlone % Boolean to indicate one of 2 modes ( 0 = Add-ON ; 1 = StandAlone);
        AllenAtlas % Structure of Top Projection coordinates of cortical areas from Mouse Allen Brain Atlas.
    end
    
    properties (Access = public)
        
        imageWinApp % Secondary App where the image is plotted.
    end
    
    methods (Access = private)
        
        function checkInput(app, input)
            % CHECKINPUT validates if the input to ROImanager is of the
            % following types:
            % 1) empty: Creates a figure with options to the user to load
            % image.
            % 2) Image: Image in 2 formats:
            %      a) 2-D numerical matrix.
            %      b) RGB image. In this case, it will be transformed in a
            %      grayscale image.
            % 3) Axis handle: Valid Axis handle (NOT UIAxes) from another
            % app (Add-on mode).
            app.b_StandAlone = true;
            if isempty(input)
                % If no input is provided:
                app.loadImage;
            elseif ismatrix(input) && isnumeric(input)
                % If the input is a 2D matrix:
                app.imageData = input;
            elseif isnumeric(input) && ndims(input) == 3 && size(input,3) == 3
                % If the Input is a RGB image:
                app.imageData = rgb2gray(input);
            elseif isa(input, 'matlab.graphics.axis.Axes')
                % If the input is an Axis and its only Child is Image:
                app.customAx = input;
                app.imageData = input.Children.CData;
                app.imageHandle= input.Children;
                app.b_StandAlone = false;
            else
                % Otherwise thrown an error message and closes the app:
                uialert(app.UIFigure, {'Invalid Input. Input must be one of these types :', 'Axis handle,', ...
                    '2-D numerical matrix', 'or a RGB image'}, 'Error!', 'Icon', 'error', ...
                    'Modal',true, 'CloseFcn', @(a,b) app.UIFigureCloseRequest);
            end
        end
        
        function loadImage(app)
            % LOADIMAGE create GUIs to open Images from File:
            % The accepted file formats are:
            % 1) .MAT file containing 2-D matrices or RGB images.
            % 2) Any graphics file format accepted by Matlab built-in function "imread".
            [filename, path] = uigetfile({'*.mat', 'MAT-files (*.mat)'; '*.png; *.jpeg; *.tif; *.bmp',...
                'Common image Files (*.png; *.jpeg; *.tif; *.bmp)'; '*.*', 'All Files (*.*)'});
            if filename == 0
                disp('Operation Cancelled by User.')
                return
            end
            
            if endsWith(filename, '.mat')
                mD = matfile(fullfile(path, filename));
                varList = setdiff(properties(mD), {'Properties'});
                [idx, tf] = listdlg("ListString", varList, "PromptString", {'Select the image' ;'from the variables below:'}, ...
                    "SelectionMode", 'single');
                if tf
                    eval(['app.imageData = mD.' varList{idx} ';'])
                else
                    disp('Operation cancelled by User.')
                    return
                end
            else
                try
                    RGB = imread(fullfile(path, filename));
                    if ndims(RGB) == 3
                        app.imageData = rgb2gray(RGB);
                    elseif ismatrix(RGB)
                        app.imageData = RGB;
                    else
                        error('Invalid Image file!');
                    end
                catch ME
                    uialert(app.UIFigure, ME.message, 'Error while loading Image file', "CloseFcn",@(a,b) app.UIFigureCloseRequest);
                end
            end
        end
        
        function lockImage(app, b_lock)
            % LOCKIMAGE changes the "NextPlot" property of "customAx" in order to
            % lock/unlock "image".
            % Input:
            % b_lock = Boolean. If TRUE, "image" is locked in "customAx", otherwise
            % "image" is unlocked and can be deleted.
            
            if b_lock
                % Change "NextPlot" property of "customAx":
                set(app.customAx, 'NextPlot', 'replacechildren');
                set(app.imageHandle, 'HandleVisibility', 'off');
            else
                set(app.customAx, 'NextPlot', 'replace');
                set(app.imageHandle, 'HandleVisibility', 'on');
            end
            
        end
        
        function displayImage(app)
            % DISPLAYIMAGE loads ROImanager_ImageWindow App with
            % "app.imageData" as input and saves axis and image handles.
            
            app.imageWinApp = ROImanager_ImageWindow(app.imageData);
            app.customAx = app.imageWinApp.customAx;
            app.imageHandle = app.imageWinApp.imgHandle;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %            Functions for generating ROIs from Mouse Allen Brain Atlas                %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function loadAtlas(app)
            % LOADATLAS saves the Mouse Allen Brain Atlas structure to
            % "AllenAtlas" property.
            
            root = getenv('Umitoolbox');
            a = load(fullfile(root, 'GUI', 'DataViz', 'mouse_ctx_borders.mat'));
            app.AllenAtlas = a.atlas;
        end
        
        function selectAreas(app)
            % SELECTAREAS creates a window with an interactive UITable
            % with the list of the mouse cortical areas.
            % Output: 
            % idx_area = index of the selected areas in atlas.areatag;
            % Load areas infos into table:
            tdata = table(app.AllenAtlas.areatag, app.AllenAtlas.area_longNames,...
                app.AllenAtlas.area_ClusterNames, true(length(app.AllenAtlas.areatag),1));
            % Create Window and UITable:
            fH = uifigure('Name', 'Select Mouse cortical areas -- Close this window to save selection',...
                'NumberTitle', 'off', 'Position', [708 455 705 526]);
            fH.KeyPressFcn = @(src,evnt) toggleTabSelection(src,evnt);
            fH.CloseRequestFcn = @(src,evnt) SaveAndCloseFig(src,evnt,app);
            tab = uitable(fH, 'Position', [30 20 560 490], 'RowStriping',"on");
            tab.Data = tdata;
            tab.ColumnName = {'Acronym', 'Area Name', 'Modality', 'Selected'};
            tab.Tooltip = 'Check boxes to select areas OR highlight boxes, click outside the table and press "Enter"';
            tab.ColumnSortable = [true true true true];
            tab.ColumnEditable = [false false false true];
            tab.CellSelectionCallback = @(src, evnt) selectElement(src,evnt);
            
            %%%%%% Callbacks %%%%%
            function toggleTabSelection(src, evnt)
                % Callback of UIFigure. Changes the State of selected cells in
                % tab if user presses "Enter".
                
                if strcmp(evnt.Key, 'return')
                    indx = src.Children.UserData;
                    if isempty(indx)
                        return
                    end
                    
                    if unique(indx(:,2)) ~= 4
                        return
                    end
                    state = src.Children.Data.Var4(indx(:,1));
                    if all(state) || all(~state)
                        src.Children.Data.Var4(indx(:,1)) = ~state;
                    else
                        src.Children.Data.Var4(indx(:,1)) = true;
                    end
                    disp('Toggle!')
                end
            end
            
            function selectElement(src, evnt)
                % Callback function of tab.
                % It stores the selected column indices in
                % "UserData" property of tab.
                src.UserData = evnt.Indices;
            end
            
            function SaveAndCloseFig(src,~, app)
                % CloseRequestFcn callback of fH.
                % It Saves the indices of the selected cortical areas to a
                % new field in app.AllenAtlas structure called
                % "idx_selected".
                app.AllenAtlas.idx_selected = src.Children.Data.Var4;
                disp('Data Saved to Allen Atlas')
                delete(src)
            end


            disp('Tab Open!')


        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % ROImanager works as add-on to other apps of the toolbox as
            % well as an standalone.
            
            % Set Figure Position:
            app.UIFigure.Position = [173 378 405 536];
            
            % Argument Validation:
            narginchk(1,2);
            p = inputParser;
            addOptional(p,'input', []);
            parse(p,varargin{:});
            app.checkInput(p.Results.input);
            if app.b_StandAlone && ~isempty(app.imageData)
                % If the App mode is StandAlone, open window to display
                % image:
                app.displayImage;
            end
            % Set customAx to lock "image":
            app.lockImage(true);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp('Close Request Called!')
            delete(app.imageWinApp)
            delete(app)
        end

        % Menu selected function: OpenImageMenu_2
        function OpenImageMenu_2Selected(app, event)
            app.loadImage;
            app.displayImage;
        end

        % Menu selected function: LoadcorticalareasMenu
        function LoadcorticalareasMenuSelected(app, event)
            app.loadAtlas;
            app.selectAreas;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenfileMenu = uimenu(app.FileMenu);
            app.OpenfileMenu.Text = 'Open file';
            app.SavefileMenu = uimenu(app.FileMenu);
            app.SavefileMenu.Text = 'Save file';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.ImportfromCSVMenu_2 = uimenu(app.FileMenu);
            app.ImportfromCSVMenu_2.Text = 'Import from CSV';
            app.ExporttoCSVMenu_2 = uimenu(app.FileMenu);
            app.ExporttoCSVMenu_2.Text = 'Export to CSV';
            app.OpenImageMenu_2 = uimenu(app.FileMenu);
            app.OpenImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenu_2Selected, true);
            app.OpenImageMenu_2.Text = 'Open Image';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreatenewROIMenu = uimenu(app.ToolsMenu);
            app.CreatenewROIMenu.Text = 'Create new ROI';
            app.RectangleMenu = uimenu(app.CreatenewROIMenu);
            app.RectangleMenu.Text = 'Rectangle';
            app.PolygonMenu = uimenu(app.CreatenewROIMenu);
            app.PolygonMenu.Text = 'Polygon';
            app.OvalMenu = uimenu(app.CreatenewROIMenu);
            app.OvalMenu.Text = 'Oval';
            app.PointsMenu = uimenu(app.CreatenewROIMenu);
            app.PointsMenu.Text = 'Point(s)';
            app.PresetMenu = uimenu(app.CreatenewROIMenu);
            app.PresetMenu.Text = 'Preset';
            app.MouseAllenBrainAtlasMenu = uimenu(app.PresetMenu);
            app.MouseAllenBrainAtlasMenu.Text = 'Mouse Allen Brain Atlas';
            app.LoadcorticalareasMenu = uimenu(app.MouseAllenBrainAtlasMenu);
            app.LoadcorticalareasMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadcorticalareasMenuSelected, true);
            app.LoadcorticalareasMenu.Text = 'Load cortical areas';
            app.LoadcentroidsMenu = uimenu(app.MouseAllenBrainAtlasMenu);
            app.LoadcentroidsMenu.Text = 'Load centroids';
            app.SelectionMenu_2 = uimenu(app.ToolsMenu);
            app.SelectionMenu_2.Text = 'Selection';
            app.EditMenu_2 = uimenu(app.SelectionMenu_2);
            app.EditMenu_2.Text = 'Edit';
            app.DeleteMenu_2 = uimenu(app.SelectionMenu_2);
            app.DeleteMenu_2.Text = 'Delete';
            app.SetCoordinatesMenu_2 = uimenu(app.ToolsMenu);
            app.SetCoordinatesMenu_2.Text = 'Set Coordinates';
            app.ReferencePointMenu_4 = uimenu(app.SetCoordinatesMenu_2);
            app.ReferencePointMenu_4.Text = 'Reference Point';
            app.NewMenu_4 = uimenu(app.ReferencePointMenu_4);
            app.NewMenu_4.Text = 'New';
            app.FromfileMenu_3 = uimenu(app.ReferencePointMenu_4);
            app.FromfileMenu_3.Text = 'From file...';
            app.SetpixelsizeMenu_3 = uimenu(app.SetCoordinatesMenu_2);
            app.SetpixelsizeMenu_3.Text = 'Set pixel size';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ListofRegionsofInterestLabel = uilabel(app.UIFigure);
            app.ListofRegionsofInterestLabel.Text = 'List of Regions of Interest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImanager_Main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end