classdef ROImanager_Opts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIOptsUIFigure              matlab.ui.Figure
        SaveButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        SetcentroidROIradiusPanel    matlab.ui.container.Panel
        UnitButtonGroup              matlab.ui.container.ButtonGroup
        pixelsdefButton              matlab.ui.control.RadioButton
        mmButton                     matlab.ui.control.RadioButton
        RadiusEditFieldLabel         matlab.ui.control.Label
        RadiusEditField              matlab.ui.control.NumericEditField
        AreaoptionsPanel             matlab.ui.container.Panel
        ROIsurfaceButtonGroup        matlab.ui.container.ButtonGroup
        UsefullsurfaceButton         matlab.ui.control.RadioButton
        ShrinkROIButton              matlab.ui.control.RadioButton
        N_pixelsSpinnerLabel         matlab.ui.control.Label
        Area_shrink_spinner          matlab.ui.control.Spinner
        SetImageColormapPanel        matlab.ui.container.Panel
        LoadfromFileButton           matlab.ui.control.Button
        choosefromlistDropDownLabel  matlab.ui.control.Label
        choosefromlistDropDown       matlab.ui.control.DropDown
        orLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Handle to ROImanager_Main app.
        optsName % String indicating which options to show : {'area', 'centroid'};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, optsName)
            app.optsName = optsName;
            app.MainApp = callingApp;
            opts = app.MainApp.AllenROIopts;
            switch app.optsName
                case 'area'
                    app.AreaoptionsPanel.Visible = 'on';
                    app.SetcentroidROIradiusPanel.Visible = 'off';
                    app.Area_shrink_spinner.Value = opts.Area.n_shrinkPix;
                    % Set params:
                    if  opts.Area.n_shrinkPix == 0
                        app.ROIsurfaceButtonGroup.SelectedObject = app.UsefullsurfaceButton;
                        app.Area_shrink_spinner.Enable = 'off';
                    else
                        app.ROIsurfaceButtonGroup.SelectedObject = app.ShrinkROIButton;
                        app.Area_shrink_spinner.Enable = 'on';
                    end
                otherwise
                    app.AreaoptionsPanel.Visible = 'off';
                    app.SetcentroidROIradiusPanel.Visible = 'on';
                    % Set params:
                    app.RadiusEditField.Value = opts.Centroid.Radius;
                    if strcmp('px', opts.Centroid.Unit)
                        app.UnitButtonGroup.SelectedObject = app.pixelsdefButton;
                    else
                        app.UnitButtonGroup.SelectedObject = app.mmButton;
                    end
                    
                    % If the pixel ratio is not set in the MainApp, Disable
                    % mmButton:
                    if isempty(app.MainApp.image_coordinates.pxPermm)
                        app.mmButton.Enable = 'off';
                    end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Set Area params:    
            app.MainApp.AllenROIopts.Area.n_shrinkPix = app.Area_shrink_spinner.Value;
            
            % Set Centroid params:
            app.MainApp.AllenROIopts.Centroid.Radius = app.RadiusEditField.Value;
            unit = app.UnitButtonGroup.SelectedObject.Text;
            switch unit
                case 'pixels (def)'
                    app.MainApp.AllenROIopts.Centroid.Unit = 'px';
                otherwise
                    app.MainApp.AllenROIopts.Centroid.Unit = 'mm';
            end
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Selection changed function: ROIsurfaceButtonGroup
        function ROIsurfaceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ROIsurfaceButtonGroup.SelectedObject;
            if selectedButton == app.ShrinkROIButton
                app.Area_shrink_spinner.Enable = 'on';
            else
                app.Area_shrink_spinner.Value = 0;
                app.Area_shrink_spinner.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIOptsUIFigure = uifigure('Visible', 'off');
            app.ROIOptsUIFigure.Name = 'ROI--Opts';
            app.SaveButton = uibutton(app.ROIOptsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ROIOptsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {''};
            app.CancelButton.Text = 'Cancel';
            app.SetcentroidROIradiusPanel = uipanel(app.ROIOptsUIFigure);
            app.SetcentroidROIradiusPanel.Title = 'Set centroid ROI radius';
            app.SetcentroidROIradiusPanel.Visible = 'off';
            app.UnitButtonGroup = uibuttongroup(app.SetcentroidROIradiusPanel);
            app.UnitButtonGroup.Title = 'Unit';
            app.pixelsdefButton = uiradiobutton(app.UnitButtonGroup);
            app.pixelsdefButton.Tooltip = {'Choose an unit'};
            app.pixelsdefButton.Text = 'pixels (def)';
            app.pixelsdefButton.Value = true;
            app.mmButton = uiradiobutton(app.UnitButtonGroup);
            app.mmButton.Tooltip = {'Disabled if Pixel size is not defined.'};
            app.mmButton.Text = 'mm';
            app.RadiusEditFieldLabel = uilabel(app.SetcentroidROIradiusPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.SetcentroidROIradiusPanel, 'numeric');
            app.RadiusEditField.Limits = [0.01 Inf];
            app.RadiusEditField.Tooltip = {'Type positive radius value.'};
            app.RadiusEditField.Value = 1;
            app.AreaoptionsPanel = uipanel(app.ROIOptsUIFigure);
            app.AreaoptionsPanel.Title = 'Area options';
            app.AreaoptionsPanel.Visible = 'off';
            app.ROIsurfaceButtonGroup = uibuttongroup(app.AreaoptionsPanel);
            app.ROIsurfaceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIsurfaceButtonGroupSelectionChanged, true);
            app.ROIsurfaceButtonGroup.Title = 'ROI surface';
            app.UsefullsurfaceButton = uiradiobutton(app.ROIsurfaceButtonGroup);
            app.UsefullsurfaceButton.Tooltip = {'Use 100% of the ROI surface (default).'};
            app.UsefullsurfaceButton.Text = 'Use full surface';
            app.UsefullsurfaceButton.Value = true;
            app.ShrinkROIButton = uiradiobutton(app.ROIsurfaceButtonGroup);
            app.ShrinkROIButton.Tooltip = {'Removes pixels from ROI boundaries using the "shrink" algorithm of bwmorph MATLAB function.'};
            app.ShrinkROIButton.Text = 'Shrink ROI';
            app.N_pixelsSpinnerLabel = uilabel(app.AreaoptionsPanel);
            app.N_pixelsSpinnerLabel.Text = 'N_pixels';
            app.Area_shrink_spinner = uispinner(app.AreaoptionsPanel);
            app.Area_shrink_spinner.Limits = [0 Inf];
            app.Area_shrink_spinner.Tooltip = {'Number of pixels to remove from ROI boundaries.'};
            app.SetImageColormapPanel = uipanel(app.ROIOptsUIFigure);
            app.SetImageColormapPanel.Title = 'Set Image Colormap';
            app.SetImageColormapPanel.Visible = 'off';
            app.LoadfromFileButton = uibutton(app.SetImageColormapPanel, 'push');
            app.LoadfromFileButton.Text = 'Load from File';
            app.choosefromlistDropDownLabel = uilabel(app.SetImageColormapPanel);
            app.choosefromlistDropDownLabel.Text = 'choose from list:';
            app.choosefromlistDropDown = uidropdown(app.SetImageColormapPanel);
            app.orLabel = uilabel(app.SetImageColormapPanel);
            app.orLabel.Text = 'or';
            % Show the figure after all components are created
            app.ROIOptsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImanager_Opts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIOptsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIOptsUIFigure)
        end
    end
end