classdef RetinotopyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SavedatatoMATMenu            matlab.ui.container.Menu
        ExportImagesMenu             matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        ImageoptionsMenu             matlab.ui.container.Menu
        PlaybackspeedMenu            matlab.ui.container.Menu
        FFTMenu                      matlab.ui.container.Menu
        MapsMenu                     matlab.ui.container.Menu
        phaseMenu                    matlab.ui.container.Menu
        setcolormapMenu              matlab.ui.container.Menu
        showasisolinesMenu           matlab.ui.container.Menu
        amplitudeMenu                matlab.ui.container.Menu
        setcolormapMenu_2            matlab.ui.container.Menu
        PlotMenu                     matlab.ui.container.Menu
        setYaxisscaletologMenu       matlab.ui.container.Menu
        ProcessMenu                  matlab.ui.container.Menu
        RawdataMenu                  matlab.ui.container.Menu
        NormalizeMenu                matlab.ui.container.Menu
        AveragesweepsMenu            matlab.ui.container.Menu
        RestoreMenu                  matlab.ui.container.Menu
        GenazimuthelevationmapsMenu  matlab.ui.container.Menu
        VSMMenu                      matlab.ui.container.Menu
        VSMsettingsMenu_2            matlab.ui.container.Menu
        GenVSMMenu                   matlab.ui.container.Menu
        OpenVSMinROImanagerMenu_2    matlab.ui.container.Menu
        CoordinatesPanel             matlab.ui.container.Panel
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        Slider_azim                  matlab.ui.control.Slider
        PlayButton                   matlab.ui.control.StateButton
        TextArea                     matlab.ui.control.TextArea
        Slider_elev                  matlab.ui.control.Slider
        UIAx_0                       matlab.ui.control.UIAxes
        UIAx_90                      matlab.ui.control.UIAxes
        UIAx_180                     matlab.ui.control.UIAxes
        UIAx_270                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RetMap_obj % Handle of a RetinotopyMapping object.
        dataSource % Full path of the .DAT file containing the retinotopy data.
        fH % Structure containing the handles of all 6 figures from the App.
        
        crossHairPos = [0,0] % Current X,Y coordinates of the white crossHair.
        crossHairHandleList % Array of handles of the existing crossHair objects in all figures.
    end
    
    methods (Access = private)
        
        function b_isOK = setup(app, inputDatFile, evntFile)
            % SETUP verifies if the inputFile contains a valid Imaging
            % dataset and if an event.mat file exists. If so, it saves the
            % data and meta data to the app's properties and instantiates a
            % "RetinotopyMapping" object.
            % In this case, a valid image dataset is comprised of
            % a 3-D movie with dimensions {Y,X,T}.
            %
            % Inputs:
            %   inputDatFile (char): Full path to the .dat file containing
            %       the imaging data.
            %   evntFile (char): Full path to the "event.mat" file
            %       associated with the "inputDatFile".
            % Output:
            %   b_isOK (bool): True if all inputs were successfully read and saved in the app.
            
            b_isOK = false;
            
            if ~isfile(inputDatFile) || ~endsWith(inputDatFile, '.dat')
                app.UIFigure.Visible = 'off';
                err = errordlg({'Wrong data file! This app only reads valid .DAT file.', 'See documentation for details.', 'This app will close now.'},...
                    'Invalid input file', 'modal');
                waitfor(err)
                app.TextArea.Value = ['Failed to open: ' inputDatFile]; drawnow;
                return
            end
            % Force fullpath to inputFile:
            [ifPath,~,~] = fileparts(inputDatFile);
            if isempty(ifPath)
                inputDatFile = fullfile(pwd,inputDatFile);
            end
            % Load data and metaData:
            app.TextArea.Value = ['Please wait. Loading ' inputDatFile]; drawnow;
            [data, metaData] = loadDatFile(inputDatFile);
            app.TextArea.Value = 'Data loaded!'; drawnow;
            dim_names = metaData.dim_names;
            if iscell([dim_names{:}]) % For retrocompatibility with .dat files containing more than one array.
                dim_names = dim_names{1};
            end
            % Validate if the data is an Image time series:
            valid_dims = {'Y', 'X', 'T'};
            if ~isequal(dim_names, valid_dims)
                app.TextArea.Value = ['Failed to open: ' inputDatFile]; drawnow;
                uialert(app.UIFigure, 'Invalid input dimension. The data must have the dimensions: {"Y", "X", "T"}', ...
                    'Invalid input', 'Modal',true, 'CloseFcn', 'uiresume(gcbf)');
                uiwait(app.UIFigure);
                return
            end
            
            % Validate if the event.mat file exists:
            if ~isfile(evntFile)
                app.TextArea.Value = ['Failed to open: ' inputDatFile]; drawnow;
                uialert(app.UIFigure, 'Events file not found!', ...
                    'Invalid input', 'Modal',true, 'CloseFcn', 'uiresume(gcbf)');
                uiwait(app.UIFigure);
                return
            end
            % Load event info:
            app.TextArea.Value = 'Loading events.mat file...'; drawnow;
            evntData = load(evntFile);
            % Save path of data source:
            app.dataSource = inputDatFile;
            % Instantiate a "RetinotopyMapping" object:
            app.RetMap_obj = RetinotopyMapping(data, metaData, evntData);
            app.TextArea.Value = ['Retinotopy file: ' inputDatFile]; drawnow;
            % Set Output variable to TRUE:
            b_isOK = true;
        end
        
        function setFigs(app)
            % SETFIGS creates the app's figures and sets their respective
            % callbacks and default parameters.
            % Here, a total of 6 figures will be created:
            % Fig1 : Shows the raw movies of each stimulus direction.
            % Fig2 : Shows the FFT phase and amplitude maps for each direction.
            % Fig3 : Shows the FFT plot for the selected pixel from any image.
            % Fig4 : Shows the Azimuth and Elevation Phase/Amplitude maps.
            % Fig5 : Shows the Visual Sign Map (VSM) of the recording.
            
            app.TextArea.Value = 'Creating figures...'; drawnow;
            app.crossHairHandleList = [];
            %%% Create Fig1: %%%
            app.fH.Fig1.fig = figure('Name', 'Stimulus Direction Movies', 'NumberTitle',"off", "MenuBar","none", "ToolBar","figure", 'Position', [1 1 500 500],...
                'Visible', 'on','CloseRequestFcn',@app.DoNotCloseFig);
            app.fH.Fig1.fig.BusyAction = 'cancel';
            
            % Create axes:
            app.fH.Fig1.ax1 = subplot(221); app.fH.Fig1.ax1.Title.Interpreter = 'tex'; app.fH.Fig1.ax1.Title.String = '0^{\circ}';
            app.fH.Fig1.ax2 = subplot(222); app.fH.Fig1.ax2.Title.Interpreter = 'tex'; app.fH.Fig1.ax2.Title.String = '90^{\circ}';
            app.fH.Fig1.ax3 = subplot(223); app.fH.Fig1.ax3.Title.Interpreter = 'tex'; app.fH.Fig1.ax3.Title.String = '180^{\circ}';
            app.fH.Fig1.ax4 = subplot(224); app.fH.Fig1.ax4.Title.Interpreter = 'tex'; app.fH.Fig1.ax4.Title.String = '270^{\circ}';
            
            % Display dummy image and create crossHair object in axis 1:
            img1 = imagesc(app.fH.Fig1.ax1, 'CData', magic(6), 'Tag', 'fig1_img1', "HandleVisibility","callback", 'ButtonDownFcn', @app.ImageButtonDown);
            cH1 = line(app.fH.Fig1.ax1, [1 1; app.fH.Fig1.ax1.XLim]', [app.fH.Fig1.ax1.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback',...
                'Tag', 'fig1_crossHair1');
            app.crossHairHandleList = [app.crossHairHandleList,cH1];
            % Make copies of image and crossHair objects to populate the other axes:
            copyAxChildren(img1,cH1, app.fH.Fig1.ax1, app.fH.Fig1.ax2, 1,2)
            copyAxChildren(img1,cH1, app.fH.Fig1.ax1, app.fH.Fig1.ax3, 1,3)
            copyAxChildren(img1,cH1, app.fH.Fig1.ax1, app.fH.Fig1.ax4, 1,4)
            
            % Set behaviour for first axis. The properties will be expanded to the others:
            set(app.fH.Fig1.ax1,...
                'NextPlot', 'replacechildren',...
                'YDir', 'reverse',...
                'DataAspectRatio', [1 1 1],...
                'DataAspectRatioMode', 'manual', ...
                'Colormap', colormap('gray'));
            
            %%% Create Fig2: %%%
            app.fH.Fig2.fig = figure('Name', 'FFT phase and amplitude maps', 'NumberTitle',"off", "MenuBar","none", "ToolBar","figure",...
                'Position', [1 1 700 500], 'Visible', 'on','CloseRequestFcn',@app.DoNotCloseFig);
            
            % Create axes:
            app.fH.Fig2.ax1 = subplot(241); app.fH.Fig2.ax1.Title.Interpreter = 'tex'; app.fH.Fig2.ax1.Title.String = '0^{\circ} A';
            app.fH.Fig2.ax2 = subplot(242); app.fH.Fig2.ax2.Title.Interpreter = 'tex'; app.fH.Fig2.ax2.Title.String = '0^{\circ} \phi';
            
            app.fH.Fig2.ax3 = subplot(243); app.fH.Fig2.ax3.Title.Interpreter = 'tex'; app.fH.Fig2.ax3.Title.String = '90^{\circ} A';
            app.fH.Fig2.ax4 = subplot(244); app.fH.Fig2.ax4.Title.Interpreter = 'tex'; app.fH.Fig2.ax4.Title.String = '90^{\circ} \phi';
            
            app.fH.Fig2.ax5 = subplot(245); app.fH.Fig2.ax5.Title.Interpreter = 'tex'; app.fH.Fig2.ax5.Title.String = '180^{\circ} A';
            app.fH.Fig2.ax6 = subplot(246); app.fH.Fig2.ax6.Title.Interpreter = 'tex'; app.fH.Fig2.ax6.Title.String = '180^{\circ} \phi';
            
            app.fH.Fig2.ax7 = subplot(247); app.fH.Fig2.ax7.Title.Interpreter = 'tex'; app.fH.Fig2.ax7.Title.String = '270^{\circ} A';
            app.fH.Fig2.ax8 = subplot(248); app.fH.Fig2.ax8.Title.Interpreter = 'tex'; app.fH.Fig2.ax8.Title.String = '270^{\circ} \phi';
            
            % Display dummy image and create crossHair object in axis 1:
            img1 = imagesc(app.fH.Fig2.ax1, 'CData', magic(6), 'Tag', 'fig2_img1', "HandleVisibility","callback", 'ButtonDownFcn', @app.ImageButtonDown);
            cH1 = line(app.fH.Fig2.ax1, [1 1; app.fH.Fig2.ax1.XLim]', [app.fH.Fig2.ax1.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback',...
                'Tag', 'fig2_crossHair1');
            app.crossHairHandleList = [app.crossHairHandleList,cH1];
            % Make copies of image and crossHair objects to populate the other axes:
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax2, 2,2)
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax3, 2,3)
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax4, 2,4)
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax5, 2,5)
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax6, 2,6)
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax7, 2,7)
            copyAxChildren(img1,cH1, app.fH.Fig2.ax1, app.fH.Fig2.ax8, 2,8)
            %
            
            %%% Create Fig3: %%%
            app.fH.Fig3.fig = figure('Name', 'FFT plot of selected pixel', 'NumberTitle',"off", "Color", "w", "MenuBar","none", "ToolBar","figure",...
                'Position', [1 1 600 250], 'Visible', 'on','CloseRequestFcn',@app.DoNotCloseFig);
            app.fH.Fig3.ax1 = axes(app.fH.Fig3.fig);
            set(app.fH.Fig3.ax1, 'Position', [0.08 0.2 0.85 0.72],'NextPlot','replacechildren');
            set(app.fH.Fig3.ax1.Title, 'String', 'FFT amplitude');
            set(app.fH.Fig3.ax1.XLabel, 'String', 'Freq(Hz)')
            set(app.fH.Fig3.ax1.YLabel, 'String', 'Amplitude(A.U)');
            
            
            %%% Create Fig4: %%%
            app.fH.Fig4.fig = figure('Name', 'Azimuth and Elevation Maps', 'NumberTitle',"off", "MenuBar","none", "ToolBar","figure", 'Position', [1 1 500 500],...
                'Visible', 'on','CloseRequestFcn',@app.DoNotCloseFig);
            % Create axes:
            app.fH.Fig4.ax1 = subplot(221); app.fH.Fig4.ax1.Title.Interpreter = 'tex'; app.fH.Fig4.ax1.Title.String = 'Azimuth \phi';
            app.fH.Fig4.ax2 = subplot(222); app.fH.Fig4.ax2.Title.Interpreter = 'tex'; app.fH.Fig4.ax2.Title.String = 'Azimuth A';
            app.fH.Fig4.ax3 = subplot(223); app.fH.Fig4.ax3.Title.Interpreter = 'tex'; app.fH.Fig4.ax3.Title.String = 'Elevation \phi';
            app.fH.Fig4.ax4 = subplot(224); app.fH.Fig4.ax4.Title.Interpreter = 'tex'; app.fH.Fig4.ax4.Title.String = 'Elevation A';
            
            % Display dummy image and create crossHair object in axis 1:
            img1 = imagesc(app.fH.Fig4.ax1, 'CData', magic(6), 'Tag', 'fig4_img1', "HandleVisibility","callback", 'ButtonDownFcn', @app.ImageButtonDown);
            cH1 = line(app.fH.Fig4.ax1, [1 1; app.fH.Fig4.ax1.XLim]', [app.fH.Fig4.ax1.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback',...
                'Tag', 'fig4_crossHair1');
            app.crossHairHandleList = [app.crossHairHandleList,cH1];
            % Make copies of image and crossHair objects to populate the other axes:
            copyAxChildren(img1,cH1, app.fH.Fig4.ax1, app.fH.Fig4.ax2, 4,2)
            copyAxChildren(img1,cH1, app.fH.Fig4.ax1, app.fH.Fig4.ax3, 4,3)
            copyAxChildren(img1,cH1, app.fH.Fig4.ax1, app.fH.Fig4.ax4, 4,4)
            
            %%% Create Fig5: %%%
            app.fH.Fig5.fig = figure('Name', 'Visual Sign Map', 'NumberTitle',"off", "MenuBar","none", "ToolBar","figure", 'Position', [1 1 500 500],...
                'Visible', 'on','CloseRequestFcn',@app.DoNotCloseFig);
            % Create axes:
            app.fH.Fig5.ax1 = subplot(121); app.fH.Fig5.ax1.Title.Interpreter = 'tex'; app.fH.Fig5.ax1.Title.String = 'VSM';
            app.fH.Fig5.ax2 = subplot(122); app.fH.Fig5.ax2.Title.Interpreter = 'tex'; app.fH.Fig5.ax2.Title.String = 'Segmented Areas';
            
            % Display dummy image and create crossHair object in axis 1:
            img1 = imagesc(app.fH.Fig5.ax1, 'CData', magic(6), 'Tag', 'fig5_img1', "HandleVisibility","callback", 'ButtonDownFcn', @app.ImageButtonDown);
            cH1 = line(app.fH.Fig5.ax1, [1 1; app.fH.Fig5.ax1.XLim]', [app.fH.Fig5.ax1.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback',...
                'Tag', 'fig5_crossHair1');
            app.crossHairHandleList = [app.crossHairHandleList,cH1];
            % Make copies of image and crossHair objects to populate the other axis:
            copyAxChildren(img1,cH1, app.fH.Fig5.ax1, app.fH.Fig5.ax2, 5,2)
            
            % Link properties of all axes from figures 1,2,4 and 5 to have the same behaviour:
            app.fH.Fig1.ax1.UserData = linkprop([app.fH.Fig1.ax1, app.fH.Fig1.ax2, app.fH.Fig1.ax3, app.fH.Fig1.ax4, ...
                app.fH.Fig2.ax1, app.fH.Fig2.ax2, app.fH.Fig2.ax3, app.fH.Fig2.ax4,...
                app.fH.Fig2.ax5, app.fH.Fig2.ax6, app.fH.Fig2.ax7, app.fH.Fig2.ax8,...
                app.fH.Fig4.ax1, app.fH.Fig4.ax2, app.fH.Fig4.ax3, app.fH.Fig4.ax4,...
                app.fH.Fig5.ax1, app.fH.Fig5.ax2],...
                {'NextPlot', 'YDir', 'DataAspectRatio', 'DataAspectRatioMode', 'PlotBoxAspectRatio',...
                'PlotBoxAspectRatioMode', 'XLim','XLimMode','YLim','YLimMode'});
            
            % Local function
            function copyAxChildren(imgHandle, crossHairHandle, source,target, figIndx, axIndx)
                % This function copies the imagesc and the crossHair handles from a source
                % axis to a target one and rename their respective Tags.
                
                % Copy image Handle
                newH1 = copyobj(imgHandle, source);
                newH1.Tag = ['fig' num2str(figIndx) '_img' num2str(axIndx)];
                newH1.ButtonDownFcn = @app.ImageButtonDown;
                newH1.Parent = target;
                % Copy Crosshair handles:
                newH2 = copyobj(crossHairHandle, source);
                arrayfun(@(x) set(x, 'Tag', ['fig' num2str(figIndx) '_crossHair' num2str(axIndx)],'Parent', target), newH2);
                % Store handle in crossHairList:
                app.crossHairHandleList = [app.crossHairHandleList,newH2];
            end
            
        end
        
        
        function flag = isMultipleCall(~)
            % ISMULTIPLECALL detects multiple calls to a given function
            % (where it is called from) in order to avoid reentrancy
            % problems (e.g. when a callback is called multiple times
            % before finishing the execution of the first one, this
            % function detects it and the callback execution can be suspended until the first one is finished).
            
            flag = false;
            % Get the stack
            s = dbstack();
            if numel(s) <= 2
                % Stack too short for a multiple call
                return
            end
            % How many calls to the calling function are in the stack?
            names = {s(:).name};
            TF = strcmp(s(2).name,names);
            count = sum(TF);
            if count>1
                % More than 1 == multiple calls!
                flag = true;
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function DoNotCloseFig(~,~,~)
            % EMPTY CALLBACK TO AVOID USER CLOSING SOME FIGURES %
        end
        
        function ImageButtonDown(app,src,evnt)
            b_isMultiCall = app.isMultipleCall;
            if b_isMultiCall
                disp('Please Wait!')
                return
            end
            click = get(src.Parent.Parent, 'SelectionType');
            coords = get(src.Parent, 'CurrentPoint');
            app.crossHairPos = round(coords(1,1:2));
            b_in_bounds = app.getBounds(src.Parent);
            if ~b_in_bounds
                return
            end
            
            if strcmp(click, 'normal')
                app.updateCrossHairPos;
                app.XEditField.Value = double(app.crossHairPos(1));
                app.YEditField.Value = double(app.crossHairPos(2));
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updateCrossHairPos(app,varargin)
            % Changes the position of the crosshair and updates the plots
            % Optional input:
            % [X,Y] coordinates.
            
            if nargin == 2
                app.crossHairPos = varargin{:};
            end
            % Shift CrossHair in all Images:
            for i = 1:size(app.crossHairHandleList,2)
                cH = app.crossHairHandleList(:,i);
                set(cH(1), 'XData', cH(1).Parent.XLim, 'YData', [app.crossHairPos(2) app.crossHairPos(2)]);
                set(cH(2), 'XData', [app.crossHairPos(1) app.crossHairPos(1)], 'YData', cH(2).Parent.XLim);
            end
        end
        
        function b_in_bounds = getBounds(app, ax)
            % GETBOUNDS verifies if the current position of the mouse
            % cursor (stored in "app.crossHairPos") is inside the boundaries
            % of the axis "UIAxes".
            % Input:
            %  ax(handle): handle of the axis where the user clicked over.
            % Output:
            % b_in_bounds (bool) : true, if cursor is inside the image,
            % false, if is not.
            
            % get axis limits:
            x_lims = get(ax, 'XLim');
            y_lims = get(ax, 'YLim');
            
            % Check if cursor is inside the axis limits:
            b_in_bounds = (app.crossHairPos(1) >= x_lims(1)) && ...
                (app.crossHairPos(1) <= x_lims(2)) && ...
                (app.crossHairPos(2) >= y_lims(1)) && ...
                (app.crossHairPos(2) <= y_lims(2));
        end
        
        function splitMovs(app)
            % This function separates the raw data into four movies, each one containing
            % the Image time series associated with one direction of the
            % retinotopic visual stimulus.


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datFile, evntFile)
            % Startup fcn:
            %   - Instantiates the handles of all figures and sets its
            %   callbacks.
            %   - Instantiates a "RetinotopyMapping" object.
            % Inputs:
            %   datFile(char): Full path of the .dat file containing the
            %   retinotopy data.
            %   evntFile(char|handle): Full path of the "event.mat" file
            %   associated with the recording OR the Protocol's object
            %   handle.
            
            % Get event.mat file path from object handle:
            if isa(evntFile, 'Modality')
                evntFile = fullfile(evntFile.SaveFolder,'event.mat');
            end
            % Check input and instantiate RetinotopyMapping object:
            b_isOK = app.setup(datFile, evntFile);
            % Close app if conditions are not met:
            if ~b_isOK
                app.UIFigureCloseRequest;
            end            
            % Create Figures:
            app.setFigs;
            % Load raw data and populate Figure 1:
            
            % All set!
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            fN = fieldnames(app.fH);
            for i = 1:numel(fN)
                delete(app.fH.(fN{i}).fig);
            end
            delete(app)
        end

        % Value changed function: XEditField, YEditField
        function XYEditFieldValueChanged(app, event)
            
            X = round(app.XEditField.Value);
            Y = round(app.YEditField.Value);
            % Find image1 from fig1:
            imgH = findall(app.fH.Fig1.fig, 'Tag', 'fig1_img1');
            % Control for out of bounds values:
            if X < 1 || X > size(imgH.CData,2)
                app.XEditField.Value = event.PreviousValue;
                return
            end
            if Y < 1 || Y > size(imgH.CData,1)
                app.YEditField.Value = event.PreviousValue;
                return
            end
            % Update crossHair position:
            app.updateCrossHairPos([X,Y]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Retinotopy App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SavedatatoMATMenu = uimenu(app.FileMenu);
            app.SavedatatoMATMenu.Text = 'Save data to .MAT';
            app.ExportImagesMenu = uimenu(app.FileMenu);
            app.ExportImagesMenu.Text = 'Export Images';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ImageoptionsMenu = uimenu(app.SettingsMenu);
            app.ImageoptionsMenu.Text = 'Image options';
            app.PlaybackspeedMenu = uimenu(app.SettingsMenu);
            app.PlaybackspeedMenu.Text = 'Playback speed';
            app.FFTMenu = uimenu(app.SettingsMenu);
            app.FFTMenu.Text = 'FFT';
            app.MapsMenu = uimenu(app.FFTMenu);
            app.MapsMenu.Text = 'Maps';
            app.phaseMenu = uimenu(app.MapsMenu);
            app.phaseMenu.Text = 'phase';
            app.setcolormapMenu = uimenu(app.phaseMenu);
            app.setcolormapMenu.Text = 'set colormap';
            app.showasisolinesMenu = uimenu(app.phaseMenu);
            app.showasisolinesMenu.Text = 'show as isolines';
            app.amplitudeMenu = uimenu(app.MapsMenu);
            app.amplitudeMenu.Text = 'amplitude';
            app.setcolormapMenu_2 = uimenu(app.amplitudeMenu);
            app.setcolormapMenu_2.Text = 'set colormap';
            app.PlotMenu = uimenu(app.FFTMenu);
            app.PlotMenu.Text = 'Plot';
            app.setYaxisscaletologMenu = uimenu(app.PlotMenu);
            app.setYaxisscaletologMenu.Text = 'set Y axis scale to log';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.RawdataMenu = uimenu(app.ProcessMenu);
            app.RawdataMenu.Text = 'Raw data';
            app.NormalizeMenu = uimenu(app.RawdataMenu);
            app.NormalizeMenu.Text = 'Normalize';
            app.AveragesweepsMenu = uimenu(app.RawdataMenu);
            app.AveragesweepsMenu.Text = 'Average sweeps';
            app.RestoreMenu = uimenu(app.RawdataMenu);
            app.RestoreMenu.Text = 'Restore';
            app.GenazimuthelevationmapsMenu = uimenu(app.ProcessMenu);
            app.GenazimuthelevationmapsMenu.Text = 'Gen. azimuth/elevation maps';
            app.VSMMenu = uimenu(app.ProcessMenu);
            app.VSMMenu.Tooltip = {'Visual Sign Map'};
            app.VSMMenu.Text = 'VSM';
            app.VSMsettingsMenu_2 = uimenu(app.VSMMenu);
            app.VSMsettingsMenu_2.Tooltip = {'Parameters to calculate the visual sign map'};
            app.VSMsettingsMenu_2.Text = 'VSM settings';
            app.GenVSMMenu = uimenu(app.VSMMenu);
            app.GenVSMMenu.Tooltip = {'Generate visual sign map'};
            app.GenVSMMenu.Text = 'Gen. VSM';
            app.OpenVSMinROImanagerMenu_2 = uimenu(app.VSMMenu);
            app.OpenVSMinROImanagerMenu_2.Tooltip = {'Opens the visual sign map as ROIs in ROImanager app'};
            app.OpenVSMinROImanagerMenu_2.Text = 'Open VSM in ROImanager';
            app.CoordinatesPanel = uipanel(app.UIFigure);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.XEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XYEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @XYEditFieldValueChanged, true);
            app.Slider_azim = uislider(app.UIFigure);
            app.Slider_azim.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_azim.MinorTicks = [];
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.Icon = 'icon_playButton.png';
            app.PlayButton.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.Slider_elev = uislider(app.UIFigure);
            app.Slider_elev.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_elev.MinorTicks = [];
            app.UIAx_0 = uiaxes(app.UIFigure);
            title(app.UIAx_0, '0^{\circ}')
            xlabel(app.UIAx_0, '')
            ylabel(app.UIAx_0, '')
            app.UIAx_90 = uiaxes(app.UIFigure);
            title(app.UIAx_90, '90^{\circ}')
            xlabel(app.UIAx_90, 'X')
            ylabel(app.UIAx_90, 'Y')
            app.UIAx_180 = uiaxes(app.UIFigure);
            title(app.UIAx_180, '180^{\circ}')
            xlabel(app.UIAx_180, 'X')
            ylabel(app.UIAx_180, 'Y')
            app.UIAx_270 = uiaxes(app.UIFigure);
            title(app.UIAx_270, '270^{\circ}')
            xlabel(app.UIAx_270, 'X')
            ylabel(app.UIAx_270, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RetinotopyApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end