classdef SeedPixMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        SeedPixelMapLabel      matlab.ui.control.Label
        MapButtonGroup         matlab.ui.container.ButtonGroup
        CorrelationButton      matlab.ui.control.ToggleButton
        PValueButton           matlab.ui.control.ToggleButton
        colormapDropDownLabel  matlab.ui.control.Label
        colormapDropDown       matlab.ui.control.DropDown
        TextArea               matlab.ui.control.TextArea
        GeneratefigureButton   matlab.ui.control.Button
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mDat % memmapfile of SeedPxCorr Data.
        btnDwnPos % Position of the clicked point in the plot.
        clickType % ID of left or right mouse click
        CurrentCoords = [1 1]; % Mouse Current Coordinates;
        SeedCoords = [1 1] % Seed Pixel Coordinates.
        CurrentVal  = -1% CurrentValue from Plot
        Data % Currently selected data
        CurrentFrame % Currently plotted Frame
        start = false % start getting Mouse position.
        my_cmap % selected colormap from DropDown menu
%         crossHairPtr % Description
        clims = [-1 1] % color limit for imagesc plots
        path % Directory of SeedPixelCorr data.
        atlas % Contains mouse cortical areas boundaries.
        inputObj % input Object
        inputFile % inputFile.
    end
    
    methods (Access = private)
        
        function displayInfo(app)
            % Displays important information in APP.LABEL.
            txt = {};
            txt{1} = ['Current position (X,Y) : ' num2str(app.CurrentCoords(1)) ' , ' num2str(app.CurrentCoords(2))];
            txt{2} = ['Current Value : ' num2str(app.CurrentVal)];
            txt{3} = ['Seed Pixel Coordinate (X,Y) :' num2str(app.SeedCoords(1)) ' , ' num2str(app.SeedCoords(2))];
            app.TextArea.Value = txt;
        end
        
        
        function UpdateMap(app)
            ind = sub2ind(fliplr(size(app.CurrentFrame)), app.SeedCoords(1), app.SeedCoords(2));    
            app.CurrentFrame = squeeze(app.Data(:,:,ind));
            imagesc(app.UIAxes,app.CurrentFrame, app.clims); hold(app.UIAxes, 'on')
%             app.crossHairPtr = images.roi.Crosshair(app.UIAxes, 'InteractionsAllowed', 'none', 'Position', app.CurrentCoords, 'Color', [1 1 1]);
            line(app.UIAxes, [app.UIAxes.XLim(1) app.UIAxes.XLim(2)], [app.CurrentCoords(2) app.CurrentCoords(2)], 'Color', 'white')
            line(app.UIAxes, [app.CurrentCoords(1) app.CurrentCoords(1)], [app.UIAxes.YLim(1) app.UIAxes.YLim(2)],'Color', 'white')
            hold(app.UIAxes, 'on')
        end
        
        %         function UpdateCurrentPt(app, hFig, eve)
        %             C = get(gca, 'CurrentPoint');
        %             app.CurrentCoords = round(C(1,1:2));
        %             in_bounds = getBounds(app.UIAxes, app.CurrentCoords);
        %             if ~in_bounds
        %                 return
        %             end
        %             hold on
        %             line(app.UIAxes, [app.CurrentCoords(1) app.CurrentCoords(1)], app.UIAxes.YLim, "Color", "white");
        %             line(app.UIAxes, app.UIAxes.XLim, [app.CurrentCoords(2) app.CurrentCoords(2)], app.UIAxes.YLim, "Color", "white");
        %             hold off
        %             app.displayInfo;
        %             function in_bounds = getBounds(axes_handle, position)
        %                 x_lims = get(axes_handle, 'XLim');
        %                 y_lims = get(axes_handle, 'YLim');
        %
        %                 in_bounds = (position(1) >= x_lims(1)) && ...
        %                     (position(1) <= x_lims(2)) && ...
        %                     (position(2) >= y_lims(1)) && ...
        %                     (position(2) <= y_lims(2));
        %             end
        %         end
        
        function updateCurrentCoords(app)
            coords = get(app.UIAxes, 'CurrentPoint');
            app.CurrentCoords = round(coords(1,1:2));
            
        end
        
        function in_bounds = getBounds(app)
            x_lims = get(app.UIAxes, 'XLim');
            y_lims = get(app.UIAxes, 'YLim');
            
            in_bounds = (app.CurrentCoords(1) >= x_lims(1)) && ...
                (app.CurrentCoords(1) <= x_lims(2)) && ...
                (app.CurrentCoords(2) >= y_lims(1)) && ...
                (app.CurrentCoords(2) <= y_lims(2));
        end
        
        function checkInput(app)
            % CHECKINPUT verifies if inputObj and inputFile obey the
            % necessary conditions.
            % Output:
            
            if numel(app.inputObj) ~=1
                uiwait(errordlg('Only one object is accepted!', 'Error!', 'modal'))
                return
            end
            file = fullfile(app.inputObj{:}.SaveFolder, app.inputFile);
            app.mDat = mapDatFile(file);
            fields = fieldnames(app.mDat.Data);
            if ~all(ismember(fields, {'CM', 'P'}))
                uiwait(errordlg('Wrong data File! Choose a Seed-Pixel Correlation .DAT file.', 'Error!', 'modal'))
                return
            end
            app.Label.Text = 'Opening file...'; drawnow
            app.Data = flipud(rot90(app.mDat.Data.CM));
            % Draw first Frame
            app.CurrentFrame = squeeze(app.Data(:,:,1));
            FrSz = size(app.CurrentFrame);
            app.UIAxes.XLim = [1 FrSz(2)]; app.UIAxes.YLim = [1 FrSz(1)];
            imagesc(app.UIAxes,app.CurrentFrame, app.clims); colormap(app.UIAxes, app.my_cmap); colorbar(app.UIAxes, 'westoutside')
            app.start = true;
            app.Label.Text = ['File : ' file]; drawnow
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputObj, inputFile)
            root = getenv('Umitoolbox');
            file = fullfile(root, 'GUI\DataViz\mouse_ctx_borders.mat');
            a = load(file);
            app.atlas = a.atlas;
            app.my_cmap = app.colormapDropDown.Value;
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.Title.String = app.MapButtonGroup.SelectedObject.Text;
            % Initialize parameters
            if ~iscell(inputObj)
                inputObj = {inputObj};
            end
            app.inputObj = inputObj;
            app.inputFile = inputFile;
            app.checkInput;
            if ~app.start
                delete(app)
            end
        end

        % Callback function
        function OpenFileButtonPushed(app, event)
            path = uigetdir(pwd, 'Select Experiment Folder');
            app.path = fullfile(path, 'CtxImag');
            cd(app.path);
            files = dir('*.dat');
            
            if ~ismember('SeedPixCorr.dat', {files.name})
                uialert(app.UIFigure, ['Cant find file SeedPixCorr.dat in ' app.path], 'Error!', "Modal",true);
                return
            end
            
            app.mDat = mapDatFile(fullfile(app.path, 'SeedPixCorr.dat'));
            app.Data = flipud(rot90(app.mDat.Data.CM));
            % Draw first Frame
            app.CurrentFrame = squeeze(app.Data(:,:,1));
            FrSz = size(app.CurrentFrame);
            app.UIAxes.XLim = [1 FrSz(1)]; app.UIAxes.YLim = [1 FrSz(2)];
            imagesc(app.UIAxes,app.CurrentFrame, app.clims); colormap(app.UIAxes, app.my_cmap); colorbar(app.UIAxes, 'westoutside')
%             app.crossHairPtr = images.roi.Crosshair(app.UIAxes, 'InteractionsAllowed', 'none', 'Position', [1,1], 'Color', [1 1 1]);
            app.start = true;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            click = get(app.UIFigure, 'SelectionType');
            in_bounds = app.getBounds;
            if ~in_bounds
                return
            end
            if strcmp(click, 'normal')
                app.SeedCoords = app.CurrentCoords;
                app.UpdateMap;
            end
            app.CurrentVal = app.CurrentFrame(app.CurrentCoords(2), app.CurrentCoords(1));
            app.displayInfo;
        end

        % Selection changed function: MapButtonGroup
        function MapButtonGroupSelectionChanged(app, event)
            selectedButton = app.MapButtonGroup.SelectedObject;
            map = selectedButton.Text;
            switch map
                case 'Correlation'
                    app.Data = flipud(rot90(app.mDat.Data.CM));
                    app.UIAxes.Title.String = 'Correlation Map';
                    app.clims = [-1 1];
                otherwise
                    app.Data = flipud(rot90(app.mDat.Data.P));
                    app.UIAxes.Title.String = 'P-Value Map';
                    app.clims = [0 1];
            end
            
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            app.my_cmap = app.colormapDropDown.Value;
            colormap(app.UIAxes, app.my_cmap);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.start
            app.updateCurrentCoords;
            in_bounds = app.getBounds;
            if ~in_bounds
                return
            else
%                 app.crossHairPtr.Position = app.CurrentCoords;
                app.CurrentVal = app.CurrentFrame(app.CurrentCoords(2), app.CurrentCoords(1));
                app.displayInfo;
            end
            end
        end

        % Button pushed function: GeneratefigureButton
        function GeneratefigureButtonPushed(app, event)
        factor = 10; % interpolation factor;    
        cmap = app.colormapDropDown.Value;
        cd(app.inputObj{:}.SaveFolder)
            chanFile = 'gChan.dat';
            chanFiles = dir('*Chan*.dat'); chanFiles = {chanFiles.name};
            tf = true;
            if ~ismember(chanFile, chanFiles)
                [answer,tf] = listdlg("ListString", chanFiles, "PromptString", 'Select a channel to click on landmarks', "SelectionMode", "single");
                chanFile = chanFiles(answer);
            end
            if ~tf
                return
            end
            dat = mapDatFile(chanFile);
            Fr_anat = flipud(rot90(dat.Data.data(:,:,1)));
            imsz = size(app.CurrentFrame)*factor;
            Fr_anat = imresize(Fr_anat,imsz, 'Method',"bicubic");
            fig = figure;
            imagesc(Fr_anat); colormap gray;
            axis image
            X = []; Y = [];
            str = {'Bregma', 'Lambda'};
            for i = 1:2
                title(['Click on ' str{i}])
                [x,y] = ginput(1);
                X = [X;x];
                Y = [Y;y];
            end
            close(fig)
            % Perform geometric transformation in mask
            tform = fitgeotrans([app.atlas.BX app.atlas.BY ; app.atlas.LX app.atlas.LY],[X Y], 'nonreflectivesimilarity');
            R = imref2d(imsz);
            areas = imwarp(app.atlas.flatAtlas, tform, 'nearest', 'OutputView', R);
            borders = boundarymask(areas);
            indx = [6 7 8 24];
            indx = [indx indx+24];
            areaNames = app.atlas.areatag(indx);
            areaX = app.atlas.sx(indx);
            areaY = app.atlas.sy(indx);
%             areaY = [app.atlas.sy(indx) app.atlas.sy(indx)];
%             distX = areaX - app.atlas.BX;
%             areaX = [areaX areaX - 2*distX+1];
            [Xnew, Ynew] = transformPointsForward(tform, areaX, areaY);
            figure;
            subplot(2,5,1); ax=gca;
            imagesc(ax, Fr_anat); colormap(ax, 'gray'); hold(ax,"on");
            imagesc(ax, borders, 'AlphaData', borders); title(ax, 'Anat')
            plot(X, Y, 'rx', 'MarkerSize',10);
            hold(ax, "off"); axis image off
            for i = 1:numel(Xnew)
                subplot(2,5,i+1); ax=gca;
                x = round(Xnew(i)/factor);
                y = round(Ynew(i)/factor);
                ind = sub2ind(fliplr(size(app.CurrentFrame)), x, y);
                Fr = squeeze(app.Data(:,:,ind));
                imagesc(ax,imresize(Fr, factor, 'method', 'bicubic'), app.clims); hold(ax, "on");
                imagesc(ax, borders, 'AlphaData', borders); hold(ax, "off"); axis image off
                colormap(ax, cmap)
                title(areaNames{i});
%                 if i == 5
%                     colorbar(ax, 'westoutside')
%                 end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SeedPixelMapLabel = uilabel(app.UIFigure);
            app.SeedPixelMapLabel.Text = 'Seed Pixel Map';
            app.MapButtonGroup = uibuttongroup(app.UIFigure);
            app.MapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MapButtonGroupSelectionChanged, true);
            app.MapButtonGroup.Title = 'Map';
            app.CorrelationButton = uitogglebutton(app.MapButtonGroup);
            app.CorrelationButton.Text = 'Correlation';
            app.CorrelationButton.Value = true;
            app.PValueButton = uitogglebutton(app.MapButtonGroup);
            app.PValueButton.Text = 'P-Value';
            app.colormapDropDownLabel = uilabel(app.UIFigure);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.UIFigure);
            app.colormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'parula';
            app.TextArea = uitextarea(app.UIFigure);
            app.GeneratefigureButton = uibutton(app.UIFigure, 'push');
            app.GeneratefigureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefigureButtonPushed, true);
            app.GeneratefigureButton.Text = {'Generate'; 'figure'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeedPixMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end