classdef funcImag_viz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FunctionalImagingViewerUIFigure  matlab.ui.Figure
        SelectdataDropDownLabel  matlab.ui.control.Label
        SelectdataDropDown       matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        Slider                   matlab.ui.control.Slider
        ExportplotsButton        matlab.ui.control.Button
        PlotoptionsPanel         matlab.ui.container.Panel
        colormapDropDownLabel    matlab.ui.control.Label
        colormapDropDown         matlab.ui.control.DropDown
        minEditField             matlab.ui.control.NumericEditField
        maxLabel                 matlab.ui.control.Label
        maxEditField             matlab.ui.control.NumericEditField
        showcolorbarCheckBox     matlab.ui.control.CheckBox
        ClippingvaluesLabel      matlab.ui.control.Label
        minEditFieldLabel_2      matlab.ui.control.Label
        resetMinButton           matlab.ui.control.Button
        resetMaxButton           matlab.ui.control.Button
        clipplotCheckBox         matlab.ui.control.CheckBox
        TextArea                 matlab.ui.control.TextArea
        PromptLabel              matlab.ui.control.Label
        EventsoptionsPanel       matlab.ui.container.Panel
        ConditionDropDownLabel   matlab.ui.control.Label
        ConditionDropDown        matlab.ui.control.DropDown
        RepetitionDropDownLabel  matlab.ui.control.Label
        RepetitionDropDown       matlab.ui.control.DropDown
        OpenROImanagerButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        my_cmap % current colormap
        inputFile % input File
        expType % experiment type one of the following: {1 = 'ImgTimeSeries', 2 = 'ImgTimeSeriesByEvent', 3 = 'SeedPixCorr'}
        timeFig % structure containing figure parameters.
        eventList % cell array with event ID (Case 2 only).
        start = false % boolean to start grabbing the mouse cursor position.
        colBar % colorbar handle.
        dataInfo % structure containing input Data and metaData.
        currInfo = struct('pT', [1 1], 'sel_pT', [1 1], 'frame', 1, 'value',0, ...
            'rep', 1, 'cond', 1) % structure containing current spatiotemporal information of data
        
    end
    
    methods (Access = private)
        
        function b_isOK = checkInput(app)
            % CHECKINPUT verifies if the inputFile contains a valid Imaging
            % dataset.
            % Output: b_isOK, boolean to indicate if inputs obey all
            % conditions.
            b_isOK = false;
            [mDat, metaDat] = mapDatFile(app.inputFile);
            dim_names = metaDat.dim_names;
            if iscell([dim_names{:}])
                dim_names = dim_names{1};
            end
            % Validate dimension names:
                % Accepted dimension names
            valid_dims = {'Y', 'X', 'T', 'S', 'E'};
            [idx, locB] = ismember(upper(dim_names), valid_dims);
            if ~all(idx)
                uialert(app.FunctionalImagingViewerUIFigure, 'Invalid input dimension names. Names must be part of the set: ("X", "Y", "T", "S","E")', ...
                    'Invalid input MetaData', 'Modal',true, 'CloseFcn', 'uiresume(gcbf)');
                uiwait(app.FunctionalImagingViewerUIFigure);
                return
            end
            % Determine what type of experiment is represented by the Data:
            if isequal(sort(locB),[1:3])
                % If it is an "Image time series":
                app.expType = 1; % Experiment code for "Image time series"
                % Get dimension positions to map to {"Y", "X", "T"}:
                [~,dim_idx] = ismember({'Y', 'X','T'}, dim_names);
            elseif isequal(sort(locB),[1:3 5])
                % If it is an "Image time series split by Events":
                app.expType = 2; % Experiment code for "Image time series split by Events"
                % Look for Event variables "eventID" and "eventNameList" in
                % metaDat:
                % * In this type of data, a list of events indices
                % (eventID) and a cell array containing the names of each
                % event (eventNameList) are necessary.
                if ~all(ismember({'eventID', 'eventNameList'}, properties(metaDat)))
                    uialert(app.FunctionalImagingViewerUIFigure, {'Event info missing in meta data for experiment type "Image time series split by event", ...' ...
                        'Meta data file must have "eventID" and "eventNameList". Refer to the App''s documentation for details.'}, ...
                        'Missing Event Info in MetaData file', 'Icon', "error", 'Modal',true, 'CloseFcn', 'uiresume(gcbf)');
                    uiwait(app.FunctionalImagingViewerUIFigure);
                    return
                else
                    % If Event info variables exist in meta data file, validate
                    % format:
                    if numel(unique(metaDat.eventID)) ~= length(metaDat.eventNameList)
                        uialert(app.FunctionalImagingViewerUIFigure, 'The number of unique elements of "eventID" list is different from the number of elements in "eventNameList".', ...
                            'Wrong event info', 'Icon', "error", 'Modal',true, 'CloseFcn', 'uiresume(gcbf)');
                        uiwait(app.FunctionalImagingViewerUIFigure);
                        return
                    end
                end
                % Get dimension positions to map to {"E", "Y", "X", "T"}:
                [~,dim_idx] = ismember({'Y', 'X', 'T', 'E'}, dim_names);
            elseif isequal(sort(locB),[1 2 4])
                % If it is a "Seed-Pixel Correlation Map":
                app.expType = 3; % Experiment code for "Seed-Pixel Correlation Maps (SPCM)"
                % Get dimension positions to map to {"Y", "X", "S"}:
                [~,dim_idx] = ismember({'Y', 'X', 'S'}, dim_names);
            elseif isequal(sort(locB),[1 2])
                % If it is a 2-D "Image":
                app.expType = 4; % Experiment code for "Image".
                % Get dimension positions to map to {"Y", "X"}:
                [~,dim_idx] = ismember({'Y', 'X'}, dim_names);
            else
                uiwait(errordlg('Cannot know what type of experiment this is.', 'Input validation error', 'modal'))
                return
            end
            
            % Save Data and MetaData memory maps to structure dataInfo:
            app.dataInfo.Map = mDat;
            app.dataInfo.MetaMap = metaDat;
            app.dataInfo.data = [];
            app.dataInfo.dim_idx = dim_idx;
            app.dataInfo.dim_names = dim_names(dim_idx);
            % Initialize some variables:
            if ~iscell(metaDat.datName)
                app.SelectdataDropDown.Items = {metaDat.datName};
            else
                app.SelectdataDropDown.Items = metaDat.datName;
            end
            app.PromptLabel.Text = ['File : ' app.inputFile];
            % Set Output variable to TRUE:
            b_isOK = true;
        end
        
        function updateImage(app)
            % UPDATEIMAGE changes the background frame without changing the
            % other elements over it.
            img = findall(app.FunctionalImagingViewerUIFigure, 'Tag', 'MyImg');
            img.CData = app.dataInfo.data(:,:,round(app.currInfo.frame)); drawnow;
            switch app.expType
                case {1,2}
                    set(app.UIAxes.Title, 'String',['Frame #' num2str(app.currInfo.frame)])
                case 3
                    %%% NOTHING %%%
                case 4
                    %%% NOTHING %%%
            end
        end
        
        function updateTimeFig(app)
            % Changes the temporal profile plot when use clicks on a pixel.
            
            p = findall(app.timeFig.axT1, 'Tag', 'sigLine');
            if squeeze(app.dataInfo.data(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:))' ~= p.YData
                p.XData = single(1:size(app.dataInfo.data,3));
                p.YData = squeeze(app.dataInfo.data(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:));
            end
            
            % Show/Hide Patch if "average" is selected:
            if app.expType == 2
                if strcmp(app.RepetitionDropDown.Value, '#Average')
                    sh = findall(app.timeFig.axT1, 'Type', 'patch');
                    x = p.XData;
                    y = [p.YData - squeeze(app.dataInfo.SEM(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:))';...
                        p.YData + squeeze(app.dataInfo.SEM(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:))'];
                    sh.XData = [x, fliplr(x)];
                    sh.YData = [y(1,:), fliplr(y(2,:))];
                end
            end
        end
        
        %         function showFrame(app, ID, overlayPlot)
        %             % ID = title
        %             % overlayPlot = cell array containing {X, Y, Marker,
        %             % MarkerFaceColor}.
        %             cmap = app.colormapDropDown.Value;
        %             if app.clipplotCheckBox.Value
        %                 clims = [app.minEditField.Value app.maxEditField.Value];
        %                 imagesc(app.UIAxes, app.currFr, clims); colormap(app.UIAxes, cmap); drawnow;
        %             else
        %                 imagesc(app.UIAxes, app.currFr); colormap(app.UIAxes, cmap); drawnow;
        %                 app.UIAxes.CLimMode = "auto";
        %             end
        %             title(app.UIAxes, ID)
        %             hold(app.UIAxes,'on');
        %             plot(app.UIAxes,app.currInfo.sel_pT(1), app.currInfo.sel_pT(2), 'ro', "MarkerSize",4);
        %             for i = 1:size(overlayPlot,2)
        %                 dataPt = overlayPlot(i,:);
        %                 plot(app.UIAxes, dataPt{2}, dataPt{1}, 'Marker', dataPt{3}, 'MarkerFaceColor', dataPt{4});
        %             end
        %             hold(app.UIAxes,'off');
        %             if app.showcolorbarCheckBox.Value
        %                 app.colBar.Visible = 'on';
        %             end
        %         end
        
        %         function showTemporalProfile(app, linePos,X, data, Colors, xl)
        %             % data = M x time . Data can be a 1-D array or a series of
        %             % arrays.
        %             % X = array with time (in seconds).
        %             % linePos = vertical line position in time corresponding to the
        %             % frame currently displayed in APP.UIAXES.
        %             % Colors = cell array of colors of each line ploted in the array.
        %             % xl = X axis label
        %             cla(app.timeFig.axT1);
        %             hold(app.timeFig.axT1, 'on')
        %             for i = 1:size(data,2)
        %                 plot(app.timeFig.axT1, X, data(i,:), 'Color', Colors{i});
        %             end
        %             yl = [app.minEditField.Value app.maxEditField.Value];
        %             app.timeFig.axT1.YLim = yl;
        %             line(app.timeFig.axT1, [linePos linePos], [yl(1) yl(2)], 'Color', 'k');drawnow
        %             if isprop(app.metaData,'preEventTime_sec')
        %                 event_onset = app.metaData.preEventTime_sec;
        %                 line(app.timeFig.axT1, [event_onset event_onset], [yl(1) yl(2)], 'Color', 'r');drawnow
        %             end
        %             hold(app.timeFig.axT1, 'off')
        %             app.timeFig.axT1.YLabel.String = 'Signal amplitude';
        %             app.timeFig.axT1.XLabel.String = xl;
        %         end
        
        function DoNotCloseFig(~,~,~)
            
        end
        
        function in_bounds = getBounds(app)
            x_lims = get(app.UIAxes, 'XLim');
            y_lims = get(app.UIAxes, 'YLim');
            
            in_bounds = (app.currInfo.pT(1) >= x_lims(1)) && ...
                (app.currInfo.pT(1) <= x_lims(2)) && ...
                (app.currInfo.pT(2) >= y_lims(1)) && ...
                (app.currInfo.pT(2) <= y_lims(2));
        end
        
        function displayInfo(app)
            % Displays important information in APP.LABEL.
            txt = {};
            switch app.expType
                case 1
                    str = 'Image time series';
                case 2
                    str = 'Image time series split by event';
                case 3
                    str = 'Correlation Map';
                case 4
                    str = 'Image';
            end
            txt{1} = ['Experiment type: ' str];
            txt{2} = ['Current position (X,Y) : ' num2str(app.currInfo.pT(1)) ' , ' num2str(app.currInfo.pT(2)) '                ' ...
                'Current Value : ' num2str(app.currInfo.value)];
            txt{3} = ['Selected Pixel position (X,Y) :' num2str(app.currInfo.sel_pT(1)) ' , ' num2str(app.currInfo.sel_pT(2)) '       ' ...
                'Selected Pixel Value : ' num2str(app.dataInfo.data(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1), app.currInfo.frame))];
            app.TextArea.Value = txt;
        end
        
        %         function updateFigure(app)
        %             % Updates image and plot
        %             switch app.expType
        %                 case {1,2}
        %                     app.currFr = app.dataInfo.data(:,:,app.currFr_idx);
        %                     app.showFrame(['Frame #' num2str(app.currFr_idx)], {});
        %                     tempData = app.dataInfo.data(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:);
        %                     try
        %                         X = (1:length(tempData))./app.metaData.Freq;
        %                         linePos = X(app.currFr_idx);
        %                         xLab = 'time (seconds)';
        %                     catch
        %                         X = 1:length(tempData);
        %                         linePos = app.currFr_idx;
        %                         xLab = 'frame number';
        %                     end
        %                     app.showTemporalProfile(linePos, X, tempData, {'b'}, xLab)
        %
        %                 case 3
        %                     szdat = size(app.data);
        %                     app.currFr_idx = sub2ind(szdat([1 2]), app.currInfo.sel_pT(2), app.currInfo.sel_pT(1));
        %                     app.currFr = app.dataInfo.data(:,:,app.currFr_idx);
        %                     app.showFrame('SPCM',{});
        %             end
        %             app.Slider.Value = app.currFr_idx;
        %
        %         end
        %
        function changeFrame(app,src,~)
        % Callback of timeFig.axT1. When User clicks on the plot, it picks
        % the closest point to the cursor and shifts the frame to the
        % clicked point in time.
            
            click = get(src, 'SelectionType');
            if strcmp(click, 'open')
                coords = get(src.Children, 'CurrentPoint');
                x_pos = round(coords(1,1));
                if x_pos < 1
                    x_pos = 1;
                elseif x_pos > size(app.dataInfo.data,3)
                    x_pos = size(app.dataInfo.data,3);
                end
                app.currInfo.frame = x_pos;
                % Update Image:
                app.updateImage;
                % Update display info:
                app.displayInfo;
                % Update slider position:
                app.Slider.Value = x_pos;
                % Update timebar position:
                src.Children.Children(2).XData = [x_pos x_pos];
            end
        end
        
        function loadData(app)
            %Load data into app.dataInfo.data. Here, "data" will store only
            % 3D or 2D matrices.
            dataField = app.SelectdataDropDown.Value;
            switch app.expType
                case 1
                    % In cases where dimensions are {'Y', 'X', 'T'}:
                    app.timeFig.Fig.Visible = 'on';
                    app.dataInfo.data = permute(app.dataInfo.Map.Data.(dataField), app.dataInfo.dim_idx);
                case 2
                    % In case where dimensions are {'Y', 'X', 'T', 'E'}:
                    app.timeFig.Fig.Visible = 'on';
                    app.EventsoptionsPanel.Visible = 'on';
                    app.loadEvents();
                    % Find condition and repetition indices:
                    indx_evID = find(app.eventList == find(strcmp(app.ConditionDropDown.Value, app.ConditionDropDown.Items)));
                    % Get data snippet:
                    app.dataInfo.data = permute(app.dataInfo.Map.Data.(dataField), app.dataInfo.dim_idx);
                    rep_ID = app.RepetitionDropDown.Value;
                    if strcmp(rep_ID, '#Average')
                        % Store SEM data :
                        app.dataInfo.SEM = std(app.dataInfo.data(:,:,:,indx_evID),0, 4, 'omitnan')./...
                            sqrt(size(app.dataInfo.data(:,:,:,indx_evID),4));
                        app.dataInfo.data = mean(app.dataInfo.data(:,:,:,indx_evID), 4, 'omitnan');
                    else
                        app.dataInfo.data = app.dataInfo.data(:,:,:,indx_evID(str2double(rep_ID)));
                    end
                case 3
                    % In cases where dimensions are {'Y', 'X', 'S'}:
                    app.timeFig.Fig.Visible = 'off';
                    app.Slider.Visible = 'off';
                    app.dataInfo.data = permute(app.dataInfo.Map.Data.(dataField), app.dataInfo.dim_idx);
                case 4
                    % In cases where dimensions are {'Y', 'X'}:
                    app.timeFig.Fig.Visible = 'off';
                    app.Slider.Visible = 'off';
                    dat = permute(app.dataInfo.Map.Data.(dataField), app.dataInfo.dim_idx);
                    app.dataInfo.data = repmat(dat,1,1,2);
            end
            % Find Time dimension:
            indxT = find(strcmp(app.dataInfo.dim_names, 'T'));
            if ~isempty(indxT)
                % Set Slider:
                app.Slider.Limits = [1 size(app.dataInfo.data,indxT)];
                step = round(size(app.dataInfo.data,indxT)/20);
                app.Slider.MajorTicks = 1:step:size(app.dataInfo.data,indxT);
                app.Slider.MajorTickLabels = {};
            end
        end
        
        function loadEvents(app)
            % Saves the event list to property "eventList"
            if ~isprop(app.dataInfo.MetaMap, 'eventID')
                uiwait(errordlg('Can''t find event list in Meta Data file!', 'Error!', 'modal'))
                app.MainWindow_CloseRequest;
            end
            list = app.dataInfo.MetaMap.eventNameList;
            IDs = app.dataInfo.MetaMap.eventID;
            % transforms list items to strings:
            condList = cellfun(@(x) num2str(x), list, 'UniformOutput',false);
            n_reps = numel(IDs)/numel(unique(IDs));
            repList = arrayfun(@(x) num2str(x), (1:n_reps), 'UniformOutput', false);
            app.eventList = IDs;
            app.ConditionDropDown.Items = condList;
            app.RepetitionDropDown.Items = [repList, '#Average'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputFile)
            
            % Initialize temporal Figure:
            app.timeFig.Fig = uifigure('Name', 'Temporal signal', 'NumberTitle','off',...
                'Position', [841 748 750 250], 'Color', 'w', 'MenuBar', 'none',...
                'ToolBar', 'none', 'Resize', 'off', 'Visible','off',...
                'CloseRequestFcn', @app.DoNotCloseFig, 'WindowButtonDownFcn', @app.changeFrame);
            % Graph:
            app.timeFig.axT1 = uiaxes(app.timeFig.Fig, 'Position', [5 5 740 240], 'NextPlot',"replacechildren");
            set(app.timeFig.axT1.Title, 'String', 'Signal Amplitude');
            patch(app.timeFig.axT1, [0 1 1 0],[0 1 1 0] ,1,'FaceColor','b','EdgeColor','b', 'FaceAlpha', .2, 'EdgeAlpha', .5,...
                'HandleVisibility', 'callback', 'Tag', 'sigShade', 'Visible', 'off');
            plot(app.timeFig.axT1, 0,0, 'b-', 'HandleVisibility',"callback", 'Tag', 'sigLine');
            line(app.timeFig.axT1,[0 0], [-10 10], 'Color', [0 0 0], 'HandleVisibility',"callback", 'Tag', 'TimeBar');
            line(app.timeFig.axT1,[0 0], [-10 10], 'Color', [1 0 0], 'HandleVisibility',"callback", 'Tag', 'EvntBar', 'Visible', 'off');
            
            % Set Panels visibility:
            app.EventsoptionsPanel.Visible = 'off';
%             app.ExportplotsButton.Enable = 'off';
            app.my_cmap = app.colormapDropDown.Value;
            % Input validation:
            p = inputParser;
            addRequired(p, 'inputFile', @(x) isfile(x) & endsWith(x, '.dat'));
            parse(p, inputFile)
            % Initialize inputs:
            app.inputFile = p.Results.inputFile;
            b_isOK = app.checkInput;
            if ~b_isOK
                delete(app)
            else
                app.loadData;
                % Display first frame (dummy frame) and red dot and create Tags for each handle:
                app.UIAxes.NextPlot = 'replacechildren';
                imagesc(app.UIAxes, [1:10,1:10], 'Tag', 'MyImg', 'HandleVisibility','callback');
%                 plot(app.UIAxes, 1, 1, 'ro', "MarkerSize",4, 'Tag', 'MyRedDot', "HandleVisibility","callback");
                line(app.UIAxes, [1 1; app.UIAxes.XLim]', [app.UIAxes.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback',...
                    'Tag', 'selPixMarker');
                % Initialize some plot parameter values:
                app.UIAxes.DataAspectRatio = [1 1 1];
                app.UIAxes.Title.String = '';
                set(app.UIAxes, 'XLim', [1 size(app.dataInfo.data,2)],'YLim',[1 size(app.dataInfo.data,1)], 'XLimMode', 'manual',...
                    'YLimMode', 'manual', 'ZLimMode', 'manual', 'CLimMode', 'manual', 'ALimMode', 'manual')
                app.UIAxes.XLabel.String = 'X (pixels)';
                app.UIAxes.YLabel.String = 'Y (pixels)';
                set(app.FunctionalImagingViewerUIFigure, 'doublebuffer', 'off');
                app.minEditField.Value = double(min(app.dataInfo.data(:)));
                if isinf(app.minEditField.Value)
                    idx = isinf(app.dataInfo.data(:));
                    app.minEditField.Value = round(double(min(app.dataInfo.data(~idx))),4);
                end
                app.maxEditField.Value = double(max(app.dataInfo.data(:)));
                if isinf(app.maxEditField.Value)
                    idx = isinf(app.dataInfo.data(:));
                    app.maxEditField.Value = round(double(max(app.dataInfo.data(~idx))),2);
                end
                app.colBar = colorbar(app.UIAxes, 'eastoutside');
                if ~app.showcolorbarCheckBox.Value
                    app.colBar.Visible = 'off';
                end
                if ~app.clipplotCheckBox.Value
                    app.minEditField.Enable = 'off';
                    app.maxEditField.Enable = 'off';
                    app.resetMinButton.Enable = 'off';
                    app.resetMaxButton.Enable = 'off';
                end
                % Show First Frame:
                app.updateImage;
                set(app.UIAxes, 'CLim', [app.minEditField.Value app.maxEditField.Value]);
                % Set time plot:
                set(app.timeFig.axT1, 'XLim', [1 size(app.dataInfo.data,3)], 'YLim',[app.minEditField.Value app.maxEditField.Value], ...
                    'XLimMode', 'manual', 'YLimMode', 'manual', 'ZLimMode', 'manual')
                set(app.timeFig.axT1, 'XTickLabel', num2cell(app.timeFig.axT1.XTick./app.dataInfo.MetaMap.Freq));
                set(app.timeFig.axT1.XLabel, 'String', 'time (sec)');
                % Set some extra parameters for each experiment type:
                switch app.expType
                    case {1,2}
                    % Set signal plot:
                    p = findall(app.timeFig.axT1);
                    set(p(4), 'XData', 1:size(app.dataInfo.data,3), 'YData', app.dataInfo.data(app.currInfo.sel_pT(1), app.currInfo.sel_pT(2), :))
                    % Set Timebar:
                    set(p(3), 'XData', [app.currInfo.frame app.currInfo.frame], 'YData', p(1).YLim)
                    % Set Eventbar:
                    if app.expType == 2
                        set(p(2), 'XData', repmat(round(app.dataInfo.MetaMap.Freq*app.dataInfo.MetaMap.preEventTime_sec),2,1), ...
                            'YData', p(1).YLim, 'Visible', 'on');
                         set(app.timeFig.axT1, 'XTickLabel',...
                             num2cell((app.timeFig.axT1.XTick./app.dataInfo.MetaMap.Freq) - app.dataInfo.MetaMap.preEventTime_sec));
                    end
                    case 3
                        set(app.UIAxes.Title, 'String','SPCM')
                end
                
                % Display Info:
                app.displayInfo;
                app.start = true;
            end
        end

        % Close request function: FunctionalImagingViewerUIFigure
        function MainWindow_CloseRequest(app, event)
            delete(app.timeFig.Fig)
            delete(app)
        end

        % Window button motion function: 
        % FunctionalImagingViewerUIFigure
        function MainWindow_WindowButtonMotion(app, event)
            if app.start
                coords = get(app.UIAxes, 'CurrentPoint');
                app.currInfo.pT = round(coords(1,1:2));
                in_bounds = app.getBounds;
                if ~in_bounds
                    return
                else
                    app.currInfo.value = app.dataInfo.data(app.currInfo.pT(2), app.currInfo.pT(1), app.currInfo.frame);
                    app.displayInfo;
                end
            end
        end

        % Window button down function: 
        % FunctionalImagingViewerUIFigure
        function MainWindow_WindowButtonDown(app, event)
            click = get(app.FunctionalImagingViewerUIFigure, 'SelectionType');
            in_bounds = app.getBounds;
            if ~in_bounds || ~strcmp(click, 'normal')
                return
            end
            % Find current point coordinates and shift Dot:
            pT = findall(app.FunctionalImagingViewerUIFigure,'Tag', 'selPixMarker');
            pT(1).XData = app.UIAxes.XLim;
            pT(1).YData = [app.currInfo.pT(2) app.currInfo.pT(2)];
            pT(2).YData = app.UIAxes.YLim;
            pT(2).XData =  [app.currInfo.pT(1) app.currInfo.pT(1)];
            app.currInfo.sel_pT = app.currInfo.pT;
            switch app.expType
                case {1,2}
                    % Update Time figure:
                    app.updateTimeFig;
                case 3
                    % Update Image:
                    app.currInfo.frame = sub2ind([size(app.dataInfo.data,1) size(app.dataInfo.data,2)], app.currInfo.sel_pT(2), app.currInfo.sel_pT(1));
                    app.updateImage;
            end
            app.displayInfo;
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.UIAxes.Title.String = ['moving to Frame # ' num2str(round(event.Value))]; drawnow;
            app.timeFig.axT1.Children(2).XData = [round(event.Value) round(event.Value)];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.currInfo.frame = round(event.Value);
            app.updateImage;
            app.displayInfo;
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            if event.Value > app.maxEditField.Value
                app.minEditField.Value = event.PreviousValue;
                return
            else
                app.minEditField.Value = event.Value;
                set(app.UIAxes, 'CLim', [event.Value app.maxEditField.Value])
                set(app.timeFig.axT1, 'YLim', [event.Value app.maxEditField.Value])
            end
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            if event.Value < app.minEditField.Value
                app.maxEditField.Value = event.PreviousValue;
                return
            else
                app.maxEditField.Value = event.Value;
                set(app.UIAxes, 'CLim', [app.minEditField.Value event.Value])
                set(app.timeFig.axT1, 'YLim', [app.minEditField.Value event.Value])
            end
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            colormap(app.UIAxes, event.Value)
        end

        % Button pushed function: resetMinButton
        function resetMinButtonPushed(app, event)
            if isinf(app.minEditField.Value)
                idx = isinf(app.dataInfo.data(:));
                evnt.Value = double(min(app.dataInfo.data(~idx)));
            else
                evnt.Value = double(min(app.dataInfo.data(:)));
            end
            evnt.PreviousValue = app.minEditField.Value;
            app.minEditFieldValueChanged(evnt);
        end

        % Button pushed function: resetMaxButton
        function resetMaxButtonPushed(app, event)
            if isinf(app.maxEditField.Value)
                idx = isinf(app.dataInfo.data(:));
                evnt.Value = double(max(app.dataInfo.data(~idx)));
            else
                evnt.Value = double(max(app.dataInfo.data(:)));
            end
            evnt.PreviousValue = app.maxEditField.Value;
            app.maxEditFieldValueChanged(evnt);
        end

        % Value changed function: showcolorbarCheckBox
        function showcolorbarCheckBoxValueChanged(app, event)
            value = app.showcolorbarCheckBox.Value;
            if value
                app.colBar.Visible = 'on';
            else
                app.colBar.Visible = 'off';
            end
        end

        % Value changed function: clipplotCheckBox
        function clipplotCheckBoxValueChanged(app, event)
            value = app.clipplotCheckBox.Value;
            if value
                app.minEditField.Enable = 'on';
                app.maxEditField.Enable = 'on';
                app.resetMinButton.Enable = 'on';
                app.resetMaxButton.Enable = 'on';
            else
                app.minEditField.Enable = 'off';
                app.maxEditField.Enable = 'off';
                app.resetMinButton.Enable = 'off';
                app.resetMaxButton.Enable = 'off';
            end
        end

        % Value changed function: SelectdataDropDown
        function SelectdataDropDownValueChanged(app, event)
            app.loadData;
            app.updateImage;
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            app.loadData;
            app.updateImage;
            app.updateTimeFig;
        end

        % Value changed function: RepetitionDropDown
        function RepetitionDropDownValueChanged(app, event)
            app.loadData;
            app.updateImage;
            app.updateTimeFig;
            sh = findall(app.timeFig.axT1, 'Type', 'patch');
            if strcmp(app.RepetitionDropDown.Value, '#Average')
                sh.Visible = 'on';
            else
                sh.Visible = 'off';
            end
        end

        % Button pushed function: ExportplotsButton
        function ExportplotsButtonPushed(app, event)
            [Path, ~, ~] = fileparts(app.inputFile);
            if isempty(Path)
                Path = pwd;
            end
            % CD to Path:
            cd(Path);
            % These are the options to save the plots:
            fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)';...
                '*.pdf', 'Portable Document Format  (*.pdf)';...
                '*.eps', 'Encapsulated PostScript  (*.eps)'};
            [filename, Path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'data.png');
            print_dict = {'-dpng', '-djpeg', '-dpdf', '-depsc'};
            
            if filename == 0
                return
            end
            % Get axes handles and create figure:
            fig1 = figure('Name', 'frame','Visible', 'off');
            ax1 = axes(fig1);
            copyobj(app.UIAxes.Children, ax1);
            % Copy UIAxes parameters to new axis:
            copyAxparams(app.UIAxes, ax1);
            print(fullfile(Path, filename), fig1, print_dict{indx}, '-painters');
            close(fig1)
            if strcmp(get(app.timeFig.Fig, 'Visible'), 'on')
                fig2 = figure('Name', 'temporal_profile','Visible', 'off', 'Position', app.timeFig.Fig.Position);
                ax2 = axes(fig2);
                copyobj(app.timeFig.axT1.Children, ax2);
                % Copy UIAxes parameters to new axis:
                copyAxparams(app.timeFig.axT1, ax2);
                [~,name,ext] = fileparts(filename);
                print(fullfile(Path, [name '_tempProf' ext]), fig2, print_dict{indx},'-painters');
                close(fig2)
            end
            uialert(app.FunctionalImagingViewerUIFigure, ['Plots saved in ' Path], 'Plots Saved!', 'Icon', "info", 'Modal',true, 'CloseFcn', 'uiresume(gcbf)');
            uiwait(app.FunctionalImagingViewerUIFigure);
            % Open folder with exported images:
            if (ispc)
                winopen(Path)
            elseif (ismac)
                system(['open ' Path]);
            else
                system(['xdg-open ' Path]);
            end
            
            function copyAxparams(sourceAx, targetAx)
                uiAxParams = get(sourceAx);
                uiAxParamNames = fieldnames(sourceAx);
                % Get list of editable params in new axis
                editableParams = arrayfun(@(x) fieldnames(set(x)), targetAx, 'UniformOutput', false); editableParams = unique(vertcat(editableParams{:}));
                % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
                badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
                badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition'; 'XLabel'; 'YLabel'; 'Title'];
                uiAxGoodParams = rmfield(uiAxParams,badFields);
                % set editable params on new axes
                set(targetAx, 'Title', copy(sourceAx.Title), 'XLabel', copy(sourceAx.XLabel), 'YLabel', copy(sourceAx.YLabel), 'XLim', sourceAx.XLim, ...
                    'YLim', sourceAx.YLim); drawnow
%                 targetAx.XLabel = copy(sourceAx.XLabel);
%                 targetAx.YLabel = copy(sourceAx.YLabel);
%                 targetAx.XLim = sourceAx.XLim;
%                 targetAx.YLim = sourceAx.YLim;
                set(targetAx, uiAxGoodParams);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FunctionalImagingViewerUIFigure = uifigure('Visible', 'off');
            app.FunctionalImagingViewerUIFigure.Name = 'Functional Imaging Viewer';
            app.FunctionalImagingViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainWindow_CloseRequest, true);
            app.FunctionalImagingViewerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MainWindow_WindowButtonDown, true);
            app.FunctionalImagingViewerUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MainWindow_WindowButtonMotion, true);
            app.SelectdataDropDownLabel = uilabel(app.FunctionalImagingViewerUIFigure);
            app.SelectdataDropDownLabel.Text = 'Select data';
            app.SelectdataDropDown = uidropdown(app.FunctionalImagingViewerUIFigure);
            app.SelectdataDropDown.Items = {'data'};
            app.SelectdataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectdataDropDownValueChanged, true);
            app.SelectdataDropDown.Value = 'data';
            app.UIAxes = uiaxes(app.FunctionalImagingViewerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Slider = uislider(app.FunctionalImagingViewerUIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ExportplotsButton = uibutton(app.FunctionalImagingViewerUIFigure, 'push');
            app.ExportplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportplotsButtonPushed, true);
            app.ExportplotsButton.Text = 'Export plots';
            app.PlotoptionsPanel = uipanel(app.FunctionalImagingViewerUIFigure);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.colormapDropDownLabel = uilabel(app.PlotoptionsPanel);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.PlotoptionsPanel);
            app.colormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'parula';
            app.minEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.4f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxLabel = uilabel(app.PlotoptionsPanel);
            app.maxLabel.Text = 'max';
            app.maxEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.4f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.showcolorbarCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.showcolorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @showcolorbarCheckBoxValueChanged, true);
            app.showcolorbarCheckBox.Text = 'show colorbar';
            app.ClippingvaluesLabel = uilabel(app.PlotoptionsPanel);
            app.ClippingvaluesLabel.Text = 'Clipping values:';
            app.minEditFieldLabel_2 = uilabel(app.PlotoptionsPanel);
            app.minEditFieldLabel_2.Text = 'min';
            app.resetMinButton = uibutton(app.PlotoptionsPanel, 'push');
            app.resetMinButton.ButtonPushedFcn = createCallbackFcn(app, @resetMinButtonPushed, true);
            app.resetMinButton.Icon = 'icon_back_arrow.png';
            app.resetMinButton.Text = '';
            app.resetMaxButton = uibutton(app.PlotoptionsPanel, 'push');
            app.resetMaxButton.ButtonPushedFcn = createCallbackFcn(app, @resetMaxButtonPushed, true);
            app.resetMaxButton.Icon = 'icon_back_arrow.png';
            app.resetMaxButton.Text = '';
            app.clipplotCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.clipplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @clipplotCheckBoxValueChanged, true);
            app.clipplotCheckBox.Text = 'clip plot';
            app.TextArea = uitextarea(app.FunctionalImagingViewerUIFigure);
            app.PromptLabel = uilabel(app.FunctionalImagingViewerUIFigure);
            app.PromptLabel.Text = 'Prompt:';
            app.EventsoptionsPanel = uipanel(app.FunctionalImagingViewerUIFigure);
            app.EventsoptionsPanel.Title = 'Events options';
            app.ConditionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.EventsoptionsPanel);
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.RepetitionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.RepetitionDropDownLabel.Text = 'Repetition';
            app.RepetitionDropDown = uidropdown(app.EventsoptionsPanel);
            app.RepetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @RepetitionDropDownValueChanged, true);
            app.OpenROImanagerButton = uibutton(app.FunctionalImagingViewerUIFigure, 'push');
            app.OpenROImanagerButton.Enable = 'off';
            app.OpenROImanagerButton.Text = 'Open ROI manager';
            % Show the figure after all components are created
            app.FunctionalImagingViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = funcImag_viz(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FunctionalImagingViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FunctionalImagingViewerUIFigure)
        end
    end
end