classdef funcImag_viz_evntWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventsUIFigure           matlab.ui.Figure
        OptionsMenu              matlab.ui.container.Menu
        DeletecurrentMenu        matlab.ui.container.Menu
        ConditionMenu            matlab.ui.container.Menu
        RepetitionMenu           matlab.ui.container.Menu
        RestoreallMenu           matlab.ui.container.Menu
        EventsoptionsPanel       matlab.ui.container.Panel
        ConditionDropDownLabel   matlab.ui.control.Label
        ConditionDropDown        matlab.ui.control.DropDown
        RepetitionDropDownLabel  matlab.ui.control.Label
        RepetitionDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MainApp % Handle to Main App
        condList % cell array of initial condition names
        ID_list % array of indices of condList.
    end
    
    methods (Access = private)
        
        function genCondList(app)
            % This function creates the items from the DropDown menu of
            % Conditions
            % transform list to cell array of char
            app.ConditionDropDown.Items = cellfun(@(x) num2str(x), app.MainApp.eventName_list, 'UniformOutput',0);
        end
        
        function genRepList(app)
            % This function creates the List of repetitions for the selected condition
            % from the ConditionDropDown menu
            condName = app.ConditionDropDown.Value;
            idx = find(strcmp(app.ConditionDropDown.Items,condName));
            % Generate RepetitionDropDown menu Items:
            app.RepetitionDropDown.Items = [arrayfun(@(x) num2str(x), 1:sum(app.MainApp.eventID_list == idx), 'UniformOutput', 0) '#Average'];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            % Store initial event info to be able to restore to original
            % values:
            app.ID_list = app.MainApp.eventID_list;
            app.condList = app.MainApp.eventName_list;
            % Populate dropdown menus
            app.genCondList;
            app.genRepList;
        end

        % Close request function: EventsUIFigure
        function EventsUIFigureCloseRequest(app, event)
            %%% Do Nothing!!!%%%
            
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            app.genRepList;
            app.MainApp.condChanged(app,event);
        end

        % Value changed function: RepetitionDropDown
        function RepetitionDropDownValueChanged(app, event)
            app.MainApp.repChanged(app,event);
        end

        % Menu selected function: RepetitionMenu
        function RepetitionMenuSelected(app, event)
            % Deletes the current repetition in the Repetition list
            disp('Go Rep!')
            rep_ID = app.RepetitionDropDown.Value;
            if strcmp(rep_ID, '#Average')
                errordlg('Can''t delete "#Average"!', 'Failed to delete repetition')
                return
            end
            
            % Find Repetition index in app.MainApp.eventID_list
            condArr = cellfun(@(x) num2str(x), app.MainApp.eventName_list, 'UniformOutput',false);
            condID = find(strcmp(app.ConditionDropDown.Value, condArr));
            indx_rep = find(app.MainApp.eventID_list == condID);
            % Select the repetition index to be erased:
            indx_2Del= indx_rep(str2double(rep_ID));
            % If this is the only repetition, call the Condition Deletion
            % Callback:
            if numel(indx_rep) == 1
                % Confirm deletion:
                answer = questdlg('This is the last Repetition. Confirm deletion of the whole condition?', 'Delete Condition?', 'Yes', 'No', 'No');
                if strcmp(answer, 'Yes')
                    %%% CALL CONDITION DELETION CALLBACK %%%
                end
                return
            else
                answer = questdlg(['Confirm deletion of repetition ' rep_ID '?'], 'Delete Repetition?', 'Yes', 'No', 'No');
                if strcmp(answer, 'No')
                    return
                end
            end
            % Delete repetition from "eventID_list" in main app:
            app.MainApp.eventID_list(indx_2Del) = [];
            % Update Repetition DropDown menu:
            app.genRepList;
            % Create dummy event struct:
            evnt.Value = app.RepetitionDropDown.Value;
            evnt.Source.Items = app.RepetitionDropDown.Items;
            % Update plots in Main App:
            app.MainApp.repChanged(app,evnt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventsUIFigure = uifigure('Visible', 'off');
            app.EventsUIFigure.Name = 'Events';
            app.EventsUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventsUIFigureCloseRequest, true);
            app.OptionsMenu = uimenu(app.EventsUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.DeletecurrentMenu = uimenu(app.OptionsMenu);
            app.DeletecurrentMenu.Text = 'Delete current...';
            app.ConditionMenu = uimenu(app.DeletecurrentMenu);
            app.ConditionMenu.Text = 'Condition';
            app.RepetitionMenu = uimenu(app.DeletecurrentMenu);
            app.RepetitionMenu.MenuSelectedFcn = createCallbackFcn(app, @RepetitionMenuSelected, true);
            app.RepetitionMenu.Text = 'Repetition';
            app.RestoreallMenu = uimenu(app.OptionsMenu);
            app.RestoreallMenu.Text = 'Restore all';
            app.EventsoptionsPanel = uipanel(app.EventsUIFigure);
            app.EventsoptionsPanel.Title = 'Events options';
            app.ConditionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.EventsoptionsPanel);
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.RepetitionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.RepetitionDropDownLabel.Text = 'Repetition';
            app.RepetitionDropDown = uidropdown(app.EventsoptionsPanel);
            app.RepetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @RepetitionDropDownValueChanged, true);
            % Show the figure after all components are created
            app.EventsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = funcImag_viz_evntWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EventsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventsUIFigure)
        end
    end
end