classdef mouse_ref_frame_creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CorticalimagingreferenceframecreatorLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AnatomicalreferencesButtonGroup  matlab.ui.container.ButtonGroup
        BregmaButton                    matlab.ui.control.ToggleButton
        LambdaButton                    matlab.ui.control.ToggleButton
        SetButton                       matlab.ui.control.Button
        ShowlandmarksButton             matlab.ui.control.Button
        alignimagetorefptsCheckBox      matlab.ui.control.CheckBox
        ResetButton_3                   matlab.ui.control.Button
        ovelayctxareasCheckBox          matlab.ui.control.CheckBox
        createlogicalmaskPanel          matlab.ui.container.Panel
        DrawROIButton                   matlab.ui.control.Button
        ROISpinnerLabel                 matlab.ui.control.Label
        ROISpinner                      matlab.ui.control.Spinner
        ResetButton_2                   matlab.ui.control.Button
        showasalphaCheckBox             matlab.ui.control.CheckBox
        ShowMaskButton                  matlab.ui.control.Button
        RestoreButton                   matlab.ui.control.Button
        CreatereferenceframefileButton  matlab.ui.control.Button
        ImageadjustmentsPanel           matlab.ui.container.Panel
        enhancecontrastCheckBox         matlab.ui.control.CheckBox
        colormapDropDownLabel           matlab.ui.control.Label
        colormapDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        inputObj % input Object
        inputFile % input File
        frame % Anatomical frame from inputFile
        BregmaXY % Bregma coordinates
        LambdaXY % Lambda coordinates
        CurrentCoords % Current coordinates in axis.
        b_captureCoords = false % Boolean to indicate to save coordinates from click
        logicalMask % Logical Mask.
        customAx % custom axis (this is not a UIAXIS).
        ROIobj % ROI objects created with drawpolygon function.
        atlas % Mouse Allen Brain Map.
    end
    
    methods (Access = private)
        
        function getFrame(app)
            %GETFRAME creates the anatomical image from the average of the first 100
            %frames from the movie in app.inputFile. It uses an unsharp
            %mask to highlight blood vessels.
            mData = mapDatFile(app.inputFile);
            if size(mData.Data.data,3) < 100
                Frame = mean(mData.Data.data);
            else
                Frame = mean(mData.Data.data(:,:,1:100),3);
            end
            Frame = imsharpen(flipud(rot90(Frame)), 'Radius', 1.5, 'Amount',1);
            app.frame = (Frame - min(Frame(:)))./(max(Frame(:) - min(Frame(:))));
            app.Label.Text = ['File : ' app.inputFile];
        end
        
        function showFrame(app)
            % SHOWFRAME shows the IMAGESC of APP.FRAME in GUI
            cmap = app.colormapDropDown.Value;
            b_highContrast = app.enhancecontrastCheckBox.Value;
            fr = app.frame;
            if b_highContrast
                fr = imadjust(fr, [0.2 0.8]);
            end
            imagesc(app.customAx, fr); colormap(app.customAx, cmap);
            imag_sz = size(fr);
            
            app.customAx.XLim = [1 imag_sz(2)];
            app.customAx.YLim = [1 imag_sz(1)];
            app.customAx.Title.String = 'Anatomical Image';
            app.customAx.DataAspectRatio = [1 1 1];
        end
        
        function drawLandMark(app,landMark)
            % DRAWLANDMARK draws landmark over image.
            if isempty(app.CurrentCoords)
                return
            end
            
            hold(app.customAx, 'on');
            plot(app.customAx, app.CurrentCoords(1), app.CurrentCoords(2), 'ro', 'MarkerSize',8);
            text(app.customAx, app.CurrentCoords(1)+2, app.CurrentCoords(2), landMark, 'Color', 'r', 'HorizontalAlignment',"left");
            hold(app.customAx, 'off');
            eval(['app.' landMark 'XY = app.CurrentCoords;'])
            app.CurrentCoords = [];
            app.b_captureCoords = false;
            app.customAx.Title.String = 'Anatomical Image';
        end
        
        function updateCurrentCoords(app)
            app.CurrentCoords = get(app.customAx, 'CurrentPoint');
            in_bounds = app.getBounds(app.CurrentCoords);
            if ~in_bounds
                app.CurrentCoords = [];
                app.b_captureCoords = false;
            else
                app.CurrentCoords = round(app.CurrentCoords(1,1:2));
            end
        end
        
        function in_bounds = getBounds(app, XYcoords)
            x_lims = get(app.customAx, 'XLim');
            y_lims = get(app.customAx, 'YLim');
            
            in_bounds = (XYcoords(1) >= x_lims(1)) && ...
                (XYcoords(1) <= x_lims(2)) && ...
                (XYcoords(2) >= y_lims(1)) && ...
                (XYcoords(2) <= y_lims(2));
        end
        
        
        function rotateImage(app)
            % ROTATEIMAGE rotates the frame so Bregma and Lambda are
            % vertically aligned.
            if isempty(app.BregmaXY) || isempty(app.LambdaXY)
                uialert(app.UIFigure, 'Impossible to rotate image. You need to provide Bregma and Lambda positions first!', 'Missing Inputs', 'Icon', ...
                    "error", "Modal",true);
                return
            end
            Bpos = app.BregmaXY;
            Lpos = app.LambdaXY;
            xDif = abs(Bpos(1) - Lpos(1));
            yDif = abs(Bpos(2) - Lpos(2));
            radius = sqrt(xDif^2 + yDif^2);
            Lpos_new = [Bpos(1) (Bpos(2) + radius)];
            theta = atan2([yDif radius], [xDif 0]);
            phi = theta(2) - theta(1);
            phi = rad2deg(phi);
            if Lpos(1) > Bpos(1) && Lpos(2) < Bpos(2)
                phi = 180 + phi;
            elseif Lpos(1) < Bpos(1) && Lpos(2) < Bpos(2)
                phi = rad2deg(theta(1) - theta(2)) + 180;
            elseif Lpos(1) > Bpos(1) && Lpos(2) > Bpos(2)
                phi = rad2deg(theta(1) - theta(2));
            end
            app.frame = rotateAround(app.frame, Bpos(2), Bpos(1), phi, 'bicubic');
            app.LambdaXY = Lpos_new;
        end
        
        function drawROI(app, nROI)
            % DRAWROI creates a logical mask with a number of NROI and
            % displays over anatomical image.
            for i = 1:nROI
                app.customAx.Title.String = ['Draw ROI #' num2str(i) '/' num2str(nROI)];
                h(i) = drawpolygon(app.customAx,'Color', 'g');
            end
            app.ROIobj = h;
            app.customAx.Title.String = 'Done. Click on Show Mask to finish.';
        end
        
        function overlayAllenMap(app)
            X = [app.BregmaXY(1);app.LambdaXY(1)];
            Y = [app.BregmaXY(2);app.LambdaXY(2)];
            tform = fitgeotrans([app.atlas.BX app.atlas.BY ; app.atlas.LX app.atlas.LY],[X Y], 'nonreflectivesimilarity');
            
            R = imref2d(size(app.frame));
            areas = imwarp(app.atlas.flatAtlas, tform, 'nearest', 'OutputView', R);
            borders = boundarymask(areas,4); borders = imerode(borders,[1 1]);
            [x,y] = find(borders);
%             [xNew, yNew] = transformPointsForward(tform, x, y);
            hold(app.customAx, 'on');
%             imagesc(app.customAx, borders, 'AlphaData', .4.*borders)
%             plot(app.customAx,floor(yNew),floor(xNew),'r.');
            p = plot(app.customAx, y, x,'g.');
            p.Color(4) = .5;
            hold(app.customAx, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputObj, inputFile)
            root = getenv('Umitoolbox');
            file = fullfile(root, 'GUI\DataViz\mouse_ctx_borders.mat');
            a = load(file);
            app.atlas = a.atlas;
            app.Label.Text = 'Opening file...';
            app.customAx = axes('Parent', app.UIFigure);
            app.customAx.Position = [0.25 0.1 0.65 0.75];
            app.customAx.XLabel.String = 'X';
            app.customAx.YLabel.String = 'Y';
            app.customAx.Title.String = 'Anatomical Image';
            ok = false;
            if iscell(inputObj)
                inputObj = [inputObj{:}];
            end
            if numel(inputObj) > 1
                uiwait(errordlg('This app accepts only one object! Try Again.', 'Too many inputs.', 'modal'))
            elseif ~isa(inputObj, 'Modality') || isa(inputObj, 'Acquisition') || isa(inputObj, 'Subject')
                uiwait(errordlg('Input #1 must be a Modality, Acquisition or Subject.', 'Wrong input data type'))
            elseif ~isfile(fullfile(inputObj.SaveFolder, inputFile))
                uiwait(errordlg('Input #2 must be an existing file', 'Wrong input data type'))
            else
                ok = true;
            end
            
            if ~ok
                close(app.UIFigure);
                delete(app);
            else
                app.inputObj = inputObj;
                app.inputFile = fullfile(inputObj.SaveFolder,inputFile);
                app.getFrame;
                app.colormapDropDown.Value = 'gray';
                app.showFrame;
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            landMark = app.AnatomicalreferencesButtonGroup.SelectedObject.Text;
            if strcmp(landMark, 'Bregma') && ~isempty(app.BregmaXY)
                return
            elseif strcmp(landMark, 'Lambda') && ~isempty(app.LambdaXY)
                return
            end
            app.b_captureCoords = true;
            app.customAx.Title.String = ['Click on ' landMark ];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.b_captureCoords
                landMark = app.AnatomicalreferencesButtonGroup.SelectedObject.Text;
                app.updateCurrentCoords;
                app.drawLandMark(landMark)
                app.customAx.Title.String = 'Anatomical Image';
            else
                return
            end
        end

        % Button pushed function: ShowlandmarksButton
        function ShowlandmarksButtonPushed(app, event)
            app.SetButton.Enable ='off';
            if app.alignimagetorefptsCheckBox.Value
                app.rotateImage;
                app.showFrame;
            end
            if app.ovelayctxareasCheckBox.Value
                app.overlayAllenMap;
            end
            app.CurrentCoords = app.BregmaXY;
            app.drawLandMark('Bregma');
            app.CurrentCoords = app.LambdaXY;
            app.drawLandMark('Lambda');
            
        end

        % Value changed function: enhancecontrastCheckBox
        function enhancecontrastCheckBoxValueChanged(app, event)
            app.showFrame;
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            value = app.colormapDropDown.Value;
            app.showFrame;
            app.drawLandMark('Bregma');
            app.drawLandMark('Lambda');
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.BregmaXY = [];
            app.LambdaXY = [];
            app.CurrentCoords = [];
            app.SetButton.Enable = 'on';
            uialert(app.UIFigure, 'Anatomical landmarks erased!', 'Reset successful', 'Icon', ...
                "info");
            app.showFrame;
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.logicalMask = [];
            app.DrawROIButton.Enable = 'on';
            app.showFrame;
            uialert(app.UIFigure, 'Logical mask erased!', 'Reset successful', 'Icon', ...
                "info");
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.BregmaXY = [];
            app.LambdaXY = [];
            app.CurrentCoords = [];
            app.logicalMask = [];
            app.ROIobj = [];
            app.getFrame;
            app.showFrame;
            app.SetButton.Enable = 'on';
            uialert(app.UIFigure, 'Image restored! Anatomical landmarks and masks were erased.', 'Reset successful', 'Icon', ...
                "info");
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            nROI = app.ROISpinner.Value;
            app.drawROI(nROI);
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, 'escape')
                app.b_escapeKey = true;
            end
            
        end

        % Button pushed function: ShowMaskButton
        function ShowMaskButtonPushed(app, event)
            maskObj = app.ROIobj;
            bw = false(size(app.frame,1),size(app.frame,2), length(maskObj));
            for i = 1:length(maskObj)
                bw(:,:,i) = createMask(maskObj(i), app.frame);
            end
            mask = any(bw,3);
            % Overlay on image
            b_applyAlpha = app.showasalphaCheckBox.Value;
            hold(app.customAx, 'on');
            if b_applyAlpha
                imagesc(app.customAx, mask, 'AlphaData', 0.7.*(~mask));
            else
                imagesc(app.customAx, mask, 'AlphaData', ~mask);
            end
            app.logicalMask = mask;
            delete(app.ROIobj);
            app.ROIobj = [];
            hold(app.customAx, 'off');
            app.customAx.Title.String = 'Done. Click on Create Reference Frame File to save OR Restore to try again.';
        end

        % Button pushed function: CreatereferenceframefileButton
        function CreatereferenceframefileButtonPushed(app, event)
            
            subjObj = app.inputObj.MyParent.MyParent;
            filename = fullfile(subjObj.SaveFolder, 'ImagingReferenceFrame.mat');
            reference_frame = app.frame;
            logical_mask = app.logicalMask;
            BregmaXY = app.BregmaXY;%#ok
            LambdaXY = app.LambdaXY;%#ok
            datSize = size(reference_frame);
            datFile = app.inputFile;
            save(filename, 'reference_frame', 'logical_mask', 'BregmaXY', 'LambdaXY', 'datSize', 'datFile');
            sel = uiconfirm(app.UIFigure, ['Reference Frame data file saved as ' filename], 'Data Saved', 'Options', {'Confirm and close', 'Return'});
            if strcmp(sel, 'Confirm and close')
                close(app.UIFigure)
                delete(app)
            else
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.CorticalimagingreferenceframecreatorLabel = uilabel(app.UIFigure);
            app.CorticalimagingreferenceframecreatorLabel.Text = 'Cortical imaging reference frame creator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AnatomicalreferencesButtonGroup = uibuttongroup(app.UIFigure);
            app.AnatomicalreferencesButtonGroup.Title = 'Anatomical references';
            app.BregmaButton = uitogglebutton(app.AnatomicalreferencesButtonGroup);
            app.BregmaButton.Text = 'Bregma';
            app.BregmaButton.Value = true;
            app.LambdaButton = uitogglebutton(app.AnatomicalreferencesButtonGroup);
            app.LambdaButton.Text = 'Lambda';
            app.SetButton = uibutton(app.AnatomicalreferencesButtonGroup, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ShowlandmarksButton = uibutton(app.AnatomicalreferencesButtonGroup, 'push');
            app.ShowlandmarksButton.ButtonPushedFcn = createCallbackFcn(app, @ShowlandmarksButtonPushed, true);
            app.ShowlandmarksButton.Text = 'Show landmarks';
            app.alignimagetorefptsCheckBox = uicheckbox(app.AnatomicalreferencesButtonGroup);
            app.alignimagetorefptsCheckBox.Tooltip = {'Rotates image so Bregma and Lambda are vertically aligned.'};
            app.alignimagetorefptsCheckBox.Text = 'align image';
            app.ResetButton_3 = uibutton(app.AnatomicalreferencesButtonGroup, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ovelayctxareasCheckBox = uicheckbox(app.AnatomicalreferencesButtonGroup);
            app.ovelayctxareasCheckBox.Text = 'ovelay ctx. areas';
            app.createlogicalmaskPanel = uipanel(app.UIFigure);
            app.createlogicalmaskPanel.Title = 'create logical mask';
            app.DrawROIButton = uibutton(app.createlogicalmaskPanel, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.ROISpinnerLabel = uilabel(app.createlogicalmaskPanel);
            app.ROISpinnerLabel.Text = '# ROI';
            app.ROISpinner = uispinner(app.createlogicalmaskPanel);
            app.ResetButton_2 = uibutton(app.createlogicalmaskPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.showasalphaCheckBox = uicheckbox(app.createlogicalmaskPanel);
            app.showasalphaCheckBox.Tooltip = {'Overlay transparency ROI mask on image.'};
            app.showasalphaCheckBox.Text = ' show as alpha';
            app.ShowMaskButton = uibutton(app.createlogicalmaskPanel, 'push');
            app.ShowMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMaskButtonPushed, true);
            app.ShowMaskButton.Text = 'Show Mask';
            app.RestoreButton = uibutton(app.UIFigure, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.CreatereferenceframefileButton = uibutton(app.UIFigure, 'push');
            app.CreatereferenceframefileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatereferenceframefileButtonPushed, true);
            app.CreatereferenceframefileButton.Text = {'Create reference'; ' frame file'};
            app.ImageadjustmentsPanel = uipanel(app.UIFigure);
            app.ImageadjustmentsPanel.Title = 'Image adjustments';
            app.enhancecontrastCheckBox = uicheckbox(app.ImageadjustmentsPanel);
            app.enhancecontrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @enhancecontrastCheckBoxValueChanged, true);
            app.enhancecontrastCheckBox.Tooltip = {'For visualization only.'};
            app.enhancecontrastCheckBox.Text = 'enhance contrast';
            app.colormapDropDownLabel = uilabel(app.ImageadjustmentsPanel);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.ImageadjustmentsPanel);
            app.colormapDropDown.Items = {'gray', 'bone', 'copper', 'pink'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'gray';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mouse_ref_frame_creator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end