classdef newDataViewer_canvas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        PreviewRawMenu           matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        ImportMenu               matlab.ui.container.Menu
        frombinMenu              matlab.ui.container.Menu
        fromtifMenu              matlab.ui.container.Menu
        ExportMenu               matlab.ui.container.Menu
        totifMenu                matlab.ui.container.Menu
        menu_separator           matlab.ui.container.Menu
        BatchProcessMenu         matlab.ui.container.Menu
        menu_separator_2         matlab.ui.container.Menu
        ViewdatahistoryMenu      matlab.ui.container.Menu
        EditmetadataMenu         matlab.ui.container.Menu
        HelpMenu_2               matlab.ui.container.Menu
        PreferencesMenu          matlab.ui.container.Menu
        OnlinedocumentationMenu  matlab.ui.container.Menu
        GridLayoutMain           matlab.ui.container.GridLayout
        GridLayoutSub1           matlab.ui.container.GridLayout
        GridLayoutRight          matlab.ui.container.GridLayout
        EventsGrid               matlab.ui.container.GridLayout
        RestoreButton            matlab.ui.control.Button
        DeleteRepetitionButton   matlab.ui.control.Button
        DeleteConditionButton    matlab.ui.control.Button
        RepetitionDropDown       matlab.ui.control.DropDown
        RepetitionDropDownLabel  matlab.ui.control.Label
        ConditionDropDown        matlab.ui.control.DropDown
        ConditionDropDownLabel   matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        EventsLabel              matlab.ui.control.Label
        ROItableLabel            matlab.ui.control.Label
        TemporalProfileLabel     matlab.ui.control.Label
        PlotAxes                 matlab.ui.control.UIAxes
        GridLayoutLeft           matlab.ui.container.GridLayout
        StatusLabel              matlab.ui.control.Label
        ImageViewLabel           matlab.ui.control.Label
        TopGrid                  matlab.ui.container.GridLayout
        NextFrameButton          matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        MovieSpeedLabel          matlab.ui.control.Label
        MovieSpeedDropDown       matlab.ui.control.DropDown
        PreviousFrameButton      matlab.ui.control.Button
        PlayMovieButton          matlab.ui.control.Button
        BottomGrid               matlab.ui.container.GridLayout
        ClimMaxField             matlab.ui.control.NumericEditField
        toLabel                  matlab.ui.control.Label
        ClimMinField             matlab.ui.control.NumericEditField
        autoButton               matlab.ui.control.Button
        ClipSlider               matlab.ui.control.RangeSlider
        ClipSliderLabel          matlab.ui.control.Label
        flipCheckBox             matlab.ui.control.CheckBox
        ColormapDropDown         matlab.ui.control.DropDown
        ColormapDropDownLabel    matlab.ui.control.Label
        ImageAxes                matlab.ui.control.UIAxes
        TabGroup                 matlab.ui.container.TabGroup
        UtilitiesTab             matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        PipelineTab              matlab.ui.container.Tab
        GridLayout2_2            matlab.ui.container.GridLayout
        Label_10                 matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Button_10                matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        ROIsTab                  matlab.ui.container.Tab
        GridLayout2_3            matlab.ui.container.GridLayout
        Label_15                 matlab.ui.control.Label
        Label_14                 matlab.ui.control.Label
        Label_13                 matlab.ui.control.Label
        Label_12                 matlab.ui.control.Label
        Label_11                 matlab.ui.control.Label
        Button_15                matlab.ui.control.Button
        Button_14                matlab.ui.control.Button
        Button_13                matlab.ui.control.Button
        Button_12                matlab.ui.control.Button
        Button_11                matlab.ui.control.Button
        ContextMenu              matlab.ui.container.ContextMenu
        SetclippinglimitsMenu    matlab.ui.container.Menu
    end


    properties (Access = private)
        % Data backend
        DataSource = []
        CurrentFile char = ''
        CurrentEntry char = ''

        % Viewer state
        CurrentFrame double = 1
        CrosshairXY double = [1 1]   % [X Y]

        % Display/cache state
        ShowCacheRectangle logical = true

        % Graphics handles
        ImageHandle = matlab.graphics.primitive.Image.empty
        CrosshairHandles = gobjects(0)
        CacheRectHandle = gobjects(0)
        CrossTraceHandle = gobjects(0)
        TimeBarHandle = gobjects(0)

        % Image context menu
        ImageContextMenu matlab.ui.container.ContextMenu
        UpdateCacheMenu matlab.ui.container.Menu
        ToggleCacheLockMenu matlab.ui.container.Menu
        ToggleCacheAreaMenu matlab.ui.container.Menu
    end

    methods (Access = private)

        function initializeViewerGraphics(app)
            %INITIALIZEVIEWERGRAPHICS Initialize persistent graphics objects.

            cla(app.ImageAxes);
            cla(app.PlotAxes);

            % Image placeholder.
            app.ImageHandle = imagesc(app.ImageAxes, zeros(100, 100, 'single'));
            app.ImageHandle.ButtonDownFcn = @(src, evt) app.onImageClicked(src, evt);
            app.ImageHandle.PickableParts = 'all';
            app.ImageHandle.HitTest = 'on';

            axis(app.ImageAxes, 'image');
            app.ImageAxes.Box = 'on';
            app.ImageAxes.YDir = 'normal';

            % Crosshair.
            app.CrosshairHandles = gobjects(2, 1);
            app.CrosshairHandles(1) = line(app.ImageAxes, [1 1], [1 100], ...
                'Color', [1 1 1], ...
                'HitTest', 'off', ...
                'PickableParts', 'none');

            app.CrosshairHandles(2) = line(app.ImageAxes, [1 100], [1 1], ...
                'Color', [1 1 1], ...
                'HitTest', 'off', ...
                'PickableParts', 'none');

            % Cache rectangle.
            app.CacheRectHandle = rectangle(app.ImageAxes, ...
                'Position', [1 1 1 1], ...
                'EdgeColor', [1 1 1], ...
                'LineStyle', '--', ...
                'LineWidth', 1, ...
                'Visible', 'off', ...
                'HitTest', 'off', ...
                'PickableParts', 'none');

            % Temporal plot.
            app.CrossTraceHandle = plot(app.PlotAxes, nan, nan, ...
                'LineWidth', 1);

            hold(app.PlotAxes, 'on');
            app.TimeBarHandle = line(app.PlotAxes, [1 1], [0 1], ...
                'LineStyle', '--', ...
                'LineWidth', 1, ...
                'Visible', 'off');
            hold(app.PlotAxes, 'off');

            xlabel(app.PlotAxes, 'Frame');
            ylabel(app.PlotAxes, 'Amplitude');
            app.PlotAxes.TickDir = 'out';

            app.createImageContextMenu();

            app.setStatusMessage('Ready.');

        end

        function createImageContextMenu(app)
            %CREATEIMAGECONTEXTMENU Create right-click menu for image/cache actions.

            app.ImageContextMenu = uicontextmenu(app.UIFigure);

            app.UpdateCacheMenu = uimenu(app.ImageContextMenu);
            app.UpdateCacheMenu.Text = 'Update cache around crosshair';
            app.UpdateCacheMenu.MenuSelectedFcn = @(src, evt) app.onUpdateCacheAroundCrosshair();

            app.ToggleCacheLockMenu = uimenu(app.ImageContextMenu);
            app.ToggleCacheLockMenu.Text = 'Lock cache';
            app.ToggleCacheLockMenu.MenuSelectedFcn = @(src, evt) app.onToggleCacheLock();

            app.ToggleCacheAreaMenu = uimenu(app.ImageContextMenu);
            app.ToggleCacheAreaMenu.Text = 'Hide cache area';
            app.ToggleCacheAreaMenu.MenuSelectedFcn = @(src, evt) app.onToggleCacheRectangle();

            app.ImageAxes.ContextMenu = app.ImageContextMenu;
            app.ImageHandle.ContextMenu = app.ImageContextMenu;

        end

        function openDataFromDialog(app)
            %OPENDATAFROMDIALOG Open .dat or .umt image data.

            if isempty(app.CurrentFile)
                startFolder = pwd;
            else
                startFolder = fileparts(app.CurrentFile);
            end

            [file, folder] = uigetfile( ...
                {'*.dat;*.umt', 'UMIT image data (*.dat, *.umt)'; ...
                '*.dat', 'Raw DAT image data (*.dat)'; ...
                '*.umt', 'UMT image data (*.umt)'}, ...
                'Open image data', ...
                startFolder);

            if isequal(file, 0)
                return
            end

            filePath = fullfile(folder, file);
            app.loadDataSource(filePath);
            app.refreshViewerAfterLoad();

        end

        function loadDataSource(app, filePath)
            %LOADDATASOURCE Create the correct backend for a selected image file.

            if ~isfile(filePath)
                error('newDataViewer:fileNotFound', ...
                    'File not found: "%s".', filePath);
            end

            [~, ~, ext] = fileparts(filePath);
            ext = lower(ext);

            app.setStatusMessage(sprintf('Opening "%s"...', filePath));

            switch ext
                case '.dat'
                    app.DataSource = DatImageSource(filePath);

                case '.umt'
                    app.DataSource = UMTImageSource(filePath);

                otherwise
                    error('newDataViewer:unsupportedFileType', ...
                        'Unsupported file type: "%s".', ext);
            end

            app.CurrentFile = filePath;
            app.CurrentFrame = 1;

            sz = app.getDataSize();
            app.CrosshairXY = [round(sz(2) / 2), round(sz(1) / 2)];

            app.setStatusMessage(sprintf('Loaded "%s".', filePath));

        end

        function refreshViewerAfterLoad(app)
            %REFRESHVIEWERAFTERLOAD Initialize all viewer panels after loading data.

            if ~app.hasData()
                return
            end

            app.refreshImageFrame();
            app.refreshCrosshairGraphics();
            app.refreshTemporalProfile();
            app.refreshCacheOverlay();
            app.refreshFrameControls();
            app.refreshCacheMenuState();

        end

        function refreshImageFrame(app)
            %REFRESHIMAGEFRAME Display the current image frame.

            frame = app.getCurrentFrame();

            if isempty(app.ImageHandle) || ~isvalid(app.ImageHandle)
                app.ImageHandle = imagesc(app.ImageAxes, frame);
                app.ImageHandle.ButtonDownFcn = @(src, evt) app.onImageClicked(src, evt);
            else
                app.ImageHandle.CData = frame;
            end

            sz = app.getDataSize();

            app.ImageAxes.XLim = [1 sz(2)];
            app.ImageAxes.YLim = [1 sz(1)];
            axis(app.ImageAxes, 'image');

            title(app.ImageAxes, app.getImageTitle());

        end

        function refreshCrosshairGraphics(app)
            %REFRESHCROSSHAIRGRAPHICS Update crosshair line positions.

            if isempty(app.CrosshairHandles) || any(~isvalid(app.CrosshairHandles))
                return
            end

            sz = app.getDataSize();
            x = app.CrosshairXY(1);
            y = app.CrosshairXY(2);

            app.CrosshairHandles(1).XData = [x x];
            app.CrosshairHandles(1).YData = [1 sz(1)];

            app.CrosshairHandles(2).XData = [1 sz(2)];
            app.CrosshairHandles(2).YData = [y y];

        end

        function refreshTemporalProfile(app)
            %REFRESHTEMPORALPROFILE Update the pixel temporal trace.

            if ~app.hasData()
                return
            end

            x = app.CrosshairXY(1);
            y = app.CrosshairXY(2);

            % Locked-cache behavior: allow crosshair outside cache, but hide trace.
            if app.isCacheLocked() && ~app.DataSource.isInsideCache(y, x)
                app.hideTemporalTrace();
                app.setStatusMessage('Selected pixel is outside the locked temporal cache.');
                return
            end

            wasInsideCache = app.DataSource.isInsideCache(y, x);

            if ~wasInsideCache
                app.setStatusMessage('Updating temporal cache...');
                drawnow limitrate
            end

            trace = app.getCurrentPixelTrace();

            if isempty(trace)
                app.hideTemporalTrace();
                app.setStatusMessage('No temporal trace available for selected pixel.');
                return
            end

            t = 1:numel(trace);

            app.CrossTraceHandle.XData = t;
            app.CrossTraceHandle.YData = trace(:);
            app.CrossTraceHandle.Visible = 'on';

            app.PlotAxes.XLim = [1 max(1, numel(trace))];

            yMin = min(trace, [], 'omitnan');
            yMax = max(trace, [], 'omitnan');

            if ~isfinite(yMin) || ~isfinite(yMax) || yMin == yMax
                yMin = yMin - 1;
                yMax = yMax + 1;
            end

            app.PlotAxes.YLim = [yMin yMax];

            app.refreshTimeBar();

            if ~wasInsideCache
                app.refreshCacheOverlay();
                app.setStatusMessage('Temporal cache updated.');
            end

        end

        function refreshTimeBar(app)
            %REFRESHTIMEBAR Update current-frame marker on temporal plot.

            if isempty(app.TimeBarHandle) || ~isvalid(app.TimeBarHandle)
                return
            end

            yl = app.PlotAxes.YLim;

            app.TimeBarHandle.XData = [app.CurrentFrame app.CurrentFrame];
            app.TimeBarHandle.YData = yl;
            app.TimeBarHandle.Visible = 'on';

        end
        function refreshCacheOverlay(app)
            %REFRESHCACHEOVERLAY Update cache rectangle overlay.

            if isempty(app.CacheRectHandle) || ~isvalid(app.CacheRectHandle)
                return
            end

            if ~app.hasData() || ~app.ShowCacheRectangle
                app.CacheRectHandle.Visible = 'off';
                return
            end

            rectPos = app.DataSource.getCacheRectangle();

            if isempty(rectPos)
                app.CacheRectHandle.Visible = 'off';
                return
            end

            app.CacheRectHandle.Position = rectPos;
            app.CacheRectHandle.Visible = 'on';

        end

        function refreshFrameControls(app)
            %REFRESHFRAMECONTROLS Update slider limits/value after load or frame change.

            if ~app.hasData()
                return
            end

            sz = app.getDataSize();
            nFrames = max(1, sz(3));

            if isprop(app, 'Slider') && isvalid(app.Slider)
                app.Slider.Limits = [1 nFrames];
                app.Slider.Value = min(max(app.CurrentFrame, 1), nFrames);

                if nFrames > 1
                    app.Slider.MajorTicks = unique(round(linspace(1, nFrames, min(5, nFrames))));
                else
                    app.Slider.MajorTicks = 1;
                end
            end

        end
        function refreshCacheMenuState(app)
            %REFRESHCACHEMENUSTATE Update context-menu text for cache state.

            if isempty(app.ToggleCacheLockMenu) || ~isvalid(app.ToggleCacheLockMenu)
                return
            end

            if app.isCacheLocked()
                app.ToggleCacheLockMenu.Text = 'Unlock cache';
            else
                app.ToggleCacheLockMenu.Text = 'Lock cache';
            end

            if app.ShowCacheRectangle
                app.ToggleCacheAreaMenu.Text = 'Hide cache area';
            else
                app.ToggleCacheAreaMenu.Text = 'Show cache area';
            end

        end

        function onImageClicked(app, ~, event)
            %ONIMAGECLICKED Handle left-click image selection.

            if ~app.hasData()
                return
            end

            if isprop(event, 'Button') && event.Button ~= 1
                return
            end

            coords = round(event.IntersectionPoint(1:2));

            x = coords(1);
            y = coords(2);

            [x, y] = app.clampImageCoordinates(x, y);

            app.CrosshairXY = [x y];

            app.refreshCrosshairGraphics();
            app.refreshTemporalProfile();

        end

        function onUpdateCacheAroundCrosshair(app)
            %ONUPDATECACHEAROUNDCROSSHAIR Manually rebuild cache around selected pixel.

            if ~app.hasData()
                return
            end

            x = app.CrosshairXY(1);
            y = app.CrosshairXY(2);

            app.setStatusMessage('Updating temporal cache around crosshair...');
            drawnow limitrate

            app.DataSource.updateCacheAround(y, x);

            app.refreshCacheOverlay();
            app.refreshTemporalProfile();

            app.setStatusMessage('Temporal cache updated around crosshair.');

        end

        function onToggleCacheLock(app)
            %ONTOGGLECACHELOCK Toggle temporal cache lock state.

            if ~app.hasData()
                return
            end

            newState = ~app.isCacheLocked();
            app.DataSource.setCacheLocked(newState);

            app.refreshCacheMenuState();

            if newState
                app.setStatusMessage('Temporal cache locked.');
            else
                app.setStatusMessage('Temporal cache unlocked.');
            end

            app.refreshTemporalProfile();

        end

        function onToggleCacheRectangle(app)
            %ONTOGGLECACHERECTANGLE Show or hide cache overlay.

            app.ShowCacheRectangle = ~app.ShowCacheRectangle;

            app.refreshCacheMenuState();
            app.refreshCacheOverlay();

        end

        function tf = hasData(app)
            %HASDATA Return true when a data source is loaded.

            tf = ~isempty(app.DataSource) && isvalid(app.DataSource);

        end

        function sz = getDataSize(app)
            %GETDATASIZE Return normalized data size [Y X T E].

            rawSize = app.DataSource.getSize();

            if numel(rawSize) < 3
                rawSize(3) = 1;
            end

            if numel(rawSize) < 4
                rawSize(4) = 1;
            end

            sz = double(rawSize(1:4));

        end

        function frame = getCurrentFrame(app)
            %GETCURRENTFRAME Read current frame from active backend.

            sz = app.getDataSize();

            app.CurrentFrame = min(max(round(app.CurrentFrame), 1), sz(3));

            frame = app.DataSource.getFrame(app.CurrentFrame);

            frame = squeeze(frame);

        end

        function trace = getCurrentPixelTrace(app)
            %GETCURRENTPIXELTRACE Read current crosshair temporal trace.

            sz = app.getDataSize();

            x = app.CrosshairXY(1);
            y = app.CrosshairXY(2);

            x = min(max(round(x), 1), sz(2));
            y = min(max(round(y), 1), sz(1));

            trace = app.DataSource.getPixelTrace(y, x);

            trace = trace(:);

        end

        function tf = isCacheLocked(app)
            %ISCACHELOCKED Return true when backend cache is locked.

            tf = false;

            if ~app.hasData()
                return
            end

            if ismethod(app.DataSource, 'isCacheLocked')
                tf = app.DataSource.isCacheLocked();
            end

        end

        function [x, y] = clampImageCoordinates(app, x, y)
            %CLAMPIMAGECOORDINATES Clamp coordinates to image bounds.

            sz = app.getDataSize();

            x = min(max(round(x), 1), sz(2));
            y = min(max(round(y), 1), sz(1));

        end

        function hideTemporalTrace(app)
            %HIDETEMPORALTRACE Hide crosshair temporal trace and frame marker.

            if ~isempty(app.CrossTraceHandle) && isvalid(app.CrossTraceHandle)
                app.CrossTraceHandle.XData = nan;
                app.CrossTraceHandle.YData = nan;
                app.CrossTraceHandle.Visible = 'off';
            end

            if ~isempty(app.TimeBarHandle) && isvalid(app.TimeBarHandle)
                app.TimeBarHandle.Visible = 'off';
            end

        end

        function titleText = getImageTitle(app)
            %GETIMAGETITLE Build image axis title from current state.

            [~, name, ext] = fileparts(app.CurrentFile);
            sz = app.getDataSize();

            titleText = sprintf('%s%s | Frame %d/%d', ...
                name, ext, app.CurrentFrame, sz(3));

        end

        function setStatusMessage(app, msg)
            %SETSTATUSMESSAGE Update status/message label.

            msg = char(string(msg));

            if isprop(app, 'StatusLabel') && ~isempty(app.StatusLabel) && isvalid(app.StatusLabel)
                app.StatusLabel.Text = msg;
            else
                fprintf('[newDataViewer] %s\n', msg);
            end

            % drawnow limitrate

        end

        function setCurrentFrame(app, frameIdx)
            %SETCURRENTFRAME Set current frame and refresh frame-dependent views.

            if ~app.hasData()
                return
            end

            sz = app.getDataSize();

            app.CurrentFrame = min(max(round(frameIdx), 1), sz(3));

            app.refreshImageFrame();
            app.refreshTemporalProfile();
            app.refreshFrameControls();

        end

        function goToPreviousFrame(app)
            %GOTOPREVIOUSFRAME Move to previous frame.

            app.setCurrentFrame(app.CurrentFrame - 1);

        end
        function goToNextFrame(app)
            %GOTONEXTFRAME Move to next frame.

            app.setCurrentFrame(app.CurrentFrame + 1);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            app.initializeViewerGraphics();

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.openDataFromDialog();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.setCurrentFrame(app.Slider.Value);

        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            app.goToPreviousFrame();
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            app.goToNextFrame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.PreviewRawMenu = uimenu(app.FileMenu);
            app.PreviewRawMenu.Text = 'Preview Raw';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.frombinMenu = uimenu(app.ImportMenu);
            app.frombinMenu.Text = 'from .bin';
            app.fromtifMenu = uimenu(app.ImportMenu);
            app.fromtifMenu.Text = 'from .tif';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.totifMenu = uimenu(app.ExportMenu);
            app.totifMenu.Text = 'to tif';
            app.menu_separator = uimenu(app.FileMenu);
            app.menu_separator.Enable = 'off';
            app.menu_separator.Text = '___________';
            app.BatchProcessMenu = uimenu(app.FileMenu);
            app.BatchProcessMenu.Text = 'Batch Process';
            app.menu_separator_2 = uimenu(app.FileMenu);
            app.menu_separator_2.Enable = 'off';
            app.menu_separator_2.Text = '___________';
            app.ViewdatahistoryMenu = uimenu(app.FileMenu);
            app.ViewdatahistoryMenu.Text = 'View data history';
            app.EditmetadataMenu = uimenu(app.FileMenu);
            app.EditmetadataMenu.Text = 'Edit meta data';
            app.HelpMenu_2 = uimenu(app.UIFigure);
            app.HelpMenu_2.Text = 'Help';
            app.PreferencesMenu = uimenu(app.HelpMenu_2);
            app.PreferencesMenu.Text = 'Preferences';
            app.OnlinedocumentationMenu = uimenu(app.HelpMenu_2);
            app.OnlinedocumentationMenu.Text = 'Online documentation';
            app.GridLayoutMain = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutMain);
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.GridLayout2 = uigridlayout(app.UtilitiesTab);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_4 = uibutton(app.GridLayout2, 'push');
            app.Button_5 = uibutton(app.GridLayout2, 'push');
            app.Label = uilabel(app.GridLayout2);
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_5 = uilabel(app.GridLayout2);
            app.PipelineTab = uitab(app.TabGroup);
            app.PipelineTab.Title = 'Pipeline';
            app.GridLayout2_2 = uigridlayout(app.PipelineTab);
            app.Button_6 = uibutton(app.GridLayout2_2, 'push');
            app.Button_7 = uibutton(app.GridLayout2_2, 'push');
            app.Button_8 = uibutton(app.GridLayout2_2, 'push');
            app.Button_9 = uibutton(app.GridLayout2_2, 'push');
            app.Button_10 = uibutton(app.GridLayout2_2, 'push');
            app.Label_6 = uilabel(app.GridLayout2_2);
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_8 = uilabel(app.GridLayout2_2);
            app.Label_9 = uilabel(app.GridLayout2_2);
            app.Label_10 = uilabel(app.GridLayout2_2);
            app.ROIsTab = uitab(app.TabGroup);
            app.ROIsTab.Title = 'ROIs';
            app.GridLayout2_3 = uigridlayout(app.ROIsTab);
            app.Button_11 = uibutton(app.GridLayout2_3, 'push');
            app.Button_12 = uibutton(app.GridLayout2_3, 'push');
            app.Button_13 = uibutton(app.GridLayout2_3, 'push');
            app.Button_14 = uibutton(app.GridLayout2_3, 'push');
            app.Button_15 = uibutton(app.GridLayout2_3, 'push');
            app.Label_11 = uilabel(app.GridLayout2_3);
            app.Label_12 = uilabel(app.GridLayout2_3);
            app.Label_13 = uilabel(app.GridLayout2_3);
            app.Label_14 = uilabel(app.GridLayout2_3);
            app.Label_15 = uilabel(app.GridLayout2_3);
            app.GridLayoutSub1 = uigridlayout(app.GridLayoutMain);
            app.GridLayoutLeft = uigridlayout(app.GridLayoutSub1);
            app.ImageAxes = uiaxes(app.GridLayoutLeft);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.BottomGrid = uigridlayout(app.GridLayoutLeft);
            app.ColormapDropDownLabel = uilabel(app.BottomGrid);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.BottomGrid);
            app.ColormapDropDown.Items = {'parula', 'turbo', 'nebula', 'jet', 'hsv', 'hot', 'gray'};
            app.ColormapDropDown.Value = 'parula';
            app.flipCheckBox = uicheckbox(app.BottomGrid);
            app.flipCheckBox.Tooltip = {'Inverts the colormap'};
            app.flipCheckBox.Text = 'flip';
            app.ClipSliderLabel = uilabel(app.BottomGrid);
            app.ClipSliderLabel.Text = 'Clip';
            app.ClipSlider = uislider(app.BottomGrid, 'range');
            app.ClipSlider.MajorTicks = [0 50 100];
            app.autoButton = uibutton(app.BottomGrid, 'push');
            app.autoButton.Text = 'auto';
            app.ClimMinField = uieditfield(app.BottomGrid, 'numeric');
            app.ClimMinField.ValueDisplayFormat = '%11.3f';
            app.toLabel = uilabel(app.BottomGrid);
            app.toLabel.Text = 'to';
            app.ClimMaxField = uieditfield(app.BottomGrid, 'numeric');
            app.ClimMaxField.ValueDisplayFormat = '%11.3f';
            app.TopGrid = uigridlayout(app.GridLayoutLeft);
            app.PlayMovieButton = uibutton(app.TopGrid, 'push');
            app.PlayMovieButton.Icon = 'icon_rightTriangle_BW.png';
            app.PlayMovieButton.Text = '';
            app.PreviousFrameButton = uibutton(app.TopGrid, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = '<';
            app.MovieSpeedDropDown = uidropdown(app.TopGrid);
            app.MovieSpeedDropDown.Items = {'0.25x', '0.5x', '1x', '2x', '4x'};
            app.MovieSpeedDropDown.Value = '1x';
            app.MovieSpeedLabel = uilabel(app.TopGrid);
            app.MovieSpeedLabel.Text = 'Fr(Hz)';
            app.Slider = uislider(app.TopGrid);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.NextFrameButton = uibutton(app.TopGrid, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = '>';
            app.ImageViewLabel = uilabel(app.GridLayoutLeft);
            app.ImageViewLabel.Text = 'Image View';
            app.StatusLabel = uilabel(app.GridLayoutLeft);
            app.StatusLabel.Text = 'Status';
            app.GridLayoutRight = uigridlayout(app.GridLayoutSub1);
            app.PlotAxes = uiaxes(app.GridLayoutRight);
            xlabel(app.PlotAxes, 'Time (s)')
            ylabel(app.PlotAxes, 'Amplitude')
            app.TemporalProfileLabel = uilabel(app.GridLayoutRight);
            app.TemporalProfileLabel.Text = 'Temporal Profile';
            app.ROItableLabel = uilabel(app.GridLayoutRight);
            app.ROItableLabel.Text = 'ROI table';
            app.EventsLabel = uilabel(app.GridLayoutRight);
            app.EventsLabel.Text = 'Events ';
            app.UITable = uitable(app.GridLayoutRight);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EventsGrid = uigridlayout(app.GridLayoutRight);
            app.ConditionDropDownLabel = uilabel(app.EventsGrid);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.EventsGrid);
            app.RepetitionDropDownLabel = uilabel(app.EventsGrid);
            app.RepetitionDropDownLabel.Text = 'Repetition';
            app.RepetitionDropDown = uidropdown(app.EventsGrid);
            app.DeleteConditionButton = uibutton(app.EventsGrid, 'push');
            app.DeleteConditionButton.Text = 'Delete';
            app.DeleteRepetitionButton = uibutton(app.EventsGrid, 'push');
            app.DeleteRepetitionButton.Text = 'Delete';
            app.RestoreButton = uibutton(app.EventsGrid, 'push');
            app.RestoreButton.Text = 'Restore';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SetclippinglimitsMenu = uimenu(app.ContextMenu);
            app.SetclippinglimitsMenu.Text = 'Set clipping limits';
            % Assign app.ContextMenu
            app.ClipSlider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newDataViewer_canvas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end