classdef MergeRecs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MergeRecordingtoolUIFigure      matlab.ui.Figure
        Tree                            matlab.ui.container.Tree
        ChooseacquisitionsListBoxLabel  matlab.ui.control.Label
        ChooseacquisitionsListBox       matlab.ui.control.ListBox
        ChooseModalityListBoxLabel      matlab.ui.control.Label
        ChooseModalityListBox           matlab.ui.control.ListBox
        ChooseFileListBoxLabel          matlab.ui.control.Label
        ChooseFileListBox               matlab.ui.control.ListBox
        SelectButton                    matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        SetorderListBoxLabel            matlab.ui.control.Label
        SetorderListBox                 matlab.ui.control.ListBox
        ButtonUp                        matlab.ui.control.Button
        ButtonDown                      matlab.ui.control.Button
        EventsPanel                     matlab.ui.container.Panel
        StimIDEditField                 matlab.ui.control.EditField
        OverwriteeventIDsCheckBox       matlab.ui.control.CheckBox
        IgnoreeventsCheckBox            matlab.ui.control.CheckBox
        NewAcquistionNameEditFieldLabel  matlab.ui.control.Label
        NewAcquistionNameEditField      matlab.ui.control.EditField
        MergeDataButton                 matlab.ui.control.Button
        NewFileNameEditFieldLabel       matlab.ui.control.Label
        NewFileNameEditField            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MainApp % Handle to umIToolbox app
        nodeLevels % list of level indice of Nodes in TREE.
        modObjList % List of selected nodes   
    end
    
    methods (Access = private)
        
        function NodeObj = getNodeObj(app,Node, lvl)
            % GETNODEOBJ retrieves the object associated with the selected node
            % in a UITree.
            % Input:
            % node = handle to the selected UITREENODE.
            % lvl = level of the node (1 = subject; 2 =
            % acquisition; 3 = modality).
            % Outputs:
            % NodeObj = handle of the Protocol object associated with the
            % UITREE node.
            
            switch lvl
                case 1
                    idx = app.MainApp.ProtocolObj.Array.findElement('ID', Node.Text, 'strcmp');
                    NodeObj = app.MainApp.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.MainApp.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Text, 'strcmp');
                    NodeObj = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.MainApp.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                    idxA = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', Node.Text, 'strcmp');
                    NodeObj = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
        end
        
        function setPanelContent(app)
            % This method populates the content of the uipanel based on the
            % selection of the items in the list boxes:
            
            app.SetorderListBox.Items = app.ChooseacquisitionsListBox.Items;
            app.MergedfilenameListBox = app.ChooseModalityListBox.Value;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.Panel.Visible = 'off';
            app.MergeRecordingtoolUIFigure.Visible = 'off';
            movegui(app.MergeRecordingtoolUIFigure, "center");
            app.MainApp = MainApp;
            app.MainApp.b_HasNewAcq = false;
            % Copy UITree from main app:
            copyobj(app.MainApp.Tree.Children, app.Tree, 'legacy')
            expand(app.Tree);
            % Make figure visible:
            app.MergeRecordingtoolUIFigure.Visible = 'on';
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.Tree.Enable = 'off';drawnow
            app.SelectButton.Enable = 'off';
            if isempty(app.Tree.SelectedNodes)
                app.ChooseFileListBox.Items = {};
                app.ChooseModalityListBox.Items ={};
                app.ChooseacquisitionsListBox.Items = {};
                app.Tree.Enable = 'on';
                return
            end
            % Get Highest node level ('Subject'):
            Nodes = [];
            for i = 1:numel(app.Tree.SelectedNodes)
                if isa(app.Tree.SelectedNodes(i).Parent, 'matlab.ui.container.Tree')
                    Nodes = [Nodes;app.Tree.SelectedNodes(i).Children];
                else
                    Nodes = [Nodes;app.Tree.SelectedNodes(i)];
                end
            end
            loc_nodeList = [];
            % Select all children of the selected Node.
            for i = 1:numel(Nodes)
                node = Nodes(i);               
                try
                    loc_nodeList = [loc_nodeList;node;node.Children;[node.Children.Children]'];
                catch
                    try
                        loc_nodeList = [loc_nodeList;node;node.Children];
                    catch
                        loc_nodeList = [loc_nodeList;node];
                    end
                end
                
            end                     
            % Select branches:
            app.Tree.SelectedNodes = unique(loc_nodeList);
            % Erase modality and File List boxes:
            app.ChooseFileListBox.Items = {};
            app.ChooseModalityListBox.Items ={};
            % Update acquisition list with all acquisition names that exist
            % in all selected subjects:
            indxM = arrayfun(@(x) isempty(x.Children), app.Tree.SelectedNodes);
            modNodes = app.Tree.SelectedNodes(indxM);
            acqNodes = [modNodes.Parent]';            
            subjNodes = [acqNodes.Parent]';            
            %             
            acqNames = unique({acqNodes.Text});
            idxAll = false(size(acqNames));
            for i = 1:numel(acqNames)
                idxAll(i) = sum(strcmp({acqNodes.Text}, acqNames(i))) == numel(unique({subjNodes.Text}));                
            end            
            app.ChooseacquisitionsListBox.Items = acqNames(idxAll);
            app.ChooseacquisitionsListBox.Value = {};
            % Expand nodes and re-enable UITREE:
            arrayfun(@expand, app.Tree.SelectedNodes)
            app.Tree.Enable = 'on';drawnow
        end

        % Value changed function: ChooseacquisitionsListBox
        function ChooseacquisitionsListBoxValueChanged(app, event)
            app.SelectButton.Enable ='off';
            app.ChooseModalityListBox.Items = {};
            app.ChooseFileListBox.Items = {};                       
            acqList = app.ChooseacquisitionsListBox.Value;           
            if isempty(acqList)
                return
            end
            idxM = arrayfun(@(x) isempty(x.Children), app.Tree.SelectedNodes);            
            modNodes = app.Tree.SelectedNodes(idxM);
            acqNodes = [modNodes.Parent]';
            subjNodes = [acqNodes.Parent]';            
            idxAll = false(size(modNodes));
            for i = 1:numel(acqList)
                idxAll(i) = sum(strcmp({acqNodes.Text}, acqList(i))) == numel(unique({subjNodes.Text}));                
            end                      
            app.ChooseModalityListBox.Items = unique({modNodes(idxAll).Text});
            app.ChooseModalityListBox.Value = {};                       
        end

        % Value changed function: ChooseModalityListBox
        function ChooseModalityListBoxValueChanged(app, event)
            % This callback looks for all .dat files inside the chosen
            % modality folder and updates the Files ListBox
            app.ChooseFileListBox.Items = {'Loading files...'};            
            %
            modName = app.ChooseModalityListBox.Value;
            if isempty(modName)
                app.ChooseFileListBox.Items = {};
                return               
            end            
            idxM = arrayfun(@(x) isempty(x.Children), app.Tree.SelectedNodes);            
            modNodes = app.Tree.SelectedNodes(idxM);
            acqNames = arrayfun(@(x) x.Parent.Text, modNodes, 'UniformOutput', false);            
            modNodes = modNodes(ismember(acqNames, app.ChooseacquisitionsListBox.Value));
            % Get the input file full paths from each modality
            datList = cell(size(modNodes));
            app.modObjList = datList;
            for i = 1:length(modNodes)
                % Get protocol object from Tree node:
                nodeObj = app.getNodeObj(modNodes(i), 3);                
                % Get list of .dat files that also have a meta data .mat
                % file:
                datFiles = dir(fullfile(nodeObj.SaveFolder,'*.dat'));
                matFiles = dir(fullfile(nodeObj.SaveFolder,'*.mat'));                
                idxDat = ismember(erase({datFiles.name}, '.dat'), erase({matFiles.name},'.mat'));
                % Save list to cell array:
                datList{i} = datFiles(idxDat);
                app.modObjList{i} = nodeObj;
            end
            % Keep files that exist in all folders:
            datNameList = cellfun(@(x) {x.name}, datList, 'UniformOutput',false);
            datNames = unique([datNameList{:}]);            
            idxAll = false(size(datNames));
            for i = 1:length(datNames)                
                idxAll(i) = all(cellfun(@(x) any(strcmp(datNames{i},x)), datNameList, 'UniformOutput',true));
            end
            datNames = datNames(idxAll);                              
            app.ChooseFileListBox.Items = datNames;                       
        end

        % Value changed function: SelectButton
        function SelectButtonValueChanged(app, event)
            
            if app.SelectButton.Value
                app.SelectButton.Text = 'Undo';
                app.Tree.Enable = 'off';
                app.ChooseacquisitionsListBox.Enable = 'off';
                app.ChooseModalityListBox.Enable = 'off';
                app.ChooseFileListBox.Enable = 'off';
                
                % Populate list boxes in uipanel:                                              
                acqItems= app.ChooseacquisitionsListBox.Value;                
                if ischar(acqItems); acqItems = {acqItems};end                
                app.SetorderListBox.Items = acqItems;
                app.NewFileNameEditField.Value = app.ChooseFileListBox.Value;
                app.Panel.Visible = 'on';                             
            else
                % Reset file list:                
                app.SelectButton.Text = 'Select';
                app.SelectButton.Enable = 'off';
                app.Tree.Enable = 'on';
                app.ChooseacquisitionsListBox.Enable = 'on';
                app.ChooseModalityListBox.Enable = 'on';
                app.ChooseFileListBox.Enable = 'on';
                app.Panel.Visible = 'off';
                app.StimIDEditField.Value = '';
                app.OverwriteeventIDsCheckBox.Value = false;
            end
            
        end

        % Value changed function: ChooseFileListBox
        function ChooseFileListBoxValueChanged(app, event)
            app.SelectButton.Enable = 'on';                       
        end

        % Button pushed function: ButtonUp
        function ButtonUpUpPushed(app, event)
              Item_selected = app.SetorderListBox.Value;
            if~isempty(Item_selected)
                Items = app.SetorderListBox.Items;                
                pos = find(strcmp(Item_selected, app.SetorderListBox.Items));                
                if pos > 1
                    range = 1:numel(Items);
                    range(pos) = pos -1 ;
                    range(pos -1) = pos;
                    app.SetorderListBox.Items = Items(range);                    
                end
            end
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
             Item_selected = app.SetorderListBox.Value;
            if~isempty(Item_selected)
                Items = app.SetorderListBox.Items;                
                pos = find(strcmp(Item_selected, app.SetorderListBox.Items)); 
                if pos < numel(Items)
                    range = 1:numel(Items);
                    range(pos) = pos + 1;
                    range(pos + 1) = pos;
                    app.SetorderListBox.Items = Items(range);                    
                end
            end
        end

        % Callback function
        function ChangenameButtonPushed(app, event)
            
            item = app.MergedfilenameListBox.Value;
            if isempty(item)
                return
            end
            idx_item = strcmp(item, app.MergedfilenameListBox.ItemsData);
            currName = app.MergedfilenameListBox.Items(idx_item);            
            newName = inputdlg('New file name', ['Rename ' item], [1 50], currName);
            if isempty(newName)
                disp('Operation cancelled by User');
                return
            elseif isempty(newName{:})
                uialert(app.MergeRecordingtoolUIFigure, 'Cannot set empty string as file name', 'Invalid Name', 'Icon', "warning");
                return
            end
            newName = newName{:};
            if ~endsWith(newName, '.dat')
                newName = [newName '.dat'];
            end           
            app.MergedfilenameListBox.Value = {};
            app.MergedfilenameListBox.Items{idx_item} = newName;
            
        end

        % Value changed function: NewFileNameEditField
        function NewFileNameEditFieldValueChanged(app, event)
            value = app.NewFileNameEditField.Value;
            if isempty(strip(value)) || strcmpi(strip(value), '.dat')
                app.NewFileNameEditField.Value = event.PreviousValue;                
                return
            end
            if ~endsWith(value, '.dat')
                value = [strip(value), '.dat'];
            end
            app.NewFileNameEditField.Value = value;
        end

        % Value changed function: OverwriteeventIDsCheckBox
        function OverwriteeventIDsCheckBoxValueChanged(app, event)
           
            if app.OverwriteeventIDsCheckBox.Value
                app.StimIDEditField.Enable = 'on';
                app.MergeDataButton.Enable = 'off';
            else
                app.StimIDEditField.Enable = 'off';                
                app.StimIDEditField.Value = '';
                app.MergeDataButton.Enable = 'on';
            end
        end

        % Value changed function: StimIDEditField
        function StimIDEditFieldValueChanged(app, event)
            % This callback checks if the number of event IDs separated by ";"
            % are the same as the number of acquisitions.
            
            str = strsplit(strip(app.StimIDEditField.Value),";");            
            str = str(~cellfun(@isempty, str));
            if length(str)~= length(app.SetorderListBox.Items)
                app.StimIDEditField.Enable ='off';
                app.OverwriteeventIDsCheckBox.Value = false;
                uialert(app.MergeRecordingtoolUIFigure, 'The number of event IDs must be the same as the number of recordings!', 'Warning','Icon', 'warning');
            else
                app.StimIDEditField.Value = strjoin(str, ';');
                app.MergeDataButton.Enable = 'on';
            end
        end

        % Button pushed function: MergeDataButton
        function MergeDataButtonPushed(app, event)
            % This callback creates a new modality and acquisition for each
            % selected subject and merges the data using the
            % "mergeRecordings" function.
            
            
            % Gather the info needed to merge the recordings:           
            params = struct('SaveFileName',app.NewFileNameEditField.Value, 'filename',app.ChooseFileListBox.Value,...
                'merge_order',app.SetorderListBox.ItemsData, 'b_IgnoreEvents',app.IgnoreeventsCheckBox.Value);
            % Update modObjList:
            idxM = cellfun(@(x) contains(x.SaveFolder,cellfun(@(y) [filesep y filesep],app.SetorderListBox.Items, 'UniformOutput',false)), app.modObjList);
            modList = app.modObjList(idxM);
            % Get Acquisition and subject Names:
            subjNameList = cellfun(@(x) x.MyParent.MyParent.ID,modList, 'UniformOutput', false);     
            subjNames = unique(subjNameList);
            acqNames = app.SetorderListBox.Items;                                  
            acqNames_txt = vertcat(num2cell(1:length(acqNames)), acqNames);            
             % Show summary and confirm:
            txt = 'SUMMARY: ';
            txt = [txt,sprintf('\n\nInput file name: "%s"\nOutput file name: "%s"\nAcquisition order:\n\t', params.filename, params.SaveFileName),...
                sprintf('%d-%s\n\t', acqNames_txt{:}),...
                sprintf('\rThe merging will be done for each one of the subjects below:\n\t'), sprintf('%s\n\t',subjNames{:})];
            answer = uiconfirm(app.MergeRecordingtoolUIFigure, txt,'Merge Summary. Confirm?', 'Options',{'Proceed', 'Cancel'},...
                'DefaultOption',2, 'Icon', 'question');
            if strcmpi(answer, 'Cancel')
                return
            end
            % For each subject on the list, create a new acquisition and
            % modality and merge the data.
            
            d = uiprogressdlg(app.MergeRecordingtoolUIFigure, 'Message','Merging recordings...', 'Title',['Merging ' params.filename],'Indeterminate', 'on');
            for i = 1:length(subjNames)
                d.Message = ['Merging data of subject ' subjNames{i}];                  
                indxS = find(strcmp(subjNameList, subjNames{i}));
                modClass = class(modList{indxS(1)});
                subjHandle = modList{indxS(1)}.MyParent.MyParent;                
                
                % Create new protocol elements and save folders:
                newModHandle = app.MainApp.ProtocolObj.manualAddModality(modClass, modList{indxS(1)}.ID,...
                    app.NewAcquistionNameEditField.Value, subjHandle); 
                if isempty(newModHandle)
                    idxH_acq = subjHandle.Array.findElement('ID', app.NewAcquistionNameEditField.Value);
                    idxH_mod = subjHandle.Array.ObjList(idxH_acq).Array.findElement('ID', modList{indxS(1)}.ID);
                    newModHandle = subjHandle.Array.ObjList(idxH_acq).Array.ObjList(idxH_mod);
                end
                %%% Merge recordings:
                % Generate list of folders:
                folderList = cellfun(@(x) x.SaveFolder, modList(indxS), 'UniformOutput',false);
                % Get order of  folder list:                
                params.merge_order = cellfun(@(x) find(contains(folderList, [filesep x filesep])), app.SetorderListBox.Items, 'UniformOutput',true);                
                % Get trial markers:
                trialMarkers = {};
                if app.OverwriteeventIDsCheckBox.Value
                    trialMarkers = strsplit(strip(app.StimIDEditField.Value),";");  
                end
                % Execute Merging:
                try
                    mergeRecordings(fullfile(newModHandle.SaveFolder,params.SaveFileName),folderList,...
                        params.filename,params.merge_order,trialMarkers, params.b_IgnoreEvents);
                catch ME
                    delete(d);
                    msg = {'Failed to merge recordings! See error message below:',getReport(ME, 'basic', "hyperlinks","off")};
                    uialert(app.MergeRecordingtoolUIFigure, msg, 'Error!','Icon','error', 'CloseFcn','uiresume(gcbf)')
                    uiwait(app.MergeRecordingtoolUIFigure)
                    return
                end
            end
            d.Value = 1;
            d.Indeterminate = 'off';
            d.Message = 'Finished Merging data';
            pause(2)
            close(d)
            
            % Ask user what to do
            answer = uiconfirm(app.MergeRecordingtoolUIFigure, 'Finished merging data.', 'Finished', 'Options',{'Close tool', 'Go back'},...
                'DefaultOption',2, 'Icon', 'question');            
            app.MainApp.b_HasNewAcq = true;
            if strcmpi(answer, 'Close tool')                
                delete(app)
            else
                return
            end
        end

        % Value changed function: NewAcquistionNameEditField
        function NewAcquistionNameEditFieldValueChanged(app, event)
            value = strip(app.NewAcquistionNameEditField.Value);
            if isempty(value)
                app.NewAcquistionNameEditField.Value = event.PreviousValue;               
            else
                app.NewAcquistionNameEditField.Value = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MergeRecordingtoolUIFigure = uifigure('Visible', 'off');
            app.MergeRecordingtoolUIFigure.Name = 'MergeRecording tool';
            app.Tree = uitree(app.MergeRecordingtoolUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ChooseacquisitionsListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseacquisitionsListBoxLabel.Text = 'Choose acquisitions';
            app.ChooseacquisitionsListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseacquisitionsListBox.Items = {};
            app.ChooseacquisitionsListBox.Multiselect = 'on';
            app.ChooseacquisitionsListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseacquisitionsListBoxValueChanged, true);
            app.ChooseacquisitionsListBox.Value = {};
            app.ChooseModalityListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseModalityListBoxLabel.Text = 'Choose Modality';
            app.ChooseModalityListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseModalityListBox.Items = {};
            app.ChooseModalityListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseModalityListBoxValueChanged, true);
            app.ChooseModalityListBox.Value = {};
            app.ChooseFileListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseFileListBoxLabel.Text = 'Choose File';
            app.ChooseFileListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseFileListBox.Items = {};
            app.ChooseFileListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseFileListBoxValueChanged, true);
            app.ChooseFileListBox.Value = {};
            app.SelectButton = uibutton(app.MergeRecordingtoolUIFigure, 'state');
            app.SelectButton.ValueChangedFcn = createCallbackFcn(app, @SelectButtonValueChanged, true);
            app.SelectButton.Text = 'Select';
            app.Panel = uipanel(app.MergeRecordingtoolUIFigure);
            app.SetorderListBoxLabel = uilabel(app.Panel);
            app.SetorderListBoxLabel.Text = 'Set order';
            app.SetorderListBox = uilistbox(app.Panel);
            app.SetorderListBox.Items = {};
            app.SetorderListBox.Value = {};
            app.ButtonUp = uibutton(app.Panel, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpUpPushed, true);
            app.ButtonUp.Icon = 'icon_arrow_up.png';
            app.ButtonUp.Text = '';
            app.ButtonDown = uibutton(app.Panel, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Icon = 'icon_arrow_dwn.png';
            app.ButtonDown.Text = '';
            app.EventsPanel = uipanel(app.Panel);
            app.EventsPanel.Title = 'Events';
            app.StimIDEditField = uieditfield(app.EventsPanel, 'text');
            app.StimIDEditField.ValueChangedFcn = createCallbackFcn(app, @StimIDEditFieldValueChanged, true);
            app.StimIDEditField.Enable = 'off';
            app.StimIDEditField.Tooltip = {'Type trial IDs separated by a "'; '". The number of IDs must be equal to the number of recordings to be merged.'};
            app.OverwriteeventIDsCheckBox = uicheckbox(app.EventsPanel);
            app.OverwriteeventIDsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverwriteeventIDsCheckBoxValueChanged, true);
            app.OverwriteeventIDsCheckBox.Tooltip = {'Replaces the IDs of existing events stored in the "events.mat" files. If no files exist'; ' the new IDs will mark the first and last frames from each recording.'};
            app.OverwriteeventIDsCheckBox.Text = 'Overwrite event IDs';
            app.IgnoreeventsCheckBox = uicheckbox(app.EventsPanel);
            app.IgnoreeventsCheckBox.Tooltip = {'Ignores the events stored in "event.mat" files from source recordings and creates new timestamps marking the first and last frames from each recording.'};
            app.IgnoreeventsCheckBox.Text = 'Ignore events';
            app.NewAcquistionNameEditFieldLabel = uilabel(app.Panel);
            app.NewAcquistionNameEditFieldLabel.Text = 'New Acquistion Name';
            app.NewAcquistionNameEditField = uieditfield(app.Panel, 'text');
            app.NewAcquistionNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewAcquistionNameEditFieldValueChanged, true);
            app.NewAcquistionNameEditField.Value = 'NewAcq';
            app.MergeDataButton = uibutton(app.Panel, 'push');
            app.MergeDataButton.ButtonPushedFcn = createCallbackFcn(app, @MergeDataButtonPushed, true);
            app.MergeDataButton.Text = 'Merge Data';
            app.NewFileNameEditFieldLabel = uilabel(app.Panel);
            app.NewFileNameEditFieldLabel.Text = 'New File Name';
            app.NewFileNameEditField = uieditfield(app.Panel, 'text');
            app.NewFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewFileNameEditFieldValueChanged, true);
            app.NewFileNameEditField.Value = 'newfile.dat';
            % Show the figure after all components are created
            app.MergeRecordingtoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergeRecs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MergeRecordingtoolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MergeRecordingtoolUIFigure)
        end
    end
end