classdef PipelineConfig_dlgBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AvailablefunctionsListBoxLabel  matlab.ui.control.Label
        AvailablefunctionsListBox       matlab.ui.control.ListBox
        MypipelineLabel                 matlab.ui.control.Label
        MypipelineListBox               matlab.ui.control.ListBox
        ButtonUp                        matlab.ui.control.Button
        ButtonDwn                       matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SetParamsButton                 matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        SavestepButton                  matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        openButton                      matlab.ui.control.Button
        HelpButton                      matlab.ui.control.StateButton
        ConfirmButton                   matlab.ui.control.StateButton
        ResetButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        pipeObj % Instantiation of PipelineManager class.
        b_isStandAlone % True if calling app is DataViewer StandAlone version.
        b_OpenDoc = false; % If true, the valuechanged callback of the "Available functions" list is executed. This is controlled by the state of the "Help" button.
        avFcnTooltip % Default tooltip for "Available" functions list.
        mainApp % Calling app.
    end
    
    methods (Access = private)
        function createFuncList(app)
            % This function populates the "AvailablefunctionsListBox" with
            % the functions listed in "pipeObj".
            
            Items = {app.pipeObj.funcList.name};
            ItemsData = app.pipeObj.funcList;
            list_of_folders = cellfun(@(x) erase(x, app.pipeObj.fcnDir), {app.pipeObj.funcList.folder}, 'UniformOutput',false);
            if app.b_isStandAlone
                idx = contains(list_of_folders, 'DataImport') | contains(list_of_folders, 'DataExport'); % Remove all DataImport or DataExport options for the StandAlone version. The GUI has a separate button for that!
                list_of_folders(idx) = [];
                Items(idx) = [];
                ItemsData(idx) = [];
            end
            idx = arrayfun(@(x) ~isempty(x.info.opts), ItemsData);
            Items(idx) = cellfun(@(x) ['(!) ' x ], Items(idx), 'UniformOutput',false);
            [val,ind] = unique(list_of_folders);
            new_Items = {};
            new_ItemsData = [];
            for i = 1:numel(ind)
                header =['---------- ' val{i} ' ----------'];
                dummyData = ItemsData(1); dummyData.name = '----------';
                if i < numel(ind)
                    sub_Items = Items(ind(i):ind(i+1)-1);
                    sub_ItemsData = ItemsData(ind(i):ind(i+1)-1);
                else
                    sub_Items = Items(ind(i):end);
                    sub_ItemsData = ItemsData(ind(i):end);
                end
                new_Items = [new_Items, header, sub_Items];
                new_ItemsData = [new_ItemsData; dummyData; sub_ItemsData];
            end
            app.AvailablefunctionsListBox.Items = new_Items;
            app.AvailablefunctionsListBox.ItemsData = new_ItemsData;
            app.MypipelineListBox.Items = {};
            app.MypipelineListBox.ItemsData = [];
            
        end
        
        function b_ok = createPipe(app)
            % This function uses the content of the
            % SelectedfunctionsListBox to add each function to the
            % pipeObj's pipeline.
            % Output:
            %   b_ok (bool): If TRUE, all steps were added to the pipeline.
            %   If FALSE, an error occured or User decided to abort.
            % Erase existing pipeline:
            b_ok = false;
            if ~isempty(app.pipeObj)
                app.pipeObj.reset_pipe;
            end
            disp('Creating pipeline...')
            % Create pipeline
            ItemsData = app.MypipelineListBox.ItemsData;
            ItemsData(1).seq = 0;
            ItemsData(1).seqIndx = 0;
            curr_seq = 1;
            for ii = 1:numel(ItemsData)
                % Check for exising steps:
                currPipe = app.pipeObj.pipe(arrayfun(@(x) any(x.seq == curr_seq), app.pipeObj.pipe));
                if any(strcmpi(ItemsData(ii).name, {currPipe.name}))
                    % If there is an existing function, force the creation
                    % of a new branch:
                    state = app.pipeObj.addTask(ItemsData(ii).name, ItemsData(ii).b_save, ItemsData(ii).saveFileName, 'inputFrom','_LOCAL_');
                else
                    % Add item to new pipeline sequence:
                    state = app.pipeObj.addTask(ItemsData(ii).name, ItemsData(ii).b_save, ItemsData(ii).saveFileName);
                end
                % Update optional parameters:
                app.pipeObj.pipe(end).opts = ItemsData(ii).info.opts;
                % Update ItemsData:                
                app.MypipelineListBox.ItemsData(ii).seq = app.pipeObj.pipe(end).seq;
                app.MypipelineListBox.ItemsData(ii).seqIndx = app.pipeObj.pipe(end).seqIndx;                                
                % If failed to add a step to the pipeline, reset and
                % return.
                if ~state
                    app.pipeObj.reset_pipe;
                    return
                end
            end            
            b_ok = true;
        end
        
        function fillPipeListBox(app)
            % This function uses the content of app.pipeObj.pipe to
            % display the pipeline steps in the SelectedfunctionsListBox as
            % well as to update the items with custom options such as
            % Save2File, custom opts settings.
            
            for ii = 1:length(app.pipeObj.pipe)
%                 idxList = strcmp(app.pipeObj.pipe(ii).name, {app.AvailablefunctionsListBox.ItemsData.name});
                idxPipe = strcmp(app.pipeObj.pipe(ii).name, {app.pipeObj.funcList.name});                              
                % Add items to "Selected" list:
                app.MypipelineListBox.Items{ii} = app.pipeObj.pipe(ii).name;                
                if ~isempty(app.pipeObj.pipe(ii).opts)
                    % Append Params str:
                    app.MypipelineListBox.Items{ii} = ['(Params set!) ' app.MypipelineListBox.Items{ii}];
                end
                if app.pipeObj.pipe(ii).b_save2File
                    % Add filename when step will be saved:
                    app.MypipelineListBox.Items{ii} = [app.MypipelineListBox.Items{ii} ' (save as: ' app.pipeObj.pipe(ii).saveFileName ')'];
                end
                % Add meta Data:
                newItemData = app.pipeObj.funcList(idxPipe);                
                newItemData.info.opts = app.pipeObj.pipe(ii).opts; 
                newItemData.b_save = app.pipeObj.pipe(ii).b_save2File;
                newItemData.saveFileName= app.pipeObj.pipe(ii).saveFileName;
                newItemData.seq = app.pipeObj.pipe(ii).seq;
                newItemData.seqIndx = app.pipeObj.pipe(ii).seqIndx;
                newItemData.ItemIndx = ii;
                app.MypipelineListBox.ItemsData = [app.MypipelineListBox.ItemsData newItemData];                                
            end
            app.toggleListFont(false);            
        end
        
        
        function toggleListFont(app, b_highlight)
            % IF b_highlight is TRUE, this function highlights the text of
            % the "Selected functions" list, otherwise, doesnt'
            if b_highlight
                set(app.MypipelineListBox, 'FontColor', [1 0 0], 'FontWeight','bold')
            else
                set(app.MypipelineListBox, 'FontColor', [0 0 0], 'FontWeight','normal')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, pipeObj, b_isStandAlone)
            
            % Update UIFigure position:
            app.UIFigure.Visible ='off';
            movegui(app.UIFigure, 'center');
            app.mainApp = mainApp;
            app.pipeObj = pipeObj;
            app.b_isStandAlone = b_isStandAlone;
            app.createFuncList;
            if ~isempty(app.pipeObj.pipe)
                app.fillPipeListBox;
            end
            % ToolTips of buttons
            app.ButtonUp.Tooltip = 'Moves function up.';
            app.ButtonDwn.Tooltip = 'Moves function down.';
            %%
            app.UIFigure.Visible ='on';
            app.avFcnTooltip = app.AvailablefunctionsListBox.Tooltip;
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %             app.CallingApp.ConfigureNewPipelineButton.Enable = 'on';            
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Adds the selected function from the "Available" to the
            % "Selected list.
            ItemIndx = 0;
            FuncSel = app.AvailablefunctionsListBox.Value;
            if isempty(FuncSel) || strcmp(FuncSel.name, '----------')
                return
            end            
            idx = strcmp({app.AvailablefunctionsListBox.ItemsData.name}, FuncSel.name);
            idx_pipe = strcmp(app.AvailablefunctionsListBox.ItemsData(idx).name, {app.pipeObj.funcList.name});
            newItem = app.AvailablefunctionsListBox.Items(idx); newItem = newItem{:};
            % Reset Item's data of selected functions list directly from pipeObj:
            newItemData = app.pipeObj.funcList(idx_pipe);
            if startsWith(strip(newItem),'(!)')
                % Add a string to point users to set parameters:               
                newItem = ['(PARAMS NOT SET!) ' newItemData.name];
                % Change background color to "Red" to bring attention of
                % user:
                app.toggleListFont(true);
            end
            
            % Add fields to save file:
            newItemData.b_save = false;
            newItemData.saveFileName = '';  
            newItemData.ItemIndx = numel(app.MypipelineListBox.Items) + 1;
            newItemData.seq = 0; newItemData.seqIndx = 0; % These params will be updated in "createPipe" function.
            % Control for already added items:            
            b_addItem = ( isempty(app.MypipelineListBox.Items) || ~ismember(newItemData.name, {app.MypipelineListBox.ItemsData.name}) );
            if ~b_addItem                 
                if app.b_isStandAlone
                   % Allow creation of branches for umIToolbox only.                   
                   return 
                end
                if ~isempty(newItemData.info.outFileName)
                    % Ask User what to do with duplicate functions:
                    answer = uiconfirm(app.UIFigure, sprintf('The function "%s" already exists in the pipeline. A new sequence will be created. Proceed?',newItemData.name),...
                        'Duplicates found', 'Options',{'Yes','No'}, 'DefaultOption',2);
                    if strcmpi(answer,'yes')                        
                        b_addItem = true;
                    end
                end
            end
            if b_addItem
                app.MypipelineListBox.Items = [app.MypipelineListBox.Items newItem];
                app.MypipelineListBox.ItemsData = [app.MypipelineListBox.ItemsData newItemData];
            end
        end

        % Button pushed function: ButtonDwn, ButtonUp
        function ButtonUpDownPushed(app, event)
            ItemData_selected = app.MypipelineListBox.Value;
            if isempty(ItemData_selected) || ( ItemData_selected.ItemIndx == 1 && contains(event.Source.Icon, 'up', 'IgnoreCase',true) ) ||...
                    ( ItemData_selected.ItemIndx == max([app.MypipelineListBox.ItemsData.ItemIndx],[],'all') && contains(event.Source.Icon, 'dwn', 'IgnoreCase',true) )
                return
            end
            Items = app.MypipelineListBox.Items;
            ItemsData = app.MypipelineListBox.ItemsData;
            pos = ItemData_selected.ItemIndx;
            range = 1:numel(Items);
            
            if contains(event.Source.Icon, 'dwn','IgnoreCase',true)
                range(pos) = pos + 1;
                range(pos + 1) = pos;
            else
                range(pos) = pos -1 ;
                range(pos -1) = pos;
            end
            newPos = find(range == pos);
            app.MypipelineListBox.Items = Items(range);
            app.MypipelineListBox.ItemsData = ItemsData(range);            
            % Update indices:
            for ii = 1:length(ItemsData)
                app.MypipelineListBox.ItemsData(ii).ItemIndx = ii;
            end
            app.MypipelineListBox.Value = app.MypipelineListBox.ItemsData(newPos);
            
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            
            ItemData_selected = app.MypipelineListBox.Value;
            if ~isempty(ItemData_selected) && ~isempty(ItemData_selected.info.opts)
                % Hide app:
                app.UIFigure.Visible = 'off';
                app.pipeObj.setOpts(ItemData_selected.name,ItemData_selected.seq);
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.MypipelineListBox.ItemsData);
                [~,idx_pipe] = ismember(ItemData_selected.name, {app.pipeObj.pipe.name});
                app.MypipelineListBox.Items{idx} = strrep(app.MypipelineListBox.Items{idx}, 'PARAMS NOT SET', 'Params Set');
%                 app.MypipelineListBox.Items{idx} = ['(Params Set!) ' app.MypipelineListBox.ItemsData(idx).name];
                % Update Items's parameters:
                app.MypipelineListBox.ItemsData(idx).info.opts = app.pipeObj.pipe(idx_pipe).opts;
                % Show app:
                app.UIFigure.Visible = 'on';
            end
            % Reset list background color to "white" when there is no
            % params to set anymore:
            if all(~startsWith(app.MypipelineListBox.Items, '(PARAMS NOT SET!)'))
               app.toggleListFont(false)
            end
        end

        % Value changed function: MypipelineListBox
        function MypipelineListBoxValueChanged(app, event)
            % This callback changes the name of the "Save Step button"
            % depending on the save/not-save state of the selected item of
            % the list.
            selection = app.MypipelineListBox.Value;
            Color = {[0.07,0.91,1], [0.97,0.44,0.35]};
            
            app.SetParamsButton.Enable = 'off';
            app.SavestepButton.Enable = 'off';
            % Control for functions that do not have outputs:
            if app.ConfirmButton.Value
                if  isempty(selection.info.opts)
                    app.SetParamsButton.Enable = 'off';
                else
                    app.SetParamsButton.Enable = 'on';
                end               
                
                if ~any(ismember({'outData', 'outDataStat'}, selection.info.argsOut))
                    app.SavestepButton.Enable = 'off';
                    app.SavestepButton.Tooltip = 'This step does not output any data.';
                else
                    app.SavestepButton.Enable ='on';
                    app.SavestepButton.Tooltip = '';
                end
            end
            
            % Change Button appearence if the step is set to be saved or not:
            if  ~isfield(selection, 'b_save') || isempty(selection.b_save) || ( islogical(selection.b_save) && ~selection.b_save )
                app.SavestepButton.Text = {'Save' ,'Step'};
                app.SavestepButton.BackgroundColor = Color{1};
                
            else
                app.SavestepButton.Text = {'Undo' ,'Save'};
                app.SavestepButton.BackgroundColor = Color{2};
            end
        end

        % Button pushed function: SavestepButton
        function SavestepButtonPushed(app, event)
            % This callback opens a dialog file to save a file from a
            % step in the pipeline.
            selection = app.MypipelineListBox.Value;
            if isempty(selection)
                return
            end
            idx = arrayfun(@(x) isequal(selection,x), app.MypipelineListBox.ItemsData);
            Color = {[0.07,0.91,1], [0.97,0.44,0.35]};
            if ~isfield(selection, 'b_save') || isempty(selection.b_save) || ( islogical(selection.b_save) && ~selection.b_save )
                selection = addsaveFileName(selection);
                if ~selection.b_save
                    return
                end
                app.MypipelineListBox.ItemsData(idx).b_save = selection.b_save;
                app.MypipelineListBox.ItemsData(idx).saveFileName = selection.saveFileName;
                app.SavestepButton.Text = {'Undo', 'Save'};
                app.SavestepButton.BackgroundColor = Color{2};
                app.MypipelineListBox.Items{idx} =  [app.MypipelineListBox.Items{idx}, '(save as: ', selection.saveFileName ')'];
            else
                app.MypipelineListBox.ItemsData(idx).b_save = false;
                app.MypipelineListBox.ItemsData(idx).saveFileName = '';
                app.SavestepButton.Text = {'Save' ,'Step'};
                app.SavestepButton.BackgroundColor = Color{1};
                idx_str = strfind(app.MypipelineListBox.Items{idx}, '(save as: ');
                if idx_str
                    app.MypipelineListBox.Items{idx} = app.MypipelineListBox.Items{idx}(1:idx_str-1);
                end
            end
            app.MypipelineListBox.Value = app.MypipelineListBox.ItemsData(idx);
            % Update save info in pipeline:            
            idx_pipe = strcmpi(app.MypipelineListBox.ItemsData(idx).name, {app.pipeObj.pipe.name}) & [app.pipeObj.pipe.seq] == app.MypipelineListBox.ItemsData(idx).seq;
            app.pipeObj.pipe(idx_pipe).b_save2File = app.MypipelineListBox.ItemsData(idx).b_save;
            app.pipeObj.pipe(idx_pipe).saveFileName= app.MypipelineListBox.ItemsData(idx).saveFileName;
        
            % Local function
            function item = addsaveFileName(item)
                % This functions opens a dialog box so the user types in
                % the filename of the .dat file to be saved.
                item.b_save = false;
                if ~isfield(item, 'saveFileName') || isempty(item.saveFileName)
                    [~,defName,~] = fileparts(item.info.outFileName);
                else
                    defName = item.saveFileName;
                end
                opts.Resize = 'on';
                datName = inputdlg('File name:', 'New .DAT file name', [1 50], {defName}, opts);
                if isempty(datName)
                    disp('Operation Cancelled by User')
                    return
                end
                if ~endsWith(datName{1},'.dat')
                    datName{1} = [datName{1}, '.dat'];
                end
                item.saveFileName = datName{1};
                item.b_save = true;
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            ItemData_selected = app.MypipelineListBox.Value;
            if~isempty(ItemData_selected)
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.MypipelineListBox.ItemsData);
                app.MypipelineListBox.Items(idx)=[];
                app.MypipelineListBox.ItemsData(idx)=[];
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Launches the execution of the pipeline.
            if isempty(app.MypipelineListBox.Items)
                uialert(app.UIFigure, 'Create a pipeline first!', 'Failed to save config', 'Icon',"warning");
                return
            end
            % If the user did not verified all functions' parameters, raise
            % a warning:
            idx = contains(app.MypipelineListBox.Items, 'NOT SET', 'IgnoreCase',true);
            if any(idx)
                names = arrayfun(@(x) ['"' x.name '"'], app.MypipelineListBox.ItemsData(idx), 'UniformOutput',false);
                answer = uiconfirm(app.UIFigure, [{'The parameters of the following functions were not set:'}, names, {'Proceed?'}],...
                    'Warning!',"Icon","warning","CloseFcn",'uiresume(gcbf)', 'Options',{'Yes','No'}, 'DefaultOption',1);
                if strcmpi(answer, 'no')
                    return
                end
            end
            % Execute pipeline through mainApp:
            app.mainApp.runPipeline(app.pipeObj, app);            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % This callback saves the current pipeline to a .JSON file by
            % calling a method from pipeObj.
            if isempty(app.pipeObj.pipe)
                return
            end
            % If the user did not verified all functions' parameters, raise
            % a warning:
            idx = contains(app.MypipelineListBox.Items, 'NOT SET', 'IgnoreCase',true);
            if any(idx)
                names = arrayfun(@(x) ['"' x.name '"'], app.MypipelineListBox.ItemsData(idx), 'UniformOutput',false);
                answer = uiconfirm(app.UIFigure, [{'The parameters of the following functions were not set:'}, names, {'Proceed?'}],...
                    'Warning!',"Icon","warning","CloseFcn",'uiresume(gcbf)', 'Options',{'Yes','No'}, 'DefaultOption',1);
                if strcmpi(answer, 'no')
                    return
                end
            end
            if app.b_isStandAlone
               app.pipeObj.savePipe;               
            else
                answer = inputdlg('Name of Pipeline:', 'Save Pipeline Config File', [1 40]);
                if isempty(answer)
                    disp('Operation Cancelled by User')
                    % empty pipeline in pipeObj:
                    app.pipeObj.pipe = struct();
                    return
                end
                answer = answer{:};
                app.pipeObj.savePipe(answer)
                msgbox(['Pipeline saved as ' answer ' in ' fullfile(app.pipeObj.ProtocolObj.SaveDir, 'PipelineConfigFiles')]);
            end
            figure(app.UIFigure);
        end

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            % This callback loads a saved pipeline from a .JSON file using
            % the "loadPipe" method from pipeObj.
            
            if app.b_isStandAlone
                [file,Path] = uigetfile('*.json', 'Load Pipeline Config file', 'pipeConfigFile');
            else
                [file,Path] = uigetfile(fullfile(app.pipeObj.ProtocolObj.SaveDir,'*.json'),...
                    'Load Pipeline Config file', 'pipeConfigFile');
            end
            
            if file == 0
                disp('Operation Cancelled by User')
                figure(app.UIFigure);
                return
            end
            % Reset function List:
            app.createFuncList;
            % Load Pipeline from file:
            app.pipeObj.loadPipe(fullfile(Path,file))
            % Update ListBox:
            app.fillPipeListBox;
            if ~isempty(app.MypipelineListBox.Items)
                % Update Params in MyPipelineListBox:
                for ii = 1:length(app.pipeObj.pipe)
                    idx = strcmpi(app.pipeObj.pipe(ii).name, {app.MypipelineListBox.ItemsData.name});
                    app.MypipelineListBox.ItemsData(idx).info.opts = app.pipeObj.pipe(ii).opts;
                end
                % Toggle "Apply/Reset" button:
                app.ConfirmButton.Value = true;
                app.ConfirmButtonValueChanged;
            end
            figure(app.UIFigure);
        end

        % Value changed function: AvailablefunctionsListBox
        function AvailablefunctionsListBoxValueChanged(app, event)
            % Shows the documentation when User clicks on a function.
            if ~app.b_OpenDoc
                return
            end
            % Get the name of the function:
            selection = app.AvailablefunctionsListBox.Value;
            if strcmpi(selection.name, '----------')
                return
            end
            if isdeployed
                % In .exe, point directly to the wiki. !Access to local documentation not tested yet! 
                web(['https://labeotech.github.io/Umit/documentation/userDocs/' selection.name '.html'],'-browser');
            else
                % Search html file inside "doc" folder:
                htmlFile = which([selection.name '.html']);
                
                if isempty(htmlFile)
                    if contains(selection.folder, 'custom', 'IgnoreCase',true)
                        msg = 'Custom functions not supported!';
                    else
                        msg = ['Documentation not available for function "' selection.name '". Please contact developers for more info.'];
                    end
                    uialert(app.UIFigure, msg,'Help failed',"CloseFcn",'uiresume(gcbf)');
                    uiwait(app.UIFigure);
                    return
                end
                % Display html file in web browser:
                web(htmlFile)
            end
            % Disable "Help mode"
            app.HelpButton.Value = false;
            app.HelpButtonValueChanged;
            
        end

        % Value changed function: HelpButton
        function HelpButtonValueChanged(app, event)
            % If TRUE, when the user clicks on a function from the
            % "Available" functions list, the documentation pops up.            
            idx = arrayfun(@(x) isprop(x, 'Enable'), app.UIFigure.Children);            
            UIelements = setdiff(app.UIFigure.Children(idx),app.HelpButton);
            % Get current state of UIelements:
            currState = arrayfun(@(x) strcmpi(x.Enable,'on'), UIelements);
            boolMap = containers.Map([true,false],{'on','off'});
            app.b_OpenDoc = app.HelpButton.Value;
            arrayfun(@(x,y) set(x, 'Enable',boolMap(y)), UIelements, currState);
            % Update List tooltip:
            if app.HelpButton.Value
                app.AvailablefunctionsListBox.Tooltip = 'Click on a function to see documentation!';
            else
                app.AvailablefunctionsListBox.Tooltip = app.avFcnTooltip;
            end
        end

        % Value changed function: ConfirmButton
        function ConfirmButtonValueChanged(app, event)
            
            if isempty(app.MypipelineListBox.Items)
                app.ConfirmButton.Value  = event.PreviousValue;
                return
            end
            value = app.ConfirmButton.Value;
            boolMap = containers.Map([true,false], {'on','off'});
            if value
                b_OK = true;
                %                 if isempty(app.pipeObj.pipe)
                % Create pipeline from selection:
                b_OK = app.createPipe;
                %                 end
                if ~b_OK
                    app.ConfirmButton.Value = event.PreviousValue;
                    figure(app.UIFigure);
                    return
                end
                % Change button icon and color:
                set(app.ConfirmButton, 'Text', '', 'Icon', 'icon_unlock.png', 'ToolTip', 'Unlock pipeline');    
                app.MypipelineListBox.Value = {};
            else                               
                % Update Params in MyPipeline List box from PipelineManager
                % object:
                for ii = 1:length(app.pipeObj.pipe)
                    idx = strcmpi(app.pipeObj.pipe(ii).name, {app.MypipelineListBox.ItemsData.name}) & [app.MypipelineListBox.ItemsData.seq] == app.pipeObj.pipe(ii).seq;
                    app.MypipelineListBox.ItemsData(idx).info.opts = app.pipeObj.pipe(ii).opts;
                end
                % Erase pipeline 
                app.pipeObj.reset_pipe;
                % Change button icon and color:
                set(app.ConfirmButton, 'Text', '', 'Icon','icon_checkBW.png','ToolTip', 'Build pipeline');
            end
            % Enable/Disable GUI elements:
            set(app.AddButton, 'Enable', boolMap(~value))
            set(app.RemoveButton, 'Enable', boolMap(~value))
            set(app.ButtonDwn, 'Enable',boolMap(~value))
            set(app.ButtonUp,'Enable',boolMap(~value));
            set(app.SavestepButton, 'Enable',boolMap(value))
            set(app.SetParamsButton, 'Enable',boolMap(value))
            set(app.openButton, 'Enable',boolMap(~value))
            set(app.saveButton, 'Enable', boolMap(value))
            set(app.RunButton, 'Enable', boolMap(value))
            set(app.ResetButton, 'Enable',boolMap(~value))
            set(app.HelpButton, 'Enable', boolMap(~value))
%             set(app.MypipelineListBox, 'Enable', boolMap(~value), 'Value',{})
            set(app.AvailablefunctionsListBox, 'Enable', boolMap(~value), 'Value',{})
            % Focus on app:
            figure(app.UIFigure);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clears the pipeline and resets all parameters to default.
            if isempty(app.pipeObj) || ~isvalid(app.pipeObj)
                return
            end
            app.pipeObj.reset_pipe;
            app.MypipelineListBox.Items = {};
            app.MypipelineListBox.ItemsData = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pipeline Configuration tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AvailablefunctionsListBoxLabel = uilabel(app.UIFigure);
            app.AvailablefunctionsListBoxLabel.Text = 'Available functions:';
            app.AvailablefunctionsListBox = uilistbox(app.UIFigure);
            app.AvailablefunctionsListBox.Items = {};
            app.AvailablefunctionsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailablefunctionsListBoxValueChanged, true);
            app.AvailablefunctionsListBox.Tooltip = {'Items with (!) have optional parameters.'; 'Click in "Set Params" to change them.'};
            app.AvailablefunctionsListBox.Value = {};
            app.MypipelineLabel = uilabel(app.UIFigure);
            app.MypipelineLabel.Text = 'My pipeline:';
            app.MypipelineListBox = uilistbox(app.UIFigure);
            app.MypipelineListBox.Items = {};
            app.MypipelineListBox.ValueChangedFcn = createCallbackFcn(app, @MypipelineListBoxValueChanged, true);
            app.MypipelineListBox.Value = {};
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpDownPushed, true);
            app.ButtonUp.Icon = 'icon_arrow_up.png';
            app.ButtonUp.Tooltip = {'Move function UP'};
            app.ButtonUp.Text = '';
            app.ButtonDwn = uibutton(app.UIFigure, 'push');
            app.ButtonDwn.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpDownPushed, true);
            app.ButtonDwn.Icon = 'icon_arrow_dwn.png';
            app.ButtonDwn.Tooltip = {'Move function DOWN'};
            app.ButtonDwn.Text = '';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Execute current pipeline'};
            app.RunButton.Text = 'Run!';
            app.SetParamsButton = uibutton(app.UIFigure, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Enable = 'off';
            app.SetParamsButton.Tooltip = {'Set parameters of selected function'};
            app.SetParamsButton.Text = {'Set'; ' Params'};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = 'icon_plusBW.png';
            app.AddButton.Tooltip = {'Add function to pipeline'};
            app.AddButton.Text = '';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Icon = 'icon_minusBW.png';
            app.RemoveButton.Tooltip = {'Remove function from pipeline'};
            app.RemoveButton.Text = '';
            app.SavestepButton = uibutton(app.UIFigure, 'push');
            app.SavestepButton.ButtonPushedFcn = createCallbackFcn(app, @SavestepButtonPushed, true);
            app.SavestepButton.Enable = 'off';
            app.SavestepButton.Tooltip = {'Save ouptut of selected function to a file'};
            app.SavestepButton.Text = {'Save '; 'step'};
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'icon_saveBW.png';
            app.saveButton.Enable = 'off';
            app.saveButton.Tooltip = {'Save pipeline to file'};
            app.saveButton.Text = 'save';
            app.openButton = uibutton(app.UIFigure, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Icon = 'icon_openFolderBW.png';
            app.openButton.Tooltip = {'Load pipeline from file'};
            app.openButton.Text = 'open';
            app.HelpButton = uibutton(app.UIFigure, 'state');
            app.HelpButton.ValueChangedFcn = createCallbackFcn(app, @HelpButtonValueChanged, true);
            app.HelpButton.Tooltip = {'Click here and select a function from "Available" list fo see the documentation.'};
            app.HelpButton.Text = '?';
            app.ConfirmButton = uibutton(app.UIFigure, 'state');
            app.ConfirmButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.ConfirmButton.Tooltip = {'Build pipeline'};
            app.ConfirmButton.Icon = 'icon_checkBW.png';
            app.ConfirmButton.Text = '';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'icon_deleteBW.png';
            app.ResetButton.Tooltip = {'Erase current pipeline.'};
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineConfig_dlgBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end