classdef PipelineManagerTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        DiagnosticsPanel              matlab.ui.container.Panel
        GridDiagnostics               matlab.ui.container.GridLayout
        StatusLabel                   matlab.ui.control.Label
        DiagnosticsTextArea           matlab.ui.control.TextArea
        ExecutionOrderPanel           matlab.ui.container.Panel
        GridExecutionOrder            matlab.ui.container.GridLayout
        ExecGraphHTML                 matlab.ui.control.HTML
        SelectedstepdetailsPanel      matlab.ui.container.Panel
        GridSelectedStepDetails       matlab.ui.container.GridLayout
        FunctionNameField             matlab.ui.control.EditField
        ParametersTextArea            matlab.ui.control.TextArea
        ParametersLabel               matlab.ui.control.Label
        StepSummaryTextArea           matlab.ui.control.TextArea
        StepSummaryLabel              matlab.ui.control.Label
        StepTagField                  matlab.ui.control.EditField
        StepTagLabel                  matlab.ui.control.Label
        StepNameLabel                 matlab.ui.control.Label
        PipelineGraphPanel            matlab.ui.container.Panel
        GridPipelineGraph             matlab.ui.container.GridLayout
        ClearPipelineButton           matlab.ui.control.Button
        GraphHTML                     matlab.ui.control.HTML
        AvailableFunctionsPanel       matlab.ui.container.Panel
        GridAvailableFunctions        matlab.ui.container.GridLayout
        AddasNewBranchButton          matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        FunctionTree                  matlab.ui.container.Tree
        SearchFunctionEditField       matlab.ui.control.EditField
        GridLayoutTop                 matlab.ui.container.GridLayout
        MoreButton                    matlab.ui.control.StateButton
        Sep2                          matlab.ui.container.Panel
        Sep1                          matlab.ui.container.Panel
        SafetyLabel                   matlab.ui.control.Label
        RAMLabel                      matlab.ui.control.Label
        AutoSaveFinalOutputsLabel     matlab.ui.control.Label
        RAMSafetyPolicyDropDown       matlab.ui.control.DropDown
        RAMModeDropDown               matlab.ui.control.DropDown
        AutoSaveFinalOutputsDropDown  matlab.ui.control.DropDown
        RunButton                     matlab.ui.control.Button
        SelectDataFoldersButton       matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        pm = []
        graphHtmlFile char = ''
        execHtmlFile char = ''
        selectedNodeID double = NaN
        selectedStepTag char = ''
        selectedFunctionName char = ''
        bGraphHtmlReady logical = false
        bExecHtmlReady logical = false

        bReconnectMode logical = false
        reconnectTargetNodeID double = NaN
        reconnectTargetStepTag char = ''
        reconnectTargetInputName char = ''
        reconnectCandidateNodeIDs double = []
        MoreActionsFigure = []
        FolderPairTable table = table()
        ExecutionPlanFigure = []
    end

    methods (Access = private)

        function initializeUiState(app)
            %INITIALIZEUISTATE Reset passive UI content and local state.

            app.UIFigure.Name = 'Pipeline Manager Tool';

            app.pm = [];

            app.selectedNodeID = NaN;
            app.selectedStepTag = '';
            app.selectedFunctionName = '';
            app.bGraphHtmlReady = false;
            app.bExecHtmlReady = false;

            app.bReconnectMode = false;
            app.reconnectTargetNodeID = NaN;
            app.reconnectTargetStepTag = '';
            app.reconnectTargetInputName = '';
            app.reconnectCandidateNodeIDs = [];

            app.FolderPairTable = app.makeEmptyFolderPairTable();

            app.StepTagField.Value = '';
            app.FunctionNameField.Value = '';
            app.StepSummaryTextArea.Value = {'<no step selected>'};
            app.ParametersTextArea.Value = {'<no step selected>'};
            app.DiagnosticsTextArea.Value = {''};

            app.AddButton.Enable = 'off';
            app.AddasNewBranchButton.Enable = 'off';
            app.StepNameLabel.Text = 'Function';

            app.configureExecutionControlDropDowns();
            app.closeMoreActionsWindow();
            app.closeExecutionPlanWindow();

            try
                app.ClearPipelineButton.Tooltip = ...
                    'Remove all steps from the current pipeline while keeping the selected data folders and execution settings.';
            catch
            end

            app.setPipelineControlsAvailable(false);
            app.updateFolderSelectionButtonSummary();


            app.setStatus('Select data folders to start.');

            try
                delete(app.FunctionTree.Children);
            catch
            end
        end

        function ensureHtmlFilesExist(app)
            %ENSUREHTMLFILESEXIST Resolve the HTML files used by the uihtml controls.

            appFolder = fileparts(mfilename('fullpath'));

            graphTemplate = fullfile(appFolder, 'pipeline_manager_graph.html');
            execTemplate  = fullfile(appFolder, 'pipeline_manager_execution.html');

            if ~isfile(graphTemplate)
                error('PipelineManagerTool:ensureHtmlFilesExist:MissingGraphHtml', ...
                    ['The graph HTML file was not found. Expected file:\n%s\n\n' ...
                    'Place pipeline_manager_graph.html in the same folder as this app.'], ...
                    graphTemplate);
            end

            if ~isfile(execTemplate)
                error('PipelineManagerTool:ensureHtmlFilesExist:MissingExecutionHtml', ...
                    ['The execution-order HTML file was not found. Expected file:\n%s\n\n' ...
                    'Place pipeline_manager_execution.html in the same folder as this app.'], ...
                    execTemplate);
            end

            app.graphHtmlFile = graphTemplate;
            app.execHtmlFile = execTemplate;

            app.GraphHTML.HTMLSource = app.graphHtmlFile;
            app.ExecGraphHTML.HTMLSource = app.execHtmlFile;
        end

        function populateFunctionTree(app)
            %POPULATEFUNCTIONTREE Populate the available-function tree.

            app.AddButton.Enable = 'off';
            app.selectedFunctionName = '';

            try
                delete(app.FunctionTree.Children);
            catch
            end

            if isempty(app.pm) || ~isprop(app.pm, 'funcList') || isempty(app.pm.funcList)
                placeholderNode = uitreenode(app.FunctionTree);
                placeholderNode.Text = 'Select data folders first';
                placeholderNode.NodeData = struct('kind', 'placeholder');
                return
            end

            funcs = app.pm.funcList;

            filterText = strtrim(char(string(app.SearchFunctionEditField.Value)));
            if ~isempty(filterText)
                keepMask = false(numel(funcs), 1);
                filterLower = lower(filterText);

                for iFunc = 1:numel(funcs)
                    fName = lower(char(string(funcs(iFunc).name)));
                    fFolder = '';
                    if isfield(funcs(iFunc), 'folder') && ~isempty(funcs(iFunc).folder)
                        fFolder = lower(char(string(funcs(iFunc).folder)));
                    end
                    keepMask(iFunc) = contains(fName, filterLower) || contains(fFolder, filterLower);
                end

                funcs = funcs(keepMask);
            end

            if isempty(funcs)
                placeholderNode = uitreenode(app.FunctionTree);
                placeholderNode.Text = 'No matching functions';
                placeholderNode.NodeData = struct('kind', 'placeholder');
                return
            end

            folderList = strings(numel(funcs), 1);
            for iFunc = 1:numel(funcs)
                if isfield(funcs(iFunc), 'folder') && ~isempty(funcs(iFunc).folder)
                    folderList(iFunc) = string(funcs(iFunc).folder);
                else
                    folderList(iFunc) = "Uncategorized";
                end
            end

            % Inline common-folder-prefix logic. This avoids keeping three small
            % path-format helpers that are only used for this tree display.
            commonRoot = '';
            if ~isempty(folderList)
                partsCell = cell(numel(folderList), 1);
                minLen = inf;

                for i = 1:numel(folderList)
                    parts = regexp(char(folderList(i)), ['[\\' filesep '/]+'], 'split');
                    parts = parts(~cellfun(@isempty, parts));
                    partsCell{i} = parts;
                    minLen = min(minLen, numel(parts));
                end

                commonParts = {};
                for iPart = 1:minLen
                    token = partsCell{1}{iPart};
                    isShared = true;

                    for iList = 2:numel(partsCell)
                        if ~strcmpi(partsCell{iList}{iPart}, token)
                            isShared = false;
                            break
                        end
                    end

                    if ~isShared
                        break
                    end

                    commonParts{end+1} = token; %#ok<AGROW>
                end

                if ~isempty(commonParts)
                    commonRoot = strjoin(commonParts, filesep);
                end
            end

            nodeMap = containers.Map('KeyType', 'char', 'ValueType', 'any');

            for iFunc = 1:numel(funcs)
                folderStr = char(folderList(iFunc));
                relFolder = folderStr;

                if ~isempty(commonRoot)
                    prefixWithSep = [commonRoot filesep];

                    if startsWith(folderStr, prefixWithSep)
                        relFolder = extractAfter(string(folderStr), strlength(prefixWithSep));
                        relFolder = char(relFolder);
                    elseif strcmpi(folderStr, commonRoot)
                        relFolder = '';
                    end
                end

                if isempty(relFolder)
                    folderParts = {};
                else
                    folderParts = regexp(relFolder, ['[\\' filesep '/]+'], 'split');
                    folderParts = folderParts(~cellfun(@isempty, folderParts));
                end

                if isempty(folderParts)
                    folderParts = {'Uncategorized'};
                end

                parentHandle = app.FunctionTree;
                currentKey = '';

                for iPart = 1:numel(folderParts)
                    thisPart = folderParts{iPart};

                    if isempty(currentKey)
                        currentKey = thisPart;
                    else
                        currentKey = [currentKey '|' thisPart]; %#ok<AGROW>
                    end

                    if ~isKey(nodeMap, currentKey)
                        catNode = uitreenode(parentHandle);
                        catNode.Text = thisPart;
                        catNode.NodeData = struct('kind', 'category', 'pathKey', currentKey);
                        nodeMap(currentKey) = catNode;
                    end

                    parentHandle = nodeMap(currentKey);
                end

                funcNode = uitreenode(parentHandle);
                funcNode.Text = char(string(funcs(iFunc).name));
                funcNode.NodeData = struct( ...
                    'kind', 'function', ...
                    'name', char(string(funcs(iFunc).name)), ...
                    'folder', folderStr);
            end

            rootNodes = app.FunctionTree.Children;
            for iRoot = 1:numel(rootNodes)
                try
                    expand(rootNodes(iRoot));
                catch
                end
            end
        end

        function refreshAllViews(app)
            %REFRESHALLVIEWS Refresh graph, execution view, and selected-step details.

            app.refreshGraphView();
            app.refreshExecutionView();
            app.refreshSelectedStepPanel();
        end

        function refreshGraphView(app)
            if ~app.bGraphHtmlReady
                return
            end
            payload = app.buildGraphPayload();
            payload.command = 'refresh';
            app.GraphHTML.Data = payload;
        end

        function refreshExecutionView(app)
            if ~app.bExecHtmlReady
                return
            end
            payload = app.buildExecutionPayload();
            app.ExecGraphHTML.Data = payload;
        end

        function payload = buildGraphPayload(app)
            %BUILDGRAPHPAYLOAD Build the payload rendered by the graph HTML view.

            payload = struct();
            payload.nodes = struct('id', {}, 'stepTag', {}, 'funcName', {}, 'kind', {}, ...
                'x', {}, 'y', {}, 'width', {}, 'height', {}, 'topoIndex', {}, ...
                'execIndex', {}, 'hasSavedOutputs', {}, 'hasWarning', {}, 'hasError', {}, ...
                'hasInvalidNonblocking', {}, 'hoverText', {}, 'isReconnectTarget', {}, ...
                'isReconnectCandidate', {}, 'isReconnectMuted', {}, ...
                'reconnectChoiceLabels', {}, 'reconnectChoiceRefs', {});
            payload.edges = struct('sourceNodeID', {}, 'targetNodeID', {}, ...
                'sourceLabel', {}, 'targetLabel', {});
            payload.selectedNodeID = app.selectedNodeID;
            payload.reconnectMode = app.bReconnectMode;
            payload.reconnectBanner = '';
            payload.reconnectGhost = struct([]);

            targetInputDef = [];
            targetNodeLocal = [];

            if app.bReconnectMode && ~isempty(app.reconnectTargetStepTag) && ~isempty(app.reconnectTargetInputName)
                payload.reconnectBanner = sprintf('Reconnect input "%s" of "%s": click a highlighted source or Use File....', ...
                    app.reconnectTargetInputName, app.reconnectTargetStepTag);
            end

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            plan = app.getCurrentExecutionPlan();
            if isempty(plan)
                return
            end

            nodesLocal = app.pm.nodes;
            connectionsLocal = app.pm.connections;
            topoOrder = app.getTopoOrderFromPlan(plan);
            if isempty(topoOrder)
                topoOrder = [nodesLocal.id];
            end

            levelMap = app.computeNodeLevels(nodesLocal, connectionsLocal, topoOrder);
            nodeTable = table();
            outputPlan = table();
            nodeStatusTable = table();
            edgeTable = table();

            if isfield(plan, 'nodeTable') && istable(plan.nodeTable)
                nodeTable = plan.nodeTable;
            end
            if isfield(plan, 'outputPlan') && istable(plan.outputPlan)
                outputPlan = plan.outputPlan;
            end
            if isfield(plan, 'edgeTable') && istable(plan.edgeTable)
                edgeTable = plan.edgeTable;
            end
            if isfield(plan, 'validationReport') && isstruct(plan.validationReport) && ...
                    isfield(plan.validationReport, 'nodeStatus') && istable(plan.validationReport.nodeStatus)
                nodeStatusTable = plan.validationReport.nodeStatus;
            end

            if app.bReconnectMode
                tgtIdx = find([nodesLocal.id] == app.reconnectTargetNodeID, 1, 'first');
                if ~isempty(tgtIdx)
                    targetNodeLocal = nodesLocal(tgtIdx);
                    if isfield(targetNodeLocal, 'info') && isfield(targetNodeLocal.info, 'inputs') && ~isempty(targetNodeLocal.info.inputs)
                        dataInputsLocal = targetNodeLocal.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, targetNodeLocal.info.inputs));
                        for iIn = 1:numel(dataInputsLocal)
                            if strcmpi(char(string(dataInputsLocal(iIn).name)), app.reconnectTargetInputName)
                                targetInputDef = dataInputsLocal(iIn);
                                break
                            end
                        end
                    end
                end
            end

            rowCounterMap = containers.Map('KeyType', 'char', 'ValueType', 'double');

            for iTopo = 1:numel(topoOrder)
                nodeID = topoOrder(iTopo);
                nodeIdx = find([nodesLocal.id] == nodeID, 1, 'first');
                if isempty(nodeIdx)
                    continue
                end
                nodeLocal = nodesLocal(nodeIdx);

                if isKey(levelMap, nodeID)
                    levelVal = levelMap(nodeID);
                else
                    levelVal = 1;
                end
                levelKey = sprintf('L%d', levelVal);

                if ~isKey(rowCounterMap, levelKey)
                    rowCounterMap(levelKey) = 0;
                end

                rowVal = rowCounterMap(levelKey) + 1;
                rowCounterMap(levelKey) = rowVal;

                x = 40 + (levelVal - 1) * 240;
                y = 40 + (rowVal - 1) * 120;
                widthVal = 190;
                heightVal = 76;

                nodeRow = app.getNodeTableRowByID(nodeTable, nodeLocal.id);
                topoPos = [];
                execPos = [];
                if ~isempty(nodeRow)
                    if ismember('topoIndex', nodeRow.Properties.VariableNames) && ~isnan(nodeRow.topoIndex(1))
                        topoPos = nodeRow.topoIndex(1);
                    end
                    if ismember('execIndex', nodeRow.Properties.VariableNames) && ~isnan(nodeRow.execIndex(1))
                        execPos = nodeRow.execIndex(1);
                    end
                end

                if isempty(topoPos)
                    topoPos = iTopo;
                end

                funcName = app.getNodeFunctionName(nodeLocal);
                if ~isempty(nodeRow) && ismember('functionName', nodeRow.Properties.VariableNames)
                    funcName = char(string(nodeRow.functionName(1)));
                end

                hasSavedOutputs = app.getNodePersistenceFlagFromPlan(nodeLocal.id, outputPlan);
                statusInfo = app.getNodeStatusInfo(nodeLocal, nodeStatusTable);
                statusInfo = app.addRawFolderWarningToStatusInfo(nodeLocal, statusInfo);
                hoverText = app.buildNodeHoverText(nodeLocal, topoPos, execPos, statusInfo, plan);


                isReconnectTarget = app.bReconnectMode && nodeLocal.id == app.reconnectTargetNodeID;
                isReconnectCandidate = app.bReconnectMode && any(app.reconnectCandidateNodeIDs == nodeLocal.id);
                isReconnectMuted = app.bReconnectMode && ~(isReconnectTarget || isReconnectCandidate);

                reconnectChoiceLabels = {};
                reconnectChoiceRefs = {};

                if isReconnectCandidate && ~isempty(targetInputDef) && ~isempty(targetNodeLocal)
                    dstTypes = {};
                    if isfield(targetInputDef, 'type') && ~isempty(targetInputDef.type)
                        if ischar(targetInputDef.type) || (isstring(targetInputDef.type) && isscalar(targetInputDef.type))
                            dstTypes = {char(string(targetInputDef.type))};
                        else
                            dstTypes = cellstr(string(targetInputDef.type(:)));
                        end
                    end

                    [choiceLabels, choiceRefs] = app.buildExplicitSourceChoices(dstTypes, ...
                        'leafOnly', false, ...
                        'excludeNodeID', targetNodeLocal.id, ...
                        'candidateNodeIDs', nodeLocal.id);

                    reconnectChoiceLabels = choiceLabels;
                    reconnectChoiceRefs = choiceRefs;
                end

                payload.nodes(end+1) = struct( ...
                    'id', nodeLocal.id, ...
                    'stepTag', char(string(nodeLocal.name)), ...
                    'funcName', funcName, ...
                    'kind', char(string(nodeLocal.kind)), ...
                    'x', x, ...
                    'y', y, ...
                    'width', widthVal, ...
                    'height', heightVal, ...
                    'topoIndex', topoPos, ...
                    'execIndex', execPos, ...
                    'hasSavedOutputs', hasSavedOutputs, ...
                    'hasWarning', statusInfo.hasWarning, ...
                    'hasError', statusInfo.hasError, ...
                    'hasInvalidNonblocking', statusInfo.hasInvalidNonblocking, ...
                    'hoverText', hoverText, ...
                    'isReconnectTarget', isReconnectTarget, ...
                    'isReconnectCandidate', isReconnectCandidate, ...
                    'isReconnectMuted', isReconnectMuted, ...
                    'reconnectChoiceLabels', {reconnectChoiceLabels}, ...
                    'reconnectChoiceRefs', {reconnectChoiceRefs});
            end

            if app.bReconnectMode
                ghostY = 40;
                if ~isempty(targetNodeLocal)
                    try
                        targetLevelVal = levelMap(targetNodeLocal.id);
                        rowCounterTmp = 0;
                        for iTopo = 1:numel(topoOrder)
                            curID = topoOrder(iTopo);
                            if curID == targetNodeLocal.id
                                break
                            end
                            if isKey(levelMap, curID) && levelMap(curID) == targetLevelVal
                                rowCounterTmp = rowCounterTmp + 1;
                            end
                        end
                        ghostY = 40 + rowCounterTmp * 120;
                    catch
                        ghostY = 40;
                    end
                end

                payload.reconnectGhost = struct( ...
                    'x', 20, ...
                    'y', ghostY, ...
                    'width', 170, ...
                    'height', 64, ...
                    'stepTag', 'Use File...', ...
                    'funcName', 'File Source', ...
                    'hoverText', sprintf('Select a file from SaveFolder for input "%s".', app.reconnectTargetInputName));
            end

            if istable(edgeTable) && ~isempty(edgeTable) && ...
                    all(ismember({'sourceNodeID','targetNodeID','sourceOutput','selectedFile','targetInput'}, edgeTable.Properties.VariableNames))

                for iEdge = 1:height(edgeTable)
                    srcLabel = char(string(edgeTable.sourceOutput(iEdge)));
                    if strlength(strtrim(string(edgeTable.selectedFile(iEdge)))) > 0
                        srcLabel = char(string(edgeTable.selectedFile(iEdge)));
                    end

                    payload.edges(end+1) = struct( ...
                        'sourceNodeID', edgeTable.sourceNodeID(iEdge), ...
                        'targetNodeID', edgeTable.targetNodeID(iEdge), ...
                        'sourceLabel', srcLabel, ...
                        'targetLabel', char(string(edgeTable.targetInput(iEdge))));
                end
            else
                for iConn = 1:numel(connectionsLocal)
                    connLocal = connectionsLocal(iConn);

                    srcLabel = char(string(connLocal.sourceOutputName));
                    if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                        srcLabel = char(string(connLocal.selectedFile));
                    end

                    payload.edges(end+1) = struct( ...
                        'sourceNodeID', connLocal.sourceNodeID, ...
                        'targetNodeID', connLocal.targetNodeID, ...
                        'sourceLabel', srcLabel, ...
                        'targetLabel', char(string(connLocal.targetInputName)));
                end
            end
        end

        function info = getNodeStatusInfo(~, nodeLocal, nodeStatusTable)
            %GETNODESTATUSINFO Return structured node status from diagnosePipeline.

            info = struct( ...
                'status', "valid", ...
                'severity', "none", ...
                'messages', strings(0,1), ...
                'hasWarning', false, ...
                'hasInvalidNonblocking', false, ...
                'hasError', false);

            if ~istable(nodeStatusTable) || isempty(nodeStatusTable)
                return
            end

            if ~ismember('nodeID', nodeStatusTable.Properties.VariableNames) || ...
                    ~ismember('status', nodeStatusTable.Properties.VariableNames)
                return
            end

            idx = find(nodeStatusTable.nodeID == nodeLocal.id, 1, 'first');
            if isempty(idx)
                return
            end

            info.status = string(nodeStatusTable.status(idx));

            if ismember('severity', nodeStatusTable.Properties.VariableNames)
                info.severity = string(nodeStatusTable.severity(idx));
            end

            if ismember('messages', nodeStatusTable.Properties.VariableNames) && iscell(nodeStatusTable.messages)
                msgLocal = string(nodeStatusTable.messages{idx});
                info.messages = msgLocal(:);
            end

            info.hasWarning = info.status == "warning";
            info.hasInvalidNonblocking = info.status == "invalid_nonblocking";
            info.hasError = info.status == "invalid_blocking";
        end

        function hoverText = buildNodeHoverText(app, nodeLocal, topoPos, execPos, statusInfo, plan)
            %BUILDNODEHOVERTEXT Build one compact hover summary string for a node.

            if nargin < 6
                plan = struct();
            end

            lines = strings(0,1);

            stepTag = char(string(nodeLocal.name));
            funcName = app.getNodeFunctionName(nodeLocal);

            lines(end+1,1) = "Step: " + string(stepTag);
            lines(end+1,1) = "Function: " + string(funcName);
            lines(end+1,1) = "Topological order: " + string(app.valueOrPlaceholder(topoPos));
            lines(end+1,1) = "Execution order: " + string(app.valueOrPlaceholder(execPos));

            if isstruct(statusInfo)
                lines(end+1,1) = "Status: " + string(statusInfo.status);
                lines(end+1,1) = "Severity: " + string(statusInfo.severity);
            end

            inputLines = string(app.getDataInputLines(nodeLocal));
            outputLines = string(app.getOutputLines(nodeLocal));
            outputPlanLines = string(app.getOutputPlanLinesForNode(plan, nodeLocal.id));
            saveLines = string(app.getSavedOutputLines(nodeLocal));

            if ~isempty(inputLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Inputs:";
                lines = [lines; inputLines(:)];
            end

            if ~isempty(outputLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Outputs:";
                lines = [lines; outputLines(:)];
            end

            if ~isempty(outputPlanLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Output handling plan:";
                lines = [lines; outputPlanLines(:)];
            elseif ~isempty(saveLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Saved outputs:";
                lines = [lines; saveLines(:)];
            end

            if isstruct(statusInfo) && isfield(statusInfo, 'messages') && ~isempty(statusInfo.messages)
                lines(end+1,1) = "";
                lines(end+1,1) = "Issues:";
                lines = [lines; "- " + string(statusInfo.messages(:))];
            end

            hoverText = char(join(lines, newline));
        end

        function payload = buildExecutionPayload(app)
            %BUILDEXECUTIONPAYLOAD Build the payload rendered by the execution HTML view.

            payload = struct();
            payload.steps = struct('id', {}, 'stepTag', {}, 'funcName', {}, 'orderIndex', {});
            payload.selectedNodeID = app.selectedNodeID;
            payload.note = 'Execution preview follows backend execution order.';

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            plan = app.getCurrentExecutionPlan();
            if isempty(plan)
                return
            end

            if isfield(plan, 'leafOutputPolicy') && strlength(string(plan.leafOutputPolicy)) > 0
                payload.note = sprintf('Backend execution order. Output policy: %s', ...
                    char(string(plan.leafOutputPolicy)));
            end

            execOrder = app.getExecOrderFromPlan(plan);
            if isempty(execOrder)
                execOrder = app.getTopoOrderFromPlan(plan);
            end
            if isempty(execOrder)
                execOrder = [app.pm.nodes.id];
            end

            nodeTable = table();
            if isfield(plan, 'nodeTable') && istable(plan.nodeTable)
                nodeTable = plan.nodeTable;
            end

            for iStep = 1:numel(execOrder)
                nodeID = execOrder(iStep);
                nodeIdx = find([app.pm.nodes.id] == nodeID, 1, 'first');
                if isempty(nodeIdx)
                    continue
                end

                nodeLocal = app.pm.nodes(nodeIdx);
                nodeRow = app.getNodeTableRowByID(nodeTable, nodeID);

                orderIndex = iStep;
                funcName = app.getNodeFunctionName(nodeLocal);

                if ~isempty(nodeRow)
                    if ismember('execIndex', nodeRow.Properties.VariableNames) && ~isnan(nodeRow.execIndex(1))
                        orderIndex = nodeRow.execIndex(1);
                    end
                    if ismember('functionName', nodeRow.Properties.VariableNames)
                        funcName = char(string(nodeRow.functionName(1)));
                    end
                end

                payload.steps(end+1) = struct( ... %#ok<AGROW>
                    'id', nodeLocal.id, ...
                    'stepTag', char(string(nodeLocal.name)), ...
                    'funcName', funcName, ...
                    'orderIndex', orderIndex);
            end
        end

        function topoOrder = computeTopoOrderFromGraph(~, nodesLocal, connectionsLocal)
            %COMPUTETOPOORDERFROMGRAPH Compute a topological order from nodes/connections.

            if isempty(nodesLocal)
                topoOrder = [];
                return
            end

            nodeIDs = [nodesLocal.id];

            if isempty(connectionsLocal)
                topoOrder = nodeIDs;
                return
            end

            inDegree = zeros(1, numel(nodeIDs));

            for iConn = 1:numel(connectionsLocal)
                tgtIdx = find(nodeIDs == connectionsLocal(iConn).targetNodeID, 1, 'first');
                if ~isempty(tgtIdx)
                    inDegree(tgtIdx) = inDegree(tgtIdx) + 1;
                end
            end

            queue = nodeIDs(inDegree == 0);
            topoOrder = [];

            while ~isempty(queue)
                currentID = queue(1);
                queue(1) = [];
                topoOrder(end+1) = currentID; %#ok<AGROW>

                outMask = [connectionsLocal.sourceNodeID] == currentID;
                outgoing = connectionsLocal(outMask);

                for iOut = 1:numel(outgoing)
                    tgtIdx = find(nodeIDs == outgoing(iOut).targetNodeID, 1, 'first');
                    if isempty(tgtIdx)
                        continue
                    end

                    inDegree(tgtIdx) = inDegree(tgtIdx) - 1;

                    if inDegree(tgtIdx) == 0
                        queue(end+1) = nodeIDs(tgtIdx); %#ok<AGROW>
                    end
                end
            end

            if numel(topoOrder) ~= numel(nodeIDs)
                topoOrder = nodeIDs;
            end
        end

        function levelMap = computeNodeLevels(~, nodesLocal, connectionsLocal, topoOrder)
            %COMPUTENODELEVELS Compute a simple layered layout index for each node.

            levelMap = containers.Map('KeyType', 'double', 'ValueType', 'double');

            if isempty(nodesLocal)
                return
            end

            % No connections -> every node is a root-level node
            if isempty(connectionsLocal)
                for iTopo = 1:numel(topoOrder)
                    levelMap(topoOrder(iTopo)) = 1;
                end
                return
            end

            for iTopo = 1:numel(topoOrder)
                nodeID = topoOrder(iTopo);

                inMask = [connectionsLocal.targetNodeID] == nodeID;
                incoming = connectionsLocal(inMask);

                if isempty(incoming)
                    levelMap(nodeID) = 1;
                    continue
                end

                parentLevels = zeros(1, numel(incoming));
                for iIn = 1:numel(incoming)
                    srcID = incoming(iIn).sourceNodeID;
                    if isKey(levelMap, srcID)
                        parentLevels(iIn) = levelMap(srcID);
                    else
                        parentLevels(iIn) = 1;
                    end
                end

                levelMap(nodeID) = max(parentLevels) + 1;
            end
        end

        function leafIDs = computeLeafNodeIDs(~, nodesLocal, connectionsLocal)
            %COMPUTELEAFNODEIDS Return node IDs with no outgoing edges.

            if isempty(nodesLocal)
                leafIDs = [];
                return
            end

            nodeIDs = [nodesLocal.id];
            if isempty(connectionsLocal)
                leafIDs = nodeIDs;
                return
            end

            srcIDs = [connectionsLocal.sourceNodeID];
            leafIDs = nodeIDs(~ismember(nodeIDs, srcIDs));
        end

        function refreshSelectedStepPanel(app)
            %REFRESHSELECTEDSTEPPANEL Refresh the selected-step details panel.

            if isempty(app.pm) || isempty(app.pm.nodes) || isnan(app.selectedNodeID)
                app.clearSelectedStepPanel();
                return
            end

            nodeIdx = find([app.pm.nodes.id] == app.selectedNodeID, 1, 'first');
            if isempty(nodeIdx)
                app.clearSelectedStepPanel();
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            app.StepTagField.Value = char(string(nodeLocal.name));
            app.FunctionNameField.Value = app.getNodeFunctionName(nodeLocal);
            app.StepSummaryTextArea.Value = app.buildStepSummaryText(nodeLocal);
            app.ParametersTextArea.Value = app.buildParameterText(nodeLocal);
        end

        function clearSelectedStepPanel(app)
            %CLEARSELECTEDSTEPPANEL Clear the right-hand details panel.

            app.StepTagField.Value = '';
            app.FunctionNameField.Value = '';
            app.StepSummaryTextArea.Value = {'<no step selected>'};
            app.ParametersTextArea.Value = {'<no step selected>'};
        end

        function outLines = buildStepSummaryText(app, nodeLocal)
            %BUILDSTEPSUMMARYTEXT Build the Step Summary text for one selected node.

            outLines = strings(0, 1);

            if isempty(app.pm)
                outLines = "<no step selected>";
                outLines = cellstr(outLines);
                return
            end

            plan = app.getCurrentExecutionPlan();
            [topoPos, execPos] = app.getNodeOrderPositionsFromPlan(plan, nodeLocal.id);

            outLines(end+1, 1) = "Topological order: " + string(app.valueOrPlaceholder(topoPos)); %#ok<AGROW>
            outLines(end+1, 1) = "Execution order: " + string(app.valueOrPlaceholder(execPos)); %#ok<AGROW>
            outLines(end+1, 1) = ""; %#ok<AGROW>

            outLines(end+1, 1) = "Inputs:"; %#ok<AGROW>
            dataInputLines = app.getDataInputLines(nodeLocal);
            outLines = [outLines; string(dataInputLines(:))]; %#ok<AGROW>
            outLines(end+1, 1) = ""; %#ok<AGROW>

            outLines(end+1, 1) = "Outputs:"; %#ok<AGROW>
            outputLines = app.getOutputLines(nodeLocal);
            outLines = [outLines; string(outputLines(:))]; %#ok<AGROW>

            outputPlanLines = app.getOutputPlanLinesForNode(plan, nodeLocal.id);
            if ~isempty(outputPlanLines)
                outLines(end+1, 1) = ""; %#ok<AGROW>
                outLines(end+1, 1) = "Output handling plan:"; %#ok<AGROW>
                outLines = [outLines; string(outputPlanLines(:))]; %#ok<AGROW>
            else
                saveLines = app.getSavedOutputLines(nodeLocal);
                if ~isempty(saveLines)
                    outLines(end+1, 1) = ""; %#ok<AGROW>
                    outLines(end+1, 1) = "Saved files:"; %#ok<AGROW>
                    outLines = [outLines; string(saveLines(:))]; %#ok<AGROW>
                end
            end

            outLines = cellstr(outLines);
        end

        function outLines = getDataInputLines(app, nodeLocal)
            %GETDATAINPUTLINES Build the input lines shown in the selected-step summary.

            outLines = strings(0, 1);

            if strcmpi(nodeLocal.kind, 'folder')
                outLines(end+1, 1) = "<file source node>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'inputs') || isempty(nodeLocal.info.inputs)
                outLines(end+1, 1) = "<no inputs>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iIn = 1:numel(nodeLocal.info.inputs)
                inDef = nodeLocal.info.inputs(iIn);

                if ~isfield(inDef, 'isData') || ~inDef.isData
                    continue
                end

                connIdx = find([app.pm.connections.targetNodeID] == nodeLocal.id & ...
                    strcmpi({app.pm.connections.targetInputName}, char(string(inDef.name))), 1, 'first');

                if isempty(connIdx)
                    outLines(end+1, 1) = "- " + string(inDef.name) + " <= <missing>"; %#ok<AGROW>
                    continue
                end

                connLocal = app.pm.connections(connIdx);
                srcIdx = find([app.pm.nodes.id] == connLocal.sourceNodeID, 1, 'first');
                srcNode = app.pm.nodes(srcIdx);

                srcRef = char(string(connLocal.sourceOutputName));
                if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                    srcRef = char(string(connLocal.selectedFile));
                end

                outLines(end+1, 1) = "- " + string(inDef.name) + " <= " + string(srcNode.name) + ":" + string(srcRef); %#ok<AGROW>
            end

            if isempty(outLines)
                outLines(end+1, 1) = "<no data inputs>"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getOutputLines(app, nodeLocal)
            %GETOUTPUTLINES Build the output lines shown in the selected-step summary.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                outLines(end+1, 1) = "<no outputs>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iOut = 1:numel(nodeLocal.info.outputs)
                outDef = nodeLocal.info.outputs(iOut);
                outName = char(string(outDef.name));
                outType = app.typeListToText(outDef.type);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                outLines(end+1, 1) = "- " + string(outName) + " (" + string(outType) + ", " + string(outMode) + ")"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getSavedOutputLines(app, nodeLocal)
            %GETSAVEDOUTPUTLINES Return manager-save-target lines for one step.
            %
            % Only saveable outputs are listed:
            %   isData == true AND outputMode ~= 'file'

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                outLines = cellstr(outLines);
                return
            end

            for iOut = 1:numel(nodeLocal.info.outputs)
                outDef = nodeLocal.info.outputs(iOut);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                isSaveable = isfield(outDef, 'isData') && outDef.isData && ~strcmpi(outMode, 'file');

                if isSaveable && isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                    try
                        if ischar(outDef.saveFileName) || (isstring(outDef.saveFileName) && isscalar(outDef.saveFileName))
                            saveNames = string(outDef.saveFileName);
                        elseif isstring(outDef.saveFileName)
                            saveNames = outDef.saveFileName(:);
                        elseif iscell(outDef.saveFileName)
                            saveNames = string(outDef.saveFileName(:));
                        else
                            saveNames = string(outDef.saveFileName);
                            saveNames = saveNames(:);
                        end
                    catch
                        saveNames = strings(0,1);
                    end

                    saveNames = strip(saveNames);
                    saveNames = saveNames(strlength(saveNames) > 0);
                    saveNames = saveNames(~ismissing(saveNames));

                    for iName = 1:numel(saveNames)
                        outLines(end+1, 1) = "- " + string(outDef.name) + " -> " + saveNames(iName); %#ok<AGROW>
                    end
                end
            end

            outLines = cellstr(outLines);
        end

        function outLines = buildParameterText(app, nodeLocal)
            %BUILDPARAMETERTEXT Build the Parameters text for one selected node.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'parameters') || isempty(nodeLocal.info.parameters)
                outLines(end+1, 1) = "<no parameters>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iParam = 1:numel(nodeLocal.info.parameters)
                p = nodeLocal.info.parameters(iParam);

                if ~isfield(p, 'name') || isempty(p.name)
                    continue
                end

                pValue = [];
                if isfield(p, 'value')
                    pValue = p.value;
                elseif isfield(p, 'default')
                    pValue = p.default;
                end

                outLines(end+1, 1) = string(p.name) + ": " + string(app.formatValueForDisplay(pValue)); %#ok<AGROW>
            end

            if isempty(outLines)
                outLines(end+1, 1) = "<no parameters>"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function txt = formatValueForDisplay(~, valueIn)
            %FORMATVALUEFORDISPLAY Convert a MATLAB value to short readable text.

            if isempty(valueIn)
                txt = '[]';
                return
            end

            if ischar(valueIn) || (isstring(valueIn) && isscalar(valueIn))
                txt = char(string(valueIn));
                return
            end

            if isnumeric(valueIn) || islogical(valueIn)
                if isscalar(valueIn) || (isvector(valueIn) && numel(valueIn) <= 6)
                    txt = mat2str(valueIn);
                else
                    txt = sprintf('[%s %s]', strjoin(string(size(valueIn)), 'x'), class(valueIn));
                end
                return
            end

            if iscell(valueIn)
                txt = sprintf('[%s cell]', strjoin(string(size(valueIn)), 'x'));
                return
            end

            if isstruct(valueIn)
                txt = sprintf('[%s struct]', strjoin(string(size(valueIn)), 'x'));
                return
            end

            if isa(valueIn, 'function_handle')
                txt = ['@' func2str(valueIn)];
                return
            end

            txt = class(valueIn);
        end

        function txt = getNodeFunctionName(~, nodeLocal)
            %GETNODEFUNCTIONNAME Return the callable function name for one node.

            if isfield(nodeLocal, 'kind') && strcmpi(nodeLocal.kind, 'folder')
                txt = 'File Source';
                return
            end

            txt = char(string(nodeLocal.name));

            if isfield(nodeLocal, 'info') && isfield(nodeLocal.info, 'name') && ~isempty(nodeLocal.info.name)
                txt = char(string(nodeLocal.info.name));
            end
        end

        function txt = typeListToText(~, typeIn)
            %TYPELISTTOTEXT Convert type metadata to one display string.

            if isempty(typeIn)
                txt = 'Unknown';
                return
            end

            if ischar(typeIn) || (isstring(typeIn) && isscalar(typeIn))
                txt = char(string(typeIn));
                return
            end

            txt = strjoin(string(typeIn), ' / ');
            txt = char(txt);
        end

        function outVal = valueOrPlaceholder(~, val)
            %VALUEORPLACEHOLDER Return a string-friendly value or placeholder.

            if isempty(val)
                outVal = '<n/a>';
            else
                outVal = val;
            end
        end

        function appendDiagnostic(app, msg)
            %APPENDDIAGNOSTIC Append one timestamped line to the diagnostics box.

            stamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            newLine = sprintf('[%s] %s', stamp, msg);

            currentVal = string(app.DiagnosticsTextArea.Value);
            if isempty(currentVal) || (numel(currentVal) == 1 && strlength(currentVal(1)) == 0)
                app.DiagnosticsTextArea.Value = {newLine};
            else
                app.DiagnosticsTextArea.Value = cellstr([currentVal; string(newLine)]);
            end
        end

        function setStatus(app, msg)
            %SETSTATUS Update the bottom status label.

            app.StatusLabel.Text = ['Status: ' char(string(msg))];
        end

        function funcName = getSelectedFunctionName(app)
            %GETSELECTEDFUNCTIONNAME Return the currently selected function-tree leaf name.

            funcName = '';

            selectedNodes = app.FunctionTree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end

            selectedNode = selectedNodes(1);
            nodeData = selectedNode.NodeData;

            if isempty(nodeData) || ~isstruct(nodeData) || ~isfield(nodeData, 'kind')
                return
            end

            if strcmpi(nodeData.kind, 'function') && isfield(nodeData, 'name')
                funcName = char(string(nodeData.name));
            end
        end

        function tryAddSelectedFunction(app, isNewBranch)
            %TRYADDSELECTEDFUNCTION Add the selected function using GUI-resolved inputs.
            %
            %   tryAddSelectedFunction(app, false) -> standard add
            %   tryAddSelectedFunction(app, true)  -> add as new branch
            %
            % Standard add:
            %   - selected-node-first continuation
            %   - fallback to compatible leaf sources
            %
            % New-branch add:
            %   - always starts from a freshly selected file input
            %   - does not auto-use existing FileSource nodes
            %
            % In both modes, all DATA-input steps are added using explicit
            % 'input' references to avoid MATLAB CLI prompting.

            if nargin < 2 || isempty(isNewBranch)
                isNewBranch = false;
            end

            if isempty(app.pm)
                app.appendDiagnostic('No PipelineManager is attached.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            funcName = app.getSelectedFunctionName();
            if isempty(funcName)
                app.appendDiagnostic('No function is selected.');
                app.setStatus('No function selected.');
                return
            end

            try
                info = app.pm.getPipelineInfo(funcName);

                dataInputs = struct([]);
                if isfield(info, 'inputs') && ~isempty(info.inputs)
                    dataMask = arrayfun(@(x) isfield(x, 'isData') && x.isData, info.inputs);
                    dataInputs = info.inputs(dataMask);
                end

                nDataInputs = numel(dataInputs);

                % -------------------------------------------------------------
                % No DATA inputs -> safe direct add
                % -------------------------------------------------------------
                if nDataInputs == 0
                    stepTag = app.pm.addStep(funcName);

                else
                    inputRefs = app.promptExplicitInputsForAdd(funcName, dataInputs, isNewBranch);

                    if isempty(inputRefs)
                        if isNewBranch
                            app.appendDiagnostic(sprintf('Add-as-new-branch operation for "%s" was cancelled.', funcName));
                            app.setStatus('Add-as-new-branch cancelled.');
                        else
                            app.appendDiagnostic(sprintf('Add-step operation for "%s" was cancelled.', funcName));
                            app.setStatus('Add-step cancelled.');
                        end
                        return
                    end

                    if nDataInputs == 1
                        stepTag = app.pm.addStep(funcName, 'input', inputRefs{1});
                    else
                        stepTag = app.pm.addStep(funcName, 'input', inputRefs);
                    end
                end

                if isempty(stepTag)
                    if isNewBranch
                        app.appendDiagnostic(sprintf('Add-as-new-branch operation for "%s" was cancelled.', funcName));
                        app.setStatus('Add-as-new-branch cancelled.');
                    else
                        app.appendDiagnostic(sprintf('Add-step operation for "%s" was cancelled.', funcName));
                        app.setStatus('Add-step cancelled.');
                    end
                    return
                end

                newIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
                if ~isempty(newIdx)
                    app.selectedNodeID = app.pm.nodes(newIdx).id;
                    app.selectedStepTag = char(string(app.pm.nodes(newIdx).name));
                else
                    app.selectedNodeID = NaN;
                    app.selectedStepTag = '';
                end

                app.refreshAllViews();

                if isNewBranch
                    app.appendDiagnostic(sprintf('Added function "%s" as new-branch step "%s".', funcName, stepTag));
                    app.setStatus('New branch step added.');
                else
                    app.appendDiagnostic(sprintf('Added function "%s" as step "%s".', funcName, stepTag));
                    app.setStatus('Step added.');
                end

            catch ME
                if isNewBranch
                    app.appendDiagnostic(sprintf('Failed to add "%s" as new branch: %s', funcName, ME.message));
                    app.setStatus('Failed to add new branch step.');
                else
                    app.appendDiagnostic(sprintf('Failed to add function "%s": %s', funcName, ME.message));
                    app.setStatus('Failed to add step.');
                end
            end
        end

        function inputRefs = promptExplicitInputsForAdd(app, funcName, dataInputs, isNewBranch)
            %PROMPTEXPLICITINPUTSFORADD Resolve explicit input references for addStep.
            %
            %   Standard mode (isNewBranch = false):
            %       1) selected node only
            %       2) compatible leaf nodes
            %       3) browse file
            %
            %   New-branch mode (isNewBranch = true):
            %       - always prompt for a new file selection for each DATA input
            %       - do not auto-use existing FileSource nodes
            %
            % Output:
            %   inputRefs - 1xN cell array of explicit input references suitable for
            %               PipelineManager.addStep(...,'input',inputRefs)
            %
            % Notes:
            %   - Each returned item is one of:
            %         'StepTag:OutputName'
            %         'StepTag:filename.ext'
            %         'filename.ext'
            %   - Returning [] means user cancelled.

            if nargin < 4 || isempty(isNewBranch)
                isNewBranch = false;
            end

            inputRefs = [];

            if nargin < 3 || isempty(dataInputs)
                inputRefs = {};
                return
            end

            inputRefs = cell(1, numel(dataInputs));

            for iIn = 1:numel(dataInputs)

                inputDef = dataInputs(iIn);
                inputName = char(string(inputDef.name));
                chosenRef = '';

                % -------------------------------------------------------------
                % MODE A: Add as new branch
                % -------------------------------------------------------------
                if isNewBranch
                    chosenRef = app.promptFileInputReference(inputName);

                    if isempty(chosenRef)
                        inputRefs = [];
                        return
                    end

                    app.appendDiagnostic(sprintf( ...
                        'Selected new file source "%s" for input "%s" of "%s".', ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                % -------------------------------------------------------------
                % MODE B: Standard add (selected-node-first)
                % -------------------------------------------------------------

                % First pass: selected node only
                [selectedDisplay, selectedRefs, selectedDefaultIdx] = app.getAddChoicesForInput( ...
                    inputDef, 'selectedNodeOnly', true);

                if ~isempty(selectedRefs)

                    if numel(selectedRefs) == 1
                        chosenRef = selectedRefs{1};

                        app.appendDiagnostic(sprintf( ...
                            ['Auto-selected source "%s" for input "%s" of "%s" ' ...
                            'from the currently selected step.'], ...
                            chosenRef, inputName, funcName));

                        inputRefs{iIn} = chosenRef;
                        continue
                    end

                    chosenRef = app.promptSourceChoiceDialog( ...
                        inputName, funcName, selectedDisplay, selectedRefs, selectedDefaultIdx);

                    if isempty(chosenRef)
                        inputRefs = [];
                        return
                    end

                    app.appendDiagnostic(sprintf( ...
                        ['Selected source "%s" for input "%s" of "%s" ' ...
                        'from the currently selected step.'], ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                % Second pass: global leaf search
                [displayList, refList, defaultIdx] = app.getAddChoicesForInput( ...
                    inputDef, 'selectedNodeOnly', false);

                if isempty(refList)
                    chosenRef = app.promptFileInputReference(inputName);

                    if isempty(chosenRef)
                        inputRefs = [];
                        return
                    end

                    app.appendDiagnostic(sprintf( ...
                        'Selected file "%s" for input "%s" of "%s".', ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                if numel(refList) == 1
                    chosenRef = refList{1};

                    app.appendDiagnostic(sprintf( ...
                        'Auto-selected source "%s" for input "%s" of "%s".', ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                chosenRef = app.promptSourceChoiceDialog( ...
                    inputName, funcName, displayList, refList, defaultIdx);

                if isempty(chosenRef)
                    inputRefs = [];
                    return
                end

                app.appendDiagnostic(sprintf( ...
                    'Selected source "%s" for input "%s" of "%s".', ...
                    chosenRef, inputName, funcName));

                inputRefs{iIn} = chosenRef;
            end
        end

        function [displayList, refList, defaultIdx] = getAddChoicesForInput(app, inputDef, varargin)
            %GETADDCHOICESFORINPUT Build explicit source choices for one standard add-step input.
            %
            %   [displayList, refList, defaultIdx] = getAddChoicesForInput(app, inputDef)
            %   [displayList, refList, defaultIdx] = getAddChoicesForInput(..., ...
            %       'selectedNodeOnly', true)
            %
            % Standard add-step policy:
            %   - Default search uses current leaf nodes only
            %   - If selectedNodeOnly=true, only the currently selected graph node is used
            %   - File outputs are expanded into explicit file selections:
            %         StepTag:filename.ext
            %   - Data outputs use:
            %         StepTag:OutputPortName

            p = inputParser;
            addParameter(p, 'selectedNodeOnly', false, @(x) islogical(x) && isscalar(x));
            parse(p, varargin{:});

            selectedNodeOnly = p.Results.selectedNodeOnly;

            displayList = {};
            refList = {};
            defaultIdx = [];

            dstTypes = {};
            if isfield(inputDef, 'type') && ~isempty(inputDef.type)
                if ischar(inputDef.type) || (isstring(inputDef.type) && isscalar(inputDef.type))
                    dstTypes = {char(string(inputDef.type))};
                else
                    dstTypes = cellstr(string(inputDef.type(:)));
                end
            end

            if selectedNodeOnly
                if isnan(app.selectedNodeID)
                    return
                end

                [displayList, refList, defaultIdx] = app.buildExplicitSourceChoices(dstTypes, ...
                    'leafOnly', false, ...
                    'candidateNodeIDs', app.selectedNodeID);
            else
                [displayList, refList, defaultIdx] = app.buildExplicitSourceChoices(dstTypes, ...
                    'leafOnly', true, ...
                    'preferredNodeID', app.selectedNodeID);
            end
        end

        function [displayList, refList, defaultIdx] = buildExplicitSourceChoices(app, dstTypes, varargin)
            %BUILDEXPLICITSOURCECHOICES Build explicit selectable source references.
            %
            %   [displayList, refList, defaultIdx] = buildExplicitSourceChoices(app, dstTypes)
            %   [displayList, refList, defaultIdx] = buildExplicitSourceChoices(..., ...
            %       'leafOnly', true, ...
            %       'excludeNodeID', NaN, ...
            %       'preferredNodeID', NaN, ...
            %       'candidateNodeIDs', [])
            %
            % Output:
            %   displayList - Cellstr for listdlg display.
            %   refList     - Cellstr explicit references suitable for addStep/reconnectStep:
            %                   StepTag:OutputPortName
            %                   StepTag:filename.ext
            %   defaultIdx  - Suggested default selection index.
            %
            % Notes:
            %   - candidateNodeIDs, when provided, restricts the search to exactly
            %     those node IDs.
            %   - preferredNodeID only affects ordering when candidateNodeIDs is not used.

            p = inputParser;
            addParameter(p, 'leafOnly', false, @(x) islogical(x) && isscalar(x));
            addParameter(p, 'excludeNodeID', NaN, @(x) isnumeric(x) && isscalar(x));
            addParameter(p, 'preferredNodeID', NaN, @(x) isnumeric(x) && isscalar(x));
            addParameter(p, 'candidateNodeIDs', [], @(x) isnumeric(x) || isempty(x));
            parse(p, varargin{:});

            leafOnly = p.Results.leafOnly;
            excludeNodeID = p.Results.excludeNodeID;
            preferredNodeID = p.Results.preferredNodeID;
            candidateNodeIDs = p.Results.candidateNodeIDs;

            displayList = {};
            refList = {};
            defaultIdx = [];

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            candidateNodes = app.pm.nodes;

            % -------------------------------------------------------------
            % Restrict to explicit node IDs when requested
            % -------------------------------------------------------------
            if ~isempty(candidateNodeIDs)
                candidateNodeIDs = unique(candidateNodeIDs(:).', 'stable');
                candidateNodes = candidateNodes(ismember([candidateNodes.id], candidateNodeIDs));
            elseif leafOnly
                leafIDs = app.computeLeafNodeIDs(app.pm.nodes, app.pm.connections);
                candidateNodes = candidateNodes(ismember([candidateNodes.id], leafIDs));
            end

            if ~isnan(excludeNodeID)
                candidateNodes = candidateNodes([candidateNodes.id] ~= excludeNodeID);
            end

            if isempty(candidateNodes)
                return
            end

            % -------------------------------------------------------------
            % Prefer the currently selected node when applicable
            % -------------------------------------------------------------
            if isempty(candidateNodeIDs) && ~isnan(preferredNodeID) && any([candidateNodes.id] == preferredNodeID)
                selMask = [candidateNodes.id] == preferredNodeID;
                candidateNodes = [candidateNodes(selMask), candidateNodes(~selMask)];
            end

            for iNode = 1:numel(candidateNodes)

                srcNode = candidateNodes(iNode);

                if ~isfield(srcNode, 'info') || ~isfield(srcNode.info, 'outputs') || isempty(srcNode.info.outputs)
                    continue
                end

                for iOut = 1:numel(srcNode.info.outputs)

                    outDef = srcNode.info.outputs(iOut);

                    if ~isfield(outDef, 'isData') || ~outDef.isData
                        continue
                    end

                    srcTypes = {};
                    if isfield(outDef, 'type') && ~isempty(outDef.type)
                        if ischar(outDef.type) || (isstring(outDef.type) && isscalar(outDef.type))
                            srcTypes = {char(string(outDef.type))};
                        else
                            srcTypes = cellstr(string(outDef.type(:)));
                        end
                    end

                    if ~app.areTypesCompatible(srcTypes, dstTypes)
                        continue
                    end

                    outName = char(string(outDef.name));
                    outMode = 'data';
                    if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                        outMode = char(string(outDef.outputMode));
                    end

                    typeText = app.typeListToText(srcTypes);

                    if strcmpi(outMode, 'file')

                        declaredFiles = {};
                        if isfield(outDef, 'defOutfilename') && ~isempty(outDef.defOutfilename)
                            if ischar(outDef.defOutfilename) || (isstring(outDef.defOutfilename) && isscalar(outDef.defOutfilename))
                                declaredFiles = {char(string(outDef.defOutfilename))};
                            elseif iscell(outDef.defOutfilename)
                                declaredFiles = cellfun(@(x) char(string(x)), outDef.defOutfilename, 'UniformOutput', false);
                            end
                        end

                        declaredFiles = declaredFiles(~cellfun(@isempty, declaredFiles));
                        declaredFiles = unique(declaredFiles, 'stable');

                        if isempty(declaredFiles)
                            displayList{end+1,1} = sprintf('%s:%s [%s, file]', srcNode.name, outName, typeText); %#ok<AGROW>
                            refList{end+1,1} = sprintf('%s:%s', srcNode.name, outName); %#ok<AGROW>
                        else
                            for iFile = 1:numel(declaredFiles)
                                displayList{end+1,1} = sprintf('%s:%s [%s, file]', ...
                                    srcNode.name, declaredFiles{iFile}, typeText); %#ok<AGROW>
                                refList{end+1,1} = sprintf('%s:%s', ...
                                    srcNode.name, declaredFiles{iFile}); %#ok<AGROW>
                            end
                        end

                    else
                        displayList{end+1,1} = sprintf('%s:%s [%s]', srcNode.name, outName, typeText); %#ok<AGROW>
                        refList{end+1,1} = sprintf('%s:%s', srcNode.name, outName); %#ok<AGROW>
                    end
                end
            end

            if isempty(displayList)
                return
            end

            defaultIdx = 1;
        end

        function chosenRef = promptSourceChoiceDialog(app, inputName, funcName, displayList, refList, defaultIdx)
            %PROMPTSOURCECHOICEDIALOG Show the GUI dialog for ambiguous source selection.
            %
            % Output:
            %   chosenRef - Explicit source reference string, or '' if cancelled.

            chosenRef = '';

            if isempty(displayList) || isempty(refList)
                return
            end

            browseToken = '__BROWSE_FILE__';
            promptDisplay = [displayList(:); {sprintf('[Browse SaveFolder file...]  -> %s', inputName)}];
            promptRefs    = [refList(:); {browseToken}];

            if isempty(defaultIdx) || defaultIdx < 1 || defaultIdx > numel(displayList)
                defaultIdx = 1;
            end

            [selIdx, tf] = listdlg( ...
                'PromptString', sprintf('Select source for input "%s" of "%s":', inputName, funcName), ...
                'SelectionMode', 'single', ...
                'ListString', promptDisplay, ...
                'InitialValue', defaultIdx, ...
                'ListSize', [700 320]);

            if ~tf || isempty(selIdx)
                return
            end

            chosenRef = promptRefs{selIdx};

            if strcmp(chosenRef, browseToken)
                chosenRef = app.promptFileInputReference(inputName);
            end
        end

        function [displayList, refList] = getReconnectChoices(app, targetNode, inputDef)
            %GETRECONNECTCHOICES Build explicit reconnect choices for one target input.
            %
            % Reconnect policy:
            %   - Show all compatible source nodes except the target node itself
            %   - Do not restrict to leaf nodes

            displayList = {};
            refList = {};

            dstTypes = {};
            if isfield(inputDef, 'type') && ~isempty(inputDef.type)
                if ischar(inputDef.type) || (isstring(inputDef.type) && isscalar(inputDef.type))
                    dstTypes = {char(string(inputDef.type))};
                else
                    dstTypes = cellstr(string(inputDef.type(:)));
                end
            end

            [displayList, refList] = app.buildExplicitSourceChoices(dstTypes, ...
                'leafOnly', false, ...
                'excludeNodeID', targetNode.id);
        end

        function inputRef = promptFileInputReference(app, inputName)
            %PROMPTFILEINPUTREFERENCE Select an existing file from SaveFolder for one input.
            %
            % Output:
            %   inputRef - filename.ext suitable for PipelineManager.resolveInputReference
            %              or '' if cancelled.

            inputRef = '';

            startFolder = pwd;
            if ~isempty(app.pm) && isprop(app.pm, 'SaveFolderList') && ~isempty(app.pm.SaveFolderList)
                startFolder = app.pm.SaveFolderList{1};
            end

            [fileName, folderName] = uigetfile('*.*', ...
                sprintf('Select file for input "%s"', inputName), ...
                startFolder);

            if isequal(fileName, 0)
                return
            end

            % PipelineManager.resolveInputReference expects a file name relative to SaveFolder
            % for the file-in-folder path.
            inputRef = char(string(fileName));

            app.appendDiagnostic(sprintf('Selected file "%s" for input "%s".', inputRef, inputName));
        end

        function reconnectStepInteractive(app, stepTag)
            %RECONNECTSTEPINTERACTIVE Start reconnect mode for one selected step.

            if isempty(app.pm)
                app.appendDiagnostic('Reconnect requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            nodeIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
            if isempty(nodeIdx)
                app.appendDiagnostic(sprintf('Reconnect failed. Step "%s" was not found.', stepTag));
                app.setStatus('Reconnect failed.');
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            if strcmpi(nodeLocal.kind, 'folder')
                app.appendDiagnostic('File source steps cannot be reconnected.');
                app.setStatus('Reconnect not allowed.');
                return
            end

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'inputs') || isempty(nodeLocal.info.inputs)
                app.appendDiagnostic(sprintf('Step "%s" has no inputs to reconnect.', stepTag));
                app.setStatus('No inputs to reconnect.');
                return
            end

            dataInputs = nodeLocal.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, nodeLocal.info.inputs));

            if isempty(dataInputs)
                app.appendDiagnostic(sprintf('Step "%s" has no data inputs to reconnect.', stepTag));
                app.setStatus('No data inputs to reconnect.');
                return
            end

            if numel(dataInputs) == 1
                targetInputName = char(string(dataInputs(1).name));
            else
                inputLabels = cell(numel(dataInputs), 1);
                for iIn = 1:numel(dataInputs)
                    inputLabels{iIn} = sprintf('%s [%s]', ...
                        char(string(dataInputs(iIn).name)), ...
                        app.typeListToText(dataInputs(iIn).type));
                end

                [selIdx, tf] = listdlg( ...
                    'PromptString', sprintf('Select input to reconnect for "%s":', stepTag), ...
                    'SelectionMode', 'single', ...
                    'ListString', inputLabels, ...
                    'ListSize', [450 260]);

                if ~tf || isempty(selIdx)
                    app.appendDiagnostic('Reconnect cancelled by user.');
                    app.setStatus('Reconnect cancelled.');
                    return
                end

                targetInputName = char(string(dataInputs(selIdx).name));
            end

            targetInputDef = [];
            for iIn = 1:numel(dataInputs)
                if strcmpi(char(string(dataInputs(iIn).name)), targetInputName)
                    targetInputDef = dataInputs(iIn);
                    break
                end
            end

            if isempty(targetInputDef)
                app.appendDiagnostic('Reconnect failed. Target input definition was not found.');
                app.setStatus('Reconnect failed.');
                return
            end

            [~, refList] = app.getReconnectChoices(nodeLocal, targetInputDef);
            candidateNodeIDs = app.refListToNodeIDs(refList);

            if isempty(candidateNodeIDs)
                app.appendDiagnostic(sprintf('No compatible source steps were found for input "%s".', targetInputName));
                app.setStatus('Reconnect aborted.');
                return
            end

            app.bReconnectMode = true;
            app.reconnectTargetNodeID = nodeLocal.id;
            app.reconnectTargetStepTag = stepTag;
            app.reconnectTargetInputName = targetInputName;
            app.reconnectCandidateNodeIDs = candidateNodeIDs;

            app.selectedNodeID = nodeLocal.id;
            app.selectedStepTag = stepTag;

            app.refreshGraphView();
            app.appendDiagnostic(sprintf('Reconnect mode started for input "%s" of "%s".', targetInputName, stepTag));
            app.setStatus(sprintf('Reconnect mode: click a new source for %s', targetInputName));
        end

        function inputRef = getCurrentExplicitInputReference(app, targetNode, inputDef)
            %GETCURRENTEXPLICITINPUTREFERENCE Return the current explicit input reference string.

            inputRef = '';

            if isempty(app.pm) || isempty(app.pm.connections)
                return
            end

            inputName = char(string(inputDef.name));

            connIdx = find([app.pm.connections.targetNodeID] == targetNode.id & ...
                strcmpi({app.pm.connections.targetInputName}, inputName), 1, 'first');

            if isempty(connIdx)
                return
            end

            connLocal = app.pm.connections(connIdx);
            srcIdx = find([app.pm.nodes.id] == connLocal.sourceNodeID, 1, 'first');
            if isempty(srcIdx)
                return
            end

            srcNode = app.pm.nodes(srcIdx);

            srcRef = char(string(connLocal.sourceOutputName));
            if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                srcRef = char(string(connLocal.selectedFile));
            end

            inputRef = sprintf('%s:%s', char(string(srcNode.name)), srcRef);
        end

        function nodeIDs = refListToNodeIDs(app, refList)
            %REFLISTTONODEIDS Convert explicit source refs to unique node IDs.

            nodeIDs = [];

            if isempty(refList) || isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            nodeIDs = zeros(0,1);

            for iRef = 1:numel(refList)
                refStr = char(string(refList{iRef}));
                tok = regexp(refStr, '^([^:]+):', 'tokens', 'once');
                if isempty(tok)
                    continue
                end

                stepTag = tok{1};
                idx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
                if ~isempty(idx)
                    nodeIDs(end+1,1) = app.pm.nodes(idx).id; %#ok<AGROW>
                end
            end

            nodeIDs = unique(nodeIDs(:).', 'stable');
        end

        function exitReconnectMode(app)
            %EXITRECONNECTMODE Leave reconnect mode and clear transient state.

            app.bReconnectMode = false;
            app.reconnectTargetNodeID = NaN;
            app.reconnectTargetStepTag = '';
            app.reconnectTargetInputName = '';
            app.reconnectCandidateNodeIDs = [];

            app.refreshGraphView();
        end

        function tf = areTypesCompatible(~, srcTypes, dstTypes)
            %ARETYPESCOMPATIBLE Return true when two type lists are compatible.

            if isempty(srcTypes) || isempty(dstTypes)
                tf = true;
                return
            end

            srcTypes = cellstr(string(srcTypes(:)));
            dstTypes = cellstr(string(dstTypes(:)));

            srcHasWildcard = any(strcmp(srcTypes, 'UnknownDataType'));
            dstHasWildcard = any(strcmp(dstTypes, 'UnknownDataType'));

            tf = srcHasWildcard || dstHasWildcard || ~isempty(intersect(srcTypes, dstTypes));
        end

        function configureSaveTargetsInteractive(app, stepTag)
            %CONFIGURESAVETARGETSINTERACTIVE Configure PipelineManager-managed save targets.

            if isempty(app.pm)
                app.appendDiagnostic('Configure-save requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            nodeIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
            if isempty(nodeIdx)
                app.appendDiagnostic(sprintf('Configure-save failed. Step "%s" was not found.', stepTag));
                app.setStatus('Configure-save failed.');
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            if strcmpi(nodeLocal.kind, 'folder')
                app.appendDiagnostic('File source steps cannot have PipelineManager-managed save targets.');
                app.setStatus('Configure-save not allowed.');
                return
            end

            saveableOutputs = app.getSaveableOutputDefsForNode(nodeLocal);

            if isempty(saveableOutputs)
                app.appendDiagnostic(sprintf('Step "%s" has no manager-saveable outputs.', stepTag));
                app.setStatus('No saveable outputs.');
                return
            end

            promptList = cell(numel(saveableOutputs), 1);
            defaultVals = cell(numel(saveableOutputs), 1);

            for iOut = 1:numel(saveableOutputs)
                outDef = saveableOutputs(iOut);

                outName = char(string(outDef.name));
                outType = app.typeListToText(outDef.type);

                defaultTarget = '';
                if isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                    defaultTarget = char(string(outDef.saveFileName));
                elseif isfield(outDef, 'defOutfilename') && ~isempty(outDef.defOutfilename)
                    if iscell(outDef.defOutfilename)
                        defaultTarget = char(string(outDef.defOutfilename{1}));
                    else
                        defaultTarget = char(string(outDef.defOutfilename));
                    end
                end

                promptList{iOut} = sprintf('Save filename for output "%s" [%s]:', outName, outType);
                defaultVals{iOut} = defaultTarget;
            end

            answer = inputdlg( ...
                promptList, ...
                sprintf('Save Outputs - %s', stepTag), ...
                repmat([1 70], numel(promptList), 1), ...
                defaultVals);

            if isempty(answer)
                app.appendDiagnostic('Configure-save action cancelled by user.');
                app.setStatus('Configure-save cancelled.');
                return
            end

            answer = cellfun(@(x) strtrim(char(string(x))), answer, 'UniformOutput', false);
            isBlank = cellfun(@isempty, answer);

            try
                if numel(saveableOutputs) == 1

                    if isBlank(1)
                        app.pm.setSaveTargets(stepTag, '');
                        app.appendDiagnostic(sprintf('Cleared save target for "%s".', stepTag));
                        app.setStatus('Save target cleared.');
                    else
                        app.pm.setSaveTargets(stepTag, answer{1});
                        app.appendDiagnostic(sprintf('Updated save target for "%s" -> %s', stepTag, answer{1}));
                        app.setStatus('Save target updated.');
                    end

                else
                    if all(isBlank)
                        app.pm.setSaveTargets(stepTag, '');
                        app.appendDiagnostic(sprintf('Cleared all save targets for "%s".', stepTag));
                        app.setStatus('Save targets cleared.');

                    elseif any(isBlank)
                        app.appendDiagnostic(['Partial save configuration is not supported yet for multi-output steps. ' ...
                            'Fill every saveable output filename or leave all blank to clear all.']);
                        app.setStatus('Invalid save-target selection.');
                        return

                    else
                        app.pm.setSaveTargets(stepTag, answer);
                        app.appendDiagnostic(sprintf('Updated %d save targets for "%s".', numel(answer), stepTag));
                        app.setStatus('Save targets updated.');
                    end
                end

                app.refreshAllViews();

            catch ME
                app.appendDiagnostic(sprintf('Failed to configure save outputs for "%s": %s', stepTag, ME.message));
                app.setStatus('Failed to configure save outputs.');
            end
        end

        function saveableOutputs = getSaveableOutputDefsForNode(~, nodeLocal)
            %GETSAVEABLEOUTPUTDEFSFORNODE Return saveable outputs for one node.
            %
            % Saveable outputs are:
            %   isData == true AND outputMode ~= 'file'

            saveableOutputs = struct([]);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                return
            end

            mask = false(1, numel(nodeLocal.info.outputs));
            for iOut = 1:numel(nodeLocal.info.outputs)

                outDef = nodeLocal.info.outputs(iOut);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                mask(iOut) = isfield(outDef, 'isData') && outDef.isData && ~strcmpi(outMode, 'file');
            end

            saveableOutputs = nodeLocal.info.outputs(mask);
        end

        function completeReconnectWithRef(app, chosenRef)
            %COMPLETERECONNECTWITHREF Complete reconnect using one explicit reference.

            if ~app.bReconnectMode || isempty(app.pm)
                return
            end

            targetIdx = find([app.pm.nodes.id] == app.reconnectTargetNodeID, 1, 'first');
            if isempty(targetIdx)
                app.appendDiagnostic('Reconnect failed. Target step no longer exists.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            targetNode = app.pm.nodes(targetIdx);

            if ~isfield(targetNode, 'info') || ~isfield(targetNode.info, 'inputs') || isempty(targetNode.info.inputs)
                app.appendDiagnostic('Reconnect failed. Target step has no inputs.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            dataInputs = targetNode.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, targetNode.info.inputs));

            inputRefs = cell(1, numel(dataInputs));

            for iIn = 1:numel(dataInputs)
                thisInputName = char(string(dataInputs(iIn).name));

                if strcmpi(thisInputName, app.reconnectTargetInputName)
                    inputRefs{iIn} = chosenRef;
                else
                    inputRefs{iIn} = app.getCurrentExplicitInputReference(targetNode, dataInputs(iIn));
                    if isempty(inputRefs{iIn})
                        error('Current input "%s" is not connected, so partial reconnect cannot proceed.', thisInputName);
                    end
                end
            end

            try
                targetStepTag = app.reconnectTargetStepTag;
                targetInputName = app.reconnectTargetInputName;

                app.pm.reconnectStep(targetStepTag, 'input', inputRefs);

                app.exitReconnectMode();
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('Reconnected input "%s" of "%s".', targetInputName, targetStepTag));
                app.setStatus('Step reconnected.');

            catch ME
                app.appendDiagnostic(sprintf('Reconnect failed for "%s": %s', app.reconnectTargetStepTag, ME.message));
                app.setStatus('Reconnect failed.');
            end
        end

        function plan = getCurrentExecutionPlan(app)
            %GETCURRENTEXECUTIONPLAN Return the backend execution plan when available.
            %
            % The PipelineManager backend owns graph order, execution order,
            % validation status, data-flow edges, and output-persistence planning.
            % The app only falls back to a local display-only plan when the backend
            % cannot build a full plan from an already-invalid loaded graph.

            plan = [];

            if isempty(app.pm)
                return
            end

            try
                plan = app.pm.getExecutionPlan();
            catch
                plan = app.buildFallbackExecutionPlan();
            end
        end

        function plan = buildFallbackExecutionPlan(app)
            %BUILDFALLBACKEXECUTIONPLAN Build a display-only plan for invalid graphs.
            %
            % This fallback is intentionally narrow. It keeps the graph visible when
            % a loaded pipeline is structurally broken enough that getExecutionPlan
            % cannot compute backend order metadata. Normal valid/repairable graphs
            % should use PipelineManager.getExecutionPlan().

            plan = struct();
            plan.createdOn = datetime('now');
            plan.leafOutputPolicy = "";
            plan.tempOutputPrefix = "";
            plan.tempSessionTag = "";
            plan.saveFolder = "";

            if isempty(app.pm) || isempty(app.pm.nodes)
                plan.nodeOrders = struct('topoOrder', [], 'execOrder', [], 'nodeTable', table());
                plan.nodeTable = table();
                plan.edgeTable = table();
                plan.outputPlan = table();
                plan.validationReport = struct('isValid', true, 'errors', {{}}, 'warnings', {{}}, 'nodeStatus', table());
                return
            end

            try
                validationReport = app.pm.diagnosePipeline('verbose', false);
            catch
                validationReport = struct('isValid', false, 'errors', {{}}, 'warnings', {{}}, 'nodeStatus', table());
            end

            nodesLocal = app.pm.nodes;
            connectionsLocal = app.pm.connections;
            topoOrder = app.computeTopoOrderFromGraph(nodesLocal, connectionsLocal);
            nodeTable = app.buildFallbackNodeTable(nodesLocal, connectionsLocal, topoOrder);
            edgeTable = app.buildFallbackEdgeTable(nodesLocal, connectionsLocal);

            plan.nodeOrders = struct();
            plan.nodeOrders.topoOrder = topoOrder;
            plan.nodeOrders.execOrder = topoOrder;
            plan.nodeOrders.nodeTable = nodeTable;
            plan.nodeTable = nodeTable;
            plan.edgeTable = edgeTable;
            plan.outputPlan = table();
            plan.validationReport = validationReport;
        end

        function nodeTable = buildFallbackNodeTable(app, nodesLocal, connectionsLocal, topoOrder)
            %BUILDFALLBACKNODETABLE Build a minimal backend-shaped node table.

            if isempty(nodesLocal)
                nodeTable = table();
                return
            end

            nodeID = zeros(numel(nodesLocal), 1);
            stepName = strings(numel(nodesLocal), 1);
            functionName = strings(numel(nodesLocal), 1);
            kind = strings(numel(nodesLocal), 1);
            topoIndex = nan(numel(nodesLocal), 1);
            execIndex = nan(numel(nodesLocal), 1);
            isRoot = false(numel(nodesLocal), 1);
            isLeaf = false(numel(nodesLocal), 1);

            for iNode = 1:numel(nodesLocal)
                nodeLocal = nodesLocal(iNode);
                nodeID(iNode) = nodeLocal.id;
                stepName(iNode) = string(nodeLocal.name);
                functionName(iNode) = string(app.getNodeFunctionName(nodeLocal));
                kind(iNode) = string(nodeLocal.kind);

                pos = find(topoOrder == nodeLocal.id, 1, 'first');
                if ~isempty(pos)
                    topoIndex(iNode) = pos;
                    execIndex(iNode) = pos;
                end

                if isempty(connectionsLocal)
                    isRoot(iNode) = true;
                    isLeaf(iNode) = true;
                else
                    isRoot(iNode) = ~any([connectionsLocal.targetNodeID] == nodeLocal.id);
                    isLeaf(iNode) = ~any([connectionsLocal.sourceNodeID] == nodeLocal.id);
                end
            end

            nodeTable = table(nodeID, stepName, functionName, kind, topoIndex, execIndex, isRoot, isLeaf);
            [~, ord] = sort(nodeTable.topoIndex, 'ascend');
            nodeTable = nodeTable(ord, :);
        end

        function edgeTable = buildFallbackEdgeTable(app, nodesLocal, connectionsLocal)
            %BUILDFALLBACKEDGETABLE Build a minimal backend-shaped edge table.

            if isempty(connectionsLocal)
                edgeTable = table();
                return
            end

            nConn = numel(connectionsLocal);
            sourceNodeID = zeros(nConn, 1);
            sourceStep = strings(nConn, 1);
            sourceOutput = strings(nConn, 1);
            selectedFile = strings(nConn, 1);
            targetNodeID = zeros(nConn, 1);
            targetStep = strings(nConn, 1);
            targetInput = strings(nConn, 1);
            sourceTypes = cell(nConn, 1);

            for iConn = 1:nConn
                connLocal = connectionsLocal(iConn);
                sourceNodeID(iConn) = connLocal.sourceNodeID;
                targetNodeID(iConn) = connLocal.targetNodeID;
                sourceOutput(iConn) = string(connLocal.sourceOutputName);
                targetInput(iConn) = string(connLocal.targetInputName);

                if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                    selectedFile(iConn) = string(connLocal.selectedFile);
                end

                srcIdx = find([nodesLocal.id] == connLocal.sourceNodeID, 1, 'first');
                if isempty(srcIdx)
                    sourceStep(iConn) = "<missing>";
                else
                    sourceStep(iConn) = string(nodesLocal(srcIdx).name);
                end

                dstIdx = find([nodesLocal.id] == connLocal.targetNodeID, 1, 'first');
                if isempty(dstIdx)
                    targetStep(iConn) = "<missing>";
                else
                    targetStep(iConn) = string(nodesLocal(dstIdx).name);
                end

                if isfield(connLocal, 'sourceOutputType') && ~isempty(connLocal.sourceOutputType)
                    sourceTypes{iConn} = cellstr(string(connLocal.sourceOutputType(:)));
                else
                    sourceTypes{iConn} = {};
                end
            end

            edgeTable = table(sourceNodeID, sourceStep, sourceOutput, selectedFile, ...
                targetNodeID, targetStep, targetInput, sourceTypes);
        end

        function topoOrder = getTopoOrderFromPlan(~, plan)
            %GETTOPOORDERFROMPLAN Extract backend topological order from a plan.

            topoOrder = [];

            if isstruct(plan) && isfield(plan, 'nodeOrders') && isstruct(plan.nodeOrders) && ...
                    isfield(plan.nodeOrders, 'topoOrder') && ~isempty(plan.nodeOrders.topoOrder)
                topoOrder = plan.nodeOrders.topoOrder(:).';
                return
            end

            if isstruct(plan) && isfield(plan, 'nodeTable') && istable(plan.nodeTable) && ...
                    ~isempty(plan.nodeTable) && all(ismember({'nodeID','topoIndex'}, plan.nodeTable.Properties.VariableNames))
                nodeTable = plan.nodeTable;
                [~, ord] = sort(nodeTable.topoIndex, 'ascend');
                topoOrder = nodeTable.nodeID(ord).';
            end
        end

        function execOrder = getExecOrderFromPlan(~, plan)
            %GETEXECORDERFROMPLAN Extract backend execution order from a plan.

            execOrder = [];

            if isstruct(plan) && isfield(plan, 'nodeOrders') && isstruct(plan.nodeOrders) && ...
                    isfield(plan.nodeOrders, 'execOrder') && ~isempty(plan.nodeOrders.execOrder)
                execOrder = plan.nodeOrders.execOrder(:).';
                return
            end

            if isstruct(plan) && isfield(plan, 'nodeTable') && istable(plan.nodeTable) && ...
                    ~isempty(plan.nodeTable) && all(ismember({'nodeID','execIndex'}, plan.nodeTable.Properties.VariableNames))
                nodeTable = plan.nodeTable;
                keep = ~isnan(nodeTable.execIndex);
                nodeTable = nodeTable(keep, :);
                [~, ord] = sort(nodeTable.execIndex, 'ascend');
                execOrder = nodeTable.nodeID(ord).';
            end
        end

        function row = getNodeTableRowByID(~, nodeTable, nodeID)
            %GETNODETABLEROWBYID Return one nodeTable row by node ID.

            row = table();

            if ~istable(nodeTable) || isempty(nodeTable) || ~ismember('nodeID', nodeTable.Properties.VariableNames)
                return
            end

            idx = find(nodeTable.nodeID == nodeID, 1, 'first');
            if ~isempty(idx)
                row = nodeTable(idx, :);
            end
        end

        function tf = getNodePersistenceFlagFromPlan(~, nodeID, outputPlan)
            %GETNODEPERSISTENCEFLAGFROMPLAN True when a step has planned persisted outputs.

            tf = false;

            if ~istable(outputPlan) || isempty(outputPlan) || ...
                    ~all(ismember({'nodeID','plannedPersistence'}, outputPlan.Properties.VariableNames))
                return
            end

            rows = outputPlan(outputPlan.nodeID == nodeID, :);
            if isempty(rows)
                return
            end

            persistedStates = ["already_file", "saved_explicit", "saved_final", "saved_temp", "saved_leaf"];
            tf = any(ismember(string(rows.plannedPersistence), persistedStates));
        end

        function outLines = getOutputPlanLinesForNode(app, plan, nodeID)
            %GETOUTPUTPLANLINESFORNODE Return output-persistence plan lines for one step.

            outLines = strings(0,1);

            if ~isstruct(plan) || ~isfield(plan, 'outputPlan') || ~istable(plan.outputPlan) || isempty(plan.outputPlan)
                outLines = cellstr(outLines);
                return
            end

            outputPlan = plan.outputPlan;
            requiredVars = {'nodeID','outputName','plannedPersistence','plannedFileName','reason'};
            if ~all(ismember(requiredVars, outputPlan.Properties.VariableNames))
                outLines = cellstr(outLines);
                return
            end

            rows = outputPlan(outputPlan.nodeID == nodeID, :);
            if isempty(rows)
                outLines = cellstr(outLines);
                return
            end

            for iRow = 1:height(rows)
                fileName = string(rows.plannedFileName(iRow));
                persistenceText = app.formatPersistenceStateForDisplay(rows.plannedPersistence(iRow));

                if strlength(strtrim(fileName)) > 0
                    outLines(end+1,1) = "- " + string(rows.outputName(iRow)) + ": " + ...
                        persistenceText + " -> " + fileName; %#ok<AGROW>
                else
                    outLines(end+1,1) = "- " + string(rows.outputName(iRow)) + ": " + ...
                        persistenceText; %#ok<AGROW>
                end

                reasonText = app.formatOutputPlanReasonForDisplay(rows.reason(iRow));
                if strlength(strtrim(reasonText)) > 0
                    outLines(end+1,1) = "  " + reasonText; %#ok<AGROW>
                end
            end

            outLines = cellstr(outLines);
        end

        function [topoPos, execPos] = getNodeOrderPositionsFromPlan(app, plan, nodeID)
            %GETNODEORDERPOSITIONSFROMPLAN Return topo/exec positions for one node.

            topoPos = [];
            execPos = [];

            if ~isstruct(plan) || ~isfield(plan, 'nodeTable')
                return
            end

            row = app.getNodeTableRowByID(plan.nodeTable, nodeID);
            if isempty(row)
                return
            end

            if ismember('topoIndex', row.Properties.VariableNames) && ~isnan(row.topoIndex(1))
                topoPos = row.topoIndex(1);
            end
            if ismember('execIndex', row.Properties.VariableNames) && ~isnan(row.execIndex(1))
                execPos = row.execIndex(1);
            end
        end

        function lineList = formatDiagnosticReport(~, report)
            %FORMATDIAGNOSTICREPORT Convert a diagnosePipeline report to text lines.

            lineList = strings(0,1);

            if isempty(report) || ~isstruct(report)
                lineList = "<no diagnostic report available>";
                lineList = cellstr(lineList);
                return
            end

            if isfield(report, 'isValid') && report.isValid
                lineList(end+1,1) = "Pipeline status: VALID"; %#ok<AGROW>
            else
                lineList(end+1,1) = "Pipeline status: INVALID"; %#ok<AGROW>
            end

            if isfield(report, 'graphIsValid')
                lineList(end+1,1) = "Graph valid: " + string(logical(report.graphIsValid)); %#ok<AGROW>
            end
            if isfield(report, 'paramsAreValid')
                lineList(end+1,1) = "Parameters valid: " + string(logical(report.paramsAreValid)); %#ok<AGROW>
            end

            errList = {};
            warnList = {};
            if isfield(report, 'errors') && ~isempty(report.errors)
                errList = report.errors;
            end
            if isfield(report, 'warnings') && ~isempty(report.warnings)
                warnList = report.warnings;
            end

            lineList(end+1,1) = ""; %#ok<AGROW>
            lineList(end+1,1) = "Errors (" + string(numel(errList)) + "):"; %#ok<AGROW>
            if isempty(errList)
                lineList(end+1,1) = "  <none>"; %#ok<AGROW>
            else
                for iErr = 1:numel(errList)
                    lineList(end+1,1) = "  " + string(iErr) + ") " + string(errList{iErr}); %#ok<AGROW>
                end
            end

            lineList(end+1,1) = ""; %#ok<AGROW>
            lineList(end+1,1) = "Warnings (" + string(numel(warnList)) + "):"; %#ok<AGROW>
            if isempty(warnList)
                lineList(end+1,1) = "  <none>"; %#ok<AGROW>
            else
                for iWarn = 1:numel(warnList)
                    lineList(end+1,1) = "  " + string(iWarn) + ") " + string(warnList{iWarn}); %#ok<AGROW>
                end
            end

            if isfield(report, 'nodeStatus') && istable(report.nodeStatus) && ~isempty(report.nodeStatus)
                badRows = report.nodeStatus(report.nodeStatus.status ~= "valid", :);
                lineList(end+1,1) = ""; %#ok<AGROW>
                lineList(end+1,1) = "Step status (" + string(height(badRows)) + " non-valid):"; %#ok<AGROW>
                if isempty(badRows)
                    lineList(end+1,1) = "  <all steps valid>"; %#ok<AGROW>
                else
                    for iRow = 1:height(badRows)
                        lineList(end+1,1) = "  " + string(badRows.stepName(iRow)) + ...
                            " | " + string(badRows.status(iRow)) + ...
                            " | " + string(badRows.severity(iRow)); %#ok<AGROW>
                    end
                end
            end

            lineList = cellstr(lineList);
        end

        function removeInvalidStepsInteractive(app)
            %REMOVEINVALIDSTEPSINTERACTIVE Remove invalid graph steps through the backend.

            if isempty(app.pm)
                app.appendDiagnostic('Remove-invalid requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            try
                report = app.pm.diagnosePipeline('verbose', false);
            catch ME
                app.appendDiagnostic(sprintf('Could not diagnose pipeline before cleanup: %s', ME.message));
                app.setStatus('Remove invalid failed.');
                return
            end

            invalidRows = table();
            if isfield(report, 'nodeStatus') && istable(report.nodeStatus) && ~isempty(report.nodeStatus)
                invalidRows = report.nodeStatus(ismember(report.nodeStatus.status, ...
                    ["invalid_blocking", "invalid_nonblocking"]), :);
            end

            if isempty(invalidRows)
                app.DiagnosticsTextArea.Value = app.formatDiagnosticReport(report);
                app.appendDiagnostic('No invalid steps were found.');
                app.setStatus('No invalid steps found.');
                app.refreshAllViews();
                return
            end

            nBlocking = sum(invalidRows.status == "invalid_blocking");
            nNonBlocking = sum(invalidRows.status == "invalid_nonblocking");

            msg = sprintf(['Remove invalid steps from the current pipeline?\n\n' ...
                'Blocking invalid steps: %d\n' ...
                'Non-blocking invalid steps: %d\n\n' ...
                'This cannot be undone from this dialog.'], ...
                nBlocking, nNonBlocking);

            choice = uiconfirm(app.UIFigure, msg, 'Remove Invalid Steps', ...
                'Options', {'Remove Invalid', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(choice, 'Remove Invalid')
                app.appendDiagnostic('Remove-invalid action cancelled.');
                app.setStatus('Remove invalid cancelled.');
                return
            end

            try
                removed = app.pm.removeInvalidNodes();

                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.exitReconnectMode();

                reportAfter = app.pm.diagnosePipeline('verbose', false);
                app.DiagnosticsTextArea.Value = app.formatDiagnosticReport(reportAfter);
                app.refreshAllViews();

                if isempty(removed)
                    app.appendDiagnostic('removeInvalidNodes completed. No steps were removed.');
                else
                    app.appendDiagnostic(sprintf('Removed invalid step ID(s): %s', strjoin(string(removed), ', ')));
                end

                if isfield(reportAfter, 'isValid') && reportAfter.isValid
                    app.setStatus('Invalid steps removed. Pipeline is valid.');
                else
                    app.setStatus('Invalid-step cleanup completed. Pipeline still needs attention.');
                end

            catch ME
                app.appendDiagnostic(sprintf('Failed to remove invalid steps: %s', ME.message));
                app.setStatus('Failed to remove invalid steps.');
            end
        end

        function txt = formatPersistenceStateForDisplay(~, state)
            %FORMATPERSISTENCESTATEFORDISPLAY Convert backend state to user-facing text.

            state = lower(strtrim(string(state)));

            switch state
                case "already_file"
                    txt = "already file-backed";
                case "saved_explicit"
                    txt = "saved by user setting";
                case {"saved_final", "saved_leaf"}
                    txt = "save final output";
                case "saved_temp"
                    txt = "temporary output for viewer";
                case "transient_explicit"
                    txt = "temporary in memory";
                case "unsaved_reported"
                    txt = "not saved";
                case "unsupported_save"
                    txt = "cannot be saved";
                case "not_final_intermediate"
                    txt = "intermediate output";
                case "not_leaf_intermediate"
                    txt = "intermediate output";
                case "not_produced"
                    txt = "not produced";
                case "missing_expected_file"
                    txt = "missing expected file";
                otherwise
                    txt = string(state);
            end
        end

        function txt = formatOutputPlanReasonForDisplay(~, reasonText)
            %FORMATOUTPUTPLANREASONFORDISPLAY Remove backend jargon from plan reasons.

            txt = string(reasonText);

            if strlength(strtrim(txt)) == 0
                return
            end

            replacements = {
                "LeafOutputPolicy=saveLeaves.", ...
                "Output policy=saveLeaves.";
                "LeafOutputPolicy=viewerTemp and output is a viewer-compatible candidate.", ...
                "Output policy=viewerTemp and output can be opened by DataViewer.";
                "LeafOutputPolicy=viewerTemp but output is not a DataViewer-compatible candidate.", ...
                "Output policy=viewerTemp, but output is not a DataViewer-compatible candidate.";
                "No explicit save target and LeafOutputPolicy=explicit.", ...
                "No explicit save target and output policy=explicit.";
                "Output feeds downstream node(s) or is not a DATA leaf.", ...
                "Output feeds another step or is not DATA."
                };

            for iRep = 1:size(replacements, 1)
                txt = replace(txt, replacements{iRep,1}, replacements{iRep,2});
            end
        end

        function configureExecutionControlDropDowns(app)
            %CONFIGUREEXECUTIONCONTROLDROPDOWNS Configure UI labels and backend values.

            app.AutoSaveFinalOutputsDropDown.Items = { ...
                'Disabled', ...
                'Enabled', ...
                'Enabled as temporary files'};
            app.AutoSaveFinalOutputsDropDown.ItemsData = { ...
                'explicit', ...
                'saveLeaves', ...
                'viewerTemp'};

            app.RAMModeDropDown.Items = { ...
                'Auto', ...
                'RAM-safe'};
            app.RAMModeDropDown.ItemsData = { ...
                'auto', ...
                'ramsafe'};

            app.RAMSafetyPolicyDropDown.Items = { ...
                'Strict', ...
                'Best effort'};
            app.RAMSafetyPolicyDropDown.ItemsData = { ...
                'strict', ...
                'bestEffort'};

            if isempty(app.AutoSaveFinalOutputsDropDown.Value) || ...
                    ~ismember(app.AutoSaveFinalOutputsDropDown.Value, app.AutoSaveFinalOutputsDropDown.ItemsData)
                app.AutoSaveFinalOutputsDropDown.Value = 'saveLeaves';
            end

            if isempty(app.RAMModeDropDown.Value) || ...
                    ~ismember(app.RAMModeDropDown.Value, app.RAMModeDropDown.ItemsData)
                app.RAMModeDropDown.Value = 'auto';
            end

            if isempty(app.RAMSafetyPolicyDropDown.Value) || ...
                    ~ismember(app.RAMSafetyPolicyDropDown.Value, app.RAMSafetyPolicyDropDown.ItemsData)
                app.RAMSafetyPolicyDropDown.Value = 'bestEffort';
            end

            app.updateRamSafetyControlState();
        end

        function syncExecutionControlsFromManager(app)
            %SYNCEXECUTIONCONTROLSFROMMANAGER Reflect PipelineManager settings in the UI.

            app.configureExecutionControlDropDowns();

            if isempty(app.pm)
                return
            end

            try
                if isprop(app.pm, 'leafOutputPolicy') && ...
                        ismember(char(string(app.pm.leafOutputPolicy)), app.AutoSaveFinalOutputsDropDown.ItemsData)
                    app.AutoSaveFinalOutputsDropDown.Value = char(string(app.pm.leafOutputPolicy));
                end
            catch
            end

            try
                if isprop(app.pm, 'ramMode') && ...
                        ismember(char(string(app.pm.ramMode)), app.RAMModeDropDown.ItemsData)
                    app.RAMModeDropDown.Value = char(string(app.pm.ramMode));
                end
            catch
            end

            try
                if isprop(app.pm, 'ramSafePolicy') && ...
                        ismember(char(string(app.pm.ramSafePolicy)), app.RAMSafetyPolicyDropDown.ItemsData)
                    app.RAMSafetyPolicyDropDown.Value = char(string(app.pm.ramSafePolicy));
                end
            catch
            end

            app.updateRamSafetyControlState();
        end

        function applyExecutionControlsToManager(app)
            %APPLYEXECUTIONCONTROLSTOMANAGER Push visible execution settings to backend.

            if isempty(app.pm)
                return
            end

            try
                app.pm.setLeafOutputPolicy(app.AutoSaveFinalOutputsDropDown.Value);
            catch ME
                app.appendDiagnostic(sprintf('Failed to update output policy: %s', ME.message));
            end

            try
                app.pm.ramMode = char(string(app.RAMModeDropDown.Value));
            catch ME
                app.appendDiagnostic(sprintf('Failed to update RAM mode: %s', ME.message));
            end

            try
                app.pm.ramSafePolicy = char(string(app.RAMSafetyPolicyDropDown.Value));
            catch ME
                app.appendDiagnostic(sprintf('Failed to update RAM safety policy: %s', ME.message));
            end

            app.updateRamSafetyControlState();
        end

        function updateRamSafetyControlState(app)
            %UPDATERAMSAFETYCONTROLSTATE Enable safety dropdown only in RAM-safe mode.

            try
                if strcmpi(char(string(app.RAMModeDropDown.Value)), 'ramsafe')
                    app.RAMSafetyPolicyDropDown.Enable = 'on';
                    app.SafetyLabel.Enable = 'on';
                else
                    app.RAMSafetyPolicyDropDown.Enable = 'off';
                    app.SafetyLabel.Enable = 'off';
                end
            catch
            end
        end

        function runPipelineFromGui(app)
            %RUNPIPELINEFROMGUI Validate and execute the current pipeline.

            if isempty(app.pm)
                app.appendDiagnostic('Run requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            app.applyExecutionControlsToManager();

            try
                report = app.pm.diagnosePipeline('verbose', false);
                app.DiagnosticsTextArea.Value = app.formatDiagnosticReport(report);
                app.refreshGraphView();
            catch ME
                app.appendDiagnostic(sprintf('Could not validate pipeline before execution: %s', ME.message));
                app.setStatus('Run aborted.');
                return
            end

            hasBlockingInvalid = false;
            if isfield(report, 'nodeStatus') && istable(report.nodeStatus) && ...
                    ismember('status', report.nodeStatus.Properties.VariableNames)
                hasBlockingInvalid = any(string(report.nodeStatus.status) == "invalid_blocking");
            elseif isfield(report, 'isValid')
                hasBlockingInvalid = ~logical(report.isValid);
            end

            if hasBlockingInvalid
                uialert(app.UIFigure, ...
                    'The pipeline has blocking invalid steps. Fix them or remove invalid steps before running.', ...
                    'Cannot Run Pipeline');
                app.appendDiagnostic('Run aborted because blocking invalid steps were found.');
                app.setStatus('Run blocked by invalid steps.');
                return
            end

            hasNonBlockingInvalid = false;
            if isfield(report, 'hasInvalidNonblocking')
                hasNonBlockingInvalid = logical(report.hasInvalidNonblocking);
            elseif isfield(report, 'nodeStatus') && istable(report.nodeStatus) && ...
                    ismember('status', report.nodeStatus.Properties.VariableNames)
                hasNonBlockingInvalid = any(string(report.nodeStatus.status) == "invalid_nonblocking");
            end

            if hasNonBlockingInvalid
                choice = uiconfirm(app.UIFigure, ...
                    ['The pipeline contains non-blocking invalid steps, usually unused stale steps. ' ...
                    'Execution can continue, but the graph should be cleaned when convenient.'], ...
                    'Run Pipeline', ...
                    'Options', {'Run Anyway', 'Cancel'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2);

                if ~strcmp(choice, 'Run Anyway')
                    app.appendDiagnostic('Run cancelled by user.');
                    app.setStatus('Run cancelled.');
                    return
                end
            end

            try
                app.RunButton.Enable = 'off';
                cleanupRunButton = onCleanup(@() set(app.RunButton, 'Enable', 'on')); %#ok<NASGU>

                app.appendDiagnostic('Pipeline execution started.');
                app.setStatus('Running pipeline...');
                drawnow;

                result = app.pm.executePipeline('PrintSummary', false);

                app.renderExecutionResult(result);
                app.refreshAllViews();

                app.appendDiagnostic(sprintf('Pipeline execution finished with status: %s', char(string(result.status))));
                app.setStatus(sprintf('Run finished: %s', char(string(result.status))));

            catch ME
                app.appendDiagnostic(sprintf('Pipeline execution failed: %s', ME.message));
                app.setStatus('Pipeline execution failed.');
            end
        end

        function renderExecutionResult(app, result)
            %RENDEREXECUTIONRESULT Render latest execution result in Diagnostics.

            lines = strings(0,1);

            lines(end+1,1) = "Execution result"; %#ok<AGROW>
            lines(end+1,1) = "================"; %#ok<AGROW>

            if isstruct(result) && isfield(result, 'status')
                lines(end+1,1) = "Status: " + string(result.status); %#ok<AGROW>
            end
            if isstruct(result) && isfield(result, 'duration_s')
                lines(end+1,1) = "Duration: " + string(round(result.duration_s, 3)) + " s"; %#ok<AGROW>
            end
            if isstruct(result) && isfield(result, 'leafOutputPolicy')
                lines(end+1,1) = "Output policy: " + string(result.leafOutputPolicy); %#ok<AGROW>
            end

            if isstruct(result) && isfield(result, 'globalPipeLog') && istable(result.globalPipeLog)
                T = result.globalPipeLog;
                lines(end+1,1) = ""; %#ok<AGROW>
                lines(end+1,1) = "Folder execution log:"; %#ok<AGROW>

                if isempty(T)
                    lines(end+1,1) = "  <empty>"; %#ok<AGROW>
                else
                    for iRow = 1:height(T)
                        statusText = "<unknown>";
                        msgText = "";
                        saveFolderText = "";

                        if ismember('Status', T.Properties.VariableNames)
                            statusText = string(T.Status(iRow));
                        end
                        if ismember('Messages_short', T.Properties.VariableNames)
                            try
                                msgText = string(T.Messages_short{iRow});
                            catch
                                msgText = string(T.Messages_short(iRow));
                            end
                        end
                        if ismember('SaveFolder', T.Properties.VariableNames)
                            try
                                saveFolderText = string(T.SaveFolder{iRow});
                            catch
                                saveFolderText = string(T.SaveFolder(iRow));
                            end
                            [~, saveFolderText] = fileparts(char(saveFolderText));
                            saveFolderText = string(saveFolderText);
                        end

                        if strlength(strtrim(msgText)) > 0
                            lines(end+1,1) = "  " + string(iRow) + ") " + saveFolderText + ...
                                " | " + statusText + " | " + msgText; %#ok<AGROW>
                        else
                            lines(end+1,1) = "  " + string(iRow) + ") " + saveFolderText + ...
                                " | " + statusText; %#ok<AGROW>
                        end
                    end
                end
            end

            if isstruct(result) && isfield(result, 'createdFiles') && istable(result.createdFiles)
                T = result.createdFiles;
                maxRows = 30;
                lines(end+1,1) = ""; %#ok<AGROW>
                lines(end+1,1) = "Files created in this run:"; %#ok<AGROW>

                if isempty(T)
                    lines(end+1,1) = "  <none>"; %#ok<AGROW>
                else
                    nRows = min(height(T), maxRows);
                    for iRow = 1:nRows
                        fileText = "<unknown>";
                        tempText = "";

                        if ismember('FileName', T.Properties.VariableNames)
                            fileText = string(T.FileName(iRow));
                        elseif ismember('FilePath', T.Properties.VariableNames)
                            [~, f, e] = fileparts(char(string(T.FilePath(iRow))));
                            fileText = string([f e]);
                        end

                        if ismember('IsTemporary', T.Properties.VariableNames) && logical(T.IsTemporary(iRow))
                            tempText = " [temporary]";
                        end

                        lines(end+1,1) = "  - " + fileText + tempText; %#ok<AGROW>
                    end

                    if height(T) > maxRows
                        lines(end+1,1) = "  ... " + string(height(T) - maxRows) + " more file(s)"; %#ok<AGROW>
                    end
                end
            end

            finalViewerTable = app.getExecutionResultTable(result, ...
                {'viewerCompatibleFinalOutputs', 'viewerCompatibleLeafOutputs'});
            if ~isempty(finalViewerTable)
                lines(end+1,1) = ""; %#ok<AGROW>
                lines(end+1,1) = "Viewer-compatible final outputs:"; %#ok<AGROW>
                lines = [lines; app.formatOutputManifestTable(finalViewerTable, 20)]; %#ok<AGROW>
            end

            unsavedTable = app.getExecutionResultTable(result, ...
                {'unsavedFinalOutputs', 'unsavedLeafOutputs'});
            if ~isempty(unsavedTable)
                lines(end+1,1) = ""; %#ok<AGROW>
                lines(end+1,1) = "Final outputs not saved:"; %#ok<AGROW>
                lines = [lines; app.formatOutputManifestTable(unsavedTable, 20)]; %#ok<AGROW>
            end

            app.DiagnosticsTextArea.Value = cellstr(lines);
        end

        function T = getExecutionResultTable(~, result, fieldNames)
            %GETEXECUTIONRESULTTABLE Return the first available non-empty result table.

            T = table();

            if ~isstruct(result)
                return
            end

            for iField = 1:numel(fieldNames)
                fieldName = fieldNames{iField};
                if isfield(result, fieldName) && istable(result.(fieldName)) && ~isempty(result.(fieldName))
                    T = result.(fieldName);
                    return
                end
            end
        end

        function lines = formatOutputManifestTable(app, T, maxRows)
            %FORMATOUTPUTMANIFESTTABLE Format output manifest-like rows.

            if nargin < 3 || isempty(maxRows)
                maxRows = 20;
            end

            lines = strings(0,1);

            if isempty(T)
                lines(end+1,1) = "  <none>"; %#ok<AGROW>
                return
            end

            nRows = min(height(T), maxRows);
            for iRow = 1:nRows
                stepText = app.getTableStringValue(T, 'StepTag', iRow, '<step>');
                outputText = app.getTableStringValue(T, 'OutputName', iRow, '<output>');
                fileText = app.getTableStringValue(T, 'FileName', iRow, '');
                if strlength(fileText) == 0
                    fileText = app.getTableStringValue(T, 'FilePath', iRow, '');
                end

                stateText = app.getTableStringValue(T, 'ActualPersistence', iRow, '');
                if strlength(stateText) == 0
                    stateText = app.getTableStringValue(T, 'plannedPersistence', iRow, '');
                end
                if strlength(stateText) > 0
                    stateText = app.formatPersistenceStateForDisplay(stateText);
                end

                line = "  - " + stepText + ":" + outputText;
                if strlength(strtrim(stateText)) > 0
                    line = line + " | " + stateText;
                end
                if strlength(strtrim(fileText)) > 0
                    line = line + " | " + fileText;
                end

                lines(end+1,1) = line; %#ok<AGROW>
            end

            if height(T) > maxRows
                lines(end+1,1) = "  ... " + string(height(T) - maxRows) + " more output(s)"; %#ok<AGROW>
            end
        end

        function value = getTableStringValue(~, T, varName, rowIdx, defaultValue)
            %GETTABLESTRINGVALUE Return a string scalar from a table variable.

            value = string(defaultValue);

            if ~istable(T) || ~ismember(varName, T.Properties.VariableNames) || rowIdx > height(T)
                return
            end

            try
                rawValue = T.(varName)(rowIdx);
                if iscell(rawValue)
                    value = string(rawValue{1});
                else
                    value = string(rawValue);
                end
            catch
                value = string(defaultValue);
            end
        end

        function openMoreActionsWindow(app)
            %OPENMOREACTIONSWINDOW Show a small state-button controlled action window.

            if ~isempty(app.MoreActionsFigure) && isvalid(app.MoreActionsFigure)
                figure(app.MoreActionsFigure);
                return
            end

            app.setTopControlEnableForMoreWindow('off');
            app.MoreButton.Enable = 'on';
            app.MoreButton.Value = true;

            mainPos = app.UIFigure.Position;
            menuW = 280;
            menuH = 170;
            x = mainPos(1) + max(20, (mainPos(3) - menuW) / 2);
            y = mainPos(2) + max(20, (mainPos(4) - menuH) / 2);

            app.MoreActionsFigure = uifigure( ...
                'Name', 'More Actions', ...
                'Position', [x y menuW menuH], ...
                'Resize', 'off', ...
                'WindowStyle', 'normal', ...
                'CloseRequestFcn', @(src, event) app.closeMoreActionsWindow());

            grid = uigridlayout(app.MoreActionsFigure);
            grid.RowHeight = {30, 34, 34, 34, 34};
            grid.ColumnWidth = {'1x'};
            grid.Padding = [10 10 10 10];
            grid.RowSpacing = 6;

            titleLabel = uilabel(grid);
            titleLabel.Text = 'Additional pipeline actions';
            titleLabel.FontWeight = 'bold';
            titleLabel.Layout.Row = 1;
            titleLabel.Layout.Column = 1;

            btn = uibutton(grid, 'push');
            btn.Text = 'Generate Script...';
            btn.Tooltip = 'Export the current pipeline as a standalone MATLAB script.';
            btn.Layout.Row = 2;
            btn.Layout.Column = 1;
            btn.ButtonPushedFcn = @(src, event) app.runMoreAction('generateScript');

            btn = uibutton(grid, 'push');
            btn.Text = 'Show Execution Plan...';
            btn.Tooltip = 'Open the backend execution plan in a dedicated table viewer.';
            btn.Layout.Row = 3;
            btn.Layout.Column = 1;
            btn.ButtonPushedFcn = @(src, event) app.runMoreAction('showExecutionPlan');

            btn = uibutton(grid, 'push');
            btn.Text = 'Export Error Log...';
            btn.Tooltip = 'Export non-completed execution rows from the latest run.';
            btn.Layout.Row = 4;
            btn.Layout.Column = 1;
            btn.ButtonPushedFcn = @(src, event) app.runMoreAction('exportErrorLog');

            btn = uibutton(grid, 'push');
            btn.Text = 'Remove Invalid Steps';
            btn.Tooltip = 'Remove invalid blocking and non-blocking steps using PipelineManager.';
            btn.Layout.Row = 5;
            btn.Layout.Column = 1;
            btn.ButtonPushedFcn = @(src, event) app.runMoreAction('removeInvalidSteps');
        end

        function closeMoreActionsWindow(app)
            %CLOSEMOREACTIONSWINDOW Close the More window and restore top controls.

            try
                if ~isempty(app.MoreActionsFigure) && isvalid(app.MoreActionsFigure)
                    fig = app.MoreActionsFigure;
                    app.MoreActionsFigure = [];
                    delete(fig);
                else
                    app.MoreActionsFigure = [];
                end
            catch
                app.MoreActionsFigure = [];
            end

            app.setTopControlEnableForMoreWindow('on');
            app.updateRamSafetyControlState();

            try
                app.MoreButton.Enable = 'on';
                app.MoreButton.Value = false;
            catch
            end
        end

        function setTopControlEnableForMoreWindow(app, stateValue)
            %SETTOPCONTROLENABLEFORMOREWINDOW Enable/disable top controls except More.

            stateValue = char(string(stateValue));

            try
                topChildren = app.GridLayoutTop.Children;
            catch
                return
            end

            for iChild = 1:numel(topChildren)
                h = topChildren(iChild);

                if isequal(h, app.MoreButton)
                    continue
                end

                if isprop(h, 'Enable')
                    try
                        h.Enable = stateValue;
                    catch
                    end
                end
            end

            % More remains active so the user can toggle the action window closed.
            try
                app.MoreButton.Enable = 'on';
            catch
            end
        end

        function runMoreAction(app, actionName)
            %RUNMOREACTION Dispatch one More-window action.

            actionName = char(string(actionName));
            app.closeMoreActionsWindow();
            drawnow;

            switch lower(actionName)
                case 'generatescript'
                    app.generateScriptInteractive();

                case 'showexecutionplan'
                    app.showExecutionPlanInteractive();

                case 'exporterrorlog'
                    app.exportErrorLogInteractive();

                case 'removeinvalidsteps'
                    app.removeInvalidStepsInteractive();

                case 'clearpipeline'
                    % Kept for backward compatibility with older More windows.
                    app.clearPipelineInteractive();

                otherwise
                    app.appendDiagnostic(sprintf('Unknown More action: %s', actionName));
                    app.setStatus('Unknown More action.');
            end
        end

        function generateScriptInteractive(app)
            %GENERATESCRIPTINTERACTIVE Export current pipeline as a MATLAB script.

            if isempty(app.pm)
                app.appendDiagnostic('Generate-script requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            [fileName, folderName] = uiputfile({'*.m', 'MATLAB script (*.m)'}, ...
                'Generate pipeline script', fullfile(pwd, 'generated_pipeline.m'));

            if isequal(fileName, 0)
                app.appendDiagnostic('Generate-script action cancelled by user.');
                app.setStatus('Generate script cancelled.');
                return
            end

            [~,~,ext] = fileparts(fileName);
            if isempty(ext)
                fileName = [fileName '.m'];
            end

            scriptFile = fullfile(folderName, fileName);

            try
                app.applyExecutionControlsToManager();
                app.pm.generateScript(scriptFile);
                app.appendDiagnostic(sprintf('Generated pipeline script: %s', scriptFile));
                app.setStatus('Pipeline script generated.');
            catch ME
                app.appendDiagnostic(sprintf('Failed to generate pipeline script: %s', ME.message));
                app.setStatus('Failed to generate script.');
            end
        end

        function exportErrorLogInteractive(app)
            %EXPORTERRORLOGINTERACTIVE Export latest pipeline error log.

            if isempty(app.pm)
                app.appendDiagnostic('Export-error-log requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            startFolder = pwd;
            try
                if isprop(app.pm, 'SaveFolderList') && ~isempty(app.pm.SaveFolderList)
                    startFolder = app.pm.SaveFolderList{1};
                end
            catch
            end

            outputFolder = uigetdir(startFolder, 'Select folder for pipeline error log');
            if isequal(outputFolder, 0)
                app.appendDiagnostic('Export-error-log action cancelled by user.');
                app.setStatus('Export error log cancelled.');
                return
            end

            try
                outFile = app.pm.exportPipeErrorLog(outputFolder);
                app.appendDiagnostic(sprintf('Exported pipeline error log: %s', outFile));
                app.setStatus('Pipeline error log exported.');
            catch ME
                app.appendDiagnostic(sprintf('Failed to export pipeline error log: %s', ME.message));
                app.setStatus('Failed to export error log.');
            end
        end

        function clearPipelineInteractive(app)
            %CLEARPIPELINEINTERACTIVE Clear current pipeline with confirmation.

            if isempty(app.pm)
                app.appendDiagnostic('Clear-pipeline requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end
            if isempty(app.pm.nodes)
                app.setStatus('Pipeline is empty.');
                return
            end
            choice = uiconfirm(app.UIFigure, ...
                sprintf(['Clear all steps from the current pipeline?\n\n' ...
                'The selected SaveFolder/RawFolder context and backend settings are kept.']), ...
                'Clear Pipeline', ...
                'Options', {'Clear Pipeline', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(choice, 'Clear Pipeline')
                app.appendDiagnostic('Clear-pipeline action cancelled.');
                app.setStatus('Clear pipeline cancelled.');
                return
            end

            try
                app.pm.clearPipeline();
                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.selectedFunctionName = '';
                app.exitReconnectMode();
                app.refreshAllViews();
                app.appendDiagnostic('Pipeline cleared.');
                app.setStatus('Pipeline cleared.');
            catch ME
                app.appendDiagnostic(sprintf('Failed to clear pipeline: %s', ME.message));
                app.setStatus('Failed to clear pipeline.');
            end
        end

        function setPipelineControlsAvailable(app, tf)
            %SETPIPELINECONTROLSAVAILABLE Enable/disable controls requiring a PipelineManager.
            %
            % Standalone startup should expose only Select Data Folders. Once a
            % PipelineManager context exists, the pipeline-building controls are enabled.

            if tf
                state = 'on';
            else
                state = 'off';
            end

            topControls = { ...
                app.LoadButton, ...
                app.SaveButton, ...
                app.AutoSaveFinalOutputsDropDown, ...
                app.RAMModeDropDown, ...
                app.RAMSafetyPolicyDropDown, ...
                app.RunButton, ...
                app.MoreButton};

            for iCtrl = 1:numel(topControls)
                try
                    topControls{iCtrl}.Enable = state;
                catch
                end
            end

            try
                app.ClearPipelineButton.Enable = state;
            catch
            end

            % Labels are passive, but greying them helps communicate that the
            % execution context has not been created yet.
            topLabels = { ...
                app.AutoSaveFinalOutputsLabel, ...
                app.RAMLabel, ...
                app.SafetyLabel};

            for iLabel = 1:numel(topLabels)
                try
                    topLabels{iLabel}.Enable = state;
                catch
                end
            end

            sideControls = { ...
                app.SearchFunctionEditField, ...
                app.FunctionTree};

            for iCtrl = 1:numel(sideControls)
                try
                    sideControls{iCtrl}.Enable = state;
                catch
                end
            end

            if ~tf
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
            end

            % Always keep this control available: it creates or replaces the
            % execution context.
            app.SelectDataFoldersButton.Enable = 'on';
        end

        function tf = selectDataFoldersInteractive(app)
            %SELECTDATAFOLDERSINTERACTIVE Select SaveFolder/RawFolder pairs.
            %
            % This implementation keeps folder-list management local to the
            % PipelineManagerTool. It supports manual one-by-one folder selection
            % and Java-free multi-folder selection from one parent folder.

            tf = false;

            initialTable = app.getFolderPairTableFromPipelineManager();
            if isempty(initialTable)
                initialTable = app.makeEmptyFolderPairTable();
            end
            initialTable = app.validateFolderPairTable(initialTable);

            dlgW = 1120;
            dlgH = 540;

            mainPos = app.UIFigure.Position;
            dlgX = mainPos(1) + max(20, (mainPos(3) - dlgW) / 2);
            dlgY = mainPos(2) + max(20, (mainPos(4) - dlgH) / 2);

            dlg = uifigure( ...
                'Name', 'Select Data Folders', ...
                'Position', [dlgX dlgY dlgW dlgH], ...
                'Resize', 'off', ...
                'WindowStyle', 'modal');

            dlg.UserData = struct('selectedRows', []);

            mainGrid = uigridlayout(dlg);
            mainGrid.RowHeight = {36, '1x', 42};
            mainGrid.ColumnWidth = {'1x'};
            mainGrid.Padding = [10 10 10 10];
            mainGrid.RowSpacing = 8;

            headerLabel = uilabel(mainGrid);
            headerLabel.Layout.Row = 1;
            headerLabel.Layout.Column = 1;
            headerLabel.Text = ['Build the SaveFolder / RawFolder list used to create the pipeline context. ' ...
                'RawFolder may be Missing for pipelines that do not need raw data.'];
            headerLabel.FontWeight = 'bold';

            tbl = uitable(mainGrid);
            tbl.Layout.Row = 2;
            tbl.Layout.Column = 1;
            tbl.Data = initialTable;
            tbl.ColumnName = {'Use', 'SaveFolder', 'RawFolder', 'Status', 'Notes'};
            tbl.ColumnEditable = [true false false false true];
            tbl.ColumnFormat = {'logical', 'char', 'char', 'char', 'char'};
            tbl.CellSelectionCallback = @onTableSelection;
            tbl.CellEditCallback = @onTableEdited;

            bottomGrid = uigridlayout(mainGrid);
            bottomGrid.Layout.Row = 3;
            bottomGrid.Layout.Column = 1;
            bottomGrid.RowHeight = {'1x'};
            bottomGrid.ColumnWidth = {72, 108, 148, 102, 142, 82, 70, '1x', 76, 76};
            bottomGrid.ColumnSpacing = 6;
            bottomGrid.Padding = [0 0 0 0];

            addPairButton = uibutton(bottomGrid, 'push');
            addPairButton.Text = 'Add Pair';
            addPairButton.Tooltip = 'Add one SaveFolder/RawFolder pair.';
            addPairButton.Layout.Row = 1;
            addPairButton.Layout.Column = 1;
            addPairButton.ButtonPushedFcn = @(~,~) addPair();

            addSaveButton = uibutton(bottomGrid, 'push');
            addSaveButton.Text = 'Add SaveFolder';
            addSaveButton.Tooltip = 'Add one SaveFolder and mark RawFolder as Missing.';
            addSaveButton.Layout.Row = 1;
            addSaveButton.Layout.Column = 2;
            addSaveButton.ButtonPushedFcn = @(~,~) addSaveFolderOnly();

            addSaveParentButton = uibutton(bottomGrid, 'push');
            addSaveParentButton.Text = 'Save From Parent...';
            addSaveParentButton.Tooltip = 'Scan one parent folder and add selected folders as SaveFolders.';
            addSaveParentButton.Layout.Row = 1;
            addSaveParentButton.Layout.Column = 3;
            addSaveParentButton.ButtonPushedFcn = @(~,~) addSaveFoldersFromParent();

            setRawButton = uibutton(bottomGrid, 'push');
            setRawButton.Text = 'Set RawFolder';
            setRawButton.Tooltip = 'Set one RawFolder for the selected row(s).';
            setRawButton.Layout.Row = 1;
            setRawButton.Layout.Column = 4;
            setRawButton.ButtonPushedFcn = @(~,~) setRawFolderForSelection();

            addRawParentButton = uibutton(bottomGrid, 'push');
            addRawParentButton.Text = 'Raw From Parent...';
            addRawParentButton.Tooltip = 'Scan one parent folder and assign selected folders as RawFolders to selected rows.';
            addRawParentButton.Layout.Row = 1;
            addRawParentButton.Layout.Column = 5;
            addRawParentButton.ButtonPushedFcn = @(~,~) addRawFoldersFromParent();

            rawEqualsSaveButton = uibutton(bottomGrid, 'push');
            rawEqualsSaveButton.Text = 'Raw = Save';
            rawEqualsSaveButton.Tooltip = 'Set RawFolder equal to SaveFolder for the selected row(s).';
            rawEqualsSaveButton.Layout.Row = 1;
            rawEqualsSaveButton.Layout.Column = 6;
            rawEqualsSaveButton.ButtonPushedFcn = @(~,~) setRawEqualsSave();

            removeButton = uibutton(bottomGrid, 'push');
            removeButton.Text = 'Remove';
            removeButton.Tooltip = 'Remove selected row(s).';
            removeButton.Layout.Row = 1;
            removeButton.Layout.Column = 7;
            removeButton.ButtonPushedFcn = @(~,~) removeSelectedRows();

            applyButton = uibutton(bottomGrid, 'push');
            applyButton.Text = 'Apply';
            applyButton.FontWeight = 'bold';
            applyButton.Layout.Row = 1;
            applyButton.Layout.Column = 9;
            applyButton.ButtonPushedFcn = @(~,~) applySelection();

            cancelButton = uibutton(bottomGrid, 'push');
            cancelButton.Text = 'Cancel';
            cancelButton.Layout.Row = 1;
            cancelButton.Layout.Column = 10;
            cancelButton.ButtonPushedFcn = @(~,~) cancelSelection();

            dlg.CloseRequestFcn = @(~,~) cancelSelection();

            uiwait(dlg);

            if isvalid(dlg)
                delete(dlg);
            end

            % -------------------------------------------------------------
            % Nested callbacks
            % -------------------------------------------------------------

            function onTableSelection(~, event)
                if isempty(event.Indices)
                    dlg.UserData = struct('selectedRows', []);
                    return
                end

                selectedRows = unique(event.Indices(:,1), 'stable');
                dlg.UserData = struct('selectedRows', selectedRows(:).');
            end

            function onTableEdited(~, ~)
                T = tbl.Data;
                tbl.Data = app.validateFolderPairTable(T);
            end

            function rows = getSelectedRows()
                rows = [];
                if isempty(dlg.UserData) || ~isstruct(dlg.UserData) || ~isfield(dlg.UserData, 'selectedRows')
                    return
                end
                rows = dlg.UserData.selectedRows;
                rows = rows(rows >= 1 & rows <= height(tbl.Data));
            end

            function addPair()
                saveFolder = uigetdir(app.getDefaultFolderSelectionStartFolder('save'), 'Select SaveFolder');
                if isequal(saveFolder, 0)
                    return
                end

                rawFolder = uigetdir(saveFolder, 'Select RawFolder - Cancel to mark as Missing');
                if isequal(rawFolder, 0)
                    rawFolder = 'Missing';
                end

                T = tbl.Data;
                newRow = table(true, string(saveFolder), string(rawFolder), "", "", ...
                    'VariableNames', {'Use','SaveFolder','RawFolder','Status','Notes'});
                T = [T; newRow]; %#ok<AGROW>
                tbl.Data = app.validateFolderPairTable(T);
            end

            function addSaveFolderOnly()
                saveFolder = uigetdir(app.getDefaultFolderSelectionStartFolder('save'), 'Select SaveFolder');
                if isequal(saveFolder, 0)
                    return
                end

                T = tbl.Data;
                newRow = table(true, string(saveFolder), "Missing", "", "", ...
                    'VariableNames', {'Use','SaveFolder','RawFolder','Status','Notes'});
                T = [T; newRow]; %#ok<AGROW>
                tbl.Data = app.validateFolderPairTable(T);
            end

            function addSaveFoldersFromParent()
                selectedFolders = app.selectFoldersFromParentInteractive( ...
                    dlg, ...
                    app.getDefaultFolderSelectionStartFolder('save'), ...
                    'Add SaveFolders From Parent');

                if isempty(selectedFolders)
                    return
                end

                selectedFolders = string(selectedFolders(:));
                T = tbl.Data;

                for iFolder = 1:numel(selectedFolders)
                    newRow = table(true, selectedFolders(iFolder), "Missing", "", "", ...
                        'VariableNames', {'Use','SaveFolder','RawFolder','Status','Notes'});
                    T = [T; newRow]; %#ok<AGROW>
                end

                tbl.Data = app.validateFolderPairTable(T);
            end

            function setRawFolderForSelection()
                rows = getSelectedRows();
                if isempty(rows)
                    uialert(dlg, 'Select one or more table rows first.', 'No Row Selected');
                    return
                end

                rawFolder = uigetdir(app.getDefaultFolderSelectionStartFolder('raw'), 'Select RawFolder');
                if isequal(rawFolder, 0)
                    return
                end

                T = tbl.Data;
                for iRow = rows
                    T.RawFolder(iRow) = string(rawFolder);
                end
                tbl.Data = app.validateFolderPairTable(T);
            end

            function addRawFoldersFromParent()
                rows = getSelectedRows();

                if isempty(rows)
                    uialert(dlg, ...
                        ['Select the SaveFolder row(s) that should receive RawFolder assignments ' ...
                        'before using Raw From Parent.'], ...
                        'No Row Selected');
                    return
                end

                selectedFolders = app.selectFoldersFromParentInteractive( ...
                    dlg, ...
                    app.getDefaultFolderSelectionStartFolder('raw'), ...
                    'Add RawFolders From Parent');

                if isempty(selectedFolders)
                    return
                end

                selectedFolders = string(selectedFolders(:));

                if numel(selectedFolders) ~= numel(rows)
                    uialert(dlg, sprintf(['The number of selected RawFolders must match the number of selected ' ...
                        'SaveFolder rows.\n\nSelected SaveFolder rows: %d\nSelected RawFolders: %d'], ...
                        numel(rows), numel(selectedFolders)), ...
                        'RawFolder Count Mismatch');
                    return
                end

                T = tbl.Data;
                rows = rows(:);
                rows = rows(rows >= 1 & rows <= height(T));

                for iRow = 1:numel(rows)
                    T.RawFolder(rows(iRow)) = selectedFolders(iRow);
                end

                tbl.Data = app.validateFolderPairTable(T);
            end

            function setRawEqualsSave()
                rows = getSelectedRows();
                if isempty(rows)
                    uialert(dlg, 'Select one or more table rows first.', 'No Row Selected');
                    return
                end

                T = tbl.Data;
                for iRow = rows
                    T.RawFolder(iRow) = T.SaveFolder(iRow);
                end
                tbl.Data = app.validateFolderPairTable(T);
            end

            function removeSelectedRows()
                rows = getSelectedRows();
                if isempty(rows)
                    uialert(dlg, 'Select one or more table rows first.', 'No Row Selected');
                    return
                end

                T = tbl.Data;
                T(rows, :) = [];
                dlg.UserData = struct('selectedRows', []);
                tbl.Data = app.validateFolderPairTable(T);
            end

            function applySelection()
                T = app.validateFolderPairTable(tbl.Data);

                if isempty(T) || height(T) == 0
                    uialert(dlg, 'Add at least one SaveFolder/RawFolder pair before applying.', ...
                        'No Folder Pairs');
                    return
                end

                enabledRows = T(T.Use, :);

                if isempty(enabledRows)
                    uialert(dlg, 'Enable at least one folder pair before applying.', ...
                        'No Enabled Folder Pairs');
                    return
                end

                blockingRows = enabledRows(contains(enabledRows.Status, "Missing SaveFolder") | ...
                    contains(enabledRows.Status, "Duplicate SaveFolder"), :);

                if ~isempty(blockingRows)
                    uialert(dlg, ['One or more enabled rows cannot be applied.' newline newline ...
                        'Fix missing or duplicate SaveFolder entries before continuing.'], ...
                        'Invalid Folder Pairs');
                    tbl.Data = T;
                    return
                end

                if ~isempty(app.pm) && isprop(app.pm, 'nodes') && ~isempty(app.pm.nodes)
                    choice = uiconfirm(dlg, ...
                        ['Applying a new folder selection will create a new empty PipelineManager context.' newline newline ...
                        'The current pipeline steps will be replaced in this first implementation.'], ...
                        'Replace Current Pipeline Context', ...
                        'Options', {'Replace Context', 'Cancel'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);

                    if ~strcmp(choice, 'Replace Context')
                        return
                    end
                end

                try
                    app.applyFolderPairTable(T);
                    tf = true;
                    uiresume(dlg);

                catch ME
                    uialert(dlg, ME.message, 'Failed to Apply Folder Pairs');
                end
            end

            function cancelSelection()
                tf = false;
                uiresume(dlg);
            end
        end

        function T = makeEmptyFolderPairTable(~)
            %MAKEEMPTYFOLDERPAIRTABLE Return an empty folder-pair table.

            T = table( ...
                false(0,1), ...
                strings(0,1), ...
                strings(0,1), ...
                strings(0,1), ...
                strings(0,1), ...
                'VariableNames', {'Use','SaveFolder','RawFolder','Status','Notes'});
        end

        function T = getFolderPairTableFromPipelineManager(app)
            %GETFOLDERPAIRTABLEFROMPIPELINEMANAGER Build a folder-pair table from app.pm.

            T = app.makeEmptyFolderPairTable();

            if isempty(app.pm)
                return
            end

            if ~isprop(app.pm, 'SaveFolderList') || isempty(app.pm.SaveFolderList)
                return
            end

            saveFolders = string(app.pm.SaveFolderList(:));

            if isprop(app.pm, 'RawFolderList') && ~isempty(app.pm.RawFolderList)
                rawFolders = string(app.pm.RawFolderList(:));
            else
                rawFolders = repmat("Missing", numel(saveFolders), 1);
            end

            if numel(rawFolders) ~= numel(saveFolders)
                rawFolders = repmat("Missing", numel(saveFolders), 1);
            end

            T = table( ...
                true(numel(saveFolders),1), ...
                saveFolders(:), ...
                rawFolders(:), ...
                strings(numel(saveFolders),1), ...
                strings(numel(saveFolders),1), ...
                'VariableNames', {'Use','SaveFolder','RawFolder','Status','Notes'});

            T = app.validateFolderPairTable(T);
        end

        function T = validateFolderPairTable(~, T)
            %VALIDATEFOLDERPAIRTABLE Validate folder-pair rows for UI display.

            if isempty(T)
                T = table( ...
                    false(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    'VariableNames', {'Use','SaveFolder','RawFolder','Status','Notes'});
                return
            end

            requiredVars = {'Use','SaveFolder','RawFolder','Status','Notes'};
            for iVar = 1:numel(requiredVars)
                if ~ismember(requiredVars{iVar}, T.Properties.VariableNames)
                    switch requiredVars{iVar}
                        case 'Use'
                            T.Use = true(height(T),1);
                        otherwise
                            T.(requiredVars{iVar}) = strings(height(T),1);
                    end
                end
            end

            T.Use = logical(T.Use);
            T.SaveFolder = string(T.SaveFolder);
            T.RawFolder = string(T.RawFolder);
            T.Status = strings(height(T),1);
            T.Notes = string(T.Notes);

            emptyRaw = strlength(strtrim(T.RawFolder)) == 0;
            T.RawFolder(emptyRaw) = "Missing";

            enabledRows = find(T.Use);
            enabledSaveFolders = strtrim(T.SaveFolder(enabledRows));
            duplicateEnabledMask = false(height(T),1);

            for i = 1:numel(enabledRows)
                rowIdx = enabledRows(i);
                sf = enabledSaveFolders(i);
                if strlength(sf) == 0
                    continue
                end
                isDup = sum(strcmpi(enabledSaveFolders, sf)) > 1;
                duplicateEnabledMask(rowIdx) = isDup;
            end

            for iRow = 1:height(T)
                rowIssues = strings(0,1);

                saveFolder = strtrim(T.SaveFolder(iRow));
                rawFolder = strtrim(T.RawFolder(iRow));

                if ~T.Use(iRow)
                    T.Status(iRow) = "Disabled";
                    continue
                end

                if strlength(saveFolder) == 0 || ~isfolder(char(saveFolder))
                    rowIssues(end+1,1) = "Missing SaveFolder"; %#ok<AGROW>
                end

                if duplicateEnabledMask(iRow)
                    rowIssues(end+1,1) = "Duplicate SaveFolder"; %#ok<AGROW>
                end

                if strlength(rawFolder) == 0
                    T.RawFolder(iRow) = "Missing";
                    rawFolder = "Missing";
                end

                if ~strcmpi(rawFolder, "Missing") && ~isfolder(char(rawFolder))
                    rowIssues(end+1,1) = "Missing RawFolder"; %#ok<AGROW>
                end

                if isempty(rowIssues)
                    if strcmpi(rawFolder, "Missing")
                        T.Status(iRow) = "Ready - RawFolder Missing";
                    else
                        T.Status(iRow) = "Ready";
                    end
                else
                    T.Status(iRow) = strjoin(rowIssues, "; ");
                end
            end
        end

        function applyFolderPairTable(app, T)
            %APPLYFOLDERPAIRTABLE Create a new PipelineManager from enabled folder pairs.

            T = app.validateFolderPairTable(T);
            enabledRows = T(T.Use, :);

            if isempty(enabledRows)
                error('PipelineManagerTool:applyFolderPairTable:NoEnabledRows', ...
                    'No enabled folder pairs were provided.');
            end

            blockingRows = enabledRows(contains(enabledRows.Status, "Missing SaveFolder") | ...
                contains(enabledRows.Status, "Duplicate SaveFolder"), :);

            if ~isempty(blockingRows)
                error('PipelineManagerTool:applyFolderPairTable:InvalidRows', ...
                    'One or more enabled rows have invalid SaveFolder entries.');
            end

            saveFolders = cellstr(enabledRows.SaveFolder);
            rawFolders = cellstr(enabledRows.RawFolder);

            rawFolders(cellfun(@isempty, rawFolders)) = {'Missing'};

            % Create the backend object first.
            app.pm = PipelineManager(saveFolders, rawFolders);
            app.FolderPairTable = T;

            % Critical: the visible top-panel settings are the source of truth
            % for a newly created backend context. Do this immediately after
            % object creation, before graph/output planning is refreshed.
            app.configureExecutionControlDropDowns();
            app.applyExecutionControlsToManager();

            app.selectedNodeID = NaN;
            app.selectedStepTag = '';
            app.selectedFunctionName = '';

            app.bReconnectMode = false;
            app.reconnectTargetNodeID = NaN;
            app.reconnectTargetStepTag = '';
            app.reconnectTargetInputName = '';
            app.reconnectCandidateNodeIDs = [];

            app.setPipelineControlsAvailable(true);
            app.updateFolderSelectionButtonSummary();

            app.populateFunctionTree();
            app.refreshAllViews();

            nEnabled = height(enabledRows);
            app.appendDiagnostic(sprintf(['Selected %d folder pair(s) and created a new PipelineManager context. ' ...
                'Execution settings applied from the top panel.'], nEnabled));
            app.setStatus(sprintf('Data folders selected: %d pair(s).', nEnabled));
        end

        function updateFolderSelectionButtonSummary(app)
            %UPDATEFOLDERSELECTIONBUTTONSUMMARY Update Select Data Folders text/tooltip.

            nPairs = 0;
            firstSave = "";
            firstRaw = "";

            if ~isempty(app.FolderPairTable) && istable(app.FolderPairTable) && height(app.FolderPairTable) > 0
                enabledRows = app.FolderPairTable(app.FolderPairTable.Use, :);
                nPairs = height(enabledRows);
                if nPairs > 0
                    firstSave = string(enabledRows.SaveFolder(1));
                    firstRaw = string(enabledRows.RawFolder(1));
                end
            end

            if nPairs == 0
                app.SelectDataFoldersButton.Text = 'Select Data Folders';
                app.SelectDataFoldersButton.Tooltip = { ...
                    'Choose or edit the SaveFolder/RawFolder pairs used to create the pipeline context.'};
            elseif nPairs == 1
                app.SelectDataFoldersButton.Text = 'Data Folders (1)';
                app.SelectDataFoldersButton.Tooltip = {sprintf('1 folder pair selected.\nSaveFolder: %s\nRawFolder: %s', ...
                    char(firstSave), char(firstRaw))};
            else
                app.SelectDataFoldersButton.Text = sprintf('Data Folders (%d)', nPairs);
                app.SelectDataFoldersButton.Tooltip = {sprintf('%d folder pairs selected.\nFirst SaveFolder: %s\nFirst RawFolder: %s', ...
                    nPairs, char(firstSave), char(firstRaw))};
            end
        end

        function selectedFolders = selectFoldersFromParentInteractive(app, parentFig, startFolder, titleText)
            %SELECTFOLDERSFROMPARENTINTERACTIVE Select multiple folders without Java.
            %
            % The user selects one parent folder, optionally scans recursively, then
            % checks one or more candidate folders from a table. A filter field uses
            % case-insensitive contains matching to pre-check and highlight rows.

            selectedFolders = {};

            if nargin < 2 || isempty(parentFig) || ~isvalid(parentFig)
                parentFig = app.UIFigure;
            end

            if nargin < 3 || isempty(startFolder) || ~isfolder(char(string(startFolder)))
                startFolder = pwd;
            end

            if nargin < 4 || isempty(titleText)
                titleText = 'Select Folders From Parent';
            end

            parentFolder = uigetdir(char(string(startFolder)), titleText);
            if isequal(parentFolder, 0)
                return
            end

            dlgW = 980;
            dlgH = 560;

            try
                parentPos = parentFig.Position;
            catch
                parentPos = app.UIFigure.Position;
            end

            dlgX = parentPos(1) + max(20, (parentPos(3) - dlgW) / 2);
            dlgY = parentPos(2) + max(20, (parentPos(4) - dlgH) / 2);

            dlg = uifigure( ...
                'Name', titleText, ...
                'Position', [dlgX dlgY dlgW dlgH], ...
                'Resize', 'off', ...
                'WindowStyle', 'modal');

            dlg.UserData = struct( ...
                'parentFolder', string(parentFolder), ...
                'matchedRows', []);

            mainGrid = uigridlayout(dlg);
            mainGrid.RowHeight = {36, 36, '1x', 42};
            mainGrid.ColumnWidth = {'1x'};
            mainGrid.Padding = [10 10 10 10];
            mainGrid.RowSpacing = 8;

            parentGrid = uigridlayout(mainGrid);
            parentGrid.Layout.Row = 1;
            parentGrid.Layout.Column = 1;
            parentGrid.RowHeight = {'1x'};
            parentGrid.ColumnWidth = {90, '1x', 110, 95};
            parentGrid.Padding = [0 0 0 0];
            parentGrid.ColumnSpacing = 6;

            parentLabel = uilabel(parentGrid);
            parentLabel.Text = 'Parent folder:';
            parentLabel.Layout.Row = 1;
            parentLabel.Layout.Column = 1;

            parentField = uieditfield(parentGrid, 'text');
            parentField.Value = char(string(parentFolder));
            parentField.Editable = 'off';
            parentField.Layout.Row = 1;
            parentField.Layout.Column = 2;

            browseButton = uibutton(parentGrid, 'push');
            browseButton.Text = 'Browse...';
            browseButton.Layout.Row = 1;
            browseButton.Layout.Column = 3;
            browseButton.ButtonPushedFcn = @(~,~) browseParentFolder();

            recursiveCheckBox = uicheckbox(parentGrid);
            recursiveCheckBox.Text = 'Recursive';
            recursiveCheckBox.Value = false;
            recursiveCheckBox.Layout.Row = 1;
            recursiveCheckBox.Layout.Column = 4;
            recursiveCheckBox.ValueChangedFcn = @(~,~) refreshCandidates();

            filterGrid = uigridlayout(mainGrid);
            filterGrid.Layout.Row = 2;
            filterGrid.Layout.Column = 1;
            filterGrid.RowHeight = {'1x'};
            filterGrid.ColumnWidth = {60, '1x', 56, 90, 80};
            filterGrid.Padding = [0 0 0 0];
            filterGrid.ColumnSpacing = 6;

            filterLabel = uilabel(filterGrid);
            filterLabel.Text = 'Filter:';
            filterLabel.Layout.Row = 1;
            filterLabel.Layout.Column = 1;

            filterField = uieditfield(filterGrid, 'text');
            filterField.Placeholder = 'Text contained in folder name or path';
            filterField.Layout.Row = 1;
            filterField.Layout.Column = 2;
            filterField.ValueChangedFcn = @(~,~) previewFilterMatches();

            filterOkButton = uibutton(filterGrid, 'push');
            filterOkButton.Text = 'OK';
            filterOkButton.Tooltip = 'Apply filter selection and remove highlight.';
            filterOkButton.Layout.Row = 1;
            filterOkButton.Layout.Column = 3;
            filterOkButton.ButtonPushedFcn = @(~,~) applyFilterAndClearHighlight();

            selectAllButton = uibutton(filterGrid, 'push');
            selectAllButton.Text = 'Select All';
            selectAllButton.Layout.Row = 1;
            selectAllButton.Layout.Column = 4;
            selectAllButton.ButtonPushedFcn = @(~,~) selectAllCandidates();

            clearButton = uibutton(filterGrid, 'push');
            clearButton.Text = 'Clear';
            clearButton.Layout.Row = 1;
            clearButton.Layout.Column = 5;
            clearButton.ButtonPushedFcn = @(~,~) clearCandidateSelection();

            candTable = uitable(mainGrid);
            candTable.Layout.Row = 3;
            candTable.Layout.Column = 1;
            candTable.ColumnName = {'Use', 'FolderName', 'FullPath', 'Status'};
            candTable.ColumnEditable = [true false false false];
            candTable.ColumnFormat = {'logical', 'char', 'char', 'char'};

            bottomGrid = uigridlayout(mainGrid);
            bottomGrid.Layout.Row = 4;
            bottomGrid.Layout.Column = 1;
            bottomGrid.RowHeight = {'1x'};
            bottomGrid.ColumnWidth = {'1x', 80, 80};
            bottomGrid.Padding = [0 0 0 0];
            bottomGrid.ColumnSpacing = 8;

            infoLabel = uilabel(bottomGrid);
            infoLabel.Layout.Row = 1;
            infoLabel.Layout.Column = 1;
            infoLabel.Text = '';
            infoLabel.FontColor = [0.35 0.35 0.35];

            applyButton = uibutton(bottomGrid, 'push');
            applyButton.Text = 'Apply';
            applyButton.FontWeight = 'bold';
            applyButton.Layout.Row = 1;
            applyButton.Layout.Column = 2;
            applyButton.ButtonPushedFcn = @(~,~) applyCandidateSelection();

            cancelButton = uibutton(bottomGrid, 'push');
            cancelButton.Text = 'Cancel';
            cancelButton.Layout.Row = 1;
            cancelButton.Layout.Column = 3;
            cancelButton.ButtonPushedFcn = @(~,~) cancelCandidateSelection();

            dlg.CloseRequestFcn = @(~,~) cancelCandidateSelection();

            refreshCandidates();
            uiwait(dlg);

            if isvalid(dlg)
                delete(dlg);
            end

            % -------------------------------------------------------------
            % Nested callbacks
            % -------------------------------------------------------------

            function browseParentFolder()
                newParent = uigetdir(char(string(dlg.UserData.parentFolder)), titleText);
                if isequal(newParent, 0)
                    return
                end

                data = dlg.UserData;
                data.parentFolder = string(newParent);
                data.matchedRows = [];
                dlg.UserData = data;

                parentField.Value = char(string(newParent));
                filterField.Value = '';
                refreshCandidates();
            end

            function refreshCandidates()
                data = dlg.UserData;
                parentFolderLocal = char(string(data.parentFolder));

                T = app.scanFolderCandidates(parentFolderLocal, recursiveCheckBox.Value);
                candTable.Data = T;
                filterField.Value = '';
                data.matchedRows = [];
                dlg.UserData = data;

                app.clearTableStylesSafe(candTable);
                updateInfoLabel();
            end

            function previewFilterMatches()
                T = candTable.Data;
                if isempty(T)
                    return
                end

                app.clearTableStylesSafe(candTable);

                filterText = strtrim(string(filterField.Value));
                if strlength(filterText) == 0
                    data = dlg.UserData;
                    data.matchedRows = [];
                    dlg.UserData = data;
                    updateInfoLabel();
                    return
                end

                folderName = lower(string(T.FolderName));
                fullPath = lower(string(T.FullPath));
                token = lower(filterText);

                matchMask = contains(folderName, token) | contains(fullPath, token);

                T.Use(:) = false;
                T.Use(matchMask) = true;
                candTable.Data = T;

                matchedRows = find(matchMask);
                data = dlg.UserData;
                data.matchedRows = matchedRows(:).';
                dlg.UserData = data;

                app.highlightTableRowsSafe(candTable, matchedRows);
                updateInfoLabel();
            end

            function applyFilterAndClearHighlight()
                previewFilterMatches();
                app.clearTableStylesSafe(candTable);

                data = dlg.UserData;
                data.matchedRows = [];
                dlg.UserData = data;

                updateInfoLabel();
            end

            function selectAllCandidates()
                T = candTable.Data;
                if isempty(T)
                    return
                end

                T.Use(:) = true;
                candTable.Data = T;
                app.clearTableStylesSafe(candTable);

                data = dlg.UserData;
                data.matchedRows = [];
                dlg.UserData = data;

                updateInfoLabel();
            end

            function clearCandidateSelection()
                T = candTable.Data;
                if isempty(T)
                    return
                end

                T.Use(:) = false;
                candTable.Data = T;
                filterField.Value = '';
                app.clearTableStylesSafe(candTable);

                data = dlg.UserData;
                data.matchedRows = [];
                dlg.UserData = data;

                updateInfoLabel();
            end

            function applyCandidateSelection()
                T = candTable.Data;

                if isempty(T) || height(T) == 0
                    selectedFolders = {};
                    uiresume(dlg);
                    return
                end

                selectedRows = T(T.Use, :);
                if isempty(selectedRows)
                    uialert(dlg, 'Select at least one folder before applying.', 'No Folders Selected');
                    return
                end

                selectedFolders = cellstr(string(selectedRows.FullPath));
                app.clearTableStylesSafe(candTable);
                uiresume(dlg);
            end

            function cancelCandidateSelection()
                selectedFolders = {};
                uiresume(dlg);
            end

            function updateInfoLabel()
                T = candTable.Data;
                if isempty(T)
                    infoLabel.Text = 'No candidate folders found.';
                    return
                end

                nSelected = sum(T.Use);
                data = dlg.UserData;
                nMatched = 0;
                if isstruct(data) && isfield(data, 'matchedRows')
                    nMatched = numel(data.matchedRows);
                end

                if nMatched > 0
                    infoLabel.Text = sprintf('%d candidate folder(s), %d matched by filter, %d selected.', ...
                        height(T), nMatched, nSelected);
                else
                    infoLabel.Text = sprintf('%d candidate folder(s), %d selected.', ...
                        height(T), nSelected);
                end
            end
        end

        function T = scanFolderCandidates(~, parentFolder, recursiveFlag)
            %SCANFOLDERCANDIDATES Return candidate subfolders under one parent.
            %
            % Output table columns:
            %   Use, FolderName, FullPath, Status

            if nargin < 3 || isempty(recursiveFlag)
                recursiveFlag = false;
            end

            parentFolder = char(string(parentFolder));

            if isempty(parentFolder) || ~isfolder(parentFolder)
                T = table( ...
                    false(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    'VariableNames', {'Use','FolderName','FullPath','Status'});
                return
            end

            folderPaths = strings(0,1);

            if recursiveFlag
                pending = string(parentFolder);

                while ~isempty(pending)
                    currentFolder = pending(1);
                    pending(1) = [];
                    pending = pending(:);

                    d = dir(char(currentFolder));
                    d = d([d.isdir]);

                    if isempty(d)
                        continue
                    end

                    names = string({d.name})';
                    keep = names ~= "." & names ~= "..";
                    names = names(keep);

                    if isempty(names)
                        continue
                    end

                    fullPaths = strings(numel(names), 1);
                    for iName = 1:numel(names)
                        fullPaths(iName) = string(fullfile(char(currentFolder), char(names(iName))));
                    end

                    folderPaths = [folderPaths; fullPaths]; %#ok<AGROW>
                    pending = [pending; fullPaths]; %#ok<AGROW>
                end

            else
                d = dir(parentFolder);
                d = d([d.isdir]);

                if ~isempty(d)
                    names = string({d.name})';
                    keep = names ~= "." & names ~= "..";
                    names = names(keep);

                    folderPaths = strings(numel(names), 1);
                    for iName = 1:numel(names)
                        folderPaths(iName) = string(fullfile(parentFolder, char(names(iName))));
                    end
                end
            end

            if isempty(folderPaths)
                T = table( ...
                    false(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    'VariableNames', {'Use','FolderName','FullPath','Status'});
                return
            end

            folderPaths = unique(folderPaths(:), 'stable');
            [~, ord] = sort(lower(folderPaths));
            folderPaths = folderPaths(ord);

            folderNames = strings(numel(folderPaths), 1);
            status = strings(numel(folderPaths), 1);

            for iFolder = 1:numel(folderPaths)
                [~, folderNames(iFolder)] = fileparts(folderPaths(iFolder));

                if isfolder(char(folderPaths(iFolder)))
                    status(iFolder) = "Ready";
                else
                    status(iFolder) = "Missing";
                end
            end

            T = table( ...
                false(numel(folderPaths), 1), ...
                folderNames(:), ...
                folderPaths(:), ...
                status(:), ...
                'VariableNames', {'Use','FolderName','FullPath','Status'});
        end

        function startFolder = getDefaultFolderSelectionStartFolder(app, mode)
            %GETDEFAULTFOLDERSELECTIONSTARTFOLDER Return a useful folder-picker start path.

            if nargin < 2 || isempty(mode)
                mode = 'save';
            end

            startFolder = pwd;

            try
                if ~isempty(app.FolderPairTable) && istable(app.FolderPairTable) && height(app.FolderPairTable) > 0
                    enabledRows = app.FolderPairTable(app.FolderPairTable.Use, :);

                    if isempty(enabledRows)
                        enabledRows = app.FolderPairTable;
                    end

                    switch lower(char(string(mode)))
                        case 'raw'
                            rawFolders = string(enabledRows.RawFolder);
                            rawFolders = rawFolders(strlength(strtrim(rawFolders)) > 0 & ~strcmpi(rawFolders, "Missing"));
                            if ~isempty(rawFolders) && isfolder(char(rawFolders(1)))
                                startFolder = char(rawFolders(1));
                                return
                            end

                        otherwise
                            saveFolders = string(enabledRows.SaveFolder);
                            saveFolders = saveFolders(strlength(strtrim(saveFolders)) > 0);
                            if ~isempty(saveFolders) && isfolder(char(saveFolders(1)))
                                startFolder = char(saveFolders(1));
                                return
                            end
                    end
                end
            catch
            end

            try
                if ~isempty(app.pm) && isprop(app.pm, 'SaveFolderList') && ~isempty(app.pm.SaveFolderList) && ...
                        isfolder(app.pm.SaveFolderList{1})
                    startFolder = app.pm.SaveFolderList{1};
                end
            catch
            end
        end

        function highlightTableRowsSafe(~, tbl, rows)
            %HIGHLIGHTTABLEROWSSAFE Highlight table rows when uistyle is available.

            if isempty(rows)
                return
            end

            try
                removeStyle(tbl);
            catch
            end

            try
                styleObj = uistyle('BackgroundColor', [1.00 0.95 0.65]);
                addStyle(tbl, styleObj, 'row', rows);
            catch
                % Styling is cosmetic. Ignore unsupported cases.
            end
        end

        function clearTableStylesSafe(~, tbl)
            %CLEARTABLESTYLESSAFE Remove table styles when supported.

            try
                removeStyle(tbl);
            catch
            end
        end

        function statusInfo = addRawFolderWarningToStatusInfo(app, stepLocal, statusInfo)
            %ADDRAWFOLDERWARNINGTOSTATUSINFO Add GUI warning for missing RawFolder.
            %
            % This is a display-only warning. Missing RawFolder can be valid for
            % pipelines that do not use RawFolder. The warning is attached only to
            % steps that explicitly declare a RawFolder input.

            if ~app.hasAnyMissingRawFolder()
                return
            end

            if ~app.stepUsesRawFolder(stepLocal)
                return
            end

            msg = "This step uses RawFolder, but at least one selected folder pair has RawFolder set to Missing.";

            if ~isfield(statusInfo, 'messages') || isempty(statusInfo.messages)
                statusInfo.messages = strings(0,1);
            end

            if ~any(strcmpi(string(statusInfo.messages), msg))
                statusInfo.messages(end+1,1) = msg;
            end

            % Preserve stronger backend states. The graph renderer already gives
            % blocking/non-blocking invalid states priority over warnings.
            if ~isfield(statusInfo, 'hasError') || ~statusInfo.hasError
                if ~isfield(statusInfo, 'hasInvalidNonblocking') || ~statusInfo.hasInvalidNonblocking
                    statusInfo.hasWarning = true;

                    if ~isfield(statusInfo, 'status') || strcmpi(string(statusInfo.status), "valid")
                        statusInfo.status = "warning";
                    end

                    if ~isfield(statusInfo, 'severity') || ...
                            strcmpi(string(statusInfo.severity), "none") || ...
                            strlength(strtrim(string(statusInfo.severity))) == 0
                        statusInfo.severity = "warning";
                    end
                end
            end
        end

        function tf = hasAnyMissingRawFolder(app)
            %HASANYMISSINGRAWFOLDER True if any active RawFolder is Missing.

            tf = false;

            rawFolders = strings(0,1);

            % Prefer the current folder-pair table because it tracks enabled rows.
            try
                if isprop(app, 'FolderPairTable') && istable(app.FolderPairTable) && ...
                        ~isempty(app.FolderPairTable) && height(app.FolderPairTable) > 0

                    T = app.FolderPairTable;
                    if ismember('Use', T.Properties.VariableNames)
                        T = T(logical(T.Use), :);
                    end

                    if ismember('RawFolder', T.Properties.VariableNames)
                        rawFolders = string(T.RawFolder(:));
                    end
                end
            catch
                rawFolders = strings(0,1);
            end

            % Fallback for externally attached PipelineManager objects.
            if isempty(rawFolders)
                try
                    if ~isempty(app.pm) && isprop(app.pm, 'RawFolderList') && ~isempty(app.pm.RawFolderList)
                        rawFolders = string(app.pm.RawFolderList(:));
                    end
                catch
                    rawFolders = strings(0,1);
                end
            end

            if isempty(rawFolders)
                return
            end

            rawFolders = strtrim(rawFolders);
            tf = any(strcmpi(rawFolders, "Missing") | strlength(rawFolders) == 0);
        end

        function tf = stepUsesRawFolder(~, stepLocal)
            %STEPUSESRAWFOLDER True when a step declares a RawFolder input.

            tf = false;

            if isempty(stepLocal) || ~isstruct(stepLocal)
                return
            end

            if ~isfield(stepLocal, 'kind') || ~strcmpi(char(string(stepLocal.kind)), 'stream')
                return
            end

            if ~isfield(stepLocal, 'info') || ~isfield(stepLocal.info, 'inputs') || ...
                    isempty(stepLocal.info.inputs)
                return
            end

            for iIn = 1:numel(stepLocal.info.inputs)
                inDef = stepLocal.info.inputs(iIn);

                inputName = "";
                if isfield(inDef, 'name') && ~isempty(inDef.name)
                    inputName = lower(strtrim(string(inDef.name)));
                end

                inputTypes = strings(0,1);
                if isfield(inDef, 'type') && ~isempty(inDef.type)
                    if ischar(inDef.type) || (isstring(inDef.type) && isscalar(inDef.type))
                        inputTypes = string(inDef.type);
                    else
                        inputTypes = string(inDef.type(:));
                    end
                end

                if strcmp(inputName, "rawfolder") || any(strcmpi(inputTypes, "RawFolder"))
                    tf = true;
                    return
                end
            end
        end

        function cleanupAuxiliaryFigures(app)
            %CLEANUPAUXILIARYFIGURES Delete non-modal child windows owned by the app.
            %
            % This method intentionally avoids restoring control states because it
            % can be called while the main app is being destroyed.

            try
                if ~isempty(app.MoreActionsFigure) && isvalid(app.MoreActionsFigure)
                    fig = app.MoreActionsFigure;
                    app.MoreActionsFigure = [];
                    delete(fig);
                else
                    app.MoreActionsFigure = [];
                end
            catch
                app.MoreActionsFigure = [];
            end

            try
                if ~isempty(app.ExecutionPlanFigure) && isvalid(app.ExecutionPlanFigure)
                    fig = app.ExecutionPlanFigure;
                    app.ExecutionPlanFigure = [];
                    delete(fig);
                else
                    app.ExecutionPlanFigure = [];
                end
            catch
                app.ExecutionPlanFigure = [];
            end
        end

        function closeExecutionPlanWindow(app)
            %CLOSEEXECUTIONPLANWINDOW Close the execution-plan viewer.

            try
                if ~isempty(app.ExecutionPlanFigure) && isvalid(app.ExecutionPlanFigure)
                    fig = app.ExecutionPlanFigure;
                    app.ExecutionPlanFigure = [];
                    delete(fig);
                else
                    app.ExecutionPlanFigure = [];
                end
            catch
                app.ExecutionPlanFigure = [];
            end
        end

        function showExecutionPlanInteractive(app)
            %SHOWEXECUTIONPLANINTERACTIVE Show backend execution plan in UI tables.

            if isempty(app.pm)
                app.appendDiagnostic('Show-execution-plan requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            try
                app.applyExecutionControlsToManager();
                plan = app.getCurrentExecutionPlan();

                if isempty(plan)
                    error('Execution plan is empty.');
                end

                app.closeExecutionPlanWindow();

                mainPos = app.UIFigure.Position;
                figW = 1050;
                figH = 620;
                x = mainPos(1) + max(20, (mainPos(3) - figW) / 2);
                y = mainPos(2) + max(20, (mainPos(4) - figH) / 2);

                app.ExecutionPlanFigure = uifigure( ...
                    'Name', 'Execution Plan', ...
                    'Position', [x y figW figH], ...
                    'Resize', 'on', ...
                    'WindowStyle', 'normal', ...
                    'CloseRequestFcn', @(src, event) app.closeExecutionPlanWindow());

                mainGrid = uigridlayout(app.ExecutionPlanFigure);
                mainGrid.RowHeight = {32, '1x'};
                mainGrid.ColumnWidth = {'1x'};
                mainGrid.Padding = [10 10 10 10];
                mainGrid.RowSpacing = 8;

                nSteps = 0;
                nLinks = 0;
                nOutputs = 0;
                policyText = "";

                if isstruct(plan)
                    if isfield(plan, 'nodeTable') && istable(plan.nodeTable)
                        nSteps = height(plan.nodeTable);
                    end
                    if isfield(plan, 'edgeTable') && istable(plan.edgeTable)
                        nLinks = height(plan.edgeTable);
                    end
                    if isfield(plan, 'outputPlan') && istable(plan.outputPlan)
                        nOutputs = height(plan.outputPlan);
                    end
                    if isfield(plan, 'leafOutputPolicy')
                        policyText = string(plan.leafOutputPolicy);
                    end
                end

                summaryLabel = uilabel(mainGrid);
                summaryLabel.Layout.Row = 1;
                summaryLabel.Layout.Column = 1;
                summaryLabel.FontWeight = 'bold';
                if strlength(policyText) > 0
                    summaryLabel.Text = sprintf('Execution plan: %d step(s), %d data link(s), %d planned output(s). Output policy: %s.', ...
                        nSteps, nLinks, nOutputs, char(policyText));
                else
                    summaryLabel.Text = sprintf('Execution plan: %d step(s), %d data link(s), %d planned output(s).', ...
                        nSteps, nLinks, nOutputs);
                end

                tabGroup = uitabgroup(mainGrid);
                tabGroup.Layout.Row = 2;
                tabGroup.Layout.Column = 1;

                if isfield(plan, 'nodeTable') && istable(plan.nodeTable)
                    app.createExecutionPlanTableTab(tabGroup, 'Steps', plan.nodeTable, 'steps');
                else
                    app.createExecutionPlanTableTab(tabGroup, 'Steps', table(), 'steps');
                end

                if isfield(plan, 'edgeTable') && istable(plan.edgeTable)
                    app.createExecutionPlanTableTab(tabGroup, 'Data Flow', plan.edgeTable, 'dataFlow');
                else
                    app.createExecutionPlanTableTab(tabGroup, 'Data Flow', table(), 'dataFlow');
                end

                if isfield(plan, 'outputPlan') && istable(plan.outputPlan)
                    app.createExecutionPlanTableTab(tabGroup, 'Output Handling', plan.outputPlan, 'outputHandling');
                else
                    app.createExecutionPlanTableTab(tabGroup, 'Output Handling', table(), 'outputHandling');
                end

                if isfield(plan, 'validationReport') && isstruct(plan.validationReport) && ...
                        isfield(plan.validationReport, 'nodeStatus') && istable(plan.validationReport.nodeStatus)
                    app.createExecutionPlanTableTab(tabGroup, 'Diagnostics', plan.validationReport.nodeStatus, 'diagnostics');
                else
                    app.createExecutionPlanTableTab(tabGroup, 'Diagnostics', table(), 'diagnostics');
                end

                app.appendDiagnostic('Opened execution plan window.');
                app.setStatus('Execution plan shown.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to show execution plan: %s', ME.message));
                app.setStatus('Failed to show execution plan.');
            end
        end

        function createExecutionPlanTableTab(app, tabGroup, tabTitle, T, tableKind)
            %CREATEEXECUTIONPLANTABLETAB Create one table tab in the plan viewer.

            tab = uitab(tabGroup);
            tab.Title = tabTitle;

            grid = uigridlayout(tab);
            grid.RowHeight = {'1x'};
            grid.ColumnWidth = {'1x'};
            grid.Padding = [8 8 8 8];

            if ~istable(T) || isempty(T)
                label = uilabel(grid);
                label.Layout.Row = 1;
                label.Layout.Column = 1;
                label.Text = sprintf('No %s information available.', lower(tabTitle));
                label.HorizontalAlignment = 'center';
                label.FontColor = [0.45 0.45 0.45];
                return
            end

            [displayTable, columnNames] = app.prepareExecutionPlanDisplayTable(T, tableKind);

            tbl = uitable(grid);
            tbl.Layout.Row = 1;
            tbl.Layout.Column = 1;
            tbl.Data = displayTable;
            tbl.ColumnName = columnNames;
            tbl.ColumnEditable = false(1, width(displayTable));
            tbl.RowName = 'numbered';
        end

        function [displayTable, columnNames] = prepareExecutionPlanDisplayTable(app, T, tableKind)
            %PREPAREEXECUTIONPLANDISPLAYTABLE Convert backend tables to UI-friendly tables.

            if nargin < 3
                tableKind = '';
            end

            displayTable = T;

            for iVar = 1:width(displayTable)
                varName = displayTable.Properties.VariableNames{iVar};
                values = displayTable.(varName);

                if iscell(values)
                    displayTable.(varName) = string(cellfun( ...
                        @(x) app.formatTableValueForDisplay(x), ...
                        values, ...
                        'UniformOutput', false));

                elseif isstruct(values)
                    displayTable.(varName) = arrayfun( ...
                        @(x) string(app.formatTableValueForDisplay(x)), ...
                        values);

                elseif isnumeric(values) || islogical(values) || isstring(values) || ...
                        isdatetime(values) || isduration(values) || iscategorical(values)
                    % These are supported by uitable/table display.
                else
                    displayTable.(varName) = arrayfun( ...
                        @(x) string(app.formatTableValueForDisplay(x)), ...
                        values);
                end
            end

            if strcmpi(tableKind, 'outputHandling')
                if ismember('plannedPersistence', displayTable.Properties.VariableNames)
                    displayTable.plannedPersistence = arrayfun( ...
                        @(x) string(app.formatPersistenceStateForDisplay(x)), ...
                        string(displayTable.plannedPersistence));
                end

                if ismember('reason', displayTable.Properties.VariableNames)
                    displayTable.reason = arrayfun( ...
                        @(x) string(app.formatOutputPlanReasonForDisplay(x)), ...
                        string(displayTable.reason));
                end
            end

            columnNames = app.getExecutionPlanColumnNames(displayTable.Properties.VariableNames, tableKind);
        end

        function txt = formatTableValueForDisplay(app, valueIn)
            %FORMATTABLEVALUEFORDISPLAY Convert one table element to readable text.

            if isempty(valueIn)
                txt = "";
                return
            end

            if ischar(valueIn) || (isstring(valueIn) && isscalar(valueIn))
                txt = char(string(valueIn));
                return
            end

            if iscell(valueIn)
                if isempty(valueIn)
                    txt = "";
                    return
                end

                try
                    asText = string(valueIn(:));
                    asText = asText(strlength(strtrim(asText)) > 0);
                    txt = char(strjoin(asText, ', '));
                catch
                    txt = sprintf('[%s cell]', strjoin(string(size(valueIn)), 'x'));
                end
                return
            end

            if isnumeric(valueIn) || islogical(valueIn)
                if isscalar(valueIn) || (isvector(valueIn) && numel(valueIn) <= 8)
                    txt = mat2str(valueIn);
                else
                    txt = sprintf('[%s %s]', strjoin(string(size(valueIn)), 'x'), class(valueIn));
                end
                return
            end

            if isdatetime(valueIn) || isduration(valueIn) || iscategorical(valueIn)
                txt = char(string(valueIn));
                return
            end

            if isstruct(valueIn)
                if isscalar(valueIn)
                    txt = sprintf('[struct: %s]', strjoin(string(fieldnames(valueIn)), ', '));
                else
                    txt = sprintf('[%s struct]', strjoin(string(size(valueIn)), 'x'));
                end
                return
            end

            txt = app.formatValueForDisplay(valueIn);
        end

        function columnNames = getExecutionPlanColumnNames(~, varNames, tableKind)
            %GETEXECUTIONPLANCOLUMNNAMES Return user-facing column labels.

            if nargin < 3
                tableKind = '';
            end

            columnNames = cellstr(string(varNames));

            for iName = 1:numel(columnNames)
                nameLower = lower(columnNames{iName});

                switch nameLower
                    case 'nodeid'
                        columnNames{iName} = 'Step ID';
                    case {'stepname', 'steptag'}
                        columnNames{iName} = 'Step';
                    case 'functionname'
                        columnNames{iName} = 'Function';
                    case 'kind'
                        columnNames{iName} = 'Kind';
                    case 'topoindex'
                        columnNames{iName} = 'Topological order';
                    case 'execindex'
                        columnNames{iName} = 'Execution order';
                    case 'isroot'
                        columnNames{iName} = 'Starts branch';
                    case 'isleaf'
                        columnNames{iName} = 'Final step';
                    case 'isfinaloutput'
                        columnNames{iName} = 'Final output';
                    case 'sourcenodeid'
                        columnNames{iName} = 'Source step ID';
                    case 'sourcestep'
                        columnNames{iName} = 'Source step';
                    case 'sourceoutput'
                        columnNames{iName} = 'Source output';
                    case 'selectedfile'
                        columnNames{iName} = 'Selected file';
                    case 'targetnodeid'
                        columnNames{iName} = 'Target step ID';
                    case 'targetstep'
                        columnNames{iName} = 'Target step';
                    case 'targetinput'
                        columnNames{iName} = 'Target input';
                    case 'sourcetypes'
                        columnNames{iName} = 'Source type';
                    case 'outputname'
                        columnNames{iName} = 'Output';
                    case 'outputmode'
                        columnNames{iName} = 'Output mode';
                    case 'plannedpersistence'
                        columnNames{iName} = 'Handling';
                    case 'plannedfilename'
                        columnNames{iName} = 'Planned file';
                    case 'reason'
                        columnNames{iName} = 'Reason';
                    case 'status'
                        columnNames{iName} = 'Status';
                    case 'severity'
                        columnNames{iName} = 'Severity';
                    case 'messages'
                        columnNames{iName} = 'Messages';
                    otherwise
                        % Keep backend name if no user-facing alias is known.
                end
            end

            %#ok<NASGU> tableKind is kept for future table-specific aliases.
        end

    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.initializeUiState();

            try
                app.ensureHtmlFilesExist();
                app.appendDiagnostic(sprintf('Loaded graph HTML: %s', app.graphHtmlFile));
                app.appendDiagnostic(sprintf('Loaded execution HTML: %s', app.execHtmlFile));
            catch ME
                app.appendDiagnostic(sprintf('Failed to initialize HTML components: %s', ME.message));
                app.setStatus('Failed to initialize HTML components.');
            end

            app.populateFunctionTree();
            app.refreshAllViews();

            % Standalone startup requires an explicit folder-context selection.
            % All pipeline-dependent controls remain disabled until Select Data
            % Folders creates or attaches a PipelineManager object.
            app.setPipelineControlsAvailable(false);
            app.updateFolderSelectionButtonSummary();

            app.appendDiagnostic('App initialized. Select data folders to start.');
            app.setStatus('Select data folders to start.');


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.pm)
                app.appendDiagnostic('Load requested before selecting data folders.');
                app.setStatus('Select data folders before loading a pipeline.');
                return
            end

            [fileName, folderName] = uigetfile({'*.pipe', 'Pipeline files (*.pipe)'}, 'Load pipeline');
            if isequal(fileName, 0)
                app.appendDiagnostic('Load pipeline cancelled by user.');
                app.setStatus('Load cancelled.');
                return
            end

            pipeFile = fullfile(folderName, fileName);

            try
                app.pm.loadPipe(pipeFile);

                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.exitReconnectMode();

                app.FolderPairTable = app.getFolderPairTableFromPipelineManager();
                app.setPipelineControlsAvailable(true);
                app.updateFolderSelectionButtonSummary();


                try
                    app.syncExecutionControlsFromManager();
                catch ME
                    app.appendDiagnostic(sprintf('Loaded pipeline, but failed to sync execution controls: %s', ME.message));
                end

                app.populateFunctionTree();
                app.refreshAllViews();

                try
                    report = app.pm.diagnosePipeline('verbose', false);
                    app.DiagnosticsTextArea.Value = app.formatDiagnosticReport(report);
                catch
                end

                app.appendDiagnostic(sprintf('Loaded pipeline: %s', pipeFile));
                app.setStatus('Pipeline loaded.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to load pipeline: %s', ME.message));
                app.setStatus('Failed to load pipeline.');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.pm)
                app.appendDiagnostic('Save requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            [fileName, folderName] = uiputfile({'*.pipe', 'Pipeline files (*.pipe)'}, ...
                'Save pipeline as', fullfile(pwd, 'MyPipeline.pipe'));

            if isequal(fileName, 0)
                app.appendDiagnostic('Save pipeline cancelled by user.');
                app.setStatus('Save cancelled.');
                return
            end

            [~,~,ext] = fileparts(fileName);
            if isempty(ext)
                fileName = [fileName '.pipe'];
            end

            pipeFile = fullfile(folderName, fileName);

            try
                app.applyExecutionControlsToManager();
                app.pm.savePipe(pipeFile);
                app.appendDiagnostic(sprintf('Saved pipeline to %s', pipeFile));
                app.setStatus('Pipeline saved.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to save pipeline: %s', ME.message));
                app.setStatus('Failed to save pipeline.');
            end

        end

        % Value changed function: SearchFunctionEditField
        function SearchFunctionEditFieldValueChanged(app, event)
            app.populateFunctionTree();

            if isempty(strtrim(char(string(app.SearchFunctionEditField.Value))))
                app.appendDiagnostic('Function search cleared.');
            else
                app.appendDiagnostic(sprintf('Function search filter updated: %s', char(string(app.SearchFunctionEditField.Value))));
            end

            app.setStatus('Function tree updated.');


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.tryAddSelectedFunction(false);
        end

        % Button pushed function: AddasNewBranchButton
        function AddasNewBranchButtonPushed(app, event)
            app.tryAddSelectedFunction(true);
        end

        % Data changed function: GraphHTML
        function GraphHTMLDataChanged(app, event)
            % This callback is currently not used.
            % Graph interactions are handled through HTMLEventReceived.


        end

        % Event received function: GraphHTML
        function GraphHTMLEventReceived(app, event)
            eventName = lower(char(string(event.HTMLEventName)));
            eventData = event.HTMLEventData;

            switch eventName

                case 'htmlready'
                    if isstruct(eventData) && isfield(eventData, 'component') && strcmpi(char(string(eventData.component)), 'graph')
                        app.bGraphHtmlReady = true;
                        app.refreshGraphView();
                        app.appendDiagnostic('HTML graph is ready.');
                        app.setStatus('Graph view ready.');
                    end

                case 'graphrendered'
                    % No action needed.

                case 'nodeselected'
                    if isstruct(eventData) && isfield(eventData, 'nodeID')
                        app.selectedNodeID = double(eventData.nodeID);

                        if isfield(eventData, 'stepTag')
                            app.selectedStepTag = char(string(eventData.stepTag));
                        else
                            app.selectedStepTag = '';
                        end

                        app.refreshSelectedStepPanel();
                        app.refreshGraphView();
                        app.refreshExecutionView();

                        if ~isempty(app.selectedStepTag)
                            app.setStatus(['Selected step: ' app.selectedStepTag]);
                        else
                            app.setStatus('Step selected.');
                        end
                    end

                case 'reconnectsourcechosen'
                    if isstruct(eventData) && isfield(eventData, 'ref')
                        chosenRef = char(string(eventData.ref));
                        if ~isempty(chosenRef)
                            app.completeReconnectWithRef(chosenRef);
                        end
                    end

                case 'reconnectusefile'
                    if app.bReconnectMode
                        chosenRef = app.promptFileInputReference(app.reconnectTargetInputName);
                        if isempty(chosenRef)
                            app.appendDiagnostic('Reconnect file selection cancelled.');
                            app.setStatus('Reconnect still active.');
                        else
                            app.completeReconnectWithRef(chosenRef);
                        end
                    end

                case 'cancelreconnect'
                    if app.bReconnectMode
                        app.exitReconnectMode();
                        app.appendDiagnostic('Reconnect mode cancelled.');
                        app.setStatus('Reconnect cancelled.');
                    end

                case 'contextaction'
                    if ~isstruct(eventData) || ~isfield(eventData, 'action')
                        return
                    end

                    action = lower(char(string(eventData.action)));
                    stepTag = '';

                    if isfield(eventData, 'stepTag')
                        stepTag = char(string(eventData.stepTag));
                    end

                    switch action

                        case 'removestep'
                            if isempty(stepTag) || isempty(app.pm)
                                return
                            end

                            choice = uiconfirm(app.UIFigure, ...
                                sprintf('Remove step "%s"?', stepTag), ...
                                'Remove Step', ...
                                'Options', {'Remove', 'Cancel'}, ...
                                'DefaultOption', 2, ...
                                'CancelOption', 2);

                            if ~strcmp(choice, 'Remove')
                                app.appendDiagnostic('Remove-step action cancelled.');
                                app.setStatus('Remove-step cancelled.');
                                return
                            end

                            try
                                app.pm.rmStep(stepTag);

                                if strcmpi(app.selectedStepTag, stepTag)
                                    app.selectedNodeID = NaN;
                                    app.selectedStepTag = '';
                                end

                                app.exitReconnectMode();
                                app.refreshAllViews();
                                app.appendDiagnostic(sprintf('Removed step "%s".', stepTag));
                                app.setStatus('Step removed.');

                            catch ME
                                app.appendDiagnostic(sprintf('Failed to remove step "%s": %s', stepTag, ME.message));
                                app.setStatus('Failed to remove step.');
                            end

                        case 'editparameters'
                            if isempty(stepTag) || isempty(app.pm)
                                return
                            end

                            try
                                app.pm.setParameters(stepTag);
                                app.refreshAllViews();
                                app.appendDiagnostic(sprintf('Parameter dialog completed for "%s".', stepTag));
                                app.setStatus('Parameters updated.');

                            catch ME
                                app.appendDiagnostic(sprintf('Failed to edit parameters for "%s": %s', stepTag, ME.message));
                                app.setStatus('Failed to edit parameters.');
                            end

                        case 'reconnectstep'
                            if isempty(stepTag)
                                return
                            end
                            app.reconnectStepInteractive(stepTag);

                        case 'configuresaveoutputs'
                            if isempty(stepTag)
                                return
                            end
                            app.configureSaveTargetsInteractive(stepTag);

                        otherwise
                            app.appendDiagnostic(sprintf('Unknown graph context action: %s', action));
                            app.setStatus('Unknown graph action.');
                    end
            end

        end

        % Data changed function: ExecGraphHTML
        function ExecGraphHTMLDataChanged(app, event)
            % This callback is currently not used.
            % Execution-view interactions are handled through HTMLEventReceived.


        end

        % Event received function: ExecGraphHTML
        function ExecGraphHTMLEventReceived(app, event)

            eventName = lower(char(string(event.HTMLEventName)));
            eventData = event.HTMLEventData;

            switch eventName

                case 'htmlready'
                    if isstruct(eventData) && isfield(eventData, 'component') && strcmpi(char(string(eventData.component)), 'execution')
                        app.bExecHtmlReady = true;
                        app.refreshExecutionView();
                        app.appendDiagnostic('Execution-order HTML view is ready.');
                        app.setStatus('Execution view ready.');
                    end

                case 'stepselected'
                    if isstruct(eventData) && isfield(eventData, 'nodeID')
                        app.selectedNodeID = double(eventData.nodeID);

                        if isfield(eventData, 'stepTag')
                            app.selectedStepTag = char(string(eventData.stepTag));
                        else
                            app.selectedStepTag = '';
                        end

                        app.refreshSelectedStepPanel();
                        app.refreshGraphView();
                        app.refreshExecutionView();

                        if ~isempty(app.selectedStepTag)
                            app.setStatus(['Selected step: ' app.selectedStepTag]);
                        else
                            app.setStatus('Execution step selected.');
                        end
                    end
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.cleanupAuxiliaryFigures();
            delete(app)
        end

        % Selection changed function: FunctionTree
        function FunctionTreeSelectionChanged(app, event)

            selectedNodes = app.FunctionTree.SelectedNodes;

            if isempty(selectedNodes)
                app.selectedFunctionName = '';
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
                return
            end

            selectedNode = selectedNodes(1);
            nodeData = selectedNode.NodeData;

            if isempty(nodeData) || ~isstruct(nodeData) || ~isfield(nodeData, 'kind')
                app.selectedFunctionName = '';
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
                return
            end

            if strcmpi(nodeData.kind, 'function') && isfield(nodeData, 'name')
                app.selectedFunctionName = char(string(nodeData.name));
                app.AddButton.Enable = 'on';
                app.AddasNewBranchButton.Enable = 'on';
                app.appendDiagnostic(sprintf('Selected function: %s', app.selectedFunctionName));
                app.setStatus('Function selected.');
            else
                app.selectedFunctionName = '';
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
            end

        end

        % Button pushed function: SelectDataFoldersButton
        function SelectDataFoldersButtonPushed(app, event)
            app.selectDataFoldersInteractive();

        end

        % Value changed function: AutoSaveFinalOutputsDropDown
        function AutoSaveFinalOutputsDropDownValueChanged(app, event)
            value = app.AutoSaveFinalOutputsDropDown.Value;

            if isempty(app.pm)
                app.appendDiagnostic(sprintf('Auto-save final outputs setting changed: %s', char(string(value))));
                app.setStatus('Output policy updated.');
                return
            end

            try
                app.pm.setLeafOutputPolicy(value);
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('Auto-save final outputs setting changed: %s', char(string(value))));
                app.setStatus('Output policy updated.');
            catch ME
                app.appendDiagnostic(sprintf('Failed to update output policy: %s', ME.message));
                app.syncExecutionControlsFromManager();
                app.setStatus('Failed to update output policy.');
            end

        end

        % Value changed function: RAMModeDropDown
        function RAMModeDropDownValueChanged(app, event)
            value = app.RAMModeDropDown.Value;
            app.updateRamSafetyControlState();

            if isempty(app.pm)
                app.appendDiagnostic(sprintf('RAM mode changed: %s', char(string(value))));
                app.setStatus('RAM mode updated.');
                return
            end

            try
                app.pm.ramMode = char(string(value));
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('RAM mode changed: %s', char(string(value))));
                app.setStatus('RAM mode updated.');
            catch ME
                app.appendDiagnostic(sprintf('Failed to update RAM mode: %s', ME.message));
                app.syncExecutionControlsFromManager();
                app.setStatus('Failed to update RAM mode.');
            end

        end

        % Value changed function: RAMSafetyPolicyDropDown
        function RAMSafetyPolicyDropDownValueChanged(app, event)
            value = app.RAMSafetyPolicyDropDown.Value;

            if isempty(app.pm)
                app.appendDiagnostic(sprintf('RAM safety policy changed: %s', char(string(value))));
                app.setStatus('RAM safety updated.');
                return
            end

            try
                app.pm.ramSafePolicy = char(string(value));
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('RAM safety policy changed: %s', char(string(value))));
                app.setStatus('RAM safety updated.');
            catch ME
                app.appendDiagnostic(sprintf('Failed to update RAM safety policy: %s', ME.message));
                app.syncExecutionControlsFromManager();
                app.setStatus('Failed to update RAM safety.');
            end

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.runPipelineFromGui();

        end

        % Value changed function: MoreButton
        function MoreButtonValueChanged(app, event)
            if app.MoreButton.Value
                app.openMoreActionsWindow();
            else
                app.closeMoreActionsWindow();
            end

        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)

            app.clearPipelineInteractive();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutTop = uigridlayout(app.GridLayout);
            app.LoadButton = uibutton(app.GridLayoutTop, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load a saved pipeline definition from a .pipe file.'};
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayoutTop, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save the current pipeline definition to a .pipe file.'};
            app.SaveButton.Text = 'Save';
            app.SelectDataFoldersButton = uibutton(app.GridLayoutTop, 'push');
            app.SelectDataFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFoldersButtonPushed, true);
            app.SelectDataFoldersButton.Tooltip = {'Choose or edit the SaveFolder/RawFolder pairs used to create the pipeline context.'};
            app.SelectDataFoldersButton.Text = 'Select Data Folders';
            app.RunButton = uibutton(app.GridLayoutTop, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Execute the current pipeline using the selected folder pairs and execution settings.'};
            app.RunButton.Text = 'Run';
            app.AutoSaveFinalOutputsDropDown = uidropdown(app.GridLayoutTop);
            app.AutoSaveFinalOutputsDropDown.Items = {'Disabled', 'Enabled', 'Enabled as temporary files'};
            app.AutoSaveFinalOutputsDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoSaveFinalOutputsDropDownValueChanged, true);
            app.AutoSaveFinalOutputsDropDown.Tooltip = {'Controls whether final pipeline outputs are automatically saved. Manually selected outputs are always saved.'};
            app.AutoSaveFinalOutputsDropDown.Placeholder = 'Output Policy';
            app.AutoSaveFinalOutputsDropDown.Value = 'Disabled';
            app.RAMModeDropDown = uidropdown(app.GridLayoutTop);
            app.RAMModeDropDown.Items = {'Auto', 'RAM-safe'};
            app.RAMModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RAMModeDropDownValueChanged, true);
            app.RAMModeDropDown.Tooltip = {'Choose how pipeline data is passed between steps: automatically or using file-backed RAM-safe execution.'};
            app.RAMModeDropDown.Placeholder = 'RAM Mode';
            app.RAMModeDropDown.Value = 'Auto';
            app.RAMSafetyPolicyDropDown = uidropdown(app.GridLayoutTop);
            app.RAMSafetyPolicyDropDown.Items = {'Strict', 'Best effort'};
            app.RAMSafetyPolicyDropDown.ValueChangedFcn = createCallbackFcn(app, @RAMSafetyPolicyDropDownValueChanged, true);
            app.RAMSafetyPolicyDropDown.Tooltip = {'Choose how strictly RAM-safe mode handles steps that cannot receive file-backed inputs.'};
            app.RAMSafetyPolicyDropDown.Placeholder = 'RAM safety';
            app.RAMSafetyPolicyDropDown.Value = 'Best effort';
            app.AutoSaveFinalOutputsLabel = uilabel(app.GridLayoutTop);
            app.AutoSaveFinalOutputsLabel.Text = 'Auto-save final outputs:';
            app.RAMLabel = uilabel(app.GridLayoutTop);
            app.RAMLabel.Text = 'RAM:';
            app.SafetyLabel = uilabel(app.GridLayoutTop);
            app.SafetyLabel.Text = 'Safety:';
            app.Sep1 = uipanel(app.GridLayoutTop);
            app.Sep2 = uipanel(app.GridLayoutTop);
            app.MoreButton = uibutton(app.GridLayoutTop, 'state');
            app.MoreButton.ValueChangedFcn = createCallbackFcn(app, @MoreButtonValueChanged, true);
            app.MoreButton.Text = 'More...';
            app.AvailableFunctionsPanel = uipanel(app.GridLayout);
            app.AvailableFunctionsPanel.Title = 'Available Functions';
            app.GridAvailableFunctions = uigridlayout(app.AvailableFunctionsPanel);
            app.SearchFunctionEditField = uieditfield(app.GridAvailableFunctions, 'text');
            app.SearchFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @SearchFunctionEditFieldValueChanged, true);
            app.SearchFunctionEditField.Placeholder = 'Search function ...';
            app.FunctionTree = uitree(app.GridAvailableFunctions);
            app.FunctionTree.SelectionChangedFcn = createCallbackFcn(app, @FunctionTreeSelectionChanged, true);
            app.AddButton = uibutton(app.GridAvailableFunctions, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add ';
            app.AddasNewBranchButton = uibutton(app.GridAvailableFunctions, 'push');
            app.AddasNewBranchButton.ButtonPushedFcn = createCallbackFcn(app, @AddasNewBranchButtonPushed, true);
            app.AddasNewBranchButton.Enable = 'off';
            app.AddasNewBranchButton.Text = 'Add as New Branch';
            app.PipelineGraphPanel = uipanel(app.GridLayout);
            app.PipelineGraphPanel.Title = 'Pipeline Graph ';
            app.GridPipelineGraph = uigridlayout(app.PipelineGraphPanel);
            app.GraphHTML = uihtml(app.GridPipelineGraph);
            app.GraphHTML.DataChangedFcn = createCallbackFcn(app, @GraphHTMLDataChanged, true);
            app.GraphHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @GraphHTMLEventReceived, true);
            app.ClearPipelineButton = uibutton(app.GridPipelineGraph, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.SelectedstepdetailsPanel = uipanel(app.GridLayout);
            app.SelectedstepdetailsPanel.Title = 'Selected step details';
            app.GridSelectedStepDetails = uigridlayout(app.SelectedstepdetailsPanel);
            app.StepNameLabel = uilabel(app.GridSelectedStepDetails);
            app.StepNameLabel.Text = 'Step';
            app.StepTagLabel = uilabel(app.GridSelectedStepDetails);
            app.StepTagLabel.Text = 'Step Tag';
            app.StepTagField = uieditfield(app.GridSelectedStepDetails, 'text');
            app.StepTagField.Editable = 'off';
            app.StepSummaryLabel = uilabel(app.GridSelectedStepDetails);
            app.StepSummaryLabel.Text = 'Step Summary';
            app.StepSummaryTextArea = uitextarea(app.GridSelectedStepDetails);
            app.StepSummaryTextArea.Editable = 'off';
            app.ParametersLabel = uilabel(app.GridSelectedStepDetails);
            app.ParametersLabel.Text = 'Parameters';
            app.ParametersTextArea = uitextarea(app.GridSelectedStepDetails);
            app.ParametersTextArea.Editable = 'off';
            app.FunctionNameField = uieditfield(app.GridSelectedStepDetails, 'text');
            app.FunctionNameField.Editable = 'off';
            app.ExecutionOrderPanel = uipanel(app.GridLayout);
            app.ExecutionOrderPanel.Title = 'Execution Order';
            app.GridExecutionOrder = uigridlayout(app.ExecutionOrderPanel);
            app.ExecGraphHTML = uihtml(app.GridExecutionOrder);
            app.ExecGraphHTML.DataChangedFcn = createCallbackFcn(app, @ExecGraphHTMLDataChanged, true);
            app.ExecGraphHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ExecGraphHTMLEventReceived, true);
            app.DiagnosticsPanel = uipanel(app.GridLayout);
            app.DiagnosticsPanel.Title = 'Diagnostics';
            app.GridDiagnostics = uigridlayout(app.DiagnosticsPanel);
            app.DiagnosticsTextArea = uitextarea(app.GridDiagnostics);
            app.DiagnosticsTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.GridDiagnostics);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineManagerTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end