classdef ProjectManagerTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayoutBottom                matlab.ui.container.GridLayout
        SelectedItemPanel               matlab.ui.container.Panel
        SelectedItemGrid                matlab.ui.container.GridLayout
        MetadataTabGroup                matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SelectedItemBottomGrid          matlab.ui.container.GridLayout
        DeleteProjectButton             matlab.ui.control.Button
        RemoveSubjectButton             matlab.ui.control.Button
        BindRawFolderButton             matlab.ui.control.Button
        RemoveSessionFromProjectButton  matlab.ui.control.Button
        OpenSaveFolderButton            matlab.ui.control.Button
        RepairSaveFolderButton          matlab.ui.control.Button
        RebindSaveFolderButton          matlab.ui.control.Button
        OpenResourceLocationButton      matlab.ui.control.Button
        RestoreResourceButton           matlab.ui.control.Button
        ArchiveResourceButton           matlab.ui.control.Button
        SetActiveResourceButton         matlab.ui.control.Button
        MoveSessionButton               matlab.ui.control.Button
        AddSubjectButton                matlab.ui.control.Button
        AddImageReferenceButton         matlab.ui.control.Button
        AddSessionButton                matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
        SaveChangesButton               matlab.ui.control.Button
        ItemHeaderLabel                 matlab.ui.control.Label
        ProjectStructurePanel           matlab.ui.container.Panel
        ProjectStructureGrid            matlab.ui.container.GridLayout
        ProjectTree                     matlab.ui.container.Tree
        GridLayoutTop                   matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        ValidationModeDropDown          matlab.ui.control.DropDown
        ValidateButton                  matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        NewProjectButton                matlab.ui.control.Button
        OpenProjectButton               matlab.ui.control.Button
    end

    properties (Access = private)
        Store = []
        NodeMap
        CurrentNodeKey = ''
        Editor = struct()
        IsDirty = false
        IsSelectionGuard = false
        IsClosing = false
    end

    methods (Access = private)

        function initializeUI(app)
            app.UIFigure.Name = 'UMIT Project Manager';
            app.ValidationModeDropDown.Items = {'Quick', 'Full'};
            app.ValidationModeDropDown.Value = 'Quick';
            app.ValidationModeDropDown.Tooltip = sprintf([ ...
                'Quick: metadata, registry, folder, and binding checks ' ...
                'without recomputing file checksums.\n' ...
                'Full: Quick validation plus SHA-256 verification of ' ...
                'managed resource contents.']);
            app.ValidateButton.Tooltip = ...
                'Validate project consistency using the selected mode.';
            % Preserve the four-column layout configured in Design View.
            if numel(app.SelectedItemBottomGrid.ColumnWidth) < 4
                app.SelectedItemBottomGrid.ColumnWidth = ...
                    {'1x', '1x', '1x', '1x'};
            end
            app.SelectedItemBottomGrid.RowHeight = {28, 28};

            app.hideAllActionButtons();
            app.clearMetadataTabs();
            app.ItemHeaderLabel.Text = 'No item selected';
            app.setToolbarEnabled(false);
            app.setStatus('Ready.', 'neutral');
        end

        function store = resolveStore(app, storeOrProjectUUID)
            if isempty(storeOrProjectUUID)
                store = [];
            elseif isa(storeOrProjectUUID, 'UMITProjectStore')
                store = storeOrProjectUUID;
            elseif ischar(storeOrProjectUUID) || ...
                    (isstring(storeOrProjectUUID) && ...
                    isscalar(storeOrProjectUUID))
                store = UMITProjectStore.open( ...
                    char(string(storeOrProjectUUID)));
            else
                error( ...
                    'Umitoolbox:ProjectManagerTool:invalidStartupInput', ...
                    ['Input must be a UMITProjectStore object, a project ' ...
                    'UUID text scalar, or empty.']);
            end
        end

        function store = chooseProjectStore(app)
            store = [];
            projects = UMITProjectStore.listProjects();

            if isempty(projects)
                uialert(app.UIFigure, ...
                    'No UMIT projects were found.', ...
                    'Open Project');
                return
            end

            labels = string(projects.ProjectName);
            invalid = ~projects.IsReadable;
            labels(invalid) = labels(invalid) + ...
                " [read-only: validation failed]";

            [selection, confirmed] = listdlg( ...
                'ListString', cellstr(labels), ...
                'SelectionMode', 'single', ...
                'Name', 'Open UMIT Project', ...
                'PromptString', 'Select a project to open:', ...
                'ListSize', [460, 280]);

            if ~confirmed || isempty(selection)
                return
            end

            store = UMITProjectStore.open( ...
                char(projects.ProjectUUID(selection)));
        end

        function completeProjectSelection(app, selectionFigure, selection)
            if isempty(selectionFigure) || ~isvalid(selectionFigure)
                return
            end
            selectionFigure.UserData = selection;
            uiresume(selectionFigure);
        end

        function deleteTemporaryFigure(app, selectionFigure)
            if ~isempty(selectionFigure) && isvalid(selectionFigure)
                delete(selectionFigure);
            end
        end

        function setStore(app, store)
            app.Store = store;
            app.Editor = struct();
            app.CurrentNodeKey = '';
            app.IsDirty = false;
            app.setToolbarEnabled(true);
            app.refreshTree('');
        end

        function showEmptyState(app, message)
            app.Store = [];
            app.NodeMap = containers.Map( ...
                'KeyType', 'char', 'ValueType', 'any');
            app.CurrentNodeKey = '';
            app.Editor = struct();
            app.IsDirty = false;
            delete(app.ProjectTree.Children);
            app.clearMetadataTabs();
            app.hideAllActionButtons();
            app.ItemHeaderLabel.Text = 'No item selected';
            app.setToolbarEnabled(false);
            app.setStatus(message, 'info');
        end

        function setToolbarEnabled(app, hasStore)
            app.RefreshButton.Enable = app.onOff(hasStore);
            app.ValidateButton.Enable = app.onOff(hasStore);
            app.ValidationModeDropDown.Enable = app.onOff(hasStore);
        end

        function onOpenProject(app)
            if ~app.guardPendingEdits('opening another project')
                return
            end

            try
                store = app.chooseProjectStore();
                if ~isempty(store)
                    app.setStore(store);
                end
            catch ME
                app.showException(ME, 'Open Project Failed');
            end
        end

        function onNewProject(app)
            if ~app.guardPendingEdits('creating another project')
                return
            end

            answer = inputdlg( ...
                {'Project name:', 'Description:'}, ...
                'Create UMIT Project', ...
                [1, 70; 4, 70], ...
                {'', ''});
            if isempty(answer)
                return
            end

            projectName = strtrim(answer{1});
            if isempty(projectName)
                uialert(app.UIFigure, ...
                    'Project name cannot be empty.', ...
                    'Invalid Project Name');
                return
            end

            try
                app.setStatus('Creating project...', 'busy');
                store = UMITProjectStore.create(struct( ...
                    'projectName', projectName, ...
                    'description', answer{2}));
                app.setStore(store);
                app.setStatus('Project created successfully.', 'success');
            catch ME
                app.showException(ME, 'Project Creation Failed');
            end
        end

        function onRefresh(app)
            if isempty(app.Store) || ...
                    ~app.guardPendingEdits('refreshing the project')
                return
            end
            app.refreshTree(app.CurrentNodeKey);
        end

        function refreshTree(app, requestedKey)
            if isempty(app.Store)
                app.showEmptyState('No project is open.');
                return
            end

            app.setStatus('Refreshing project...', 'busy');
            delete(app.ProjectTree.Children);
            nodeMap = containers.Map( ...
                'KeyType', 'char', 'ValueType', 'any');

            try
                projectInfo = app.Store.getProjectInfo();
                projectData = app.nodeData( ...
                    'project', projectInfo.projectUUID, '', '', '');
                projectNode = uitreenode(app.ProjectTree, ...
                    'Text', sprintf('Project: %s', ...
                    projectInfo.projectName), ...
                    'NodeData', projectData);
                nodeMap(projectData.key) = projectNode;

                for iSubject = 1:numel(projectInfo.subjectRegistry)
                    subjectRecord = projectInfo.subjectRegistry(iSubject);
                    try
                        subjectInfo = app.Store.getSubjectInfoByUUID( ...
                            subjectRecord.uuid);
                        subjectData = app.nodeData( ...
                            'subject', subjectInfo.uuid, ...
                            subjectInfo.uuid, '', '');
                        subjectNode = uitreenode(projectNode, ...
                            'Text', app.displayNodeText( ...
                            'Subject', subjectInfo.displayName, ...
                            subjectInfo.subjectID), ...
                            'NodeData', subjectData);
                        nodeMap(subjectData.key) = subjectNode;

                        % Resources are represented only in registry tables.
                        for iSession = 1:numel(subjectInfo.sessionRegistry)
                            sessionRecord = ...
                                subjectInfo.sessionRegistry(iSession);
                            try
                                sessionInfo = ...
                                    app.Store.getSessionInfoByUUID( ...
                                    sessionRecord.uuid);
                                sessionData = app.nodeData( ...
                                    'session', sessionInfo.uuid, ...
                                    subjectInfo.uuid, ...
                                    sessionInfo.uuid, '');
                                sessionNode = uitreenode(subjectNode, ...
                                    'Text', app.displayNodeText( ...
                                    'Session', ...
                                    sessionInfo.displayName, ...
                                    sessionInfo.sessionID), ...
                                    'NodeData', sessionData);
                                nodeMap(sessionData.key) = sessionNode;
                            catch ME
                                app.addErrorNode( ...
                                    subjectNode, nodeMap, ...
                                    sessionRecord.uuid, ...
                                    subjectInfo.uuid, ...
                                    sessionRecord.uuid, ...
                                    sprintf('Session error: %s', ...
                                    sessionRecord.id), ME.message);
                            end
                        end
                        expand(subjectNode);
                    catch ME
                        app.addErrorNode( ...
                            projectNode, nodeMap, ...
                            subjectRecord.uuid, ...
                            subjectRecord.uuid, '', ...
                            sprintf('Subject error: %s', ...
                            subjectRecord.id), ME.message);
                    end
                end

                expand(projectNode);
                app.NodeMap = nodeMap;

                if nargin < 2 || isempty(requestedKey) || ...
                        ~isKey(nodeMap, requestedKey)
                    selectedNode = projectNode;
                else
                    selectedNode = nodeMap(requestedKey);
                end

                app.selectNodeWithoutCallback(selectedNode);
                app.renderSelectedNode(selectedNode);
                app.updateToolbarStatus();
            catch ME
                app.NodeMap = nodeMap;
                app.showException(ME, 'Project Refresh Failed');
            end
        end

        function addResourceNodes(app, parentNode, nodeMap, resources, ...
                subjectUUID, sessionUUID)
            for iResource = 1:numel(resources)
                resource = resources(iResource);

                if strcmp(resource.type, 'registrationTransform')
                    continue
                end

                resourceData = app.nodeData( ...
                    'resource', resource.uuid, subjectUUID, ...
                    sessionUUID, resource.uuid);
                resourceNode = uitreenode(parentNode, ...
                    'Text', app.resourceNodeText(resource), ...
                    'NodeData', resourceData);
                nodeMap(resourceData.key) = resourceNode;
            end
        end


        function addErrorNode(app, parentNode, nodeMap, uuid, ...
                subjectUUID, sessionUUID, label, message)
            errorData = app.nodeData( ...
                'error', uuid, subjectUUID, sessionUUID, '');
            errorData.message = message;
            errorNode = uitreenode(parentNode, ...
                'Text', label, 'NodeData', errorData);
            nodeMap(errorData.key) = errorNode; %#ok<NASGU>
        end

        function onTreeSelectionChanged(app, event)
            if app.IsSelectionGuard || isempty(event.SelectedNodes)
                return
            end

            selectedNode = event.SelectedNodes(1);
            selectedKey = selectedNode.NodeData.key;
            if strcmp(selectedKey, app.CurrentNodeKey)
                return
            end

            previousKey = app.CurrentNodeKey;
            [proceed, didSave] = app.resolvePendingEdits( ...
                'changing the selection');
            if ~proceed
                if ~isempty(previousKey) && ...
                        isKey(app.NodeMap, previousKey)
                    app.selectNodeWithoutCallback( ...
                        app.NodeMap(previousKey));
                end
                return
            end

            if didSave
                app.refreshTree(selectedKey);
            else
                app.renderSelectedNode(selectedNode);
            end
        end

        function selectNodeWithoutCallback(app, node)
            app.IsSelectionGuard = true;
            cleanup = onCleanup(@() app.clearSelectionGuard());
            app.ProjectTree.SelectedNodes = node;
            clear cleanup
        end

        function clearSelectionGuard(app)
            app.IsSelectionGuard = false;
        end

        function renderSelectedNode(app, node)
            nodeData = node.NodeData;

            try
                switch nodeData.kind
                    case 'project'
                        metadata = app.Store.getProjectInfo();
                        metadata.projectRoot = app.Store.ProjectRoot;
                        metadata.isReadOnly = app.Store.IsReadOnly;
                        report = app.Store.LastValidationReport;
                        if isstruct(report) && ...
                                isfield(report, 'isValid')
                            metadata.validationIsValid = report.isValid;
                        end
                        titleText = sprintf('Project: %s', ...
                            metadata.projectName);

                    case 'subject'
                        [metadata, record] = ...
                            app.Store.getSubjectInfoByUUID( ...
                            nodeData.subjectUUID);
                        metadata.relativePath = record.relativePath;
                        titleText = app.displayNodeText( ...
                            'Subject', metadata.displayName, ...
                            metadata.subjectID);

                    case 'session'
                        [metadata, subjectInfo, record] = ...
                            app.Store.getSessionInfoByUUID( ...
                            nodeData.sessionUUID);
                        metadata.parentSubjectDisplayName = ...
                            subjectInfo.displayName;
                        metadata.relativePath = record.relativePath;

                        bindingStatus = ...
                            app.Store.getSaveFolderBindingStatus( ...
                            subjectInfo.subjectID, ...
                            metadata.sessionID);
                        metadata.saveFolderRuntimeState = ...
                            bindingStatus.state;
                        metadata.saveFolderRuntimeMessage = ...
                            bindingStatus.message;

                        titleText = app.displayNodeText( ...
                            'Session', metadata.displayName, ...
                            metadata.sessionID);

                    case 'resource'
                        metadata = app.Store.getResource( ...
                            nodeData.resourceUUID);
                        titleText = sprintf('Resource: %s', ...
                            metadata.displayName);

                    case 'error'
                        metadata = struct('message', nodeData.message);
                        titleText = 'Unavailable Item';

                    otherwise
                        error( ...
                            'Umitoolbox:ProjectManagerTool:invalidNode', ...
                            'Unsupported tree node kind: %s', ...
                            nodeData.kind);
                end

                editor = app.renderMetadataEditor( ...
                    nodeData.kind, metadata);
                editor.NodeData = nodeData;
                editor.Metadata = metadata;
                app.Editor = editor;
                app.CurrentNodeKey = nodeData.key;
                app.IsDirty = false;

                app.ItemHeaderLabel.Text = titleText;
                app.configureActionButtons(nodeData.kind, metadata);
            catch ME
                app.showException(ME, 'Metadata Display Failed');
            end
        end

        function editor = renderMetadataEditor(app, kind, metadata)
            app.clearMetadataTabs();

            detailTab = uitab(app.MetadataTabGroup, 'Title', 'Details', ...
                'Scrollable', 'on');
            fields = fieldnames(metadata);
            hiddenFields = cellfun(@(fieldName) ...
                app.isHiddenMetadataField(fieldName), fields);
            fields = fields(~hiddenFields);

            structured = false(size(fields));
            for iField = 1:numel(fields)
                fieldName = fields{iField};
                % experimenters is a user-editable text list, not a registry table.
                structured(iField) = ~strcmp(fieldName, 'experimenters') && ...
                    app.requiresStructuredView(metadata.(fieldName));
            end

            scalarFields = app.orderMetadataFields(kind, fields(~structured));
            nRows = max(numel(scalarFields), 1);
            rowHeights = repmat({28}, 1, nRows);
            for iField = 1:numel(scalarFields)
                if app.isLongFormMetadataField(scalarFields{iField}) || ...
                        strcmp(scalarFields{iField}, 'experimenters')
                    rowHeights{iField} = 90;
                end
            end

            grid = uigridlayout(detailTab, [nRows, 2]);
            grid.ColumnWidth = {210, '1x'};
            grid.RowHeight = rowHeights;
            grid.Padding = [10, 10, 10, 10];
            grid.RowSpacing = 6;
            grid.ColumnSpacing = 8;
            grid.Scrollable = 'on';

            controls = struct();
            if isempty(scalarFields)
                label = uilabel(grid, 'Text', ...
                    'No scalar metadata fields are available.');
                label.Layout.Row = 1;
                label.Layout.Column = [1, 2];
            else
                for iField = 1:numel(scalarFields)
                    fieldName = scalarFields{iField};
                    editable = ~app.Store.IsReadOnly && ...
                        app.isEditableField(kind, fieldName);
                    tooltip = app.getBackendMetadataTooltip(kind, fieldName);

                    label = uilabel(grid, ...
                        'Text', app.humanizeField(fieldName), ...
                        'HorizontalAlignment', 'right', ...
                        'Tooltip', tooltip);
                    label.Layout.Row = iField;
                    label.Layout.Column = 1;

                    control = app.createMetadataControl(grid, fieldName, ...
                        metadata.(fieldName), editable);
                    if ~isempty(tooltip)
                        control.Tooltip = tooltip;
                    end
                    control.Layout.Row = iField;
                    control.Layout.Column = 2;
                    controls.(fieldName) = control;
                end
            end

            structuredFields = fields(structured);
            for iField = 1:numel(structuredFields)
                fieldName = structuredFields{iField};
                tab = uitab(app.MetadataTabGroup, ...
                    'Title', app.humanizeField(fieldName), 'Scrollable', 'on');
                tableGrid = uigridlayout(tab, [1, 1]);
                tableGrid.Padding = [8, 8, 8, 8];
                tableGrid.Scrollable = 'on';
                tableData = app.tabularizeMetadata(fieldName, metadata.(fieldName));
                uitable(tableGrid, 'Data', tableData, ...
                    'ColumnSortable', true, ...
                    'ColumnEditable', false(1, width(tableData)));
            end

            editor = struct('Kind', kind, 'Controls', controls);
        end

        function control = createMetadataControl(app, parent, fieldName, value, editable)
            if app.isLongFormMetadataField(fieldName)
                control = uitextarea(parent, ...
                    'Value', app.textAreaValue(value), ...
                    'Editable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if strcmp(fieldName, 'experimenters')
                control = uitextarea(parent, ...
                    'Value', app.experimentersTextAreaValue(value), ...
                    'Editable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if strcmp(fieldName, 'rigID') && editable
                control = app.createRigDropDown(parent, value);
                app.assignDirtyCallback(control, true);
                return
            end

            if isdatetime(value) && isscalar(value)
                if editable && app.isDateMetadataField(fieldName)
                    if isnat(value)
                        control = uidatepicker(parent, ...
                            'DisplayFormat', 'yyyy-MM-dd');
                    else
                        control = uidatepicker(parent, 'Value', value, ...
                            'DisplayFormat', 'yyyy-MM-dd');
                    end
                else
                    control = uieditfield(parent, 'text', ...
                        'Value', app.formatDatetime(value), 'Editable', 'off');
                end
                app.assignDirtyCallback(control, editable);
                return
            end

            if islogical(value) && isscalar(value)
                control = uicheckbox(parent, 'Text', '', 'Value', value, ...
                    'Enable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if isnumeric(value) && isscalar(value)
                if isnan(value)
                    % Numeric edit fields cannot display NaN. Keep an explicit
                    % empty-state marker so an untouched field remains NaN on save.
                    control = uieditfield(parent, 'numeric', 'Value', 0, ...
                        'Editable', app.onOff(editable));
                    control.UserData = struct('metadataIsUnset', true);
                    if editable
                        control.ValueChangedFcn = @(source, ~) ...
                            app.onNumericMetadataChanged(source);
                    end
                else
                    control = uieditfield(parent, 'numeric', 'Value', double(value), ...
                        'Editable', app.onOff(editable));
                    app.assignDirtyCallback(control, editable);
                end
                return
            end

            if app.isCategoricalField(fieldName)
                currentValue = char(string(value));
                items = {currentValue};
                if strcmp(fieldName, 'status') && ...
                        isfield(app.Store.Schema, 'resourceStates')
                    items = unique([{currentValue}, ...
                        app.Store.Schema.resourceStates(:).'], 'stable');
                end
                control = uidropdown(parent, 'Items', items, 'Value', currentValue, ...
                    'Enable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if strcmp(fieldName, 'rawDataFolder') && ...
                    strlength(strtrim(string(value))) == 0
                value = 'MISSING';
            end
            textValue = app.scalarText(value);
            control = uieditfield(parent, 'text', 'Value', textValue, ...
                'Editable', app.onOff(editable), 'Tooltip', textValue);
            app.assignDirtyCallback(control, editable);
        end


        function control = createRigDropDown(app, parent, currentRigID)
            currentRigID = char(string(currentRigID));
            ids = {};
            labels = {};

            try
                rigs = UMITRigStore.listRigs();
                [labels, ids, defaultRigID] = ...
                    buildSessionRigChoices(rigs, currentRigID);
                if isempty(currentRigID)
                    currentRigID = defaultRigID;
                end
            catch
                % Preserve the current value if rig discovery is unavailable.
            end

            if ~isempty(currentRigID) && ...
                    ~any(strcmpi(ids, currentRigID))
                ids{end+1} = currentRigID;
                labels{end+1} = sprintf('%s [unavailable]', currentRigID);
            end

            if isempty(ids)
                ids = {''};
                labels = {'No assignable Rigs available'};
            end

            control = uidropdown(parent, ...
                'Items', labels, ...
                'ItemsData', ids, ...
                'Value', currentRigID);
            if isempty(currentRigID) && isequal(ids, {''})
                control.Enable = 'off';
            end
        end

        function assignDirtyCallback(app, control, editable)
            if editable && isprop(control, 'ValueChangedFcn')
                control.ValueChangedFcn = ...
                    @(~, ~) app.markDirty();
            end
        end

        function markDirty(app)
            app.IsDirty = true;
            if ~isempty(app.Store) && ~app.Store.IsReadOnly
                app.SaveChangesButton.Enable = 'on';
            end
            app.setStatus('Unsaved changes.', 'warning');
        end

        function configureActionButtons(app, kind, metadata)
            app.hideAllActionButtons();
            if isempty(app.Store)
                return
            end

            canWrite = ~app.Store.IsReadOnly;
            buttons = {};
            if any(strcmp(kind, {'project', 'subject', 'session', 'resource'}))
                app.SaveChangesButton.Enable = 'off';
                app.RevertButton.Enable = 'on';
                buttons = {app.SaveChangesButton, app.RevertButton};
            end

            switch kind
                case 'project'
                    buttons{end+1} = app.AddSubjectButton;
                    buttons{end+1} = app.DeleteProjectButton;
                    app.DeleteProjectButton.Enable = 'on';
                case 'subject'
                    buttons{end+1} = app.AddSessionButton;
                    buttons{end+1} = app.AddImageReferenceButton;
                    buttons{end+1} = app.RemoveSubjectButton;
                    app.RemoveSubjectButton.Enable = app.onOff(canWrite);
                case 'session'
                    buttons{end+1} = app.RebindSaveFolderButton;
                    runtimeState = char(string(metadata.saveFolderRuntimeState));
                    if any(strcmp(runtimeState, {'invalid', 'conflicting'}))
                        buttons{end+1} = app.RepairSaveFolderButton;
                        app.RepairSaveFolderButton.Enable = app.onOff(canWrite);
                    end
                    if isfolder(metadata.processedDataFolder)
                        buttons{end+1} = app.OpenSaveFolderButton;
                        app.OpenSaveFolderButton.Enable = 'on';
                    end
                    if isempty(strtrim(char(string(metadata.rawDataFolder))))
                        app.BindRawFolderButton.Text = 'Bind Raw Folder';
                    else
                        app.BindRawFolderButton.Text = 'Change Raw Folder';
                    end
                    buttons{end+1} = app.BindRawFolderButton;
                    buttons{end+1} = app.RemoveSessionFromProjectButton;
                    buttons{end+1} = app.MoveSessionButton;
                    app.RebindSaveFolderButton.Enable = app.onOff(canWrite);
                    app.BindRawFolderButton.Enable = app.onOff(canWrite);
                    app.RemoveSessionFromProjectButton.Enable = app.onOff(canWrite);
            end

            nColumns = max(1, numel(app.SelectedItemBottomGrid.ColumnWidth));
            rowCount = max(1, ceil(numel(buttons) / nColumns));
            app.SelectedItemBottomGrid.RowHeight = repmat({28}, 1, rowCount);
            explicitlyControlledButtons = { ...
                app.RebindSaveFolderButton, app.RepairSaveFolderButton, ...
                app.OpenSaveFolderButton, app.BindRawFolderButton, ...
                app.RemoveSessionFromProjectButton, app.RemoveSubjectButton, ...
                app.DeleteProjectButton};
            for iButton = 1:numel(buttons)
                button = buttons{iButton};
                button.Visible = 'on';
                button.Layout.Row = ceil(iButton / nColumns);
                button.Layout.Column = mod(iButton - 1, nColumns) + 1;
                if isequal(button, app.SaveChangesButton)
                    button.Enable = app.onOff(canWrite && app.IsDirty);
                elseif isequal(button, app.RevertButton) || any(cellfun( ...
                        @(candidate) isequal(button, candidate), ...
                        explicitlyControlledButtons))
                    % Enable state was assigned explicitly above.
                else
                    button.Enable = app.onOff(canWrite);
                end
            end
        end

        function hideAllActionButtons(app)
            buttons = app.actionButtons();
            for iButton = 1:numel(buttons)
                buttons{iButton}.Visible = 'off';
                buttons{iButton}.Enable = 'off';
            end
        end

        function buttons = actionButtons(app)
            buttons = { ...
                app.SaveChangesButton, app.RevertButton, app.AddSubjectButton, ...
                app.AddSessionButton, app.AddImageReferenceButton, ...
                app.RemoveSubjectButton, app.DeleteProjectButton, ...
                app.RebindSaveFolderButton, app.RepairSaveFolderButton, ...
                app.OpenSaveFolderButton, app.BindRawFolderButton, ...
                app.RemoveSessionFromProjectButton, app.MoveSessionButton, ...
                app.SetActiveResourceButton, app.ArchiveResourceButton, ...
                app.RestoreResourceButton, app.OpenResourceLocationButton};
        end

        function onBindRawFolder(app)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);

            hasExistingRawFolder = ~isempty(strtrim( ...
                char(string(sessionInfo.rawDataFolder))));
            startFolder = pwd;
            if hasExistingRawFolder && ...
                    isfolder(sessionInfo.rawDataFolder)
                startFolder = sessionInfo.rawDataFolder;
            end

            selectedFolder = uigetdir(startFolder, ...
                'Select the RawFolder for this session');
            if isequal(selectedFolder, 0)
                return
            end

            if hasExistingRawFolder
                if strcmp(char(string(selectedFolder)), ...
                        char(string(sessionInfo.rawDataFolder)))
                    app.refreshTree(app.Editor.NodeData.key);
                    return
                end

                message = sprintf([ ...
                    'Change the RawFolder binding?\n\n' ...
                    'Current: %s\nNew: %s\n\n' ...
                    'No raw scientific data will be moved or deleted.'], ...
                    sessionInfo.rawDataFolder, selectedFolder);
                choice = uiconfirm(app.UIFigure, message, ...
                    'Change Raw Folder', ...
                    'Options', {'Change Binding', 'Cancel'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
                if ~strcmp(choice, 'Change Binding')
                    return
                end
            end

            try
                if hasExistingRawFolder
                    app.Store.relocateRawDataFolder( ...
                        subjectInfo.subjectID, ...
                        sessionInfo.sessionID, ...
                        selectedFolder);
                    successMessage = ...
                        'RawFolder binding changed successfully.';
                else
                    app.Store.bindRawDataFolder( ...
                        subjectInfo.subjectID, ...
                        sessionInfo.sessionID, ...
                        selectedFolder);
                    successMessage = ...
                        'RawFolder bound successfully.';
                end

                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus(successMessage, 'success');
            catch ME
                app.showException(ME, ...
                    'Raw Folder Binding Failed');
            end
        end


        function onSaveChanges(app)
            if app.applyEditorChanges()
                app.refreshTree(app.CurrentNodeKey);
                app.setStatus('Changes saved successfully.', 'success');
            end
        end

        function success = applyEditorChanges(app)
            success = false;
            if isempty(app.Store) || isempty(fieldnames(app.Editor))
                return
            end
            if app.Store.IsReadOnly
                uialert(app.UIFigure, 'This project is open in read-only mode.', ...
                    'Read-only Project');
                return
            end

            try
                switch app.Editor.Kind
                    case 'project'
                        app.saveProjectChanges();
                    case 'subject'
                        app.saveSubjectChanges();
                    case 'session'
                        app.saveSessionChanges();
                    case 'resource'
                        app.saveResourceChanges();
                    otherwise
                        success = true;
                        return
                end
                app.IsDirty = false;
                success = true;
            catch ME
                app.showException(ME, 'Save Failed');
            end
        end


        function saveSubjectChanges(app)
            original = app.Editor.Metadata;
            controls = app.Editor.Controls;
            newSubjectID = app.readTextControl(controls.subjectID);
            currentSubjectID = original.subjectID;
            if ~strcmp(newSubjectID, original.subjectID)
                app.Store.renameEntity('subject', original.uuid, newSubjectID);
                currentSubjectID = newSubjectID;
            end

            fields = {'displayName', 'description', 'species', 'strain', ...
                'genotype', 'sex', 'dateOfBirth', 'ageAtProjectEntry', ...
                'ageReference', 'weight_g', 'housing', 'diet', 'healthStatus', ...
                'earTag', 'rfid', 'notes'};
            updates = app.collectMetadataUpdates(original, controls, fields);
            if ~isempty(fieldnames(updates))
                app.Store.updateSubjectMetadata(currentSubjectID, updates);
            end
        end

        function saveSessionChanges(app)
            original = app.Editor.Metadata;
            controls = app.Editor.Controls;
            newSessionID = app.readTextControl(controls.sessionID);
            if ~strcmp(newSessionID, original.sessionID)
                app.Store.renameEntity('session', original.uuid, newSessionID);
            end

            [currentSession, currentSubject] = ...
                app.Store.getSessionInfoByUUID(original.uuid);
            fields = {'displayName', 'description', 'acquisitionDate', ...
                'behavioralTask', 'stimulusNotes', 'pharmacology', 'surgery', ...
                'virus', 'anesthesia', 'notes', 'rigID'};
            updates = app.collectMetadataUpdates(original, controls, fields);
            if ~isempty(fieldnames(updates))
                app.Store.updateSessionMetadata(currentSubject.subjectID, ...
                    currentSession.sessionID, updates);
            end
        end

        function saveResourceChanges(app)
            original = app.Editor.Metadata;
            controls = app.Editor.Controls;
            updates = struct();
            newDisplayName = ...
                app.readTextControl(controls.displayName);
            newDescription = ...
                app.readTextControl(controls.description);

            if ~strcmp(newDisplayName, original.displayName)
                updates.displayName = newDisplayName;
            end
            if ~strcmp(newDescription, original.description)
                updates.description = newDescription;
            end
            if ~isempty(fieldnames(updates))
                app.Store.updateResourceMetadata( ...
                    original.uuid, updates);
            end
        end

        function onRevertSelection(app)
            if isempty(app.CurrentNodeKey) || ...
                    ~isKey(app.NodeMap, app.CurrentNodeKey)
                return
            end
            app.renderSelectedNode( ...
                app.NodeMap(app.CurrentNodeKey));
            app.setStatus('Changes reverted.', 'info');
        end

        function [proceed, didSave] = resolvePendingEdits(app, actionText)
            proceed = true;
            didSave = false;
            if ~app.IsDirty
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                sprintf(['There are unsaved changes. Save them before ' ...
                '%s?'], actionText), ...
                'Unsaved Changes', ...
                'Options', {'Save', 'Discard', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);
            switch choice
                case 'Save'
                    proceed = app.applyEditorChanges();
                    didSave = proceed;
                case 'Discard'
                    app.IsDirty = false;
                otherwise
                    proceed = false;
            end
        end

        function proceed = guardPendingEdits(app, actionText)
            [proceed, ~] = app.resolvePendingEdits(actionText);
        end

        function onAddSubject(app)
            answer = inputdlg( ...
                {'Subject ID:', 'Display name:', 'Description:'}, ...
                'Add Subject', ...
                [1, 60; 1, 60; 4, 60], ...
                {'', '', ''});
            if isempty(answer)
                return
            end

            try
                uuid = app.Store.addSubject(struct( ...
                    'subjectID', strtrim(answer{1}), ...
                    'displayName', answer{2}, ...
                    'description', answer{3}));
                app.refreshTree(app.makeNodeKey('subject', uuid));
                app.setStatus('Subject added successfully.', 'success');
            catch ME
                app.showException(ME, 'Add Subject Failed');
            end
        end


        function onAddSession(app)
            subjectInfo = app.Store.getSubjectInfoByUUID( ...
                app.Editor.NodeData.subjectUUID);

            saveFolder = uigetdir(pwd, ...
                'Select the existing SaveFolder for this dataset');
            if isequal(saveFolder, 0)
                return
            end

            sessionInfo = app.promptSessionInfo( ...
                subjectInfo, saveFolder);
            if isempty(sessionInfo)
                return
            end

            try
                sessionInfo.processedDataFolder = saveFolder;
                uuid = app.Store.addSession( ...
                    subjectInfo.subjectID, sessionInfo);
                app.refreshTree(app.makeNodeKey('session', uuid));
                app.setStatus( ...
                    'Session added successfully.', 'success');
            catch ME
                app.showException(ME, 'Add Session Failed');
            end
        end

        function sessionInfo = promptSessionInfo( ...
                app, subjectInfo, saveFolder)
            sessionInfo = [];
            inferredDate = ...
                app.acquisitionDateFromSaveFolder(saveFolder);
            [rigLabels, rigIDs, defaultRigID] = ...
                app.sessionRigChoices();

            dialogFigure = uifigure( ...
                'Name', sprintf('Add Session to %s', ...
                subjectInfo.subjectID), ...
                'Visible', 'off', ...
                'WindowStyle', 'modal', ...
                'Position', [100, 100, 620, 440]);
            cleanupFigure = onCleanup(@() ...
                app.deleteTemporaryFigure(dialogFigure));
            dialogFigure.UserData = [];

            grid = uigridlayout(dialogFigure, [7, 2]);
            grid.RowHeight = {28, 28, 90, 28, 28, '1x', 32};
            grid.ColumnWidth = {180, '1x'};
            grid.Padding = [14, 14, 14, 14];
            grid.RowSpacing = 8;
            grid.ColumnSpacing = 8;

            uilabel(grid, 'Text', 'Session ID:', ...
                'HorizontalAlignment', 'right');
            sessionIDField = uieditfield(grid, 'text');

            displayLabel = uilabel(grid, 'Text', 'Display name:', ...
                'HorizontalAlignment', 'right');
            displayLabel.Layout.Row = 2;
            displayLabel.Layout.Column = 1;
            displayNameField = uieditfield(grid, 'text');
            displayNameField.Layout.Row = 2;
            displayNameField.Layout.Column = 2;

            descriptionLabel = uilabel(grid, 'Text', 'Description:', ...
                'HorizontalAlignment', 'right');
            descriptionLabel.Layout.Row = 3;
            descriptionLabel.Layout.Column = 1;
            descriptionField = uitextarea(grid);
            descriptionField.Layout.Row = 3;
            descriptionField.Layout.Column = 2;

            dateLabel = uilabel(grid, 'Text', 'Acquisition date:', ...
                'HorizontalAlignment', 'right');
            dateLabel.Layout.Row = 4;
            dateLabel.Layout.Column = 1;
            dateField = uidatepicker(grid, ...
                'DisplayFormat', 'yyyy-MM-dd', ...
                'Value', inferredDate);
            dateField.Layout.Row = 4;
            dateField.Layout.Column = 2;

            rigLabel = uilabel(grid, 'Text', 'Rig:', ...
                'HorizontalAlignment', 'right');
            rigLabel.Layout.Row = 5;
            rigLabel.Layout.Column = 1;
            rigField = uidropdown(grid, ...
                'Items', rigLabels, ...
                'ItemsData', rigIDs, ...
                'Value', defaultRigID);
            if isempty(defaultRigID)
                rigField.Enable = 'off';
            end
            rigField.Layout.Row = 5;
            rigField.Layout.Column = 2;

            folderLabel = uilabel(grid, ...
                'Text', sprintf('SaveFolder: %s', saveFolder), ...
                'Tooltip', saveFolder);
            folderLabel.Layout.Row = 6;
            folderLabel.Layout.Column = [1, 2];

            controls = struct( ...
                'sessionID', sessionIDField, ...
                'displayName', displayNameField, ...
                'description', descriptionField, ...
                'acquisitionDate', dateField, ...
                'rigID', rigField);

            addButton = uibutton(grid, 'push', ...
                'Text', 'Add Session', ...
                'ButtonPushedFcn', @(~, ~) ...
                app.completeSessionDialog(dialogFigure, controls));
            addButton.Layout.Row = 7;
            addButton.Layout.Column = 1;

            cancelButton = uibutton(grid, 'push', ...
                'Text', 'Cancel', ...
                'ButtonPushedFcn', @(~, ~) ...
                app.completeSessionDialog(dialogFigure, []));
            cancelButton.Layout.Row = 7;
            cancelButton.Layout.Column = 2;

            dialogFigure.CloseRequestFcn = @(~, ~) ...
                app.completeSessionDialog(dialogFigure, []);
            placeAppInsideCaller(app.UIFigure, dialogFigure, 'center');
            dialogFigure.Visible = 'on';
            drawnow
            figure(dialogFigure);
            focus(sessionIDField);
            uiwait(dialogFigure);

            if isvalid(dialogFigure)
                sessionInfo = dialogFigure.UserData;
            end
            clear cleanupFigure
        end

        function completeSessionDialog(app, dialogFigure, controls)
            if isempty(dialogFigure) || ~isvalid(dialogFigure)
                return
            end

            if isempty(controls)
                dialogFigure.UserData = [];
                uiresume(dialogFigure);
                return
            end

            sessionID = strtrim(char(string( ...
                controls.sessionID.Value)));
            if isempty(sessionID)
                uialert(dialogFigure, ...
                    'Session ID cannot be empty.', ...
                    'Invalid Session ID');
                focus(controls.sessionID);
                return
            end

            rigID = char(string(controls.rigID.Value));
            if isempty(rigID)
                uialert(dialogFigure, ...
                    'Select an Active or Available Rig.', ...
                    'Rig assignment required');
                return
            end

            description = strjoin( ...
                string(controls.description.Value(:)), newline);
            dialogFigure.UserData = struct( ...
                'sessionID', sessionID, ...
                'displayName', char(string( ...
                controls.displayName.Value)), ...
                'description', char(description), ...
                'acquisitionDate', controls.acquisitionDate.Value, ...
                'rigID', rigID);
            uiresume(dialogFigure);
        end

        function acquisitionDate = ...
                acquisitionDateFromSaveFolder(app, saveFolder)
            acquisitionDate = ...
                getSessionAcquisitionDatePrefill(saveFolder);
        end
        function [labels, ids, defaultRigID] = ...
                sessionRigChoices(app)
            try
                rigs = UMITRigStore.listRigs();
            catch
                rigs = table();
            end
            [labels, ids, defaultRigID] = ...
                buildSessionRigChoices(rigs);
        end

        function onRebindSaveFolder(app)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);

            selectedFolder = uigetdir(pwd, ...
                'Select the relocated SaveFolder');
            if isequal(selectedFolder, 0)
                return
            end

            try
                app.Store.relocateSaveFolder( ...
                    subjectInfo.subjectID, ...
                    sessionInfo.sessionID, ...
                    selectedFolder);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus( ...
                    'SaveFolder relocated successfully.', ...
                    'success');
            catch ME
                app.showException(ME, ...
                    'SaveFolder Relocation Failed');
            end
        end

        function onRepairSaveFolder(app)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);

            try
                app.Store.repairSaveFolderBinding( ...
                    subjectInfo.subjectID, ...
                    sessionInfo.sessionID);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus( ...
                    'SaveFolder binding repaired.', ...
                    'success');
            catch ME
                app.showException(ME, ...
                    'SaveFolder Repair Failed');
            end
        end

        function onOpenSaveFolder(app)
            sessionInfo = app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);

            if ~isfolder(sessionInfo.processedDataFolder)
                uialert(app.UIFigure, ...
                    ['The stored SaveFolder is currently ' ...
                    'unavailable.'], ...
                    'SaveFolder Unavailable');
                return
            end

            try
                app.openFolder( ...
                    sessionInfo.processedDataFolder);
            catch ME
                app.showException(ME, ...
                    'Open SaveFolder Failed');
            end
        end

        function onRetrySaveFolder(app)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);

            try
                status = app.Store.retrySaveFolderAvailability( ...
                    subjectInfo.subjectID, ...
                    sessionInfo.sessionID);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus(sprintf( ...
                    'Availability refreshed: %s - %s', ...
                    status.state, status.message), 'info');
            catch ME
                app.showException(ME, ...
                    'Availability Check Failed');
            end
        end


        function onRemoveSessionFromProject(app)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);

            message = sprintf([ ...
                'Remove session "%s" from the project ledger?' ...
                '\n\nScientific data in the SaveFolder will ' ...
                'not be deleted.'], sessionInfo.sessionID);
            choice = uiconfirm(app.UIFigure, message, ...
                'Remove Session from Project', ...
                'Options', ...
                {'Remove from Project', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Remove from Project')
                return
            end

            try
                app.Store.removeSessionFromProject( ...
                    subjectInfo.subjectID, ...
                    sessionInfo.sessionID);
                app.refreshTree(app.makeNodeKey( ...
                    'subject', subjectInfo.uuid));
                app.setStatus( ...
                    ['Session removed from project. ' ...
                    'Scientific data was preserved.'], ...
                    'success');
            catch ME
                app.showException(ME, ...
                    'Remove Session Failed');
            end
        end

        function onAddImageReference(app)
            subjectInfo = app.Store.getSubjectInfoByUUID( ...
                app.Editor.NodeData.subjectUUID);
            subjectKey = app.makeNodeKey( ...
                'subject', subjectInfo.uuid);

            try
                manager = ImageReferenceManager( ...
                    app, 'manage', ...
                    'ProjectStore', app.Store, ...
                    'SubjectID', subjectInfo.subjectID);
                waitfor(manager.UIFigure);

                if isvalid(app)
                    app.refreshTree(subjectKey);
                    app.setStatus( ...
                        'Image Reference Registry refreshed.', ...
                        'success');
                end
            catch ME
                if isvalid(app)
                    app.showException(ME, ...
                        'Image Reference Manager Failed');
                end
            end
        end


        function onMoveSession(app)
            [sessionInfo, currentSubject] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);
            projectInfo = app.Store.getProjectInfo();
            targets = projectInfo.subjectRegistry( ...
                ~strcmpi({projectInfo.subjectRegistry.uuid}, ...
                currentSubject.uuid));

            if isempty(targets)
                uialert(app.UIFigure, ...
                    'There is no other subject in this project.', ...
                    'Move Session');
                return
            end

            labels = strings(numel(targets), 1);
            for iTarget = 1:numel(targets)
                labels(iTarget) = sprintf('%s (%s)', ...
                    targets(iTarget).displayName, ...
                    targets(iTarget).id);
            end
            [selection, ok] = listdlg( ...
                'PromptString', sprintf( ...
                'Move session "%s" to:', sessionInfo.sessionID), ...
                'SelectionMode', 'single', ...
                'ListString', cellstr(labels), ...
                'ListSize', [420, 260]);
            if ~ok
                return
            end

            try
                app.Store.moveSessionToSubject( ...
                    sessionInfo.uuid, targets(selection).uuid);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Session moved successfully.', 'success');
            catch ME
                app.showException(ME, 'Move Session Failed');
            end
        end

        function onSetActiveResource(app)
            resource = app.Store.getResource( ...
                app.Editor.NodeData.resourceUUID);
            try
                switch resource.ownerType
                    case 'subject'
                        subjectInfo = ...
                            app.Store.getSubjectInfoByUUID( ...
                            resource.ownerUUID);
                        app.Store.setActiveImageReference( ...
                            subjectInfo.subjectID, resource.uuid);
                    case 'session'
                        [sessionInfo, subjectInfo] = ...
                            app.Store.getSessionInfoByUUID( ...
                            resource.ownerUUID);
                        app.Store.setActiveRegistrationTransform( ...
                            subjectInfo.subjectID, ...
                            sessionInfo.sessionID, resource.uuid);
                    otherwise
                        error( ...
                            ['Umitoolbox:ProjectManagerTool:' ...
                            'invalidResourceOwner'], ...
                            'Unsupported resource owner type: %s', ...
                            resource.ownerType);
                end
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Active resource updated.', 'success');
            catch ME
                app.showException(ME, ...
                    'Set Active Resource Failed');
            end
        end

        function onArchiveResource(app)
            resource = app.Store.getResource( ...
                app.Editor.NodeData.resourceUUID);
            if strcmp(resource.status, 'active')
                uialert(app.UIFigure, ...
                    ['Select another active resource before archiving ' ...
                    'this one.'], ...
                    'Active Resource');
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                sprintf('Archive resource "%s"?', ...
                resource.displayName), ...
                'Confirm Archive', ...
                'Options', {'Archive', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Archive')
                return
            end

            try
                app.Store.archiveResource(resource.uuid);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Resource archived.', 'success');
            catch ME
                app.showException(ME, 'Archive Resource Failed');
            end
        end

        function onRestoreResource(app)
            try
                app.Store.restoreResource( ...
                    app.Editor.NodeData.resourceUUID);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Resource restored.', 'success');
            catch ME
                app.showException(ME, 'Restore Resource Failed');
            end
        end

        function onOpenResourceLocation(app)
            resource = app.Store.getResource( ...
                app.Editor.NodeData.resourceUUID);
            if ~resource.fileExists
                uialert(app.UIFigure, ...
                    'The managed resource file is unavailable.', ...
                    'Missing Resource File');
                return
            end

            try
                app.openFolder(fileparts(resource.absolutePath));
            catch ME
                app.showException(ME, 'Open Folder Failed');
            end
        end

        function onValidateProject(app)
            if isempty(app.Store)
                return
            end

            mode = lower(app.ValidationModeDropDown.Value);

            try
                app.setStatus('Validating project...', 'busy');
                report = app.Store.validate('Mode', mode);

                app.updateToolbarStatus();
                nErrors = app.structCount(report, 'errors');
                nWarnings = app.structCount(report, 'warnings');

                if report.isValid
                    message = sprintf( ...
                        'Project is valid.\nWarnings: %d\nMode: %s', ...
                        nWarnings, mode);
                    titleText = 'Validation Passed';
                    alertIcon = 'success';
                else
                    message = sprintf( ...
                        ['Validation failed.\nErrors: %d\nWarnings: %d' ...
                        '\n\n%s'], ...
                        nErrors, nWarnings, ...
                        app.issueSummary(report.errors, 8));
                    titleText = 'Validation Failed';
                    alertIcon = 'error';
                end
                uialert(app.UIFigure, message, titleText, ...
                    'Icon', alertIcon);

                if isfield(app.Editor, 'Kind') && ...
                        strcmp(app.Editor.Kind, 'project') && ...
                        isKey(app.NodeMap, app.CurrentNodeKey)
                    app.renderSelectedNode( ...
                        app.NodeMap(app.CurrentNodeKey));
                end
            catch ME

                app.showException(ME, 'Validation Failed');
            end
        end

        function updateToolbarStatus(app)
            projectInfo = app.Store.getProjectInfo();
            if app.Store.IsReadOnly
                accessText = 'Read-only';
            else
                accessText = 'Writable';
            end

            validationText = 'Not validated';
            report = app.Store.LastValidationReport;
            if isstruct(report) && isfield(report, 'isValid')
                if report.isValid
                    validationText = 'Valid';

                else
                    validationText = 'Invalid';

                end
            end

            app.setStatus(sprintf('%s | %s | %s', ...
                projectInfo.projectName, accessText, ...
                validationText), 'neutral');
        end


        function onCloseRequest(app)
            if app.IsClosing
                return
            end
            if ~app.guardPendingEdits('closing the app')
                return
            end

            app.IsClosing = true;
            app.UIFigure.CloseRequestFcn = [];
            delete(app);
        end

        function clearMetadataTabs(app)
            tabs = app.MetadataTabGroup.Children;
            if ~isempty(tabs)
                delete(tabs);
            end
        end

        function setStatus(app, message, severity)
            setStatusMessage(app.StatusLabel, message, ...
                'Severity', severity, ...
                'Background', 'light', ...
                'MaxLength', 180);
        end

        function showException(app, exception, titleText)
            app.setStatus(exception, 'error');
            message = exception.message;
            if ~isempty(exception.identifier)
                message = sprintf('%s\n\n%s', ...
                    message, exception.identifier);
            end
            uialert(app.UIFigure, message, titleText);
        end
        function filterSpec = buildResourceFileFilter(app,  ...
                resourceType, label)
            filterSpec = {'*.*', 'All files'};
            if ~isfield(app.Store.Schema, 'resourceTypes') || ...
                    ~isfield(app.Store.Schema.resourceTypes, resourceType)
                return
            end
            definition = ...
                app.Store.Schema.resourceTypes.(resourceType);
            if ~isfield(definition, 'allowedExtensions') || ...
                    isempty(definition.allowedExtensions)
                return
            end
            extensions = cellstr( ...
                string(definition.allowedExtensions(:)));
            patterns = cellfun( ...
                @(extension) ['*', extension], extensions, ...
                'UniformOutput', false);
            filterSpec = { ...
                strjoin(patterns, ';'), label; ...
                '*.*', 'All files'};
        end

        function resourceInfo = promptResourceInfo(~, resourceLabel, defaultName)
            [~, baseName, ~] = fileparts(defaultName);
            answer = inputdlg( ...
                {'Display name:', 'Description:'}, ...
                sprintf('Add %s', resourceLabel), ...
                [1, 60; 4, 60], ...
                {baseName, ''});
            if isempty(answer)
                resourceInfo = [];
            else
                resourceInfo = struct( ...
                    'displayName', answer{1}, ...
                    'description', answer{2});
            end
        end

        function data = nodeData(app,  ...
                kind, uuid, subjectUUID, sessionUUID, resourceUUID)
            data = struct( ...
                'kind', kind, ...
                'uuid', uuid, ...
                'subjectUUID', subjectUUID, ...
                'sessionUUID', sessionUUID, ...
                'resourceUUID', resourceUUID, ...
                'key', ...
                app.makeNodeKey(kind, uuid));
        end

        function key = makeNodeKey(app, kind, uuid)
            key = sprintf('%s:%s', ...
                char(string(kind)), char(string(uuid)));
        end

        function text = displayNodeText(app, prefix, displayName, id)
            displayName = char(string(displayName));
            id = char(string(id));
            if isempty(displayName) || strcmp(displayName, id)
                text = sprintf('%s: %s', prefix, id);
            else
                text = sprintf('%s: %s (%s)', ...
                    prefix, displayName, id);
            end
        end

        function text = resourceNodeText(app, resource)
            text = sprintf('%s: %s [%s]', ...
                app.humanizeField( ...
                resource.type), ...
                resource.displayName, resource.status);
            if ~resource.fileExists
                text = sprintf('%s [missing]', text);
            end
        end

        function tf = requiresStructuredView(app, value)
            tf = isstruct(value) || istable(value) || ...
                (iscell(value) && ~isscalar(value)) || ...
                (isstring(value) && ~isscalar(value)) || ...
                (isnumeric(value) && ~isscalar(value)) || ...
                (islogical(value) && ~isscalar(value)) || ...
                iscategorical(value);
        end

        function fields = orderMetadataFields(app, kind, fields)
            switch kind
                case 'project'
                    preferred = { ...
                        'projectName', 'description', ...
                        'createdOn', 'modifiedOn', ...
                        'projectRoot', 'isReadOnly', ...
                        'validationIsValid'};
                case 'subject'
                    preferred = { ...
                        'subjectID', 'displayName', ...
                        'description', ...
                        'createdOn', 'modifiedOn', ...
                        'relativePath'};
                case 'session'
                    preferred = { ...
                        'sessionID', 'displayName', ...
                        'description', 'acquisitionDate', ...
                        'subjectID', ...
                        'parentSubjectDisplayName', ...
                        'rigID', 'rawDataFolder', ...
                        'processedDataFolder', ...
                        'saveFolderRuntimeState', ...
                        'saveFolderRuntimeMessage', ...
                        'createdOn', 'modifiedOn', ...
                        'relativePath'};
                case 'resource'
                    preferred = { ...
                        'displayName', 'description', ...
                        'type', 'status', 'ownerType', ...
                        'fileName', 'fileExists', ...
                        'absolutePath', 'relativePath', ...
                        'sourceFile', 'checksum', ...
                        'createdOn', 'modifiedOn', ...
                        'archivedOn'};
                otherwise
                    preferred = {'message'};
            end

            ordered = preferred( ...
                ismember(preferred, fields));
            remaining = fields( ...
                ~ismember(fields, ordered));
            fields = [ordered(:); remaining(:)];
        end

        function tf = isHiddenMetadataField(app, fieldName)
            fieldName = char(string(fieldName));
            tf = strcmpi(fieldName, 'schemaVersion') || ...
                strcmpi(fieldName, 'uuid') || ...
                endsWith(fieldName, 'UUID', ...
                'IgnoreCase', true);
        end

        function tf = isCategoricalField(app, fieldName)
            tf = any(strcmp(fieldName, ...
                {'status', 'type', 'ownerType', 'folderRole'}));
        end

        function tf = isEditableField(~, kind, fieldName)
            switch kind
                case 'project'
                    tf = any(strcmp(fieldName, { ...
                        'description', 'institution', 'lab', 'principalInvestigator', ...
                        'experimenters', 'projectStartDate', 'projectEndDate', 'notes'}));
                case 'subject'
                    tf = any(strcmp(fieldName, { ...
                        'subjectID', 'displayName', 'description', 'species', ...
                        'strain', 'genotype', 'sex', 'dateOfBirth', ...
                        'ageAtProjectEntry', 'ageReference', 'weight_g', 'housing', ...
                        'diet', 'healthStatus', 'earTag', 'rfid', 'notes'}));
                case 'session'
                    tf = any(strcmp(fieldName, { ...
                        'sessionID', 'displayName', 'description', 'acquisitionDate', ...
                        'behavioralTask', 'stimulusNotes', 'pharmacology', 'surgery', ...
                        'virus', 'anesthesia', 'notes', 'rigID'}));
                case 'resource'
                    tf = any(strcmp(fieldName, {'displayName', 'description'}));
                otherwise
                    tf = false;
            end
        end

        function data = tabularizeMetadata(app, fieldName, value)
            try
                if istable(value)
                    data = value;
                elseif isstruct(value)
                    % AsArray is required for a scalar registry record whose text
                    % fields have different character-vector lengths.
                    data = struct2table(value(:), 'AsArray', true);
                elseif iscell(value)
                    data = table( ...
                        (1:numel(value)).', ...
                        string(value(:)), ...
                        'VariableNames', {'Index', 'Value'});
                elseif isstring(value) || iscategorical(value)
                    data = table( ...
                        (1:numel(value)).', ...
                        string(value(:)), ...
                        'VariableNames', {'Index', 'Value'});
                elseif isnumeric(value) || islogical(value)
                    data = table( ...
                        (1:numel(value)).', value(:), ...
                        'VariableNames', {'Index', 'Value'});
                else
                    data = table(string(value), ...
                        'VariableNames', {'Value'});
                end
            catch
                data = table( ...
                    string(evalc('disp(value)')), ...
                    'VariableNames', {'Value'});
            end

            isRegistry = endsWith( ...
                char(string(fieldName)), ...
                'Registry', 'IgnoreCase', true);
            data = app.orderTableColumns(data, isRegistry);
        end


        function data = orderTableColumns( ...
                app, data, allowUUID)
            if isempty(data.Properties.VariableNames)
                return
            end

            variableNames = ...
                string(data.Properties.VariableNames);
            schemaMask = strcmpi( ...
                variableNames, 'schemaVersion');
            if any(schemaMask)
                data(:, schemaMask) = [];
                variableNames = ...
                    string(data.Properties.VariableNames);
            end

            uuidMask = strcmpi(variableNames, 'uuid') | ...
                endsWith(variableNames, 'UUID', ...
                'IgnoreCase', true);
            if ~allowUUID && any(uuidMask)
                data(:, uuidMask) = [];
                variableNames = ...
                    string(data.Properties.VariableNames);
                uuidMask = false(size(variableNames));
            end

            preferredNames = [ ...
                "id", "name", "projectName", ...
                "subjectID", "sessionID", ...
                "rigID", "fileName", ...
                "displayName"];
            nameIndexes = zeros(1, 0);
            for iName = 1:numel(preferredNames)
                match = find(strcmpi( ...
                    variableNames, ...
                    preferredNames(iName)), 1);
                if ~isempty(match)
                    nameIndexes(end+1) = match; %#ok<AGROW>
                end
            end

            uuidIndexes = find(uuidMask);
            leadingIndexes = unique( ...
                [nameIndexes, uuidIndexes], 'stable');
            remainingIndexes = setdiff( ...
                1:numel(variableNames), ...
                leadingIndexes, 'stable');
            data = data(:, ...
                [leadingIndexes, remainingIndexes]);
        end


        function value = readTextControl(app, control)
            if isa(control, 'matlab.ui.control.TextArea')
                value = strjoin(string(control.Value(:)), newline);
                value = char(value);
            else
                value = char(string(control.Value));
            end
        end

        function value = textAreaValue(app, inputValue)
            if isempty(inputValue)
                value = {''};
            else
                value = cellstr(splitlines(string(inputValue)));
            end
        end

        function text = scalarText(app, value)
            if isempty(value)
                text = '';
            elseif ischar(value)
                text = value;
            elseif isstring(value) && isscalar(value)
                text = char(value);
            else
                text = char(string(value));
            end
        end

        function text = formatDatetime(app, value)
            if isempty(value) || isnat(value)
                text = '';
            else
                formatted = value;
                formatted.Format = 'yyyy-MM-dd HH:mm:ss';
                text = char(string(formatted));
            end
        end

        function text = humanizeField(app, fieldName)
            text = regexprep(char(string(fieldName)), ...
                '([a-z0-9])([A-Z])', '$1 $2');
            text = strrep(text, '_', ' ');
            if ~isempty(text)
                text(1) = upper(text(1));
            end
        end

        function value = onOff(app, tf)
            if tf
                value = 'on';
            else
                value = 'off';
            end
        end

        function count = structCount(app, container, fieldName)
            if isfield(container, fieldName)
                count = numel(container.(fieldName));
            else
                count = 0;
            end
        end

        function text = issueSummary(app, issues, maxIssues)
            if isempty(issues)
                text = '';
                return
            end
            nShow = min(numel(issues), maxIssues);
            lines = strings(nShow, 1);
            for iIssue = 1:nShow
                if isfield(issues(iIssue), 'message')
                    lines(iIssue) = sprintf('%d. %s', ...
                        iIssue, issues(iIssue).message);
                else
                    lines(iIssue) = sprintf( ...
                        '%d. Validation issue', iIssue);
                end
            end
            if numel(issues) > nShow
                lines(end+1) = sprintf('...and %d more.', ...
                    numel(issues) - nShow);
            end
            text = char(strjoin(lines, newline));
        end

        function openFolder(app, folderPath)
            if ispc
                winopen(folderPath);
            elseif ismac
                system(sprintf('open "%s"', folderPath));
            else
                system(sprintf( ...
                    'xdg-open "%s" >/dev/null 2>&1 &', folderPath));
            end
        end


        function onRemoveSubjectFromProject(app)
            subjectUUID = app.Editor.NodeData.subjectUUID;
            [subjectInfo, ~] = app.Store.getSubjectInfoByUUID(subjectUUID);
            sessionCount = numel(subjectInfo.sessionRegistry);
            if sessionCount == 0
                message = sprintf(['Remove subject "%s" from this project?\n\n' ...
                    'Its centralized metadata and managed resources will be removed.'], ...
                    subjectInfo.subjectID);
            else
                message = sprintf(['Remove subject "%s" and its %d session(s)?\n\n' ...
                    'All sessions will be unbound, so their SaveFolders will no longer ' ...
                    'be associated with this project or subject. External raw and ' ...
                    'processed imaging data will not be deleted. The subject is removed ' ...
                    'only after every session is successfully unbound.'], ...
                    subjectInfo.subjectID, sessionCount);
            end
            choice = uiconfirm(app.UIFigure, message, 'Remove Subject', ...
                'Options', {'Remove Subject', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if ~strcmp(choice, 'Remove Subject')
                return
            end
            projectKey = app.makeNodeKey('project', ...
                app.Store.getProjectInfo().projectUUID);
            try
                result = app.Store.removeSubjectFromProject(subjectUUID);
                app.refreshTree(projectKey);
                app.setStatus(sprintf(['Subject removed successfully. %d session(s) ' ...
                    'were unbound; external imaging data was preserved.'], ...
                    result.sessionsRemoved), 'success');
            catch ME
                try
                    app.refreshTree(projectKey);
                catch refreshME
                    app.showException(refreshME, 'Project Refresh Failed');
                end
                app.showException(ME, 'Remove Subject Failed');
            end
        end

        function onDeleteProject(app)
            projectInfo = app.Store.getProjectInfo();
            subjectCount = numel(projectInfo.subjectRegistry);
            sessionCount = 0;
            countsAvailable = true;
            try
                for iSubject = 1:subjectCount
                    subjectInfo = app.Store.getSubjectInfoByUUID( ...
                        projectInfo.subjectRegistry(iSubject).uuid);
                    sessionCount = sessionCount + numel(subjectInfo.sessionRegistry);
                end
            catch
                countsAvailable = false;
            end

            if countsAvailable && sessionCount == 0 && subjectCount == 0
                message = sprintf(['Delete project "%s"?\n\nThe centralized project ' ...
                    'record and managed project metadata will be removed. External raw ' ...
                    'and processed imaging data will not be deleted.'], projectInfo.projectName);
            elseif countsAvailable && sessionCount == 0
                message = sprintf(['Delete project "%s" and its %d subject(s)?\n\n' ...
                    'The centralized project record, managed metadata, and subjects ' ...
                    'will be removed. External raw and processed imaging data will not ' ...
                    'be deleted.'], projectInfo.projectName, subjectCount);
            elseif countsAvailable
                message = sprintf(['Delete project "%s" with %d subject(s) and %d ' ...
                    'session(s)?\n\nAll sessions will be unbound, all subjects will ' ...
                    'be removed, and then the project will be deleted. Affected ' ...
                    'SaveFolders will no longer be associated with this project. ' ...
                    'External raw and processed imaging data will not be deleted.'], ...
                    projectInfo.projectName, subjectCount, sessionCount);
            else
                message = sprintf(['Delete invalid project "%s"?\n\nIts centralized ' ...
                    'project record and managed metadata will be removed. Any accessible ' ...
                    'registered SaveFolder bindings will be removed. External raw and ' ...
                    'processed imaging data will not be deleted.'], projectInfo.projectName);
            end
            choice = uiconfirm(app.UIFigure, message, 'Delete Project', ...
                'Options', {'Delete Project', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if ~strcmp(choice, 'Delete Project')
                return
            end
            try
                result = app.Store.deleteProject(app.Editor.NodeData.uuid);
                app.showEmptyState(sprintf(['Project deleted successfully. %d ' ...
                    'subject(s) and %d session(s) were removed; external imaging ' ...
                    'data was preserved.'], result.subjectsRemoved, result.sessionsRemoved));
            catch ME
                try
                    app.refreshTree(app.makeNodeKey('project', projectInfo.projectUUID));
                catch refreshME
                    app.showException(refreshME, 'Project Refresh Failed');
                end
                app.showException(ME, 'Delete Project Failed');
            end
        end

        function tf = isCallerApp(~, value)
            tf = isa(value, 'matlab.ui.Figure') || ...
                (isobject(value) && (isprop(value, 'UIFigure') || ...
                isprop(value, 'Position')));
        end

        function tooltip = getBackendMetadataTooltip(app, entityType, fieldName)
            %GETBACKENDMETADATATOOLTIP Return a backend-owned field description.
            tooltip = '';
            if isempty(app.Store) || isempty(entityType) || isempty(fieldName)
                return
            end
            descriptions = app.Store.getMetadataFieldDescriptions(entityType);
            if isfield(descriptions, fieldName)
                tooltip = descriptions.(fieldName);
            end
        end

        function tf = isLongFormMetadataField(~, fieldName)
            %ISLONGFORMMETADATAFIELD Identify fields that use multiline editing.
            tf = ismember(fieldName, { ...
                'description', 'notes', 'housing', 'diet', 'healthStatus', ...
                'stimulusNotes', 'pharmacology', 'surgery', 'virus', 'anesthesia'});
        end

        function values = normalizeExperimentersForStore(~, value)
            %NORMALIZEEXPERIMENTERSFORSTORE Convert text-area lines to a cellstr.
            values = cellstr(string(value(:)));
            values = values(strlength(strtrim(string(values))) > 0);
        end

        function saveProjectChanges(app)
            original = app.Editor.Metadata;
            fields = {'description', 'institution', 'lab', 'principalInvestigator', ...
                'experimenters', 'projectStartDate', 'projectEndDate', 'notes'};
            updates = app.collectMetadataUpdates(original, app.Editor.Controls, fields);
            if ~isempty(fieldnames(updates))
                app.Store.updateProjectMetadata(updates);
            end
        end

        function updates = collectMetadataUpdates(app, original, controls, fields)
            updates = struct();
            for iField = 1:numel(fields)
                fieldName = fields{iField};
                if ~isfield(controls, fieldName) || ~isfield(original, fieldName)
                    continue
                end
                value = app.readMetadataControlValue(fieldName, controls.(fieldName));
                if ~isequaln(value, original.(fieldName))
                    updates.(fieldName) = value;
                end
            end
        end

        function value = readMetadataControlValue(app, fieldName, control)
            if strcmp(fieldName, 'experimenters')
                value = app.normalizeExperimentersForStore(control.Value);
            elseif strcmp(fieldName, 'weight_g') && isstruct(control.UserData) && ...
                    isfield(control.UserData, 'metadataIsUnset') && ...
                    control.UserData.metadataIsUnset
                value = NaN;
            elseif isa(control, 'matlab.ui.control.TextArea')
                value = app.readTextControl(control);
            else
                value = control.Value;
                if ischar(value) || (isstring(value) && isscalar(value)) || ...
                        (iscategorical(value) && isscalar(value))
                    value = char(string(value));
                end
            end
        end

        function onNumericMetadataChanged(app, control)
            if isstruct(control.UserData) && ...
                    isfield(control.UserData, 'metadataIsUnset')
                control.UserData.metadataIsUnset = false;
            end
            app.markDirty();
        end
        function tf = isDateMetadataField(~, fieldName)
            tf = any(strcmp(fieldName, {'acquisitionDate', 'projectStartDate', ...
                'projectEndDate', 'dateOfBirth'}));
        end

        function value = experimentersTextAreaValue(~, inputValue)
            if isempty(inputValue)
                value = {''};
            else
                value = cellstr(string(inputValue(:)));
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            callerApp = [];
            if ~isempty(varargin) && app.isCallerApp(varargin{1})
                callerApp = varargin{1};
                varargin(1) = [];
            end

            app.NodeMap = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.initializeUI();
            if ~isempty(callerApp)
                placeAppInsideCaller(callerApp, app, 'center');
            end

            try
                if isempty(varargin)
                    % Finish realizing and raise Project Manager before listdlg creates its
                    % own modal figure, so the chooser is the window opened most recently.
                    focus(app.UIFigure);drawnow;pause(.2);
                    store = app.chooseProjectStore();

                elseif isscalar(varargin)
                    store = app.resolveStore(varargin{1});
                else
                    error('Umitoolbox:ProjectManagerTool:invalidStartupInput', ...
                        'Expected a caller followed by at most one store object or project UUID.');
                end

                if ~isempty(store)
                    app.setStore(store);
                else
                    app.showEmptyState('Open or create a project to begin.');
                end
            catch ME
                app.showException(ME, 'Project Manager Startup Failed');
            end
            focus(app.UIFigure);
        end

        % Button pushed function: OpenProjectButton
        function OpenProjectButtonPushed(app, event)
            app.onOpenProject();
        end

        % Button pushed function: NewProjectButton
        function NewProjectButtonPushed(app, event)
            app.onNewProject();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.onRefresh();
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.onValidateProject();
        end

        % Selection changed function: ProjectTree
        function ProjectTreeSelectionChanged(app, event)
            app.onTreeSelectionChanged(event);

        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            app.onSaveChanges();
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.onRevertSelection();
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            app.onAddSubject();
        end

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)
            app.onAddSession();
        end

        % Button pushed function: AddImageReferenceButton
        function AddImageReferenceButtonPushed(app, event)
            app.onAddImageReference();
        end

        % Button pushed function: MoveSessionButton
        function MoveSessionButtonPushed(app, event)
            app.onMoveSession();
        end

        % Button pushed function: SetActiveResourceButton
        function SetActiveResourceButtonPushed(app, event)
            app.onSetActiveResource();
        end

        % Button pushed function: ArchiveResourceButton
        function ArchiveResourceButtonPushed(app, event)
            app.onArchiveResource();
        end

        % Button pushed function: RestoreResourceButton
        function RestoreResourceButtonPushed(app, event)
            app.onRestoreResource();
        end

        % Button pushed function: OpenResourceLocationButton
        function OpenResourceLocationButtonPushed(app, event)
            app.onOpenResourceLocation();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.onCloseRequest();

        end

        % Button pushed function: RebindSaveFolderButton
        function RebindSaveFolderButtonPushed(app, event)
            app.onRebindSaveFolder();
        end

        % Button pushed function: RepairSaveFolderButton
        function RepairSaveFolderButtonPushed(app, event)
            app.onRepairSaveFolder();

        end

        % Button pushed function: OpenSaveFolderButton
        function OpenSaveFolderButtonPushed(app, event)
            app.onOpenSaveFolder();

        end

        % Button pushed function: RemoveSessionFromProjectButton
        function RemoveSessionFromProjectButtonPushed(app, event)
            app.onRemoveSessionFromProject();

        end

        % Button pushed function: BindRawFolderButton
        function BindRawFolderButtonPushed(app, event)
            app.onBindRawFolder();
        end

        % Button pushed function: RemoveSubjectButton
        function RemoveSubjectButtonPushed(app, event)
            app.onRemoveSubjectFromProject;
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            app.onDeleteProject();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Project Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutTop = uigridlayout(app.GridLayout);
            app.OpenProjectButton = uibutton(app.GridLayoutTop, 'push');
            app.OpenProjectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenProjectButtonPushed, true);
            app.OpenProjectButton.Text = 'Open Project';
            app.NewProjectButton = uibutton(app.GridLayoutTop, 'push');
            app.NewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProjectButton.Text = 'New Project';
            app.RefreshButton = uibutton(app.GridLayoutTop, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ValidateButton = uibutton(app.GridLayoutTop, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ValidationModeDropDown = uidropdown(app.GridLayoutTop);
            app.ValidationModeDropDown.Items = {'Quick', 'Full'};
            app.ValidationModeDropDown.Value = 'Quick';
            app.StatusLabel = uilabel(app.GridLayoutTop);
            app.StatusLabel.Text = 'Status';
            app.GridLayoutBottom = uigridlayout(app.GridLayout);
            app.ProjectStructurePanel = uipanel(app.GridLayoutBottom);
            app.ProjectStructurePanel.Title = 'Project Structure';
            app.ProjectStructureGrid = uigridlayout(app.ProjectStructurePanel);
            app.ProjectTree = uitree(app.ProjectStructureGrid);
            app.ProjectTree.SelectionChangedFcn = createCallbackFcn(app, @ProjectTreeSelectionChanged, true);
            app.SelectedItemPanel = uipanel(app.GridLayoutBottom);
            app.SelectedItemPanel.Title = 'Selected Item';
            app.SelectedItemGrid = uigridlayout(app.SelectedItemPanel);
            app.ItemHeaderLabel = uilabel(app.SelectedItemGrid);
            app.ItemHeaderLabel.Text = 'ItemHeader';
            app.SelectedItemBottomGrid = uigridlayout(app.SelectedItemGrid);
            app.SaveChangesButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.RevertButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.AddSessionButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.AddImageReferenceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddImageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageReferenceButtonPushed, true);
            app.AddImageReferenceButton.Text = 'Add Image Reference';
            app.AddSubjectButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Text = 'Add Subject';
            app.MoveSessionButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.MoveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @MoveSessionButtonPushed, true);
            app.MoveSessionButton.Visible = 'off';
            app.MoveSessionButton.Text = 'Move Session';
            app.SetActiveResourceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.SetActiveResourceButton.ButtonPushedFcn = createCallbackFcn(app, @SetActiveResourceButtonPushed, true);
            app.SetActiveResourceButton.Visible = 'off';
            app.SetActiveResourceButton.Text = 'Set Active Resource';
            app.ArchiveResourceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.ArchiveResourceButton.ButtonPushedFcn = createCallbackFcn(app, @ArchiveResourceButtonPushed, true);
            app.ArchiveResourceButton.Visible = 'off';
            app.ArchiveResourceButton.Text = 'Archive Resource';
            app.RestoreResourceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RestoreResourceButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreResourceButtonPushed, true);
            app.RestoreResourceButton.Visible = 'off';
            app.RestoreResourceButton.Text = 'Restore Resource';
            app.OpenResourceLocationButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.OpenResourceLocationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResourceLocationButtonPushed, true);
            app.OpenResourceLocationButton.Visible = 'off';
            app.OpenResourceLocationButton.Text = 'Open Resource Location';
            app.RebindSaveFolderButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RebindSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RebindSaveFolderButtonPushed, true);
            app.RebindSaveFolderButton.Text = 'Rebind / Relocate';
            app.RepairSaveFolderButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RepairSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RepairSaveFolderButtonPushed, true);
            app.RepairSaveFolderButton.Text = 'Repair Binding';
            app.OpenSaveFolderButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.OpenSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSaveFolderButtonPushed, true);
            app.OpenSaveFolderButton.Text = 'Open Folder';
            app.RemoveSessionFromProjectButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RemoveSessionFromProjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSessionFromProjectButtonPushed, true);
            app.RemoveSessionFromProjectButton.Text = 'Remove Session from Project';
            app.BindRawFolderButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.BindRawFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BindRawFolderButtonPushed, true);
            app.BindRawFolderButton.Enable = 'off';
            app.BindRawFolderButton.Visible = 'off';
            app.BindRawFolderButton.Text = 'Bind Raw Folder';
            app.RemoveSubjectButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RemoveSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSubjectButtonPushed, true);
            app.RemoveSubjectButton.Text = 'Remove Subject';
            app.DeleteProjectButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Text = 'Delete Project';
            app.MetadataTabGroup = uitabgroup(app.SelectedItemGrid);
            app.Tab = uitab(app.MetadataTabGroup);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectManagerTool(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end