classdef RigManagerTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    properties (Access = public)
        RootGrid matlab.ui.container.GridLayout
        ToolbarGrid matlab.ui.container.GridLayout
        NewRigButton matlab.ui.control.Button
        DuplicateRigButton matlab.ui.control.Button
        RefreshButton matlab.ui.control.Button
        OpticalLibraryButton matlab.ui.control.Button
        ValidateButton matlab.ui.control.Button
        ValidationModeDropDown matlab.ui.control.DropDown
        RigStatusLabel matlab.ui.control.Label
        StatusLabel matlab.ui.control.Label

        RigWorkspaceGrid matlab.ui.container.GridLayout
        RigListPanel matlab.ui.container.Panel
        RigListTable matlab.ui.control.Table
        ShowArchivedCheckBox matlab.ui.control.CheckBox
        SelectedRigPanel matlab.ui.container.Panel
        SelectedRigHeading matlab.ui.control.Label
        RigTabGroup matlab.ui.container.TabGroup
        OverviewTab matlab.ui.container.Tab
        HardwareTab matlab.ui.container.Tab
        CoregistrationTab matlab.ui.container.Tab

        RigIDField matlab.ui.control.EditField
        DisplayNameField matlab.ui.control.EditField
        DescriptionArea matlab.ui.control.TextArea
        DefaultStatusField matlab.ui.control.EditField
        RigStateField matlab.ui.control.EditField
        CreatedField matlab.ui.control.EditField
        ModifiedField matlab.ui.control.EditField
        MetadataArea matlab.ui.control.TextArea
        AdvancedArea matlab.ui.control.TextArea
        SaveOverviewButton matlab.ui.control.Button
        RevertOverviewButton matlab.ui.control.Button

        Camera1Panel matlab.ui.container.Panel
        CameraLayoutGrid matlab.ui.container.GridLayout
        Camera1DisplayField matlab.ui.control.EditField
        Camera1ManufacturerField matlab.ui.control.EditField
        Camera1ModelField matlab.ui.control.EditField
        Camera1SerialField matlab.ui.control.EditField
        Camera1SpectrumField matlab.ui.control.EditField
        SelectCamera1SpectrumButton matlab.ui.control.Button
        PreviewCamera1SpectrumButton matlab.ui.control.Button
        Camera2Panel matlab.ui.container.Panel
        OptionalCamera2Panel matlab.ui.container.Panel
        Camera2DisplayField matlab.ui.control.EditField
        Camera2ManufacturerField matlab.ui.control.EditField
        Camera2ModelField matlab.ui.control.EditField
        Camera2SerialField matlab.ui.control.EditField
        Camera2SpectrumField matlab.ui.control.EditField
        SelectCamera2SpectrumButton matlab.ui.control.Button
        PreviewCamera2SpectrumButton matlab.ui.control.Button
        RemoveCamera2Button matlab.ui.control.Button
        AddCamera2Button matlab.ui.control.Button
        IlluminationTable matlab.ui.control.Table
        SelectedIlluminationLabel matlab.ui.control.Label
        SelectIlluminationSpectrumButton matlab.ui.control.Button
        RemoveIlluminationSpectrumButton matlab.ui.control.Button
        PreviewIlluminationSpectrumButton matlab.ui.control.Button
        EditHardwareButton matlab.ui.control.Button
        ApplyHardwareButton matlab.ui.control.Button
        RevertHardwareButton matlab.ui.control.Button

        CoregistrationMessageLabel matlab.ui.control.Label
        CoregistrationTable matlab.ui.control.Table
        ImportCoregistrationButton matlab.ui.control.Button
        ActivateCoregistrationButton matlab.ui.control.Button
        EditCoregistrationButton matlab.ui.control.Button
        ArchiveCoregistrationButton matlab.ui.control.Button
        RestoreCoregistrationButton matlab.ui.control.Button
        PurgeCoregistrationButton matlab.ui.control.Button

        SetDefaultButton matlab.ui.control.Button
        ArchiveRigButton matlab.ui.control.Button
        RestoreRigButton matlab.ui.control.Button
        RenameRigButton matlab.ui.control.Button
        UseSelectedRigButton matlab.ui.control.Button

        LibraryWorkspaceGrid matlab.ui.container.GridLayout
        BackToRigsButton matlab.ui.control.Button
        LibraryCategoryDropDown matlab.ui.control.DropDown
        LibrarySearchField matlab.ui.control.EditField
        LibraryTable matlab.ui.control.Table
        LibraryImportButton matlab.ui.control.Button
        LibraryEditButton matlab.ui.control.Button
        LibraryRemoveButton matlab.ui.control.Button
        LibraryMetadataArea matlab.ui.control.TextArea
        LibraryAxes matlab.ui.control.UIAxes
    end

    properties (SetAccess = private)
        SelectedRigUUID char = ''
        SelectedRigID char = ''
        OutputRigUUID char = ''
        OutputRigID char = ''
        WasSelectionConfirmed logical = false
    end

    properties (Access = private)
        ParentApp = []
        AppMode char = 'manage'
        Store = []
        RigTable table = table()
        VisibleRigRows table = table()
        IsSelectionGuard logical = false
        IsClosing logical = false
        IsHardwareEditing logical = false
        HardwareOriginal struct = struct()
        HardwarePending struct = struct()
        SelectedIlluminationRow double = 1
        CurrentValidationReport struct = struct()
        CoregistrationRows struct = struct([])
        LibraryData table = table()
        LibraryUnfilteredData table = table()
        LibraryCategory char = 'illumination'
    end

    properties (Access = private)

    end

    methods (Access = public)
        function refreshRigs(app, requestedRig)
            %REFRESHRIGS Reload the Rig list while preserving selection.

            if nargin < 2
                requestedRig = app.SelectedRigUUID;
            end
            if app.IsHardwareEditing && ...
                    ~app.resolvePendingHardware('refreshing the Rig list')
                return
            end

            app.setStatus('Refreshing Rigs...', 'busy');
            try
                rigs = UMITRigStore.listRigs();
                app.RigTable = rigs;
                if ~app.ShowArchivedCheckBox.Value && ~isempty(rigs)
                    rigs = rigs(rigs.Status ~= "archived", :);
                end
                if ~isempty(rigs)
                    rigs = sortrows(rigs, ...
                        {'IsDefault', 'DisplayName', 'RigID'}, ...
                        {'descend', 'ascend', 'ascend'});
                end
                app.VisibleRigRows = rigs;

                if isempty(rigs)
                    app.RigListTable.Data = table( ...
                        strings(0, 1), strings(0, 1), strings(0, 1), ...
                        strings(0, 1), 'VariableNames', ...
                        {'Default', 'DisplayName', 'RigID', 'State'});
                    app.RigListTable.Selection = [];
                    app.RigListTable.UserData = [];
                    app.RigListTable.Enable = 'off';
                    app.clearSelectedRig('Create a Rig to begin.');
                    app.setStatus('No Rigs are configured.', 'info');
                    return
                end

                displayName = rigs.DisplayName;
                emptyNames = strlength(displayName) == 0;
                displayName(emptyNames) = rigs.RigID(emptyNames);
                defaultText = repmat("", height(rigs), 1);
                defaultText(rigs.IsDefault) = "Default";
                stateText = strings(height(rigs), 1);
                for iRig = 1:height(rigs)
                    if ~rigs.IsReadable(iRig)
                        stateText(iRig) = "Invalid / unreadable";
                    else
                        stateText(iRig) = string(app.titleCase(rigs.Status(iRig)));
                    end
                end

                app.RigListTable.Data = table(defaultText, displayName, ...
                    rigs.RigID, stateText, 'VariableNames', ...
                    {'Default', 'DisplayName', 'RigID', 'State'});
                app.RigListTable.Enable = 'on';
                selectedIndex = app.resolveRequestedRigIndex(rigs, requestedRig);
                app.selectRigTableRow(selectedIndex);
                app.openRigRow(selectedIndex);
                app.setStatus(sprintf('%d Rig(s) available.', height(rigs)), 'success');
            catch ME
                app.showException(ME, 'Rig Refresh Failed');
            end
        end

        function showRig(app, rigSelector)
            %SHOWRIG Select a Rig by store object, UUID, or Rig ID.

            if isa(rigSelector, 'UMITRigStore')
                info = rigSelector.getRigInfo();
                rigSelector = info.uuid;
            end
            if ~(ischar(rigSelector) || ...
                    (isstring(rigSelector) && isscalar(rigSelector)))
                error('Umitoolbox:RigManagerTool:invalidRigSelector', ...
                    'Rig selector must be a UMITRigStore, UUID, or Rig ID.');
            end
            app.refreshRigs(char(string(rigSelector)));
        end
    end


    methods (Access = private)
        function configureInitialState(app)
            app.UIFigure.Name = 'UMIT Rig Manager';
            app.ValidationModeDropDown.Items = {'Quick', 'Full'};
            app.ValidationModeDropDown.Value = 'Quick';
            app.ValidationModeDropDown.Tooltip = ...
                'Full validation also verifies managed resource contents.';
            app.ShowArchivedCheckBox.Value = true;
            app.LibraryWorkspaceGrid.Visible = 'off';
            app.RigWorkspaceGrid.Visible = 'on';
            app.UseSelectedRigButton.Visible = app.onOff( ...
                ismember(app.AppMode, {'setup', 'select'}));
            if ismember(app.AppMode, {'setup', 'select'})
                app.UIFigure.WindowStyle = 'modal';
                if strcmp(app.AppMode, 'setup')
                    app.UseSelectedRigButton.Text = 'Finish Setup';
                else
                    app.UseSelectedRigButton.Text = 'Use Selected Rig';
                end
            end
            if exist('setGUIcolorScheme', 'file') == 2
                setGUIcolorScheme(app.UIFigure, 'light');
            end
            app.setStatus('Ready.', 'neutral');
        end

        function tf = isCallerApp(~, value)
            tf = isa(value, 'matlab.apps.AppBase') || ...
                (isobject(value) && isprop(value, 'UIFigure')) || ...
                isa(value, 'matlab.ui.Figure');
        end

        function index = resolveRequestedRigIndex(~, rigs, requestedRig)
            index = [];
            if isa(requestedRig, 'UMITRigStore')
                requestedRig = requestedRig.getRigInfo().uuid;
            end
            if ischar(requestedRig) || ...
                    (isstring(requestedRig) && isscalar(requestedRig))
                requested = string(requestedRig);
                index = find(strcmpi(rigs.RigUUID, requested) | ...
                    strcmpi(rigs.RigID, requested), 1);
            end
            if isempty(index)
                index = find(rigs.IsDefault, 1);
            end
            if isempty(index)
                index = 1;
            end
        end

        function onRigSelectionChanged(app, event)
            if app.IsSelectionGuard || isempty(app.VisibleRigRows) || ...
                    isempty(event.Indices)
                return
            end
            index = event.Indices(1);
            if index > height(app.VisibleRigRows)
                return
            end
            if app.IsHardwareEditing && ...
                    ~app.resolvePendingHardware('changing the selected Rig')
                app.restoreRigListSelection();
                return
            end
            app.selectRigTableRow(index);
            app.openRigRow(index);
        end

        function restoreRigListSelection(app)
            if isempty(app.SelectedRigUUID) || isempty(app.VisibleRigRows)
                return
            end
            idx = find(strcmpi(app.VisibleRigRows.RigUUID, ...
                string(app.SelectedRigUUID)), 1);
            if isempty(idx)
                return
            end
            app.selectRigTableRow(idx);
        end

        function selectRigTableRow(app, index)
            app.IsSelectionGuard = true;
            app.RigListTable.UserData = index;
            columnCount = width(app.RigListTable.Data);
            app.RigListTable.Selection = [ ...
                repmat(index, columnCount, 1), (1:columnCount)'];
            app.IsSelectionGuard = false;
        end

        function openRigRow(app, index)
            row = app.VisibleRigRows(index, :);
            app.SelectedRigUUID = char(row.RigUUID);
            app.SelectedRigID = char(row.RigID);
            app.IsHardwareEditing = false;

            if ~row.IsReadable
                app.Store = [];
                app.renderUnreadableRig(row);
                return
            end
            try
                app.Store = UMITRigStore.open(char(row.RigUUID));
                app.CurrentValidationReport = app.Store.validate('Mode', 'quick');
                app.renderSelectedRig();
            catch ME
                app.Store = [];
                app.renderUnreadableRig(row, ME);
            end
        end

        function renderUnreadableRig(app, row, exception)
            if nargin < 3
                message = sprintf('Rig metadata for "%s" is unreadable.', row.RigID);
            else
                message = exception.message;
            end
            app.SelectedRigHeading.Text = sprintf('Unavailable Rig: %s', row.RigID);
            app.RigIDField.Value = char(row.RigID);
            app.DisplayNameField.Value = char(row.DisplayName);
            app.DescriptionArea.Value = {message};
            app.DefaultStatusField.Value = app.yesNo(row.IsDefault);
            app.RigStateField.Value = 'Unreadable';
            app.CreatedField.Value = '';
            app.ModifiedField.Value = '';
            app.MetadataArea.Value = {'Unavailable'};
            app.AdvancedArea.Value = {message, char(row.RigRoot)};
            app.RigStatusLabel.Text = sprintf('%s | Read-only | Invalid', row.RigID);
            app.setMutationControls(false, false, false);
            app.renderEmptyHardware();
            app.renderCoregistration();
            app.setStatus(message, 'error');
        end

        function renderSelectedRig(app)
            info = app.Store.getRigInfo();
            rowIndex = find(strcmpi(app.RigTable.RigUUID, string(info.uuid)), 1);
            isDefault = ~isempty(rowIndex) && app.RigTable.IsDefault(rowIndex);
            isArchived = strcmp(info.status, 'archived');
            isWritable = ~app.Store.IsReadOnly && ~isArchived;
            isValid = isfield(app.CurrentValidationReport, 'isValid') && ...
                app.CurrentValidationReport.isValid;

            app.SelectedRigUUID = info.uuid;
            app.SelectedRigID = info.rigID;
            app.SelectedRigHeading.Text = sprintf('%s (%s)', ...
                app.nonemptyText(info.displayName, info.rigID), info.rigID);
            app.RigIDField.Value = info.rigID;
            app.DisplayNameField.Value = info.displayName;
            app.DescriptionArea.Value = app.textAreaValue(info.description);
            app.DefaultStatusField.Value = app.yesNo(isDefault);
            app.RigStateField.Value = app.titleCase(info.status);
            app.CreatedField.Value = app.formatDate(info.createdOn);
            app.ModifiedField.Value = app.formatDate(info.modifiedOn);
            app.MetadataArea.Value = app.formatMetadata(info.metadata);
            app.AdvancedArea.Value = app.formatAdvancedDetails(info);
            app.RigStatusLabel.Text = sprintf('%s | %s | %s', info.rigID, ...
                app.chooseText(isWritable, 'Writable', 'Read-only'), ...
                app.chooseText(isValid, 'Valid', 'Invalid'));

            app.HardwareOriginal = struct( ...
                'cameras', app.normalizeCameras(info.cameras), ...
                'illuminations', app.normalizeIlluminations(info.illuminations));
            app.HardwarePending = app.HardwareOriginal;
            app.renderHardware();
            app.renderCoregistration();
            app.setMutationControls(isWritable, isArchived, ...
                strcmp(info.status, 'active'));
            if app.Store.IsReadOnly
                app.setStatus('Rig validation failed; mutation controls are disabled.', 'error');
            elseif isArchived
                app.setStatus('Archived Rig is read-only until restored.', 'warning');
            end
        end

        function clearSelectedRig(app, message)
            app.Store = [];
            app.SelectedRigUUID = '';
            app.SelectedRigID = '';
            app.SelectedRigHeading.Text = message;
            app.RigStatusLabel.Text = 'No Rig selected';
            fields = {app.RigIDField, app.DisplayNameField, ...
                app.DefaultStatusField, app.RigStateField, ...
                app.CreatedField, app.ModifiedField};
            for iField = 1:numel(fields)
                fields{iField}.Value = '';
            end
            app.DescriptionArea.Value = {''};
            app.MetadataArea.Value = {''};
            app.AdvancedArea.Value = {message};
            app.renderEmptyHardware();
            app.renderCoregistration();
            app.setMutationControls(false, false, false);
        end

        function setMutationControls(app, writable, archived, isActive)
            app.DisplayNameField.Editable = app.onOff(writable);
            app.DescriptionArea.Editable = app.onOff(writable);
            app.SaveOverviewButton.Enable = app.onOff(writable);
            app.RevertOverviewButton.Enable = app.onOff(writable);
            app.EditHardwareButton.Enable = app.onOff(writable);
            app.DuplicateRigButton.Enable = app.onOff(~isempty(app.Store) && ~archived);
            app.SetDefaultButton.Enable = app.onOff(writable && ~isActive);
            app.ArchiveRigButton.Enable = app.onOff(writable && ~archived);
            app.ArchiveRigButton.Visible = app.onOff(~archived);
            app.RestoreRigButton.Visible = app.onOff(archived);
            app.RestoreRigButton.Enable = app.onOff(archived && ...
                ~isempty(app.Store) && ~app.Store.IsReadOnly);
            app.RenameRigButton.Enable = app.onOff(writable);
            app.ValidateButton.Enable = app.onOff(~isempty(app.Store));
            app.UseSelectedRigButton.Enable = app.onOff(writable || ...
                (~isempty(app.Store) && ~archived && ~app.Store.IsReadOnly));
        end

        function onSaveOverview(app)
            if isempty(app.Store)
                return
            end
            try
                updates = struct( ...
                    'displayName', strtrim(app.DisplayNameField.Value), ...
                    'description', app.readTextArea(app.DescriptionArea));
                app.Store.updateRigMetadata(updates);
                app.refreshRigs(app.SelectedRigUUID);
                app.setStatus('Rig details saved.', 'success');
            catch ME
                app.showException(ME, 'Save Rig Details Failed');
            end
        end

        function onRenameRig(app)
            if isempty(app.Store)
                return
            end
            answer = inputdlg({'New Rig ID:'}, 'Rename Rig ID', [1 60], ...
                {app.SelectedRigID});
            if isempty(answer)
                return
            end
            choice = uiconfirm(app.UIFigure, sprintf([ ...
                'Rename Rig ID "%s" to "%s"?\n\nThe backend will ' ...
                'transactionally rename the managed Rig folder.'], ...
                app.SelectedRigID, strtrim(answer{1})), 'Rename Rig ID', ...
                'Options', {'Rename', 'Cancel'}, 'DefaultOption', 2, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Rename')
                return
            end
            try
                app.Store.renameRigID(strtrim(answer{1}));
                app.refreshRigs(app.Store.getRigInfo().uuid);
                app.setStatus('Rig ID renamed.', 'success');
            catch ME
                app.showException(ME, 'Rename Rig Failed');
            end
        end

        function onSetDefault(app)
            try
                UMITRigStore.setDefaultRig(app.SelectedRigUUID);
                app.refreshRigs(app.SelectedRigUUID);
                app.setStatus('Active Rig updated.', 'success');
            catch ME
                app.showException(ME, 'Activate Rig Failed');
            end
        end

        function onArchiveRig(app)
            info = app.Store.getRigInfo();
            choice = uiconfirm(app.UIFigure, sprintf( ...
                'Archive Rig "%s"? It will remain available for historical data.', ...
                info.rigID), 'Archive Rig', ...
                'Options', {'Archive', 'Cancel'}, 'DefaultOption', 2, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Archive')
                return
            end
            replacementUUID = '';
            if strcmp(info.status, 'active')
                rigs = UMITRigStore.listAssignableRigs();
                rigs = rigs(~strcmpi(rigs.RigUUID, info.uuid), :);
                if isempty(rigs)
                    uialert(app.UIFigure, ...
                        'Activate another Rig before archiving the Active Rig.', ...
                        'Replacement Rig required');
                    return
                end
                labels = string(rigs.DisplayName);
                labels(strlength(labels) == 0) = rigs.RigID(strlength(labels) == 0);
                [selection, confirmed] = listdlg( ...
                    'ListString', cellstr(labels), ...
                    'SelectionMode', 'single', ...
                    'Name', 'Replacement Active Rig', ...
                    'PromptString', 'Choose the Rig that becomes Active:', ...
                    'ListSize', [420, 220]);
                if ~confirmed || isempty(selection)
                    return
                end
                replacementUUID = char(rigs.RigUUID(selection));
            end
            try
                app.Store.archiveRig(replacementUUID);
                app.refreshRigs(info.uuid);
                app.setStatus('Rig archived.', 'success');
            catch ME
                app.showException(ME, 'Archive Rig Failed');
            end
        end

        function onRestoreRig(app)
            try
                app.Store.restoreRig();
                app.refreshRigs(app.SelectedRigUUID);
                app.setStatus('Rig restored.', 'success');
            catch ME
                app.showException(ME, 'Restore Rig Failed');
            end
        end

        function onValidateRig(app)
            if isempty(app.Store)
                return
            end
            mode = lower(app.ValidationModeDropDown.Value);
            try
                app.setStatus('Validating Rig...', 'busy');
                report = app.Store.validate('Mode', mode);
                app.CurrentValidationReport = report;
                app.renderSelectedRig();
                if report.isValid
                    warnings = app.getField(report, 'warnings', []);
                    uialert(app.UIFigure, sprintf( ...
                        'Rig is valid.\nWarnings: %d\nMode: %s', ...
                        numel(warnings), mode), ...
                        'Validation Passed', 'Icon', 'success');
                else
                    errors = app.getField(report, 'errors', []);
                    uialert(app.UIFigure, sprintf( ...
                        'Validation failed.\n\n%s', ...
                        app.issueSummary(errors, 10)), ...
                        'Validation Failed', 'Icon', 'error');
                end
            catch ME
                app.showException(ME, 'Rig Validation Failed');
            end
        end
        function renderEmptyHardware(app)
            app.HardwareOriginal = struct('cameras', app.blankCamera(1), ...
                'illuminations', app.blankIlluminations());
            app.HardwarePending = app.HardwareOriginal;
            app.renderHardware();
        end

        function renderHardware(app)
            hardware = app.HardwarePending;
            cameras = app.normalizeCameras(hardware.cameras);
            app.assignCameraControls(1, cameras(1));
            hasCamera2 = numel(cameras) >= 2;
            app.Camera2Panel.Visible = app.onOff(hasCamera2);
            app.OptionalCamera2Panel.Visible = app.onOff(~hasCamera2);
            app.AddCamera2Button.Visible = app.onOff(~hasCamera2);
            if hasCamera2
                app.CameraLayoutGrid.ColumnWidth = {'1x', '1x', 0};
                app.assignCameraControls(2, cameras(2));
            else
                app.CameraLayoutGrid.ColumnWidth = {'1x', 0, 180};
            end

            illuminations = app.normalizeIlluminations(hardware.illuminations);
            data = cell(3, 5);
            for iRow = 1:3
                data(iRow, :) = {app.titleCase(illuminations(iRow).name), ...
                    illuminations(iRow).displayName, ...
                    illuminations(iRow).manufacturer, ...
                    illuminations(iRow).model, ...
                    illuminations(iRow).spectrumID};
            end
            app.IlluminationTable.Data = data;
            app.IlluminationTable.ColumnEditable = ...
                [false app.IsHardwareEditing app.IsHardwareEditing ...
                app.IsHardwareEditing false];
            app.SelectedIlluminationRow = min(max( ...
                app.SelectedIlluminationRow, 1), 3);
            app.updateSelectedIlluminationLabel();
            app.setHardwareEditingState();
        end

        function setHardwareEditingState(app)
            editable = app.IsHardwareEditing;
            controls = {app.Camera1DisplayField, app.Camera1ManufacturerField, ...
                app.Camera1ModelField, app.Camera1SerialField, ...
                app.Camera2DisplayField, app.Camera2ManufacturerField, ...
                app.Camera2ModelField, app.Camera2SerialField};
            for iControl = 1:numel(controls)
                controls{iControl}.Editable = app.onOff(editable);
            end
            spectrumButtons = {app.SelectCamera1SpectrumButton, ...
                app.SelectCamera2SpectrumButton, ...
                app.SelectIlluminationSpectrumButton, app.AddCamera2Button, ...
                app.RemoveIlluminationSpectrumButton, app.RemoveCamera2Button};
            for iControl = 1:numel(spectrumButtons)
                spectrumButtons{iControl}.Enable = app.onOff(editable);
            end
            selectedSpectrum = app.HardwarePending.illuminations( ...
                app.SelectedIlluminationRow).spectrumID;
            hasSelectedSpectrum = ~isempty(selectedSpectrum);
            app.RemoveIlluminationSpectrumButton.Enable = app.onOff( ...
                editable && hasSelectedSpectrum);
            app.PreviewIlluminationSpectrumButton.Enable = ...
                app.onOff(hasSelectedSpectrum);
            app.EditHardwareButton.Visible = app.onOff(~editable);
            app.ApplyHardwareButton.Visible = app.onOff(editable);
            app.RevertHardwareButton.Visible = app.onOff(editable);
        end

        function assignCameraControls(app, index, record)
            if index == 1
                fields = {app.Camera1DisplayField, app.Camera1ManufacturerField, ...
                    app.Camera1ModelField, app.Camera1SerialField, ...
                    app.Camera1SpectrumField};
            else
                fields = {app.Camera2DisplayField, app.Camera2ManufacturerField, ...
                    app.Camera2ModelField, app.Camera2SerialField, ...
                    app.Camera2SpectrumField};
            end
            values = {record.displayName, record.manufacturer, record.model, ...
                record.serialNumber, record.spectrumID};
            for iField = 1:numel(fields)
                fields{iField}.Value = values{iField};
            end
        end

        function onEditHardware(app)
            app.HardwareOriginal = struct( ...
                'cameras', app.normalizeCameras( ...
                app.Store.getRigInfo().cameras), ...
                'illuminations', app.normalizeIlluminations( ...
                app.Store.getRigInfo().illuminations));
            app.HardwarePending = app.HardwareOriginal;
            app.IsHardwareEditing = true;
            app.renderHardware();
            app.setStatus('Hardware edits are staged until Apply.', 'info');
        end

        function collectHardwareControls(app)
            cameras = app.HardwarePending.cameras;
            cameras(1) = app.readCameraControls(1, cameras(1));
            if numel(cameras) >= 2
                cameras(2) = app.readCameraControls(2, cameras(2));
            end
            app.HardwarePending.cameras = cameras;
            data = app.IlluminationTable.Data;
            illuminations = app.HardwarePending.illuminations;
            for iRow = 1:3
                illuminations(iRow).displayName = char(string(data{iRow, 2}));
                illuminations(iRow).manufacturer = char(string(data{iRow, 3}));
                illuminations(iRow).model = char(string(data{iRow, 4}));
            end
            app.HardwarePending.illuminations = illuminations;
        end

        function record = readCameraControls(app, index, record)
            if index == 1
                controls = {app.Camera1DisplayField, app.Camera1ManufacturerField, ...
                    app.Camera1ModelField, app.Camera1SerialField};
            else
                controls = {app.Camera2DisplayField, app.Camera2ManufacturerField, ...
                    app.Camera2ModelField, app.Camera2SerialField};
            end
            record.displayName = strtrim(controls{1}.Value);
            record.manufacturer = strtrim(controls{2}.Value);
            record.model = strtrim(controls{3}.Value);
            record.serialNumber = strtrim(controls{4}.Value);
        end

        function onAddCamera2(app)
            app.collectHardwareControls();
            if numel(app.HardwarePending.cameras) >= 2
                return
            end
            spectrumID = app.pickSpectrum('camera', '');
            if isempty(spectrumID)
                app.setStatus( ...
                    'Camera 2 was not added because no spectral profile was selected.', ...
                    'info');
                return
            end
            camera2 = app.blankCamera(2);
            camera2.spectrumID = spectrumID;
            app.HardwarePending.cameras(2) = camera2;
            app.renderHardware();
            app.setStatus('Camera 2 added to the staged configuration.', 'info');
        end

        function onRemoveCamera2(app)
            choice = uiconfirm(app.UIFigure, [ ...
                'Remove Camera 2 from this configuration? Camera ' ...
                'coregistration resources may no longer be applicable.'], ...
                'Remove Camera 2', 'Options', {'Remove', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if ~strcmp(choice, 'Remove')
                return
            end
            app.collectHardwareControls();
            app.HardwarePending.cameras = app.HardwarePending.cameras(1);
            app.renderHardware();
        end

        function onSelectCameraSpectrum(app, index)
            currentID = '';
            if numel(app.HardwarePending.cameras) >= index
                currentID = app.HardwarePending.cameras(index).spectrumID;
            end
            spectrumID = app.pickSpectrum('camera', currentID);
            if isempty(spectrumID)
                return
            end
            app.collectHardwareControls();
            app.HardwarePending.cameras(index).spectrumID = spectrumID;
            app.renderHardware();
        end

        function onPreviewCameraSpectrum(app, index)
            if numel(app.HardwarePending.cameras) < index
                return
            end
            app.previewSpectrum('camera', ...
                app.HardwarePending.cameras(index).spectrumID);
        end

        function onIlluminationSelection(app, event)
            if isempty(event.Indices)
                return
            end
            app.SelectedIlluminationRow = event.Indices(1);
            app.updateSelectedIlluminationLabel();
        end

        function updateSelectedIlluminationLabel(app)
            illuminations = app.HardwarePending.illuminations;
            row = app.SelectedIlluminationRow;
            if isempty(illuminations) || row > numel(illuminations)
                app.SelectedIlluminationLabel.Text = 'No illumination selected';
                return
            end
            app.SelectedIlluminationLabel.Text = sprintf('%s | Spectrum: %s', ...
                app.titleCase(illuminations(row).name), ...
                app.nonemptyText(illuminations(row).spectrumID, 'Not selected'));
        end

        function onSelectIlluminationSpectrum(app)
            row = app.SelectedIlluminationRow;
            currentID = app.HardwarePending.illuminations(row).spectrumID;
            spectrumID = app.pickSpectrum('illumination', currentID);
            if isempty(spectrumID)
                return
            end
            app.collectHardwareControls();
            app.HardwarePending.illuminations(row).spectrumID = spectrumID;
            app.renderHardware();
        end

        function onRemoveIlluminationSpectrum(app)
            if ~app.IsHardwareEditing
                return
            end
            row = app.SelectedIlluminationRow;
            if isempty(app.HardwarePending.illuminations(row).spectrumID)
                return
            end
            app.collectHardwareControls();
            app.HardwarePending.illuminations(row).spectrumID = '';
            app.renderHardware();
            app.setStatus(sprintf( ...
                '%s spectrum removed from the staged configuration.', ...
                app.titleCase(app.HardwarePending.illuminations(row).name)), ...
                'info');
        end

        function onPreviewIlluminationSpectrum(app)
            row = app.SelectedIlluminationRow;
            app.previewSpectrum('illumination', ...
                app.HardwarePending.illuminations(row).spectrumID);
        end

        function onApplyHardware(app)
            app.collectHardwareControls();
            if ~app.cameraSpectraAreComplete( ...
                    app.HardwarePending.cameras, ...
                    'Incomplete Camera Configuration')
                return
            end
            choice = uiconfirm(app.UIFigure, sprintf([ ...
                'Changing physical hardware can affect how future analysis ' ...
                'resolves Rig "%s".\n\nApply a metadata correction to this ' ...
                'Rig, or duplicate it for a physical configuration change?'], ...
                app.SelectedRigID), 'Apply Hardware Configuration', ...
                'Options', {'Apply correction', 'Duplicate as new Rig', 'Cancel'}, ...
                'DefaultOption', 3, 'CancelOption', 3);
            switch choice
                case 'Apply correction'
                    app.applyHardwareToStore(app.Store);
                case 'Duplicate as new Rig'
                    app.duplicateWithPendingHardware();
            end
        end

        function success = applyHardwareToStore(app, store)
            success = false;
            if ~app.cameraSpectraAreComplete( ...
                    app.HardwarePending.cameras, ...
                    'Incomplete Camera Configuration')
                return
            end
            try
                store.setHardwareConfiguration( ...
                    app.HardwarePending.cameras, ...
                    app.HardwarePending.illuminations);
                app.IsHardwareEditing = false;
                app.refreshRigs(store.getRigInfo().uuid);
                app.setStatus('Hardware configuration applied.', 'success');
                success = true;
            catch ME
                app.showException(ME, 'Apply Hardware Failed');
            end
        end

        function duplicateWithPendingHardware(app)
            if ~app.cameraSpectraAreComplete( ...
                    app.HardwarePending.cameras, ...
                    'Incomplete Camera Configuration')
                return
            end
            answer = inputdlg({'New Rig ID:', 'Display name:'}, ...
                'Duplicate Rig for Hardware Change', [1 60; 1 60], ...
                {[app.SelectedRigID '_new'], ...
                [app.DisplayNameField.Value ' - New Configuration']});
            if isempty(answer)
                return
            end
            try
                newStore = app.Store.duplicate(strtrim(answer{1}), ...
                    'DisplayName', strtrim(answer{2}));
                newStore.setHardwareConfiguration( ...
                    app.HardwarePending.cameras, ...
                    app.HardwarePending.illuminations);
                app.IsHardwareEditing = false;
                app.refreshRigs(newStore.getRigInfo().uuid);
                app.setStatus('Rig duplicated with the staged hardware.', 'success');
            catch ME
                app.showException(ME, 'Duplicate and Apply Failed');
            end
        end

        function onRevertHardware(app)
            app.HardwarePending = app.HardwareOriginal;
            app.IsHardwareEditing = false;
            app.renderHardware();
            app.setStatus('Staged hardware changes discarded.', 'info');
        end

        function proceed = resolvePendingHardware(app, actionText)
            proceed = true;
            if ~app.IsHardwareEditing
                return
            end
            choice = uiconfirm(app.UIFigure, sprintf( ...
                'Hardware configuration has unapplied changes. Apply them before %s?', ...
                actionText), 'Unapplied Hardware Changes', ...
                'Options', {'Apply', 'Discard', 'Cancel'}, ...
                'DefaultOption', 3, 'CancelOption', 3);
            switch choice
                case 'Apply'
                    app.collectHardwareControls();
                    proceed = app.applyHardwareToStore(app.Store);
                case 'Discard'
                    app.onRevertHardware();
                otherwise
                    proceed = false;
            end
        end

        function onDuplicateRig(app)
            if isempty(app.Store)
                return
            end
            if ~app.cameraSpectraAreComplete( ...
                    app.Store.getRigInfo().cameras, ...
                    'Cannot Duplicate Rig')
                return
            end
            answer = inputdlg({'New Rig ID:', 'Display name:'}, ...
                'Duplicate Rig', [1 60; 1 60], ...
                {[app.SelectedRigID '_copy'], [app.DisplayNameField.Value ' Copy']});
            if isempty(answer)
                return
            end
            try
                newStore = app.Store.duplicate(strtrim(answer{1}), ...
                    'DisplayName', strtrim(answer{2}));
                app.refreshRigs(newStore.getRigInfo().uuid);
                app.setStatus('Rig duplicated with a new identity.', 'success');
            catch ME
                app.showException(ME, 'Duplicate Rig Failed');
            end
        end

        function onNewRig(app)
            choices = {'OiS200 LightTrack Imaging System', ...
                'Blank custom Rig', 'Duplicate existing Rig'};
            [selection, ok] = listdlg('ListString', choices, ...
                'SelectionMode', 'single', 'Name', 'New Rig', ...
                'PromptString', 'Choose a starting point:', ...
                'ListSize', [420 180]);
            if ~ok
                return
            end
            if selection == 3
                app.newRigFromDuplicate();
                return
            end

            if selection == 1
                definition = UMITRigStore.getBuiltInRigDefinition('OiS200');
                templateCamera2 = definition.cameras(2);
                definition.cameras = definition.cameras(1);
                includeSecond = uiconfirm(app.UIFigure, [ ...
                    'The OiS200 template includes an optional second camera. ' ...
                    'Add Camera 2 to this new Rig?'], ...
                    'Optional Camera 2', ...
                    'Options', {'Keep Camera 1 only', 'Add Camera 2'}, ...
                    'DefaultOption', 1, 'CancelOption', 1);
                if strcmp(includeSecond, 'Add Camera 2')
                    definition.cameras(2) = templateCamera2;
                end
            else
                definition = struct( ...
                    'rigID', '', 'displayName', '', 'description', '', ...
                    'cameras', app.blankCamera(1), ...
                    'illuminations', app.blankIlluminations());
                if ~app.configureCustomRigSpectra(definition)
                    return
                end
                definition = app.HardwarePending;
                definition.rigID = '';
                definition.displayName = '';
                definition.description = '';
            end

            if ~app.cameraSpectraAreComplete( ...
                    definition.cameras, 'Incomplete Camera Configuration')
                return
            end

            answer = inputdlg({'Rig ID:', 'Display name:', 'Description:'}, ...
                'New Rig Details', [1 64; 1 64; 4 64], ...
                {definition.rigID, definition.displayName, definition.description});
            if isempty(answer)
                return
            end
            definition.rigID = strtrim(answer{1});
            definition.displayName = strtrim(answer{2});
            definition.description = answer{3};

            makeDefault = false;
            if ~makeDefault
                defaultChoice = uiconfirm(app.UIFigure, ...
                    'Make this the Active Rig for new work?', 'Active Rig', ...
                    'Options', {'Yes', 'No'}, 'DefaultOption', 2, ...
                    'CancelOption', 2);
                makeDefault = strcmp(defaultChoice, 'Yes');
            end
            review = sprintf([ ...
                'Review the new Rig:\n\nID: %s\nDisplay name: %s\n' ...
                'Cameras: %d\nIlluminations: %d\nActive: %s'], ...
                definition.rigID, definition.displayName, ...
                numel(definition.cameras), numel(definition.illuminations), ...
                app.yesNo(makeDefault));
            choice = uiconfirm(app.UIFigure, review, 'Review New Rig', ...
                'Options', {'Create Rig', 'Cancel'}, 'DefaultOption', 1, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Create Rig')
                return
            end
            try
                store = UMITRigStore.create(definition);
                if makeDefault
                    UMITRigStore.setDefaultRig(store.getRigInfo().uuid);
                end
                app.refreshRigs(store.getRigInfo().uuid);
                app.setStatus('Rig created successfully.', 'success');
            catch ME
                app.showException(ME, 'Create Rig Failed');
            end
        end

        function success = configureCustomRigSpectra(app, definition)
            success = false;
            app.HardwarePending = struct('cameras', definition.cameras, ...
                'illuminations', definition.illuminations);
            cameraID = app.pickSpectrum('camera', '');
            if isempty(cameraID)
                return
            end
            app.HardwarePending.cameras(1).spectrumID = cameraID;
            for iRow = 1:3
                spectrumID = app.pickSpectrum('illumination', '');
                if isempty(spectrumID)
                    return
                end
                app.HardwarePending.illuminations(iRow).spectrumID = spectrumID;
            end
            includeSecond = uiconfirm(app.UIFigure, ...
                'Add a second camera to this custom Rig?', ...
                'Optional Camera 2', ...
                'Options', {'No', 'Add Camera 2'}, ...
                'DefaultOption', 1, 'CancelOption', 1);
            if strcmp(includeSecond, 'Add Camera 2')
                camera2 = app.blankCamera(2);
                camera2.spectrumID = app.pickSpectrum('camera', '');
                if isempty(camera2.spectrumID)
                    return
                end
                app.HardwarePending.cameras(2) = camera2;
            end
            success = true;
        end

        function newRigFromDuplicate(app)
            rigs = UMITRigStore.listRigs();
            rigs = rigs(rigs.IsReadable & rigs.Status ~= "archived", :);
            if isempty(rigs)
                uialert(app.UIFigure, 'No active readable Rig can be duplicated.', ...
                    'Duplicate Rig');
                return
            end
            labels = cellstr(rigs.DisplayName + " (" + rigs.RigID + ")");
            [selection, ok] = listdlg('ListString', labels, ...
                'SelectionMode', 'single', 'Name', 'Duplicate Existing Rig', ...
                'PromptString', 'Choose the source Rig:');
            if ~ok
                return
            end
            source = UMITRigStore.open(char(rigs.RigUUID(selection)));
            sourceInfo = source.getRigInfo();
            if ~app.cameraSpectraAreComplete( ...
                    sourceInfo.cameras, 'Cannot Duplicate Rig')
                return
            end
            answer = inputdlg({'New Rig ID:', 'Display name:', 'Description:'}, ...
                'Duplicate Rig Details', [1 64; 1 64; 4 64], ...
                {[sourceInfo.rigID '_copy'], ...
                [sourceInfo.displayName ' Copy'], sourceInfo.description});
            if isempty(answer)
                return
            end
            review = sprintf('Duplicate %s as %s (%s)?', ...
                sourceInfo.rigID, strtrim(answer{2}), strtrim(answer{1}));
            choice = uiconfirm(app.UIFigure, review, 'Review Duplicate Rig', ...
                'Options', {'Duplicate', 'Cancel'}, 'DefaultOption', 1, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Duplicate')
                return
            end
            try
                store = source.duplicate(strtrim(answer{1}), ...
                    'DisplayName', strtrim(answer{2}));
                if ~strcmp(answer{3}, sourceInfo.description)
                    store.updateRigMetadata(struct('description', answer{3}));
                end
                if strcmp(app.AppMode, 'setup')
                    UMITRigStore.setDefaultRig(store.getRigInfo().uuid);
                end
                app.refreshRigs(store.getRigInfo().uuid);
                app.setStatus('Rig duplicated successfully.', 'success');
            catch ME
                app.showException(ME, 'Duplicate Rig Failed');
            end
        end
        function renderCoregistration(app)
            app.CoregistrationRows = struct([]);
            if isempty(app.Store)
                app.CoregistrationMessageLabel.Text = ...
                    'Select a readable Rig to view camera coregistration.';
                app.CoregistrationTable.Data = cell(0, 5);
                app.setCoregistrationButtons(false, '', false);
                return
            end
            info = app.Store.getRigInfo();
            dualCamera = numel(info.cameras) >= 2;
            if ~dualCamera
                app.CoregistrationMessageLabel.Text = [ ...
                    'Camera coregistration is optional and only applies to ' ...
                    'Rigs with Camera 2 configured.'];
            else
                app.CoregistrationMessageLabel.Text = ...
                    'Managed transforms for Camera 1 / Camera 2 alignment.';
            end
            resources = app.Store.listResources('Type', 'cameraCoregistration');
            app.CoregistrationRows = resources;
            data = cell(numel(resources), 5);
            for iResource = 1:numel(resources)
                data(iResource, :) = {app.titleCase(resources(iResource).status), ...
                    resources(iResource).displayName, ...
                    resources(iResource).description, ...
                    app.formatDate(resources(iResource).createdOn), ...
                    app.formatDate(resources(iResource).modifiedOn)};
            end
            app.CoregistrationTable.Data = data;
            writable = dualCamera && ~app.Store.IsReadOnly && ...
                strcmp(info.status, 'active');
            app.ImportCoregistrationButton.Enable = app.onOff(writable);
            app.setCoregistrationButtons(false, '', writable);
        end

        function onCoregistrationSelection(app, event)
            if isempty(event.Indices) || isempty(app.CoregistrationRows)
                app.setCoregistrationButtons(false, '', false);
                return
            end
            row = event.Indices(1);
            app.CoregistrationTable.UserData = row;
            resource = app.CoregistrationRows(row);
            info = app.Store.getRigInfo();
            writable = ~app.Store.IsReadOnly && strcmp(info.status, 'active') && ...
                numel(info.cameras) >= 2;
            app.setCoregistrationButtons(true, resource.status, writable);
        end

        function setCoregistrationButtons(app, selected, status, writable)
            app.ActivateCoregistrationButton.Enable = app.onOff( ...
                selected && writable && strcmp(status, 'available'));
            app.EditCoregistrationButton.Enable = app.onOff(selected && writable);
            app.ArchiveCoregistrationButton.Enable = app.onOff( ...
                selected && writable && ~strcmp(status, 'archived'));
            app.RestoreCoregistrationButton.Enable = app.onOff( ...
                selected && writable && strcmp(status, 'archived'));
            app.PurgeCoregistrationButton.Enable = app.onOff( ...
                selected && writable && strcmp(status, 'archived'));
        end

        function resource = selectedCoregistration(app)
            resource = struct([]);
            row = app.CoregistrationTable.UserData;
            if isnumeric(row) && isscalar(row) && row >= 1 && ...
                    row <= numel(app.CoregistrationRows)
                resource = app.CoregistrationRows(row);
            end
        end

        function onImportCoregistration(app)
            [fileName, folder] = uigetfile('*.mat', ...
                'Select Camera Coregistration Transform');
            if isequal(fileName, 0)
                return
            end
            answer = inputdlg({'Display name:', 'Description:'}, ...
                'Coregistration Details', [1 60; 3 60], {fileName, ''});
            if isempty(answer)
                return
            end
            mode = uiconfirm(app.UIFigure, ...
                'Import this transform and make it active?', ...
                'Import Coregistration', ...
                'Options', {'Import and Activate', 'Import Only', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            if strcmp(mode, 'Cancel')
                return
            end
            info = struct('displayName', answer{1}, 'description', answer{2});
            try
                if strcmp(mode, 'Import and Activate')
                    app.Store.importAndActivateCameraCoregistration( ...
                        fullfile(folder, fileName), info);
                else
                    app.Store.addCameraCoregistration( ...
                        fullfile(folder, fileName), info);
                end
                app.renderCoregistration();
                app.setStatus('Coregistration transform imported.', 'success');
            catch ME
                app.showException(ME, 'Import Coregistration Failed');
            end
        end

        function onActivateCoregistration(app)
            resource = app.selectedCoregistration();
            if isempty(resource)
                return
            end
            try
                app.Store.setActiveCameraCoregistration(resource.uuid);
                app.renderCoregistration();
                app.setStatus('Active coregistration updated.', 'success');
            catch ME
                app.showException(ME, 'Activate Coregistration Failed');
            end
        end

        function onEditCoregistration(app)
            resource = app.selectedCoregistration();
            if isempty(resource)
                return
            end
            answer = inputdlg({'Display name:', 'Description:'}, ...
                'Edit Coregistration Details', [1 60; 3 60], ...
                {resource.displayName, resource.description});
            if isempty(answer)
                return
            end
            try
                app.Store.updateResourceMetadata(resource.uuid, struct( ...
                    'displayName', answer{1}, 'description', answer{2}));
                app.renderCoregistration();
                app.setStatus('Coregistration details updated.', 'success');
            catch ME
                app.showException(ME, 'Edit Coregistration Failed');
            end
        end

        function onArchiveCoregistration(app)
            resource = app.selectedCoregistration();
            if isempty(resource)
                return
            end
            replacementUUID = '';
            if strcmp(resource.status, 'active')
                candidates = app.CoregistrationRows( ...
                    ~strcmp({app.CoregistrationRows.uuid}, resource.uuid) & ...
                    ~strcmp({app.CoregistrationRows.status}, 'archived'));
                if isempty(candidates)
                    uialert(app.UIFigure, [ ...
                        'Activate or import a replacement before archiving ' ...
                        'the active transform.'], 'Replacement Required');
                    return
                end
                labels = cellstr(string({candidates.displayName}) + " (" + ...
                    string({candidates.status}) + ")");
                [selection, ok] = listdlg('ListString', labels, ...
                    'SelectionMode', 'single', ...
                    'PromptString', 'Choose the replacement active transform:');
                if ~ok
                    return
                end
                replacementUUID = candidates(selection).uuid;
            end
            choice = uiconfirm(app.UIFigure, ...
                sprintf('Archive transform "%s"?', resource.displayName), ...
                'Archive Coregistration', ...
                'Options', {'Archive', 'Cancel'}, 'DefaultOption', 2, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Archive')
                return
            end
            try
                app.Store.archiveResource(resource.uuid, ...
                    'ReplacementUUID', replacementUUID);
                app.renderCoregistration();
                app.setStatus('Coregistration archived.', 'success');
            catch ME
                app.showException(ME, 'Archive Coregistration Failed');
            end
        end

        function onRestoreCoregistration(app)
            resource = app.selectedCoregistration();
            if isempty(resource)
                return
            end
            try
                app.Store.restoreResource(resource.uuid);
                app.renderCoregistration();
                app.setStatus('Coregistration restored.', 'success');
            catch ME
                app.showException(ME, 'Restore Coregistration Failed');
            end
        end

        function onPurgeCoregistration(app)
            resource = app.selectedCoregistration();
            if isempty(resource)
                return
            end
            choice = uiconfirm(app.UIFigure, sprintf([ ...
                'Permanently purge archived transform "%s"?\n\nThis deletes ' ...
                'the managed resource file and cannot be undone.'], ...
                resource.displayName), 'Purge Coregistration', ...
                'Options', {'Purge Permanently', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if ~strcmp(choice, 'Purge Permanently')
                return
            end
            try
                app.Store.purgeResource(resource.uuid);
                app.renderCoregistration();
                app.setStatus('Archived coregistration purged.', 'success');
            catch ME
                app.showException(ME, 'Purge Coregistration Failed');
            end
        end

        function showLibraryWorkspace(app)
            if app.IsHardwareEditing && ...
                    ~app.resolvePendingHardware('opening the Optical Library')
                return
            end
            app.RigWorkspaceGrid.Visible = 'off';
            app.LibraryWorkspaceGrid.Visible = 'on';
            app.OpticalLibraryButton.Enable = 'off';
            app.refreshLibrary();
        end

        function showRigWorkspace(app)
            app.LibraryWorkspaceGrid.Visible = 'off';
            app.RigWorkspaceGrid.Visible = 'on';
            app.OpticalLibraryButton.Enable = 'on';
        end

        function refreshLibrary(app)
            app.LibraryCategory = char(app.LibraryCategoryDropDown.Value);
            try
                if strcmp(app.LibraryCategory, 'filterSets')
                    data = UMITRigStore.listFilterSets();
                else
                    data = UMITRigStore.listSpectra(app.LibraryCategory);
                end
                app.LibraryUnfilteredData = data;
                app.configureLibraryActions();
                app.filterLibrary();
                app.setStatus('Optical Library refreshed.', 'success');
            catch ME
                app.showException(ME, 'Optical Library Refresh Failed');
            end
        end

        function filterLibrary(app)
            data = app.LibraryUnfilteredData;
            query = lower(strtrim(string(app.LibrarySearchField.Value)));
            if strlength(query) > 0 && ~isempty(data)
                searchable = strings(height(data), 1);
                for iColumn = 1:width(data)
                    searchable = searchable + " " + lower(string(data{:, iColumn}));
                end
                data = data(contains(searchable, query), :);
            end
            app.LibraryData = data;
            app.LibraryTable.Data = data;
            app.LibraryMetadataArea.Value = {'Select a library entry.'};
            cla(app.LibraryAxes);
            title(app.LibraryAxes, 'No profile selected');
            app.LibraryTable.UserData = [];
            app.LibraryRemoveButton.Enable = 'off';
            app.LibraryEditButton.Enable = 'off';
        end

        function configureLibraryActions(app)
            isFilterSet = strcmp(app.LibraryCategory, 'filterSets');
            if isFilterSet
                app.LibraryImportButton.Text = 'Add New';
                app.LibraryRemoveButton.Text = 'Remove User Filter Set';
                app.LibraryRemoveButton.Visible = 'on';
                app.LibraryEditButton.Visible = 'on';
                app.LibraryEditButton.Layout.Column = 2;
                app.LibraryRemoveButton.Layout.Column = 3;
                app.LibraryEditButton.Tooltip = ...
                    ['User filter sets: the name can be edited; changing a profile ' ...
                    'saves a new filter set. Built-ins are read-only.'];
            else
                app.LibraryImportButton.Text = 'Import...';
                app.LibraryRemoveButton.Text = 'Remove User Spectrum';
                app.LibraryRemoveButton.Visible = 'on';
                app.LibraryEditButton.Visible = 'off';
                app.LibraryRemoveButton.Layout.Column = [2 3];
            end
        end

        function onLibrarySelection(app, event)
            if isempty(event.Indices) || isempty(app.LibraryData)
                return
            end
            row = event.Indices(1);
            app.LibraryTable.UserData = row;
            record = app.LibraryData(row, :);
            app.LibraryMetadataArea.Value = app.tableRowText(record);
            app.LibraryRemoveButton.Enable = 'off';
            app.LibraryEditButton.Enable = 'off';
            try
                if strcmp(app.LibraryCategory, 'filterSets')
                    filterSet = UMITRigStore.getFilterSet(char(record.ID));
                    app.plotFilterSet(filterSet);
                    isUser = strcmp(char(record.Origin), 'user');
                    app.LibraryEditButton.Enable = app.onOff(isUser);
                    app.LibraryRemoveButton.Enable = app.onOff(isUser);
                else
                    spectrum = UMITRigStore.getSpectrum( ...
                        app.LibraryCategory, char(record.ID));
                    app.plotSpectrum(app.LibraryAxes, spectrum);
                    if strcmp(char(record.Origin), 'user')
                        app.LibraryRemoveButton.Enable = 'on';
                    end
                end
            catch ME
                app.showException(ME, 'Optical Library Preview Failed');
            end
        end

        function onImportLibrary(app)
            if strcmp(app.LibraryCategory, 'filterSets')
                definition = app.editFilterSetDialog(struct(), false);
                if isempty(definition)
                    return
                end
                try
                    UMITRigStore.importFilterSet(definition);
                    app.refreshLibrary();
                    app.setStatus('Filter set added.', 'success');
                catch ME
                    app.showException(ME, 'Add Filter Set Failed');
                end
            else
                app.importSpectrum(app.LibraryCategory);
                app.refreshLibrary();
            end
        end

        function onEditFilterSet(app)
            row = app.LibraryTable.UserData;
            if isempty(row) || ~strcmp(app.LibraryCategory, 'filterSets') || ...
                    row > height(app.LibraryData)
                return
            end
            record = app.LibraryData(row, :);
            if ~strcmp(char(record.Origin), 'user')
                uialert(app.UIFigure, ...
                    'Built-in filter sets are read-only.', ...
                    'Edit Filter Set', 'Icon', 'info');
                return
            end
            try
                current = UMITRigStore.getFilterSet(char(record.ID));
                definition = app.editFilterSetDialog(current, true);
                if isempty(definition)
                    return
                end
                contentChanged = ...
                    ~strcmpi(definition.excitationSpectrumID, current.excitationSpectrumID) || ...
                    ~strcmpi(definition.emissionSpectrumID, current.emissionSpectrumID);
                if ~contentChanged
                    UMITRigStore.updateFilterSet(current.id, definition);
                    app.refreshLibrary();
                    app.setStatus('Filter set updated.', 'success');
                    return
                end
                % The profiles of a filter set are fixed under its ID: folders
                % commit a filter set by ID and content. Offer a new filter set.
                answer = uiconfirm(app.UIFigure, sprintf([ ...
                    'Changing the profiles changes the content of filter set "%s". ' ...
                    'Folders that already used it keep the old content, so the ' ...
                    'filter set cannot be changed in place.\n\nSave the edited ' ...
                    'profiles as a new filter set instead? "%s" stays as it is.'], ...
                    current.id, current.id), 'Save as new filter set?', ...
                    'Options', {'Save as new filter set...', 'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');
                if ~strcmp(answer, 'Save as new filter set...')
                    return
                end
                suggestion = definition;
                suggestion.id = app.freeFilterSetID(current.id);
                newDefinition = app.editFilterSetDialog(suggestion, false);
                if isempty(newDefinition)
                    return
                end
                UMITRigStore.importFilterSet(newDefinition);
                app.refreshLibrary();
                app.setStatus('New filter set added; the original is unchanged.', 'success');
            catch ME
                app.showException(ME, 'Edit Filter Set Failed');
            end
        end

        function newID = freeFilterSetID(~, baseID)
            %FREEFILTERSETID First unused filter-set ID of the form <baseID>_2, _3, ...
            existing = string(UMITRigStore.listFilterSets().ID);
            k = 2;
            newID = sprintf('%s_%d', baseID, k);
            while any(strcmpi(existing, newID))
                k = k + 1;
                newID = sprintf('%s_%d', baseID, k);
            end
        end

        function definition = editFilterSetDialog(app, current, isEditing)
            definition = struct([]);
            if nargin < 3
                isEditing = false;
            end
            if isempty(current)
                current = struct();
            end
            currentID = app.getField(current, 'id', '');
            currentName = app.getField(current, 'displayName', '');
            currentExcitation = app.getField( ...
                current, 'excitationSpectrumID', '');
            currentEmission = app.getField( ...
                current, 'emissionSpectrumID', '');

            dlg = uifigure('Name', app.chooseText(isEditing, ...
                'Edit Filter Set', 'Add Filter Set'), ...
                'Position', [100 100 720 360], 'WindowStyle', 'modal');
            grid = uigridlayout(dlg, [6 3]);
            grid.RowHeight = {32, 32, 32, 32, '1x', 40};
            grid.ColumnWidth = {150, '1x', 110};
            grid.Padding = [14 14 14 14];

            app.addLabel(grid, 'Filter Set ID', 1);
            idField = uieditfield(grid, 'text', 'Value', currentID, ...
                'Editable', app.onOff(~isEditing));
            idField.Layout.Row = 1; idField.Layout.Column = [2 3];
            app.addLabel(grid, 'Display Name', 2);
            nameField = uieditfield(grid, 'text', 'Value', currentName);
            nameField.Layout.Row = 2; nameField.Layout.Column = [2 3];
            app.addLabel(grid, 'Excitation Profile', 3);
            excitationField = uieditfield(grid, 'text', ...
                'Value', currentExcitation, 'Editable', 'off');
            excitationField.Layout.Row = 3; excitationField.Layout.Column = 2;
            excitationButton = uibutton(grid, 'Text', 'Select...');
            excitationButton.Layout.Row = 3; excitationButton.Layout.Column = 3;
            app.addLabel(grid, 'Emission Profile', 4);
            emissionField = uieditfield(grid, 'text', ...
                'Value', currentEmission, 'Editable', 'off');
            emissionField.Layout.Row = 4; emissionField.Layout.Column = 2;
            emissionButton = uibutton(grid, 'Text', 'Select...');
            emissionButton.Layout.Row = 4; emissionButton.Layout.Column = 3;
            helpLabel = uilabel(grid, 'Text', [ ...
                'Filter sets reference existing Filter Spectra profiles. ' ...
                'Use the profile picker to preview and select each curve. ' ...
                'Changing a profile of an existing filter set saves a new filter set.'], ...
                'WordWrap', 'on', 'HorizontalAlignment', 'center');
            helpLabel.Layout.Row = 5; helpLabel.Layout.Column = [1 3];

            actions = uigridlayout(grid, [1 3]);
            actions.Layout.Row = 6; actions.Layout.Column = [1 3];
            actions.ColumnWidth = {'1x', 100, 100};
            cancelButton = uibutton(actions, 'Text', 'Cancel');
            cancelButton.Layout.Column = 2;
            saveButton = uibutton(actions, 'Text', ...
                app.chooseText(isEditing, 'Save', 'Add'));
            saveButton.Layout.Column = 3;

            state = struct('confirmed', false);
            dlg.UserData = state;
            excitationButton.ButtonPushedFcn = ...
                @(~, ~) selectFilterSpectrum(excitationField);
            emissionButton.ButtonPushedFcn = ...
                @(~, ~) selectFilterSpectrum(emissionField);
            cancelButton.ButtonPushedFcn = @(~, ~) closeDialog(false);
            saveButton.ButtonPushedFcn = @(~, ~) closeDialog(true);
            dlg.CloseRequestFcn = @(~, ~) closeDialog(false);
            if exist('placeAppInsideCaller', 'file') == 2
                placeAppInsideCaller(app.UIFigure, dlg, 'center');
            end
            uiwait(dlg);
            if isvalid(dlg)
                state = dlg.UserData;
                if state.confirmed
                    definition = struct('id', strtrim(idField.Value), ...
                        'displayName', strtrim(nameField.Value), ...
                        'excitationSpectrumID', excitationField.Value, ...
                        'emissionSpectrumID', emissionField.Value);
                end
                delete(dlg);
            end

            function selectFilterSpectrum(field)
                selected = app.pickSpectrum('filter', field.Value);
                if ~isempty(selected)
                    field.Value = selected;
                end
            end

            function closeDialog(confirmed)
                if ~isvalid(dlg)
                    return
                end
                if confirmed && (isempty(strtrim(idField.Value)) || ...
                        isempty(strtrim(nameField.Value)) || ...
                        isempty(excitationField.Value) || ...
                        isempty(emissionField.Value))
                    uialert(dlg, ['ID, display name, excitation profile, ' ...
                        'and emission profile are required.'], ...
                        'Incomplete Filter Set', 'Icon', 'warning');
                    return
                end
                state = dlg.UserData;
                state.confirmed = confirmed;
                dlg.UserData = state;
                dlg.Visible = 'off';
                uiresume(dlg);
            end
        end

        function spectrum = importSpectrum(app, category)
            spectrum = struct([]);
            [fileName, folder] = uigetfile( ...
                {'*.csv;*.txt;*.dat', 'Spectrum data (*.csv, *.txt, *.dat)'}, ...
                'Import Spectrum');
            if isequal(fileName, 0)
                return
            end
            [~, defaultID] = fileparts(fileName);
            answer = inputdlg({'Profile ID:', 'Display name:', ...
                'Manufacturer:', 'Model:'}, 'Import Spectrum', ...
                [1 60; 1 60; 1 60; 1 60], ...
                {defaultID, defaultID, '', ''});
            if isempty(answer)
                return
            end
            metadata = struct('displayName', answer{2}, ...
                'manufacturer', answer{3}, 'model', answer{4});
            try
                spectrum = UMITRigStore.importSpectrum( ...
                    fullfile(folder, fileName), category, ...
                    strtrim(answer{1}), metadata);
                app.setStatus('Spectrum imported.', 'success');
            catch ME
                app.showException(ME, 'Import Spectrum Failed');
            end
        end

        function onRemoveLibrary(app)
            row = app.LibraryTable.UserData;
            if isempty(row) || row > height(app.LibraryData)
                return
            end
            record = app.LibraryData(row, :);
            if strcmp(app.LibraryCategory, 'filterSets')
                choice = uiconfirm(app.UIFigure, sprintf([ ...
                    'Remove user filter set "%s"? Existing datasets that ' ...
                    'reference this ID may no longer resolve it.'], record.ID), ...
                    'Remove Filter Set', ...
                    'Options', {'Remove', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);
                if ~strcmp(choice, 'Remove')
                    return
                end
                try
                    UMITRigStore.removeFilterSet(char(record.ID));
                    app.refreshLibrary();
                    app.setStatus('User filter set removed.', 'success');
                catch ME
                    app.showException(ME, 'Remove Filter Set Failed');
                end
                return
            end
            choice = uiconfirm(app.UIFigure, sprintf( ...
                'Remove user spectrum "%s"?', record.ID), ...
                'Remove Spectrum', 'Options', {'Remove', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if ~strcmp(choice, 'Remove')
                return
            end
            try
                UMITRigStore.removeSpectrum( ...
                    app.LibraryCategory, char(record.ID));
                app.refreshLibrary();
                app.setStatus('User spectrum removed.', 'success');
            catch ME
                app.showException(ME, 'Remove Spectrum Failed');
            end
        end

        function spectrumID = pickSpectrum(app, category, currentID)
            spectrumID = '';
            dlg = uifigure('Name', sprintf('Select %s Spectrum', ...
                app.titleCase(category)), 'Position', [100 100 820 500], ...
                'WindowStyle', 'modal');
            grid = uigridlayout(dlg, [3 2]);
            grid.RowHeight = {32, '1x', 30};
            grid.ColumnWidth = {'1.25x', '1x'};
            search = uieditfield(grid, 'text', 'Placeholder', 'Search profiles');
            search.Layout.Row = 1;
            search.Layout.Column = 1;
            sharedLabel = uilabel(grid, 'Text', ...
                'Shared Optical Library profile preview', ...
                'HorizontalAlignment', 'center');
            sharedLabel.Layout.Row = 1;
            sharedLabel.Layout.Column = 2;
            tbl = uitable(grid, 'ColumnEditable', false);
            tbl.Layout.Row = 2;
            tbl.Layout.Column = 1;
            ax = uiaxes(grid);
            ax.Layout.Row = 2;
            ax.Layout.Column = 2;
            buttons = uigridlayout(grid, [1 4]);
            buttons.Layout.Row = 3;
            buttons.Layout.Column = [1 2];
            buttons.RowHeight = {30};
            buttons.Padding = [0 0 0 0];
            buttons.ColumnWidth = {'1x', 110, 90, 90};
            importButton = uibutton(buttons, 'Text', 'Import New...');
            importButton.Layout.Column = 2;
            cancelButton = uibutton(buttons, 'Text', 'Cancel');
            cancelButton.Layout.Column = 3;
            useButton = uibutton(buttons, 'Text', 'Use Profile', 'Enable', 'off');
            useButton.Layout.Column = 4;
            state = struct('all', UMITRigStore.listSpectra(category), ...
                'visible', table(), 'selectedID', '', 'confirmed', false);
            dlg.UserData = state;
            refreshPicker();

            search.ValueChangedFcn = @(~, ~) refreshPicker();
            tbl.CellSelectionCallback = @(~, event) selectPickerRow(event);
            importButton.ButtonPushedFcn = @(~, ~) importPickerSpectrum();
            cancelButton.ButtonPushedFcn = @(~, ~) closePicker(false);
            useButton.ButtonPushedFcn = @(~, ~) closePicker(true);
            dlg.CloseRequestFcn = @(~, ~) closePicker(false);
            if ~isempty(currentID)
                state = dlg.UserData;
                idx = find(strcmpi(state.visible.ID, currentID), 1);
                if ~isempty(idx)
                    state.selectedID = char(state.visible.ID(idx));
                    dlg.UserData = state;
                    useButton.Enable = 'on';
                    previewPickerID(state.selectedID);
                end
            end
            if exist('placeAppInsideCaller', 'file') == 2
                placeAppInsideCaller(app.UIFigure, dlg, 'center');
            end
            uiwait(dlg);
            if isvalid(dlg)
                state = dlg.UserData;
                if state.confirmed
                    spectrumID = state.selectedID;
                end
                delete(dlg);
            end

            function refreshPicker()
                state = dlg.UserData;
                visible = state.all;
                query = lower(strtrim(string(search.Value)));
                if strlength(query) > 0
                    text = lower(visible.ID + " " + visible.DisplayName + ...
                        " " + visible.Origin + " " + visible.Manufacturer + ...
                        " " + visible.Model);
                    visible = visible(contains(text, query), :);
                end
                state.visible = visible;
                dlg.UserData = state;
                tbl.Data = visible(:, {'ID', 'DisplayName', 'Origin', ...
                    'Manufacturer', 'Model'});
            end

            function selectPickerRow(event)
                if isempty(event.Indices)
                    return
                end
                state = dlg.UserData;
                row = event.Indices(1);
                if row > height(state.visible)
                    return
                end
                state.selectedID = char(state.visible.ID(row));
                dlg.UserData = state;
                useButton.Enable = 'on';
                previewPickerID(state.selectedID);
            end

            function previewPickerID(id)
                try
                    spectrum = UMITRigStore.getSpectrum(category, id);
                    app.plotSpectrum(ax, spectrum);
                catch ME
                    uialert(dlg, ME.message, 'Preview Failed');
                end
            end

            function importPickerSpectrum()
                imported = app.importSpectrum(category);
                if isempty(imported)
                    return
                end
                state = dlg.UserData;
                state.all = UMITRigStore.listSpectra(category);
                state.selectedID = imported.id;
                dlg.UserData = state;
                refreshPicker();
                useButton.Enable = 'on';
                previewPickerID(imported.id);
            end

            function closePicker(confirmed)
                if ~isvalid(dlg)
                    return
                end
                state = dlg.UserData;
                state.confirmed = confirmed && ~isempty(state.selectedID);
                dlg.UserData = state;
                dlg.Visible = 'off';
                uiresume(dlg);
            end
        end

        function previewSpectrum(app, category, spectrumID)
            if isempty(spectrumID)
                uialert(app.UIFigure, 'No spectral profile is selected.', ...
                    'Spectrum Preview');
                return
            end
            try
                spectrum = UMITRigStore.getSpectrum(category, spectrumID);
                dlg = uifigure('Name', sprintf('Spectrum: %s', spectrumID), ...
                    'Position', [100 100 680 430]);
                ax = uiaxes(uigridlayout(dlg, [1 1]));
                app.plotSpectrum(ax, spectrum);
                if exist('placeAppInsideCaller', 'file') == 2
                    placeAppInsideCaller(app.UIFigure, dlg, 'center');
                end
            catch ME
                app.showException(ME, 'Spectrum Preview Failed');
            end
        end

        function plotSpectrum(~, axesHandle, spectrum)
            plot(axesHandle, spectrum.wavelengthNm, spectrum.response, ...
                'LineWidth', 1.8);
            xlim(axesHandle, [400 700]);
            ylim(axesHandle, [0 1.05]);
            grid(axesHandle, 'on');
            xlabel(axesHandle, 'Wavelength (nm)');
            ylabel(axesHandle, 'Normalized response');
            title(axesHandle, spectrum.id, 'Interpreter', 'none');
        end

        function plotFilterSet(app, filterSet)
            cla(app.LibraryAxes);
            hold(app.LibraryAxes, 'on');
            excitation = struct([]);
            emission = struct([]);
            if ~isempty(filterSet.emissionSpectrumID)
                emission = UMITRigStore.getSpectrum( ...
                    'filter', filterSet.emissionSpectrumID);
            end
            if ~isempty(filterSet.excitationSpectrumID)
                excitation = UMITRigStore.getSpectrum( ...
                    'filter', filterSet.excitationSpectrumID);
            end
            handles = gobjects(0, 1);
            labels = {};
            % Draw emission first so a coincident dashed excitation curve
            % remains visible (for example, the built-in GCaMP set).
            if ~isempty(emission)
                emissionLine = plot(app.LibraryAxes, emission.wavelengthNm, ...
                    emission.response, '-', 'LineWidth', 2.2, ...
                    'Color', [0.85 0.33 0.10]);
            end
            if ~isempty(excitation)
                excitationLine = plot(app.LibraryAxes, ...
                    excitation.wavelengthNm, excitation.response, '--', ...
                    'LineWidth', 2.0, 'Color', [0 0.45 0.74]);
                handles(end+1, 1) = excitationLine;
                labels{end+1} = 'Excitation';
            end
            if ~isempty(emission)
                handles(end+1, 1) = emissionLine;
                labels{end+1} = 'Emission';
            end
            hold(app.LibraryAxes, 'off');
            xlim(app.LibraryAxes, [400 700]);
            ylim(app.LibraryAxes, [0 1.05]);
            grid(app.LibraryAxes, 'on');
            title(app.LibraryAxes, filterSet.displayName, 'Interpreter', 'none');
            if ~isempty(labels)
                legend(app.LibraryAxes, handles, labels, 'Location', 'best');
            else
                text(app.LibraryAxes, 550, 0.5, 'No filter spectra', ...
                    'HorizontalAlignment', 'center');
            end
        end
        function onUseSelectedRig(app)
            if isempty(app.Store)
                return
            end
            info = app.Store.getRigInfo();
            if app.Store.IsReadOnly || strcmp(info.status, 'archived')
                uialert(app.UIFigure, ...
                    'Select an Active or Available valid Rig.', 'Rig Selection');
                return
            end
            try
                if strcmp(info.status, 'available')
                    UMITRigStore.setDefaultRig(info.uuid);
                end
                app.OutputRigUUID = info.uuid;
                app.OutputRigID = info.rigID;
                app.WasSelectionConfirmed = true;
                app.UIFigure.Visible = 'off';
                uiresume(app.UIFigure);
            catch ME
                app.showException(ME, 'Complete Rig Selection Failed');
            end
        end

        function onCloseRequest(app)
            if app.IsClosing
                return
            end
            if app.IsHardwareEditing && ...
                    ~app.resolvePendingHardware('closing Rig Manager')
                return
            end
            app.WasSelectionConfirmed = false;
            if ismember(app.AppMode, {'setup', 'select'})
                app.UIFigure.Visible = 'off';
                uiresume(app.UIFigure);
            else
                app.IsClosing = true;
                delete(app);
            end
        end

        function complete = cameraSpectraAreComplete(app, cameras, titleText)
            complete = false;
            if isempty(cameras)
                uialert(app.UIFigure, ...
                    'Camera 1 and its spectral profile are required.', ...
                    titleText, 'Icon', 'warning');
                return
            end
            missing = find(arrayfun(@(camera) ...
                isempty(strtrim(char(string(camera.spectrumID)))), cameras));
            if ~isempty(missing)
                cameraNames = "Camera " + string([cameras(missing).index]);
                uialert(app.UIFigure, sprintf( ...
                    'Select a spectral profile for %s before continuing.', ...
                    char(strjoin(cameraNames, ', '))), ...
                    titleText, 'Icon', 'warning');
                return
            end
            complete = true;
        end

        function cameras = normalizeCameras(app, cameras)
            if isempty(cameras)
                cameras = app.blankCamera(1);
                return
            end
            [~, order] = sort([cameras.index]);
            cameras = cameras(order);
            if cameras(1).index ~= 1
                cameras = [app.blankCamera(1), cameras];
            end
        end

        function illuminations = normalizeIlluminations(app, records)
            illuminations = app.blankIlluminations();
            for iRecord = 1:numel(records)
                name = UMITRigStore.normalizeIlluminationName(records(iRecord).name);
                idx = find(strcmp({illuminations.name}, name), 1);
                if ~isempty(idx)
                    illuminations(idx) = records(iRecord);
                    illuminations(idx).name = name;
                end
            end
        end

        function camera = blankCamera(~, index)
            camera = struct('index', index, ...
                'displayName', sprintf('Camera %d', index), ...
                'manufacturer', '', 'model', '', 'serialNumber', '', ...
                'spectrumID', '');
        end

        function illuminations = blankIlluminations(~)
            illuminations = struct( ...
                'name', {'red', 'green', 'yellow'}, ...
                'displayName', {'Red', 'Green', 'Yellow'}, ...
                'manufacturer', {'', '', ''}, ...
                'model', {'', '', ''}, ...
                'spectrumID', {'', '', ''});
        end

        function values = formatMetadata(app, metadata)
            if isempty(fieldnames(metadata))
                values = {'No optional metadata.'};
                return
            end
            names = fieldnames(metadata);
            values = cell(numel(names), 1);
            for iName = 1:numel(names)
                values{iName} = sprintf('%s: %s', ...
                    app.titleCase(names{iName}), ...
                    app.scalarText(metadata.(names{iName})));
            end
        end

        function values = formatAdvancedDetails(app, info)
            values = {sprintf('UUID: %s', info.uuid), ...
                sprintf('Rig folder: %s', app.Store.RigRoot), ...
                sprintf('Validation mode: %s', ...
                app.getField(app.CurrentValidationReport, 'mode', 'not run')), ...
                sprintf('Validation: %s', app.chooseText( ...
                app.getField(app.CurrentValidationReport, 'isValid', false), ...
                'Valid', 'Invalid'))};
            if isfield(app.CurrentValidationReport, 'errors') && ...
                    ~isempty(app.CurrentValidationReport.errors)
                values{end+1} = app.issueSummary( ...
                    app.CurrentValidationReport.errors, 8);
            end
        end

        function values = tableRowText(app, row)
            names = row.Properties.VariableNames;
            values = cell(numel(names), 1);
            for iName = 1:numel(names)
                values{iName} = sprintf('%s: %s', app.titleCase(names{iName}), ...
                    app.scalarText(row{1, iName}));
            end
        end

        function text = issueSummary(~, issues, maxIssues)
            if isempty(issues)
                text = 'No issues.';
                return
            end
            count = min(numel(issues), maxIssues);
            messages = cell(count, 1);
            for iIssue = 1:count
                messages{iIssue} = sprintf('%s: %s', ...
                    issues(iIssue).code, issues(iIssue).message);
            end
            text = strjoin(messages, newline);
            if numel(issues) > count
                text = sprintf('%s\n... and %d more.', text, numel(issues) - count);
            end
        end

        function value = getField(~, source, name, defaultValue)
            if isstruct(source) && isfield(source, name)
                value = source.(name);
            else
                value = defaultValue;
            end
        end

        function text = scalarText(~, value)
            if ischar(value)
                text = value;
            elseif isstring(value) && isscalar(value)
                text = char(value);
            elseif isnumeric(value) || islogical(value)
                text = strtrim(evalc('disp(value)'));
            else
                text = strtrim(evalc('disp(value)'));
            end
        end

        function value = readTextArea(~, control)
            value = strjoin(cellstr(string(control.Value)), newline);
        end

        function value = textAreaValue(~, input)
            if isempty(input)
                value = {''};
            else
                value = cellstr(splitlines(string(input)));
            end
        end

        function text = formatDate(~, value)
            if isa(value, 'datetime') && ~isnat(value)
                text = char(value, 'yyyy-MM-dd HH:mm:ss');
            else
                text = '';
            end
        end

        function text = nonemptyText(~, value, fallback)
            if strlength(strtrim(string(value))) == 0
                text = char(string(fallback));
            else
                text = char(string(value));
            end
        end

        function text = chooseText(~, condition, trueText, falseText)
            if condition
                text = trueText;
            else
                text = falseText;
            end
        end

        function text = yesNo(app, value)
            text = app.chooseText(value, 'Yes', 'No');
        end

        function text = titleCase(~, value)
            value = char(string(value));
            if isempty(value)
                text = '';
            else
                text = [upper(value(1)), value(2:end)];
            end
        end

        function value = onOff(~, condition)
            if condition
                value = 'on';
            else
                value = 'off';
            end
        end

        function setStatus(app, message, severity)
            if exist('setStatusMessage', 'file') == 2
                setStatusMessage(app.StatusLabel, message, ...
                    'Severity', severity, 'MaxLength', 220);
            else
                app.StatusLabel.Text = char(string(message));
            end
        end

        function showException(app, exception, titleText)
            app.setStatus(exception, 'error');
            message = exception.message;
            if ~isempty(exception.identifier)
                message = sprintf('%s\n\n%s', message, exception.identifier);
            end
            uialert(app.UIFigure, message, titleText, 'Icon', 'error');
        end

        function createOverviewTab(app)
            grid = uigridlayout(app.OverviewTab, [9 2]);
            grid.Scrollable = 'on';
            grid.ColumnWidth = {170, '1x'};
            grid.RowHeight = {28, 28, 80, 28, 28, 28, 28, 80, 110};
            grid.Padding = [12 12 12 12];
            app.addLabel(grid, 'Rig ID', 1);
            app.RigIDField = uieditfield(grid, 'text', 'Editable', 'off');
            app.RigIDField.Layout.Row = 1; app.RigIDField.Layout.Column = 2;
            app.addLabel(grid, 'Display Name', 2);
            app.DisplayNameField = uieditfield(grid, 'text');
            app.DisplayNameField.Layout.Row = 2; app.DisplayNameField.Layout.Column = 2;
            app.addLabel(grid, 'Description', 3);
            app.DescriptionArea = uitextarea(grid);
            app.DescriptionArea.Layout.Row = 3; app.DescriptionArea.Layout.Column = 2;
            app.addLabel(grid, 'Default Rig', 4);
            app.DefaultStatusField = uieditfield(grid, 'text', 'Editable', 'off');
            app.DefaultStatusField.Layout.Row = 4; app.DefaultStatusField.Layout.Column = 2;
            app.addLabel(grid, 'State', 5);
            app.RigStateField = uieditfield(grid, 'text', 'Editable', 'off');
            app.RigStateField.Layout.Row = 5; app.RigStateField.Layout.Column = 2;
            app.addLabel(grid, 'Created', 6);
            app.CreatedField = uieditfield(grid, 'text', 'Editable', 'off');
            app.CreatedField.Layout.Row = 6; app.CreatedField.Layout.Column = 2;
            app.addLabel(grid, 'Modified', 7);
            app.ModifiedField = uieditfield(grid, 'text', 'Editable', 'off');
            app.ModifiedField.Layout.Row = 7; app.ModifiedField.Layout.Column = 2;
            app.addLabel(grid, 'Optional Metadata', 8);
            app.MetadataArea = uitextarea(grid, 'Editable', 'off');
            app.MetadataArea.Layout.Row = 8; app.MetadataArea.Layout.Column = 2;
            app.addLabel(grid, 'Advanced Details', 9);
            app.AdvancedArea = uitextarea(grid, 'Editable', 'off');
            app.AdvancedArea.Layout.Row = 9; app.AdvancedArea.Layout.Column = 2;
        end

        function createHardwareTab(app)
            outer = uigridlayout(app.HardwareTab, [3 1]);
            outer.Scrollable = 'on';
            outer.RowHeight = {315, 245, 50};
            outer.Padding = [8 8 8 8];
            camerasPanel = uipanel(outer, 'Title', 'Cameras');
            camerasPanel.Layout.Row = 1;
            app.CameraLayoutGrid = uigridlayout(camerasPanel, [1 3]);
            app.CameraLayoutGrid.ColumnWidth = {'1x', 0, 180};
            app.Camera1Panel = uipanel(app.CameraLayoutGrid, 'Title', 'Camera 1');
            app.createCameraEditor(app.Camera1Panel, 1);
            app.Camera2Panel = uipanel(app.CameraLayoutGrid, 'Title', 'Camera 2');
            app.createCameraEditor(app.Camera2Panel, 2);
            app.OptionalCamera2Panel = uipanel(app.CameraLayoutGrid, ...
                'Title', 'Optional Camera 2');
            optionalGrid = uigridlayout(app.OptionalCamera2Panel, [3 1]);
            optionalGrid.RowHeight = {'1x', 32, '1x'};
            app.AddCamera2Button = uibutton(optionalGrid, ...
                'Text', 'Add second camera...');
            app.AddCamera2Button.Layout.Row = 2;
            app.AddCamera2Button.ButtonPushedFcn = @(~, ~) app.onAddCamera2();

            illuminationPanel = uipanel(outer, 'Title', 'Illuminations');
            illuminationPanel.Layout.Row = 2;
            illumGrid = uigridlayout(illuminationPanel, [2 5]);
            illumGrid.RowHeight = {'1x', 32};
            illumGrid.ColumnWidth = {'1x', 145, 120, 130, 120};
            app.IlluminationTable = uitable(illumGrid, ...
                'ColumnName', {'Illumination', 'Display Name', ...
                'Manufacturer', 'Model', 'Spectral Profile'}, ...
                'ColumnEditable', false(1, 5), 'RowName', {});
            app.IlluminationTable.Layout.Row = 1;
            app.IlluminationTable.Layout.Column = [1 5];
            app.IlluminationTable.CellSelectionCallback = ...
                @(~, event) app.onIlluminationSelection(event);
            app.SelectedIlluminationLabel = uilabel(illumGrid, ...
                'Text', 'No illumination selected');
            app.SelectedIlluminationLabel.Layout.Row = 2;
            app.SelectedIlluminationLabel.Layout.Column = 1;
            app.SelectIlluminationSpectrumButton = uibutton(illumGrid, ...
                'Text', 'Add / Replace...');
            app.SelectIlluminationSpectrumButton.Layout.Row = 2;
            app.SelectIlluminationSpectrumButton.Layout.Column = 2;
            app.SelectIlluminationSpectrumButton.ButtonPushedFcn = ...
                @(~, ~) app.onSelectIlluminationSpectrum();
            app.RemoveIlluminationSpectrumButton = uibutton(illumGrid, ...
                'Text', 'Remove Spectrum');
            app.RemoveIlluminationSpectrumButton.Layout.Row = 2;
            app.RemoveIlluminationSpectrumButton.Layout.Column = 3;
            app.RemoveIlluminationSpectrumButton.ButtonPushedFcn = ...
                @(~, ~) app.onRemoveIlluminationSpectrum();
            app.PreviewIlluminationSpectrumButton = uibutton(illumGrid, ...
                'Text', 'Preview Spectrum');
            app.PreviewIlluminationSpectrumButton.Layout.Row = 2;
            app.PreviewIlluminationSpectrumButton.Layout.Column = 4;
            app.PreviewIlluminationSpectrumButton.ButtonPushedFcn = ...
                @(~, ~) app.onPreviewIlluminationSpectrum();

            actions = uigridlayout(outer, [1 4]);
            actions.Layout.Row = 3;
            actions.RowHeight = {38};
            actions.Padding = [0 4 0 4];
            actions.ColumnWidth = {'1x', 100, 100, 105};
            app.RevertHardwareButton = uibutton(actions, 'Text', 'Cancel');
            app.RevertHardwareButton.Layout.Column = 2;
            app.RevertHardwareButton.ButtonPushedFcn = @(~, ~) app.onRevertHardware();
            app.ApplyHardwareButton = uibutton(actions, 'Text', 'Apply');
            app.ApplyHardwareButton.Layout.Column = 3;
            app.ApplyHardwareButton.ButtonPushedFcn = @(~, ~) app.onApplyHardware();
            app.EditHardwareButton = uibutton(actions, 'Text', 'Edit Configuration');
            app.EditHardwareButton.Layout.Column = 4;
            app.EditHardwareButton.ButtonPushedFcn = @(~, ~) app.onEditHardware();
        end

        function createCameraEditor(app, parent, index)
            grid = uigridlayout(parent, [6 3]);
            grid.ColumnWidth = {105, '1x', 95};
            grid.RowHeight = {28, 28, 28, 28, 28, 32};
            names = {'Display Name', 'Manufacturer', 'Model', ...
                'Serial Number', 'Spectral Profile *'};
            controls = cell(1, 5);
            for iRow = 1:5
                label = uilabel(grid, 'Text', names{iRow}, ...
                    'HorizontalAlignment', 'right');
                label.Layout.Row = iRow; label.Layout.Column = 1;
                controls{iRow} = uieditfield(grid, 'text');
                controls{iRow}.Layout.Row = iRow;
                controls{iRow}.Layout.Column = 2;
            end
            controls{5}.Editable = 'off';
            selectButton = uibutton(grid, 'Text', 'Select...');
            selectButton.Layout.Row = 5; selectButton.Layout.Column = 3;
            previewButton = uibutton(grid, 'Text', 'Preview');
            previewButton.Layout.Row = 6; previewButton.Layout.Column = 3;
            if index == 1
                app.Camera1DisplayField = controls{1};
                app.Camera1ManufacturerField = controls{2};
                app.Camera1ModelField = controls{3};
                app.Camera1SerialField = controls{4};
                app.Camera1SpectrumField = controls{5};
                app.SelectCamera1SpectrumButton = selectButton;
                app.PreviewCamera1SpectrumButton = previewButton;
                selectButton.ButtonPushedFcn = @(~, ~) app.onSelectCameraSpectrum(1);
                previewButton.ButtonPushedFcn = @(~, ~) app.onPreviewCameraSpectrum(1);
            else
                app.Camera2DisplayField = controls{1};
                app.Camera2ManufacturerField = controls{2};
                app.Camera2ModelField = controls{3};
                app.Camera2SerialField = controls{4};
                app.Camera2SpectrumField = controls{5};
                app.SelectCamera2SpectrumButton = selectButton;
                app.PreviewCamera2SpectrumButton = previewButton;
                selectButton.ButtonPushedFcn = @(~, ~) app.onSelectCameraSpectrum(2);
                previewButton.ButtonPushedFcn = @(~, ~) app.onPreviewCameraSpectrum(2);
                app.RemoveCamera2Button = uibutton(grid, 'Text', 'Remove Camera 2');
                app.RemoveCamera2Button.Layout.Row = 6;
                app.RemoveCamera2Button.Layout.Column = [1 2];
                app.RemoveCamera2Button.ButtonPushedFcn = @(~, ~) app.onRemoveCamera2();
            end
        end

        function createCoregistrationTab(app)
            grid = uigridlayout(app.CoregistrationTab, [3 1]);
            grid.Scrollable = 'on';
            grid.RowHeight = {42, 260, 50};
            grid.Padding = [10 10 10 10];
            app.CoregistrationMessageLabel = uilabel(grid, ...
                'Text', 'Camera coregistration is a dual-camera feature.', ...
                'WordWrap', 'on');
            app.CoregistrationTable = uitable(grid, ...
                'ColumnName', {'Status', 'Display Name', 'Description', ...
                'Created', 'Modified'}, 'ColumnEditable', false(1, 5), ...
                'RowName', {});
            app.CoregistrationTable.Layout.Row = 2;
            app.CoregistrationTable.CellSelectionCallback = ...
                @(~, event) app.onCoregistrationSelection(event);
            actions = uigridlayout(grid, [1 7]);
            actions.Layout.Row = 3;
            actions.RowHeight = {40};
            actions.Padding = [0 4 0 4];
            actions.ColumnWidth = {'1x', 90, 90, 90, 90, 90, 90};
            app.ImportCoregistrationButton = uibutton(actions, 'Text', 'Import...');
            app.ImportCoregistrationButton.Layout.Column = 2;
            app.ImportCoregistrationButton.ButtonPushedFcn = ...
                @(~, ~) app.onImportCoregistration();
            app.ActivateCoregistrationButton = uibutton(actions, 'Text', 'Activate');
            app.ActivateCoregistrationButton.Layout.Column = 3;
            app.ActivateCoregistrationButton.ButtonPushedFcn = ...
                @(~, ~) app.onActivateCoregistration();
            app.EditCoregistrationButton = uibutton(actions, 'Text', 'Edit Details');
            app.EditCoregistrationButton.Layout.Column = 4;
            app.EditCoregistrationButton.ButtonPushedFcn = ...
                @(~, ~) app.onEditCoregistration();
            app.ArchiveCoregistrationButton = uibutton(actions, 'Text', 'Archive');
            app.ArchiveCoregistrationButton.Layout.Column = 5;
            app.ArchiveCoregistrationButton.ButtonPushedFcn = ...
                @(~, ~) app.onArchiveCoregistration();
            app.RestoreCoregistrationButton = uibutton(actions, 'Text', 'Restore');
            app.RestoreCoregistrationButton.Layout.Column = 6;
            app.RestoreCoregistrationButton.ButtonPushedFcn = ...
                @(~, ~) app.onRestoreCoregistration();
            app.PurgeCoregistrationButton = uibutton(actions, 'Text', 'Purge');
            app.PurgeCoregistrationButton.Layout.Column = 7;
            app.PurgeCoregistrationButton.ButtonPushedFcn = ...
                @(~, ~) app.onPurgeCoregistration();
        end

        function createRigWorkspace(app)
            app.RigWorkspaceGrid = uigridlayout(app.RootGrid, [2 1]);
            app.RigWorkspaceGrid.Layout.Row = 2;
            app.RigWorkspaceGrid.RowHeight = {175, '1x'};
            app.RigWorkspaceGrid.ColumnWidth = {'1x'};
            app.RigWorkspaceGrid.Padding = [8 8 8 8];
            app.RigListPanel = uipanel(app.RigWorkspaceGrid, 'Title', 'Rigs');
            app.RigListPanel.Layout.Row = 1;
            left = uigridlayout(app.RigListPanel, [2 1]);
            left.RowHeight = {'1x', 28};
            app.RigListTable = uitable(left, ...
                'ColumnName', {'Default', 'Display Name', 'Rig ID', 'State'}, ...
                'ColumnEditable', false(1, 4), 'RowName', {}, ...
                'ColumnWidth', {80, 'auto', 'auto', 130});
            app.RigListTable.CellSelectionCallback = ...
                @(~, event) app.onRigSelectionChanged(event);
            app.ShowArchivedCheckBox = uicheckbox(left, ...
                'Text', 'Show archived Rigs', 'Value', true);
            app.ShowArchivedCheckBox.ValueChangedFcn = ...
                @(~, ~) app.refreshRigs(app.SelectedRigUUID);

            app.SelectedRigPanel = uipanel(app.RigWorkspaceGrid, ...
                'Title', 'Selected Rig');
            app.SelectedRigPanel.Layout.Row = 2;
            right = uigridlayout(app.SelectedRigPanel, [3 1]);
            right.RowHeight = {36, '1x', 46};
            app.SelectedRigHeading = uilabel(right, ...
                'Text', 'No Rig selected', 'FontSize', 16, ...
                'FontWeight', 'bold');
            app.RigTabGroup = uitabgroup(right);
            app.RigTabGroup.Layout.Row = 2;
            app.OverviewTab = uitab(app.RigTabGroup, 'Title', 'Overview');
            app.HardwareTab = uitab(app.RigTabGroup, 'Title', 'Hardware');
            app.CoregistrationTab = uitab(app.RigTabGroup, 'Title', 'Coregistration');
            app.createOverviewTab();
            app.createHardwareTab();
            app.createCoregistrationTab();

            actions = uigridlayout(right, [1 7]);
            actions.Layout.Row = 3;
            actions.RowHeight = {36};
            actions.ColumnWidth = {'1x', 110, 110, 125, 110, 110, 130};
            app.SaveOverviewButton = uibutton(actions, 'Text', 'Save Details');
            app.SaveOverviewButton.Layout.Column = 2;
            app.SaveOverviewButton.ButtonPushedFcn = @(~, ~) app.onSaveOverview();
            app.RevertOverviewButton = uibutton(actions, 'Text', 'Revert Details');
            app.RevertOverviewButton.Layout.Column = 3;
            app.RevertOverviewButton.ButtonPushedFcn = @(~, ~) app.renderSelectedRig();
            app.RenameRigButton = uibutton(actions, 'Text', 'Rename Rig ID...');
            app.RenameRigButton.Layout.Column = 4;
            app.RenameRigButton.ButtonPushedFcn = @(~, ~) app.onRenameRig();
            app.SetDefaultButton = uibutton(actions, 'Text', 'Set Active');
            app.SetDefaultButton.Layout.Column = 5;
            app.SetDefaultButton.ButtonPushedFcn = @(~, ~) app.onSetDefault();
            app.ArchiveRigButton = uibutton(actions, 'Text', 'Archive Rig');
            app.ArchiveRigButton.Layout.Column = 6;
            app.ArchiveRigButton.ButtonPushedFcn = @(~, ~) app.onArchiveRig();
            app.RestoreRigButton = uibutton(actions, 'Text', 'Restore Rig');
            app.RestoreRigButton.Layout.Column = 6;
            app.RestoreRigButton.ButtonPushedFcn = @(~, ~) app.onRestoreRig();
            app.UseSelectedRigButton = uibutton(actions, 'Text', 'Use Selected Rig');
            app.UseSelectedRigButton.Layout.Column = 7;
            app.UseSelectedRigButton.ButtonPushedFcn = @(~, ~) app.onUseSelectedRig();
        end

        function createLibraryWorkspace(app)
            app.LibraryWorkspaceGrid = uigridlayout(app.RootGrid, [2 2]);
            app.LibraryWorkspaceGrid.Layout.Row = 2;
            app.LibraryWorkspaceGrid.RowHeight = {50, '1x'};
            app.LibraryWorkspaceGrid.ColumnWidth = {430, '1x'};
            app.LibraryWorkspaceGrid.Padding = [8 8 8 8];
            header = uigridlayout(app.LibraryWorkspaceGrid, [1 3]);
            header.Layout.Row = 1; header.Layout.Column = [1 2];
            header.RowHeight = {40};
            header.ColumnWidth = {120, '1x', 300};
            app.BackToRigsButton = uibutton(header, 'Text', 'Back to Rigs');
            app.BackToRigsButton.ButtonPushedFcn = @(~, ~) app.showRigWorkspace();
            uilabel(header, 'Text', ...
                'Optical Library — shared across all Rigs; not owned by the selected Rig', ...
                'FontWeight', 'bold', 'HorizontalAlignment', 'center');

            leftPanel = uipanel(app.LibraryWorkspaceGrid, 'Title', 'Library Objects');
            leftPanel.Layout.Row = 2; leftPanel.Layout.Column = 1;
            left = uigridlayout(leftPanel, [4 3]);
            left.RowHeight = {30, 30, '1x', 34};
            left.ColumnWidth = {'1x', '1x', '1x'};
            app.LibraryCategoryDropDown = uidropdown(left, ...
                'Items', {'Illumination Spectra', 'Camera Spectra', ...
                'Filter Spectra', 'Filter Sets'}, ...
                'ItemsData', {'illumination', 'camera', 'filter', 'filterSets'}, ...
                'Value', 'illumination');
            app.LibraryCategoryDropDown.Layout.Row = 1;
            app.LibraryCategoryDropDown.Layout.Column = [1 3];
            app.LibraryCategoryDropDown.ValueChangedFcn = @(~, ~) app.refreshLibrary();
            app.LibrarySearchField = uieditfield(left, 'text', ...
                'Placeholder', 'Search ID, name, origin, manufacturer, model');
            app.LibrarySearchField.Layout.Row = 2;
            app.LibrarySearchField.Layout.Column = [1 3];
            app.LibrarySearchField.ValueChangedFcn = @(~, ~) app.filterLibrary();
            app.LibraryTable = uitable(left, 'ColumnEditable', false, 'RowName', {});
            app.LibraryTable.Layout.Row = 3;
            app.LibraryTable.Layout.Column = [1 3];
            app.LibraryTable.CellSelectionCallback = ...
                @(~, event) app.onLibrarySelection(event);
            app.LibraryImportButton = uibutton(left, 'Text', 'Import...');
            app.LibraryImportButton.Layout.Row = 4;
            app.LibraryImportButton.ButtonPushedFcn = @(~, ~) app.onImportLibrary();
            app.LibraryEditButton = uibutton(left, 'Text', 'Edit Selected', ...
                'Visible', 'off', 'Enable', 'off');
            app.LibraryEditButton.Layout.Row = 4;
            app.LibraryEditButton.Layout.Column = [2 3];
            app.LibraryEditButton.ButtonPushedFcn = ...
                @(~, ~) app.onEditFilterSet();
            app.LibraryRemoveButton = uibutton(left, 'Text', 'Remove User Spectrum');
            app.LibraryRemoveButton.Layout.Row = 4;
            app.LibraryRemoveButton.Layout.Column = [2 3];
            app.LibraryRemoveButton.ButtonPushedFcn = @(~, ~) app.onRemoveLibrary();

            rightPanel = uipanel(app.LibraryWorkspaceGrid, 'Title', 'Selected Library Object');
            rightPanel.Layout.Row = 2; rightPanel.Layout.Column = 2;
            right = uigridlayout(rightPanel, [2 1]);
            right.RowHeight = {150, '1x'};
            app.LibraryMetadataArea = uitextarea(right, 'Editable', 'off');
            app.LibraryAxes = uiaxes(right);
            app.LibraryAxes.Layout.Row = 2;
        end

        function addLabel(~, parent, textValue, row)
            label = uilabel(parent, 'Text', textValue, ...
                'HorizontalAlignment', 'right');
            label.Layout.Row = row;
            label.Layout.Column = 1;
        end
        function buildRuntimeComponents(app)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Position = [100 100 1280 780];
            app.UIFigure.Name = 'UMIT Rig Manager';
            app.UIFigure.CloseRequestFcn = @(~, ~) app.onCloseRequest();
            app.RootGrid = uigridlayout(app.UIFigure, [3 1]);
            app.RootGrid.RowHeight = {48, '1x', 26};
            app.RootGrid.Padding = [0 0 0 0];

            app.ToolbarGrid = uigridlayout(app.RootGrid, [1 8]);
            app.ToolbarGrid.Layout.Row = 1;
            app.ToolbarGrid.ColumnWidth = {90, 105, 78, 115, '1x', 85, 90, 270};
            app.ToolbarGrid.Padding = [8 8 8 6];
            app.NewRigButton = uibutton(app.ToolbarGrid, 'Text', 'New Rig');
            app.NewRigButton.ButtonPushedFcn = @(~, ~) app.onNewRig();
            app.DuplicateRigButton = uibutton(app.ToolbarGrid, 'Text', 'Duplicate Rig');
            app.DuplicateRigButton.ButtonPushedFcn = @(~, ~) app.onDuplicateRig();
            app.RefreshButton = uibutton(app.ToolbarGrid, 'Text', 'Refresh');
            app.RefreshButton.ButtonPushedFcn = ...
                @(~, ~) app.refreshRigs(app.SelectedRigUUID);
            app.OpticalLibraryButton = uibutton(app.ToolbarGrid, ...
                'Text', 'Optical Library');
            app.OpticalLibraryButton.ButtonPushedFcn = @(~, ~) app.showLibraryWorkspace();
            app.ValidateButton = uibutton(app.ToolbarGrid, 'Text', 'Validate');
            app.ValidateButton.Layout.Column = 6;
            app.ValidateButton.ButtonPushedFcn = @(~, ~) app.onValidateRig();
            app.ValidationModeDropDown = uidropdown(app.ToolbarGrid);
            app.ValidationModeDropDown.Layout.Column = 7;
            app.RigStatusLabel = uilabel(app.ToolbarGrid, ...
                'Text', 'No Rig selected', 'HorizontalAlignment', 'right');
            app.RigStatusLabel.Layout.Column = 8;

            app.createRigWorkspace();
            app.createLibraryWorkspace();
            app.StatusLabel = uilabel(app.RootGrid, 'Text', 'Ready.');
            app.StatusLabel.Layout.Row = 3;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, appMode, varargin)
            app.buildRuntimeComponents();
            if nargin < 2
                parentApp = [];
            end
            if nargin < 3 || isempty(appMode)
                appMode = 'manage';
            end

            if ~isempty(parentApp) && ~app.isCallerApp(parentApp)
                varargin = [{appMode}, varargin];
                appMode = parentApp;
                parentApp = [];
            end
            appMode = lower(char(string(appMode)));
            if ~ismember(appMode, {'manage', 'setup', 'select'})
                error('Umitoolbox:RigManagerTool:invalidMode', ...
                    'Mode must be "manage", "setup", or "select".');
            end

            p = inputParser;
            p.FunctionName = 'RigManagerTool.startupFcn';
            addParameter(p, 'InitialRig', []);
            addParameter(p, 'StartWorkspace', 'rigs', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'Visible', true, ...
                @(x) islogical(x) && isscalar(x));
            parse(p, varargin{:});

            app.ParentApp = parentApp;
            app.AppMode = appMode;
            app.configureInitialState();
            if ~isempty(parentApp) && exist('placeAppInsideCaller', 'file') == 2
                placeAppInsideCaller(parentApp, app.UIFigure, 'center');
            end

            startWorkspace = lower(char(string(p.Results.StartWorkspace)));
            if ~ismember(startWorkspace, {'rigs', 'opticallibrary'})
                error('Umitoolbox:RigManagerTool:invalidWorkspace', ...
                    'StartWorkspace must be "rigs" or "opticalLibrary".');
            end

            initialSelector = p.Results.InitialRig;
            if isa(initialSelector, 'UMITRigStore')
                initialSelector = initialSelector.getRigInfo().uuid;
            end
            app.refreshRigs(initialSelector);
            if strcmp(startWorkspace, 'opticallibrary')
                app.showLibraryWorkspace();
            end

            app.UIFigure.Visible = app.onOff(p.Results.Visible);
            drawnow;
            if strcmp(appMode, 'setup') && isempty(app.RigTable)
                app.onNewRig();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RigManagerTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end