classdef SearchBuilder_dlgBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AdvancedSearchBuilderLabel  matlab.ui.control.Label
        SearchButton                matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        SearchmethodButtonGroup     matlab.ui.container.ButtonGroup
        ContainstermButton          matlab.ui.control.RadioButton
        WholewordButton             matlab.ui.control.RadioButton
        UseregexpButton             matlab.ui.control.RadioButton
        SubjectPanel                matlab.ui.container.Panel
        EditField                   matlab.ui.control.EditField
        DropDown_1                  matlab.ui.control.DropDown
        EditField_4                 matlab.ui.control.EditField
        DropDown_4                  matlab.ui.control.DropDown
        QueryLabel                  matlab.ui.control.Label
        ModalityPanel               matlab.ui.container.Panel
        EditField_6                 matlab.ui.control.EditField
        DropDown_6                  matlab.ui.control.DropDown
        DropDown_3                  matlab.ui.control.DropDown
        EditField_3                 matlab.ui.control.EditField
        QueryLabel_3                matlab.ui.control.Label
        AcquisitionPanel            matlab.ui.container.Panel
        EditField_5                 matlab.ui.control.EditField
        DropDown_5                  matlab.ui.control.DropDown
        DropDown_2                  matlab.ui.control.DropDown
        EditField_2                 matlab.ui.control.EditField
        QueryLabel_2                matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Main App.
        FilterStruct % Filter structure from/to CALLINGAPP.
        QueryStr_Subj % Subject query string
    end
    
    methods (Access = private)
        function str = add2Query(app,field, queryTerm, Operator, str)
            if strcmp(field, 'Field...')
                uialert(app.UIFigure, 'You have to select a field first!', 'warning!', 'Modal',true);
                return
            end
            if isempty(queryTerm)
                uialert(app.UIFigure, 'You have to type a query term first!', 'warning!', 'Modal',true);
                return
            end
            if isempty(str) && strcmp(Operator, 'ADD')
                Operator = '';
            elseif ~isempty(str) && strcmp(Operator, 'ADD')
                return
            end
            txt = [' ' Operator '(' queryTerm '[' field '])'];
            str = [str txt];
        end
        
        function queryStruct= parseQueryStr(app, query)
            queryStruct = struct('PropName', '', 'Expression', '', 'LogicalOperator', '');
            exp = '\((\S+)\)';
            idx_str = regexp(query, exp);
            for i = 1:numel(idx_str)
                str = query(idx_str(i):end);
                propID = regexp(str, '[(\S+)\]', 'tokens', 'once'); propID = propID{:};
                Exp = regexp(str, '\((\S+)\[', 'tokens', 'once'); Exp = Exp{:};
                LogicOp = regexp(str, '(AND|OR|NOT)', 'match', 'once');
                queryStruct(i).PropName = propID;
                queryStruct(i).Expression = Exp;
                queryStruct(i).LogicalOperator = LogicOp;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, FilterStruct)
            app.CallingApp = mainapp;
            app.FilterStruct = FilterStruct;
            app.EditField.Tooltip = 'Enter search term (leave empty to select all)';
            app.EditField_2.Tooltip = 'Enter search term (leave empty to select all)';
            app.EditField_3.Tooltip = 'Enter search term (leave empty to select all)';
        end

        % Drop down opening function: DropDown_1
        function DropDown_1Opening(app, event)
            propList = [properties('Subject'); 'Field...'];
            propList = propList(~contains(propList, {'date', 'time', 'Array', 'LastLog', 'SaveFolder'}));
            app.DropDown_1.Items = propList;
        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            propList = [properties('Acquisition'); 'Field...'];
            propList = propList(~contains(propList, {'date', 'time', 'Array', 'LastLog', 'SaveFolder'}));
            app.DropDown_2.Items = propList;
        end

        % Drop down opening function: DropDown_3
        function DropDown_3Opening(app, event)
            propList = [properties('Modality'); 'Field...'];
            propList = propList(~contains(propList, {'date', 'time', 'Array', 'LastLog', 'SaveFolder', 'RawFolder', 'RawFiles'}));
            app.DropDown_3.Items = propList;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % Parse Subject query string
            queryS = strtrim(app.EditField_4.Value);
            app.FilterStruct.Subject = app.parseQueryStr(queryS);
            % Parse Aquisition query string
            queryA = strtrim(app.EditField_5.Value);
            app.FilterStruct.Acquisition = app.parseQueryStr(queryA);
            % Parse Modality query string
            queryM = strtrim(app.EditField_6.Value);
            app.FilterStruct.Modality = app.parseQueryStr(queryM);
            % Select Filter Method
            choice = app.SearchmethodButtonGroup.SelectedObject;
            switch choice.Text
                case 'Contains term'
                    app.FilterStruct.FilterMethod = 'contains';
                case 'Whole word'
                    app.FilterStruct.FilterMethod = 'strcmp';
                otherwise
                    app.FilterStruct.FilterMethod = 'regexp';
            end
            % Update FilterStruct and save to Main App)
            updateFilterStruct(app.CallingApp, app.FilterStruct);
            % Close Dialog Box
            app.CallingApp.AdvancedSearchButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AdvancedSearchButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.DropDown_1.Items = {'Field...'}; app.DropDown_1.Value = 'Field...';
            app.DropDown_2.Items = {'Field...'}; app.DropDown_2.Value = 'Field...';
            app.DropDown_3.Items = {'Field...'}; app.DropDown_3.Value = 'Field...';
            app.EditField.Value = '';
            app.EditField_2.Value = '';
            app.EditField_3.Value = '';
            app.EditField_4.Value = '';
            app.EditField_5.Value = '';
            app.EditField_6.Value = '';
            app.SearchmethodButtonGroup.SelectedObject = app.SearchmethodButtonGroup.Buttons(1);
        end

        % Drop down opening function: DropDown_4
        function DropDown_4Opening(app, event)
            Opts = {'AND', 'OR', 'NOT'};
            app.DropDown_4.Items = Opts;
            app.DropDown_4.Value = Opts{1};
            app.DropDown_4.Tooltip = {'AND = Elements common to both arrays', 'OR = Elements that are NOT common to both arrays', 'NOT = Elements from 1st array that are not in the 2nd array'};
            app.EditField.Value = ''; %Reset Query entry.
        end

        % Drop down opening function: DropDown_5
        function DropDown_5Opening(app, event)
            Opts = {'AND', 'OR', 'NOT'};
            app.DropDown_5.Items = Opts;
            app.DropDown_5.Value = Opts{1};
            app.EditField_2.Value = ''; %Reset Query entry.
        end

        % Drop down opening function: DropDown_6
        function DropDown_6Opening(app, event)
            Opts = {'AND', 'OR', 'NOT'};
            app.DropDown_6.Items = Opts;
            app.DropDown_6.Value = Opts{1};
            app.EditField_3.Value = ''; %Reset Query entry.
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            field = app.DropDown_1.Value;
            queryTerm = app.EditField.Value;
            Op = app.DropDown_4.Value;
            txt = app.EditField_4.Value;
            if strcmp(field, 'Field...')
                uialert(app.UIFigure, 'You have to select a field first!', 'warning!', 'Modal',true);
                return
            end
            if isempty(txt)
                str = app.add2Query(field, queryTerm, '', '');
                app.EditField_4.Value = str;
            else
               str = app.add2Query(field, queryTerm, Op, txt);
               app.EditField_4.Value = str;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            field = app.DropDown_2.Value;
            queryTerm = app.EditField_2.Value;
            Op = app.DropDown_5.Value;
            txt = app.EditField_5.Value;
            if strcmp(field, 'Field...')
                uialert(app.UIFigure, 'You have to select a field first!', 'warning!', 'Modal',true);
                return
            end
            if isempty(txt)
                str = app.add2Query(field, queryTerm, '', '');
                app.EditField_5.Value = str;
            else
               str = app.add2Query(field, queryTerm, Op, txt);
               app.EditField_5.Value = str;
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            field = app.DropDown_3.Value;
            queryTerm = app.EditField_3.Value;
            Op = app.DropDown_6.Value;
            txt = app.EditField_6.Value;
            if strcmp(field, 'Field...')
                uialert(app.UIFigure, 'You have to select a field first!', 'warning!', 'Modal',true);
                return
            end
            if isempty(txt)
                str = app.add2Query(field, queryTerm, '', '');
                app.EditField_6.Value = str;
            else
                str = app.add2Query(field, queryTerm, Op, txt);
                app.EditField_6.Value = str;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AdvancedSearchBuilderLabel = uilabel(app.UIFigure);
            app.AdvancedSearchBuilderLabel.Text = ' Advanced Search Builder';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SearchmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.SearchmethodButtonGroup.Title = 'Search method';
            app.ContainstermButton = uiradiobutton(app.SearchmethodButtonGroup);
            app.ContainstermButton.Text = 'Contains term';
            app.ContainstermButton.Value = true;
            app.WholewordButton = uiradiobutton(app.SearchmethodButtonGroup);
            app.WholewordButton.Text = 'Whole word';
            app.UseregexpButton = uiradiobutton(app.SearchmethodButtonGroup);
            app.UseregexpButton.Text = 'Use regexp';
            app.SubjectPanel = uipanel(app.UIFigure);
            app.SubjectPanel.Title = 'Subject';
            app.EditField = uieditfield(app.SubjectPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DropDown_1 = uidropdown(app.SubjectPanel);
            app.DropDown_1.Items = {'Field...'};
            app.DropDown_1.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_1Opening, true);
            app.DropDown_1.Value = 'Field...';
            app.EditField_4 = uieditfield(app.SubjectPanel, 'text');
            app.DropDown_4 = uidropdown(app.SubjectPanel);
            app.DropDown_4.Items = {'ADD'};
            app.DropDown_4.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_4Opening, true);
            app.DropDown_4.Value = 'ADD';
            app.QueryLabel = uilabel(app.SubjectPanel);
            app.QueryLabel.Text = 'Query';
            app.ModalityPanel = uipanel(app.UIFigure);
            app.ModalityPanel.Title = 'Modality';
            app.EditField_6 = uieditfield(app.ModalityPanel, 'text');
            app.DropDown_6 = uidropdown(app.ModalityPanel);
            app.DropDown_6.Items = {'ADD'};
            app.DropDown_6.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_6Opening, true);
            app.DropDown_6.Value = 'ADD';
            app.DropDown_3 = uidropdown(app.ModalityPanel);
            app.DropDown_3.Items = {'Field...'};
            app.DropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_3Opening, true);
            app.DropDown_3.Value = 'Field...';
            app.EditField_3 = uieditfield(app.ModalityPanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.QueryLabel_3 = uilabel(app.ModalityPanel);
            app.QueryLabel_3.Text = 'Query';
            app.AcquisitionPanel = uipanel(app.UIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.EditField_5 = uieditfield(app.AcquisitionPanel, 'text');
            app.DropDown_5 = uidropdown(app.AcquisitionPanel);
            app.DropDown_5.Items = {'ADD'};
            app.DropDown_5.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_5Opening, true);
            app.DropDown_5.Value = 'ADD';
            app.DropDown_2 = uidropdown(app.AcquisitionPanel);
            app.DropDown_2.Items = {'Field...'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.Value = 'Field...';
            app.EditField_2 = uieditfield(app.AcquisitionPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.QueryLabel_2 = uilabel(app.AcquisitionPanel);
            app.QueryLabel_2.Text = 'Query';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SearchBuilder_dlgBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end