classdef Data_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        ApplyButton           matlab.ui.control.Button
        SetButton             matlab.ui.control.Button
        OptionsPanel          matlab.ui.container.Panel
        UsecolormapLabel      matlab.ui.control.Label
        UsecolormapDropDown   matlab.ui.control.DropDown
        XdataDropDownLabel    matlab.ui.control.Label
        XdataDropDown         matlab.ui.control.DropDown
        GroupbyDropDownLabel  matlab.ui.control.Label
        GroupbyDropDown       matlab.ui.control.DropDown
        PlotbyDropDownLabel   matlab.ui.control.Label
        PlotbyDropDown        matlab.ui.control.DropDown
        ResetButton           matlab.ui.control.Button
        TimefixedLabel        matlab.ui.control.Label
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        StatsObj % "StatsManager" object
        dataArr % "dataArr" property from StatsObj.
%         dataOut  % cell array containing the data to be plotted
        gColor % array of color of each grouped item
        b_hasEvents % TRUE, if the data is separated by events.
        dimList = {'Choose ...', 'Group', 'Subject', 'ROI', 'Acquisition', 'Event'};
        curr_dimList = {'Choose ...', 'Group', 'Subject', 'ROI', 'Acquisition', 'Event'};
        b_HasTimeDim  = false % Boolean to indicate if the input data has time dimension. If TRUE, the x-axis drop-down menu is blocked.
        MainApp % Caller app.
    end
    
    properties (Access = public)
         dataOut  % cell array containing the data to be plotted % Description
    end
    
    methods (Access = private)


        function out = flatDataArr(app)
            % This function flattens the data Array into one vector for
            % scalar data and a X by T matrix for time-series data. The
            % dimensions indices are also provided to retrieve the meta
            % data information.
            
            if ( ismember(app.StatsObj.inputFeatures.dataType,{'map','matrix'}) )
                error(['Operation aborted. Plot option not available for data type ' ...
                    app.StatsObj.inputFeatures.dataType])
            end
            % Repackage data for plotting:
            disp('repackaging data...')
            % Check for time-series:
            [hasT,indxT] = ismember('T', app.StatsObj.inputFeatures.dim_names);
            dataSize = arrayfun(@(x) cellfun(@(y) size(y), x.data, 'UniformOutput',false), app.StatsObj.dataArr, 'UniformOutput', false)';
            dataSize = vertcat(dataSize{:});
            if ( hasT )
                Xsz = max(cellfun(@(x) x(indxT), dataSize));
            else
                Xsz = 1; % For scalar data.
            end
            % Preallocate output array with maximum possible data size:
            nRec = numel(app.StatsObj.dataArr);
            nEv = numel(app.StatsObj.list_of_events);
            nROI = numel(app.StatsObj.obs_list);
            datOut = nan(prod([nRec,nEv,nROI]), Xsz, 'single');
            % Populate output data structure:
            out = struct('data',[],'gIndx', [],'sIndx', [],'rIndx',[],'eIndx',[],'AcquisitionIndx',[],...
                'AcquisitionID', {},'RecStartDateTime',{});
            [hasEv,indxEv] = ismember('E', app.StatsObj.inputFeatures.dim_names);
            cnt = 1;
            for ii = 1:length(app.StatsObj.dataArr)
                % Concatenate the data:
                dat = vertcat(app.StatsObj.dataArr(ii).data{:});
                if ( hasEv )
                    datSz1 = size(app.StatsObj.dataArr(ii).data{1},indxEv) * numel(app.StatsObj.dataArr(ii).data);
                    dat = reshape(permute(dat,[indxEv, setdiff(1:ndims(dat),indxEv)]),datSz1,[]); % Reshape data;
                else
                    datSz1 =  numel(app.StatsObj.dataArr(ii).data);
                end
                
                if ( hasT )
                    datSz2 = size(app.StatsObj.dataArr(ii).data{1},indxT);
                else
                    datSz2 = 1;
                end
                % Add data to output array:
                datOut(cnt:cnt+datSz1-1,1:datSz2) = dat;
                % Get meta data:
                RecStartDateTime = repmat({app.StatsObj.dataArr(ii).RecStartDateTime},datSz1,1);
                AcquisitionID = repmat({app.StatsObj.dataArr(ii).AcquisitionID},datSz1,1);
                aIndx = repmat(app.StatsObj.dataArr(ii).AcquisitionIndx, datSz1,1);
                gIndx = repmat(app.StatsObj.dataArr(ii).gIndx,datSz1,1);
                sIndx = repmat(app.StatsObj.dataArr(ii).sIndx,datSz1,1);
                rIndx = repelem(app.StatsObj.dataArr(ii).rIndx, datSz1/length(app.StatsObj.dataArr(ii).rIndx),1);
                eIndx = repmat(app.StatsObj.dataArr(ii).eIndx, datSz1/length(app.StatsObj.dataArr(ii).eIndx),1);
                % Add meta data to output structure:
                out(1).gIndx = [out.gIndx; gIndx];
                out.sIndx = [out.sIndx; sIndx];
                out.rIndx = [out.rIndx; rIndx];
                out.eIndx = [out.eIndx; eIndx];
                out.AcquisitionIndx = [out.AcquisitionIndx; aIndx];
                out.AcquisitionID = [out.AcquisitionID; AcquisitionID];
                out.RecStartDateTime = [out.RecStartDateTime; RecStartDateTime];
                cnt = cnt + datSz1;
            end
            out.data = datOut(~all(isnan(datOut),2),:);
            out.groupID = unique(app.StatsObj.list_of_groups);
            out.SubjectID = unique({app.StatsObj.dataArr.SubjectID});
            out.EventID = app.StatsObj.list_of_events;
            out.ObsID = app.StatsObj.obs_list;
            disp('Done.')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj, MainApp, b_useGUI)
            app.UIFigure.Visible = 'off';
            app.StatsObj = statsObj;
            app.MainApp = MainApp;
            
            if ( strcmpi(app.StatsObj.inputFeatures.dataType, 'time-vector') )
                app.TimefixedLabel.Visible ='on';
                app.XdataDropDown.Visible = 'off';
                app.GroupbyDropDown.Enable = 'on';
                app.b_HasTimeDim = true;
            end
            app.b_hasEvents = app.StatsObj.inputFeatures.b_hasValidEvent;
            if ( ~app.b_hasEvents )
                app.dimList = setdiff(app.dimList, 'Event', 'stable');
                app.curr_dimList = app.dimList;
            end 
            % Update drop-down menus:
            app.XdataDropDown.Items = app.dimList;
            app.GroupbyDropDown.Items = app.dimList;
            app.PlotbyDropDown.Items = app.dimList;
            
            if ( ~b_useGUI )                               
               app.XdataDropDown.Value = 'Acquisition';
               app.GroupbyDropDown.Value = 'Group';
               app.PlotbyDropDown.Value = 'ROI';
               app.SetButtonPushed;
               app.ApplyButtonPushed;
               app.UIFigureCloseRequest;
            else
                movegui(app.UIFigure, 'center');
                app.UIFigure.Visible = 'on';    
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                                 
                delete(app);            
        end

        % Value changed function: XdataDropDown
        function XdataDropDownValueChanged(app, event)
            if strcmpi(app.XdataDropDown.Value, 'choose ...')
                return
            end
            app.curr_dimList = setdiff(app.curr_dimList, app.XdataDropDown.Value, 'stable');
            app.GroupbyDropDown.Items = app.curr_dimList;
            app.PlotbyDropDown.Items = app.curr_dimList;
            app.GroupbyDropDown.Enable = 'on';
            app.XdataDropDown.Enable = 'off';
        end

        % Value changed function: GroupbyDropDown
        function GroupbyDropDownValueChanged(app, event)
            if strcmpi(app.GroupbyDropDown.Value, 'choose ...')
                return
            end
            app.curr_dimList = setdiff(app.curr_dimList, app.GroupbyDropDown.Value, 'stable');
            app.PlotbyDropDown.Items = app.curr_dimList;
            app.PlotbyDropDown.Enable = 'on';
            app.GroupbyDropDown.Enable = 'off';
        end

        % Value changed function: PlotbyDropDown
        function PlotbyDropDownValueChanged(app, event)
            if strcmpi(app.PlotbyDropDown.Value, 'choose ...')
                return
            end
            app.XdataDropDown.Enable = 'off';
            app.GroupbyDropDown.Enable = 'off';
            app.UsecolormapDropDown.Enable = 'on';
            app.SetButton.Enable = 'on';
            app.PlotbyDropDown.Enable = 'off';
        end

        % Value changed function: UsecolormapDropDown
        function UsecolormapDropDownValueChanged(app, event)
            % Just to disable apply button and force user to click on Set
            % button.
            app.ApplyButton.Enable = 'off';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.curr_dimList = app.dimList;
            app.XdataDropDown.Items = app.dimList; app.XdataDropDown.Value = app.dimList{1};
            app.GroupbyDropDown.Items = app.dimList; app.GroupbyDropDown.Value = app.dimList{1};
            app.PlotbyDropDown.Items = app.dimList; app.PlotbyDropDown.Value = app.dimList{1};
            app.UsecolormapDropDown.Value = 'none';
            app.UsecolormapDropDown.Enable = 'off';
            if app.b_HasTimeDim
                app.GroupbyDropDown.Enable = 'on';
                app.XdataDropDown.Enable = 'off';
            else
                app.GroupbyDropDown.Enable = 'off';
                app.XdataDropDown.Enable = 'on';
            end
            app.PlotbyDropDown.Enable = 'off';
            app.SetButton.Enable = 'off';
            app.ApplyButton.Enable = 'off';
            app.UITable.Data = [];
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % This callback just populates the items in the UITable.
            
            % Populate table with Group Items and colors:
            switch app.GroupbyDropDown.Value
                case 'Group'
                    tabItems = unique(app.StatsObj.list_of_groups, 'stable')';
                case 'Subject'
                    tabItems = unique({app.StatsObj.dataArr.SubjectID}, 'stable')';
                case 'ROI'
                    tabItems = unique(app.StatsObj.obs_list, 'stable')';
                case 'Acquisition'
                    tabItems = arrayfun(@(x) ['Acquisition #' num2str(x)], unique(vertcat(app.StatsObj.dataArr.AcquisitionIndx)), 'UniformOutput',false);
                otherwise
                    tabItems = unique(app.StatsObj.list_of_events, 'stable');
            end
            
            %             if app.b_hasEvents && ~strcmpi(app.PlotbyDropDown.Value, 'event')
            %                 % If events exist and it was not selected to be presented as plots, split the grouped data by event
            %                 % in the order:
            %                 % "Item1-ev1, Item1-ev2, Item1-evN, Item2-ev1, Item2-ev2, Item2-evN ..."
            %                 tmp = cell(length(tabItems)*length(app.StatsObj.dataArr.EventID),1);
            %                 cnt = 1;
            %                 for ii = 1:length(tabItems)
            %                     tmp(cnt:cnt+length(app.StatsObj.dataArr.EventID)-1) = cellfun(@(x) [tabItems{ii} ' - '  x], app.StatsObj.dataArr.EventID, 'UniformOutput',false)';
            %                     cnt = cnt+length(app.StatsObj.dataArr.EventID);
            %                 end
            %                 tabItems = tmp; clear tmp
            %             end
            if ~strcmpi(app.UsecolormapDropDown.Value, 'none')
                % Use a given Matlab colormap:
                eval(['tabColors = ' app.UsecolormapDropDown.Value '(' num2str(numel(tabItems)) ');']);
                % Make text black to improve visibility:
                app.UITable.ForegroundColor = [0 0 0];
            else
                % Generate default color palette:
                tabColors = distinguishable_colors(numel(tabItems));
                % Make text white to improve visibility:
                app.UITable.ForegroundColor = [1 1 1];
            end
            % Populate the table with the data and colors:
            app.UITable.Data = table(tabItems);
            app.UITable.RowName = 1:height(app.UITable.Data);
            app.UITable.BackgroundColor = tabColors;
            app.UITable.Enable = 'on';
            %
            app.ApplyButton.Enable = 'on';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % This callback creates an array of indices pointing to the data stored in "dataArr" property of
            % "StatsManager" object. The indices are organized as
            % (X,Y,Plot).
            % The plotter app will use the indices to plot the data
            % reorganized following the selection from the drop-down menus
            % of this app.
            if strcmp(app.UIFigure.Visible, 'on')
                w = uiprogressdlg(app.UIFigure,'Title','Please wait...','Message','Pivoting data', 'Indeterminate','on');                
            else 
                w = [];
            end
            disp('Creating index array')
            dimName = {'Group', 'Subject', 'ROI', 'Acquisition', 'Event'};
            dimIndx = {'gIndx', 'sIndx', 'rIndx', 'AcquisitionIndx', 'eIndx'};
            indxMap = containers.Map(dimName, dimIndx);
            nameMap = containers.Map(dimName, {'groupID', 'SubjectID','ObsID','AcquisitionID','EventID'});
            % Get data array:
            dataIn = app.flatDataArr;
            
            % Get x indices:
            if ( ~app.b_HasTimeDim )
                xIndx = dataIn.(indxMap(app.XdataDropDown.Value));
            else
                xIndx = 1;
            end
            % Get y indices:
            yIndx = dataIn.(indxMap(app.GroupbyDropDown.Value));
            
            % Get Plot indices:
            pIndx = dataIn.(indxMap(app.PlotbyDropDown.Value));
            pName = nameMap(app.PlotbyDropDown.Value);
            % Get lenghts of each dimension:
            pLen = numel(unique(pIndx));
            yLen = numel(unique(yIndx));
            xLen = numel(unique(xIndx));
            cmap = app.UITable.BackgroundColor;
            app.dataOut = struct('data',[],'groupIndx',[],'gName',{}, 'gColor',[],'dataTip',{},'plotTitle','', 'xLabel',{}, 'xTitle', '');
                        
            for ii = 1:pLen
                indxP = ( pIndx == ii );
                app.dataOut(ii).data = [];app.dataOut(ii).dataTip = [];
                
                if strcmpi(app.PlotbyDropDown.Value, 'Acquisition')
                    app.dataOut(ii).plotTitle = ['Acquisition # ' num2str(ii)];
                else
                    app.dataOut(ii).plotTitle = dataIn.(pName){ii};
                end                
                
                if ( ii  == 1 )                    
                    if strcmpi(app.GroupbyDropDown.Value, 'Acquisition')
                        app.dataOut(ii).gName = arrayfun(@(x) ['Acq# ' num2str(x)], unique(dataIn.AcquisitionIndx), 'UniformOutput',false);
                    else
                        app.dataOut(ii).gName = unique(dataIn.(nameMap(app.GroupbyDropDown.Value)), 'stable');
                    end
                                        
                    if strcmpi(app.XdataDropDown.Value, 'Acquisition')
                        app.dataOut(ii).xLabel = arrayfun(@(x) ['Acq# ' num2str(x)], unique(dataIn.AcquisitionIndx), 'UniformOutput',false);
                        app.dataOut(ii).xTitle = app.XdataDropDown.Value;
                    elseif app.b_HasTimeDim
                        app.dataOut(ii).xTitle = 'Time';
                        app.dataOut(ii).xLabel = []; % Empty for now...
                    else
                        app.dataOut(ii).xTitle = app.XdataDropDown.Value;
                        app.dataOut(ii).xLabel = dataIn.(nameMap(app.XdataDropDown.Value));
                    end
                end
                for jj = 1:yLen
                    indxY = ( yIndx == jj );                
                    tmpDat = [];
                    tmpInfo = {};
                    cnt = 1;
                    for kk = 1:xLen
                        indxX = ( xIndx == kk );                        
                        indSum = ( indxP & indxY & indxX );                        
                        if any(indSum)
                            % Create string with meta data for each data
                            % point in tmpDat:
                            gName = dataIn.(nameMap('Group'))(dataIn.(indxMap('Group'))(indSum)); gName = gName(:);
                            sName = dataIn.(nameMap('Subject'))(dataIn.(indxMap('Subject'))(indSum)); sName = sName(:);
                            aName = dataIn.(nameMap('Acquisition'))(indSum)'; aName = aName(:);
                            recDat = dataIn.RecStartDateTime(indSum)'; recDat = recDat(:);
                            rName = dataIn.(nameMap('ROI'))(dataIn.(indxMap('ROI'))(indSum)); rName = rName(:);                            
                            if app.StatsObj.inputFeatures.b_hasValidEvent
                                eName = dataIn.(nameMap('Event'))(dataIn.(indxMap('Event'))(indSum)); eName = eName(:);
                                str = cellfun(@(a,b,c,d,e,f)...
                                    sprintf('Group ID: %s\nSubject ID: %s\nAcq. ID: %s\nAcq. DateTime: %s\nROI ID: %s\nEvent ID: %s', ...
                                    a,b,c,d,e,f), gName, sName, aName, recDat, rName, eName, 'UniformOutput',false);
                            else
                                str = cellfun(@(a,b,c,d,e)...
                                    sprintf('Group ID: %s\nSubject ID: %s\nAcq. ID: %s\nAcq. DateTime: %s\nROI ID: %s', ...
                                    a,b,c,d,e), gName, sName, aName, recDat, rName,'UniformOutput',false);
                            end
                            
                            if app.b_HasTimeDim
                                % Concatenate time series:
                                tmpDat = dataIn.data(indSum,:);
                                tmpInfo = str;                                                                                              
                            else
                                % Concatenate scalar data:
                                if size(tmpDat,1) < sum(indSum)                                    
                                    difSz = sum(indSum) - size(tmpDat,1);
                                    tmpDat(1:sum(indSum),cnt) = dataIn.data(indSum);
                                    if cnt > 1
                                        tmpDat(end-difSz+1:end,cnt-1) = nan;
                                        tmpInfo(end+difSz,cnt-1) = {[]};
                                    end
                                    tmpInfo = horzcat(tmpInfo, str);
                                elseif size(tmpDat,1) > sum(indSum)
                                    tmpDat(:,end+1) = nan;
                                    tmpDat(1:sum(indSum),end) = dataIn.data(indSum);
                                    tmpInfo(:,end+1) = cell(1,size(tmpDat,1));
                                    tmpInfo(1:sum(indSum),end) = str;
                                else
                                    tmpDat = horzcat(tmpDat,dataIn.data(indSum));
                                    tmpInfo = horzcat(tmpInfo, str);
                                end
                            end
                        else                            
                            if app.b_HasTimeDim
                                tmpDat = nan(1,size(dataIn.data,2));
                                tmpInfo = {[]};
                            else
                                if isempty(tmpDat)
                                    tmpDat = nan;
                                    tmpInfo = {[]};
                                else
                                    tmpDat = horzcat(tmpDat, nan(size(tmpDat,1),1));
                                    tmpInfo = horzcat(tmpInfo, repmat({[]},size(tmpDat,1),1));
                                end
                            end
                        end
                        idxMiss = cellfun(@isempty, tmpInfo);
                        tmpDat(idxMiss) = nan;
                        cnt = cnt + 1;
                    end
                    
                    % Populate output variable:
                    app.dataOut(ii).data = vertcat(app.dataOut(ii).data, tmpDat);                   
                    app.dataOut(ii).dataTip = vertcat(app.dataOut(ii).dataTip, tmpInfo);                    
                    app.dataOut(ii).groupIndx = vertcat(app.dataOut(ii).groupIndx, repmat(jj,size(tmpDat,1),1));
                    if strcmpi(app.GroupbyDropDown.Value, 'Acquisition')
                        app.dataOut(ii).gColor = vertcat(app.dataOut(ii).gColor, cmap(jj,:));
                    else
                        app.dataOut(ii).gColor = vertcat(app.dataOut(ii).gColor,...
                            cmap(strcmp(app.UITable.Data.tabItems,dataIn.(nameMap(app.GroupbyDropDown.Value)){jj}),:));
                    end
                end
            end
            delete(w)
            disp('Done');
            % Update info in Main App:
            app.MainApp.data = app.dataOut;
            % Close figure:
            app.UIFigureCloseRequest;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % This callback from UITable so the user can click on an item
            % to change its color.
            if size(event.Indices,1)>1
                return
            end
            pause(.5)
            app.UITable.BackgroundColor(event.Indices(1),:) = uisetcolor(app.UITable.BackgroundColor(event.Indices(1),:), 'Choose a color');
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Data selector';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.SetButton = uibutton(app.GridLayout3, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.OptionsPanel = uipanel(app.GridLayout2);
            app.OptionsPanel.Title = 'Options';
            app.UsecolormapLabel = uilabel(app.OptionsPanel);
            app.UsecolormapLabel.Text = 'Use colormap:';
            app.UsecolormapDropDown = uidropdown(app.OptionsPanel);
            app.UsecolormapDropDown.Items = {'none', 'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.UsecolormapDropDown.ValueChangedFcn = createCallbackFcn(app, @UsecolormapDropDownValueChanged, true);
            app.UsecolormapDropDown.Enable = 'off';
            app.UsecolormapDropDown.Value = 'none';
            app.XdataDropDownLabel = uilabel(app.OptionsPanel);
            app.XdataDropDownLabel.Text = 'X data';
            app.XdataDropDown = uidropdown(app.OptionsPanel);
            app.XdataDropDown.Items = {'Choose ...', 'Group', 'Subject', 'ROI', 'Acquisition', 'Event', ''};
            app.XdataDropDown.ValueChangedFcn = createCallbackFcn(app, @XdataDropDownValueChanged, true);
            app.XdataDropDown.Value = 'Choose ...';
            app.GroupbyDropDownLabel = uilabel(app.OptionsPanel);
            app.GroupbyDropDownLabel.Text = 'Group by';
            app.GroupbyDropDown = uidropdown(app.OptionsPanel);
            app.GroupbyDropDown.Items = {'Choose ...', 'Group', 'Subject', 'ROI', 'Acquisition', 'Event'};
            app.GroupbyDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupbyDropDownValueChanged, true);
            app.GroupbyDropDown.Enable = 'off';
            app.GroupbyDropDown.Value = 'Choose ...';
            app.PlotbyDropDownLabel = uilabel(app.OptionsPanel);
            app.PlotbyDropDownLabel.Text = 'Plot by';
            app.PlotbyDropDown = uidropdown(app.OptionsPanel);
            app.PlotbyDropDown.Items = {'Choose ...', 'Group', 'Subject', 'ROI', 'Acquisition', 'Event'};
            app.PlotbyDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotbyDropDownValueChanged, true);
            app.PlotbyDropDown.Enable = 'off';
            app.PlotbyDropDown.Value = 'Choose ...';
            app.ResetButton = uibutton(app.OptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TimefixedLabel = uilabel(app.OptionsPanel);
            app.TimefixedLabel.Visible = 'off';
            app.TimefixedLabel.Text = 'Time (fixed)';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Group Name'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'Click in a cell to change color.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end