classdef List_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OptionsMenu               matlab.ui.container.Menu
        SavelistMenu              matlab.ui.container.Menu
        LoadlistMenu              matlab.ui.container.Menu
        OriginallistListBoxLabel  matlab.ui.control.Label
        OriginallistListBox       matlab.ui.control.ListBox
        NewlistListBoxLabel       matlab.ui.control.Label
        NewlistListBox            matlab.ui.control.ListBox
        AddButton                 matlab.ui.control.Button
        ButtonUp                  matlab.ui.control.Button
        ButtonDwn                 matlab.ui.control.Button
        ConfirmButton             matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        RemoveButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        list % current list
        original % original list
        statsObj % StatsManager object handle
        type % List type.
        map  % Map object with type of list. Either 'observation' OR 'event'.
    end
    
    methods (Access = private)
        
        function out = findProtObj(app)
            % Finds the protocol object from statsObj.
            % Find the Save Directory of "Protocol" object
            out = app.statsObj.list_of_objs{1};
            while 1
                if ~isa(out, 'Protocol')
                    out = out.MyParent;
                else
                    break
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj, listType)
            map = containers.Map({'observation', 'event'},{'obs_list', 'list_of_events'});
            % Check lists:
            list = statsObj.(map(listType));
            listOrig = statsObj.([map(listType) '_original']);
            if ~all(ismember(list, listOrig))
                warning('New list items are missing in the original list.')
                delete(app);
            end
            if ~strcmpi(listType, {'observation', 'event'})
                uialert(app.UIFigure, 'The list type must be either "observation" or "event"!', 'Failed to select list type', 'CloseFcn','uiresume(gcbf)')
                uiwait(app.UIFigure);
                delete(app)
            end
            app.list = list;
            app.original = listOrig;
            app.map = containers.Map({'observation', 'event'},{'obs_list', 'list_of_events'});
            app.type = app.map(lower(listType));
            
            app.statsObj = statsObj;
            % Load items in lists:
            app.OriginallistListBox.Items = setdiff(app.original, app.list,'stable');
            app.NewlistListBox.Items = app.list;
        end

        % Button pushed function: AddButton, RemoveButton
        function AddRemove_callback(app, event)
            % Check if it is Add or Remove and set source/target lists:
            if contains(event.Source.Text, 'add', 'IgnoreCase',true)
                sourceList = app.OriginallistListBox;
                targetList = app.NewlistListBox;
            else
                sourceList = app.NewlistListBox;
                targetList = app.OriginallistListBox;
            end
            
            if isempty(sourceList.Value)
                return
            end
            idx = ismember(sourceList.Value, targetList.Items);
            if all(idx)
                return
            elseif any(idx)
                % Filter source list.
                sourceList.Value(idx) = [];
            end
            if ischar(sourceList.Value)
                % Single-select:
                targetList.Items = [targetList.Items, {sourceList.Value}]; % Add item to target list:
            else
                % Multi-select
                targetList.Items = [targetList.Items, sourceList.Value]; % Add item to target list:
            end
            sourceList.Items = setdiff(sourceList.Items, sourceList.Value, 'stable');% Remove item from source list
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            
            if isempty(app.NewlistListBox.Value)
                return
            end
            pos = find(strcmp(app.NewlistListBox.Value, app.NewlistListBox.Items));
            if pos > 1
                range = 1:numel(app.NewlistListBox.Items);
                range(pos) = pos -1 ;
                range(pos -1) = pos;
                app.NewlistListBox.Items = app.NewlistListBox.Items(range);
            end
        end

        % Button pushed function: ButtonDwn
        function ButtonDwnPushed(app, event)
            
            if isempty(app.NewlistListBox.Value)
                return
            end
            pos = find(strcmp(app.NewlistListBox.Value, app.NewlistListBox.Items));
            if pos < numel(app.NewlistListBox.Items)
                range = 1:numel(app.NewlistListBox.Items);
                range(pos) = pos + 1;
                range(pos + 1) = pos;
                app.NewlistListBox.Items = app.NewlistListBox.Items(range);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Erase new list and reset original
            app.NewlistListBox.Items = {};
            app.OriginallistListBox.Items = app.original;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if isempty(app.NewlistListBox.Items)
                uialert(app.UIFigure, 'New list is empty!','Try again!', 'Icon', 'warning');
                return
            end
            % Update lists in StatsObj:
            app.statsObj.(app.type) = app.NewlistListBox.Items;
            % Re-calculate "dataArr" in StatsObj
            app.statsObj.gen_dataArr;
            % Close app
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close figure
                        
            if ( ~isempty(app.NewlistListBox.Items) && ~isequaln(app.list, app.NewlistListBox.Items) )
                answer = uiconfirm(app.UIFigure, 'Changes were made to the current list. Save before closing?',...
                    'Save new list?', "Options",{'Yes', 'No'}, 'DefaultOption',2);
                if strcmpi(answer,'yes')
                    app.ConfirmButtonPushed;
                else
                    delete(app)
                end
            else
                delete(app)
            end
        end

        % Menu selected function: SavelistMenu
        function SavelistMenuSelected(app, event)
            % Saves the new list to a .mat file.
            
            protObj = app.findProtObj;
            keys = app.map.keys;
            ListName = keys{strcmp(app.map.values, app.type)};
            [file, path] = uiputfile('*mat','Save new list', fullfile(protObj.SaveDir,[ListName '_list.mat']));
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            newList = app.NewlistListBox.Items;
            save(fullfile(path,file), 'newList')
            figure(app.UIFigure);
            uialert(app.UIFigure, ['List saved to: ' fullfile(path,file)],'List Saved', "CloseFcn",'uiresume(gcbf)','Icon', 'info');
            uiwait(app.UIFigure);
        end

        % Menu selected function: LoadlistMenu
        function LoadlistMenuSelected(app, event)
            % Loads list from .mat file
            protObj = app.findProtObj;
            keys = app.map.keys;
            ListName = keys{strcmp(app.map.values, app.type)};
            % Open list:
            [file,path] = uigetfile('*mat','Open new list', fullfile(protObj.SaveDir,[ListName '_list.mat']));
            s = load(fullfile(path,file));
            figure(app.UIFigure);
            if ~all(ismember(s.newList, app.original))
                uialert(app.UIFigure, 'Cannot load List. All elements of the list must be part of the original. Is this the good type of list?',...
                    'Failed to load list', 'CloseFcn','uiresume(gcbf)');
                uiwait(app.UIFigure);
                return
            end
            
            % Reset current list and replace with loaded one:
            app.ResetButtonPushed;
            app.OriginallistListBox.Items =setdiff(app.OriginallistListBox.Items, s.newList,'stable');
            app.NewlistListBox.Items = s.newList;
            uialert(app.UIFigure, 'New list Loaded!', 'List Loaded!', "CloseFcn",'uiresume(gcbf)','Icon', 'info');
            uiwait(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Observation/Event selector';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SavelistMenu = uimenu(app.OptionsMenu);
            app.SavelistMenu.MenuSelectedFcn = createCallbackFcn(app, @SavelistMenuSelected, true);
            app.SavelistMenu.Text = 'Save list';
            app.LoadlistMenu = uimenu(app.OptionsMenu);
            app.LoadlistMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadlistMenuSelected, true);
            app.LoadlistMenu.Text = 'Load list';
            app.OriginallistListBoxLabel = uilabel(app.UIFigure);
            app.OriginallistListBoxLabel.Text = 'Original list';
            app.OriginallistListBox = uilistbox(app.UIFigure);
            app.OriginallistListBox.Items = {};
            app.OriginallistListBox.Multiselect = 'on';
            app.OriginallistListBox.Value = {};
            app.NewlistListBoxLabel = uilabel(app.UIFigure);
            app.NewlistListBoxLabel.Text = 'New list';
            app.NewlistListBox = uilistbox(app.UIFigure);
            app.NewlistListBox.Items = {};
            app.NewlistListBox.Multiselect = 'on';
            app.NewlistListBox.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddRemove_callback, true);
            app.AddButton.Text = '>>Add>>';
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Icon = 'icon_arrow_up.png';
            app.ButtonUp.Text = '';
            app.ButtonDwn = uibutton(app.UIFigure, 'push');
            app.ButtonDwn.ButtonPushedFcn = createCallbackFcn(app, @ButtonDwnPushed, true);
            app.ButtonDwn.Icon = 'icon_arrow_dwn.png';
            app.ButtonDwn.Text = '';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @AddRemove_callback, true);
            app.RemoveButton.Text = '<<Remove<<';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = List_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end