classdef PlotAvgMaps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OptionsPanel                 matlab.ui.container.Panel
        SetcolormapButton            matlab.ui.control.Button
        colorscaleButtonGroup        matlab.ui.container.ButtonGroup
        AutoButton_2                 matlab.ui.control.RadioButton
        UniformButton                matlab.ui.control.RadioButton
        SpatFiltsigmaEditFieldLabel  matlab.ui.control.Label
        SpatFiltsigmaEditField       matlab.ui.control.NumericEditField
        UselogicalmaskButton         matlab.ui.control.StateButton
        SubtractbaselineCheckBox     matlab.ui.control.CheckBox
        CreatemapsButton             matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        StatsObj % Handle to StatisticManager object
        mskInfo = struct('mskArr',{}, 'warpMskArr',{},'sNames',{}) % Structure containing the logical masks to be applied over each group.
        avgMapArr = {}; % Cell array containing the average maps with dimensions (group x acquisition x observation)
        gNames % List of group names
        hLink % linkprop handle for axes.
        sumMsk % sum of logical masks per acquisition.
        hiddenFig % Figure containing dummy axis for interaction with Image options app.
        currFigs = findall(0, 'Type', 'Figure');
        dataArr % statsObj data.
        hMskFig % handle to logical mask figure
    end
    
    properties (Access = public)
        image_info = struct('cmap', colormap('jet'),'clims',[0 1], 'clims_def',[0 1], 'pxPermm',0, 'refPt', [0,0], 'logical_mask', [])
        hiddenAx % handle to dummy axis.
    end
    
    methods (Access = private)
        
        function calcAvgMaps(app)
            % AVGMAPS creates an average map (i.e. image with dimensions Y,X) per group
            % per acquisition.
            disp('Averaging data...')
            
            % Get names of groups and segregate data per aquisition index:
            app.mskInfo = arrayfun(@(x) setfield(x, 'warpMskArr',{}), app.mskInfo); % Reset array of registered masks;
            indxAcq = [app.dataArr.AcquisitionIndx]';
            nAcq = unique(indxAcq); % Number of acquisitions per group.
            % Instantiate output variable:
            app.avgMapArr = cell(length(app.gNames), length(nAcq), length(app.StatsObj.obs_list));
            % Create image reference using the size of the first data in
            % "dataArr":
            szImg = size(app.dataArr(1).data{1,1});
            if app.StatsObj.inputFeatures.b_hasValidEvent
                % For maps with events.
                RA = imref2d(szImg([2,3])); % Scope of "RA" shared with local function "calcAvg".
            else
                RA = imref2d(szImg); % Scope of "RA" shared with local function "calcAvg".
            end
            
            for i = 1:numel(app.gNames)
                idxG = strcmp(app.gNames{i}, {app.dataArr.groupID})';
                for j = 1:length(nAcq)
                    % Get all acquisitions:
                    indxA = find(idxG & (indxAcq == nAcq(j)) );
                    % Preallocate data array with dimensions Subj X Obs:
                    dataIn = cell(length(indxA),length(app.StatsObj.obs_list));
                    refPt_list = zeros(length(indxA),2); % Same for reference point
                    pxPermm_list = zeros(length(indxA),1); % And pixel ratio.
                    for k = 1:length(indxA)
                        % Get data from each acquisition:
                        [idxObs, locB] = ismember(app.StatsObj.obs_list, app.dataArr(indxA(k)).observationID);
                        locB(locB == 0) = [];
                        dataIn(k,idxObs) = app.dataArr(indxA(k)).data(locB);
                        refPt_list(k,:) = app.dataArr(indxA(k)).MatFile.refPt;
                        pxPermm_list(k) = app.dataArr(indxA(k)).MatFile.pxPermm;
                    end
                    % Remove singleton dimensions:
                    dataIn = cellfun(@squeeze, dataIn,'UniformOutput',false);
                    % Permute masks order to match data order:
                    if isempty(app.mskInfo) || ~isfield(app.mskInfo(1), 'mskArr')
                        mskArr = {};
                    else
                        [~,locB] = ismember(app.mskInfo(i).sNames,{app.dataArr(indxA).SubjectID});
                        mskArr = app.mskInfo(i).mskArr(locB);                                               
                    end
                    if app.SpatFiltsigmaEditField.Value
                        disp('Filtering maps...')
                    end
                    % Perform averaging for each acquisition and group:
                    [app.avgMapArr(i,j,:), app.mskInfo(i).warpMskArr] = calcAvg(dataIn, refPt_list, pxPermm_list, mskArr, app.SpatFiltsigmaEditField.Value);
                    
                end
                if app.SubtractbaselineCheckBox.Value 
                    for kk= size(app.avgMapArr,2):-1:1
                        % Subtract from first map
                        disp('subtracting...')
                        for nn = 1:size(app.avgMapArr,3)
                            app.avgMapArr{i,kk,nn} = app.avgMapArr{i,kk,nn} - app.avgMapArr{i,1,nn};                        
                        end
                    end
                end
            end
            disp('Finished calculating averages.')
            %---------------------------------------------------------------
            % Local function:
            function [out, msk_out] = calcAvg(data, ref, px_size, logical_mask, sigma)
                % This function aligns the reference points and scales the
                % frames in data, then calculates the average maps.
                
                msk_out = {};
                %% DEALING WITH EVENTS %%
                nFrames = 1; % One map per acquisition per subject
                if ndims(data{1,1}) == 3
                    % Several maps per acquisition per subject
                    nFrames = size(data{1,1},1);
                end
                %
                myMsk = cell(size(data,1),1);
                b_hasMask = ~isempty(logical_mask);
                % Translate and scale the data to match position of first item of
                % "data":
                ref = ref(1,:) - ref;
                px_size = px_size(1)./px_size;
                %                 RA = imref2d(size(data{1,1})); % Use size of first data as reference.
                for ii = 1:size(data,1)
                    % Loop across subjects:
                    tform = affine2d([px_size(ii) 0 0; 0 px_size(ii) 0; ref(ii,1) ref(ii,2) 1]);
                    for jj = 1:size(data,2)
                        % Loop across acquisitions:
                        if isempty(data{ii,jj})
                            % Create frame with NaNs:
                            data{ii,jj} = nan([RA.ImageSize,nFrames]);
                        end
                        % Warp logical mask
                        if b_hasMask
                            myMsk{ii} = imwarp(logical_mask{ii}, tform, 'nearest', 'OutputView', RA, 'FillValues', 0);                            
                        end
                        % Deal with 3D data:
                        if nFrames > 1
                            % Push "events" to the end:
                            data{ii,jj} = permute(data{ii,jj},[2 3 1]);
                        end
                        % Remove NaNs and Infs from data
                        idxNaN = isnan(data{ii,jj});
                        idxInfPos = isinf(data{ii,jj}) & data{ii,jj} > 0;
                        idxInfNeg = isinf(data{ii,jj}) & data{ii,jj} < 0;
                        data{ii,jj}(idxNaN | idxInfPos | idxInfNeg) = 0;
                        
                        data{ii,jj} = imwarp(data{ii,jj}, tform, 'nearest', 'OutputView', RA, 'FillValues', nan);
                        % Put NaNs and Infs back to the translated data:
                        if any(idxNaN, 'all')
                            idxNaN = imwarp(idxNaN, tform, 'nearest', 'OutputView', RA,'FillValues', 0);
                            data{ii,jj}(idxNaN) = nan;
                        end
                        if any(idxInfPos, 'all')
                            idxInfPos = imwarp(idxInfPos, tform, 'nearest', 'OutputView', RA,'FillValues', 0);
                            data{ii,jj}(idxInfPos) = Inf;
                        end
                        if any(idxInfNeg, 'all')
                            idxInfNeg = imwarp(idxInfNeg, tform, 'nearest', 'OutputView', RA,'FillValues', 0);
                            data{ii,jj}(idxInfNeg) = -Inf;
                        end
                    end
                end
                
                % Average acquisitions:
                out = cell(1,size(data,2));               
                for ii = 1:size(data,2)
                    out{ii} = mean(cat(4,data{:,ii}),4,'omitnan');                                                              
                    % Populate NaN areas with interpolated data:
                    out{ii} = applyGaussFilt(out{ii},sigma);                    
                    % Permute data back:
                    out{ii} = squeeze(ipermute(out{ii},[2 3 1]));
                end
                
                if isempty(myMsk{1})
                    return
                end
                
                % Collapse mask array into a single labeled matrix:
                msk_out = sum(cat(3, myMsk{:}),3);
                % Apply mask to the data:
                if ndims(out{1}) == 3
                    % Account for events:
                    msk = repmat(msk_out,1,1,size(out{ii},1));msk = permute(msk, [3 1 2]);    
                else
                    msk = msk_out;
                end
                
                for ii = 1:size(out,2)                    
                    out{ii}(msk == 0) = nan;
                end
            end
            
            function out = applyGaussFilt(data, sig)
                % Applies a gaussian filter to an image but removes distortions
                % due to NaNs.
                if sig == 0
                    out = data;
                    return
                end
                out = zeros(size(data));                
                for ind = 1:size(data,3)
                    nanMask = isnan(data(:,:,ind));
                    % Abort for empty maps:
                    if all(nanMask)
                        out = data;
                        return                        
                    end
                    dat = data(:,:,ind);
                    [r, c] = find(~nanMask);
                    [rNan, cNan] = find(nanMask);
                    F = scatteredInterpolant(double(c), double(r), double(dat(~nanMask)), 'nearest');
                    interpVals = F(cNan, rNan);
                    % Populate NaN areas with interpolated data:
                    dat(nanMask) = single(interpVals);
                    % Perform spatial filter:
                     dat = imgaussfilt(dat,sig);
                    % Put NaNs back:
                    dat(nanMask) = nan;
                    out(:,:,ind) = dat;
                end
            end
        end
        function plotMaps(app)
            % This function creates the figures containing the average
            % maps.
            
            % Check if the data is 2d or 3D:
            b_is3D = ( ndims(app.avgMapArr{1,1,1}) == 3 );
                     
            % Plot maps:
            disp('plotting maps...')
            % Plot All Maps for each observation:
            for i = 1:size(app.avgMapArr,3)
                % Loop across observations
                f = figure('Name', app.StatsObj.obs_list{i}, 'Visible', 'off','NumberTitle', 'off',...
                    'Tag', 'avgMapFig', 'CreateFcn',{@movegui, 'northeast'}, 'CloseRequestFcn',@(src,ev) app.DoNothing);
                for j = 1:size(app.avgMapArr,1)
                    % Loop across groups:
                    ax = subplot(size(app.avgMapArr,1),1,j, 'Parent', f);
                    if b_is3D                        
                        frames = cellfun(@(x) arrayfun(@(y) squeeze(x(y,:,:)), 1:size(x,1), 'UniformOutput',false),...
                            app.avgMapArr(j,:,i), 'UniformOutput',false);frames = vertcat(frames{:});
%                         mnt = montage(frames, 'Size',[size(app.avgMapArr{j,1,i},1) size(app.avgMapArr,2)],'ThumbnailSize',size(frames{1}), 'Parent', ax);
                        myMontage = imtile(frames, 'GridSize',[size(app.avgMapArr{j,1,i},1) size(app.avgMapArr,2)],'ThumbnailSize',size(frames{1}));                        
                        mnt = imagesc(ax,myMontage);
                        clim_data = [frames{:}];
                        myClims = [min(clim_data(~isinf(clim_data)),[],'all','omitnan'), max(clim_data(~isinf(clim_data)),[],'all','omitnan')];
                    else
                        frames = cat(3, app.avgMapArr{j,:,i});
%                         mnt = montage(frames, 'Size',[1 size(app.avgMapArr,2)],'ThumbnailSize',[nan,nan], 'Parent', ax);
                        myMontage = imtile(frames, 'GridSize',[1 size(app.avgMapArr,2)],'ThumbnailSize',[size(frames,1) size(frames,2)]);
                        mnt = imagesc(ax,myMontage);
                        myClims = [min(frames(~isinf(frames)), [],'all'), max(frames(~isinf(frames)),[],'all')];
                    end

                    mnt.Tag = 'myImg';
                    mnt.AlphaData = ~isnan(mnt.CData); % Make NaNs transparent.
                    
                    if ~any(isnan(myClims(:))) && myClims(2) > myClims(1)
                        set(ax, 'CLim', myClims);
                    end
                    set(ax, 'ColorMap', colormap('jet'),...
                        'Color', 'k', ...
                        'Visible', 'on',...
                        'XTick',[],...
                        'YTick',[],...
                        'DataAspectRatio',[1 1 1]);
                    
                    if b_is3D
                        % Put group name on axis title and events on YLabel:
                        set(ax.Title,'String', app.gNames{j},'Interpreter', 'none');
                        disp('Adding event names to Ylabel');
                        yPos = (ax.YLim(2)+.5)/size(app.avgMapArr{j,1,i},1);
                        ax.YTick = linspace(yPos/2,ax.YLim(2)-(yPos/2), size(app.avgMapArr{j,1,i},1));
                        ax.YTickLabel = app.StatsObj.list_of_events;
                    else
                        % Put group names on YLabel
                        set(ax.YLabel,'String', app.gNames{j},'Interpreter', 'none');
                    end
                    % Set Ylabels with 
                    if j == size(app.avgMapArr,1)
                        set(ax.XLabel, 'String', 'Acquisition','Interpreter', 'none');
                    end
                    ax.Tag = 'myAx';
                    colorbar(ax, 'Location', 'eastoutside');
                end
                sgtitle(f,app.StatsObj.obs_list{i}, 'Interpreter','none');
                f.Visible = 'on';
                linkaxes(findobj(f, 'Tag', 'myAx'),'xy');
                set(f, 'InvertHardCopy', 'off');
            end
            % Link properties of axis
            h = findall(0, 'Tag', 'myAx');
            app.hLink = linkprop([app.hiddenAx; h], 'Colormap');
            disp('Done')
            % Plot extra figure with mask           
            if any(arrayfun(@(x) ~isempty(x.warpMskArr), app.mskInfo))
                app.hMskFig = figure("Name",'Masks', 'NumberTitle',"off");
                
                if length(app.mskInfo) == 1
                    s = axes(app.hMskFig);
                    imagesc(s,app.mskInfo.warpMskArr); axis image off; colormap(s, app.image_info.cmap);
                else
                    for ii = 1:length(app.mskInfo)
                        s = subplot(1,2,ii, 'Parent',app.hMskFig);
                        imagesc(s,app.mskInfo(ii).warpMskArr); axis image off; colormap(s, app.image_info.cmap);
                        title(s, app.gNames{ii});
                    end
                end
            end
        end
        function DoNothing(~,~)
            % EMPTY
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, StatsObj)
            % Create a hidden figure. This will enable the communication
            set(app.UIFigure, 'Visible', 'off'); movegui(app.UIFigure, 'northwest'); set(app.UIFigure, 'Visible', 'on');
            % with the "imagApps_Opts.mlapp":
            app.hiddenFig = figure('Visible', 'off','Tag', 'hiddenFig');
            % Instantiate variable:
            app.StatsObj = StatsObj;
            app.dataArr = app.StatsObj.dataArr;
            % Validate if the data has Y,X dimensions:
            disp('Checking data requirements...');
            idx = false(length(app.StatsObj.list_of_objs),4);
            for i = 1:length(app.StatsObj.list_of_objs)
                % Check if data has "dim_names" variable
                idx(i,1) = isprop(app.StatsObj.MfileArr{i}, 'dim_names');
                % Check if the data is a 2D matrix (i.e. an image):
                if idx(i,1)
                    idx(i,2) = all(ismember(upper(app.StatsObj.MfileArr{i}.dim_names), upper({'Y', 'X'})));
                end
                % Check if "refPt" coordinates exist:
                idx(i,3) = isprop(app.StatsObj.MfileArr{i}, 'refPt');
                % Check if "pxPermm" exists:
                idx(i,4) = isprop(app.StatsObj.MfileArr{i}, 'pxPermm') || ( app.StatsObj.MfileArr{i}.pxPermm == 0 );
            end
            % Show alert if any of the above checks failed:
            if ~any(idx,'all')
                % disp uialert:
                msg = {'The following recordings are invalid (Name : reason):'};
                for i = size(idx,1)
                    indx = find(~idx(i,:), 1, 'first');
                    switch indx
                        case 1
                            reason = 'dim_names is missing';
                        case 2
                            reason = 'data has is not an image with dimensions Y,X';
                        case 3
                            reason = 'Reference point is missing';
                        case 4
                            reason = 'Pixel ratio is missing';
                        otherwise
                            reason = 'unknown reason';
                    end
                    if indx
                        msg = vertcat(msg, {[strjoin({app.dataArr(i).groupID app.dataArr(i).SubjectID, app.dataArr(i).AcquisitionID}, '--') ': ' reason]});
                    end
                end
                
                app.UIFigure.Visible = 'on';
                uialert(app.UIFigure, msg, 'Data validation failed!', 'CloseFcn',@app.CloseRequestFcn)
                disp('Done');
            end
            % Check if the input data has events:
            if ( app.StatsObj.inputFeatures.b_hasValidEvent   && ~strcmp('EYXO', strjoin(app.StatsObj.inputFeatures.dim_names,'')) )
                disp('permuting maps.')
                for ii = 1:length(app.dataArr)
                    % Force data to be in the order 'E','Y','X'',O'. This should never happen, but just in case...
                    [~,permIndx] = ismember({'Y','X','E','O'},app.StatsObj.inputFeatures.dim_names);
                    app.dataArr(ii).data = cellfun(@(x) permute(x,permIndx), app.dataArr(ii).data, 'UniformOutput',false);
                end
            end
            % Get group names:
            app.gNames = unique(app.StatsObj.list_of_groups);
            % Create axis on hidden figure:
            app.hiddenAx = axes(app.hiddenFig);
            app.hiddenAx.Colormap = app.image_info.cmap;
            app.hiddenAx.CLim = app.image_info.clims;
            % Remove extra-figure that is created for some reason when the
            % app is opened.
            h = setdiff(findall(0,'Type', 'Figure'), [app.currFigs; app.hiddenFig;app.UIFigure]);
            if ~isempty(h)
                delete(h);
            end
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(findall(0, 'Tag', 'hiddenFig'));
            h = findall(0,'Tag', 'avgMapFig');
            if ~isempty(h)
                answer = questdlg('Keep figures open?', 'Close app', 'Yes', 'Close all', 'Cancel', 'Close all');
                if strcmpi(answer, 'yes')
                    arrayfun(@(x) set(x, 'Tag', '','CloseRequestFcn', 'closereq'), h);
                elseif strcmpi(answer, 'close all')
%                     arrayfun(@(x) set(x, 'Visible', 'off'), h);
                    delete(h);
                    % Delete logical mask figure:                    
                    delete(app.hMskFig);
                else
                    return
                end
            end
            
            
            delete(app)
        end

        % Button pushed function: CreatemapsButton
        function CreatemapsButtonPushed(app, event)
            % This callback performs the averaging of the data stored in
            % the "StatsObj" object.
            h = findall(0, 'Tag', 'avgMapFig');
            % Close currently opened figures:
            disp('Closing existing figures...')
            arrayfun(@(x) set(x, 'Visible', 'off'), h)
            delete(h)            
            % Create the average maps:
            app.calcAvgMaps;
            % Plot maps:
            app.plotMaps;
            % Update CLims:
            app.colorscaleButtonGroupSelectionChanged;
            % Enable buttons:
            app.ExportButton.Enable = 'on';
            app.SetcolormapButton.Enable = 'on';
        end

        % Button pushed function: SetcolormapButton
        function SetcolormapButtonPushed(app, event)
            % Changes the colormap and it's scale of  the currently opened
            % images.
            disp('changing colormap...')
            app.SetcolormapButton.Enable = 'off';
            if strcmpi(app.colorscaleButtonGroup.SelectedObject.Text, 'uniform')
                h = imagApps_Opts(app,'cmap', app.hiddenAx, false);
            else
                h = imagApps_Opts(app,'cmap', app.hiddenAx, true);
            end
            waitfor(h)
            app.SetcolormapButton.Enable = 'on';
        end

        % Selection changed function: colorscaleButtonGroup
        function colorscaleButtonGroupSelectionChanged(app, event)
            h = findall(0,'Tag', 'avgMapFig');
            if isempty(h)
                return
            end
            b_hasCLim = any(strcmpi('CLim', app.hLink.PropertyNames));
            if strcmpi(app.colorscaleButtonGroup.SelectedObject.Text, 'uniform')
                app.image_info.clims = [min([app.avgMapArr{:}],[],'all') max([app.avgMapArr{:}],[],'all')];
                app.image_info.clims_def = app.image_info.clims;
                if ~b_hasCLim
                    addprop(app.hLink, 'CLim')
                end
                if ~any(isnan(app.image_info.clims(:))) && app.image_info.clims(2)> app.image_info.clims(1)
                    set(app.hiddenAx, 'CLim',app.image_info.clims);% Update CLim.
                end
                
            else
                if b_hasCLim
                    removeprop(app.hLink, 'CLim');
                end
                if ~isempty(h)
                    for i = 1:length(h)
                        imgH = findobj(h(i), 'Tag', 'myImg');                        
                        for j = 1:length(imgH)
                            clims_data = imgH(j).CData(:);
                            if all(isnan(clims_data))
                                continue
                            end
                            myClims = [min(clims_data(~isinf(clims_data)),[],'all'), max(clims_data(~isinf(clims_data)),[],'all')];
                            if ~any(isnan(myClims(:))) && myClims(2)> myClims(1)
                                set(imgH(j).Parent, 'CLim',myClims);
                            end
                        end
                        
                    end
                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Exports currenlty opened figures to an image file.
            
            % CD to a path inside the project's savefolder:
            cd(app.StatsObj.list_of_objs{1}.SaveFolder);
            % These are the options to save the plots:
            fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)'; '*.tiff', 'Uncompressed TIF (*.tiff)'};
            print_dict = {'-dpng', '-djpeg', '-dtiffn'};
            [filename, Path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'data.png');
            if filename == 0
                return
            end
            [~,name,ext] = fileparts(filename);
            % Save figures:
            h = findall(0,'Tag', 'avgMapFig');
            w = waitbar(0,'Exporting figures...');
            for i = 1:length(h)
                prevState = h(i).WindowState;
                set(h(i), 'Visible', 'off', 'WindowState', 'maximized');
                print(fullfile(Path, [name '_' h(i).Name ext]), h(i), print_dict{indx},'-opengl');
                set(h(i),'Visible', 'on', 'WindowState', prevState);
                waitbar(i/length(h), w);
            end
            close(w)
            % Open folder with exported images:
            if (ispc) % For windows
                winopen(Path)
            elseif (ismac) % Macs
                system(['open ' Path]);
            else % Linux
                system(['xdg-open ' Path]);
            end
        end

        % Value changed function: UselogicalmaskButton
        function UselogicalmaskButtonValueChanged(app, event)
            % User chooses the .mat files that contains the "logical_mask"
            % variable for each group
            
            if ~app.UselogicalmaskButton.Value
                clearAll(app);
                return
            end
            % Here, we ask the User to select the .mat file name that contains the "logical_mask" variable.
            % The file should be stored in the subject folder
            p = fileparts(app.dataArr(1).MatFile.Properties.Source);
            path = p(1:strfind(p, app.dataArr(1).SubjectID) + length(app.dataArr(1).SubjectID));
            if ~isfolder(path)
                path = p;
            end
            [file,~] = uigetfile('*.mat', 'Select file containing the logical mask', fullfile(path, 'ImagingReferenceFrame.mat'));
            % Go through all subject folders and grab the logical mask:
            sArr = unique(cellfun(@getSubjectHandle, app.StatsObj.list_of_objs));
            sArr = sArr(ismember({sArr.ID}, {app.dataArr.SubjectID})); % Exclude subjects that are not in the "dataArr" structure;
            mskArr = arrayfun(@(x) getLogicalMask(x, file),sArr, 'UniformOutput',false);
            if any(cellfun(@isempty, mskArr))
                w = msgbox('Logical mask missing in one or more subject folders!','Failed to load masks');
                waitfor(w);
                clearAll(app);
                return
            end
            % Separate mask handles per group:
            gMap = containers.Map(app.gNames, 1:numel(app.gNames));
            sMap = containers.Map({app.dataArr.SubjectID}, {app.dataArr.groupID});
            gIndx = cellfun(@(x) gMap(sMap(x)),{sArr.ID});
            % Add masks and subject names to mskInfo structure:
            for ii = 1:numel(app.gNames)
                app.mskInfo(ii).mskArr = mskArr(gIndx == ii);
                app.mskInfo(ii).sNames = {sArr(gIndx == ii).ID};
            end
            w = msgbox('Masks Loaded!','Done!');
            waitfor(w);
            % Update Button String:
            app.UselogicalmaskButton.Text = 'Remove mask(s)';
            
            figure(app.UIFigure); % Show app.
            %----------------------------------------
            % Local functions
            function clearAll(app)
                % Clears the "mskInfo" struct and changes the button text:
                app.UselogicalmaskButton.Text = 'Use logical mask';
                app.UselogicalmaskButton.Value = false;
                % Erase logical mask information:
                app.mskInfo = struct('mskArr',{}, 'warpMskArr',{},'sNames',{});
                figure(app.UIFigure)
            end
            
            function sObj = getSubjectHandle(objH)
                % Gets the "Subject" handle from an object "Acquisition "
                % or "Modality"
                sObj = objH.MyParent;
                while 1
                    if ~isa(sObj, 'Subject')
                        sObj = sObj.MyParent;
                    else
                        break
                    end
                end
            end
            
            function out = getLogicalMask(sHandle, mskFileName)
                % Gets the logical mask from a .mat file mskFileName in the subject folder                
                try
                    info = load(fullfile(sHandle.SaveFolder,mskFileName), 'logical_mask');
                    out = info.logical_mask;
                catch
                    out = [];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PlotAvgMaps';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.SetcolormapButton = uibutton(app.OptionsPanel, 'push');
            app.SetcolormapButton.ButtonPushedFcn = createCallbackFcn(app, @SetcolormapButtonPushed, true);
            app.SetcolormapButton.Enable = 'off';
            app.SetcolormapButton.Tooltip = {''};
            app.SetcolormapButton.Text = {'Set'; 'colormap'};
            app.colorscaleButtonGroup = uibuttongroup(app.OptionsPanel);
            app.colorscaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @colorscaleButtonGroupSelectionChanged, true);
            app.colorscaleButtonGroup.Tooltip = {'Set to Uniform to keep same colorscale across figures.'};
            app.colorscaleButtonGroup.Title = 'color scale';
            app.AutoButton_2 = uiradiobutton(app.colorscaleButtonGroup);
            app.AutoButton_2.Text = 'Auto';
            app.AutoButton_2.Value = true;
            app.UniformButton = uiradiobutton(app.colorscaleButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.SpatFiltsigmaEditFieldLabel = uilabel(app.OptionsPanel);
            app.SpatFiltsigmaEditFieldLabel.Text = 'Spat. Filt. sigma';
            app.SpatFiltsigmaEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SpatFiltsigmaEditField.Limits = [0 Inf];
            app.UselogicalmaskButton = uibutton(app.OptionsPanel, 'state');
            app.UselogicalmaskButton.ValueChangedFcn = createCallbackFcn(app, @UselogicalmaskButtonValueChanged, true);
            app.UselogicalmaskButton.Tooltip = {'Select ONE mask per group!'};
            app.UselogicalmaskButton.Text = 'Use logical mask';
            app.SubtractbaselineCheckBox = uicheckbox(app.OptionsPanel);
            app.SubtractbaselineCheckBox.Text = 'Subtract baseline';
            app.CreatemapsButton = uibutton(app.UIFigure, 'push');
            app.CreatemapsButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemapsButtonPushed, true);
            app.CreatemapsButton.Tooltip = {''};
            app.CreatemapsButton.Text = {'Create'; 'maps'};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Tooltip = {''};
            app.ExportButton.Text = 'Export ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotAvgMaps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end