classdef PlotCorrMatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotCorrMatrixUIFigure     matlab.ui.Figure
        OptionsPanel               matlab.ui.container.Panel
        reorderMatrixButton        matlab.ui.control.Button
        SetbaselineButton          matlab.ui.control.Button
        ShowMatrixasDropDownLabel  matlab.ui.control.Label
        ShowMatrixasDropDown       matlab.ui.control.DropDown
        HidediagonalCheckBox       matlab.ui.control.CheckBox
        SetcolormapButton          matlab.ui.control.Button
        LockplotYaxisCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        statsObj % Handle to StatsManager object
        imgOptsApp % Handle to ImagApps_Opts app.
        matrix_struct = struct() % Structure containing the Merged Matrix from the observations listed in statsObj.
        figs = struct() % structure containing handles of matrix and plot figures.
        data = struct() % structure containing the data to be shown.
        baselineIdx = 1 % Index(es) of the data that is considered as baseline.
        labels % X/YTickLabels of matrices.
        AlphaMatrix % Logical mask to show/hide diagonal;
        color_list = jet(64); % Colormap used to label groups.
    end
    
    properties (Access = public)
        image_info = struct('clims', [-1 1],'clims_def',[-1 1], 'cmap', colormap('parula'), 'imageData',[]) % Matrix CLims and cmap.
        obsList % List of observations. Used by PlotMatrix_reorderObs app to change the order of observations in the correlation matrix.
    end
    
    methods (Access = private)
        
        function b_OK = checkInput(app)
            % This function verifies if the MATFILEs listed in statsObj contains 2-D data with
            % equal sizes.
            b_OK = false;
            
            for i = 1:length(app.statsObj.MfileArr)
                data_size = cellfun(@(x) size(x), app.statsObj.MfileArr{i}.data, 'UniformOutput',false);
                if ~isequal(data_size{:})
                    err = errordlg({'Observations has different sizes in:', app.statsObj.MfileArr{i}.Properties.Source}, 'Failed data check!');
                    waitfor(err)
                    return
                end
                if ~isequal(max(data_size{1}), length(app.statsObj.MfileArr{i}.obsID))
                    err = errordlg('Invalid Data size. The number of data per observation must match the number of observations (X by X matrix)',...
                        'Failed data check!');
                    waitfor(err)
                    return
                end
            end
            b_OK = true;
            
        end
        
        function genMatrixArray(app)
            % This function creates an array of Matrices per acquisition,
            % per group and stores it in a structure (matrix_struct).
            
            target_obs = app.statsObj.obs_list;
            groupList = unique(app.statsObj.list_of_groups);
            a = 1;
            for i = 1:numel(groupList)
                for j = 1:max([app.statsObj.stats_data{:,end}])
                    idx = [app.statsObj.stats_data{:,end}] == j & strcmp(groupList{i}, app.statsObj.stats_data(:,1)');
                    app.matrix_struct(a).groupName = groupList{i};
                    app.matrix_struct(a).IndxList = find(idx); % Index of element in app.statsObj.stats_data structure!
                    app.matrix_struct(a).acqIdx = j; % Acquisition index.
                    app.matrix_struct(a).data = createMatrix(target_obs, app.statsObj.stats_data(idx,:));
                    app.matrix_struct(a).avg = mean(app.matrix_struct(a).data,3,'omitnan');
                    app.matrix_struct(a).sem = std(app.matrix_struct(a).data,0,3,'omitnan')./sqrt(size(app.matrix_struct(a).data,3));
                    a = a+1;
                end
            end
            
            % Local function
            function out = createMatrix(obsList, stats_cell)
                % This function merges all data from obsStruct into a 3-D
                % matrix with sizes n_obsList x n_obsList x n_obsStruct.
                % Inputs
                % obsList (cell array of char) = list of observation IDs
                % stats_cell (cell array) = structure containing observation IDs
                % and their respective data.
                % Ouput
                % out (3D array) = numerical array containing data merged
                % from obsStruct.
                
                out = nan(numel(obsList),numel(obsList), size(stats_cell,1));
                for ii = 1:size(stats_cell,1)
                    for jj = 1:numel(obsList)
                        % Retrieve original observation list:
                        [idx_obs,lb] = ismember(obsList, stats_cell{ii,9});lb(lb==0) = [];
                        idx_curr_obs = strcmp(obsList{jj}, stats_cell{ii,9});
                        if ~any(idx_curr_obs)
                            continue
                        end
                        % Rearrange observation data
                        obsData = stats_cell{ii,10}(lb);
                        % Rearrange data inside each observation.
                        [~, lb2] = ismember(stats_cell{ii,9}(lb), stats_cell{ii,8});
                        out(jj,idx_obs,ii) = obsData{idx_curr_obs(lb)}(1,lb2);
                    end
                end
            end
        end
        
        function createMatrixFig(app, flag)
            % This function creates a matrix figure and its callbacks
            % Input
            % flag (str) = 'create' OR 'update'
            if strcmp(flag, 'create')
                fH = figure('Name', 'Matrices', 'NumberTitle',"off", 'MenuBar',"none", 'ToolBar',"figure",  'Units', 'normalized', ...
                    'Position',[0.1385 0.5444 0.6661 0.3889], "CloseRequestFcn",@app.DoNothing);
                app.AlphaMatrix = true(size(app.data(1).MatData(1).avg));
                % Create a merged matrix so the ImagApps_Opts app can retrieve min and max vals of avg matrices:
                MatData = [app.data.MatData];
                app.image_info.imageData = [MatData.avg];
            else
                fH = app.figs.matFig.fH;
                clf(fH)
            end
            
            groups = unique(app.statsObj.list_of_groups);
            AcqIdx_list = unique(arrayfun(@(x) x.MatData.Idx, app.data));
            a = 1;
            MatData = [app.data.MatData];
            for i = 1:numel(groups)
                for j = 1:numel(AcqIdx_list)
                    s(a) = subplot(numel(groups), numel(AcqIdx_list),a, 'Parent', fH);
                    idx = strcmp(groups{i},{app.data.groupName}) & [MatData.Idx] == AcqIdx_list(j);
                    img(a) = imagesc(s(a), MatData(idx).avg, 'AlphaData',app.AlphaMatrix);
                    img(a).Tag = 'my_matrix';
                    img(a).ButtonDownFcn = @app.pixSelect;
                    img(a).HandleVisibility = 'callback';
                    set(s(a), 'CLim', app.image_info.clims);
                    colormap(s(a), app.image_info.cmap);
                    set(s(a), 'YTick', 1:numel(app.labels), 'XTick',1:numel(app.labels),'YTickLabel', app.labels, 'XTickLabel', app.labels,...
                        'XTickLabelRotation', 90,'FontSize',8, 'TitleFontSizeMultiplier', 1.25, 'NextPlot', 'replacechildren', 'DataAspectRatio', [1 1 1]);
                    if MatData(idx).b_isBsln
                        s(j).Title.String = 'Baseline';
                    else
                        s(j).Title.String = ['Acq# ' num2str(AcqIdx_list(j))];
                    end
                    red_dot(a) = plot(s(a),1,2,'ro', 'MarkerSize',6);
                    if j == 1
                        s(a).YLabel.String = groups{i};
                        s(a).YLabel.FontSize = 10;
                        s(a).YLabel.FontWeight = 'bold';
                        s(a).YLabel.Interpreter = 'none';
                    end
                    a=a+1;
                end
            end
            cb = colorbar(s(1), 'westoutside'); cb.Position = [0.04 .6 .02 .36];
            % Link Color and axis scaling properties:
            hlink = linkprop(s, {'CLim', 'XLim', 'YLim'});
            % Save handles to fig struct:
            app.figs.matFig.fH = fH;
            app.figs.matFig.imgH = img;
            app.figs.matFig.pltH = red_dot;
            app.figs.matFig.axH = s;
            app.figs.matFig.hlink = hlink;
        end
        
        function createPlotFig(app)
            % This function creates the plot figure and sets its callbacks
            
            % The data will be plotted as requested.
            
            % Create Figure
            app.figs.tmFig.fH = figure('Name', 'Timeline', 'NumberTitle',"off", 'MenuBar',"none", 'ToolBar',"figure", 'Units', 'normalized', ...
                'Position',[0.1375 0.0861 0.6672 0.3889],"CloseRequestFcn",@app.DoNothing);
            % Create axis
            app.figs.tmFig.axH = axes('Parent', app.figs.tmFig.fH, 'Units', 'normalized', 'Position',[.05 .13 .9 .75], 'NextPlot','replacechildren', ...
                'Color',[.9 .9 .9]);            
            % Create plots
            gNames = unique({app.data.groupName});
            % Create color range:
            colors = app.color_list(linspace(1,64,numel(gNames)),:);
            hold(app.figs.tmFig.axH, 'on')
            for i = 1:numel(gNames)
                idx_g = strcmp(gNames{i}, {app.data.groupName});
                tm_data = [app.data(idx_g).TmData];
                x = 1:numel(unique([tm_data.Idx]));
                y = cellfun(@(x) x(1,2), {tm_data.avg});
                ysem = cellfun(@(x) x(1,2), {tm_data.SEM});
                % Create errorbar plot
                errPlt(i) = errorbar(app.figs.tmFig.axH, x,y,ysem, 'Tag', ['eb_' gNames{i}]);
                errPlt(i).Color = colors(i,:);
                errPlt(i).LineWidth = 1.5;
                % Create Scatterplot
                acqLen = arrayfun(@(x) size(x.data,3), tm_data);
                x_scat = arrayfun(@(a,b) repmat(a,1,b), x, acqLen, 'UniformOutput',0); x_scat = [x_scat{:}];
                y_scat = arrayfun(@(x) squeeze(x.data(1,2,:)), tm_data, 'UniformOutput',0); y_scat = vertcat(y_scat{:})';
                scatPlt(i) = scatter(x_scat, y_scat, 'Tag', ['scat_' gNames{i}], 'Parent', app.figs.tmFig.axH);
                set(scatPlt(i), 'MarkerFaceColor',colors(i,:), 'MarkerEdgeColor', 'k', 'MarkerFaceAlpha',.4);
            end
            
            hold(app.figs.tmFig.axH, 'off')
            % Store plots handles:
            %             app.figs.tmFig.errH = errPlt;
            %             app.figs.tmFig.scatH = scatPlt;
            % Set axes labels
            x_tickLabels = unique(arrayfun(@(a) num2str(a.TmData.Idx),app.data, 'UniformOutput',0));
            %             idx_list = arrayfun(@(x) x.TmData.Idx, app.data);
            bsln_idx = arrayfun(@(x) x.TmData.b_isBsln, app.data);
            bsln_idx = unique(arrayfun(@(x) x.TmData.Idx, app.data(bsln_idx)));
            x_tickLabels(bsln_idx) = {'baseline'};
            set(app.figs.tmFig.axH, 'XTick', 1:numel(x_tickLabels), 'XTickLabel', x_tickLabels, 'XLim', [0 app.figs.tmFig.axH.XLim(2) + 1]);
            xlabel(app.figs.tmFig.axH, 'Acquisition index');
            ylabel(app.figs.tmFig.axH, 'Values');
            % Set legend
            legend(app.figs.tmFig.axH, scatPlt, gNames, 'Location',"northeastoutside", 'Interpreter',"none");
            % Set title
            app.figs.tmFig.axH.Title.String = [app.figs.matFig.axH(1).YTickLabel{2} ' vs '  app.figs.matFig.axH(1).XTickLabel{1}];
            app.figs.tmFig.axH.Title.Interpreter = 'none';
            % Hide plot handles:
            arrayfun(@(x) set(x, 'HandleVisibility', 'callback'), errPlt)
            arrayfun(@(x) set(x, 'HandleVisibility', 'callback'), scatPlt)
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function pixSelect(app,src,evnt)
            % This callback shows a cursor and text over the clicked
            % pixel in a matrix.
            selPt = round(evnt.IntersectionPoint([1 2]));
            if ~src.AlphaData(selPt(2),selPt(1))
                return
            end
            % clear previous text:
            axH = src.Parent.Parent.Children;
            ht = arrayfun(@(x) findall(x.Children, 'type', 'Text'), axH, 'UniformOutput',0);
            cellfun(@(x) delete(x), ht);
            % Write x-y pair name to plot;
            text(src.Parent, selPt(1), selPt(2), [src.Parent.YTickLabel{selPt(2)} ' vs ' src.Parent.XTickLabel{selPt(1)}],...
                'HorizontalAlignment', 'left', 'VerticalAlignment','bottom', 'FontSize',7,...
                'Interpreter','none');
            % Update position of red dot
            arrayfun(@(x) set(x, 'XData', selPt(1), 'YData', selPt(2)), app.figs.matFig.pltH);
            % Update Timeline plot
            app.updatePlot;
        end
        
        function DoNothing(~,~,~)
            %%% NOTHING %%%
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function updatePlot(app)
            % This function plots the data on the TimeLine figure once the
            % user clicks on a region of the matrix.
            
            % Find X,Y location of a red dot
            x = app.figs.matFig.pltH(1).XData;
            y = app.figs.matFig.pltH(1).YData;
            % Change figure title:
            app.figs.tmFig.axH.Title.String = [app.figs.matFig.axH(1).YTickLabel{y} ' vs ' app.figs.matFig.axH(1).YTickLabel{x}];
            gNames = unique({app.data.groupName});
            
            % Update Data points in axis
            for i = 1:numel(gNames)
                idx_g = strcmp(gNames{i}, {app.data.groupName});
                tm_data = [app.data(idx_g).TmData];
                % Extract average and SEM data:
                eb_x = 1:numel(unique([tm_data.Idx]));
                eB_y = cellfun(@(a) a(y,x), {tm_data.avg});
                eB_ysem = cellfun(@(a) a(y,x), {tm_data.SEM});
                % Update values in errbar objects
                ebH = findobj(app.figs.tmFig.axH, 'Tag', ['eb_' gNames{i}]);
                set(ebH, 'XData', eb_x, 'YData', eB_y, 'YNegativeDelta', eB_ysem, 'YPositiveDelta', eB_ysem)
                % Extract data for scatter plots:
                acqLen = arrayfun(@(a) size(a.data,3), tm_data);
                x_scat = arrayfun(@(a,b) repmat(a,1,b), eb_x, acqLen, 'UniformOutput',0); x_scat = [x_scat{:}];
                y_scat = arrayfun(@(a) squeeze(a.data(y,x,:)), tm_data, 'UniformOutput',0); y_scat = vertcat(y_scat{:})';
                scatH = findobj(app.figs.tmFig.axH, 'Tag', ['scat_' gNames{i}]);
                set(scatH, 'XData', x_scat, 'YData', y_scat);
            end
            % Update Y axis limits:
            if ~app.LockplotYaxisCheckBox.Value
                ylim(app.figs.tmFig.axH,'auto')
            end
        end
        
        function updateData(app)
            % This function changes the values of data to be displayed in the matrix
            % and TimeLine figures.
            % Inputs:
            % Mat_dispType (str) = value from ShowMatrixAsDropDown menu
            
            Mat_dispType = app.ShowMatrixasDropDown.Value;
            % Clear data structure:
            app.data = struct();
            % First, average baseline data:
            groups = unique({app.matrix_struct.groupName});
            for i = 1:numel(groups)
                idx = strcmp(groups{i}, {app.matrix_struct.groupName}) & ismember([app.matrix_struct.acqIdx],app.baselineIdx);
                app.data(i).groupName = groups{i};
%                 datM = cellfun(@(x) mean(x,3), {app.matrix_struct(idx).data}, 'UniformOutput', false);
                app.data(i).MatData.data = mean(cat(4, app.matrix_struct(idx).data),4,'omitnan');
%                 app.data(i).MatData.data = mean(cat(3,datM{:}),3,'omitnan');
                app.data(i).MatData.avg = mean(app.data(i).MatData.data,3,'omitnan');
                app.data(i).MatData.SEM  = std(app.data(i).MatData.data,0,3,'omitnan')./sqrt(size(app.data(i).MatData.data,3));
                app.data(i).MatData.Idx = min(app.baselineIdx);
                app.data(i).MatData.b_isBsln = true;
                app.data(i).TmData = app.data(i).MatData; % Copy Data to TimeLine
            end
            
            % Loop over matrix_struct to finish building "data" structure:
            idx = ismember([app.matrix_struct.acqIdx], app.baselineIdx);
            tmp_mat = app.matrix_struct(~idx);
            for i = 1:length(tmp_mat)
                app.data(end+1).groupName = tmp_mat(i).groupName;
                app.data(end).MatData.data = tmp_mat(i).data;
                app.data(end).MatData.avg = tmp_mat(i).avg;
                app.data(end).MatData.SEM  = tmp_mat(i).sem;
                app.data(end).MatData.Idx = tmp_mat(i).acqIdx;
                app.data(end).MatData.b_isBsln = false;
                app.data(end).TmData = app.data(end).MatData; % Copy Data to TimeLine
            end
            
            % Perform arithmetic operation on data:
            if strcmp(Mat_dispType, 'Data')
                return
            elseif ~strcmp(Mat_dispType, 'Data')
                app.data = changeDat(app.data, Mat_dispType);
            end
            
            % Local function
            
            function out = changeDat(data,dispType)
                % Performs arithmetic operations on data
                in = [data.MatData];
                idx_bsln = [in.b_isBsln];
                gNames = unique({data.groupName});
                for ii = 1:numel(gNames)
                    idx_g = strcmp(gNames{ii}, {data.groupName});
                    if numel(idx_g)<2 % Only perform operation in data with 2 or more acquisitions per group.
                        continue
                    end
                    bsln = in(idx_bsln & idx_g);
                    if strcmp(dispType, 'Diff. from baseline')
                        in(idx_g) = arrayfun(@(x) setfield(x, 'avg', (x.avg - bsln.avg)), in(idx_g));
                    else
                        [la,lb] = sort([in(idx_g).Idx]);
                        tmp = in(idx_g); tmp = tmp(lb);
                        for jj = length(tmp):-1:2
                            tmp(jj).avg = tmp(jj).avg - tmp(jj-1).avg;
                        end
                        [~,ib] = ismember([in(idx_g).Idx],la);
                        in(idx_g) = tmp(ib);
                    end
                    disp('Finishing arithmetic op...')
                    out = arrayfun(@(x,y) setfield(x,'MatData',y), data,in);
                end
            end
            
        end
        
        
end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            % This startup function creates the Matrix and the Plot figures
            % with default parameters.
            
            app.PlotCorrMatrixUIFigure.Visible = 'off';
            app.statsObj = statsObj;
            close all
            % Update UIFigure position:
            movegui(app.PlotCorrMatrixUIFigure, 'northwest'); app.PlotCorrMatrixUIFigure.Visible = 'on';
            % Data validation
            b_OK = app.checkInput;
            if ~b_OK
                app.CloseApp;
                return
            end
            % Build Matrix arrays
            app.genMatrixArray;
            % Prepare data to be displayed:
            app.updateData
            % Create Matrix figure
            app.labels = app.statsObj.obs_list;
            app.obsList = app.labels; % Used to re-order observations in matrix.
            app.createMatrixFig('create');
            
        
            % Disable Baseline-related interface elements if there is only
            % one acquisition:
            if length(app.matrix_struct) == 1
                app.SetbaselineButton.Enable = 'off';
                app.ShowMatrixasDropDown.Enable = 'off';
            end
            % Create Plot figure
            app.createPlotFig
            % Show Matrices and plots
            app.updatePlot;
            % Reorder matrix based on Mouse Allen Brain Atlas:
            app.reorderMatrixButtonPushed('startup');
        end

        % Close request function: PlotCorrMatrixUIFigure
        function CloseApp(app, event)
            try
                delete(app.figs.matFig.fH); % delete matrix figure
                delete(app.figs.tmFig.fH); % delete time figure
                delete(app) % delete main figure
            catch
                delete(app)
            end
        end

        % Value changed function: ShowMatrixasDropDown
        function ShowMatrixasDropDownValueChanged(app, event)
            value = app.ShowMatrixasDropDown.Value;
            app.updateData;
            app.createMatrixFig('update');
            app.updatePlot;
            % Update imageData in image_info:
            imgdt = arrayfun(@(x) x.CData, app.figs.matFig.imgH, 'UniformOutput',0);
            app.image_info.imageData = [imgdt{:}];
        end

        % Value changed function: HidediagonalCheckBox
        function HidediagonalCheckBoxValueChanged(app, event)
            % This callback hides/shows the upper diagonal of the
            % correlation matrices.
            if app.HidediagonalCheckBox.Value
                for i = 1:size(app.AlphaMatrix,2)
                    app.AlphaMatrix(i,i:end) = false;
                end
            else
                app.AlphaMatrix = true(size(app.AlphaMatrix));
            end
            app.createMatrixFig('update');
        end

        % Button pushed function: SetcolormapButton
        function SetcolormapButtonPushed(app, event)
            % Launches the imagApps_Opts app to set the current image's
            % colormap.
            
            app.imgOptsApp = imagApps_Opts(app, 'cmap',[],0);
            uiwait(app.imgOptsApp.ImageOptsUIFigure);
            disp('Setting Matrix opts...');
            app.createMatrixFig('update');
            % Update YLim in time figure:
            if app.LockplotYaxisCheckBox.Value
                app.LockplotYaxisCheckBoxValueChanged
            end
        end

        % Button pushed function: reorderMatrixButton
        function reorderMatrixButtonPushed(app, event)
            % This function allows the user to reorder the observation list
            if ischar(event)
                % This condition is a workaround to reorder the observation
                % list from startup!
                
                % If the observations were created from the Mouse Allen Brain
                % Atlas, reorder them to segregate into Left and right
                % hemispheres and group by modality:
                a = load(fullfile(erase(mfilename('fullpath'),['GUI' filesep 'StatsModule' filesep 'PlotCorrMatrix']), 'GUI', 'DataViz', 'mouse_ctx_borders'), 'atlas');
                areatag_sorted = [];
                idxL = endsWith(a.atlas.areatag, '_L');
                [~,indx_mod] = sort(a.atlas.area_ClusterNames(idxL)); % Sort left areas by modality;
                areatagL = a.atlas.areatag(idxL);
                areatag_sorted =[areatag_sorted; areatagL(indx_mod)];
                [~,indx_mod] = sort(a.atlas.area_ClusterNames(~idxL)); % Sort right areas by modality;
                areatagR = a.atlas.areatag(~idxL);
                areatag_sorted =[areatag_sorted; areatagR(indx_mod)];
                % Abort reordering if there are observations that are not
                % from the Atlas:
                if ~all(ismember(app.obsList, areatag_sorted))
                    return
                end
                app.obsList = areatag_sorted(ismember(areatag_sorted, app.obsList));
            else
                reOrderObs_dlg = PlotMatrix_reorderObs(app);
                uiwait(reOrderObs_dlg.UIFigure)
            end
            disp('Re-ordering Matrices...')
            % Find re-ordered indices
            [~, lb] = ismember(app.obsList, app.labels);
            disp('Updating matrices...')
            % Change X,Y order of matrices in app.data
            for i = 1:length(app.matrix_struct)
                app.matrix_struct(i).data = app.matrix_struct(i).data(lb,lb,:);
                app.matrix_struct(i).avg = app.matrix_struct(i).avg(lb,lb);
                app.matrix_struct(i).sem = app.matrix_struct(i).sem(lb,lb);
            end
            % Update labels
            app.labels = app.obsList;
            disp('Updating figures...')
            % Update Data and figures
            app.updateData;
            app.createMatrixFig('update')
            app.updatePlot;
            % Update show/hide diagonal:
            app.HidediagonalCheckBoxValueChanged;
        end

        % Button pushed function: SetbaselineButton
        function SetbaselineButtonPushed(app, event)
            % Create uifigure
            uf = uifigure('Name', 'Set baseline', 'Position', [0 0 257 79], 'Visible', 'off'); movegui(uf, 'center');
            uilabel('Parent', uf, 'Text','From ', 'Position',[10 50 50 20]);
            up_field = uieditfield(uf,'numeric','Value',1, 'ValueDisplayFormat','%.0f',"Limits",[1 max([app.matrix_struct.acqIdx])],...
                'Position',[140 50 50 20], 'Tag', 'upperLim');
            dwn_field = uieditfield(uf,'numeric','Value',1, 'ValueDisplayFormat','%.0f', "Limits",[1 up_field.Limits(2)], ...
                'Position',[45 50 50 20], 'Tag', 'lowerLim');
            uilabel('Parent', uf, 'Text',' to ', 'Position',[110 50 50 20]);
            uibutton('Parent', uf, 'Text', 'Save and Exit', 'Position',[10 15 100 20], "ButtonPushedFcn",@checkLims);
            uf.Visible = 'on';
            waitfor(uf)
            % Update data matrix and plots
            disp('Updating data matrix...')
            app.updateData;
            delete(app.figs.matFig.fH)
            app.createMatrixFig('create')
            delete(app.figs.tmFig.fH);
            app.createPlotFig;
            %             app.updatePlot;
            % Update show/hide diagonal:
            app.HidediagonalCheckBoxValueChanged;
            % Local callbacks
            
            function checkLims(src,~)
                %  This function changes the limits of the uispinner objects
                %  to limit the choice of baseline acquisitions to the user.
                %  For example, the user cannot choose all data as baseline.
                %
                disp('Cheking limits...')
                lb = findobj(src.Parent, 'Tag', 'lowerLim');
                ub = findobj(src.Parent, 'Tag', 'upperLim');
                %  Keep values in a consecutive range:
                if lb.Value > ub.Value
                    errordlg(['Lower value cannot exceed ' num2str(ub.Value) '!'], 'Invalid input');
                    lb.Value = lb.Limits(1); drawnow
                elseif ub.Value < lb.Value
                    errordlg(['Upper value cannot be less than ' num2str(lb.Value) '!'], 'Invalid input');
                    ub.Value = ub.Limits(2); drawnow
                end
                %  Avoid selecting all matrices as baseline.
                if lb.Value == min([app.matrix_struct.acqIdx]) && ub.Value == max([app.matrix_struct.acqIdx])
                    errordlg('Sorry, cannot select all acquisitions as baseline!', 'Invalid input');
                    lb.Value = lb.Limits(1);
                    ub.Value = ub.Limits(2)-1;
                else
                    app.baselineIdx = [lb.Value:ub.Value];
                    delete(src.Parent)
                end
                disp('Limits changed!')
            end
            
        end

        % Value changed function: LockplotYaxisCheckBox
        function LockplotYaxisCheckBoxValueChanged(app, event)
                % This callback toggles the Y axis limits in the plot on the PlotFig.
                if app.LockplotYaxisCheckBox.Value
                    % Lock the plot`s Y axis to the same values as the CLims in
                    % the matrix plots:
                    set(app.figs.tmFig.axH, 'YLim', app.figs.matFig.axH(1).CLim,'YLimMode', 'manual')
                else
                    set(app.figs.tmFig.axH, 'YLimMode', 'auto');
                end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotCorrMatrixUIFigure = uifigure('Visible', 'off');
            app.PlotCorrMatrixUIFigure.Name = 'PlotCorrMatrix';
            app.PlotCorrMatrixUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.OptionsPanel = uipanel(app.PlotCorrMatrixUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.reorderMatrixButton = uibutton(app.OptionsPanel, 'push');
            app.reorderMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @reorderMatrixButtonPushed, true);
            app.reorderMatrixButton.Text = {'re-order'; 'Matrix'};
            app.SetbaselineButton = uibutton(app.OptionsPanel, 'push');
            app.SetbaselineButton.ButtonPushedFcn = createCallbackFcn(app, @SetbaselineButtonPushed, true);
            app.SetbaselineButton.Text = {'Set '; 'baseline'};
            app.ShowMatrixasDropDownLabel = uilabel(app.OptionsPanel);
            app.ShowMatrixasDropDownLabel.Text = 'Show Matrix as:';
            app.ShowMatrixasDropDown = uidropdown(app.OptionsPanel);
            app.ShowMatrixasDropDown.Items = {'Data', 'Diff. from baseline', 'Diff. from previous'};
            app.ShowMatrixasDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowMatrixasDropDownValueChanged, true);
            app.ShowMatrixasDropDown.Value = 'Data';
            app.HidediagonalCheckBox = uicheckbox(app.OptionsPanel);
            app.HidediagonalCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidediagonalCheckBoxValueChanged, true);
            app.HidediagonalCheckBox.Text = 'Hide diagonal';
            app.SetcolormapButton = uibutton(app.OptionsPanel, 'push');
            app.SetcolormapButton.ButtonPushedFcn = createCallbackFcn(app, @SetcolormapButtonPushed, true);
            app.SetcolormapButton.Text = {'Set'; 'colormap'};
            app.LockplotYaxisCheckBox = uicheckbox(app.OptionsPanel);
            app.LockplotYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockplotYaxisCheckBoxValueChanged, true);
            app.LockplotYaxisCheckBox.Text = 'Lock plot Y axis';
            % Show the figure after all components are created
            app.PlotCorrMatrixUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotCorrMatrix(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotCorrMatrixUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotCorrMatrixUIFigure)
        end
    end
end