classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FiguresMenu                 matlab.ui.container.Menu
        SavetoFIGMenu               matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        DataMenu                    matlab.ui.container.Menu
        SelectMenu                  matlab.ui.container.Menu
        RunhypothesistestsMenu      matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        DatainmainfigurePanel       matlab.ui.container.Panel
        DatainmainfigureDropDown    matlab.ui.control.DropDown
        HideaxisoptionsButton       matlab.ui.control.StateButton
        AxisoptionsPanel            matlab.ui.container.Panel
        YlabelEditFieldLabel        matlab.ui.control.Label
        YlabelEditField             matlab.ui.control.EditField
        YaxisoptionsButtonGroup     matlab.ui.container.ButtonGroup
        AutoButton                  matlab.ui.control.RadioButton
        UniformButton               matlab.ui.control.RadioButton
        LinearCheckBox              matlab.ui.control.CheckBox
        LogCheckBox                 matlab.ui.control.CheckBox
        FixedButton                 matlab.ui.control.RadioButton
        minEditFieldLabel           matlab.ui.control.Label
        minEditField                matlab.ui.control.NumericEditField
        maxEditFieldLabel           matlab.ui.control.Label
        maxEditField                matlab.ui.control.NumericEditField
        GroupplottypeDropDownLabel  matlab.ui.control.Label
        GroupplottypeDropDown       matlab.ui.control.DropDown
        XlabelEditFieldLabel        matlab.ui.control.Label
        XlabelEditField             matlab.ui.control.EditField
        XaxisoptionsButtonGroup     matlab.ui.container.ButtonGroup
        JittercategButton           matlab.ui.control.RadioButton
        NojittercategButton         matlab.ui.control.RadioButton
        ContinuousButton            matlab.ui.control.RadioButton
        XTicklabelpositionLabel     matlab.ui.control.Label
        XTickLabelEditField         matlab.ui.control.EditField
        OkButton                    matlab.ui.control.Button
        maxEditFieldLabel_2         matlab.ui.control.Label
        maxXEditField               matlab.ui.control.NumericEditField
        minEditField_2Label         matlab.ui.control.Label
        minXEditField               matlab.ui.control.NumericEditField
        VariationtypeButtonGroup    matlab.ui.container.ButtonGroup
        StandarddeviationSDButton   matlab.ui.control.RadioButton
        StandardErrorofthemeanSEMButton  matlab.ui.control.RadioButton
        ConfidenceintervalCIButton  matlab.ui.control.RadioButton
        TimevectorstyleButtonGroup  matlab.ui.container.ButtonGroup
        ShadedButton                matlab.ui.control.RadioButton
        LinesButton                 matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFigs % Handle to Summary figures.
        mainAx % Handle to axis in main figure;
        scatData % Structure containing the metaData of each item from the scatter plots in the summary figures.
        mainAxMetaData % MetaData for each scatter point in the main figure;
        dcm_obj % DataCursor mode object for datatips in Main Figure.
        cmp % current mouse position. This property is updated by the WindowButtonMotionFcn of the main fig.
        errMeasure % Measurement of error to be represented in the error bars (i.e. 'SD', 'SEM', '95%CI').
        hlink  % linkprop object handle.
        varType = 'std' % name of variation measure to be plotted using error bars.
        summaryAxH % List of axis handles from the summary figures.
        b_isTimeVector % Boolean to indicate if the input data is a time vector.
        Freq % Sampling frequency. This property is used by time vector plots ONLY.
        b_hasEvents  = false% boolean to indicate if the input data has "E"vent dimension.
        elYlim % event Listener for event bar resizing after changes in YLim.
        xOpts = struct('XJitter',false,'isCategorical',true, 'isBoxPlot',false);
        reportGUI_handle % Handle to StatsReportGUI app.
        xTickPositionList % List of XTick indices to position the labels
        xAxLims % Limits of the x axis
    end
    
    properties (Access = public)
        data % This is the data that from StatsObj that was repackaged for plotting by the "Data_selector" app.
    end
    
    methods (Access = private)
        
        function createSummaryFigs(app)
            % This method creates figures with line + scatter plots. A maximum of 8 plots
            % is allowed per figure.
            disp('Creating figures...')
            % Create progress bar:
            w = [];
            if strcmp(app.UIFigure.Visible, 'on')
                w = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', 'Deleting existing figures...', 'Indeterminate','on');
            end
            % Delete existing figures:
            delete(app.summaryFigs)
            % Get Timing of event trigger for plotting, if applicable:
            if ( app.b_hasEvents )
                preEvTm = mean(cellfun(@(x) x.preEventTime_sec, app.statsObj.MfileArr));
            end
            % Create a plot for each observation:
            plotTitleList = {app.data.plotTitle};
            nFigs = floor(numel(plotTitleList)/8) + 1;
            
            if ~isempty(w)
                w.Message = 'Creating figures...';
            end
            % Preallocate figure and axis handle arrays:
            app.summaryFigs = gobjects(1,nFigs);
            app.summaryAxH = gobjects(1,numel(plotTitleList));
            nAxPerFig = [repmat(8,1,nFigs-1), rem(numel(plotTitleList),8)];
            axIndx = [0 cumsum(nAxPerFig)];
            for ii = 1:nFigs
                % Create figure:
                app.summaryFigs(ii) = figure('Name', ['Summary figure #' num2str(ii)], 'NumberTitle', 'off',...
                    'Position',[0 0 600 700],'Visible','off', 'CreateFcn',{@movegui, 'northeast'}, 'CloseRequestFcn',@app.DoNothing);
                % Create axes:
                app.summaryAxH(axIndx(ii)+1:axIndx(ii+1)) = arrayfun(@(x) subplot(4,2,x,"replace",'Parent',app.summaryFigs(ii)),1:nAxPerFig(ii));
            end
            [xTickPos,xLabIndx] = sort(app.xTickPositionList);
            for ii = 1:length(app.summaryAxH)
                % Create axis:
                app.summaryAxH(ii).Tag = plotTitleList{ii};
                if app.b_isTimeVector
                    % Plot time vector:
                    switch app.TimevectorstyleButtonGroup.SelectedObject.Text
                        case 'Shaded'
                            app.summaryAxH(ii) = plotTimeVector(app.data(ii).data,app.varType,'plotType','shaded',...
                                'group',app.data(ii).groupIndx,'color',app.data(ii).gColor,'axHandle',app.summaryAxH(ii),...
                                'dataTip',app.data(ii).dataTip, 'Freq', app.Freq);
                        case 'Lines'
                            app.summaryAxH(ii) = plotTimeVector(app.data(ii).data,app.varType,'plotType','line',...
                                'group',app.data(ii).groupIndx,'color',app.data(ii).gColor,'axHandle',app.summaryAxH(ii),...
                                'dataTip',app.data(ii).dataTip, 'Freq', app.Freq);
                    end
                    % Plot Event bar:
                    if ( app.b_hasEvents   && ~isempty(app.Freq) )
                        l = line(app.summaryAxH(ii), [preEvTm preEvTm], [app.summaryAxH(ii).YLim(1), app.summaryAxH(ii).YLim(2)], 'Color', 'k', 'Tag', 'evBar');
                        uistack(l,'bottom'); % Push event bar to the bottom.
                    end
                else
                    % Plot scalar data
                    
                    % Reshape data to a column vector:
                    xIn = repelem(app.xTickPositionList',size(app.data(ii).data,1),1);xIn = xIn(:); % X axis index
                    gIn = repmat(app.data(ii).groupIndx,1,numel(app.data(1).xLabel)); gIn = gIn(:); % Group index
                    yIn = app.data(ii).data(:); % Data
                    dataTip = reshape(app.data(ii).dataTip,[],1); % Data tips.
                    % Plot the data in the current axis:
                    
                    app.summaryAxH(ii) = stripchart(xIn,yIn,app.varType,'group',gIn, 'axHandle',app.summaryAxH(ii),'color',app.data(ii).gColor,...
                        'Xjitter', app.xOpts.XJitter,'XGroup',app.xOpts.isCategorical, 'Boxplot', app.xOpts.isBoxPlot,'dataTip', dataTip);
                    % Set Axis properties:
                    set(app.summaryAxH(ii),'XLim', app.xAxLims, 'XTick',xTickPos, 'XTickLabel', app.data(1).xLabel(xLabIndx), ...
                        'TickLabelInterpreter', 'none', 'Color',[.95 .95 .95], 'XTickLabelRotation', -23);
                end
                % Add Title:
                title(app.summaryAxH(ii),plotTitleList{ii}, 'Interpreter',"none");
            end
            
            % Link axes properties:
            app.hlink = linkprop(app.summaryAxH,{'XLim','YLimMode', 'YScale'});
            % Add event listener to resize event bar, if applicable:
            if ( app.b_hasEvents )
                app.elYlim = addlistener([app.summaryAxH, app.mainAx], 'YLim', 'PostSet',@app.updateEvBars);
            end
            % Show figures:
            %             arrayfun(@(x) movegui(x,'northeast'),app.summaryFigs);
            arrayfun(@(x) set(x, 'Visible', 'on'), app.summaryFigs);
            % Clear progress bar:
            delete(w);
            
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(~,~,~)
            % EMPTY!%
        end
        
        function txt = showDataTip(app,~,evnt)
            % Callback to show object's information in datatip.
            txt = '';
            % Identify the type of the selected graphical object:
            switch class(evnt.Target)
                case 'matlab.graphics.chart.primitive.Scatter'
                    gIndx = cellfun(@str2double, regexp(evnt.Target.Tag,'(?<=scatG)\d+(?=X*\d*)', 'match')); % Group index
                    objType = 'scatter';
                case {'matlab.graphics.primitive.Line','matlab.graphics.chart.primitive.Line' ,'matlab.graphics.primitive.Patch'}
                    gIndx = cellfun(@str2double, regexp(evnt.Target.Parent.Tag,'(?<=(err|timeVec)G)\d+(?=X*\d*)', 'match')); % Group index
                    if isempty(gIndx)
                        objType = 'unknown';
                    else
                        childNames = arrayfun(@(x) x.Tag, evnt.Target.Parent.Children, 'UniformOutput',false);
                        if any(contains(childNames,'Box', 'IgnoreCase',true))
                            objType = 'boxplot';
                        elseif any(contains(childNames,'CapLine', 'IgnoreCase',true))
                            objType = 'errorbar';
                        else
                            objType = 'timeplot';
                        end
                    end
                otherwise
                    objType = 'unknown';
            end
            
            % Get Group Name string:
            Gstr = sprintf('Group ID: %s\n',app.data(1).gName{gIndx});
            % Create Data tips:
            switch objType
                case 'scatter'
                    % Data tip for scatter plot
                    indx = find(evnt.Target.YData == evnt.Position(2));
                    txt = [sprintf('X: %s\nValue: %0.5f\n',evnt.Target.Parent.XTickLabel{find(evnt.Target.Parent.XTick == evnt.Target.UserData.Xpos(indx),1,'first')},...
                        evnt.Position(2)), evnt.Target.UserData.DataTip{indx}];
                case 'errorbar'
                    % Data tip for error bar.
                    txt = [Gstr, sprintf(['Mean: %0.5f\n' upper(app.varType) ': \\pm%0.6f'], evnt.Target.Parent.UserData.Mean, evnt.Target.Parent.UserData.(app.varType))];
                case 'boxplot'
                    % Data tip for Boxplot:
                    fn = flipud(fieldnames(evnt.Target.Parent.UserData));% Display the field names and values from the boxplot UserData structure:
                    txt = [Gstr,txt];
                    for ii = 1:length(fn)
                        if strcmp(fn{ii},'Xpos')
                            val = evnt.Target.Parent.Parent.XTickLabel{evnt.Target.Parent.UserData.Xpos};
                            txt = [txt,sprintf('X: %s\n',val)];
                        else
                            val = evnt.Target.Parent.UserData.(fn{ii});
                            txt = [txt, sprintf('%s: %0.5f\n', fn{ii},val)];
                        end
                        
                    end
                case 'timeplot'
                    % Data tip for time vector plot:
                    % Set Time unit and precision:
                    if app.Freq
                        xPos = round(evnt.Position(1)*app.Freq);
                        timeTag = 'second(s)';
                        timePrecision = '%0.3f';
                        
                    else
                        xPos = evnt.Position(1);
                        timeTag = '(frame)';
                        timePrecision = '%i';
                    end
                    % Get event bar info:
                    evTime = 0;
                    evH = findobj(app.mainAx, 'Tag', 'evBar'); % Get event Bar
                    if ( ~isempty(evH) )
                        evTime = evH.XData(1);
                    end
                    % Return Event bar info:
                    if strcmp(evnt.Target.Tag, 'evBar')
                        txt = sprintf(['Event Trigger at ' timePrecision ' ' timeTag], evnt.Position(1));
                        return
                    end
                    
                    % Print group Stats:
                    avg = evnt.Target.Parent.UserData.Mean(xPos);
                    varDat = evnt.Target.Parent.UserData.(lower(app.varType))(xPos);
                    % Print group info:
                    if app.b_hasEvents
                        txt = [txt, sprintf(['X: ' timePrecision ' ' timeTag '\nTime from Event: ' timePrecision ' ' timeTag...
                            '\nMean: %0.5f\n' upper(app.varType) ': \\pm%0.6f'],...
                            evnt.Position(1), evnt.Position(1) - evTime, avg, varDat)];
                    else
                        txt = [txt, sprintf(['X: ' timePrecision ' ' timeTag '\nMean: %0.5f\n' upper(app.varType) ': \\pm%0.6f'],...
                            evnt.Position(1), avg, varDat)];
                    end
                    % Add more information if it is a data line:
                    if startsWith(evnt.Target.Tag,'dataLn', 'IgnoreCase',true)
                        % Print Y value from the selected point and add MetaData info:
                        txt = [txt, sprintf('\nValue: %0.5f\n%s', evnt.Position(2), evnt.Target.UserData.DataTip{:})];
                    else
                        % Get Group ID:
                        txt = [Gstr, txt];
                    end
                otherwise
                    % Generic data tip. This should never be reached.
                    txt = sprintf('X: %d\nY: %0.5f', evnt.Position(1), evnt.Position(2));
            end
            txt = strrep(txt, '_','\_'); % To keep Tex interpreter and avoid subscripting text.
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
        
        
        function addLegend(app, ax, location)
            % ADDLEGEND adds a legend to the main axis or to a figure to be
            % saved. The function will change the string inside the legend
            % elements depending on the variation measure ('std','sem' or
            % 'ci') chosen by the User. If a boxplot is selected, just the
            % group name is displayed.
            % Inputs:
            %   ax: axis to plot the legend
            %   location: 'Location' property of the legend object. See
            %   "legend" documentation for options.
            b_hasErrorBar = ( strcmpi(app.GroupplottypeDropDown.Enable,'on') &  contains(app.GroupplottypeDropDown.Value, 'error', "IgnoreCase",true) ) | ...
                ( app.b_isTimeVector & strcmpi(app.TimevectorstyleButtonGroup.SelectedObject.Text, 'Shaded') );
            
            if b_hasErrorBar
                % Get the Error bar name and append to the group name:
                errBarName = regexp(app.VariationtypeButtonGroup.SelectedObject.Text, '(?<=\()\w*\)', 'match', 'once');
                gNames = cellfun(@(x) [x ' (\pm' errBarName], app.data(1).gName, 'UniformOutput', false);
            else
                % Just get the group name:
                gNames = app.data(1).gName;
            end
            
            childNames = arrayfun(@(x) x.Tag, ax.Children, 'UniformOutput',false);
            hSample = cell(size(gNames));
            for ii = 1:numel(gNames)
                if ( app.b_isTimeVector )
                    hSample{ii} = ax.Children(strcmp(childNames, ['timeVecG' num2str(ii)]));
                else
                    hSample{ii} = ax.Children(strcmp(childNames, ['scatG' num2str(ii) 'X1']));
                end
            end
            legend(ax, vertcat(hSample{:}), gNames(~cellfun(@isempty, hSample)),'AutoUpdate',"off", 'Location',location);
        end
        
        
        function updateEvBars(app,~,~)
            % This method changes the size of Event Bar to fit the
            % current axis YLim.
            myAxes = [app.summaryAxH, app.mainAx];
            myPatch = arrayfun(@(x) findobj(x, 'Tag', 'evBar'), myAxes, 'UniformOutput', false);
            idxEmpty = cellfun(@isempty, myPatch);
            if all(idxEmpty)
                return
            end
            flip = false;
            if strcmp(app.mainAx.YLimMode, 'auto')
                flip = true;
                arrayfun(@(x) set(x, 'YLimMode', 'manual'), myAxes(~idxEmpty))
            end
            arrayfun(@(x,y) set(x, 'YData', y.YLim), [myPatch{~idxEmpty}],myAxes(~idxEmpty));
            if flip
                arrayfun(@(x) set(x, 'YLimMode', 'auto'), myAxes(~idxEmpty))
            end
        end
        
        function setXTickLabelEditField(app)
            % Sets the content and GUI "Enable" property of the
            % XTickLabelList edit field depending on the data properties
            if app.b_isTimeVector
                %Disable the tool for time vector:
                app.XTickLabelEditField.Enable = 'off';
                app.OkButton.Enable = 'off';
                return
            end
            if isempty(app.XTickLabelEditField.Value) || ~isequaln(app.xTickPositionList, length(app.data(1).xLabel))
                set(app.XTickLabelEditField, 'Value',strjoin(arrayfun(@num2str,1:length(app.data(1).xLabel),'UniformOutput',false),' ,'));
                % Reset tick position index list:
                app.xTickPositionList = 1:length(app.data(1).xLabel);                
            else
                set(app.XTickLabelEditField, 'Value',strjoin(arrayfun(@num2str,app.xTickPositionList),'UniformOutput',false),' ,');                
            end
            % Set Min and max fields:
            app.xAxLims = [min(app.xTickPositionList)-1, max(app.xTickPositionList)+1];
            app.minXEditField.Value = app.xAxLims(1);
            app.maxXEditField.Value = app.xAxLims(2);
            app.XTickLabelEditField.Enable = 'on';
            app.OkButton.Enable = 'on';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj, varargin)
            
            % Check if input data from statsObj are either scalar or time-vector (i.e. one data point per acquisition per subject):
            app.UIFigure.Visible = 'off';
            if ~ismember(statsObj.inputFeatures.dataType, {'scalar', 'time-vector'})
                w = errordlg('This app does not support this type of data.', 'Invalid input', "modal");
                waitfor(w);
                app.UIFigure.Visible = 'on';
                app.UIFigureCloseRequest;
                return
            end
            app.statsObj = statsObj;
%             if ~strcmpi(app.statsObj.selMeasure,'data')
%                 Ylabel = app.statsObj.selMeasure;
%             else
                Ylabel = '';
%             end
            % Get optional input with Xdata, GroupBy and PlotBy dimension
            % names:
            if nargin == 3
                % Get the cell array of chars containing the names of the
                % dimensions to plot.
                XGPnames = varargin{:};
            elseif nargin == 4
                XGPnames = varargin{1};
                Ylabel = varargin{2}; % Display custom Ylabel at startup
            else
                XGPnames= {};
            end
            
            % Check for Statistics toolbox and unlock extra-options:
            if app.statsObj.b_hasStatsToolbox
                app.ConfidenceintervalCIButton.Enable = 'on';
                app.GroupplottypeDropDown.Enable = 'on';
            end
            % Enable "statistics" option for scalar data:
            if strcmpi(app.statsObj.inputFeatures.dataType, 'scalar')
                app.RunhypothesistestsMenu.Enable = 'on';
            else
                app.RunhypothesistestsMenu.Enable = 'off';
            end
            movegui(app.UIFigure, 'northwest');
            % Prepare app for time-vector data:
            if strcmpi(app.statsObj.inputFeatures.dataType, 'time-vector')
                % Check if all sampling frequencies are the
                % same:
                app.b_isTimeVector = true;
                app.Freq = unique(cellfun(@(x) x.Freq, app.statsObj.MfileArr));
                if numel(app.Freq)>1
                    w = errordlg('The time-series data must have the same sampling frequencies.', 'Invalid input', "modal");
                    waitfor(w);
                    app.UIFigure.Visible = 'on';
                    app.UIFigureCloseRequest;
                    return
                end
                % Change GUI options:
                if ~isempty(app.Freq)
                    app.XlabelEditField.Value = 'Time (seconds)';
                else
                    app.XlabelEditField.Value = 'Time (frames)';
                end
                % Disable X-axis options:
                app.XlabelEditField.Enable = 'off';
                app.XaxisoptionsButtonGroup.Visible = 'off';
                app.GroupplottypeDropDown.Enable = 'off';
            else
                % Hide Time-vector options:
                app.GridLayout.RowHeight{3} = 0;
                app.UIFigure.Position([2 4]) = [app.UIFigure.Position(2) + 100, app.UIFigure.Position(4) - 100];
            end
            % Check if data has events:
            if ismember('E', upper(app.statsObj.inputFeatures.dim_names))
                app.b_hasEvents = true;
            end
            % Hide axis options and variation type panel
            app.ShowHideaxisoptions_callback;
            % Get data from "Data_selector" app with default dimension order (acquisition on the x axis, groups on the y and ROIs per plot):
            ds = Data_selector(statsObj, app,false, XGPnames);
            waitfor(ds);
            % Update XTick list values in X axis options:
            app.setXTickLabelEditField;
            % Populate dropdown list:
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};
            % Create main figure:
            app.mainFig = figure('Name', ['Main - ' app.DatainmainfigureDropDown.Value], 'NumberTitle','off',...
                'Position', [0 0 880 420],'Visible', 'off', 'CloseRequestFcn', @app.DoNothing);
            app.mainAx = axes('Parent', app.mainFig, 'Position',[.1 .22 .82 .7], 'Units',"normalized");
            % Create summary figures:
            app.createSummaryFigs;
            % Show Figures and app:
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            % Set some axis properties of mainAx:
            app.mainAx.Title.Interpreter = 'none';
            if isempty(Ylabel)
                app.mainAx.YLabel.String = app.YlabelEditField.Value;
            else
                app.mainAx.YLabel.String = Ylabel;
                app.YlabelEditField.Value = Ylabel;
            end
            app.mainAx.XLabel.String = app.XlabelEditField.Value;
            %
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
            % Add mainAx to hlink object:
            app.hlink.addtarget(app.mainAx)
            % Set Data cursor info:
            app.dcm_obj = datacursormode(app.mainFig);
            set(app.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showDataTip, 'Interpreter', 'tex');
            % Make it visible:
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            w = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', 'Closing app', 'Indeterminate',"on");
            delete(app.mainFig)
            delete(app.summaryFigs)
            delete(w)
            delete(app.reportGUI_handle)
            delete(app)
        end

        % Value changed function: maxEditField, minEditField
        function min_maxYEditFieldValueChanged(app, event)
            app.minEditField.Value;
            app.hlink.addprop('YLim');
            set(app.mainAx, 'YLim', [app.minEditField.Value, app.maxEditField.Value], 'YLimMode','manual');
        end

        % Value changed function: GroupplottypeDropDown
        function GroupplottypeDropDownValueChanged(app, event)
            szDiff = 100;
            if contains(app.GroupplottypeDropDown.Value, 'box', 'IgnoreCase', true)
                app.xOpts.isBoxPlot = true;
                app.xOpts.isCategorical = true;
                app.GridLayout.RowHeight{2} = 0;
                app.UIFigure.Position(4) = app.UIFigure.Position(4) - szDiff;
                % Force x axis options panel to a categorical option:
                if contains(app.XaxisoptionsButtonGroup.SelectedObject.Text, 'continuous', 'IgnoreCase',true)
                    idx = contains({app.XaxisoptionsButtonGroup.Children.Text}, 'no','IgnoreCase',true);
                    app.XaxisoptionsButtonGroup.SelectedObject = app.XaxisoptionsButtonGroup.Children(idx);
                    app.xOpts.XJitter = false;
                end
                % Disable "continuous" option from Xaxsi button group:P
                app.ContinuousButton.Enable = 'off';
            else
                app.xOpts.isBoxPlot = false;
                app.GridLayout.RowHeight{2} = 100;
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - szDiff;
                app.UIFigure.Position(4) = app.UIFigure.Position(4) + szDiff;
                % Enable "continuous" option from Xaxsi button group:P
                app.ContinuousButton.Enable = 'on';
            end
            % Update plots:
            app.updateAllFigs;
        end

        % Value changed function: DatainmainfigureDropDown
        function ShowPlotInMainFig(app, event)
            % This callback makes a copy of selected ROI plot from the
            % summaryFigs into the main fig axis
            idx_plot = strcmp(app.DatainmainfigureDropDown.Value, app.DatainmainfigureDropDown.Items);
            cla(app.mainAx);
            % Copy source axis content to main plot:
            copyobj(app.summaryAxH(idx_plot).Children,app.mainAx);
            % Copy title and labels:
            app.mainAx.Title.String = app.summaryAxH(idx_plot).Title.String;
            set(app.mainAx, 'YLim', app.summaryAxH(idx_plot).YLim,...
                'XTick',app.summaryAxH(idx_plot).XTick,...
                'XTickLabel', app.summaryAxH(idx_plot).XTickLabel,...
                'XTickLabelRotation', app.summaryAxH(idx_plot).XTickLabelRotation);
            % Create legend:
            app.addLegend(app.mainAx, 'bestoutside');
            % Relink main ax with summary figures:
            app.hlink.addtarget(app.mainAx);
            % Update evbars:
            app.updateEvBars;
        end

        % Selection changed function: VariationtypeButtonGroup
        function VariationtypeButtonGroup_callback(app, event)
            % This callback updates the error bars of all plots as well as
            % the legend in the main figure for the type of error measure
            % selected in the GUI.
            
            indx = find(arrayfun(@(x) isequaln(app.VariationtypeButtonGroup.SelectedObject, x), app.VariationtypeButtonGroup.Buttons));
            varName = containers.Map(1:3,{'std','sem','ci'});
            if ( app.b_isTimeVector )
                arrayfun(@(x) setErrorValue(x,'timeVecG', varName(indx)), app.summaryAxH)
            else
                arrayfun(@(x) setErrorValue(x,'errG', varName(indx)), app.summaryAxH)
            end
            app.varType = varName(indx);
            
            % Update Main figure as well:
            app.ShowPlotInMainFig;
            
            %----------------------------------------------------------
            % Local function
            function setErrorValue(axHandle, objTagPrefix, errType)
                % SETERRORVALUE calculates the data variation from a graphical object's UserData and updates the error bars /shades.
                hList = findobj(axHandle, '-regexp','Tag', [objTagPrefix '\w+']);
                for jj = 1:numel(hList)
                    avg = hList(jj).UserData.Mean;
                    % Calculate new error measure:
                    switch lower(errType)
                        case 'std'
                            errVal = hList(jj).UserData.std;
                        case 'sem'
                            errVal = (hList(jj).UserData.std) ./ sqrt(hList(jj).UserData.Ndata);
                        case 'ci'
                            errVal = (tinv([0.975],hList(jj).UserData.Ndata-1)) .* (hList(jj).UserData.std./sqrt(hList(jj).UserData.Ndata));
                    end
                    % Update element's UserData with variation measure:
                    hList(jj).UserData.(lower(errType)) = errVal;
                    % Update graphical elements with new error value:
                    if startsWith(objTagPrefix, 'time')
                        % For time vectors:
                        shadeH = findobj(hList(jj), 'Type', 'Patch');
                        set(shadeH, 'YData', [avg - errVal, fliplr(errVal + avg)]);
                    else
                        % For scalar data:
                        PosCap = hList(jj).Children(strcmp({hList(jj).Children.Tag}, 'PositiveCapLine'));
                        NegCap = hList(jj).Children(strcmp({hList(jj).Children.Tag}, 'NegativeCapLine'));
                        ErrLn = hList(jj).Children(strcmp({hList(jj).Children.Tag}, 'errorLine'));
                        set(PosCap, 'YData', [errVal + avg, errVal + avg]);
                        set(NegCap, 'YData', [avg - errVal, avg - errVal]);
                        set(ErrLn, 'YData', [avg - errVal, errVal + avg]);
                    end
                end
            end
            
        end

        % Selection changed function: YaxisoptionsButtonGroup
        function YaxisoptionsButtonGroup_callback(app, event)
            % Toggles the type of YLim.
            % "Auto" : YLim in "auto" mode. The Y axis is the best fit for
            % each axis.
            % "Uniform": The same YLim for all axes. The min and max YLim
            % is used based on the "auto" YLim from all axes.
            % "Fixed:  Another type of "uniform" but now with user-input
            % values for YLim.
            
            selectedButton = app.YaxisoptionsButtonGroup.SelectedObject;
            indx = find(arrayfun(@(x) isequaln(x,selectedButton), app.YaxisoptionsButtonGroup.Buttons));
            app.minEditField.Enable = 'off';
            app.maxEditField.Enable = 'off';
            switch indx
                case 1
                    % Auto
                    app.hlink.removeprop('YLim');
                    set(app.mainAx, 'YLimMode','auto')
                case 2
                    % Uniform
                    set(app.mainAx, 'YLimMode','auto')
                    y_lims = vertcat(app.summaryAxH.YLim);
                    y_lims = [min(y_lims(:,1)), max(y_lims(:,2))];
                    app.hlink.addprop('YLim');
                    set(app.mainAx, 'YLim',y_lims,'YLimMode','manual');
                case 3
                    % Fixed
                    app.minEditField.Enable = 'on';
                    app.maxEditField.Enable = 'on';
            end
            % Set min and max YLims values from main ax:
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
        end

        % Value changed function: LinearCheckBox, LogCheckBox
        function ToggleYaxisScale_callback(app, event)
            % Toggles Y axis scale of all axes between "linear" and "log".
            
            if ~event.Source.Value
                event.Source.Value = true;
                return
            end
            switch lower(event.Source.Text)
                case 'linear'
                    app.LogCheckBox.Value = false;drawnow
                    set(app.mainAx, 'YScale', 'linear'); % Since YScale is linked, all figures will be changed as well.
                otherwise
                    app.LinearCheckBox.Value = false;drawnow
                    set(app.mainAx, 'YScale', 'log'); % Idem
            end
        end

        % Menu selected function: ExportMenu, SavetoFIGMenu
        function Save_Export_Figs(app, event)
            % This callback either saves as .fig or exports individual plots as
            % bitmap/vector images.
            
            % Get if user chose to "Save" or to "Export:
            if startsWith(event.Source.Text, 'save', "IgnoreCase",true)
                % To save as .fig:
                [file,path] = uiputfile('*.fig', 'Save plots as...', 'out');
            else
                % To export to image file:
                fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)';...
                    '*.pdf', 'Portable Document Format  (*.pdf)';...
                    '*.eps', 'Encapsulated PostScript  (*.eps)'};
                [file, path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'out.png');
                print_dict = {'-dpng', '-djpeg', '-dpdf', '-depsc'};
            end
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            % Create new figure and copy axis from all summary figures
            [~,prefix,fmt] = fileparts(file);
            f = figure('Position',[0 0 880 420], 'Visible', 'off');
            movegui(f, 'center');f.Visible = 'on';
            h = waitbar(0,'Saving figures...'); movegui(h,'center');
            ax = axes(f, 'Units',"normalized" , 'Position',[.1 .15 .8 .7]); % Create new axis.
            for ii = 1:length(app.summaryAxH)
                copyobj(app.summaryAxH(ii).Children, ax, 'legacy'); % Copy axis and its content.
                % Set axis parameters:
                set(ax, 'Color', app.summaryAxH(ii).Parent.Color,...
                    'XLim', app.summaryAxH(ii).XLim, ...
                    'YLim', app.summaryAxH(ii).YLim,...
                    'YScale', app.summaryAxH(ii).YScale, ...
                    'XTick', app.mainAx.XTick,...
                    'XTickLabel', app.mainAx.XTickLabel,...
                    'XTickLabelRotation', app.mainAx.XTickLabelRotation);
                ax.XLabel.String = app.mainAx.XLabel.String;
                ax.YLabel.String = app.mainAx.YLabel.String;
                % Add title and legend:
                title(ax, app.summaryAxH(ii).Title.String, 'Interpreter', 'none');
                app.addLegend(ax,'best'); % Add legend inside axis.
                % Save/Export figure:
                if startsWith(event.Source.Text, 'save', "IgnoreCase",true)
                    savefig(f, fullfile(path,[prefix '_' ax.Title.String fmt]))
                else
                    if strcmp(fmt, '.pdf')
                        print(fullfile(path,[prefix '_' ax.Title.String fmt]) , f, print_dict{indx}, '-painters','-r300', '-bestfit'); % If .PDF, use "bestfit" option to avoid cropping the axis.
                    else
                        print(fullfile(path,[prefix '_' ax.Title.String fmt]) , f, print_dict{indx}, '-painters','-r300');
                    end
                end
                cla(ax); % Clear figure.
                waitbar(ii/length(app.summaryAxH), h);
            end
            close(h); % Close waitbar
            close(f); % Close figure
        end

        % Menu selected function: SelectMenu
        function DataMenuSelected(app, event)
            % This callback erases the current summary figures and launches
            % the "Data_selector" to rebuild all figures.
            
            % Clear summary figures and main axis:
            arrayfun(@delete, app.summaryFigs);app.summaryFigs = [];
            cla(app.mainAx);
            % Hide main figure and GUI:
            app.UIFigure.Visible = 'off';
            app.mainFig.Visible = 'off';
            % Launch Data_selector;
            ds = Data_selector(app.statsObj, app, true);
            waitfor(ds);
            app.setXTickLabelEditField;
            % Rebuild summary figures and update GUI:
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};
            app.createSummaryFigs;
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';
            % Relink main axis:
            app.hlink.addtarget(app.mainAx)
        end

        % Button pushed function: OkButton
        function updateAllFigs(app, event)
            % Re-creates all figures using the current parameters from the GUI.
            cla(app.mainAx); % Clear the main axis.
            app.createSummaryFigs; % Create summary figures
            app.ShowPlotInMainFig; % Create main axis
            app.YaxisoptionsButtonGroup_callback; % Rescale Y axis
        end

        % Value changed function: XlabelEditField
        function XlabelEditFieldValueChanged(app, event)
            % Changes the main axis X label
            app.mainAx.XLabel.String = app.XlabelEditField.Value;
        end

        % Value changed function: YlabelEditField
        function YlabelEditFieldValueChanged(app, event)
            % Changes the main axis Y label
            app.mainAx.YLabel.String = app.YlabelEditField.Value;
        end

        % Selection changed function: TimevectorstyleButtonGroup
        function TimeVectorStyleButtonGroup_callback(app, event)
            % Toggles the type of time vector plots between "Shaded" or
            % "Lines".
            
            w = uiprogressdlg(app.UIFigure, 'Title', '', 'Message', 'Please wait...', 'Indeterminate',"on");
            if strcmpi(app.TimevectorstyleButtonGroup.SelectedObject.Text, 'shaded')
                % Show Variation type options panel
                app.GridLayout.RowHeight{2} = 100;
            else
                % Hide Variation type options panel
                app.GridLayout.RowHeight{2} = 0;
            end
            % Update main axis:
            app.updateAllFigs;
            delete(w); % Clear progress bar.
        end

        % Value changed function: HideaxisoptionsButton
        function ShowHideaxisoptions_callback(app, event)
            % This callback shows/hides the Axis settings panel.
            if ~app.HideaxisoptionsButton.Value
                app.HideaxisoptionsButton.Text = strrep(app.HideaxisoptionsButton.Text, 'Hide', 'Show');
                app.GridLayout2.RowHeight{3} = 0;
                app.GridLayout.RowHeight{2} = 0;
                app.UIFigure.Position([2 4]) = [app.UIFigure.Position(2) + 460 + 100, app.UIFigure.Position(4) - 460 - 100];
                
            else
                app.HideaxisoptionsButton.Text = strrep(app.HideaxisoptionsButton.Text, 'Show', 'Hide');
                app.GridLayout2.RowHeight{3} = 480;
                app.GridLayout.RowHeight{2} = 100;
                app.UIFigure.Position([2 4]) = [app.UIFigure.Position(2) - 460 - 100, app.UIFigure.Position(4) + 460 + 100];
            end
        end

        % Selection changed function: XaxisoptionsButtonGroup
        function XaxisoptionsButtonGroupSelectionChanged(app, event)
            % Set x options and update plots:
            selectedButton = app.XaxisoptionsButtonGroup.SelectedObject;
            if contains(selectedButton.Text, 'No', 'IgnoreCase',true) && contains(selectedButton.Text, 'cat', 'IgnoreCase',true)
                % For No jitter - categorical
                app.xOpts.XJitter = false;
                app.xOpts.isCategorical = true;
            elseif contains(selectedButton.Text, 'jitter', 'IgnoreCase',true) && contains(selectedButton.Text, 'cat', 'IgnoreCase',true)
                % For jitter - categorical
                app.xOpts.XJitter = true;
                app.xOpts.isCategorical = true;
            else
                app.xOpts.XJitter = false;
                app.xOpts.isCategorical = false;
            end
            app.updateAllFigs; % Redraw figures
        end

        % Menu selected function: RunhypothesistestsMenu
        function RunhypothesistestsMenuSelected(app, event)
            
            % Load Stats report GUI:
            app.RunhypothesistestsMenu.Enable = 'off';
            app.reportGUI_handle = StatsReportGUI(app, app.statsObj);
            if isempty(app.statsObj.results_stats)
                app.RunhypothesistestsMenu.Enable = 'on';
                return
            end
            % Add "significance hints" to plot titles in summary figures:
            disp('Adding hints...')
            exp = '\s\((N.S.|\**)';
            colorMap = containers.Map([true,false],{[1 0 0],[0 0 0]});
            for ii = 1:length(app.summaryAxH)
                axTitle = app.summaryAxH(ii).Title.String;
                if ~isempty(regexpi(axTitle, exp))
                    % Remove existing hints:
                    axTitle = regexpi(axTitle, ['.*(?=' exp ')'], 'match');
                end
                % Add new hints:
                idx = strcmpi(axTitle, {app.statsObj.results_stats.subGroupName});
                if isempty(app.statsObj.results_stats(idx).GroupComparison.p) | all(app.statsObj.results_stats(idx).GroupComparison.p > 0.05)
                    % For non-significant
                    sufx = ' (N.S.)';
                elseif min(app.statsObj.results_stats(idx).GroupComparison.p,[],'all') <= 0.001
                    % For p-values less than or equal to 0.001, use ***:
                    sufx = ' (***)';
                elseif min(app.statsObj.results_stats(idx).GroupComparison.p,[],'all') <= 0.01
                    % For p-values less than or equal to 0.01, use **:
                    sufx = ' (**)';
                else
                    % For p-values less than or equal to 0.05, use *:
                    sufx = ' (*)';
                end
                % Append suffix to axis titles:
                app.summaryAxH(ii).Title.String = [axTitle sufx];
                app.summaryAxH(ii).Title.Color = colorMap(any(app.statsObj.results_stats(idx).GroupComparison.p<= 0.05,'all'));
            end
        end

        % Value changing function: XTickLabelEditField
        function XTickLabelEditFieldValueChanging(app, event)
            
            app.OkButton.Enable ='off';
            tmp = regexpi(event.Value,'\D*(\d+\.*\d*)\D*','tokens');
            if isempty(tmp)
                return
            end
            xTickVals = cellfun(@str2double,[tmp{:}]);
            if ( ~isequaln(length(xTickVals),length(app.data(1).xLabel)) )  || ~isequaln(numel(unique(xTickVals)),numel(xTickVals))
                return
            end
            % Enable the "Ok" button if the number of xtick indices is
            % equal to the X labels:
            app.OkButton.Enable = 'on';
            % Set X axis limits:
            app.xAxLims = [min(xTickVals) - 1,  max(xTickVals) + 1];
            app.minXEditField.Value = app.xAxLims(1);
            app.maxXEditField.Value = app.xAxLims(2);
            % Update xtick position list:
            app.xTickPositionList = unique(xTickVals,'stable');
        end

        % Callback function
        function OkButtonPushed(app, event)
            % Update X tick label positions
            
            %             app.setXTickLabelEditField;
            % Rebuild summary figures and update GUI:
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};
            app.createSummaryFigs;
            app.ShowPlotInMainFig;
            %             movegui(app.mainFig, 'center');
            %
            %             app.mainFig.Visible = 'on';
            %             app.UIFigure.Visible = 'on';
            % Relink main axis:
            app.hlink.addtarget(app.mainAx)
        end

        % Value changed function: maxXEditField, minXEditField
        function min_maxXEditFieldValueChanged(app, event)
            app.hlink.addprop('XLim');
            set(app.mainAx, 'XLim', [app.minXEditField.Value, app.maxXEditField.Value], 'YLimMode','manual');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Longitudinal data Plotter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FiguresMenu = uimenu(app.UIFigure);
            app.FiguresMenu.Text = 'Figures';
            app.SavetoFIGMenu = uimenu(app.FiguresMenu);
            app.SavetoFIGMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.SavetoFIGMenu.Text = 'Save to .FIG';
            app.ExportMenu = uimenu(app.FiguresMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.ExportMenu.Text = 'Export';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Tooltip = {'Set how dimensions are displayed.'};
            app.DataMenu.Text = 'Data';
            app.SelectMenu = uimenu(app.DataMenu);
            app.SelectMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected, true);
            app.SelectMenu.Text = 'Select';
            app.RunhypothesistestsMenu = uimenu(app.DataMenu);
            app.RunhypothesistestsMenu.MenuSelectedFcn = createCallbackFcn(app, @RunhypothesistestsMenuSelected, true);
            app.RunhypothesistestsMenu.Tooltip = {'perform statistical comparisons on data.'};
            app.RunhypothesistestsMenu.Text = 'Run hypothesis tests';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DatainmainfigurePanel = uipanel(app.GridLayout2);
            app.DatainmainfigurePanel.Title = 'Data in main figure';
            app.DatainmainfigureDropDown = uidropdown(app.DatainmainfigurePanel);
            app.DatainmainfigureDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowPlotInMainFig, true);
            app.HideaxisoptionsButton = uibutton(app.GridLayout2, 'state');
            app.HideaxisoptionsButton.ValueChangedFcn = createCallbackFcn(app, @ShowHideaxisoptions_callback, true);
            app.HideaxisoptionsButton.Text = 'Hide axis options';
            app.AxisoptionsPanel = uipanel(app.GridLayout2);
            app.AxisoptionsPanel.Title = 'Axis options';
            app.YlabelEditFieldLabel = uilabel(app.AxisoptionsPanel);
            app.YlabelEditFieldLabel.Text = 'Y label';
            app.YlabelEditField = uieditfield(app.AxisoptionsPanel, 'text');
            app.YlabelEditField.ValueChangedFcn = createCallbackFcn(app, @YlabelEditFieldValueChanged, true);
            app.YlabelEditField.Value = 'Amplitude';
            app.YaxisoptionsButtonGroup = uibuttongroup(app.AxisoptionsPanel);
            app.YaxisoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YaxisoptionsButtonGroup_callback, true);
            app.YaxisoptionsButtonGroup.Title = 'Y axis options';
            app.AutoButton = uiradiobutton(app.YaxisoptionsButtonGroup);
            app.AutoButton.Tooltip = {'Sets the best Y limits for each plot independently.'};
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.UniformButton = uiradiobutton(app.YaxisoptionsButtonGroup);
            app.UniformButton.Tooltip = {'Sets all Y limits for all plots using the largest range found across plots.'};
            app.UniformButton.Text = 'Uniform';
            app.LinearCheckBox = uicheckbox(app.YaxisoptionsButtonGroup);
            app.LinearCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggleYaxisScale_callback, true);
            app.LinearCheckBox.Text = 'Linear';
            app.LinearCheckBox.Value = true;
            app.LogCheckBox = uicheckbox(app.YaxisoptionsButtonGroup);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggleYaxisScale_callback, true);
            app.LogCheckBox.Text = 'Log';
            app.FixedButton = uiradiobutton(app.YaxisoptionsButtonGroup);
            app.FixedButton.Tooltip = {'Set Y limits manually.'};
            app.FixedButton.Text = 'Fixed';
            app.minEditFieldLabel = uilabel(app.YaxisoptionsButtonGroup);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.YaxisoptionsButtonGroup, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.3f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @min_maxYEditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.YaxisoptionsButtonGroup);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.YaxisoptionsButtonGroup, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.3f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @min_maxYEditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.GroupplottypeDropDownLabel = uilabel(app.AxisoptionsPanel);
            app.GroupplottypeDropDownLabel.Text = 'Group plot type';
            app.GroupplottypeDropDown = uidropdown(app.AxisoptionsPanel);
            app.GroupplottypeDropDown.Items = {'Error bar', 'Box plot'};
            app.GroupplottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupplottypeDropDownValueChanged, true);
            app.GroupplottypeDropDown.Enable = 'off';
            app.GroupplottypeDropDown.Value = 'Error bar';
            app.XlabelEditFieldLabel = uilabel(app.AxisoptionsPanel);
            app.XlabelEditFieldLabel.Text = 'X label';
            app.XlabelEditField = uieditfield(app.AxisoptionsPanel, 'text');
            app.XlabelEditField.ValueChangedFcn = createCallbackFcn(app, @XlabelEditFieldValueChanged, true);
            app.XlabelEditField.Value = 'Acquisition';
            app.XaxisoptionsButtonGroup = uibuttongroup(app.AxisoptionsPanel);
            app.XaxisoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XaxisoptionsButtonGroupSelectionChanged, true);
            app.XaxisoptionsButtonGroup.Title = 'X axis options';
            app.JittercategButton = uiradiobutton(app.XaxisoptionsButtonGroup);
            app.JittercategButton.Tooltip = {'Spreads data points around the X axis. Categorical data will be plotted side by side.'};
            app.JittercategButton.Text = 'Jitter - categ.';
            app.NojittercategButton = uiradiobutton(app.XaxisoptionsButtonGroup);
            app.NojittercategButton.Tooltip = {'Data points are plotted in a single X point. Categorical data will be plotted side by side.'};
            app.NojittercategButton.Text = 'No jitter - categ.';
            app.NojittercategButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.XaxisoptionsButtonGroup);
            app.ContinuousButton.Tooltip = {'Continuous data will be overlapped in the X axis. '};
            app.ContinuousButton.Text = 'Continuous';
            app.XTicklabelpositionLabel = uilabel(app.XaxisoptionsButtonGroup);
            app.XTicklabelpositionLabel.Text = 'XTick label position :';
            app.XTickLabelEditField = uieditfield(app.XaxisoptionsButtonGroup, 'text');
            app.XTickLabelEditField.ValueChangingFcn = createCallbackFcn(app, @XTickLabelEditFieldValueChanging, true);
            app.XTickLabelEditField.Tooltip = {'Set X Axis Tick values separated by a comma.'};
            app.OkButton = uibutton(app.XaxisoptionsButtonGroup, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @updateAllFigs, true);
            app.OkButton.Text = 'Ok';
            app.maxEditFieldLabel_2 = uilabel(app.XaxisoptionsButtonGroup);
            app.maxEditFieldLabel_2.Text = 'max';
            app.maxXEditField = uieditfield(app.XaxisoptionsButtonGroup, 'numeric');
            app.maxXEditField.ValueDisplayFormat = '%11.3f';
            app.maxXEditField.ValueChangedFcn = createCallbackFcn(app, @min_maxXEditFieldValueChanged, true);
            app.minEditField_2Label = uilabel(app.XaxisoptionsButtonGroup);
            app.minEditField_2Label.Text = 'min';
            app.minXEditField = uieditfield(app.XaxisoptionsButtonGroup, 'numeric');
            app.minXEditField.ValueDisplayFormat = '%11.3f';
            app.minXEditField.ValueChangedFcn = createCallbackFcn(app, @min_maxXEditFieldValueChanged, true);
            app.VariationtypeButtonGroup = uibuttongroup(app.GridLayout);
            app.VariationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariationtypeButtonGroup_callback, true);
            app.VariationtypeButtonGroup.Title = 'Variation type';
            app.StandarddeviationSDButton = uiradiobutton(app.VariationtypeButtonGroup);
            app.StandarddeviationSDButton.Text = 'Standard deviation (SD)';
            app.StandarddeviationSDButton.Value = true;
            app.StandardErrorofthemeanSEMButton = uiradiobutton(app.VariationtypeButtonGroup);
            app.StandardErrorofthemeanSEMButton.Text = 'Standard Error of the mean (SEM)';
            app.ConfidenceintervalCIButton = uiradiobutton(app.VariationtypeButtonGroup);
            app.ConfidenceintervalCIButton.Enable = 'off';
            app.ConfidenceintervalCIButton.Tooltip = {'95% CI of the mean. Available with Statistics and Machine Learning Toolbox.'};
            app.ConfidenceintervalCIButton.Text = '95% Confidence interval (CI)';
            app.TimevectorstyleButtonGroup = uibuttongroup(app.GridLayout);
            app.TimevectorstyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeVectorStyleButtonGroup_callback, true);
            app.TimevectorstyleButtonGroup.Title = 'Time vector style';
            app.ShadedButton = uiradiobutton(app.TimevectorstyleButtonGroup);
            app.ShadedButton.Tooltip = {'Shows variation as shaded areas'};
            app.ShadedButton.Text = 'Shaded';
            app.ShadedButton.Value = true;
            app.LinesButton = uiradiobutton(app.TimevectorstyleButtonGroup);
            app.LinesButton.Tooltip = {'Show individual traces'};
            app.LinesButton.Text = 'Lines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end