classdef PlotMatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotMatrixUIFigure         matlab.ui.Figure
        OptionsPanel               matlab.ui.container.Panel
        reorderMatrixButton        matlab.ui.control.Button
        SetbaselineButton          matlab.ui.control.Button
        ShowMatrixasDropDownLabel  matlab.ui.control.Label
        ShowMatrixasDropDown       matlab.ui.control.DropDown
        HidediagonalCheckBox       matlab.ui.control.CheckBox
        SetcolormapButton          matlab.ui.control.Button
        ShowPlotasDropDownLabel    matlab.ui.control.Label
        ShowPlotasDropDown         matlab.ui.control.DropDown
        PlottypeDropDownLabel      matlab.ui.control.Label
        PlottypeDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        statsObj % Handle to StatsManager object
        
        imgOptsApp % Handle to ImagApps_Opts app.
        matrix_struct = struct() % Structure containing the Merged Matrix from the observations listed in statsObj.
        figs = struct % structure containing handles of matrix and plot figures.
    end
    
    methods (Access = private)
        
        function b_OK = checkInput(app)
            % This function verifies if the MATFILEs listed in statsObj contains 2-D data with
            % equal sizes.
            b_OK = false;
            disp('Cheking input...')
            for i = 1:length(app.statsObj.MfileArr)
                data_size = cellfun(@(x) size(x), app.statsObj.MfileArr{i}.data, 'UniformOutput',false);
                if ~isequal(data_size{:})
                    err = errordlg({'Observations has different sizes in:', app.statsObj.MfileArr{i}.Properties.Source}, 'Failed data check!');
                    waitfor(err)
                    return
                end
                if ~isequal(max(data_size{1}), length(app.statsObj.MfileArr{i}.obsID))
                    err = errordlg('Invalid Data size. The number of data per observation must match the number of observations (X by X matrix)',...
                        'Failed data check!');
                    waitfor(err)
                    return
                end
            end
            b_OK = true;
            
        end
        
        function genMatrixArray(app)
            % This function creates an array of Matrices per acquisition,
            % per group and stores it in a structure (matrix_struct).
            
            disp('Creating matrix array...')
            
            target_obs = app.statsObj.obs_list;
            groupList = unique(app.statsObj.list_of_groups);
            a = 1;
            for i = 1:numel(groupList)
                for j = 1:max([app.statsObj.stats_data.acqIdx])
                    idx = [app.statsObj.stats_data.acqIdx] == j & strcmp(groupList{i}, {app.statsObj.stats_data.groupName});
                    app.matrix_struct(a).groupName = groupList{i};
                    app.matrix_struct(a).IndxList = find(idx); % Index of element in app.statsObj.stats_data structure!
                    app.matrix_struct(a).acqIdx = j; % Acquisition index.
                    app.matrix_struct(a).data = createMatrix(target_obs, app.statsObj.stats_data(idx));
                    app.matrix_struct(a).avg = mean(app.matrix_struct(a).data,3,'omitnan');
                    app.matrix_struct(a).sem = std(app.matrix_struct(a).data,0,3,'omitnan')./sqrt(size(app.matrix_struct(a).data,3));
                    
                    a = a+1;
                end
            end
            
            % Local function
            function out = createMatrix(obsList, stats_struct)
                % This function merges all data from obsStruct into a 3-D
                % matrix with sizes n_obsList x n_obsList x n_obsStruct.
                % Inputs
                % obsList (cell array of char) = list of observation IDs
                % stats_struct (struct array) = structure containing observation IDs
                % and their respective data.
                % Ouput
                % out (3D array) = numerical array containing data merged
                % from obsStruct.
                
                disp('Creating matrix...')
                out = nan(numel(obsList),numel(obsList), length(stats_struct));
                for ii = 1:length(stats_struct)
                    for jj = 1:numel(obsList)
                        % Retrieve original observation list from Mfile:
                        orig_obs = stats_struct(ii).MatFile.obsID;
                        [idx_obs,lb] = ismember(obsList, orig_obs);lb(lb==0) = [];
                        if ~any(strcmp(obsList{jj}, orig_obs))
                            continue
                        end
                        out(jj,idx_obs,ii) = stats_struct(ii).observations(jj).data(1,lb);
                    end
                end
            end
        end
        
        function createMatrixFig(app)
            % This function creates a matrix figure and its callbacks
            
            fH = figure('Name', 'Matrices', 'NumberTitle',"off", 'MenuBar',"none", 'ToolBar',"figure", 'Position',[261 561 1280 420],...
                "CloseRequestFcn",@app.DoNothing);
            groups = unique(app.statsObj.list_of_groups);
            nAcq = max([app.matrix_struct.acqIdx]);
            a = 1;
            labels = app.statsObj.obs_list;
            for i = 1:numel(groups)
                for j = 1:nAcq
                    s(a) = subplot(numel(groups), nAcq,a, 'Parent', fH);
                    idx = strcmp(groups{i},{app.matrix_struct.groupName}) & [app.matrix_struct.acqIdx] == j;
                    img(a) = imagesc(s(a), app.matrix_struct(idx).avg);
                    img(a).HandleVisibility = 'callback';
                    img(a).Tag = 'my_matrix';
                    img(a).ButtonDownFcn = @app.pixSelect;
                    set(s(a), 'YTick', 1:numel(labels), 'XTick',1:numel(labels),'YTickLabel', labels, 'XTickLabel', labels, 'XTickLabelRotation', 90, ...
                        'FontSize',8, 'TitleFontSizeMultiplier', 1.25, 'NextPlot', 'replacechildren', 'DataAspectRatio', [1 1 1]);
                    s(j).Title.String = ['Acq# ' num2str(j)];
                    red_dot(a) = plot(s(a),1,1,'ro', 'MarkerSize',6);
                    if j == 1
                        s(a).YLabel.String = groups{i};
                        s(a).YLabel.FontSize = 10;
                        s(a).YLabel.FontWeight = 'bold';
                        s(a).YLabel.Interpreter = 'none';
                    end
                    a=a+1;
                end
            end
            cb = colorbar(s(1), 'westoutside'); cb.Position = [0.04 .6 .02 .36];
            % Link Color and axis scaling properties:
            hlink = linkprop(s, {'CLim', 'XLim', 'YLim'});
            % Save handles to fig struct:
            app.figs.matFig.fH = fH;
            app.figs.matFig.imgH = img;
            app.figs.matFig.pltH = red_dot;
            app.figs.matFig.axH = s;
            app.figs.matFig.hlink = hlink;
        end
        
        function createPlotFig(app)
            % This function creates the plot figure and sets its callbacks
            
            % Create Figure
            
            % Set axis
            
            % Create color palette
            
            % Create errorbar plots
            
            % Create scatter plots
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function pixSelect(app,src,evnt)
            % This callback shows a cursor and text over the clicked
            % pixel in a matrix.
            selPt = round(evnt.IntersectionPoint([1 2]));
            %                 if ~src.AlphaData(selPt(2),selPt(1))
            %                     return
            %                 end
            % clear previous text:
            axH = src.Parent.Parent.Children;
            ht = arrayfun(@(x) findall(x.Children, 'type', 'Text'), axH, 'UniformOutput',0);
            cellfun(@(x) delete(x), ht);
            % Write x-y pair name to plot;
            text(src.Parent, selPt(1), selPt(2), [src.Parent.YTickLabel{selPt(2)} ' vs ' src.Parent.XTickLabel{selPt(1)}],...
                'HorizontalAlignment', 'left', 'VerticalAlignment','bottom', 'FontSize',7,...
                'Interpreter','none');
            arrayfun(@(x) set(x, 'XData', selPt(1), 'YData', selPt(2)), app.figs.matFig.pltH);
        end
        
        function DoNothing(~,~,~)
            %%% NOTHING %%%
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
                % This startup function creates the Matrix and the Plot figures
                % with default parameters.
                %
                app.statsObj = statsObj;
                % Data validation
                b_OK = app.checkInput;
                if ~b_OK
                    app.CloseApp;
                end
                % Build Matrix arrays
                app.genMatrixArray;
                % Create Matrix figure
                app.createMatrixFig;
                % Create Plot figure
                %             app.createPlotFig
                % Show Matrices and plots
                %             app.showData;
                
        end

        % Close request function: PlotMatrixUIFigure
        function CloseApp(app, event)
            delete(app.figs.matFig.fH);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotMatrixUIFigure = uifigure('Visible', 'off');
            app.PlotMatrixUIFigure.Name = 'Plot Matrix';
            app.PlotMatrixUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.OptionsPanel = uipanel(app.PlotMatrixUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.reorderMatrixButton = uibutton(app.OptionsPanel, 'push');
            app.reorderMatrixButton.Text = {'re-order'; 'Matrix'};
            app.SetbaselineButton = uibutton(app.OptionsPanel, 'push');
            app.SetbaselineButton.Text = {'Set '; 'baseline'};
            app.ShowMatrixasDropDownLabel = uilabel(app.OptionsPanel);
            app.ShowMatrixasDropDownLabel.Text = 'Show Matrix as:';
            app.ShowMatrixasDropDown = uidropdown(app.OptionsPanel);
            app.ShowMatrixasDropDown.Items = {'Data', 'Diff.from baseline', 'Diff. from previous'};
            app.ShowMatrixasDropDown.Value = 'Data';
            app.HidediagonalCheckBox = uicheckbox(app.OptionsPanel);
            app.HidediagonalCheckBox.Text = 'Hide diagonal';
            app.SetcolormapButton = uibutton(app.OptionsPanel, 'push');
            app.SetcolormapButton.Text = {'Set'; 'colormap'};
            app.ShowPlotasDropDownLabel = uilabel(app.OptionsPanel);
            app.ShowPlotasDropDownLabel.Text = 'Show Plot as:';
            app.ShowPlotasDropDown = uidropdown(app.OptionsPanel);
            app.ShowPlotasDropDown.Items = {'Data', 'Diff.from baseline', 'Diff. from previous'};
            app.ShowPlotasDropDown.Value = 'Data';
            app.PlottypeDropDownLabel = uilabel(app.OptionsPanel);
            app.PlottypeDropDownLabel.Text = 'Plot type:';
            app.PlottypeDropDown = uidropdown(app.OptionsPanel);
            app.PlottypeDropDown.Items = {'Scatter', 'BoxPlot'};
            app.PlottypeDropDown.Enable = 'off';
            app.PlottypeDropDown.Value = 'Scatter';
            % Show the figure after all components are created
            app.PlotMatrixUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotMatrix(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotMatrixUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotMatrixUIFigure)
        end
    end
end