classdef PlotMatrix_reorderObs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReorderobsevationsListBoxLabel  matlab.ui.control.Label
        ReorderobsevationsListBox       matlab.ui.control.ListBox
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
    end

    properties (Access = public)
        MainApp% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.UIFigure.Visible = 'off';
            app.MainApp = MainApp;
            app.ReorderobsevationsListBox.Items = app.MainApp.obsList;
            % Update UIFigure position:
            movegui(app.UIFigure, 'center');
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             Item = app.ReorderobsevationsListBox.Value;
            if~isempty(Item)
                Items = app.ReorderobsevationsListBox.Items;
                pos = find(strcmp(Item, Items));
                if pos > 1
                    range = 1:numel(Items);
                    range(pos) = pos -1 ;
                    range(pos -1) = pos;
                    app.ReorderobsevationsListBox.Items = Items(range);
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             Item = app.ReorderobsevationsListBox.Value;
            if~isempty(Item)
                Items = app.ReorderobsevationsListBox.Items;
                pos = find(strcmp(Item, Items));
                if pos < numel(Items)
                    range = 1:numel(Items);
                    range(pos) = pos + 1;
                    range(pos + 1) = pos;
                    app.ReorderobsevationsListBox.Items = Items(range);
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.obsList = app.ReorderobsevationsListBox.Items;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReorderobsevationsListBoxLabel = uilabel(app.UIFigure);
            app.ReorderobsevationsListBoxLabel.Text = 'Re-order obsevations';
            app.ReorderobsevationsListBox = uilistbox(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon_arrow_up.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icon_arrow_dwn.png';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotMatrix_reorderObs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end