classdef StatsReportGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        ExportReportButton  matlab.ui.control.Button
        ShowReportButton    matlab.ui.control.StateButton
        WarnArea            matlab.ui.control.TextArea
        TestArea            matlab.ui.control.TextArea
        RepArea             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        statsObj % Handle to "StatsManager" object.
        MainApp % Handle to calling app.
        qMatrix % Matrix of FDR-corrected pValues from correlation matrix statistical comparisons.                
        plotH % handle to figure containing the qMatrix.
    end
    
    
    methods (Access = private)
        
        function plotQmatrix(app)
            % This method plots the qMatrix with asterisks overlayed on
            % significant ROI pairs.
            disp('creating plot!');
            app.plotH = figure('Name', 'qMatrix', 'NumberTitle','off', 'MenuBar',"figure");
            ax = axes('Parent',app.plotH);
            imagesc(ax, app.qMatrix); axis(ax, 'square'); 
            cmap = flipud(jet(256));
            colormap(ax,cmap);
            set(ax, 'XTick', 1:numel(app.statsObj.obs_list), 'YTick', 1:numel(app.statsObj.obs_list),...
                'XTickLabel', app.statsObj.obs_list, 'XTickLabelRotation',90, 'YTickLabel', app.statsObj.obs_list);
            [r,c] = find(app.qMatrix <= app.statsObj.pAlpha);
            colorbar(ax,'eastoutside')
            title(ax,'FDR-corrected pValues (qValues)')
            hold(ax,'on')
            for ii = 1:length(r)
                t = text(ax, r(ii),c(ii), '*', 'Color','k','HandleVisibility',"off");
            end
            hold(ax,'off')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, statsObj)
            app.GridLayout.ColumnWidth{2} = 0; % Hide Report section
            app.UIFigure.InnerPosition(3) = 330;
            app.statsObj = statsObj;
            app.MainApp = MainApp;
            switch app.statsObj.inputFeatures.dataType
                case 'scalar'
                    % Case #1 - Scalar data:
                    [statsReport, testMsg, warnMsg] = app.statsObj.runStatsOnScalar;                   
                case 'matrix'
                    % Case #1 - Scalar data:
                    warnMsg = '';
                    [app.qMatrix, statsReport, testMsg] = app.statsObj.runStatsOnMatrix;
                    app.plotQmatrix;                    
                    if isempty(statsReport)                        
                        if contains(testMsg,'way', 'IgnoreCase',true)
                            warnMsg = 'Report not available because no post hoc test was performed.';
                        else
                            warnMsg = 'Results are displayed in the matrix!';
                        end
                        app.ShowReportButton.Enable = 'off';
                        app.ExportReportButton.Enable = 'off';
                        app.GridLayout.ColumnWidth = {300,0};  
                    end
                otherwise
                    error('Invalid data type')                              
            end            
            % Show messages in text area:
            app.TestArea.Value = testMsg;
            str = sprintf('WARNING MESSAGES: \n\n');
            if isempty(warnMsg)
                warnMsg = 'none';
            end
            app.WarnArea.Value = [str warnMsg];
            app.RepArea.Value = statsReport;
        end

        % Value changed function: ShowReportButton
        function ShowReportButtonValueChanged(app, event)
            
            if app.ShowReportButton.Value
                app.GridLayout.ColumnWidth{2}= '1x'; % Show Report section
                app.UIFigure.InnerPosition(3) = 1200;
                app.ShowReportButton.Text = 'Hide Report';
            else
                app.GridLayout.ColumnWidth{2}= 0 ; % Hide Report section
                app.UIFigure.InnerPosition(3) = 330;
                app.ShowReportButton.Text = 'Show Report';
            end
        end

        % Button pushed function: ExportReportButton
        function ExportReportButtonPushed(app, event)
            % Exports the stats report to a .txt file
            
            [file, path] = uiputfile("*.txt", 'Export Report to file','StatsReport.txt');
            if file == 0
                return
            end
            app.statsObj.exportReportToTXT(fullfile(path,file));
            w = msgbox(['Report exported to ', fullfile(path,file)],"modal");
            waitfor(w)            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.plotH);  
            app.MainApp.RunhypothesistestsMenu.Enable = 'on';
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ExportReportButton = uibutton(app.GridLayout3, 'push');
            app.ExportReportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReportButtonPushed, true);
            app.ExportReportButton.Text = 'Export Report';
            app.ShowReportButton = uibutton(app.GridLayout3, 'state');
            app.ShowReportButton.ValueChangedFcn = createCallbackFcn(app, @ShowReportButtonValueChanged, true);
            app.ShowReportButton.Text = 'Show Report';
            app.WarnArea = uitextarea(app.GridLayout2);
            app.WarnArea.Editable = 'off';
            app.TestArea = uitextarea(app.GridLayout2);
            app.TestArea.Editable = 'off';
            app.RepArea = uitextarea(app.GridLayout);
            app.RepArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatsReportGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end