classdef Stats_module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectMenu                    matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        GroupsMenu                     matlab.ui.container.Menu
        CreateEditMenu                 matlab.ui.container.Menu
        SavetofileMenu                 matlab.ui.container.Menu
        Tree                           matlab.ui.container.Tree
        ItemsLabel                     matlab.ui.control.Label
        SelectfileListBoxLabel         matlab.ui.control.Label
        SelectfileListBox              matlab.ui.control.ListBox
        SelectobservationsLabel        matlab.ui.control.Label
        SelectobservationsListBox      matlab.ui.control.ListBox
        AnalysisPanel                  matlab.ui.container.Panel
        TableViewButton                matlab.ui.control.Button
        GenAverageRSimagesButton       matlab.ui.control.Button
        CustomFunctionsVannisLabLabel  matlab.ui.control.Label
        PlotCorrelationMatrixButton    matlab.ui.control.Button
        SelectButton                   matlab.ui.control.StateButton
        ExporttoCSVButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        ProtocolObj % handle to Protocol object.
        groupInfo = struct('gType', 'descriptive stats', 'nGroups', 1, 'gIdxList',[]) % Structure containing group info created by "data_grouping_tool.mlapp".
    end
    
    properties (Access = private)
        matFileList % List of .MAT files found in FilePtrs of objects listed in the UITree nodes.
        nodeList % List of nodes from app.Tree associated with each .MAT file in "matFileList".
        nodeObjList % List of objects associated with elements from nodeList.
        
        StatsObj % StatsManager object.
    end
    
    methods (Access = private)
        
        function fillUITree(app)
            app.Tree.Enable = 'off';
            delete(app.Tree.Children)
            %%% Allow the creation of several roots!
            s = app.groupInfo.gIdxList;
            nRoots = length(s);
            for ii = 1:nRoots
                idxList = s(ii).idxList;
                root = uitreenode(app.Tree, 'Text', s(ii).Name,'Icon', 'icon_WinFolder.png');
                subjIdx = unique(idxList(:,1));
                for i = 1:numel(subjIdx)
                    ID = app.ProtocolObj.Array.ObjList(subjIdx(i)).ID;
                    subjNode = uitreenode(root, 'Text', ID, 'Icon', 'icon_WinFolder.png');
                    idx = idxList(:,1) == subjIdx(i);
                    acqIdx = unique(idxList(idx,2), 'rows');
                    for j = 1:size(acqIdx,1)
                        acqID = app.ProtocolObj.Array.ObjList(subjIdx(i)).Array.ObjList(acqIdx(j)).ID;
                        uitreenode(subjNode, 'Text', acqID, 'Icon', 'icon_WinFolder.png');
                    end
                end
            end
            %             expand(app.Tree, 'all');
            app.Tree.Enable = 'on';
            app.Tree.Multiselect = 'on';
        end
        
        function [NodeObj, lvl] = getNodeObj(app,Node)
            % GETNODEOBJ retrieves the object associated with the selected node
            % in a UITree.
            ID = Node.Text;
            b_top = false; a = 0;
            str = 'typeClass = class(Node.';
            str2 = 'Parent';
            while ~b_top
                eval([str str2 ');']);
                lvl = a;
                if strcmp(typeClass,'matlab.ui.container.Tree')
                    b_top= true;
                end
                str = [str str2 '.'];
                a = a+1;
            end
            switch lvl
                case 0
                    NodeObj = app.ProtocolObj;
                case 1
                    idx = app.ProtocolObj.Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                    idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
        end
        
        
        function [matList, nodeList, nodeObjList] = findStatsFiles(app)
            % FINDSTATSFILES looks for stats-ready .MAT files listed in the
            % FilePtr.json files from objects selected in app.Tree.
            % In addition to looking inside FilePtrs of selected objects, this function will look into
            % FilePtrs from all modalities inside Acquisition objects.
            % Output:
            % matList: A list of full paths of .MAT files that are common to
            % selected objects (grouped by hierarchy).
            % nodeList : Cell array of UITree node handles associated with each
            % .Mat file listed in MATLIST.
            % nodeObjList: Cell array of objects associated with nodeList
            % elements.
            
            nodeList = {};
            matList = {};
            nodeObjList = {};
            nodes = app.Tree.SelectedNodes;
            % First, get a list of all File Pointers from Selected Nodes:
            for i = 1:numel(nodes)
                node = nodes(i);
                nodeObj = app.getNodeObj(node);
                list = getMatfromJson(nodeObj);                
                if ~isempty(list)
                    nodeObjList = [nodeObjList, repmat({nodeObj}, 1,length(list))];
                    nodeList = [nodeList, repmat({node}, 1,length(list))];
                    matList = [matList, list];
                end
                if isa(nodeObj, 'Acquisition')
                    for j = 1:numel(nodeObj.Array.ObjList)
                        mod_list = getMatfromJson(nodeObj.Array.ObjList(j));
                        nodeList = [nodeList, repmat({node}, 1, length(mod_list))];
                        matList = [matList, mod_list];
                        if ~isempty(mod_list)
                            nodeObjList = [nodeObjList, repmat({nodeObj.Array.ObjList(j)}, 1,length(mod_list))];
                        end
                    end
                end
            end
            
            function list_of_matFiles = getMatfromJson(NodeObj)
                % GETMATFROMJSON reads the JSON file in NODEOBJ.FILEPTR and outputs
                % a list of .MAT files from the FILEPTR.
                list_of_matFiles = {};
                txt = fileread(NodeObj.FilePtr);
                a = jsondecode(txt);
                if ~isempty(a.Files)
                    names = fullfile({a.Files.Folder}, {a.Files.Name});
                    
                    idx = endsWith(names, '.mat');
                    names = names(idx);
                    ObjArr = repmat({NodeObj}, 1, length(names));
                    if ~isempty(names)
                        names = cellfun(@(x,y) tokenizePath(x, y,'detokenize'), names, ObjArr,...
                            'UniformOutput',false);
                        list_of_matFiles= [list_of_matFiles names];
                    end
                end
            end
            
        end
        
        function lvl = findNodeLevel(~,Node)
            a = false;
            lvl = 0;
            while ~a
                myParent = Node.Parent;
                if isa(myParent, 'matlab.ui.container.Tree')
                    a = true;
                else
                    Node = Node.Parent;
                    lvl = lvl + 1;
                end
            end
        end
        
        
        function groupList = getMyGroup(app,nodeList)
                % Retrieves the name of the group from each element
                % contained in nodeList.
                groupList = cell(1,length(nodeList));
                for i = 1:numel(nodeList)
                    node = nodeList{i};
                    b_isNode = true;
                    while b_isNode
                        myParent = node.Parent;
                        if isa(myParent, 'matlab.ui.container.Tree')
                            b_isNode = false;
                            groupName = node.Text;
                        else
                            node = node.Parent;
                        end
                    end
                    groupList{i} = groupName;
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, protObj, varargin)
            
            if isa(protObj, 'Protocol')
                app.ProtocolObj = protObj;
            elseif isfile(protObj)
                try
                input = matfile(protObj);
                assert(ismember('protocol', fieldnames(input)), 'File doesn''t contain "protocol" variable.')
                app.ProtocolObj = input.protocol;
                catch ME
                    baseException = MException('umIToolbox:Stats_module:WrongInput','Failed to load protocol from file!');
                    baseException = addCause(baseException, ME);
                    delete(app)
                    throw(baseException)
                end
            else
                delete(app)
                error('umIToolbox:Stats_module:WrongInput','Wrong input! Must be a Protocol object or a .mat file containing a "protocol".')
            end
            % Turn GUI invisible while loading GUI elements:
            app.UIFigure.Visible = 'off';
            % Limit options for listbox selections:
            app.SelectfileListBox.Multiselect = "off";
            app.SelectobservationsListBox.Multiselect = "on";
            
            % Load groups (optional)
            if nargin == 3
                gInfo = matfile(varargin{:});
                app.groupInfo = gInfo.groupInfo;
            else
                % Reset Filtered Items in protocol object and fill groupInfo struct:
                app.ProtocolObj.clearFilterStruct;
                app.ProtocolObj.queryFilter;
                app.groupInfo.gIdxList.Name = 'Base';
                app.groupInfo.gIdxList.idxList = app.ProtocolObj.Idx_Filtered;
            end
            % Show Nodes in UITree
            app.fillUITree;
            % Show GUI
            app.UIFigure.Visible = 'on';
        end

        % Menu selected function: CreateEditMenu
        function CreateNewGs_clbk(app, event)
            a = data_grouping_tool(app);
            waitfor(a);
            app.fillUITree;
        end

        % Menu selected function: SavetofileMenu
        function SaveGsToFile_clbk(app, event)
            % This callback creates a UISAVE interface for the user to write the filename of the StatsGroup.mat file and 
            % saves it to the protocol's SaveDir.
                        
            % Create dialog box for input of new file:
            dlg_box = uifigure('Name', 'Type file name', 'Position', [300 700 300 100], 'CloseRequestFcn', @Close_dlg_box);
            lab1 = uilabel('Parent',dlg_box, 'Text', 'StatsGroupInfo_','Position', [40 55 75 20], 'FontColor', [.5 .5 .5]);
            txt_box = uieditfield('Parent', dlg_box, "BackgroundColor", [1 1 1], "Position", [117 55 118 20]);
            lab2 = uilabel('Parent',dlg_box, 'Text', '.mat','Position', [232 55 50 20], 'FontColor', [.5 .5 .5]);
            ok_but = uibutton('Parent', dlg_box, "BackgroundColor",[0 .8 0], "Text", 'Save',...
                'Position', [40 10 60 25], 'ButtonPushedFcn',@(src,evnt) SaveROIas(src,evnt,app));
            
            % Local callbacks
            function SaveROIas(src,~,app)
                src.Enable = 'off';
                % Save StatsGroup structure to file:
                groupInfo = app.groupInfo; %#ok
                filename = fullfile(app.ProtocolObj.SaveDir, ['StatsGroupInfo_' src.Parent.Children(3).Value '.mat']);
                save(filename, 'groupInfo');
                msg = msgbox(['Stats Group info saved as ' filename],"modal");
                waitfor(msg)
                delete(src.Parent)
            end
            
            function Close_dlg_box(src,~)
                disp('Operation cancelled by User');
                delete(src)
            end
            
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % erase List boxes:
            app.SelectfileListBox.Items = {''};
            app.SelectobservationsListBox.Items = {''};
            %
            
            if isempty(selectedNodes)
                return
            end
            disp('Building Node Selection...')
            lvl = arrayfun(@(x) app.findNodeLevel(x), selectedNodes);
            top_rank = min(lvl);
            nodelist = [];
            switch top_rank
                case 0
                    top_nodes = [app.Tree.SelectedNodes(lvl == 0)];
                    for i = 1:numel(top_nodes)
                        top_node = top_nodes(i);
                        nodelist = [nodelist; top_node.Children];
                    end
                    tmp_list = nodelist;
                    for i = 1:numel(tmp_list)
                        node = tmp_list(i);
                        nodelist = [nodelist; node.Children];
                    end
                    
                    app.Tree.SelectedNodes = union(app.Tree.SelectedNodes, nodelist);
                    tmp_lvl = arrayfun(@(x) app.findNodeLevel(x), app.Tree.SelectedNodes);
                    tmp_idx = ( tmp_lvl == 0 );
                    app.Tree.SelectedNodes = app.Tree.SelectedNodes(~tmp_idx);
                case 1
                    idx = ( lvl == 1);
                    nodes = selectedNodes(idx);
                    nodelist = nodes;
                    for i = 1:numel(nodes)
                        nodelist = [nodelist; nodes(i).Children];
                        expand(nodes(i), 'all');
                    end
                    app.Tree.SelectedNodes = union(app.Tree.SelectedNodes, nodelist);
            end
            
            [app.matFileList, app.nodeList, app.nodeObjList] = app.findStatsFiles;
            disp('Done Selecting!')
            % Display Node Files in Selection ListBox:
            [~, files, ~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput', false);
            app.SelectfileListBox.Items = unique(files);
            app.SelectfileListBox.Value = {};
            app.SelectfileListBoxValueChanged;
        end

        % Value changed function: SelectfileListBox
        function SelectfileListBoxValueChanged(app, event)
            value = app.SelectfileListBox.Value;
            if isempty(value)
                cellfun(@(x) set(x, 'Icon', 'icon_WinFolder.png'), app.nodeList);
                return
            end
            [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
            idx = strcmp(all_files, value);
            cellfun(@(x) set(x, 'Icon', 'icon_WinFolder.png'), app.nodeList);
            cellfun(@(x) set(x, 'Icon', 'icon_check.png'), app.nodeList(idx));
           
            % Open .MAT files and show variable names:
            varnames = {};
            matFiles = app.matFileList(idx);
            for i = 1:numel(matFiles)
                mDt = matfile(matFiles{i});
                varnames = [varnames; mDt.obsID];
            end
            varnames = cellfun(@num2str, unique(varnames), 'UniformOutput', false);
            app.SelectobservationsListBox.Items = varnames;
        end

        % Value changed function: SelectButton
        function SelectButtonValueChanged(app, event)
            value = app.SelectButton.Value;
            if value
                app.SelectButton.Text = 'Undo selection';
                app.Tree.Enable = 'off';
                app.SelectfileListBox.Enable = 'off';
                app.SelectobservationsListBox.Enable = 'off';
                app.ExporttoCSVButton.Enable = 'on';
                app.AnalysisPanel.Visible = 'on';
                drawnow;
                obs_list = app.SelectobservationsListBox.Value;
                % Filter matFiles to get selected Nodes:
                [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
                idx = strcmp(all_files, app.SelectfileListBox.Value);
                gList = app.getMyGroup(app.nodeList(idx));
                statsFile = [app.SelectfileListBox.Value '.mat'];
                app.StatsObj = StatsManager(app.nodeObjList(idx), obs_list, gList, statsFile);
            else
                app.SelectButton.Text = 'Select';
                app.Tree.Enable = 'on';
                app.SelectfileListBox.Enable = 'on';
                app.SelectobservationsListBox.Enable = 'on';
                app.ExporttoCSVButton.Enable = 'off';
                app.AnalysisPanel.Visible = 'off';
                app.StatsObj = [];
            end
            
            
        end

        % Button pushed function: TableViewButton
        function TableViewButtonPushed(app, event)
            % Callback to create a figure with a table per observation
           
            % Delete currently opened figure before creating a new one:
            h = findall(0, 'Tag', 'TableFig');
            delete(h)
            % Get Array of tables:
            [tabList, condNames, b_isNameValid] = app.StatsObj.createTable;
            % Create figure and display tables:
            uf = uifigure('Name', 'Observation Table', 'NumberTitle', 'off', 'Position', [745 524 986 454], 'ToolBar',"none",...
                "MenuBar","none", 'Tag', 'TableFig');
            
            if ~b_isNameValid
                tbg = uitabgroup(uf,'Position', [5, 5, 750, 439]);
                cnd_tab = uitable(uf, 'Position',[760, 5, 222, 439], 'Data',condNames);
                cnd_tab.ColumnName = 'Cond ID';
            else
                tbg = uitabgroup(uf,'Position', [5, 5, 979, 439]);
            end

            for i = 1:numel(tabList) 
                t = uitab(tbg,'Title',app.StatsObj.obs_list{i});
                table = uitable('Parent', t, "Data",tabList{i}, "Position",[5 5 tbg.Position(3)-5 tbg.Position(4)-5]);
                table.ColumnSortable = true;
            end
            disp('Table created!')
        end

        % Callback function
        function createSummaryTableButtonPushed(app, event)
            tab = app.StatsObj.createTable('summary');

        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            % This callback calls StatsManager methods to create and save
            % all observation data into a .CSV file.
            [file, path] = uiputfile('*.csv', 'Export Data to .CSV file', 'obsData.csv');
            app.StatsObj.exportToCSV(fullfile(path, file));
            
        end

        % Button pushed function: GenAverageRSimagesButton
        function GenAverageRSimagesButtonPushed(app, event)
            % This is a custom callback for MV (temporary).
            % It creates a series of figures with the average Seed Pixel
            % Correlation Maps for each group of subjects per acquisition.
            avgRSimage(app.StatsObj.stats_data)
            
        end

        % Button pushed function: PlotCorrelationMatrixButton
        function PlotCorrelationMatrixButtonPushed(app, event)
            disp('Launch Plot Correlation Matrix')
            PlotCorrMatrix(app.StatsObj);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.Text = 'Open';
            app.ResetMenu = uimenu(app.ProjectMenu);
            app.ResetMenu.Text = 'Reset';
            app.GroupsMenu = uimenu(app.UIFigure);
            app.GroupsMenu.Text = 'Groups';
            app.CreateEditMenu = uimenu(app.GroupsMenu);
            app.CreateEditMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateNewGs_clbk, true);
            app.CreateEditMenu.Text = 'Create/Edit ';
            app.SavetofileMenu = uimenu(app.GroupsMenu);
            app.SavetofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveGsToFile_clbk, true);
            app.SavetofileMenu.Text = 'Save to file';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ItemsLabel = uilabel(app.UIFigure);
            app.ItemsLabel.Text = 'Items';
            app.SelectfileListBoxLabel = uilabel(app.UIFigure);
            app.SelectfileListBoxLabel.Text = 'Select file : ';
            app.SelectfileListBox = uilistbox(app.UIFigure);
            app.SelectfileListBox.Items = {};
            app.SelectfileListBox.ValueChangedFcn = createCallbackFcn(app, @SelectfileListBoxValueChanged, true);
            app.SelectfileListBox.Value = {};
            app.SelectobservationsLabel = uilabel(app.UIFigure);
            app.SelectobservationsLabel.Text = 'Select observation(s) :';
            app.SelectobservationsListBox = uilistbox(app.UIFigure);
            app.SelectobservationsListBox.Items = {};
            app.SelectobservationsListBox.Value = {};
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalysisPanel.Visible = 'off';
            app.TableViewButton = uibutton(app.AnalysisPanel, 'push');
            app.TableViewButton.ButtonPushedFcn = createCallbackFcn(app, @TableViewButtonPushed, true);
            app.TableViewButton.Text = 'Table View';
            app.GenAverageRSimagesButton = uibutton(app.AnalysisPanel, 'push');
            app.GenAverageRSimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GenAverageRSimagesButtonPushed, true);
            app.GenAverageRSimagesButton.Text = {'Gen. Average'; ' RS images'};
            app.CustomFunctionsVannisLabLabel = uilabel(app.AnalysisPanel);
            app.CustomFunctionsVannisLabLabel.Text = 'Custom Functions (Vanni''s Lab)';
            app.PlotCorrelationMatrixButton = uibutton(app.AnalysisPanel, 'push');
            app.PlotCorrelationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCorrelationMatrixButtonPushed, true);
            app.PlotCorrelationMatrixButton.Text = {'Plot Correlation'; 'Matrix'};
            app.SelectButton = uibutton(app.UIFigure, 'state');
            app.SelectButton.ValueChangedFcn = createCallbackFcn(app, @SelectButtonValueChanged, true);
            app.SelectButton.Text = 'Select';
            app.ExporttoCSVButton = uibutton(app.UIFigure, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Enable = 'off';
            app.ExporttoCSVButton.Text = 'Export to .CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stats_module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end