classdef acqMerging_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFIgure                 matlab.ui.Figure
        MergeButton              matlab.ui.control.Button
        ApplyButton              matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        FromDropDownLabel        matlab.ui.control.Label
        FromDropDown             matlab.ui.control.DropDown
        toDropDownLabel          matlab.ui.control.Label
        toDropDown               matlab.ui.control.DropDown
        SelectacqindicesLabel    matlab.ui.control.Label
        MergedAcquisitionsPanel  matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        StatsObj % Handle to a StatsManager object
        originalList % Array of acquisition indices from original data
        currentList % list of merged acquisitions.
        availableList % List of available indices to merge.
        selectList % List of pairs of indices selected from the DropDown menus.
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, StatsObj)
            app.StatsObj = StatsObj;
            app.originalList = app.StatsObj.getAcqIndexList('original');
            app.currentList = app.StatsObj.getAcqIndexList('current');
            app.availableList = app.StatsObj.getAcqIndexList('available');
            % Populate GUI elements
            disp("Populating GUI...")
            %%%%%%%%%%%%%%%%%
            app.FromDropDown.Tag = 'fromDD'; app.toDropDown.Tag = 'toDD';
            app.FromDropDown.Items = string(app.availableList); app.FromDropDown.Value = string(min(app.availableList));        
            app.toDropDown.Items = string(app.availableList); app.toDropDown.Value = string(max(app.availableList));
            if ~isempty(app.currentList)
                txt = {};
                for ii = 1:length(app.currentList)
                    txt{ii} = sprintf('(%d to %d)',app.currentList{ii}(1), app.currentList{ii}(end));
                end
                app.TextArea.Value = txt;
            else
                app.TextArea.Value = {''};
            end
            
            if isempty(app.availableList)
                app.FromDropDown.Enable = "off";
                app.toDropDown.Enable =  'off';
            end
        end

        % Value changed function: FromDropDown, toDropDown
        function DDwnValueChanged(app, event)
            from_val = str2double(app.FromDropDown.Value);           
            to_val = str2double(app.toDropDown.Value);
            if event.Source == app.FromDropDown
                if from_val > to_val
                    app.FromDropDown.Value = event.PreviousValue;
                end
            else
                if to_val < from_val
                    app.toDropDown.Value = event.PreviousValue;
                end                               
            end
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            from_val = str2double(app.FromDropDown.Value);           
            to_val = str2double(app.toDropDown.Value);
            currRange = str2double(unique([app.FromDropDown.Items, app.toDropDown.Items]));
            if from_val >= to_val || ~all(ismember(from_val:to_val, currRange))
                return
            end
            % Update merged list:
            txt = app.TextArea.Value; if isempty(txt{:}); txt = {};end
            app.TextArea.Value = [txt; {sprintf('(%s to %s)', app.FromDropDown.Value, app.toDropDown.Value)}];
            app.selectList = [app.selectList; str2double(app.FromDropDown.Value), str2double(app.toDropDown.Value)];
            % Remove merged items from DropDown menus:
            
            selRange = from_val: to_val;
            remRange = setdiff(currRange, selRange);            
            if isempty(remRange)
                app.FromDropDown.Enable = 'off';
                app.toDropDown.Enable = 'off';                
                app.toDropDown.Items = {};
                app.MergeButton.Enable = 'off';
                return
            end
            app.FromDropDown.Items = string(remRange);
            app.toDropDown.Items = string(remRange); app.toDropDown.Value = app.toDropDown.Items(end);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.TextArea.Value = {''};
            app.selectList = [];
            app.FromDropDown.Items = string(app.originalList); app.FromDropDown.Value = app.FromDropDown.Items(1);
            app.toDropDown.Items = string(app.originalList); app.toDropDown.Value = app.toDropDown.Items(end);
            app.MergeButton.Enable = 'on';
            app.FromDropDown.Enable = 'on';
            app.toDropDown.Enable = 'on';
            app.StatsObj.resetAvgIndex; % Reset list in StatsObj.
            w = msgbox('Acquisition list reset to original!');
            waitfor(w)
            delete(app);
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isempty(app.selectList)
                return
            end
            for ii = 1:size(app.selectList,1)
                app.StatsObj.setAcquisitionRange(app.selectList(ii,:));
            end
            delete(app)
        end

        % Close request function: UIFIgure
        function UIFIgureCloseRequest(app, event)
            disp('Operation Cancelled by User')
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFIgure = uifigure('Visible', 'off');
            app.UIFIgure.Name = 'Acq. Merger';
            app.UIFIgure.CloseRequestFcn = createCallbackFcn(app, @UIFIgureCloseRequest, true);
            app.MergeButton = uibutton(app.UIFIgure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge!';
            app.ApplyButton = uibutton(app.UIFIgure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.resetButton = uibutton(app.UIFIgure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.FromDropDownLabel = uilabel(app.UIFIgure);
            app.FromDropDownLabel.Text = 'From';
            app.FromDropDown = uidropdown(app.UIFIgure);
            app.FromDropDown.ValueChangedFcn = createCallbackFcn(app, @DDwnValueChanged, true);
            app.toDropDownLabel = uilabel(app.UIFIgure);
            app.toDropDownLabel.Text = 'to';
            app.toDropDown = uidropdown(app.UIFIgure);
            app.toDropDown.ValueChangedFcn = createCallbackFcn(app, @DDwnValueChanged, true);
            app.SelectacqindicesLabel = uilabel(app.UIFIgure);
            app.SelectacqindicesLabel.Text = 'Select acq. indices:';
            app.MergedAcquisitionsPanel = uipanel(app.UIFIgure);
            app.MergedAcquisitionsPanel.Title = 'Merged Acquisitions';
            app.TextArea = uitextarea(app.MergedAcquisitionsPanel);
            % Show the figure after all components are created
            app.UIFIgure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acqMerging_tool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFIgure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFIgure)
        end
    end
end