classdef data_grouping_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataGroupingtoolUIFigure       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadgroupsMenu                 matlab.ui.container.Menu
        SaveGroupsMenu                 matlab.ui.container.Menu
        DataFilterMenu                 matlab.ui.container.Menu
        filterbasedatabyMenu           matlab.ui.container.Menu
        QueryMenu                      matlab.ui.container.Menu
        DateMenu                       matlab.ui.container.Menu
        EventMenu                      matlab.ui.container.Menu
        resetbaseMenu                  matlab.ui.container.Menu
        erasebaseMenu                  matlab.ui.container.Menu
        restorebaseanderasegroupsMenu  matlab.ui.container.Menu
        GroupoptionsMenu               matlab.ui.container.Menu
        changegrouptypeMenu            matlab.ui.container.Menu
        addnewgroupMenu                matlab.ui.container.Menu
        removegroupMenu                matlab.ui.container.Menu
        erasegroupcontentMenu          matlab.ui.container.Menu
        Tree                           matlab.ui.container.Tree
        BasedataLabel                  matlab.ui.control.Label
        movetoDropDownLabel            matlab.ui.control.Label
        movetoDropDown                 matlab.ui.control.DropDown
        GoButton                       matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        group_settingsApp % handle to group_settings App.
        MainApp % handle to stats_module_main App.
        treeList = [] % array of UITree objects.
        treeNames % Names of each tree.
        SearchBuilderApp % handle to SeachBuilder_dlgBox App.
        FilterStructFromSearchBuilder % Description
        baseIdxList % current index list of objects from Base UITree.
        currentDate = datetime('now') % Date chosen by date picker
        dateSearchDir = 'before' % Direction of date search.
        groupIdxList = {}; % cell array of Protocol's indes lists for each tree.
    end
    
    properties (Access = public)
        groupType = 'inter-subject' % Type of grouping ("intra-subject" OR "inter-subject)
        Ngroups = 2 % Number of groups;
    end
    
    methods (Access = private)
        
        function fillUITree(app, Tree, tree_name, idxList)
            Tree.Enable = 'off';
            delete(Tree.Children)
%             root = uitreenode(Tree, 'Text', tree_name,'Icon', 'icon_WinFolder.png');
            root = uitreenode(Tree, 'Text', tree_name);
            subjIdx = unique(idxList(:,1));
            for i = 1:numel(subjIdx)
                ID = app.MainApp.ProtocolObj.Array.ObjList(subjIdx(i)).ID;
%                 subjNode = uitreenode(root, 'Text', ID, 'Icon', 'icon_WinFolder.png');
                subjNode = uitreenode(root, 'Text', ID);
                idx = idxList(:,1) == subjIdx(i);
                acqIdx = unique(idxList(idx,2), 'rows');
                for j = 1:size(acqIdx,1)
                    acqID = app.MainApp.ProtocolObj.Array.ObjList(subjIdx(i)).Array.ObjList(acqIdx(j)).ID;
%                     uitreenode(subjNode, 'Text', acqID, 'Icon', 'icon_WinFolder.png');
                    uitreenode(subjNode, 'Text', acqID);
                end
            end
            expand(Tree.Children);
            Tree.Enable = 'on';
            Tree.Multiselect = 'on';
        end
        
        function createUITree(app, tree_name, tree_position)
            % This function creates a uitree at coordinates
            % TREE_POSITION with label TREE_NAME and the associated buttons.
            
            % Create uitree:
            tree = uitree("Parent", app.DataGroupingtoolUIFigure, "Position", tree_position);
            tree.Enable = 'off';
            tree.Multiselect = 'on';
            % Create uitree label:
            tree_label_pos = tree_position;
            tree_label_pos(2) = tree_position(2) + tree_position(4) + 5;
            tree_label_pos(4) = 18;
            tree_label = uieditfield(app.DataGroupingtoolUIFigure, "FontWeight", "bold","BackgroundColor", [.9 .9 .9], "Position", tree_label_pos);
            tree_label.Value = tree_name;
            tree_label.Tooltip = 'click to edit';
            tree_label.ValueChangedFcn = @app.updateTreeNames;
            % Create label, drop-down menu and GO button:
            % label
            label_pos(1) = tree_position(1);
            label_pos(2) = tree_position(2) - 26;
            label_pos(3) = 50;
            label_pos(4) = 20;
            label = uilabel("Parent",app.DataGroupingtoolUIFigure, 'Text', 'move to', "Position", label_pos);
            % drop-down menu
            drop_pos(1) = label_pos(1) + label_pos(3) + 10;
            drop_pos(2) = label_pos(2);
            drop_pos(3) = 77;
            drop_pos(4) = label_pos(4);
            drop = uidropdown("Parent",app.DataGroupingtoolUIFigure, "Position", drop_pos);
            % button:
            but_pos(1) = tree_position(1) + tree_position(3) - 42;
            but_pos(2) = drop_pos(2);
            but_pos(3) = 42;
            but_pos(4) = drop_pos(4);
            but = uibutton(app.DataGroupingtoolUIFigure,"push","Text", 'Go!', "BackgroundColor", [.9 .9 .9],'Position',but_pos);
            but.ButtonPushedFcn = @app.GoButtonPushed;
            %             but.UserData = tree_label;
            % Use USERDATA property of UITree to store label and drop-down
            % objects:
            tree.UserData = {tree_label, drop, but, label};
            app.treeList = [app.treeList, tree];
            tree.Enable = 'on';
        end
        
        function showUITrees(app)
            % SHOWUITREES displays the UITrees on the UIFigure.
            nTrees = app.Ngroups;
            if strcmp(app.groupType, 'intra-subject')
                tree_names = {'group 1a', 'group 1b'};
            else
                for i = 1:app.Ngroups
                    tree_names{i} = app.groupIdxList(i).Name{1};
                end
            end
%             pos = [10 86.3198  210 500.6802];
            pos = app.Tree.InnerPosition;
            % Create Trees
            for i = 1:nTrees
                pos(1) = pos(1) + pos(3) + 10;
                app.createUITree(tree_names{i}, pos);
            end
            % Populate drop-down menus
            Items = arrayfun(@(x) x.UserData{1}.Value, app.treeList, 'UniformOutput', false);
            Items = ['Base' Items];
            app.treeNames = Items;
            app.updateDropDownItems;
            app.updateGroupMenuItems;
        end
        
        function updateDropDownItems(app)
            % UPDATEDROPDOWNITEMS uses the property treeNames to populate the
            % drop-down menus of each UITree displayed in the figure;
            app.movetoDropDown.Items = setdiff(app.treeNames, 'Base');
            for i = 1:numel(app.treeList)
                treeName = app.treeList(i).UserData{1}.Value;
                items = setdiff(app.treeNames, treeName);
                app.treeList(i).UserData{2}.Items = items;
            end
        end
        
        function updateGroupMenuItems(app)
            % UPDATEREMOVEGROUPMENUITEMS updates the list of groups inside
            % "Group Options" >> "Remove group" menu.
            gNames = setdiff(app.treeNames, {'Base'});
            delete(app.removegroupMenu.Children);
            delete(app.erasegroupcontentMenu.Children);
            for i = 1:numel(gNames)
                uimenu("Parent",app.removegroupMenu, "Text", gNames{i},'MenuSelectedFcn', @app.delGroup)
                uimenu("Parent",app.erasegroupcontentMenu, "Text", gNames{i},'MenuSelectedFcn', @app.eraseGroupNodes)
            end
        end
        
        % Helper functions (callbacks for programmatically created
        % components
        function updateTreeNames(app, src, event)
            % UPDATETREENAMES updates the names of displayed trees. It is
            % the callback of the editable fields of the programmatically
            % created trees.
            
            % Check if new name is valid (not empty and different from
            % other names):
            idx = strcmp(app.treeNames, event.PreviousValue);
            otherNames = app.treeNames(~idx);
            if isempty(event.Value) || any(strcmp(otherNames, event.Value))
                errordlg('Invalid group name. Must be non-empty and different from other names.')
                src.Value = event.PreviousValue;
                return
            end
            app.treeNames{idx} = event.Value;
            app.updateDropDownItems;
            app.updateGroupMenuItems;
            % Change Tree root name:
            idx_names = arrayfun(@(x) strcmp(x.UserData{1}.Value, event.Value), app.treeList);
            if any(idx_names) && ~isempty(app.treeList(idx_names).Children)
                app.treeList(idx_names).Children.Text = event.Value;
            end
        end
        
        function delGroup(app, src, ~)
            % DELGROUP deletes an UITREE and the associated components.
            gNameList = app.treeNames(2:end);
            gName = src.Text;
            % Check if there will be at least 2 groups left after deletion:
            if (numel(gNameList) - 1) < 2 & ~startsWith(app.groupType, 'descript')
                uialert(app.DataGroupingtoolUIFigure,'A minimum of 2 groups is allowed.', 'Group deletion failed!','Icon','warning');
                return
            elseif numel(gNameList) == 1 
                uialert(app.DataGroupingtoolUIFigure,'Cannot delete the last group.', 'Group deletion failed!','Icon','warning');            
                return
            end
            
            % Confirm deletion:
            answer = uiconfirm(app.DataGroupingtoolUIFigure,['Delete ' gName ' and all its contents?'],'Confirm deletion','Icon','warning');
            if strcmp(answer, 'Cancel')
                return
            end
            % Get index of the tree to be deleted:
            idx = strcmp(gNameList, gName);
            % Check for existing data inside tree and move them back to the
            % Base tree:
            if ~isempty(app.treeList(idx).Children)
                b_hasData = ~isempty(app.treeList(idx).Children.Children);
            else
                b_hasData = false;
            end
            if b_hasData
                app.moveNodes(app.Tree, app.Tree.Children(1).Text, app.treeList(idx).Children.Children);
            end
            
            % delete tree and associated components:
            delete(app.treeList(idx).UserData{4})
            delete(app.treeList(idx).UserData{3})
            delete(app.treeList(idx).UserData{2})
            delete(app.treeList(idx).UserData{1})
            delete(app.treeList(idx));
            app.treeList(idx) = [];
            idx = strcmp(app.treeNames, gName);
            app.treeNames(idx) = [];
            tree_position = app.Tree.Position;
            % Reposition remaining trees:
            for i = 1:length(app.treeList)
                tree_position(1) = tree_position(1) + tree_position(3) + 10;
                % Tree Label Position
                tree_label_pos = tree_position;
                tree_label_pos(2) = tree_position(2) + tree_position(4) + 5;
                tree_label_pos(4) = 18;
                % label position
                label_pos(1) = tree_position(1);
                label_pos(2) = tree_position(2) - 26;
                label_pos(3) = 50;
                label_pos(4) = 20;
                % drop-down menu position
                drop_pos(1) = label_pos(1) + label_pos(3) + 10;
                drop_pos(2) = label_pos(2);
                drop_pos(3) = 77;
                drop_pos(4) = label_pos(4);
                % button position
                but_pos(1) = tree_position(1) + tree_position(3) - 42;
                but_pos(2) = drop_pos(2);
                but_pos(3) = 42;
                but_pos(4) = drop_pos(4);
                app.treeList(i).Position = tree_position;
                app.treeList(i).UserData{1}.Position = tree_label_pos;
                app.treeList(i).UserData{2}.Position = drop_pos;
                app.treeList(i).UserData{3}.Position = but_pos;
                app.treeList(i).UserData{4}.Position = label_pos;
            end
            app.updateDropDownItems;
            app.updateGroupMenuItems;
            app.Ngroups = app.Ngroups - 1;
        end
    
        function eraseGroupNodes(app,src,~)
            % This method erases all nodes from the selected group.
            % This is a custom callback for the menu button under
            % "erasegroupcontentMenu".
            answer = uiconfirm(app.DataGroupingtoolUIFigure, ['You are about to erase all content from the group ' src.Text '. Proceed?'], 'Delete group items?');
            if strcmp(answer, 'OK')
                disp('deleting group items...');                
                idx = arrayfun(@(x) strcmp(src.Text, x.Children.Text), app.treeList);
                arrayfun(@(x) delete(x.Children.Children), app.treeList(idx));
            end
            
        end
        function GoButtonPushed(app, src, ~)
            % GOBUTTTONPUSHED is executed when the "Go!" button from programmatically generated trees is pushed.
            idx = arrayfun(@(x) isequal(x.UserData{3}, src), app.treeList);
            sourceTree= app.treeList(idx);
            selectedNodes = sourceTree.SelectedNodes;
            targetTreeName = app.treeList(idx).UserData{2}.Value;
            if strcmp(targetTreeName, 'Base')
                targetTree = app.Tree;
            else
                treeNameList = arrayfun(@(x) x.UserData{1}.Value, app.treeList, 'UniformOutput', false);
                idx_target = strcmp(treeNameList, targetTreeName);
                targetTree = app.treeList(idx_target);
            end
            selectedNodes = app.checkForDuplicates(selectedNodes,[targetTree]);
            app.moveNodes(targetTree, targetTreeName, selectedNodes);
            expand(targetTree.Children);
        end
        
        function moveNodes(~, targetTree, targetTreeName, selectedNodes)
            % Helper function for "Go!" buttons callbacks

            if isempty(targetTree.Children)
%                 root = uitreenode(targetTree, 'Text', targetTreeName,'Icon', 'icon_WinFolder.png');
                root = uitreenode(targetTree, 'Text', targetTreeName);
            else
                root = targetTree.Children;
            end
            for i = 1:numel(selectedNodes)
                node = selectedNodes(i);
                % Avoid moving the whole group:
                if isa(node.Parent, 'matlab.ui.container.Tree')
                    continue
                end
                % Find node's parents:
                myParentName = node.Parent.Text;
                % 
                if isa(node.Parent.Parent, 'matlab.ui.container.Tree')
                    myNewParent = root;
                else
                    if ~isempty(root.Children)
                        idx = strcmp(myParentName, {root.Children.Text});
                        if sum(idx) == 1
                            myNewParent = root.Children(idx);
                        elseif sum(idx) > 1
                            errordlg('Error! Found more than one Parent. Debug this!')
                            return
                        else
                            newNode = uitreenode(root, 'Text', myParentName);
                            myNewParent = newNode;
                        end
                    else
                        newNode = uitreenode(root, 'Text', myParentName);
                        myNewParent = newNode;
                    end
                end
                
                % if a subject node already exists in target Tree, merge
                % them:
                if ~isempty(root.Children)
                    idx = strcmp(node.Text, {root.Children.Text});
                    if isequal(myNewParent, root) && any(idx)
                        % Transfer acquisitions from source node to target node:
                        idxChild = ~ismember({node.Children.Text}, {root.Children(idx).Children.Text});                        
                        arrayfun(@(x) set(x,'Parent', root.Children(idx)), node.Children(idxChild))
                        % Delete source node if all acquisitions were
                        % transfered:
                        if all(idxChild)
                            delete(node)
                        end
                    elseif isequal(node.Parent.Children, node)
                        oldParent = node.Parent;
                        if isa(oldParent.Parent, 'matlab.ui.container.Tree')
                            node.Parent = root;
                        else
                            node.Parent = myNewParent;
                            delete(oldParent)
                        end
                    elseif ~isempty(myNewParent.Children)
                        myNewBrother_names = {myNewParent.Children.Text};
                        idx = ~ismember(node.Text, myNewBrother_names);
                        if idx
                            node.Parent = myNewParent;
                        end
                    else
                        node.Parent = myNewParent;
                    end
                else
                    node.Parent = myNewParent;
                end
            end
        end
        
        function filterDataByDate(app)
            idxList = app.MainApp.ProtocolObj.Idx_Filtered;
            elemArr = app.MainApp.ProtocolObj.extractFilteredObjects(2);
            dateArr = cellfun(@(x) datetime(x.Start_datetime), elemArr, 'UniformOutput',true);
            switch app.dateSearchDir
                case 'before'
                    idx = dateArr < app.currentDate;
                otherwise
                    idx = dateArr >= app.currentDate;
            end
            app.MainApp.ProtocolObj.Idx_Filtered = idxList(idx,:);
            app.fillUITree(app.Tree, 'Base', app.MainApp.ProtocolObj.Idx_Filtered);
        end
        
        function out = genIdxList(app)
            % This function generates a list of indices in "protocol" object corresponding to the selected
            % elements in the UITREE.
            
            out = [];
            % Check if at least one group has child nodes:
            idx = true(1,length(app.treeList));
            for i = 1:numel(app.treeList)
                tree = app.treeList(i);
                if isempty(tree.Children) || isempty(tree.Children.Children)
                    idx(i) = false;
                end
            end
            
            % Warn user if less than 2 groups has children:
            if ~strcmp(app.groupType, 'descriptive stats') && sum(idx) < 2
                uialert(app.DataGroupingtoolUIFigure, 'Minimum of 2 groups required! Transfer elements from Base to at least 2 groups.',...
                    'Failed to save groups', 'Modal',true, "Icon","error", 'CloseFcn','uiresume(gcbf');
                uiwait(app.DataGroupingtoolUIFigure)
                return
            elseif strcmp(app.groupType, 'descriptive stats') && sum(idx) == 0
                uialert(app.DataGroupingtoolUIFigure, ['The group is empty. Transfer elements from Base to  "' app.treeNames{2} '" and try again.'],...
                    'Failed to save groups', 'Modal',true, "Icon","error", 'CloseFcn','uiresume(gcbf)');
                uiwait(app.DataGroupingtoolUIFigure)
                return
            end
            % create structure containing idxList of each group and group
            % names:
            treeArr = app.treeList(idx);
            out = struct();
            for i = 1:length(treeArr)
                tree = treeArr(i);
                rootName = tree.Children.Text;
                prot_idx_list = [];
                subjList = {tree.Children.Children.Text};
                idx_subj = cellfun(@(x) app.MainApp.ProtocolObj.Array.findElement('ID', x, 'strcmp'), subjList, 'UniformOutput', false);
                for j = 1:numel(subjList)
                    acqList = {tree.Children.Children(j).Children.Text};
                    idx_acq = cellfun(@(x) app.MainApp.ProtocolObj.Array.ObjList(idx_subj{j}).Array.findElement('ID', x, 'strcmp'), acqList, 'UniformOutput', false);
                    idx_mod = cellfun(@(x) 1:numel(app.MainApp.ProtocolObj.Array.ObjList(idx_subj{j}).Array.ObjList(x)), idx_acq, 'UniformOutput', false);
                    [a,b,c] = cellfun(@(x,y,z)  ndgrid(x,y,z), repmat(idx_subj(j), 1, length(idx_acq)), idx_acq, idx_mod, 'UniformOutput',false);
                    tmp = [a{:}; b{:}; c{:}];
                    tmp = reshape(tmp,3,[]);
                    prot_idx_list = [prot_idx_list; tmp'];
                end                
                out(i).idxList = sortrows(prot_idx_list,[1 2]);
                out(i).Name = repmat({rootName},1,size(out(i).idxList,1));
            end
                        
        end
        
        function sendNodesToGroups(app)
            % This function was created just to run at Startup in order to
            % pre-load existing groups from MainApp.
            
            for i = 1:app.Ngroups
                gTree = app.treeList(i);
                gTree_name = app.groupIdxList(i).Name{1};
                idxList = app.groupIdxList(i).idxList;
                subjList = arrayfun(@(x) app.MainApp.ProtocolObj.Array.ObjList(x).ID, idxList(:,1), 'UniformOutput', 0);
                acqList = arrayfun(@(x,y) app.MainApp.ProtocolObj.Array.ObjList(x).Array.ObjList(y).ID, idxList(:,1), idxList(:,2), 'UniformOutput', 0);
                nodeList = getNodeFromName(app.Tree, subjList, acqList);
                app.moveNodes(gTree,gTree_name,nodeList);drawnow
            end
            disp('Finished to send nodes to groups!')
            function nodes = getNodeFromName(tree,sList,aList)
                nodes = [];
                subjs_all = arrayfun(@(x) x.Text, tree.Children.Children, 'UniformOutput',0);
                subjs = unique(sList);
                for ii = 1:numel(subjs)
                    idx_s = strcmp(subjs{ii},subjs_all);
                    acqs_all = arrayfun(@(x) x.Text, tree.Children.Children(idx_s).Children, 'UniformOutput',0);
                    acqs = aList(strcmp(sList,subjs{ii}));
                    for jj = 1:numel(acqs)
                       idx_a = strcmp(acqs{jj}, acqs_all);
                       nodes = [nodes; tree.Children.Children(idx_s).Children(idx_a)]; %#ok
                    end
                end
            end
            
        end
        
        function nodesOut= checkForDuplicates(~, selectedNodes, targetTrees)            
            % Check if the selected Nodes exist already in one of the
            % UITrees in "targetTrees:
            
            disp('Verifying duplicates...')          
            % Get Acquisition-level nodes from Base UITREE:
            baseNodes = [];
            for i = 1:length(selectedNodes)
                if ~isempty(selectedNodes(i).Children)
                    baseNodes = [baseNodes;selectedNodes(i).Children];
                else
                    baseNodes = [baseNodes;selectedNodes(i)];
                end
            end
            nodesOut = baseNodes;
            % Compare with existing nodes in treeList:
            localNodes = [];
            for i = 1:length(targetTrees)
                % Skip empty UITREE:                   
                if isempty(targetTrees(i).Children) 
                    return
                elseif isempty(targetTrees(i).Children(1).Children)
                    return
                end
                
                for j = 1:numel(targetTrees(i).Children(1).Children)
                    localNodes = [localNodes; targetTrees(i).Children(1).Children(j).Children];
                end                
            end
            
            idx_acq = ismember({baseNodes.Text}', {localNodes.Text}');
            baseTxt = arrayfun(@(x) x.Parent.Text, baseNodes, 'UniformOutput',false);
            localTxt = arrayfun(@(x) x.Parent.Text, localNodes, 'UniformOutput',false);
            idx_subj = ismember(baseTxt, localTxt);
            % Remove acquisition nodes that already exist in one of the UITRees in treeList:
            nodesOut = baseNodes(~all([idx_acq,idx_subj],2));           
        end
        
        
    end
    
    methods (Access = public)
        
        function filterSearch(app, FilterStruct)
            app.MainApp.ProtocolObj.FilterStruct = FilterStruct;
            app.FilterStructFromSearchBuilder = FilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.Tree.Enable = "off"; drawnow
            idxList = app.MainApp.ProtocolObj.Idx_Filtered;
            if isempty(idxList)
                msgbox('Your query returned zero results. Change query terms and try again!', ...
                    'Advanced Search','warn', "modal");
                app.Tree.Enable ="on";
                return
            end
            app.baseIdxList = idxList;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            app.DataGroupingtoolUIFigure.Visible = 'off';
            % Update UIFigure position:
            movegui(app.DataGroupingtoolUIFigure, 'center');
            app.DataGroupingtoolUIFigure.Visible = 'on';
            %
            app.MainApp = mainApp;                        
            app.Tree.Multiselect = 'on';
            % Reset protocol's Idx list:
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            % Populate UITree:
            app.fillUITree(app.Tree, 'Base', app.MainApp.ProtocolObj.Idx_Filtered);
            % Set group types from Main App info:
            app.groupType = app.MainApp.groupInfo.gType;
            % Update Base data Label:
            app.BasedataLabel.Text = ['Base data (' app.groupType ')'];
            app.Ngroups = app.MainApp.groupInfo.nGroups;
            app.groupIdxList = app.MainApp.groupInfo.gIdxList;
            app.showUITrees;
            % Move nodes to their respective groups when the data from Main App is already grouped:                     
            if ~isequaln(app.MainApp.ProtocolObj.Idx_Filtered, app.groupIdxList)
                % Automate "move to group" button... %
                app.sendNodesToGroups;
            end
            app.DataGroupingtoolUIFigure.Visible = 'on';            
        end

        % Menu selected function: changegrouptypeMenu
        function changegrouptypeMenuSelected(app, event)
            
            orig_Ngroups = app.Ngroups;
            orig_gType = app.groupType;            
            app.group_settingsApp = group_settings(app, false);
            waitfor(app.group_settingsApp);
            if startsWith(app.groupType, 'descr') & orig_Ngroups > 1
                uialert(app.DataGroupingtoolUIFigure, 'Cannot change group to descriptive statistics with more than one group. Remove extra groups and try again!', ...
                    'Failed to change group type');
                app.Ngroups = orig_Ngroups;
                app.groupType = orig_gType;
                return
            elseif startsWith(app.groupType, 'intra') & orig_Ngroups > 2
                uialert(app.DataGroupingtoolUIFigure, 'Cannot change group to intra-subject. A maximum of 2 groups accepted.Remove extra groups and try again!', ...
                    'Failed to change group type');
                app.Ngroups = orig_Ngroups;
                app.groupType = orig_gType;
                return
%             elseif startsWith(app.groupType, 'inter') & orig_Ngroups < 2
%                 uialert(app.DataGroupingtoolUIFigure, 'Cannot change group to inter-subject. A minimum of 2 groups is needed. Add groups and try again!', ...
%                     'Failed to change group type');
%                 app.Ngroups = orig_Ngroups;
%                 app.groupType = orig_gType;
%                 return
            end
            % Update Base data Label:
            if orig_Ngroups< app.Ngroups
                for i = 1:(app.Ngroups - orig_Ngroups)
                    app.addnewgroupMenuSelected
                end
            end
            app.BasedataLabel.Text = ['Base data (' app.groupType ')'];
        end

        % Close request function: DataGroupingtoolUIFigure
        function DataGroupingtoolUIFigureCloseRequest(app, event)
            % Close the figure and DO NOT apply changes to the Main App:
            if strcmp(event.EventName, 'Close')
                answer = uiconfirm(app.DataGroupingtoolUIFigure, 'Close app? All changes will be discarded!', 'Discard changes?');
                if strcmp(answer, 'Cancel')
                    disp('Operation cancelled by User')
                    return
                end
            end
            app.MainApp.UIFigure.Visible = 'on';
            delete(app.group_settingsApp)
            delete(app)
        end

        % Button pushed function: GoButton
        function BaseGoButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            targetTreeName = app.movetoDropDown.Value;
            treeNameList = arrayfun(@(x) x.UserData{1}.Value, app.treeList, 'UniformOutput', false);
            idx = strcmp(targetTreeName, treeNameList);
            targetTree = app.treeList(idx);
            
            % Check if the selected Nodes exist already in one of the
            % UITrees:
            selectedNodes = app.checkForDuplicates(selectedNodes,app.treeList);
            % Move remaining nodes to target tree:            
            app.moveNodes(targetTree, targetTreeName, selectedNodes);
            expand(targetTree.Children);
        end

        % Menu selected function: QueryMenu
        function QueryMenuSelected(app, event)
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.MainApp.ProtocolObj.FilterStruct);
            waitfor(app.SearchBuilderApp)
            app.fillUITree(app.Tree, 'Base', app.baseIdxList);
        end

        % Menu selected function: restorebaseanderasegroupsMenu
        function restorebaseanderasegroupsMenuSelected(app, event)
            % clear Protocol query Filter:
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.baseIdxList = app.MainApp.ProtocolObj.Idx_Filtered;
            % Reset Base tree:
            app.fillUITree(app.Tree, 'Base', app.baseIdxList);
            % Delete existing trees:
            for i = 1:numel(app.treeList)
                delete(app.treeList(i).Children);
            end
        end

        % Menu selected function: addnewgroupMenu
        function addnewgroupMenuSelected(app, event)
            if strcmp(app.groupType, 'intra-subject') & numel(app.treeList) == 2
                warndlg('Cannot add groups for intra-subject comparisons. Maximum of 2 groups allowed. Change group type and try again!',...
                    'Already reached maximum number of groups');
                return
            elseif startsWith(app.groupType, 'descr')
                warndlg('Only one group is allowed for descriptive statistics. Change group type and try again!', 'Already reached maximum number of groups')
                return
            end
            groupName = ['group ' num2str(numel(app.treeList) + 1)];
            pos = app.treeList(end).Position;
            pos(1) = pos(1) + pos(3) + 10;
            app.createUITree(groupName, pos);
            app.treeNames = [app.treeNames groupName];
            app.updateDropDownItems;
            app.updateGroupMenuItems;
            app.Ngroups = app.Ngroups + 1;
        end

        % Menu selected function: DateMenu
        function DateMenuSelected(app, event)
            dt_app = uifigure('Name', 'Pick a Date', 'Position', [1 1 460 300],'CloseRequestFcn', {@getMyDate, app}); movegui(dt_app, 'center');
            d = uidatepicker(dt_app,'Position',[18 225 150 22], 'ValueChangedFcn', @dispMsg);
            d.DisplayFormat = 'dd-MM-yyyy';
            d.Value = datetime('today');
            d.Tooltip = 'Dates are inclusive and time is set to midnight!';
            bg = uibuttongroup("Parent", dt_app, "Position",[18 100 100 60]);
            uiradiobutton(bg, "Text", 'before', 'Position',[10 35 90 15]);
            uiradiobutton(bg, "Text", 'after', 'Position',[10 10 90 15]);
            function getMyDate(src, ~,app)
                dt_comp = src.Children;
                app.currentDate = dt_comp(2).Value;
                app.dateSearchDir = dt_comp(1).SelectedObject.Text;
                delete(src);
            end
            function dispMsg(src,~)
                src.Parent.Name = 'Close Figure to apply changes!';
            end
            waitfor(dt_app)
            app.filterDataByDate;
        end

        % Menu selected function: EventMenu
        function EventMenuSelected(app, event)
            event_file = fullfile(app.MainApp.ProtocolObj.SaveDir, 'Text_events.mat');
            if ~isfile(event_file)
                uialert(app.DataGroupingtoolUIFigure,['Text_events.mat file not found in ' app.MainApp.ProtocolObj.SaveDir])
                return
            end
            a = load(event_file);
            a.timestamps.Format = 'dd-MMM-yyyy HH:mm:ss';
            t_data = table(a.eventID, a.timestamps, false(length(a.timestamps),1), 'VariableNames', {'Event', 'Date', 'Choice'});
            
            ev_app = uifigure('Name', 'Choose an event from list', 'CloseRequestFcn', {@getMyEvent, app});
            
            tab = uitable("Parent", ev_app, 'Data', t_data,'Position', [20 20 360 460]);
            tab.ColumnSortable = [false true true];
            tab.ColumnEditable = [false false true];
            tab.Tooltip = 'Check box to select row.';
            tab.CellEditCallback = @checkBox;
            bg = uibuttongroup("Parent", ev_app, "Position",[440 400 100 60]);
            uiradiobutton(bg, "Text", 'before', 'Position',[10 35 90 15]);
            uiradiobutton(bg, "Text", 'after', 'Position',[10 10 90 15]);
            function checkBox(src, event)
                indx = event.Indices(1);
                src.Data.Choice = false(length(src.Data.Choice),1);
                src.Data.Choice(indx) = true;
            end
            
            function getMyEvent(src, ~,app)
                idx = src.Children(2).Data.Choice;
                if sum(idx) == 0
                    uialert(app.DataGroupingtoolUIFigure, 'No event was selected. Operation cancelled!','Operation Cancelled', 'Icon',"error");
                    app.currentDate = datetime('now');
                    app.dateSearchDir = 'before';
                else
                    app.currentDate = src.Children(2).Data.Date(idx);
                    app.dateSearchDir = src.Children(1).SelectedObject.Text;
                end
                delete(src);
            end
            waitfor(ev_app)
            app.filterDataByDate;
        end

        % Menu selected function: resetbaseMenu
        function resetbaseMenuSelected(app, event)
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.fillUITree(app.Tree, 'Base', app.MainApp.ProtocolObj.Idx_Filtered);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % This callback saves the group information on the "groupInfo"
            % structure from the Main App.
            
            data = app.genIdxList;
            if isempty(data)
                return
            end            
            app.MainApp.groupInfo.gType = app.groupType;
            app.MainApp.groupInfo.nGroups = app.Ngroups;
            app.MainApp.groupInfo.gIdxList = data;
            app.DataGroupingtoolUIFigureCloseRequest(event);
        end

        % Menu selected function: LoadgroupsMenu
        function LoadgroupsMenuSelected(app, event)
            % This callback loads an existing StatsGroupInfo_xxx.mat file
            % from the Save Directory of protocol
            
            [file,Path] = uigetfile('StatsGr*.mat', 'Select a Stats Group File', fullfile(app.MainApp.ProtocolObj.SaveDir, 'StatsGroupInfo_data.mat'));            
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            disp('Loading data...')
            data_in = load(fullfile(Path,file));
            disp('Erasing current groups')
            app.restorebaseanderasegroupsMenuSelected;            
            disp('Saving loaded info...')
            app.groupType = data_in.groupInfo.groupType;
            app.Ngroups = data_in.groupInfo.Ngroups;
            % Delete trees:
            for i = 1:length(app.treeList)
                arrayfun(@(x) delete(app.treeList(i).UserData{x}), 1:4)
            end
            delete(app.treeList); app.treeList = [];
            % Rebuild trees:
            app.showUITrees
            app.groupIdxList = data_in.groupInfo.gIdxList;
            disp('Rebuilding trees')         
            app.sendNodesToGroups;
            disp('Done!');
        end

        % Menu selected function: SaveGroupsMenu
        function SaveGroupsMenuSelected(app, event)
            % This callback creates a UISAVE interface for the user to write the filename of the StatsGroup.mat file and
            % saves it to the protocol's SaveDir.
            
            % Create dialog box for input of new file:
            
            dlg_box = uifigure('Name', 'Type file name', 'Visible','off','Position', [0 0 330 100], 'CloseRequestFcn', @Close_dlg_box);
            movegui(dlg_box, 'center'); dlg_box.Visible = 'on';
            lab1 = uilabel('Parent',dlg_box, 'Text', 'StatsGroupInfo_','Position', [20 55 95 20], 'FontColor', [.5 .5 .5]);
            txt_box = uieditfield('Parent', dlg_box, "BackgroundColor", [1 1 1], "Position", [117 55 115 20]);
            txt_box.Value = 'data';
            lab2 = uilabel('Parent',dlg_box, 'Text', '.mat','Position', [232 55 50 20], 'FontColor', [.5 .5 .5]);
            ok_but = uibutton('Parent', dlg_box, "BackgroundColor",[0 .8 0], "Text", 'Save',...
                'Position', [40 10 60 25], 'ButtonPushedFcn',@(src,evnt) SaveROIas(src,evnt,app));
            data = app.genIdxList;
            % Local callbacks
            function SaveROIas(src,~,app)
                src.Enable = 'off';
                % Save StatsGroup structure to file:              
                groupInfo = struct('Ngroups', app.Ngroups, 'groupType', app.groupType, 'gIdxList', data);                
                filename = fullfile(app.MainApp.ProtocolObj.SaveDir, ['StatsGroupInfo_' src.Parent.Children(3).Value '.mat']);
                save(filename, 'groupInfo');
                msg = msgbox(['Stats Group info saved as ' filename],"modal");
                waitfor(msg)
                delete(src.Parent)
            end
            
            function Close_dlg_box(src,~)
                disp('Operation cancelled by User');
                delete(src)
            end
        end

        % Menu selected function: erasebaseMenu
        function erasebaseMenuSelected(app, event)
            % This callback erases all nodes from the base UITREE:
            delete(app.Tree.Children.Children);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataGroupingtoolUIFigure = uifigure('Visible', 'off');
            app.DataGroupingtoolUIFigure.Name = 'Data Grouping tool';
            app.DataGroupingtoolUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataGroupingtoolUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DataGroupingtoolUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadgroupsMenu = uimenu(app.FileMenu);
            app.LoadgroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadgroupsMenuSelected, true);
            app.LoadgroupsMenu.Text = 'Load groups';
            app.SaveGroupsMenu = uimenu(app.FileMenu);
            app.SaveGroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveGroupsMenuSelected, true);
            app.SaveGroupsMenu.Text = 'Save Groups';
            app.DataFilterMenu = uimenu(app.DataGroupingtoolUIFigure);
            app.DataFilterMenu.Text = 'Data Filter';
            app.filterbasedatabyMenu = uimenu(app.DataFilterMenu);
            app.filterbasedatabyMenu.Text = 'filter base data by';
            app.QueryMenu = uimenu(app.filterbasedatabyMenu);
            app.QueryMenu.MenuSelectedFcn = createCallbackFcn(app, @QueryMenuSelected, true);
            app.QueryMenu.Tooltip = {'Modality filter is not taken into account'};
            app.QueryMenu.Text = 'Query';
            app.DateMenu = uimenu(app.filterbasedatabyMenu);
            app.DateMenu.MenuSelectedFcn = createCallbackFcn(app, @DateMenuSelected, true);
            app.DateMenu.Text = 'Date';
            app.EventMenu = uimenu(app.filterbasedatabyMenu);
            app.EventMenu.MenuSelectedFcn = createCallbackFcn(app, @EventMenuSelected, true);
            app.EventMenu.Text = 'Event';
            app.resetbaseMenu = uimenu(app.DataFilterMenu);
            app.resetbaseMenu.MenuSelectedFcn = createCallbackFcn(app, @resetbaseMenuSelected, true);
            app.resetbaseMenu.Text = 'reset base';
            app.erasebaseMenu = uimenu(app.DataFilterMenu);
            app.erasebaseMenu.MenuSelectedFcn = createCallbackFcn(app, @erasebaseMenuSelected, true);
            app.erasebaseMenu.Text = 'erase base';
            app.restorebaseanderasegroupsMenu = uimenu(app.DataFilterMenu);
            app.restorebaseanderasegroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @restorebaseanderasegroupsMenuSelected, true);
            app.restorebaseanderasegroupsMenu.Text = 'restore base and erase groups';
            app.GroupoptionsMenu = uimenu(app.DataGroupingtoolUIFigure);
            app.GroupoptionsMenu.Text = 'Group options';
            app.changegrouptypeMenu = uimenu(app.GroupoptionsMenu);
            app.changegrouptypeMenu.MenuSelectedFcn = createCallbackFcn(app, @changegrouptypeMenuSelected, true);
            app.changegrouptypeMenu.Text = 'change group type';
            app.addnewgroupMenu = uimenu(app.GroupoptionsMenu);
            app.addnewgroupMenu.MenuSelectedFcn = createCallbackFcn(app, @addnewgroupMenuSelected, true);
            app.addnewgroupMenu.Text = 'add new group';
            app.removegroupMenu = uimenu(app.GroupoptionsMenu);
            app.removegroupMenu.Text = 'remove group';
            app.erasegroupcontentMenu = uimenu(app.GroupoptionsMenu);
            app.erasegroupcontentMenu.Text = 'erase group content';
            app.Tree = uitree(app.DataGroupingtoolUIFigure);
            app.BasedataLabel = uilabel(app.DataGroupingtoolUIFigure);
            app.BasedataLabel.Text = 'Base data';
            app.movetoDropDownLabel = uilabel(app.DataGroupingtoolUIFigure);
            app.movetoDropDownLabel.Text = 'move to';
            app.movetoDropDown = uidropdown(app.DataGroupingtoolUIFigure);
            app.GoButton = uibutton(app.DataGroupingtoolUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @BaseGoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.ApplyButton = uibutton(app.DataGroupingtoolUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.DataGroupingtoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_grouping_tool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataGroupingtoolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataGroupingtoolUIFigure)
        end
    end
end