classdef group_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroupSettingsUIFigure         matlab.ui.Figure
        GroupSettingsLabel            matlab.ui.control.Label
        SelectthecomparisontypeLabel  matlab.ui.control.Label
        TypesButtonGroup              matlab.ui.container.ButtonGroup
        intersubjectButton            matlab.ui.control.RadioButton
        intrasubjectButton            matlab.ui.control.RadioButton
        descriptivestatsButton        matlab.ui.control.RadioButton
        SelectnumberofgroupsLabel     matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        OKButton                      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % handle to data_grouping_tool APP.
        gType;
        g_num;
        disableSpinner = false % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, varargin)
            
            % Update UIFigure position:
            app.GroupSettingsUIFigure.Position = denormalize_UIfig_Position([0.4177 0.3472 0.1120 0.2972]);
            
            app.mainApp = mainApp;
            app.gType = app.mainApp.groupType;
            app.g_num = app.mainApp.Ngroups;
            btn_name = {app.TypesButtonGroup.Buttons.Text};
            idx = strcmp(app.gType, btn_name);
            app.TypesButtonGroup.SelectedObject = app.TypesButtonGroup.Buttons(idx);
            app.Spinner.Value = app.g_num;
            if nargin == 3
                app.disableSpinner = varargin{1};
            end
            if app.disableSpinner
                app.Spinner.Enable = 'off';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Selection changed function: TypesButtonGroup
        function TypesButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypesButtonGroup.SelectedObject;
            app.gType = selectedButton.Text;
            switch app.gType
                case 'intra-subject'
                    app.g_num = 2;
                app.Spinner.Value = 2;
                app.Spinner.Enable = 'off';
                case 'inter-subject'
                    if ~app.disableSpinner
                        app.Spinner.Enable = 'on';
                        app.Spinner.Limits = [2 6];
                        app.g_num = app.Spinner.Value;
                    end
                otherwise
                    app.Spinner.Limits = [1 6];
                    app.Spinner.Value = 1;
                    app.Spinner.Enable = 'off';
                    app.g_num = app.Spinner.Value;
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.g_num = app.Spinner.Value;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.groupType = app.gType;
            app.mainApp.Ngroups = app.g_num;
            delete(app);
        end

        % Close request function: GroupSettingsUIFigure
        function GroupSettingsUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroupSettingsUIFigure = uifigure('Visible', 'off');
            app.GroupSettingsUIFigure.Name = 'Group Settings';
            app.GroupSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @GroupSettingsUIFigureCloseRequest, true);
            app.GroupSettingsLabel = uilabel(app.GroupSettingsUIFigure);
            app.GroupSettingsLabel.Text = 'Group Settings';
            app.SelectthecomparisontypeLabel = uilabel(app.GroupSettingsUIFigure);
            app.SelectthecomparisontypeLabel.Text = '1) Select the comparison type:';
            app.TypesButtonGroup = uibuttongroup(app.GroupSettingsUIFigure);
            app.TypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypesButtonGroupSelectionChanged, true);
            app.TypesButtonGroup.Title = 'Types';
            app.intersubjectButton = uiradiobutton(app.TypesButtonGroup);
            app.intersubjectButton.Tooltip = {'Used to compare groups of subjects (ex. control vs treatment).'};
            app.intersubjectButton.Text = 'inter-subject';
            app.intersubjectButton.Value = true;
            app.intrasubjectButton = uiradiobutton(app.TypesButtonGroup);
            app.intrasubjectButton.Tooltip = {'Used to compare acquisition before and after an event.'};
            app.intrasubjectButton.Text = 'intra-subject';
            app.descriptivestatsButton = uiradiobutton(app.TypesButtonGroup);
            app.descriptivestatsButton.Tooltip = {'Limited to one group. Used for basic exploratory statistics.'};
            app.descriptivestatsButton.Text = 'descriptive stats';
            app.SelectnumberofgroupsLabel = uilabel(app.GroupSettingsUIFigure);
            app.SelectnumberofgroupsLabel.Text = '2) Select number of groups:';
            app.Spinner = uispinner(app.GroupSettingsUIFigure);
            app.Spinner.Limits = [1 6];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Tooltip = {'The number of groups is limited to 6 for now...'};
            app.Spinner.Value = 2;
            app.OKButton = uibutton(app.GroupSettingsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GroupSettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.GroupSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = group_settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroupSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroupSettingsUIFigure)
        end
    end
end