classdef stats_module_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProjectMenu                matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        ResetMenu                  matlab.ui.container.Menu
        ToolsMenu                  matlab.ui.container.Menu
        CreatenewgroupsMenu        matlab.ui.container.Menu
        SettingsMenu               matlab.ui.container.Menu
        Tree                       matlab.ui.container.Tree
        BasedataLabel              matlab.ui.control.Label
        SelectfileListBoxLabel     matlab.ui.control.Label
        SelectfileListBox          matlab.ui.control.ListBox
        SelectobservationsLabel    matlab.ui.control.Label
        SelectobservationsListBox  matlab.ui.control.ListBox
        AnalysisPanel              matlab.ui.container.Panel
        SaveselectionButton        matlab.ui.control.StateButton
        SavetoMatButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        ProtocolObj % handle to Protocol object.
        baseTree_idxList % structure containing root name and idxList for construction of UITree.
    end
    
    properties (Access = private)
        matFileList % List of .MAT files found in FilePtrs of objects listed in the UITree nodes.
        nodeList % List of nodes from app.Tree associated with each .MAT file in "matFileList".
        nodeObjList % List of objects associated with elements from nodeList.
        StatsObj % StatsManager object.
    end
    
    methods (Access = private)
        
        function fillUITree(app)
            app.Tree.Enable = 'off';
            delete(app.Tree.Children)
            %%% Allow the creation of several roots!
            s = app.baseTree_idxList;
            nRoots = length(s);
            for ii = 1:nRoots
                idxList = s(ii).idxList;
                root = uitreenode(app.Tree, 'Text', s(ii).Name,'Icon', 'icon_WinFolder.png');
                subjIdx = unique(idxList(:,1));
                for i = 1:numel(subjIdx)
                    ID = app.ProtocolObj.Array.ObjList(subjIdx(i)).ID;
                    subjNode = uitreenode(root, 'Text', ID, 'Icon', 'icon_WinFolder.png');
                    idx = idxList(:,1) == subjIdx(i);
                    acqIdx = unique(idxList(idx,2), 'rows');
                    for j = 1:size(acqIdx,1)
                        acqID = app.ProtocolObj.Array.ObjList(subjIdx(i)).Array.ObjList(acqIdx(j)).ID;
                        uitreenode(subjNode, 'Text', acqID, 'Icon', 'icon_WinFolder.png');
                    end
                end
            end
            %             expand(app.Tree, 'all');
            app.Tree.Enable = 'on';
            app.Tree.Multiselect = 'on';
        end
        
        function [NodeObj, lvl] = getNodeObj(app,Node)
            % GETNODEOBJ retrieves the object associated with the selected node
            % in a UITree.
            ID = Node.Text;
            b_top = false; a = 0;
            str = 'typeClass = class(Node.';
            str2 = 'Parent';
            while ~b_top
                eval([str str2 ');']);
                lvl = a;
                if strcmp(typeClass,'matlab.ui.container.Tree')
                    b_top= true;
                end
                str = [str str2 '.'];
                a = a+1;
            end
            switch lvl
                case 0
                    NodeObj = app.ProtocolObj;
                case 1
                    idx = app.ProtocolObj.Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                    idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
        end
        
        
        function [matList, nodeList, nodeObjList] = findStatsFiles(app)
            % FINDSTATSFILES looks for stats-ready .MAT files listed in the
            % FilePtr.json files from objects selected in app.Tree.
            % In addition to looking inside FilePtrs of selected objects, this function will look into
            % FilePtrs from all modalities inside Acquisition objects.
            % Output:
            % matList: A list of full paths of .MAT files that are common to
            % selected objects (grouped by hierarchy).
            % nodeList : Cell array of UITree node handles associated with each
            % .Mat file listed in MATLIST.
            % nodeObjList: Cell array of objects associated with nodeList
            % elements.
            
            nodeList = {};
            matList = {};
            nodeObjList = {};
            nodes = app.Tree.SelectedNodes;
            % First, get a list of all File Pointers from Selected Nodes:
            for i = 1:numel(nodes)
                node = nodes(i);
                nodeObj = app.getNodeObj(node);
                list = getMatfromJson(nodeObj);
                nodeList = [nodeList, repmat({node}, 1,length(list))];
                matList = [matList, list];
                if ~isempty(list)
                    nodeObjList = [nodeObjList, {nodeObj}];
                end
                if isa(nodeObj, 'Acquisition')
                    for j = 1:numel(nodeObj.Array.ObjList)
                        mod_list = getMatfromJson(nodeObj.Array.ObjList(j));
                        nodeList = [nodeList, repmat({node}, 1, length(mod_list))];
                        matList = [matList, mod_list];
                        if ~isempty(mod_list)
                            nodeObjList = [nodeObjList, {nodeObj.Array.ObjList(j)}];
                        end
                    end
                end
            end
            
            function list_of_matFiles = getMatfromJson(NodeObj)
                % GETMATFROMJSON reads the JSON file in NODEOBJ.FILEPTR and outputs
                % a list of .MAT files from the FILEPTR.
                list_of_matFiles = {};
                txt = fileread(NodeObj.FilePtr);
                a = jsondecode(txt);
                if ~isempty(a.Files)
                    names = fullfile({a.Files.Folder}, {a.Files.Name});
                    
                    idx = endsWith(names, '.mat');
                    names = names(idx);
                    ObjArr = repmat({NodeObj}, 1, length(names));
                    if ~isempty(names)
                        names = cellfun(@(x,y) tokenizePath(x, y,'detokenize'), names, ObjArr,...
                            'UniformOutput',false);
                        list_of_matFiles= [list_of_matFiles names];
                    end
                end
            end
            
        end
        
        function lvl = findNodeLevel(~,Node)
            a = false;
            lvl = 0;
            while ~a
                myParent = Node.Parent;
                if isa(myParent, 'matlab.ui.container.Tree')
                    a = true;
                else
                    Node = Node.Parent;
                    lvl = lvl + 1;
                end
            end
        end
        
        
        function groupList = getMyGroup(app,nodeList)
                % Retrieves the name of the group from each element
                % contained in nodeList.
                groupList = cell(1,length(nodeList));
                for i = 1:numel(nodeList)
                    node = nodeList{i};
                    b_isNode = true;
                    while b_isNode
                        myParent = node.Parent;
                        if isa(myParent, 'matlab.ui.container.Tree')
                            b_isNode = false;
                            groupName = node.Text;
                        else
                            node = node.Parent;
                        end
                    end
                    groupList{i} = groupName;
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, protObj)
            % Limit options for listbox selections:
            app.SelectfileListBox.Multiselect = "off";
            app.SelectobservationsListBox.Multiselect = "on";
            app.ProtocolObj = protObj;
            % Reset Filtered Items in protocol object:
            app.ProtocolObj.clearFilterStruct;
            app.ProtocolObj.queryFilter;
            s.Name = 'Base';
            s.idxList = app.ProtocolObj.Idx_Filtered;
            app.baseTree_idxList = s;
            app.fillUITree;
            
        end

        % Menu selected function: CreatenewgroupsMenu
        function CreatenewgroupsMenuSelected(app, event)
            a = data_grouping_tool(app);
            waitfor(a);
            app.fillUITree;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % erase List boxes:
            app.SelectfileListBox.Items = {''};
            app.SelectobservationsListBox.Items = {''};
            %
            
            if isempty(selectedNodes)
                return
            end
            disp('Building Node Selection...')
            lvl = arrayfun(@(x) app.findNodeLevel(x), selectedNodes);
            top_rank = min(lvl);
            nodelist = [];
            switch top_rank
                case 0
                    top_nodes = [app.Tree.SelectedNodes(lvl == 0)];
                    for i = 1:numel(top_nodes)
                        top_node = top_nodes(i);
                        nodelist = [nodelist; top_node.Children];
                    end
                    tmp_list = nodelist;
                    for i = 1:numel(tmp_list)
                        node = tmp_list(i);
                        nodelist = [nodelist; node.Children];
                    end
                    
                    app.Tree.SelectedNodes = union(app.Tree.SelectedNodes, nodelist);
                    tmp_lvl = arrayfun(@(x) app.findNodeLevel(x), app.Tree.SelectedNodes);
                    tmp_idx = ( tmp_lvl == 0 );
                    app.Tree.SelectedNodes = app.Tree.SelectedNodes(~tmp_idx);
                case 1
                    idx = ( lvl == 1);
                    nodes = selectedNodes(idx);
                    nodelist = nodes;
                    for i = 1:numel(nodes)
                        nodelist = [nodelist; nodes(i).Children];
                        expand(nodes(i), 'all');
                    end
                    app.Tree.SelectedNodes = union(app.Tree.SelectedNodes, nodelist);
            end
            
            [app.matFileList, app.nodeList, app.nodeObjList] = app.findStatsFiles;
            disp('Done Selecting!')
            % Display Node Files in Selection ListBox:
            [~, files, ~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput', false);
            app.SelectfileListBox.Items = unique(files);
            app.SelectfileListBox.Value = {};
            app.SelectfileListBoxValueChanged;
        end

        % Value changed function: SelectfileListBox
        function SelectfileListBoxValueChanged(app, event)
            value = app.SelectfileListBox.Value;
            if isempty(value)
                cellfun(@(x) set(x, 'Icon', 'icon_WinFolder.png'), app.nodeList);
                return
            end
            [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
            idx = strcmp(all_files, value);
            cellfun(@(x) set(x, 'Icon', 'icon_WinFolder.png'), app.nodeList);
            cellfun(@(x) set(x, 'Icon', 'icon_check.png'), app.nodeList(idx));
            % Open .MAT files and show variable names:
            varnames = {};
            matFiles = app.matFileList(idx);
            for i = 1:numel(matFiles)
                mDt = matfile(matFiles{i});
                indx = strcmp(mDt.dim_names,'O');
                labels = mDt.labels;
                varnames = [varnames, labels{indx}];
            end
            varnames = cellfun(@num2str, unique(varnames), 'UniformOutput', false);
            app.SelectobservationsListBox.Items = varnames;
        end

        % Value changed function: SaveselectionButton
        function SaveselectionButtonValueChanged(app, event)
            value = app.SaveselectionButton.Value;
            if value
                app.SaveselectionButton.Text = 'Undo Save';
                app.Tree.Enable = 'off';
                app.SelectfileListBox.Enable = 'off';
                app.SelectobservationsListBox.Enable = 'off';
                
                obs_list = app.SelectobservationsListBox.Value;
                gList = app.getMyGroup(app.nodeList);
                statsFile = [app.SelectfileListBox.Value '.mat'];
                app.StatsObj = StatsManager(app.nodeObjList, obs_list, gList, statsFile);
            else
                app.SaveselectionButton.Text = 'Save selection';
                app.Tree.Enable = 'on';
                app.SelectfileListBox.Enable = 'on';
                app.SelectobservationsListBox.Enable = 'on';
                app.StatsObj = [];
            end
            
            
        end

        % Button pushed function: SavetoMatButton
        function SavetoMatButtonPushed(app, event)
            gList = app.getMyGroup(app.nodeList);
            objList = app.nodeObjList;
            save(fullfile(app.ProtocolObj.SaveDir, 'stats_groupInfo.mat'), 'gList', 'objList');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.Text = 'Open';
            app.ResetMenu = uimenu(app.ProjectMenu);
            app.ResetMenu.Text = 'Reset';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreatenewgroupsMenu = uimenu(app.ToolsMenu);
            app.CreatenewgroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatenewgroupsMenuSelected, true);
            app.CreatenewgroupsMenu.Text = 'Create new groups';
            app.SettingsMenu = uimenu(app.ToolsMenu);
            app.SettingsMenu.Text = 'Settings';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.BasedataLabel = uilabel(app.UIFigure);
            app.BasedataLabel.Text = 'Base data';
            app.SelectfileListBoxLabel = uilabel(app.UIFigure);
            app.SelectfileListBoxLabel.Text = 'Select file : ';
            app.SelectfileListBox = uilistbox(app.UIFigure);
            app.SelectfileListBox.Items = {};
            app.SelectfileListBox.ValueChangedFcn = createCallbackFcn(app, @SelectfileListBoxValueChanged, true);
            app.SelectfileListBox.Value = {};
            app.SelectobservationsLabel = uilabel(app.UIFigure);
            app.SelectobservationsLabel.Text = 'Select observation(s) :';
            app.SelectobservationsListBox = uilistbox(app.UIFigure);
            app.SelectobservationsListBox.Items = {};
            app.SelectobservationsListBox.Value = {};
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.SaveselectionButton = uibutton(app.UIFigure, 'state');
            app.SaveselectionButton.ValueChangedFcn = createCallbackFcn(app, @SaveselectionButtonValueChanged, true);
            app.SaveselectionButton.Enable = 'off';
            app.SaveselectionButton.Text = 'Save selection';
            app.SavetoMatButton = uibutton(app.UIFigure, 'push');
            app.SavetoMatButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoMatButtonPushed, true);
            app.SavetoMatButton.Text = 'Save to .Mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stats_module_main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end