classdef lsaToolboxGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectMenu                    matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        UpdateMenu                     matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        ResetLogBookMenu               matlab.ui.container.Menu
        RestoreGarbageListMenu         matlab.ui.container.Menu
        UtilitiesMenu                  matlab.ui.container.Menu
        DataVisualizationtoolsMenu     matlab.ui.container.Menu
        ImagingMenu                    matlab.ui.container.Menu
        SeedPixelCorrelationMapsMenu   matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        DashboardTab                   matlab.ui.container.Tab
        Tree                           matlab.ui.container.Tree
        UITable                        matlab.ui.control.Table
        ProjectStatusPanel             matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        LastoperationsruninselecteditemLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        PropertiesofselecteditemLabel  matlab.ui.control.Label
        EditButton                     matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        SelectionLabel                 matlab.ui.control.Label
        PipelinecontrolpanelTab        matlab.ui.container.Tab
        Tree_2                         matlab.ui.container.Tree
        AdvancedSearchButton           matlab.ui.control.Button
        FilteredItemsLabel             matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        PipelineSummaryLabel           matlab.ui.control.Label
        PipelinecontrolpanelPanel      matlab.ui.container.Panel
        ConfigureNewPipelineButton     matlab.ui.control.Button
        RunPipelineButton              matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        IgnoreLoggedJobsCheckBox       matlab.ui.control.CheckBox
        DeleteIntermediateFilesCheckBox  matlab.ui.control.CheckBox
        PipelinestatusPanel            matlab.ui.container.Panel
        TextArea_2                     matlab.ui.control.TextArea
        SelectItemsButton              matlab.ui.control.Button
        ResetSearchButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        ProtocolFile % Protocol File Path
        TreeNodeSelection % Tree Node(s) selected by user in Dashboard.
        TreeNodeList % List of UITREENODE objects.
        SearchBuilderApp; % Property containing SearchBuilder app.
        PipelineConfigApp % Property containing PipelineConfig app.
        right_click % Set to TRUE with mouse right click
    end
    
    properties (Access = public)
        ProtocolObj % PROTOCOL Object
        PipelineObj % PIPELINEMANAGER Object
    end
    
    methods (Access = private)
        
        function check4newData(app)
            % Checks the Raw folder of PROTOCOL for new/suppressed Subjects
            % and Acquisitions.
            app.Label.Text = 'Checking for new Data in Raw Directory...';
            pause(1);
            newprotocol = app.ProtocolObj.ProtoFunc(app.ProtocolObj);
            newprotocol = app.ProtocolObj.removeGarbage(newprotocol);
            iNewSubj = ~ismember({newprotocol.ID}, {app.ProtocolObj.Array.ObjList.ID}); % New subjects in the newprotocol.
            iMissSubj = ~ismember({app.ProtocolObj.Array.ObjList.ID},{newprotocol.ID});% Subjects from original list that are no longer in the newprotocol.
            indNwArr = find(~iNewSubj);
            indprotocol = arrayfun(@(a) find(strcmp({newprotocol(indNwArr).ID}, a)),...
                {app.ProtocolObj.Array.ObjList.ID}, 'UniformOutput', false); indprotocol = [indprotocol{:}];
            iNewAcq = arrayfun(@(a,b) ~ismember({a.Array.ObjList.ID}, {b.Array.ObjList.ID}),...
                newprotocol(indNwArr), app.ProtocolObj.Array.ObjList(indprotocol), 'UniformOutput', false);
            iNewAcq = [iNewAcq{:}];
            iMissAcq = arrayfun(@(a,b) ~ismember({b.Array.ObjList.ID}, {a.Array.ObjList.ID}),...
                newprotocol(indNwArr), app.ProtocolObj.Array.ObjList(indprotocol), 'UniformOutput', false);
            iMissAcq = [iMissAcq{:}];
            if any([iNewSubj iMissSubj iNewAcq iMissAcq])
                ns = num2str(sum(iNewSubj));
                ms = num2str(sum(iMissSubj));
                na = num2str(sum(iNewAcq));
                ma = num2str(sum(iMissAcq));
                app.Label.Text = 'Changes in the raw folder Detected:';
                app.Label_2.Text = sprintf('%s \n%s \n%s \n%s \n%s',['New Subjects: ' ns], ['Missing Subjects: ' ms], ...
                    ['New Acquisitions: ' na], ['Missing Acquisitions: ' ma], 'Click on ''Project'' >> ''Update Project'' to apply changes!');
            else
                app.Label.Text = 'All Raw Files read.';
                app.Label_2.Text = '';
            end
            
        end
        
        function makeUItreeNodes(app, Tree, Toggle_MultiSelect, b_expAll, b_FilterTree)
            % Create UITree nodes of Protocol hierarchy.
            
%             arguments
%                 app
%                 Tree
%                 Toggle_MultiSelect {mustBeMember(Toggle_MultiSelect, {'on', 'off'})} = 'off';
%                 b_expAll = false
%                 b_FilterTree = false
%             end
            Tree.Multiselect = Toggle_MultiSelect;
            app.TreeNodeList = struct();
            delete(Tree.Children)
            if ~b_FilterTree
                app.ProtocolObj.clearFilterStruct;
            end
            root = uitreenode(Tree, 'Text', app.ProtocolObj.Name, 'NodeData', app.ProtocolObj, 'Icon', 'icon_WinFolder.png');
            app.ProtocolObj.queryFilter;
            idxList = app.ProtocolObj.Idx_Filtered;
            if isempty(idxList)
                uialert(app.UIFigure, 'The query returned Zero elements', 'Query Filter Result', "Icon","warning", 'Modal',true)
                return
            end
            uniqS = unique(idxList(:,1));
            for i = 1:numel(uniqS)
                subList = idxList(idxList(:,1) == uniqS(i),:);
                uniqA = unique(subList(:,2));
                tmpS = app.ProtocolObj.Array.ObjList(uniqS(i));
                eval(['app.TreeNodeList.s' num2str(uniqS(i)) ' = uitreenode(root, ''Text'', tmpS.ID, ''NodeData'', tmpS, ''Icon'', ''icon_WinFolder.png'');']);
                for j = 1:numel(uniqA)
                    subList = idxList(idxList(:,1) == uniqS(i) & idxList(:,2) == uniqA(j),:);
                    uniqM = unique(subList(:,3));
                    tmpA = tmpS.Array.ObjList(uniqA(j));
                    eval(['app.TreeNodeList.s' num2str(uniqS(i)) 'a' num2str(uniqA(j)) ' = uitreenode(app.TreeNodeList.s' num2str(uniqS(i)) ', ''Text'', tmpA.ID, ''NodeData'', tmpA, ''Icon'', ''icon_WinFolder.png'');']);
                    for k = 1:numel(uniqM)
                        tmpM = tmpA.Array.ObjList(uniqM(k));
                        eval(['app.TreeNodeList.s' num2str(uniqS(i)) 'a' num2str(uniqA(j)) 'm' num2str(uniqM(k)) ' = uitreenode(app.TreeNodeList.s' num2str(uniqS(i)) 'a' num2str(uniqA(j)) ', ''Text'', tmpM.ID, ''NodeData'', tmpM, ''Icon'', ''icon_WinFolder.png'');']);
                    end
                end
            end
            
            if b_expAll
                expand(Tree, "all")
            else
                expand(Tree)
            end
            app.checkTree;
        end
        
        function manualNodeSelection(app, Tree)
            % to be changed to SearchItemsButton callback!
            list = fieldnames(app.TreeNodeList);
            IdxList = app.ProtocolObj.Idx_Filtered;
            Selection = app.Tree_2.SelectedNodes;
            NodeName = cell(1,length(Selection));
            for i = 1:length(Selection)
                for j = 1:length(list)
                    if isequal(Selection(i), app.TreeNodeList.(list{j}))
                        break
                    end
                end
                NodeName{i} = list{j};
            end
            newList = zeros(length(NodeName),3);
            for i = 1:length(NodeName)
                str = NodeName{i};
                idx = regexp(str, '\s*(\d*)\s*(\d*)\s*(\d*)', 'match'); idx = str2double(idx);
                switch(length(idx))
                    case 1
                        ind = IdxList(:,1) == idx;
                        newList(i,:) = IdxList(ind,:);
                    case 2
                        ind = all(IdxList(:,[1 2]) == idx, 2);
                        newList(i,:) = IdxList(ind,:);
                    case 3
                        ind = all(IdxList == idx, 2);
                        newList(i,:) = IdxList(ind,:);
                end
            end
            app.ProtocolObj.clearFilterStruct; %Reset FilterStruct;
            app.ProtocolObj.Idx_Filtered = newList;
            
            
        end


        function displayNodeInfo(app)
            NodeObj = app.TreeNodeSelection.NodeData;
            KeySet = properties(NodeObj); KeySet = setdiff(KeySet, {'Array', 'LastLog', 'garbageList'});
            for ii = 1:length(KeySet)
                eval(['propVals{ii} = NodeObj.' KeySet{ii} ';'])
            end
            classInfo = {'Type', class(NodeObj)};
            NodeObjProps = horzcat(KeySet, propVals');
            NodeObjProps = vertcat(classInfo, NodeObjProps);
            % Temporary solution for displaying Function Handle...
            if isa(NodeObj,'Protocol')
                NodeObjProps{4,2} = char(NodeObjProps{4,2});
            end
            %%%
            Data = [];
            % The if statement here controls for known property data types.
            % Has to be improved to be more flexible.
            for i = 1:size(NodeObjProps,1)
                tag = string([NodeObjProps{i,1} ':']);
                if isnumeric(NodeObjProps{i,2})
                    value = string(['    ' num2str(NodeObjProps{i,2})]);
                elseif iscell(NodeObjProps{i,2})
                    value = string(NodeObjProps{i,2}');
                else
                    value = string(['    ' NodeObjProps{i,2}]);
                end
                
                Data = vertcat(Data, tag, value);
            end
            app.TextArea.Value = Data;
            app.TextArea.Editable = "off";
            ColNames = {'Function', 'Completed (0-1)', 'Date and Time', 'Messages'};
            if isempty(NodeObj.LastLog)
                tableData = {'None', 0, 'None', 'None'};
            else
                tableData = NodeObj.LastLog(:,[5 7:9]);
                tableData = table2cell(tableData);
                tableData = cellfun(@(x) char(string(x)), tableData, 'UniformOutput',false);
            end
            app.UITable.Data = tableData;
            app.UITable.ColumnName = ColNames;
            app.UITable.ColumnEditable = false;
        end
        
        function checkTree(app)
            % This function checks if the hierarchical tree has all
            % necessary elements for analysis. This means that at least one
            % Subject has one Acquisition that itself has one modality.
            
            idxS = arrayfun(@(x) isempty(x.Array.ObjList), app.ProtocolObj.Array.ObjList); % Empty Subject
            faultyS = find(idxS);
            faultyS_ID = arrayfun(@(x) app.ProtocolObj.Array.ObjList(x).ID, faultyS, 'UniformOutput',false);
            
            otherS = find(~idxS);
            faultyA_ID = [];
            for i = 1:length(otherS)
                faultyA = find(arrayfun(@(x) isempty(x), app.ProtocolObj.Array.ObjList(otherS(i)).Array.ObjList)); % Empty Acquisitions.
                tmp = arrayfun(@(x) app.ProtocolObj.Array.ObjList(otherS(i)).Array.ObjList(x).ID, faultyA, 'UniformOutput',false);
                faultyA_ID = [faultyA_ID tmp];
            end
            
            faultyList = [faultyS_ID faultyA_ID];
            fields = fieldnames(app.TreeNodeList);
            for i = 1:length(fields)
                eval(['NodeID{' num2str(i) '} = app.TreeNodeList.' fields{i} '.Text;'])
            end
            if ~isempty(faultyList)
                for i = 1:length(faultyList)
                    idx = strcmp(faultyList{i}, NodeID);
                    if any(idx)
                        eval(['app.TreeNodeList.' fields{idx} '.Icon = ''icon_Error.png'';'])
                    end
                    
                end
                uiwait(warndlg(['One or more objects are empty. '...
                    'Each Subject has to have at least one Acquisition with at least one Modality.' ...
                    ' Please, correct this before continuing with the analysis.'], 'Warning!'));
            end
        end
        
    end
    methods (Access = public)
        function updateFilterStruct(app, FilterStruct)
            app.ProtocolObj.FilterStruct = FilterStruct;
            app.makeUItreeNodes(app.Tree_2,'on', true, true);
            
        end
        
        function displayPipeInfo(app, ResetArea)
%             arguments
%                 app
%                 ResetArea = false;
%             end
            if ResetArea
                app.TextArea_2.Value = '';
            end
            Pipe = app.PipelineObj.Pipe;
            Text = app.TextArea_2.Value;
            txt = {['Number of steps: ' num2str(length(Pipe))]};
            txt = [txt {'------------------------------------------------------'}];
            for i = 1:length(Pipe)
                txt = [txt {['Step #' num2str(i) ': ']}];
                txt = [txt {['Class: ' Pipe(i).className]}];
                txt = [txt {['Function: ' Pipe(i).Name]}];
                if ~isempty(Pipe(i).opts)
                    fields = fieldnames(Pipe(i).opts);
                    txt = [txt {'Parameters: '}];
                    for j = 1:length(fields)
                        txt = [txt {[fields{j} ': ' num2str(Pipe(i).opts.(fields{j}))]}];
                    end
                end
                txt = [txt {'------------------------------------------------------'}];
            end
            Text = [Text txt];
            app.TextArea_2.Value = Text;
            app.TextArea_2.Editable = 'off';
            scroll(app.TextArea_2, 'bottom');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('D:\Academico\PostDoc_UdeM\LabeoTech\IsaToolbox'));
            addpath(genpath('D:\Academico\PostDoc_UdeM\LabeoTech\ioi_ana'));
            clearvars
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, path] = uigetfile('*mat', 'Open a Project File');
            app.ProtocolFile = fullfile(path, filename);
            cd(path)
            a = load(filename);
            protocol= a.protocol;
            addpath(genpath(protocol.MainDir));
            addpath(genpath(protocol.SaveDir));
            if isa(protocol, 'Protocol')
                app.ProtocolObj = protocol;
                app.Label.Text = sprintf('%s', 'Project file loaded!');
                app.Label_2.Text = sprintf('%s', ['Raw Dir:' protocol.MainDir]);
                app.check4newData;
                app.makeUItreeNodes(app.Tree, 'off', true, false);
            else
                app.Label.Text = sprintf('%s\n%s', ['The file ' filename ' does not contain a valid Project.'], 'Try again!');
            end
        end

        % Menu selected function: UpdateMenu
        function UpdateMenuSelected(app, event)
            
            if ~isempty(app.ProtocolObj)
                app.Label.Text = 'Updating Project Files...';
                app.ProtocolObj.updateList;
                app.check4newData;
                app.makeUItreeNodes(app.Tree, 'off', true, false);
            else
                errordlg('No Project Found!')
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            protocol = app.ProtocolObj;
            if isempty(app.ProtocolFile)
                app.ProtocolFile= uigetdir(app.ProtocolObj.SaveDir, 'Select Folder to Save Project File');
                app.ProtocolFile = fullfile(app.ProtocolFile, [app.ProtocolObj.Name '.mat']);
                save(app.ProtocolFile, "protocol");
                msgbox('Project Saved!');
            else
                save(app.ProtocolFile, 'protocol');
                uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % Reset Protocol Object and FileName
            app.ProtocolObj = [];
            app.ProtocolFile = [];
            %%
            
            app.Label.Text = 'Creating New Project File...';
            app.Label_2.Text = '...';
            Name = inputdlg('Type the Name of your project: ', 'Project Name', [1 60], {''}, 'on');
            if isempty(Name)
                warndlg('Operation Cancelled', 'Warning!', "modal")
                return
            end
            MainDir = uigetdir(pwd, 'Select Directory with Raw Data.');
            SaveDir = uigetdir(pwd, 'Select Save Directory.');
            [ProtoFunc, FuncPath] = uigetfile('*.m', 'Select Protocol Function.');
            ProtoFunc = erase(ProtoFunc, '.m');
            addpath(genpath(MainDir));
            addpath(genpath(SaveDir));
            addpath(genpath(FuncPath));
            cd(SaveDir);
            eval(['FuncHandle = @' ProtoFunc]);
            app.ProtocolObj = Protocol(Name{:},MainDir, SaveDir,FuncHandle, []);
            app.Label.Text = 'New Project Created!';
            app.Label_2.Text = 'Creating Object Arrays...';
            app.ProtocolObj.generateList;
            app.Label_2.Text = 'Creating Save Directories...';
            app.ProtocolObj.generateSaveFolders;
            app.Label_2.Text = 'All set! Don''t forget to save it.';
            app.makeUItreeNodes(app.Tree, 'off', false, fasle);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.TreeNodeSelection = app.Tree.SelectedNodes;
            if isa(app.TreeNodeSelection.NodeData, 'Protocol')
                return
            end
            app.displayNodeInfo;
%             
%             fields = fieldnames(app.TreeNodeList);
%             for i = 1:length(fields)
%                 eval(['NodeFolderList{' num2str(i) '} = app.TreeNodeList.' fields{i} '.NodeData.SaveFolder;'])
%             end
%             
%             NodeFolder = app.TreeNodeSelection.NodeData.SaveFolder;
%             idx = strcmp(NodeFolder, NodeFolderList);
%             
%             if any(idx & contains(fields, 'm')')
%                 ctx_menu1 = uicontextmenu(app.UIFigure);
%                 app.TreeNodeList(fields{idx}).UIContextMenu = ctx_menu1;
%                 menu1_Delete = uimenu(ctx_menu1,'Label', 'Delete', 'Callback',@app.DeleteMenuSelected);
%                 menu1_Edit = uimenu(ctx_menu1,'Label', 'Edit', 'Callback', @app.EditMenuSelected);
%             else
%                 ctx_menu2 = uicontextmenu(app.UIFigure);
%                 app.TreeNodeList(fields{idx}).UIContextMenu = ctx_menu2;
%                 menu2_Delete = uimenu(ctx_menu2,'Label', 'Delete', 'Callback',@app.DeleteMenuSelected);
%                 menu2_Edit = uimenu(ctx_menu2,'Label', 'Edit', 'Callback', @app.EditMenuSelected);
%             end
% %             others = find(~idx);
% %             for i = 1:length(others)
% %                 eval(['app.TreeNodeList.' fields{others(i)} '.ContextMenu = gobjects(0);'])
% %             end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isempty(app.ProtocolObj)
                delete(app)
            elseif isempty(app.ProtocolFile)
                msg = 'You Created a new Project. Do you want to save it?';
                title = 'Save new Project';
                selection = uiconfirm(app.UIFigure, msg, title, 'Options',{'Save', 'No', 'Cancel'}, ...
                    "DefaultOption",1, "CancelOption",3);
                switch selection
                    case 'Save'
                        protocol = app.ProtocolObj;
                        app.ProtocolFile= uigetdir(protocol.SaveDir, 'Select Folder to Save Project File');
                        app.ProtocolFile = fullfile(app.ProtocolFile, [protocol.Name '.mat']);
                        save(app.ProtocolFile, "protocol");
                        msgbox('Project Saved!');
                        delete(app);
                    case 'No'
                        delete(app)
                    case 'Cancel'
                        return
                end
            else
                a = load(app.ProtocolFile);
                if ~isequal(a.protocol, app.ProtocolObj)
                    msg = 'It seems that you made changes to your Project. Do you want to save them?';
                    title = 'Confirm Save';
                    selection = uiconfirm(app.UIFigure,msg,title,...
                        'Options',{'Save','No', 'Cancel'},...
                        'DefaultOption',1,'CancelOption',3);
                    switch selection
                        case 'Save'
                            protocol = app.ProtocolObj;
                            save(app.ProtocolFile, "protocol");
                            uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
                            delete(app)
                        case 'No'
                            delete(app)
                        case 'Cancel'
                            return
                    end
                else
                    delete(app)
                end
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            protocol = app.ProtocolObj;
            cd(protocol.SaveDir);
            uisave('protocol', [protocol.Name '.mat']);
            uiwait(msgbox('Project file saved!'));
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if isempty(app.ProtocolObj)
                uiwait(warndlg('You have to load/create a Project File first!', 'Warning!', 'modal'));
                return
            end
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Dashboard'
                    app.makeUItreeNodes(app.Tree, 'off', true, false);
                case 'Pipeline'
                    app.ProtocolObj.clearFilterStruct;
                    app.ProtocolObj.queryFilter;
                    app.makeUItreeNodes(app.Tree_2, 'on', true, false);
                    app.RunPipelineButton.Enable = 'off';
                    app.AdvancedSearchButton.Enable = 'on';
                    app.SelectItemsButton.Enable = 'on';
                    app.ConfigureNewPipelineButton.Enable = 'off';
                    app.OpenButton.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.TextArea_2.Value = '';
                    % TEMPORARY...
                    app.DeleteIntermediateFilesCheckBox.Enable = 'off';
                case 'Data Visualization'
                    app.makeUItreeNodes(app.Tree_3, 'off', true, false);
            end
        end

        % Button pushed function: AdvancedSearchButton
        function AdvancedSearchButtonPushed(app, event)
            app.AdvancedSearchButton.Enable = 'off';
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.ProtocolObj.FilterStruct);
        end

        % Button pushed function: ConfigureNewPipelineButton
        function ConfigureNewPipelineButtonPushed(app, event)
            app.ConfigureNewPipelineButton.Enable = 'off';
            app.PipelineConfigApp = PipelineConfig_dlgBox(app, app.ProtocolObj.Idx_Filtered);
            app.SaveButton.Enable = 'on';
            app.RunPipelineButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Pipeline = app.PipelineObj;
            if isempty(Pipeline)
                uialert(app.UIFigure, 'Pipeline doesn''t exist!', 'Warning!', 'Modal',true);
                return
            end
            answer = inputdlg('Name of Pipeline:', 'Save Pipeline Config File', [1 40]); answer = answer{:};
            savefolder =[app.ProtocolObj.SaveDir 'PipelineConfigFiles'];
            [~,~] = mkdir(savefolder);
            save(fullfile(savefolder, [answer '.mat']), 'Pipeline')
            msgbox(['Pipeline saved as ' answer ' in ' savefolder]);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if ~exist([app.ProtocolObj.SaveDir 'PipelineConfigFiles'], 'dir')
                uialert(app.UIFigure, 'There are no Pipelines saved yet. Create one!', 'Warning!', "Modal",true);
                return
            end
            [filename, path] = uigetfile([app.ProtocolObj.SaveDir 'PipelineConfigFiles'], 'Open a Pipeline Configuration File');
            if filename == 0
                return
            end
            a = load(fullfile(path, filename));
            app.PipelineObj = a.Pipeline;
            msgbox(['Pipeline ' filename ' loaded!']);
            app.displayPipeInfo(true);
            app.SaveButton.Enable = 'on';
            app.RunPipelineButton.Enable = 'on';
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            txt = app.TextArea_2.Value;
            txt = [txt; {['(' char(datetime('now')) ') Running pipeline...']}];
            app.TextArea_2.Value = txt;
            app.PipelineObj.run_pipeline;
            txt = [txt; {['(' char(datetime('now')) ') Finished! Check the table below!']}];
            app.TextArea_2.Value = txt;
            % Show Pipeline Summary in UITable_2:
            app.UITable_2.Data = app.PipelineObj.PipelineSummary;
            app.UITable_2.ColumnName = app.PipelineObj.PipelineSummary.Properties.VariableNames;
            app.UITable_2.ColumnEditable = false;
        end

        % Menu selected function: ResetLogBookMenu
        function ResetLogBookMenuSelected(app, event)
            app.ProtocolObj.clearLogBook;
            msgbox('Log Book reset!',"modal")
        end

        % Button pushed function: SelectItemsButton
        function SelectItemsButtonPushed(app, event)
            list = fieldnames(app.TreeNodeList);
            IdxList = app.ProtocolObj.Idx_Filtered;
            Selection = app.Tree_2.SelectedNodes;
            if isempty(Selection) || ( numel(Selection) == 1 && isa(Selection.NodeData, 'Protocol') )
                SelItems = IdxList;
            else
                NodeName = cell(1,length(Selection));
                for i = 1:length(Selection)
                    for j = 1:length(list)
                        if isequal(Selection(i), app.TreeNodeList.(list{j}))
                            break
                        end
                    end
                    NodeName{i} = list{j};
                end
                SelItems = [];
                for i = 1:length(NodeName)
                    str = NodeName{i};
                    idx = regexp(str, '\s*(\d*)\s*(\d*)\s*(\d*)', 'match'); idx = str2double(idx);
                    switch(length(idx))
                        case 1
                            ind = IdxList(:,1) == idx;
                            newList = IdxList(ind,:);
                        case 2
                            ind = all(IdxList(:,[1 2]) == idx, 2);
                            newList = IdxList(ind,:);
                        case 3
                            ind = all(IdxList == idx, 2);
                            newList = IdxList(ind,:);
                    end
                    SelItems = [SelItems; newList];
                end
            end
            app.ProtocolObj.clearFilterStruct; %Reset FilterStruct;
            app.ProtocolObj.Idx_Filtered = SelItems;
            for i = 1:size(SelItems,1)
                Subj = ['s' num2str(SelItems(i,1))];
                Acq = [Subj 'a' num2str(SelItems(i,2))];
                Mod = [Acq 'm' num2str(SelItems(i,3))];
                app.TreeNodeList.(Subj).Icon = 'icon_check.png';
                app.TreeNodeList.(Acq).Icon = 'icon_check.png';
                app.TreeNodeList.(Mod).Icon = 'icon_check.png';
            end
            app.ConfigureNewPipelineButton.Enable = 'on';
            app.OpenButton.Enable = 'on';
            app.SelectItemsButton.Enable = 'off';
            app.AdvancedSearchButton.Enable = 'off';
        end

        % Button pushed function: ResetSearchButton
        function ResetSearchButtonPushed(app, event)
            app.makeUItreeNodes(app.Tree_2, 'on', true, false);
            app.SelectItemsButton.Enable = 'on';
            app.AdvancedSearchButton.Enable = 'on';
            app.RunPipelineButton.Enable = 'off';
            app.ConfigureNewPipelineButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
        end

        % Value changed function: IgnoreLoggedJobsCheckBox
        function IgnoreLoggedJobsCheckBoxValueChanged(app, event)
            value = app.IgnoreLoggedJobsCheckBox.Value;
            app.PipelineObj.IgnoreLoggedFiles = value;
        end

        % Value changed function: DeleteIntermediateFilesCheckBox
        function DeleteIntermediateFilesCheckBoxValueChanged(app, event)
            value = app.DeleteIntermediateFilesCheckBox.Value;
            if value
                uialert(app.UIFigure, 'By deleting intermediate files, the pipeline may not be able to Ignore Logged Items next time!', 'Pipeline Warning!', 'Icon',"warning", "Modal",true);
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            click = get(app.UIFigure, 'SelectionType');
            switch click
                case 'normal'
                    app.right_click = false;
                case 'alt'
                    app.right_click = true;
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            propList = properties(app.TreeNodeSelection.NodeData);
            propList = setdiff(propList, {'ID', ...
                'Array', 'RawFiles', 'RawFolder'});
            isSettable = false([1 length(propList)]);
            isNum = isSettable;
            for i = 1:length(propList)
                propInfo = findprop(app.TreeNodeSelection.NodeData, propList{i});
                eval(['val{i} = num2str(app.TreeNodeSelection.NodeData.' propList{i} ');'])
                if strcmp(propInfo.SetAccess, 'public')
                    isSettable(i) = true;
                end
            end
            if ~any(isSettable)
                msgbox('There are no editable properties for this object.', 'modal')
                return
            end
            prompt = propList(isSettable);
            val = val(isSettable);
            dlgTitle = ['Editor : ' app.TreeNodeSelection.Text];
            answer = inputdlg(prompt, dlgTitle, 1, val, "on");
            if ~isempty(answer)
                for i = 1:length(prompt)
                    try
                        if ~isnan(str2double(answer{i}))
                            disp(answer{i})
                            eval(['app.TreeNodeSelection.NodeData.' prompt{i} ' = str2double(answer{' num2str(i) ...
                                '});'])
                        else
                            eval(['app.TreeNodeSelection.NodeData.' prompt{i} ' = answer{' num2str(i) ...
                                '};'])
                        end
                    catch ME
                        errordlg(getReport(ME, 'basic'), 'Error!')
                    end
                end
                app.displayNodeInfo;
            else
                return
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            onlyChild = false;
            
            if isempty(setdiff(app.TreeNodeSelection.Parent.NodeData.Array.listProp('ID'), app.TreeNodeSelection.NodeData.ID))
                onlyChild = true;
            end
            if onlyChild
                selection = uiconfirm(app.UIFigure,['You are about to delete ' app.TreeNodeSelection.Text '. This is the only element inside ' ...
                    app.TreeNodeSelection.Parent.Text '. This will also delete ' app.TreeNodeSelection.Parent.Text '. Proceed?'], 'Delete Element?',...
                    'Icon','warning');
            else
                selection = uiconfirm(app.UIFigure,['You are about to delete ' app.TreeNodeSelection.Text '. Proceed?'], 'Delete Element?',...
                    'Icon','warning');
            end
            
            if strcmp(selection, 'OK') && onlyChild
                IDparent = app.TreeNodeSelection.Parent.NodeData.ID;
                idxParent = app.TreeNodeSelection.Parent.Parent.NodeData.Array.findElement('ID', IDparent);
                out = app.TreeNodeSelection.Parent.Parent.NodeData.Array.removeObj(idxParent);
                app.ProtocolObj.garbageList = [app.ProtocolObj.garbageList; out]; % add deleted item to garbage list.
                app.makeUItreeNodes(app.Tree, 'off', true, false); % Re-create Tree.
            elseif strcmp(selection, 'OK') && ~onlyChild
                ID = app.TreeNodeSelection.NodeData.ID;
                idx = app.TreeNodeSelection.Parent.NodeData.Array.findElement('ID', ID);
                out = app.TreeNodeSelection.Parent.NodeData.Array.removeObj(idx);
                app.ProtocolObj.garbageList = [app.ProtocolObj.garbageList; out]; % add deleted item to garbage list.
                app.makeUItreeNodes(app.Tree, 'off', true, false); % Re-create Tree.
            end
        end

        % Menu selected function: RestoreGarbageListMenu
        function RestoreGarbageListMenuSelected(app, event)
            app.ProtocolObj.purgeGarbageList;
            uialert(app.UIFigure, 'Garbage List was restored. You should see the deleted items back into the List if the raw data were not changed.', 'Garbage List restored', "Icon","info", 'Modal',true);
        end

        % Menu selected function: SeedPixelCorrelationMapsMenu
        function SeedPixelCorrelationMapsMenuSelected(app, event)
            run('SeedPixMap.mlapp')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewMenu = uimenu(app.ProjectMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.UpdateMenu = uimenu(app.ProjectMenu);
            app.UpdateMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateMenuSelected, true);
            app.UpdateMenu.Text = 'Update';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.ProjectMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as ...';
            app.ResetLogBookMenu = uimenu(app.ProjectMenu);
            app.ResetLogBookMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetLogBookMenuSelected, true);
            app.ResetLogBookMenu.Text = 'Reset Log Book';
            app.RestoreGarbageListMenu = uimenu(app.ProjectMenu);
            app.RestoreGarbageListMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreGarbageListMenuSelected, true);
            app.RestoreGarbageListMenu.Text = 'Restore Garbage List';
            app.UtilitiesMenu = uimenu(app.UIFigure);
            app.UtilitiesMenu.Text = 'Utilities';
            app.DataVisualizationtoolsMenu = uimenu(app.UIFigure);
            app.DataVisualizationtoolsMenu.Text = 'Data Visualization tools';
            app.ImagingMenu = uimenu(app.DataVisualizationtoolsMenu);
            app.ImagingMenu.Text = 'Imaging';
            app.SeedPixelCorrelationMapsMenu = uimenu(app.ImagingMenu);
            app.SeedPixelCorrelationMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @SeedPixelCorrelationMapsMenuSelected, true);
            app.SeedPixelCorrelationMapsMenu.Text = 'Seed Pixel Correlation Maps';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.Tree = uitree(app.DashboardTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UITable = uitable(app.DashboardTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ProjectStatusPanel = uipanel(app.DashboardTab);
            app.ProjectStatusPanel.Title = 'Project Status';
            app.Label = uilabel(app.ProjectStatusPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ProjectStatusPanel);
            app.Label_2.Text = '';
            app.LastoperationsruninselecteditemLabel = uilabel(app.DashboardTab);
            app.LastoperationsruninselecteditemLabel.Text = 'Last operations run in selected item:';
            app.TextArea = uitextarea(app.DashboardTab);
            app.PropertiesofselecteditemLabel = uilabel(app.DashboardTab);
            app.PropertiesofselecteditemLabel.Text = 'Properties of selected item:';
            app.EditButton = uibutton(app.DashboardTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.DashboardTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SelectionLabel = uilabel(app.DashboardTab);
            app.SelectionLabel.Text = 'Selection';
            app.PipelinecontrolpanelTab = uitab(app.TabGroup);
            app.PipelinecontrolpanelTab.Title = 'Pipeline control panel';
            app.Tree_2 = uitree(app.PipelinecontrolpanelTab);
            app.AdvancedSearchButton = uibutton(app.PipelinecontrolpanelTab, 'push');
            app.AdvancedSearchButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSearchButtonPushed, true);
            app.AdvancedSearchButton.Text = 'Advanced Search';
            app.FilteredItemsLabel = uilabel(app.PipelinecontrolpanelTab);
            app.FilteredItemsLabel.Text = 'Filtered Items:';
            app.UITable_2 = uitable(app.PipelinecontrolpanelTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.PipelineSummaryLabel = uilabel(app.PipelinecontrolpanelTab);
            app.PipelineSummaryLabel.Text = 'Pipeline Summary:';
            app.PipelinecontrolpanelPanel = uipanel(app.PipelinecontrolpanelTab);
            app.PipelinecontrolpanelPanel.Title = 'Pipeline control panel';
            app.ConfigureNewPipelineButton = uibutton(app.PipelinecontrolpanelPanel, 'push');
            app.ConfigureNewPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureNewPipelineButtonPushed, true);
            app.ConfigureNewPipelineButton.Text = 'Configure New Pipeline';
            app.RunPipelineButton = uibutton(app.PipelinecontrolpanelPanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.OpenButton = uibutton(app.PipelinecontrolpanelPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.PipelinecontrolpanelPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.IgnoreLoggedJobsCheckBox = uicheckbox(app.PipelinecontrolpanelPanel);
            app.IgnoreLoggedJobsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreLoggedJobsCheckBoxValueChanged, true);
            app.IgnoreLoggedJobsCheckBox.Text = 'Ignore Logged Jobs';
            app.DeleteIntermediateFilesCheckBox = uicheckbox(app.PipelinecontrolpanelPanel);
            app.DeleteIntermediateFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeleteIntermediateFilesCheckBoxValueChanged, true);
            app.DeleteIntermediateFilesCheckBox.Text = 'Delete Intermediate Files';
            app.PipelinestatusPanel = uipanel(app.PipelinecontrolpanelTab);
            app.PipelinestatusPanel.Title = 'Pipeline status';
            app.TextArea_2 = uitextarea(app.PipelinestatusPanel);
            app.SelectItemsButton = uibutton(app.PipelinecontrolpanelTab, 'push');
            app.SelectItemsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectItemsButtonPushed, true);
            app.SelectItemsButton.Text = 'Select Items';
            app.ResetSearchButton = uibutton(app.PipelinecontrolpanelTab, 'push');
            app.ResetSearchButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSearchButtonPushed, true);
            app.ResetSearchButton.Text = 'Reset Search';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsaToolboxGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end