classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        dragging;
        orPos;% Description
        orPos0;
        objPos0;
        myPatchRect=[]; % Description
    end
    
    methods (Access = private)
        function dragObject(app,hObject,eventdata)
            app.dragging = hObject;
            app.orPos = get(app.UIFigure,'CurrentPoint');
            app.objPos0 = get(app.dragging,'Position');
            app.orPos0 = get(app.UIFigure,'CurrentPoint');
            app.UITable.Data(1,:)=[[0 0],app.orPos0];
        end
        
        
        function []=drawRectDyna(app,ga,Mode,xc,yc,xw,yw,cl)
            %             x=([0 1 1 0]-0.5).*xw+xc;
            %             y=([0 0 1 1]-0.5).*yw+yc;
            pos=[xc,yc,xw,yw];
            if strcmp(Mode,'add') %add new patch and store
                %                 ph=patch(ga,'XData',x,'YData',y,'FaceColor','none','EdgeColor',cl);
                ph=rectangle(ga,'Position',pos,'EdgeColor',cl);
                
                app.myPatchRect=[app.myPatchRect;ph];
                ph.ButtonDownFcn=@app.dragObject;
                ph.PickableParts='all';
            else
                ph=MH.myPatchRect(Mode);
                %                 ph.XData=x;ph.YData=y;ph.EdgeColor=cl;
                ph.Position=pos;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             clc;
%             [folder,~,~] = fileparts(mfilename('fullpath'));
%             cd(folder);
            im=imread('rice.png');
            imagesc(app.UIAxes,im);
            daspect(app.UIAxes,[1 1 1]);
            axis(app.UIAxes,'tight');
            ga=app.UIAxes;
            xc=10;
            yc=10;
            xw=50;
            yw=50;
            cl='b';
            app.drawRectDyna(ga,'add',xc,yc,xw,yw,cl);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(app.dragging)
                newPos = get(app.UIFigure,'CurrentPoint');
                posDiff = newPos - app.orPos0;
                set(app.dragging,'Position',app.objPos0 +[posDiff(1:2).*[1 -1] 0 0]);
                app.dragging = [];
                app.UITable.Data(2,:)=[newPos,app.orPos0];
                
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(app.dragging)
                newPos = get(app.UIFigure,'CurrentPoint');
                app.UITable.Data(3,:)=[newPos,app.orPos];
                posDiff = newPos - app.orPos;
                app.orPos = newPos;
                set(app.dragging,'Position',get(app.dragging,'Position')+ [posDiff(1:2).*[1 -1] 0 0]);
                %                 drawnow
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x1'; 'y1'; 'x2'; 'y2'};
            app.UITable.RowName = {'pclick'; 'pdrop'; 'pnow'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end